classdef mixed_fibers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MixedFiberUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        BradykinesiaSliderLabel         matlab.ui.control.Label
        BradykinesiaSlider              matlab.ui.control.Slider
        RigiditySliderLabel             matlab.ui.control.Label
        RigiditySlider                  matlab.ui.control.Slider
        GaitSliderLabel                 matlab.ui.control.Label
        GaitSlider                      matlab.ui.control.Slider
        TremorSliderLabel               matlab.ui.control.Label
        TremorSlider                    matlab.ui.control.Slider
        PercBradykinesiaEditFieldLabel  matlab.ui.control.Label
        PercBradykinesiaEditField       matlab.ui.control.NumericEditField
        PercRigidityEditFieldLabel      matlab.ui.control.Label
        PercRigidityEditField           matlab.ui.control.NumericEditField
        PercGaitEditFieldLabel          matlab.ui.control.Label
        PercGaitEditField               matlab.ui.control.NumericEditField
        PercTremorEditFieldLabel        matlab.ui.control.Label
        PercTremorEditField             matlab.ui.control.NumericEditField
        OKButton                        matlab.ui.control.Button
        SelectAtlasPathButton           matlab.ui.control.Button
        SmoothFibersCheckBox            matlab.ui.control.CheckBox
        SmoothingFWHMEditFieldLabel     matlab.ui.control.Label
        SmoothingFWHMEditField          matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        fig
        patch_fig = [];
        atlas_dir
        img_names
        nifti_fold
        brady
        perc_brady
        brady_nii
        rigidity
        perc_rigidity
        rigidity_nii
        gait
        perc_gait
        gait_nii
        tremor
        perc_tremor
        tremor_nii
        new_brady
        new_rigidity
        new_tremor
        new_gait
        new_img
        brady_img
        rigi_img
        gait_img
        tremor_img
        mixed_nii
        a
        sum_of_weights
        result
        ax
        % Description
    end
    
    methods (Access = public)
        
        function [brady_img,rigi_img,gait_img,tremor_img] = load_niftis(app)           
           pre_brady_img = ~cellfun('isempty', regexpi(app.img_names, 'Bradykinesia.*'));
           brady_name = app.img_names{find(pre_brady_img)};
           brady_img = ea_load_nii([app.nifti_fold '/' brady_name]);
           
           pre_rigi_img = ~cellfun('isempty', regexpi(app.img_names, 'Rigidity.*'));
           rigi_name = app.img_names{find(pre_rigi_img)};
           rigi_img = ea_load_nii([app.nifti_fold '/' rigi_name]);
           
           pre_gait_img = ~cellfun('isempty', regexpi(app.img_names, 'Axial.*'));
           gait_name = app.img_names{find(pre_gait_img)};
           gait_img = ea_load_nii([app.nifti_fold '/' gait_name]);
           
           pre_tremor_img = ~cellfun('isempty', regexpi(app.img_names, 'Tremor.*'));
           tremor_name = app.img_names{find(pre_tremor_img)};
           tremor_img = ea_load_nii([app.nifti_fold '/' tremor_name]);
           return
        end
        
        
        function calculate_mixed_nifti(app)

            app.sum_of_weights = 0;
            app.perc_brady = app.PercBradykinesiaEditField.Value;
            if app.brady
                app.sum_of_weights = app.sum_of_weights+(app.brady/100);
                app.new_brady = (app.brady/100)*app.brady_img.img;
            elseif app.perc_brady
                app.sum_of_weights = app.sum_of_weights+(app.perc_brady/100);
                app.new_brady = (app.perc_brady/100)*app.brady_img.img;
            end
            app.perc_rigidity = app.PercRigidityEditField.Value;
            if app.rigidity 
                app.sum_of_weights = app.sum_of_weights+(app.rigidity/100);
                app.new_rigidity = (app.rigidity/100)*app.rigi_img.img;
            elseif app.perc_rigidity
                app.sum_of_weights = app.sum_of_weights+(app.perc_rigidity/100);
                app.new_rigidity = (app.perc_rigidity/100)*app.rigi_img.img;
            end
            app.perc_gait = app.PercGaitEditField.Value;
            if app.gait
                app.sum_of_weights = app.sum_of_weights+(app.gait/100);
                app.new_gait = (app.gait/100)*app.gait_img.img;
            elseif app.perc_gait
                app.sum_of_weights = app.sum_of_weights+(app.perc_gait/100);
                app.new_gait = (app.perc_gait/100)*app.gait_img.img;
            end
            app.perc_tremor = app.PercTremorEditField.Value;
            if app.tremor
                app.sum_of_weights = app.sum_of_weights+(app.tremor/100);
                app.new_tremor = (app.tremor ...
                    /100)*app.tremor_img.img;
            elseif app.perc_tremor
                app.sum_of_weights = app.sum_of_weights+(app.perc_tremor/100);
                app.new_tremor = (app.perc_tremor/100)*app.tremor_img.img;
            end
            if app.sum_of_weights > 0
                %%purely for information
                if app.brady
                    disp(['Weight given to Bradykinesia:' num2str(app.brady)]);
                elseif app.perc_brady
                    disp(['Weight given to Bradykinesia:' num2str(app.perc_brady)]);
                end
                if app.rigidity
                   disp(['Weight given to Rigidity:' num2str(app.rigidity)]);
                elseif app.perc_rigidity
                    disp(['Weight given to Rigidity:' num2str(app.perc_rigidity)]);
                end
                if app.gait
                   disp(['Weight given to Gait:' num2str(app.gait)]);
                elseif app.perc_gait
                    disp(['Weight given to Gait:' num2str(app.perc_gait)]); 
                end
                if app.tremor
                   disp(['Weight given to Tremor:' num2str(app.tremor)]);
                elseif app.perc_tremor
                    disp(['Weight given to Tremor:' num2str(app.perc_tremor)]); 
                end
                disp(['Total sum of weights:' num2str(app.sum_of_weights)]);
                
                app.new_img = {app.new_brady,app.new_rigidity,app.new_gait,app.new_tremor};
                app.a = find(~cellfun(@isempty,app.new_img));
                %creates a new image of zeros for the actual mixed image. This
                %will be output into a nifti file and then will be the input
                %for a plot command (using isosurface and patch)
                if ~app.a
                    disp("There are no weights given, therefore the program will end now...")
                end
                           
                if length(app.a) == 1
                    mixed_img = app.new_img{1,app.a}./app.sum_of_weights;
                else
                    mixed_img = zeros(size(app.brady_img.img));
                end
                if length(app.a) > 1
                    for i=1:length(app.a)
                        indx = app.a(i);
                        mixed_img = mixed_img + (app.new_img{1,indx}/app.sum_of_weights);
                    end
                end
                
                ref = [ea_space,'t1.nii'];
                refnii = ea_load_nii(ref);
                refnii.img = mixed_img;
                refnii.fname = [app.nifti_fold '/Mixed_nii.nii'];
                ea_write_nii(refnii);
                
                disp("done...now plotting fibers");
              
                plot_mixed_fibers(app);
                
            end
        end
    end
    
    methods (Access = private)
        
        function plot_mixed_fibers(app)
            
            if ~isempty(app.patch_fig)
                delete(app.patch_fig);
                %set(app.patch_fig,'Visible','off')
                disp("Setting Old Patch Visibility to OFF")
                %
            end
            app.ax = app.fig.CurrentAxes;
            if app.SmoothFibersCheckBox.Value
                mixed_nii_roi = ea_nii2fv_v2([app.nifti_fold '/Mixed_nii.nii'],1,app.SmoothingFWHMEditField.Value);
            else
                mixed_nii_roi = ea_nii2fv_v2([app.nifti_fold '/Mixed_nii.nii'],0,0);
            end
            %toc
            app.patch_fig = patch(app.ax,'Faces',mixed_nii_roi.faces,'Vertices',mixed_nii_roi.vertices,'FaceColor','r', 'EdgeColor', 'r',...
            'FaceLighting','gouraud',...
            'SpecularStrength',0.9, ...
            'SpecularColorReflectance',1,'SpecularExponent',3, ...
            'DiffuseStrength',0.4, 'LineStyle','--');
            camlight left;
            camlight right;
            disp("Patch drawing complete!...")
            end 
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, atlasDir, resultfig)
           app.fig = resultfig;
           if ~strcmp(atlasDir,'')
                app.atlas_dir = atlasDir;
                app.nifti_fold = [app.atlas_dir{1} '/nifti'];
                nifti_filenames = dir(app.nifti_fold);
                app.img_names = {};
                for i=1:length(nifti_filenames)
                    if endsWith(nifti_filenames(i).name,'.nii')
                        app.img_names{i} = nifti_filenames(i).name;
                    end
                end
                [app.brady_img,app.rigi_img,app.gait_img,app.tremor_img] = load_niftis(app);
                disp('Loaded all your input arguments...');
           else
               disp("Please select the Atlas folder using the Select Atlas Button")
           end
           app.SmoothingFWHMEditField.Enable = 'off';
            
         
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MixedFiberUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {402, 402};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {504, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
            
        end

        % Value changed function: BradykinesiaSlider
        function BradykinesiaSliderValueChanged(app, event)
            app.brady = app.BradykinesiaSlider.Value;
        end

        % Value changing function: BradykinesiaSlider
        function BradykinesiaSliderValueChanging(app, event)
            %app.brady_changing = event.Value;
        end

        % Callback function
        function PercBradykinesiaEditFieldValueChanged(app, event)
            app.perc_brady = app.PercBradykinesiaEditField.Value;
        end

        % Value changed function: RigiditySlider
        function RigiditySliderValueChanged(app, event)
            app.rigidity = app.RigiditySlider.Value;
        end

        % Value changing function: RigiditySlider
        function RigiditySliderValueChanging(app, event)
            %app.rigidity_changing = event.Value;
        end

        % Callback function
        function PercRigidityEditFieldValueChanged(app, event)
           app.perc_rigidity = app.PercRigidityEditField.Value;
        end

        % Value changed function: GaitSlider
        function GaitSliderValueChanged(app, event)
            app.gait = app.GaitSlider.Value;
        end

        % Value changing function: GaitSlider
        function GaitSliderValueChanging(app, event)
            %app.gait_changing = event.Value;
        end

        % Callback function
        function PercGaitEditFieldValueChanged(app, event)
            app.perc_gait = app.PercGaitEditField.Value;
        end

        % Value changed function: TremorSlider
        function TremorSliderValueChanged(app, event)
            app.tremor = app.TremorSlider.Value;
        end

        % Value changing function: TremorSlider
        function TremorSliderValueChanging(app, event)
            %app.tremor_changing = event.Value;
        end

        % Callback function
        function PercTremorEditFieldValueChanged(app, event)
          value = app.PercTremorEditField.Value;
        end

        % Value changed function: PercBradykinesiaEditField
        function PercBradykinesiaEditFieldValueChanged2(app, event)
            app.perc_brady = app.PercBradykinesiaEditField.Value;
        end

        % Value changed function: PercRigidityEditField
        function PercRigidityEditFieldValueChanged2(app, event)
           app.perc_rigidity = app.PercRigidityEditField.Value;     
        end

        % Value changed function: PercGaitEditField
        function PercGaitEditFieldValueChanged2(app, event)
            app.perc_gait = app.PercGaitEditField.Value;
        end

        % Value changed function: PercTremorEditField
        function PercTremorEditFieldValueChanged2(app, event)
            app.perc_tremor = app.PercTremorEditField.Value;
        end

        % Callback function
        function OKButtonPushed(app, event)
           
        end

        % Button pushed function: OKButton
        function OKButtonPushed2(app, event)
            calculate_mixed_nifti(app);
        end

        % Button pushed function: SelectAtlasPathButton
        function SelectAtlasPathButtonPushed(app, event)
            selpath = uigetdir([ea_space '/atlases']);
            if isempty(app.atlas_dir)
                app.nifti_fold = [selpath '/nifti'];
                nifti_filenames = dir(app.nifti_fold);
                app.img_names = {};
                for i=1:length(nifti_filenames)
                    if endsWith(nifti_filenames(i).name,'.nii')
                        app.img_names{i} = nifti_filenames(i).name;
                    end
                end
            end
            disp(selpath)
            tic
            [app.brady_img,app.rigi_img,app.gait_img,app.tremor_img] = load_niftis(app);
            f = msgbox('Loaded all your input arguments!');
            toc
        end

        % Close request function: MixedFiberUIFigure
        function MixedFiberUIFigureCloseRequest(app, event)
            if ~isempty(app.patch_fig)
                delete(app.patch_fig)
            end
            delete(app)
            
        end

        % Value changed function: SmoothFibersCheckBox
        function SmoothFibersCheckBoxValueChanged(app, event)
            value = app.SmoothFibersCheckBox.Value;
            if app.SmoothFibersCheckBox.Value
                app.SmoothingFWHMEditField.Enable = 'on';
            else
                app.SmoothingFWHMEditField.Enable = 'off';
            end
        end

        % Value changed function: SmoothingFWHMEditField
        function SmoothingFWHMEditFieldValueChanged(app, event)
            value = app.SmoothingFWHMEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MixedFiberUIFigure = uifigure('Visible', 'off');
            app.MixedFiberUIFigure.Name = 'Mortgage Calculator';
            app.MixedFiberUIFigure.CloseRequestFcn = createCallbackFcn(app, @MixedFiberUIFigureCloseRequest, true);
            app.MixedFiberUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MixedFiberUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BradykinesiaSliderLabel = uilabel(app.LeftPanel);
            app.BradykinesiaSliderLabel.Text = 'Bradykinesia';
            app.BradykinesiaSlider = uislider(app.LeftPanel);
            app.BradykinesiaSlider.ValueChangedFcn = createCallbackFcn(app, @BradykinesiaSliderValueChanged, true);
            app.BradykinesiaSlider.ValueChangingFcn = createCallbackFcn(app, @BradykinesiaSliderValueChanging, true);
            app.RigiditySliderLabel = uilabel(app.LeftPanel);
            app.RigiditySliderLabel.Text = 'Rigidity';
            app.RigiditySlider = uislider(app.LeftPanel);
            app.RigiditySlider.ValueChangedFcn = createCallbackFcn(app, @RigiditySliderValueChanged, true);
            app.RigiditySlider.ValueChangingFcn = createCallbackFcn(app, @RigiditySliderValueChanging, true);
            app.GaitSliderLabel = uilabel(app.LeftPanel);
            app.GaitSliderLabel.Text = 'Gait';
            app.GaitSlider = uislider(app.LeftPanel);
            app.GaitSlider.ValueChangedFcn = createCallbackFcn(app, @GaitSliderValueChanged, true);
            app.GaitSlider.ValueChangingFcn = createCallbackFcn(app, @GaitSliderValueChanging, true);
            app.TremorSliderLabel = uilabel(app.LeftPanel);
            app.TremorSliderLabel.Text = 'Tremor';
            app.TremorSlider = uislider(app.LeftPanel);
            app.TremorSlider.ValueChangedFcn = createCallbackFcn(app, @TremorSliderValueChanged, true);
            app.TremorSlider.ValueChangingFcn = createCallbackFcn(app, @TremorSliderValueChanging, true);
            app.PercBradykinesiaEditFieldLabel = uilabel(app.LeftPanel);
            app.PercBradykinesiaEditFieldLabel.Text = 'Perc. Bradykinesia';
            app.PercBradykinesiaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PercBradykinesiaEditField.ValueChangedFcn = createCallbackFcn(app, @PercBradykinesiaEditFieldValueChanged2, true);
            app.PercRigidityEditFieldLabel = uilabel(app.LeftPanel);
            app.PercRigidityEditFieldLabel.Text = 'Perc. Rigidity';
            app.PercRigidityEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PercRigidityEditField.ValueChangedFcn = createCallbackFcn(app, @PercRigidityEditFieldValueChanged2, true);
            app.PercGaitEditFieldLabel = uilabel(app.LeftPanel);
            app.PercGaitEditFieldLabel.Text = 'Perc. Gait';
            app.PercGaitEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PercGaitEditField.ValueChangedFcn = createCallbackFcn(app, @PercGaitEditFieldValueChanged2, true);
            app.PercTremorEditFieldLabel = uilabel(app.LeftPanel);
            app.PercTremorEditFieldLabel.Text = 'Perc. Tremor';
            app.PercTremorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PercTremorEditField.ValueChangedFcn = createCallbackFcn(app, @PercTremorEditFieldValueChanged2, true);
            app.OKButton = uibutton(app.LeftPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed2, true);
            app.OKButton.Text = 'OK';
            app.SelectAtlasPathButton = uibutton(app.LeftPanel, 'push');
            app.SelectAtlasPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAtlasPathButtonPushed, true);
            app.SelectAtlasPathButton.Text = 'Select Atlas Path';
            app.SmoothFibersCheckBox = uicheckbox(app.LeftPanel);
            app.SmoothFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothFibersCheckBoxValueChanged, true);
            app.SmoothFibersCheckBox.Text = 'Smooth Fibers';
            app.SmoothingFWHMEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothingFWHMEditFieldLabel.Text = 'Smoothing FWHM';
            app.SmoothingFWHMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothingFWHMEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingFWHMEditFieldValueChanged, true);
            app.SmoothingFWHMEditField.Value = 11;
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.MixedFiberUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mixed_fibers(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MixedFiberUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MixedFiberUIFigure)
        end
    end
end