classdef ea_explorer20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshinformationfromLeadGroupanalysisMenu  matlab.ui.container.Menu
        RecreatevisualizationfigureMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        OptimizeParametersontrainingsetMenu  matlab.ui.container.Menu
        CrossvalidationsMenu            matlab.ui.container.Menu
        SimilaritiesDissimilaritiesMenu  matlab.ui.container.Menu
        CombinedMenu                    matlab.ui.container.Menu
        LoadPriorOptimization           matlab.ui.container.Menu
        LoadPriorOptimizationAndResume  matlab.ui.container.Menu
        LoadSettingsMenu                matlab.ui.container.Menu
        SaveSettingsMenu                matlab.ui.container.Menu
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        StatFamilyLabel                 matlab.ui.control.Label
        StatTestLabel                   matlab.ui.control.Label
        StatH0Label                     matlab.ui.control.Label
        VTAthreshold                    matlab.ui.control.EditField
        EfieldmetricLabel               matlab.ui.control.Label
        StimulationModelLabel           matlab.ui.control.Label
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        StatFamilyDropDown              matlab.ui.control.DropDown
        StatTestDropDown                matlab.ui.control.DropDown
        StatH0DropDown                  matlab.ui.control.DropDown
        StimulationModel                matlab.ui.control.DropDown
        Efieldmetric                    matlab.ui.control.DropDown
        EfieldthresholdLabel            matlab.ui.control.Label
        EfieldthresholdSlider           matlab.ui.control.Slider
        ConnthresholdLabel              matlab.ui.control.Label
        ConnthresholdSlider             matlab.ui.control.Slider
        VariableofInterestDropDown      matlab.ui.control.DropDown
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        SingleTractAnalysisTab          matlab.ui.container.Tab
        ThresholdEditField_2            matlab.ui.control.NumericEditField
        ThresholdEditField_2Label       matlab.ui.control.Label
        WeighttractsbyROIButton         matlab.ui.control.Button
        RefineFiberColor                matlab.ui.control.Button
        ExportModelButton               matlab.ui.control.Button
        ThresholdingDropDown            matlab.ui.control.DropDown
        ThresholdingDropDownLabel       matlab.ui.control.Label
        ShowAmountEditFieldNegative     matlab.ui.control.NumericEditField
        ShowAmountEditFieldPositive     matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountRightEditFieldNegative  matlab.ui.control.NumericEditField
        ShowAmountLeftEditFieldNegative  matlab.ui.control.NumericEditField
        MultTestStrategy                matlab.ui.control.DropDown
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantFibersonlyCheckBox  matlab.ui.control.CheckBox
        ExportasAtlasButton             matlab.ui.control.Button
        ShowNegativeFibersCheckBox      matlab.ui.control.CheckBox
        ShowPositiveFibersCheckBox      matlab.ui.control.CheckBox
        ColorButtonNegative             matlab.ui.control.Button
        ShowAmountLabelPositive         matlab.ui.control.Label
        ShowAmountLeftLabelPositive     matlab.ui.control.Label
        ShowAmountRightLabelPositive    matlab.ui.control.Label
        ShowAmountLeftLabelNegative     matlab.ui.control.Label
        ShowAmountLabelNegative         matlab.ui.control.Label
        ShowAmountRightLabelNegative    matlab.ui.control.Label
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        ColorButtonPositive             matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        OpeninCurrentuneButton          matlab.ui.control.Button
        ImportedModelName               matlab.ui.control.EditField
        SubcohortsDropDown_2            matlab.ui.control.DropDown
        SubcohortsDropDown_2Label       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.StateButton
        NestedCheckBox                  matlab.ui.control.CheckBox
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        OpeninCleartuneButton           matlab.ui.control.Button
        MultitractWeightsButton         matlab.ui.control.Button
        ModeltypeDropDown               matlab.ui.control.DropDown
        FittoscoresCheckBox             matlab.ui.control.CheckBox
        BinsEditField                   matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        ModelNormalizationDropDown      matlab.ui.control.DropDown
        ModelNormalizationDropDownLabel  matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        mmLabel                         matlab.ui.control.Label
        ResolutionEditField             matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel        matlab.ui.control.Label
        VmLabel                         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ConnectomeDropDown              matlab.ui.control.DropDown
        ConnectomeDropDownLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        tractset % associated ea_explorerclass object.

    end


    methods (Access = private)

        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.tractset.colorbar.fibers)
                if strcmp(app.TabGroup.SelectedTab.Title, 'Single Tract Analysis')
                    app.ColorBar.Visible = 'on';
                    cb = app.tractset.colorbar.fibers;
                    ea_plot_colorbar(cb.cmap, [], 'h', '', cb.tick, cb.ticklabel, app.ColorBar);
                else
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                end
                app.ColorBar.Visible = 'on';
                cb = app.tractset.colorbar.fibers;
                ea_plot_colorbar(cb.cmap, [], 'h', '', cb.tick, cb.ticklabel, app.ColorBar);
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end


        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end

        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            dorefresh=1;

            if (app.AutoRefreshCheckBox.Value || force) && dorefresh
                app.isbusy(1);
                drawnow;
                app.tractset.recalculate;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.ea_explorer_tractset2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end

        function Vms = getvms(app)
            switch app.tractset.statsettings.efieldmetric
                case {'Mean'}
                    Vms='V/m';
                otherwise
                    Vms='V/mm';
            end
        end


        function setefieldsliders(app)
            %% Set slider limits
            switch app.tractset.statsettings.stimulationmodel
                case 'Sigmoid Field'
                    if app.tractset.statsettings.efieldthreshold > 1
                        app.tractset.statsettings.efieldthreshold = ea_SigmoidFromEfield(app.tractset.statsettings.efieldthreshold);
                    end
                    app.EfieldthresholdSlider.Limits=[ea_SigmoidFromEfield(app.tractset.calcthreshold),1]; % probability activation
                case 'Electric Field'
                    app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                    if app.tractset.statsettings.efieldthreshold < 1
                        app.tractset.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.tractset.statsettings.efieldthreshold);
                    end
                case 'VTA'
                    app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                    if app.tractset.statsettings.efieldthreshold < 1
                        app.tractset.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.tractset.statsettings.efieldthreshold);
                    end
            end

            %% Check if threshold is outside the limits
            if app.tractset.statsettings.efieldthreshold < app.EfieldthresholdSlider.Limits(1)
                app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(1);
            elseif app.tractset.statsettings.efieldthreshold > app.EfieldthresholdSlider.Limits(2)
                app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(2);
            end
            app.EfieldthresholdSlider.Value=app.tractset.statsettings.efieldthreshold;
            %% Set labels
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
            end

        end
        function result = ea_isnan(app,input_array,flag)
            if size(input_array,2) > 1
                if strcmp(flag,'any')
                    op = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    op = all(isnan(input_array));
                end
                if length(find(op)) > 1
                    result = 1;
                else
                    result = 0;

                end
            else
                if strcmp(flag,'any')
                    result = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    result = all(isnan(input_array));
                end
            end
        end


        function results = tabupdate(app)
            n_group = unique(app.tractset.M.patient.group);
            if  app.SplitColorBySubscoreButton.Value
                app.SingleTractAnalysisTab.Title = 'Mixed Fiber Visualization';
                iter = length(app.SubscoresListBox.Value);
            elseif app.SplitColorByGroupButton.Value
                app.SingleTractAnalysisTab.Title = 'Group:Combined Visualization';
                iter = length(n_group);
            elseif app.SplitColorByPCAButton.Value
                app.SingleTractAnalysisTab.Title = 'PCA:Combined Visualization';
                iter = app.PCsEditField.Value;
            else
                app.SingleTractAnalysisTab.Parent = app.TabGroup;
                app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
                for i=1:10
                    tab_title = (['Subscore_' num2str(i) 'Tab']);
                    app.(tab_title).Parent = [];
                end
                app.ColorBar.Visible = 'on';

                return
            end

            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonPositive.Enable = 'off';
            for i=1:10
                tab_title = (['Subscore_' num2str(i) 'Tab']);
                negcolor_btn_title = (['ColorButtonNegative_' num2str(i+1)]);
                poscolor_btn_title = (['ColorButtonPositive_' num2str(i+1)]);
                showposfib_cb = ['ShowPositiveFibersCheckBox_' num2str(i+1)];
                shownegfib_cb = ['ShowNegativeFibersCheckBox_' num2str(i+1)];
                showposamt_editfield = ['ShowAmountEditFieldPositive_' num2str(i+1)];
                showposamt_label = ['ShowAmountLabelPositive_' num2str(i+1)];
                shownegamt_editfield = ['ShowAmountEditFieldNegative_' num2str(i+1)];
                shownegamt_label = ['ShowAmountLabelNegative_' num2str(i+1)];

                showposamt_l_editfield = ['ShowAmountLeftEditFieldPositive_' num2str(i+1)];
                showposamt_l_label = ['ShowAmountLeftLabelPositive_' num2str(i+1)];
                showposamt_r_editfield = ['ShowAmountRightEditFieldPositive_' num2str(i+1)];
                showposamt_r_label = ['ShowAmountRightLabelPositive_' num2str(i+1)];

                shownegamt_l_editfield = ['ShowAmountLeftEditFieldNegative_' num2str(i+1)];
                shownegamt_l_label = ['ShowAmountLeftLabelNegative_' num2str(i+1)];
                shownegamt_r_editfield = ['ShowAmountRightEditFieldNegative_' num2str(i+1)];
                shownegamt_r_label = ['ShowAmountRightLabelNegative_' num2str(i+1)];

                if i <= iter
                    % find the tab that has the title
                    app.(tab_title).Parent = app.TabGroup;
                    if app.SplitColorBySubscoreButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.subscore.colors{1,1}(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.subscore.colors{1,2}(i,:);
                    elseif app.SplitColorByPCAButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.subscore.pcacolors(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.subscore.pcacolors(i,:);
                    elseif app.SplitColorByGroupButton.Value
                        app.(negcolor_btn_title).BackgroundColor = app.tractset.M.groups.color(i,:);
                        app.(poscolor_btn_title).BackgroundColor = app.tractset.M.groups.color(i,:);
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposfib_cb).Value = 1;
                        app.tractset.subscore.posvisible(i) = 1;
                        app.(showposfib_cb).Enable = 'on';
                        app.(poscolor_btn_title).Enable = 'on';
                    else
                        app.(showposfib_cb).Value = 0;
                        app.tractset.subscore.posvisible(i) = 0;
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegfib_cb).Value = 1;
                        app.tractset.subscore.negvisible(i) = 1;
                        app.(shownegfib_cb).Enable = 'on';
                        app.(negcolor_btn_title).Enable = 'on';
                    else
                        app.(shownegfib_cb).Value = 0;
                        app.tractset.subscore.negvisible(i) = 0;
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposamt_editfield).Value = app.ShowAmountEditFieldPositive.Value;
                        app.(showposamt_editfield).Enable = 'on';
                        app.(showposamt_label).Enable = 'on';
                    else
                        app.(showposamt_editfield).Value = app.ShowAmountEditFieldPositive.Value;
                        app.(showposamt_editfield).Enable = 'off';
                        app.(showposamt_label).Enable = 'off';
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegamt_editfield).Value = app.ShowAmountEditFieldNegative.Value;
                        app.(shownegamt_editfield).Enable = 'on';
                        app.(shownegamt_label).Enable = 'on';
                    else
                        app.(shownegamt_editfield).Value = app.ShowAmountEditFieldNegative.Value;
                        app.(shownegamt_editfield).Enable = 'off';
                        app.(shownegamt_label).Enable = 'off';
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposamt_l_editfield).Value = app.ShowAmountLeftEditFieldPositive.Value;
                        app.tractset.subscore.vis.showposamount(i,2) = app.ShowAmountLeftEditFieldPositive.Value;
                        app.(showposamt_l_editfield).Enable = 'on';
                        app.(showposamt_l_label).Enable = 'on';
                    else
                        app.(showposamt_l_editfield).Value = app.ShowAmountLeftEditFieldPositive.Value;
                        app.(showposamt_l_editfield).Enable = 'off';
                        app.(showposamt_l_label).Enable = 'off';
                    end
                    if app.ShowPositiveFibersCheckBox.Value
                        app.(showposamt_r_editfield).Value = app.ShowAmountRightEditFieldPositive.Value;
                        app.tractset.subscore.vis.showposamount(i,1) = app.ShowAmountRightEditFieldPositive.Value;
                        app.(showposamt_r_editfield).Enable = 'on';
                        app.(showposamt_r_label).Enable = 'on';
                    else
                        app.(showposamt_r_editfield).Value = app.ShowAmountRightEditFieldPositive.Value;
                        app.(showposamt_r_editfield).Enable = 'off';
                        app.(showposamt_r_label).Enable = 'off';
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegamt_l_editfield).Value = app.ShowAmountLeftEditFieldNegative.Value;
                        %update the new value into the model
                        app.tractset.subscore.vis.shownegamount(i,2) = app.ShowAmountLeftEditFieldNegative.Value;
                        app.(shownegamt_l_editfield).Enable = 'on';
                        app.(shownegamt_l_label).Enable = 'on';
                    else
                        app.(shownegamt_l_editfield).Value = app.ShowAmountLeftEditFieldNegative.Value;
                        app.(shownegamt_l_editfield).Enable = 'off';
                        app.(shownegamt_l_label).Enable = 'off';
                    end
                    if app.ShowNegativeFibersCheckBox.Value
                        app.(shownegamt_r_editfield).Value = app.ShowAmountRightEditFieldNegative.Value;
                        app.tractset.subscore.vis.shownegamount(i,1) = app.ShowAmountRightEditFieldNegative.Value;
                        app.(shownegamt_r_editfield).Enable = 'on';
                        app.(shownegamt_r_label).Enable = 'on';
                    else
                        app.(shownegamt_r_editfield).Value = app.ShowAmountRightEditFieldNegative.Value;
                        app.(shownegamt_r_editfield).Enable = 'off';
                        app.(shownegamt_r_label).Enable = 'off';
                    end

                else
                    app.(tab_title).Parent = [];
                end
            end
        end
        function results = tabfill(app)

            app.ShowPositiveFibersCheckBox.Value = app.tractset.thresholding.posvisible;
            app.ShowNegativeFibersCheckBox.Value = app.tractset.thresholding.negvisible;
            app.ShowAmountEditFieldPositive.Value = mean(app.tractset.thresholding.showposamount);
            app.ShowAmountEditFieldNegative.Value = mean(app.tractset.thresholding.shownegamount);
            app.ShowAmountLeftEditFieldPositive.Value = app.tractset.thresholding.showposamount(2);
            app.ShowAmountRightEditFieldPositive.Value = app.tractset.thresholding.showposamount(1);
            app.ShowAmountLeftEditFieldNegative.Value = app.tractset.thresholding.shownegamount(2);
            app.ShowAmountRightEditFieldNegative.Value = app.tractset.thresholding.shownegamount(1);

            % num_tabs = length(app.tractset.subscore.labels);
            % for indx=1:num_tabs
            %     checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
            %     pos_label = ['ShowAmountLabelPositive_' num2str(indx+1)];
            %     left_pos_label = ['ShowAmountLeftLabelPositive_' num2str(indx+1)];
            %     right_pos_label = ['ShowAmountRightLabelPositive_' num2str(indx+1)];
            %     app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.tractset.subscore.vis.pos_available(indx,:))),')'];
            %     app.(pos_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.pos_shown(indx,:))),')']};
            %     app.(left_pos_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,2)),')']};
            %     app.(right_pos_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,1)),')']};
            %     checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
            %     neg_label = ['ShowAmountLabelNegative_' num2str(indx+1)];
            %     left_label = ['ShowAmountLeftLabelNegative_' num2str(indx+1)];
            %     right_label = ['ShowAmountRightLabelNegative_' num2str(indx+1)];
            %     app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.tractset.subscore.vis.neg_available(indx,:))),')'];
            %     app.(neg_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.neg_shown(indx,:))),')']};
            %     app.(left_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,2)),')']};
            %     app.(right_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,1)),')']};
            % end
        end


        function results = ea_explorer_tractset2UI(app,flag)
            app.isbusy(1);

            app.StatFamilyDropDown.Enable = 'on';
            app.StatFamilyLabel.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';

            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.tractset.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.tractset.leadgroup;
            app.AnalysisIDEditField.Value = app.tractset.ID;

            app.ConsiderSignificantFibersonlyCheckBox.Value = app.tractset.thresholding.showsignificantonly;
            app.levelEditField.Value = (app.tractset.thresholding.alphalevel);
            app.MultTestStrategy.Value = app.tractset.thresholding.multcompstrategy;
            try % could be that entry is not in the items list.
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if ~isempty(app.tractset.connectome) && ismember(app.tractset.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.tractset.connectome;
            end

            app.MirrorSidesCheckBox.Value = app.tractset.mirrorsides;

            app.PatientsListBox.Items=app.tractset.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.tractset.setlabels];
            for set=1:length(app.tractset.setlabels)
                if isequal(app.tractset.patientselection,find(app.tractset.setselections{set}))
                    app.SubcohortsDropDown.Value=app.tractset.setlabels{set};
                end
            end
            if isequal(app.tractset.patientselection,1:length(app.tractset.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end

            app.Efieldmetric.Value=app.tractset.statsettings.efieldmetric;

            % setup calculation threshold (only used when calculating
            % fibers for a new connectome).
            if ~isempty(app.tractset.calcthreshold)
                app.ThresholdEditField.Value = app.tractset.calcthreshold;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                end
            else
                prefs=ea_prefs;
                if isfield(app.tractset.M,'pseudoM')
                    app.VmLabel.Text = ''; % not a VTA setting, V/m does not apply. Could be arbitrary units here.
                    app.ThresholdEditField.Value=0;
                else
                    app.ThresholdEditField.Value=prefs.machine.vatsettings.horn_ethresh*1000; % set to a default value based on prefs.
                end
            end

            if ~isempty(app.tractset.statsettings.efieldthreshold)
                app.setefieldsliders;
                app.EfieldthresholdSlider.Value = app.tractset.statsettings.efieldthreshold;
            end

            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
            end

            app.ConnthresholdSlider.Value = app.tractset.statsettings.connthreshold;

            app.PosthoccorrectforgroupCheckBox.Value = app.tractset.adjustforgroups;

            app.VariableofInterestDropDown.Items = app.tractset.M.clinical.labels;
            if ismember(app.tractset.responsevarlabel,app.tractset.M.clinical.labels)
                app.VariableofInterestDropDown.Value = app.tractset.responsevarlabel;
            else
                app.VariableofInterestDropDown.Value = app.tractset.M.clinical.labels{1};
            end
            app.FittoscoresCheckBox.Value = app.tractset.doactualprediction;

            app.ShowPositiveFibersCheckBox.Value = app.tractset.thresholding.posvisible;
            try
                if strcmp(app.tractset.statsettings.statfamily,'Plain Connections')
                    app.ShowPositiveFibersCheckBox.Text = ['Show Connected Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                else
                    app.ShowPositiveFibersCheckBox.Text = ['Show Positive Fibers (',num2str(sum(app.tractset.stats.pos.available)),')'];
                end
            end

            app.ShowNegativeFibersCheckBox.Value = app.tractset.thresholding.negvisible;
            try
                app.ShowNegativeFibersCheckBox.Text = ['Show Negative Fibers (',num2str(sum(app.tractset.stats.neg.available)),')'];
            end

            app.ThresholdingDropDown.Value = app.tractset.thresholding.threshstrategy;
            % Amount EditField:
            switch app.tractset.thresholding.threshstrategy
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = sum(app.tractset.thresholding.showposamount);
                    try
                        app.ShowAmountEditFieldLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
                    end

                    app.ShowAmountEditFieldNegative.Value = sum(app.tractset.thresholding.shownegamount);
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.tractset.thresholding.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.tractset.thresholding.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.tractset.thresholding.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.tractset.thresholding.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
                    end

                case 'Fixed Fiber Value' % edit fields
                    if app.tractset.thresholding.showposamount(1) == app.tractset.thresholding.showposamount(2)
                        app.ShowAmountEditFieldPositive.Value = app.tractset.thresholding.showposamount(1);
                        app.ShowAmountEditFieldPositive.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldPositive.Value = 0;
                        app.ShowAmountEditFieldPositive.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountEditFieldLabel.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
                    end

                    if app.tractset.thresholding.shownegamount(1) == app.tractset.thresholding.shownegamount(2)
                        app.ShowAmountEditFieldNegative.Value = app.tractset.thresholding.shownegamount(1);
                        app.ShowAmountEditFieldNegative.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldNegative.Value = 0;
                        app.ShowAmountEditFieldNegative.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.tractset.thresholding.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.tractset.thresholding.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.tractset.thresholding.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.tractset.thresholding.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
                    end

                otherwise
                    if app.tractset.thresholding.showposamount(1) == app.tractset.thresholding.showposamount(2)
                        app.ShowAmountEditFieldPositive.Value = app.tractset.thresholding.showposamount(1);
                        app.ShowAmountEditFieldPositive.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldPositive.Value = 0;
                        app.ShowAmountEditFieldPositive.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelPositive.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.pos.shown)),')']};
                    end

                    if app.tractset.thresholding.shownegamount(1) == app.tractset.thresholding.shownegamount(2)
                        app.ShowAmountEditFieldNegative.Value = app.tractset.thresholding.shownegamount(1);
                        app.ShowAmountEditFieldNegative.FontColor = [0, 0, 0];
                    else
                        app.ShowAmountEditFieldNegative.Value = 0;
                        app.ShowAmountEditFieldNegative.FontColor = [0.7, 0.7, 0.7];
                    end
                    try
                        app.ShowAmountLabelNegative.Text = {'Show Amount', ['(',num2str(sum(app.tractset.stats.neg.shown)),')']};
                    end

                    app.ShowAmountLeftEditFieldPositive.Value = app.tractset.thresholding.showposamount(2);
                    try
                        app.ShowAmountLeftLabelPositive.Text = {'Left', ['(',num2str(app.tractset.stats.pos.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldPositive.Value = app.tractset.thresholding.showposamount(1);
                    try
                        app.ShowAmountRightLabelPositive.Text = {'Right', ['(',num2str(app.tractset.stats.pos.shown(1)),')']};
                    end

                    app.ShowAmountLeftEditFieldNegative.Value = app.tractset.thresholding.shownegamount(2);
                    try
                        app.ShowAmountLeftLabelNegative.Text = {'Left', ['(',num2str(app.tractset.stats.neg.shown(2)),')']};
                    end

                    app.ShowAmountRightEditFieldNegative.Value = app.tractset.thresholding.shownegamount(1);
                    try
                        app.ShowAmountRightLabelNegative.Text = {'Right', ['(',num2str(app.tractset.stats.neg.shown(1)),')']};
                    end
            end


            % if exist('indx','var')
            %     checkbox_pos = ['ShowPositiveFibersCheckBox_' num2str(indx+1)];
            %     pos_label = ['ShowAmountLabelPositive_' num2str(indx+1)];
            %     left_pos_label = ['ShowAmountLeftLabelPositive_' num2str(indx+1)];
            %     right_pos_label = ['ShowAmountRightLabelPositive_' num2str(indx+1)];
            %     app.(checkbox_pos).Text = ['Show Positive Fibers (',num2str(sum(app.tractset.subscore.vis.pos_available(indx,:))),')'];
            %     app.(pos_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.pos_shown(indx,:))),')']};
            %     app.(left_pos_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,2)),')']};
            %     app.(right_pos_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.pos_shown(indx,1)),')']};
            %     checkbox_neg = ['ShowNegativeFibersCheckBox_' num2str(indx+1)];
            %     neg_label = ['ShowAmountLabelNegative_' num2str(indx+1)];
            %     left_label = ['ShowAmountLeftLabelNegative_' num2str(indx+1)];
            %     right_label = ['ShowAmountRightLabelNegative_' num2str(indx+1)];
            %     app.(checkbox_neg).Text = ['Show Negative Fibers (',num2str(sum(app.tractset.subscore.vis.neg_available(indx,:))),')'];
            %     app.(neg_label).Text = {'Show Amount', ['(',num2str(sum(app.tractset.subscore.vis.neg_shown(indx,:))),')']};
            %     app.(left_label).Text = {'Left', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,2)),')']};
            %     app.(right_label).Text = {'Right', ['(',num2str(app.tractset.subscore.vis.neg_shown(indx,1)),')']};
            % end

            app.ModeltypeDropDown.Value = app.tractset.predictionmodel;

            app.LiveVisualizeCheckBox.Value = app.tractset.cvlivevisualize;

            app.BinsEditField.Value = app.tractset.numBins;
            app.ModelNormalizationDropDown.Value = app.tractset.modelNormalization;

            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.tractset.thresholding.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.tractset.thresholding.negcolor;


            % Base prediction on:
            try
                app.BasePredictiononDropDown.Value = app.tractset.basepredictionon;
            end
            if app.tractset.nestedLOO
                app.NestedCheckBox.Value = 1;
            end
            app.IterationsEditField.Value = app.tractset.kIter;
            app.ea_explorer_ui_enablerules;

            % refresh enablerules
            app.isbusy(0);

        end

        function results = ea_explorer_ui_enablerules(app,flag)
            app.isbusy(1);
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val='off';
            else
                val=ea_bool2onoff(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            end
            try
                numsides=numel(app.tractset.results.(ea_conn2connid(app.tractset.connectome)).(ea_explorer_method2methodid(app.tractset)).fibsval);
            catch % default
                numsides=2;
            end
            % enable controls:

            %% Stimulation Setup GUI
            app.StimulationModelLabel.Enable = val;
            app.StimulationModel.Enable = val;
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldmetricLabel.Enable = true;
                    app.Efieldmetric.Enable = true;
                otherwise
                    app.EfieldmetricLabel.Enable = true;
                    app.Efieldmetric.Enable = true;
            end
            app.ConnthresholdSlider.Enable = val;
            app.ConnthresholdLabel.Enable = val;
            app.EfieldthresholdSlider.Enable = val;
            app.EfieldthresholdLabel.Enable = val;            
            app.StatFamilyLabel.Enable=val;
            app.StatTestLabel.Enable=val;
            app.StatH0Label.Enable=val;            
            app.StatFamilyDropDown.Enable = val;
            app.StatTestDropDown.Enable = val;

            if val
                app.StatTestDropDown.Items = app.tractset.stattests.name(app.tractset.stattests.type==app.StatFamilyDropDown.Value);
                app.StatTestDropDown.Value = app.StatTestDropDown.Items(1);
                app.tractset.statsettings.statfamily=app.StatTestDropDown.Value;
            end

            switch app.StatFamilyDropDown.Value
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
            end
            
            
            app.MirrorSidesCheckBox.Enable = val;            
            app.VariableofInterestDropDownLabel.Enable = val;
            app.VariableofInterestDropDown.Enable = val;
            groupsassigned=(length(unique(app.tractset.M.patient.group))>1);           

            %% Visualization GUI
            app.ShowPositiveFibersCheckBox.Enable = val;
            app.ShowNegativeFibersCheckBox.Enable = val;

            app.ColorButtonPositive.Enable = val;
            %%%%%%%%todoapp.ShowNegativeFibersCheckBox.Enable = val;


            app.ColorBar.Visible = val;
            if strcmp(val, 'off')
                cla(app.ColorBar);
            end
            app.ExportasAtlasButton.Enable = val;
            cvEnable = val;

            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ModelNormalizationDropDown.Enable = cvEnable;
            app.ModelNormalizationDropDownLabel.Enable = cvEnable;


            switch app.BasePredictiononDropDown.Value
                case {'Histogram','z-scored Histogram'}
                    app.BinsEditField.Enable = cvEnable;
                    app.BinsEditFieldLabel.Enable = cvEnable;
                otherwise
                    app.BinsEditField.Enable = 'off';
                    app.BinsEditFieldLabel.Enable = 'off';
            end

            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.tractset.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.tractset.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    [~, patNames] = fileparts(app.tractset.M.patient.list);
                    app.TrainOnListBox.Items = patNames;
                    app.PredictListBox.Items = patNames;
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.tractset.M.groups.group));
                    end
                case 'Import Model'
                    if ~strcmp(app.tractset.ExternalModelFile, 'None')
                        [~, ExternalModelFileName] = fileparts(app.tractset.ExternalModelFile);
                        app.ImportedModelName.Value = ExternalModelFileName;
                    end

                    [~, patNames] = fileparts(app.tractset.M.patient.list);
                    app.PredictListBox.Items = patNames;
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(1:str2double(app.kEditField.Value))');
                        app.PredictListBox.Items=sprintfc('%d',(1:str2double(app.kEditField.Value))');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.tractset.setlabels;
                    app.PredictListBox.Items=app.tractset.setlabels;
            end

            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Import Model'
                    cvlEnable=cvEnable;
                    if isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.tractset.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end

                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end

            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            app.ModeltypeDropDown.Enable = cvlEnable;

            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;


            app.ConsiderSignificantFibersonlyCheckBox.Enable = val;
            app.levelEditField.Enable = val;
            app.levelEditFieldLabel.Enable = val;
            app.MultTestStrategy.Enable = val;


            app.BasePredictiononDropDown.Enable = val;
            app.BasePredictiononDropDownLabel.Enable = val;
            %app.ModelSetupOptionButtonGroup.Enable = val;


            app.FittoscoresCheckBox.Enable = cvEnable;

            switch app.FittoscoresCheckBox.Value
                case 1
                    app.ModeltypeDropDown.Enable = cvEnable;
                case 0
                    app.ModeltypeDropDown.Enable = 'off';
            end

            app.StatTestDropDown.Visible = val;


            if ~ismember(app.StatTestDropDown.Value,app.StatTestDropDown.Items)
                app.StatTestDropDown.Value=app.StatTestDropDown.Items{1};
            end


            app.PatientsListBox.Enable=val;

            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.tractset.setlabels];

            app.SubcohortsDropDown_2.Items=[app.tractset.setlabels];

            % Thresholding Method:
            app.ThresholdingDropDown.Enable = val;
            app.ThresholdingDropDownLabel.Enable = val;

            switch app.ShowNegativeFibersCheckBox.Value
                case 0
                    app.ShowAmountEditFieldNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountLabelNegative.Enable = 'off';

                    app.ShowAmountLeftEditFieldNegative.Enable = 'off';
                    app.ShowAmountRightEditFieldNegative.Enable = 'off';

                    app.ShowAmountLeftLabelNegative.Enable = 'off';
                    app.ShowAmountRightLabelNegative.Enable = 'off';
                case 1
                    app.ShowAmountEditFieldNegative.Enable = val;
                    app.ColorButtonNegative.Enable = val;
                    app.ShowAmountLabelNegative.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.ShowAmountLeftEditFieldNegative.Enable = val;
                        app.ShowAmountRightEditFieldNegative.Enable = val;

                        app.ShowAmountLeftLabelNegative.Enable = val;
                        app.ShowAmountRightLabelNegative.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.ShowAmountLeftEditFieldNegative.Enable = 'off';
                        app.ShowAmountRightEditFieldNegative.Enable = 'off';

                        app.ShowAmountLeftLabelNegative.Enable = 'off';
                        app.ShowAmountRightLabelNegative.Enable = 'off';
                    end
            end

            switch app.ShowPositiveFibersCheckBox.Value
                case 0
                    app.ShowAmountEditFieldPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.ShowAmountLabelPositive.Enable = 'off';

                    app.ShowAmountLeftEditFieldPositive.Enable = 'off';
                    app.ShowAmountRightEditFieldPositive.Enable = 'off';

                    app.ShowAmountLeftLabelPositive.Enable = 'off';
                    app.ShowAmountRightLabelPositive.Enable = 'off';
                case 1
                    app.ShowAmountEditFieldPositive.Enable = val;
                    app.ColorButtonPositive.Enable = val;
                    app.ShowAmountLabelPositive.Enable = val;
                    if numsides==2 % usual bilateral case
                        app.ShowAmountLeftEditFieldPositive.Enable = val;
                        app.ShowAmountRightEditFieldPositive.Enable = val;

                        app.ShowAmountLeftLabelPositive.Enable = val;
                        app.ShowAmountRightLabelPositive.Enable = val;
                    else % scripting case e.g. when using only one side
                        app.ShowAmountLeftEditFieldPositive.Enable = 'off';
                        app.ShowAmountRightEditFieldPositive.Enable = 'off';

                        app.ShowAmountLeftLabelPositive.Enable = 'off';
                        app.ShowAmountRightLabelPositive.Enable = 'off';
                    end

            end

            % Logic of multitract panel radio buttons - enabling of pca panel is
            % handled in updatepca()
            app.ColorButtonPositive.Enable = val;
            app.ColorButtonNegative.Enable = val;
            app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';

            % turn light back to green
            app.isbusy(0);
        end
        
        function refreshstatsettings(app)
            app.tractset.statsettings.stimulationmodel = app.StimulationModel.Value;
            app.tractset.statsettings.efieldmetric = app.Efieldmetric.Value; % if statmetric == ;Correlations / E-fields (Irmen 2020)p, efieldmetric can calculate sum, mean or peak along tracts
            app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.tractset.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.statsettings.statfamily = app.StatFamilyDropDown.Value; % the
            app.tractset.statsettings.stattest = app.StatTestDropDown.Value;
            app.tractset.statsettings.H0 = app.StatH0DropDown.Value;            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('*.mat','Please Choose Lead-Group Analysis project...');
                if isnumeric(FileName) && FileName == 0
                    ea_cprintf('CmdWinErrors', 'No group analysis selected!\n');
                    delete(app);
                    return;
                else
                    lgpath = fullfile(PathName,FileName);
                end
            elseif ~isfile(lgpath)
                ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
                delete(app);
                return;
            end
            lgpath = GetFullPath(lgpath);

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Opening application');
            d.Value = 0;
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Fiber Filtering Explorer';

            app.IterationsEditField.Visible = 'off';
            app.IterationsEditFieldLabel.Visible = 'off';
            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
            %app.ConnectomeDropDown.Items={mdl(sf==1)};

            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            d.Value = 0.33;
            % Add Value to connthreshsliderlabel
            changingValue=app.EfieldthresholdSlider.Value;

            letsdraw=0; % flag to state whether to draw directly.

            varinfo=who('-file',lgpath); % check if file is a group analysis matfile or a stored disctract object with var name 'tractset'

            if ismember('tractset',varinfo)
                D = load(lgpath,'-mat');
                app.tractset = D.tractset; % load stored disctract object
                app.tractset.analysispath=lgpath;

                app.tractset.activateby={};

                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.tractset.ID, ID)
                    app.tractset.ID = ID;
                end

                % do not initialize with Import Model
                if strcmp(app.StrategyDropDown.Value, 'Import Model')
                    app.StrategyDropDown.Value = 'Custom (Patients)';
                end

                % Fix group folder in case it has been moved
                tractGroupFolder = regexprep(app.tractset.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['explorer', filesep, app.tractset.ID, '.explorer'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.tractset.analysispath = strrep(app.tractset.analysispath, tractGroupFolder, UIGroupFolder);
                    app.tractset.leadgroup = strrep(app.tractset.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.tractset.M.root = strrep(app.tractset.M.root, tractGroupFolder, UIGroupFolder);
                    if isfield(app.tractset.M,'ui')
                        app.tractset.M.ui.groupdir = strrep(app.tractset.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                    end
                end
                d.Value = 0.50;
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                app.tractset.resultfig = resultfig; % attach to figure
                if ~isempty(app.tractset.results)
                    if isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                        letsdraw=1;
                    end
                end
                %initialize for legacy support
                if ~isprop(app.tractset,'multi_pathways')
                    app.tractset.multipath.multi_pathways = 0;
                end              
            else % init from M struct of LEAD group file
                app.tractset = ea_explorerclass;
                app.NestedCheckBox.Visible = 'off';
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                try
                    app.tractset.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
                catch ME
                    close(resultfig);
                    app.UIFigureCloseRequest;
                    rethrow(ME);
                end
            end
            if isfield(app.tractset.M,'ui')
                if ismember(app.tractset.M.ui.connectomename, app.ConnectomeDropDown.Items)
                    app.ConnectomeDropDown.Value = app.tractset.M.ui.connectomename;
                end
            end
            d.Value = 0.75;
            app.setefieldsliders;
            if letsdraw
                app.draw; % show its results
            else
                app.ea_explorer_tractset2UI;
            end
            app.ea_explorer_ui_enablerules;
           
            app.isbusy(0);
            d.Value = 1.00;
            close(d);
            hint={'Welcome to the Lead-DBS Fiber Filtering Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create discriminative tract models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');

        end

        % Value changed function: StatFamilyDropDown
        function StatFamilyDropDownValueChanged(app, event)
            app.StatFamilyDropDown.Value = event.Value;
            app.StatTestDropDown.Items = app.tractset.stattests.name(app.tractset.stattests.type==event.Value);
            app.StatTestDropDown.Value = app.StatTestDropDown.Items(1);
            app.tractset.statsettings.statfamily=app.StatTestDropDown.Value;
            switch event.Value
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
            end
            % app.ea_explorer_tractset2UI;
            % app.ea_explorer_ui_enablerules;
            % if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
            %     val=0;
            % else
            %     val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            % end
            % if val % already calculated
            %     app.draw;
            % end
            app.refreshstatsettings
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',changingValue),' ',app.getvms]};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.1f',changingValue)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',changingValue),' ',app.getvms]};
            end
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
            app.draw;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.isbusy(1)
            %app.tractset.app=app; % need to change
            app.tractset.leadgroup=app.LeadGroupPathLabel.Text;

            %% set up object properties:
            app.tractset.thresholding.posvisible = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.thresholding.negvisible = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.thresholding.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.tractset.thresholding.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.tractset.thresholding.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.thresholding.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;

            app.tractset.calcthreshold = app.ThresholdEditField.Value;

            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};

            app.tractset.statsettings.statfamily=app.StatFamilyDropDown.Value;
            app.tractset.connectome = app.ConnectomeDropDown.Value;
            app.setefieldsliders;


            app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;

            app.tractset.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;

            % calculate general setup
            app.tractset.calculate;

            % save dataset:
            app.tractset.save;

            app.ea_explorer_ui_enablerules;
            app.refreshstatsettings
            % draw results
            app.draw;
            app.isbusy(0);
        end

        % Value changed function: ShowPositiveFibersCheckBox
        function ShowPositiveFibersCheckBoxValueChanged(app, event)
            value = app.ShowPositiveFibersCheckBox.Value;
            app.tractset.thresholding.posvisible = value;
            app.ea_explorer_ui_enablerules;
            app.draw;

        end

        % Value changed function: ShowNegativeFibersCheckBox
        function ShowNegativeFibersCheckBoxValueChanged(app, event)
            value = app.ShowNegativeFibersCheckBox.Value;
            app.tractset.thresholding.negvisible = value;
            app.ea_explorer_ui_enablerules;
            app.draw;

        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,value,'.explorer'],'file')
                app.AnalysisIDEditField.Value = app.tractset.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end

            if exist([fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,app.tractset.ID,'.explorer'],'file')
                movefile([fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,app.tractset.ID,'.explorer'],...
                    [fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,value,'.explorer']);
            end

            ht = getappdata(app.tractset.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.tractset.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,value,'.explorer'], app.tractset.resultfig});
            end

            app.tractset.ID=value;
            app.tractset.save;

        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            app.ea_explorer_tractset2UI;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.tractset.thresholding.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.tractset.thresholding.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)
            app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' ',app.getvms]};
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            %if the selected connectomes have multiple .mat files inside
            %it, then it is a multi tract file and
            %app.tractset.multipath.multi_pathways = 1
            if exist(fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dmri_multitract folder
                if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
                    warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
                    app.tractset.multipath.multi_pathways = 0;
                else
                    %if it only exists in the multitract pathway, then
                    %check once more for the multiple .mat files and
                    %set app.tractset.multipath.multi_pathways = 1;
                    mt_connectome = fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value);
                    mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
                    mt_files = {mt_connectome_dir.name};
                    if length(mt_files) > 1
                        app.tractset.multipath.multi_pathways = 1;
                    else
                        app.tractset.multipath.multi_pathways = 0;
                    end
                end
            else
                app.tractset.multipath.multi_pathways = 0;
            end
            app.tractset.connectome = value;
            app.ea_explorer_ui_enablerules;
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.StimulationModel.Value
                case 'VTA'
                    bubbles='VTAs';
                case 'Electric Field'
                    bubbles='Efields';
                case 'Sigmoid Field'
                    bubbles='Sigmoids';
            end
            switch app.StatFamilyDropDown.Value
                case '2-Sample Tests'
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.1f',changingValue),'%'],['and < ',sprintf('%.1f',100-changingValue),'% of ',bubbles]};
                otherwise
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.1f',changingValue) '%'],['of ',bubbles]};
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            changingValue = app.ConnthresholdSlider.Value;
            app.tractset.statsettings.connthreshold = changingValue;
            switch app.StimulationModel.Value
                case 'VTA'
                    bubbles='VTAs';
                case 'Electric Field'
                    bubbles='Efields';
                case 'Sigmoid Field'
                    bubbles='Sigmoids';
            end
            switch app.StatFamilyDropDown.Value
                case '2-Sample Tests'
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.1f',changingValue),'%'],['and < ',sprintf('%.1f',100-changingValue),'% of ',bubbles]};
                otherwise
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.1f',changingValue) '%'],['of ',bubbles]};
            end
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            app.tractset.cleartune.optim = 0;
            customconfig.trainonitems=app.TrainOnListBox.Items;
            customconfig.trainonvalues=app.TrainOnListBox.Value;
            customconfig.predictonitems=app.PredictListBox.Items;
            customconfig.predictonvalues=app.PredictListBox.Value;

            ea_explorer_crossval(1,app.tractset,...
                app.StrategyDropDown.Value,...
                app.IterationsEditField.Value,...
                app.PosthoccorrectforgroupCheckBox.Value,...
                customconfig);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            if strcmp(app.StrategyDropDown.Value,'Leave-One-Patient-Out') || strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.NestedCheckBox.Visible = 'on';
            else
                app.NestedCheckBox.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value,'Import Model')
                app.SubcohortsDropDown_2.Visible = 'on';
                app.SubcohortsDropDown_2Label.Visible = 'on';
            else
                app.SubcohortsDropDown_2.Visible = 'off';
                app.SubcohortsDropDown_2Label.Visible = 'off';
            end


            if strcmp(app.StrategyDropDown.Value,'Import Model')
                app.LoadModelButton.Visible = 'on';
            else
                app.LoadModelButton.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.IterationsEditFieldLabel.Visible = 'on';
                app.IterationsEditField.Visible = 'on';
                app.IterationsEditFieldLabel.Enable = 'on';
                app.IterationsEditField.Enable = 'on';
                app.IterationsEditField.Value = 1;
            else
                app.IterationsEditFieldLabel.Visible = 'off';
                app.IterationsEditField.Visible = 'off';
                app.IterationsEditFieldLabel.Enable = 'off';
                app.IterationsEditField.Enable = 'off';
            end
            app.ea_explorer_ui_enablerules;

            % redraw if swtiching to and from Imported Model
            if strcmp(app.StrategyDropDown.Value,'Import Model') || strcmp(event.PreviousValue, 'Import Model')
                app.draw
            end

            app.ea_explorer_tractset2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('event', 'var')
                answer = questdlg("Save Changes?",'Close Discriminative Fiber Toolbox','Yes','No','Yes');
            else
                answer = 'Yes';
            end

            if strcmp(answer,'Yes') && ~isempty(app.tractset)
                app.isbusy(1,'saving');
                app.tractset.save;
            end

            if isprop(app.tractset, 'drawobject') && iscell(app.tractset.drawobject)
                for tract=1:numel(app.tractset.drawobject)
                    try
                        delete(app.tractset.drawobject{tract});
                    end
                end
            end

            if isprop(app.tractset, 'resultfig') && isvalid(app.tractset.resultfig) && startsWith(app.tractset.resultfig.Name, 'No Patient Selected')
                close(app.tractset.resultfig);
            end

            app.isbusy(0);
            delete(app);
        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.setefieldsliders;
            app.tractset.statsettings.efieldmetric=value;

            app.ea_explorer_ui_enablerules;
            app.ea_explorer_tractset2UI;

            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            end

            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.tractset.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.tractset.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.tractset.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(1:app.tractset.Nsets)');
                        app.PredictListBox.Items=sprintfc('%d',(1:app.tractset.Nsets)');
                    end
            end
        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.tractset.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshinformationfromLeadGroupanalysisMenu
        function RefreshinformationfromLeadGroupanalysisMenuSelected(app, event)
            app.tractset.refreshlg;
            app.ea_explorer_tractset2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: ConsiderSignificantFibersonlyCheckBox
        function ConsiderSignificantFibersonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantFibersonlyCheckBox.Value;
            app.tractset.thresholding.showsignificantonly=value;
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            disctract = app.tractset.fiberdrawn;
            disctract.info.Connectome = app.tractset.connectome;
            if app.ShowPositiveFibersCheckBox.Value
                disctract.info.PosAmount = sum(app.tractset.stats.pos.shown);
                if numel(app.tractset.stats.pos.shown)==2
                    disctract.info.PosAmountRight = sum(app.tractset.stats.pos.shown(1));
                    disctract.info.PosAmountLeft = sum(app.tractset.stats.pos.shown(2));
                    disctract.info.PosPercentRight = app.ShowAmountRightEditFieldPositive.Value;
                    disctract.info.PosPercentLeft = app.ShowAmountLeftEditFieldPositive.Value;
                end
                disctract.info.PosPercent = app.ShowAmountEditFieldPositive.Value;

            end
            if app.ShowNegativeFibersCheckBox.Value
                disctract.info.NegAmount = sum(app.tractset.stats.neg.shown);
                if numel(app.tractset.stats.neg.shown)==2
                    disctract.info.NegAmountRight = sum(app.tractset.stats.neg.shown(1));
                    disctract.info.NegAmountLeft = sum(app.tractset.stats.neg.shown(2));
                    disctract.info.NegPercentRight = app.ShowAmountRightEditFieldNegative.Value;
                    disctract.info.NegPercentLeft = app.ShowAmountLeftEditFieldNegative.Value;
                end
                disctract.info.NegPercent = app.ShowAmountEditFieldNegative.Value;
            end
            atlasname = app.tractset.ID;
            tractname = 'Tract';
            negativecolor = app.tractset.thresholding.negcolor;
            positivecolor = app.tractset.thresholding.poscolor;
            ea_discfibers_exportatlas(disctract, atlasname, tractname, negativecolor, positivecolor);
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.ea_explorer_ui_enablerules;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.ea_explorer_ui_enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.tractset.thresholding.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.tractset.thresholding.multcompstrategy=value;
            app.draw;
        end

        % Value changed function: StatTestDropDown
        function StatTestDropDownValueChanged(app, event)
            value = app.StatTestDropDown.Value;
            app.tractset.statsettings.stattest=value;
            app.refreshstatsettings
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.tractset.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.tractset.basepredictionon=value;
            app.ea_explorer_ui_enablerules;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.tractset.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.ea_explorer_ui_enablerules;
        end

        % Menu selected function: SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.tractset.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.tractset.rngseed = str2double(rngseed{1});
            end
        end

        % Value changed function: ModelNormalizationDropDown
        function ModelNormalizationDropDownValueChanged(app, event)
            value = app.ModelNormalizationDropDown.Value;
            app.tractset.modelNormalization=value;
        end

        % Value changed function: BinsEditField
        function BinsEditFieldValueChanged(app, event)
            value = app.BinsEditField.Value;
            app.numBins=value;
        end

        % Button pushed function: MultitractWeightsButton
        function MultitractWeightsButtonPushed(app, event)

            f = uifigure('Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 630;
            f.Position(4) = 800;
            for i=1:length(app.tractset.subscore.vars)
                slider_name{i} = app.tractset.subscore.labels{i};
            end


            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 700;
            m = 682;

            for i=1:length(slider_name)
                slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text','Weight Using..','Position',[20,m,80,25]);
                base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.tractset.subscore.weights(i))
                    slider_val_name{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChange(slider_text_box,slider_val_name,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChanging(event,slider_text_box,slider_val_name,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChange_text(slider_text_box,slider_val_name,i);
                base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


            function base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i)
                saved_labels = app.tractset.subscore.labels{i};
                saved_vars = app.tractset.subscore.vars{i};
                if strcmp(base_pred_dd{i}.Value,'None')
                    slider_text_box{i}.Enable = 'on';
                    slider_val_name{i}.Enable = 'on';
                    app.tractset.subscore.weightvars{i,1} = '';
                    app.tractset.subscore.weightvars{i,1} = [];
                    app.tractset.subscore.weights(i)=slider_val_name{i}.Value/100;
                else
                    slider_text_box{i}.Enable = 'off';
                    slider_val_name{i}.Enable = 'off';
                    index_of_new_subscore = cellfun(@(x)isequal(x,base_pred_dd{i}.Value),app.tractset.M.clinical.labels);
                    app.tractset.subscore.weightvars{i,1} = app.tractset.M.clinical.vars{index_of_new_subscore};
                    app.tractset.subscore.weightvarlabels{i,1} = base_pred_dd{i}.Value;
                    app.tractset.subscore.weights(i)=nan;
                end

            end


            function SliderChange_text(slider_text_box,slider_val_name,i)

                text_value = slider_text_box{i}.Value;
                remainingamount=1-(text_value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_text_box{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_val_name{slider}.Value = weights(slider)*100;
                    end
                end
                app.draw;
            end


            function SliderChange(slider_text_box,slider_val_name,i)
                %change value using sliders
                remainingamount=1-(slider_val_name{i}.Value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end

                app.draw;
            end

            function SliderChanging(event,slider_text_box,slider_val_name,i)
                remainingamount=1-(event.Value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=event.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.tractset.calcthreshold = value;


            hint={'Note that changing this value will not have an effect on values already calculated.'
                'You would need to repeat the calculation for each connectome of interest for changes to have an effect.'};
            uigetpref('LeadDBS','FiberFilteringShowCalcThreshHint','Fiber Filtering Explorer',hint,'Makes Sense');


        end

        % Value changed function: FittoscoresCheckBox
        function FittoscoresCheckBoxValueChanged(app, event)
            value = app.FittoscoresCheckBox.Value;
            app.tractset.doactualprediction = value;
            app.ModeltypeDropDown.Enable = ea_bool2onoff(value);

        end

        % Value changed function: ModeltypeDropDown
        function ModeltypeDropDownValueChanged(app, event)
            app.tractset.predictionmodel = app.ModeltypeDropDown.Value;
        end

        % Value changed function: AutoRefreshCheckBox
        function AutoRefreshCheckBoxValueChanged(app, event)
            value = app.AutoRefreshCheckBox.Value;

        end

        % Button down function: ColorBar
        function ColorBarButtonDown(app, event)

        end

        % Button pushed function: OpeninCleartuneButton
        function OpeninCleartuneButtonPushed(app, event)
            config.fibfiltmodelpath=fullfile(fileparts(app.tractset.leadgroup),'explorer',[app.tractset.ID,'.explorer']);
            config.tractset=app.tractset;
            config.resultfig=app.tractset.resultfig;
            ea_cleartune(config)
        end

        % Drop down opening function: ConnectomeDropDown
        function ConnectomeDropDownOpening2(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Value changed function: NestedCheckBox
        function NestedCheckBoxValueChanged(app, event)
            value = app.NestedCheckBox.Value;
            if app.NestedCheckBox.Value
                app.tractset.nestedLOO = true;
            else
                app.tractset.nestedLOO = false;
            end
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            value = app.IterationsEditField.Value;
            app.tractset.kIter = app.IterationsEditField.Value;
        end

        % Callback function: not associated with a component
        function ConnectivityModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConnectivityModelButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Stimulation Volume')
                %app.StatFamilyDropDown.Items = {'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)', 'Correlations / E-fields (Irmen 2020)', 'Proportion Test (Chi-Square) / VTAs (binary vars)', 'Reverse T-Tests / E-Fields (binary vars)', 'Plain Connections'};
                app.StatFamilyDropDown.Items = {'Correlations', 'Reverse T-Test', '1-Sample Weighted Regression', 'Weighted Regression vs. Unconnected'};
                app.tractset.switch_connectivity = 1;
                app.tractset.connectivity_type = 1;
                app.tractset.statsettings.statfamily = 'Two-Sample T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
            elseif strcmp(selectedButton.Text,'PAM')
                app.StatFamilyDropDown.Items = {'2-Sample T-Test', '1-Sample T-Test', 'Wilcoxon Rank-Sum Test', 'Wilcoxon Signed-Rank Test'};
                %if strcmp(app.StatFamilyDropDown.Value,'Correlations / E-fields (Irmen 2020)')
                %    app.StatFamilyDropDown.Value = 'T-Tests / VTAs (Baldermann 2019) / PAM (OSS-DBS)';
                %end
                app.tractset.connectivity_type = 2;
                app.tractset.statsettings.statfamily = '2-Sample T-Test';
                app.EfieldthresholdSlider.Enable = 'off';
                app.EfieldthresholdLabel.Enable = 'off';
                app.Efieldmetric.Enable = 'off';
                app.StatTestDropDown.Enable = 'off';
                app.tractset.switch_connectivity = 1;
                app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Histogram','z-scored Histogram'};
                if ~ismember(app.tractset.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                    app.tractset.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                end
            end

            % change to connectivity type
            %1 = vta, 2= pam
        end

        % Value changed function: ShowAmountEditFieldNegative
        function ShowAmountEditFieldNegativeValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldNegative.Value = value/2;
                    app.ShowAmountRightEditFieldNegative.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldNegative.Value = value;
                    app.ShowAmountRightEditFieldNegative.Value = value;
            end
            app.tractset.thresholding.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.draw;
        end

        % Value changed function: ShowAmountEditFieldPositive
        function ShowAmountEditFieldPositiveValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive.Value;
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLeftEditFieldPositive.Value = value/2;
                    app.ShowAmountRightEditFieldPositive.Value = value/2;
                otherwise
                    app.ShowAmountLeftEditFieldPositive.Value = value;
                    app.ShowAmountRightEditFieldPositive.Value = value;
            end
            app.tractset.thresholding.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldNegative
        function ShowAmountLeftEditFieldNegativeValueChanged(app, event)
            app.tractset.thresholding.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldNegative
        function ShowAmountRightEditFieldNegativeValueChanged(app, event)
            app.tractset.thresholding.shownegamount = [app.ShowAmountRightEditFieldNegative.Value, app.ShowAmountLeftEditFieldNegative.Value];
            app.draw;
        end

        % Value changed function: ShowAmountLeftEditFieldPositive
        function ShowAmountLeftEditFieldPositiveValueChanged(app, event)
            app.tractset.thresholding.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.draw;
        end

        % Value changed function: ShowAmountRightEditFieldPositive
        function ShowAmountRightEditFieldPositiveValueChanged(app, event)
            app.tractset.thresholding.showposamount = [app.ShowAmountRightEditFieldPositive.Value, app.ShowAmountLeftEditFieldPositive.Value];
            app.draw;
        end

        % Value changed function: ThresholdingDropDown
        function ThresholdingDropDownValueChanged(app, event)
            value = app.ThresholdingDropDown.Value;
            app.tractset.thresholding.threshstrategy=value;
            app.ShowAmountEditFieldPositive.Enable = 'on';
            app.ShowAmountEditFieldNegative.Enable = 'on';
            switch value
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountRightEditFieldPositive.Value = 0;
                    app.ShowAmountLeftEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                    app.ShowAmountRightEditFieldNegative.Value = 0;
                    app.ShowAmountLeftEditFieldNegative.Value = 0;
                    app.PlotDistributionButton.Visible = 'off';
                case 'Histogram (CDF)'
                    app.ShowAmountEditFieldPositive.Value = 0.01;
                    app.ShowAmountRightEditFieldPositive.Value = 0.01;
                    app.ShowAmountLeftEditFieldPositive.Value = 0.01;
                    app.ShowAmountEditFieldNegative.Value = 0.01;
                    app.ShowAmountRightEditFieldNegative.Value = 0.01;
                    app.ShowAmountLeftEditFieldNegative.Value = 0.01;
                    app.PlotDistributionButton.Visible = 'on';
                case 'Fixed Fiber Value'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                    app.ShowAmountRightEditFieldPositive.Value = 0;
                    app.ShowAmountLeftEditFieldPositive.Value = 0;
                    app.ShowAmountRightEditFieldNegative.Value = 0;
                    app.ShowAmountLeftEditFieldNegative.Value = 0;
                    app.PlotDistributionButton.Visible = 'on';
                otherwise
                    app.ShowAmountEditFieldPositive.Value = 25;
                    app.ShowAmountEditFieldNegative.Value = 25;
                    app.ShowAmountRightEditFieldPositive.Value = 25;
                    app.ShowAmountLeftEditFieldPositive.Value = 25;
                    app.ShowAmountRightEditFieldNegative.Value = 25;
                    app.ShowAmountLeftEditFieldNegative.Value = 25;
                    app.PlotDistributionButton.Visible = 'off';
            end
            app.tractset.thresholding.showposamount=[app.ShowAmountRightEditFieldPositive.Value,app.ShowAmountLeftEditFieldPositive.Value];
            app.tractset.thresholding.shownegamount=[app.ShowAmountRightEditFieldNegative.Value,app.ShowAmountLeftEditFieldNegative.Value];
            app.ea_explorer_ui_enablerules;
            app.draw;
        end

        % Menu selected function: RecreatevisualizationfigureMenu
        function RecreatevisualizationfigureMenuSelected(app, event)
            if isvalid(app.tractset.resultfig)
                close(app.tractset.resultfig);
            end
            app.tractset.resultfig = ea_mnifigure;
            app.draw(1);
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            [FileName,PathName]=uiputfile('*.mat','File to save the Fiber Model...');
            ExternalModelFile = fullfile(PathName,FileName);
            ea_save_fibscore_model(app.tractset, ExternalModelFile)
        end

        % Value changed function: LoadModelButton
        function LoadModelButtonValueChanged(app, event)
            [FileName,PathName]=uigetfile('*.mat','Please Choose a Fiber Model for the selected connectome...');
            if strcmp(string(FileName),'0')
                waitfor(msgbox('The Fiber Model was not chosen!'));
                return;
            end

            [~, ExternalModelFileName] = fileparts(app.tractset.ExternalModelFile);
            app.TrainOnListBox.Items = string(ExternalModelFileName); % this does not update on-the-fly
            app.tractset.ExternalModelFile = fullfile(PathName,FileName);
            app.draw(1)
        end

        % Value changed function: SubcohortsDropDown_2
        function SubcohortsDropDown_2ValueChanged(app, event)
            value = app.SubcohortsDropDown_2.Value;
            [~,whichset]=ismember(value,app.tractset.setlabels);
            app.PredictListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});


            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;


            %             tsets = find(ismember(app.tractset.setlabels, app.TrainOnListBox.Value));
            %             training=[];
            %             for ts=1:length(tsets)
            %                 training = [training,find(app.tractset.setselections{tsets(ts)})];
            %             end
            %             training=unique(training);
            %             tsets = find(ismember(app.tractset.setlabels, app.PredictListBox.Value));
            %             test=[];
            %             for ts=1:length(tsets)
            %                 test = [test,find(app.tractset.setselections{tsets(ts)})];
            %             end
            %             test=unique(test);
            %             % Patient selected based on the training and test indices
            %             app.tractset.customselection = unique([training, test]);
            %             % Construct cvp struct
            %             cvp.training{1} = ismember(app.tractset.customselection, training);
            %             cvp.test{1} = ismember(app.tractset.customselection, test);


            %             app.tractset.customselection = unique([training, test]);
            %             % Construct cvp struct
            %             cvp.training{1} = ismember(app.tractset.customselection, training);
            %             cvp.test{1} = ismember(app.tractset.customselection, test);

            %app.draw;
            % always reset to "Choose..."
            app.ea_explorer_ui_enablerules;


        end

        % Menu selected function: OptimizeParametersontrainingsetMenu
        function OptimizeParametersontrainingsetMenuSelected(app, event)

        end

        % Menu selected function: CrossvalidationsMenu
        function CrossvalidationsMenuSelected(app, event)
            ea_discfibers_optimize(app.tractset,app,'cv');
            app.ea_explorer_tractset2UI;
            app.ea_explorer_ui_enablerules;
            app.draw;
        end

        % Menu selected function: SimilaritiesDissimilaritiesMenu
        function SimilaritiesDissimilaritiesMenuSelected(app, event)
            ea_discfibers_optimize(app.tractset,app,'sim');
            app.ea_explorer_tractset2UI;
            app.ea_explorer_ui_enablerules;
            app.draw;
        end

        % Menu selected function: CombinedMenu
        function CombinedMenuSelected(app, event)
            ea_discfibers_optimize(app.tractset,app,'comb');
            app.ea_explorer_tractset2UI;
            app.ea_explorer_ui_enablerules;
            app.draw;
        end

        % Menu selected function: LoadPriorOptimization
        function LoadPriorOptimizationMenuSelected(app, event)
            optFile = uigetfile('*.mat', 'Choose Prior Optmization Status File', fileparts(app.tractset.leadgroup));
            if ischar(optFile)
                ea_discfibers_optimize(app.tractset, app, 'comb', optFile, 'stop');
                app.ea_explorer_tractset2UI;
                app.ea_explorer_ui_enablerules;
                app.draw;
            end
        end

        % Menu selected function: LoadPriorOptimizationAndResume
        function LoadPriorOptimizationAndResumeMenuSelected(app, event)
            optFile = uigetfile('*.mat', 'Choose Prior Optmization Status File', fileparts(app.tractset.leadgroup));
            if ischar(optFile)
                ea_discfibers_optimize(app.tractset, app, 'comb', optFile, 'resume');
                app.ea_explorer_tractset2UI;
                app.ea_explorer_ui_enablerules;
                app.draw;
            end
        end

        % Button pushed function: OpeninCurrentuneButton
        function OpeninCurrentuneButtonPushed(app, event)
            f = uifigure('Name','Currentune','Scrollable','on');
            %f.Position(1) = 800;
            %f.Position(2) = 600;
            f.Position(3) = 1030;
            f.Position(4) = 800;
            for i=1:length(app.tractset.subscore.vars)
                slider_name{i} = app.tractset.subscore.labels{i};
            end

            j = 720; %start position of the label
            k = 700; %start position of the slider
            l = 690;
            m = 682;

            frame = uipanel(f,'Title', 'Weights', 'FontSize',12, 'Position', [10, m - 10, 550,20]);

            % Current Optimizer
            frame2 = uipanel(f,'Title', 'Current Optimizer', 'FontSize',12, 'Position', [580, 547, 280,200]);

            ConcLbl = uilabel(f,'Text','Current Limits for Cylindrical Contacts, mA','Position',[590,697,300,25],'FontWeight','bold');
            ConcLblMin = uilabel(f,'Text','Min:','Position',[590,667,40,25]);
            ConcMin = uieditfield(f,'numeric','Value',-4.0, 'Position',[630,667,40,25], "ValueChangedFcn",@(ConcMin,event) ConcMinChanged(ConcMin));
            ConcMin.Limits = [-10.0, 10.0];  % m
            ConcLblMax = uilabel(f,'Text','Max:','Position',[700,667,40,25]);
            ConcMax = uieditfield(f,'numeric','Value',4.0, 'Position',[740,667,40,25], "ValueChangedFcn",@(ConcMax,event) ConcMaxChanged(ConcMax));
            ConcMax.Limits = [-10.0, 10.0];  % mA

            SegmLbl = uilabel(f,'Text','Current Limits for Segment Contacts, mA','Position',[590,627,300,25],'FontWeight','bold');
            SegmLblMin = uilabel(f,'Text','Min:','Position',[590,597,40,25]);
            SegmMin = uieditfield(f,'numeric','Value',-2.0, 'Position',[630,597,40,25], "ValueChangedFcn",@(SegmMin,event) SegmMinChanged(SegmMin));
            SegmMin.Limits = [-5.0, 5.0];  % mA
            SegmLblMax = uilabel(f,'Text','Max:','Position',[700,597,40,25]);
            SegmMax = uieditfield(f,'numeric','Value',2.0, 'Position',[740,597,40,25], "ValueChangedFcn",@(SegmMax,event) SegmMaxChanged(SegmMax));
            SegmMax.Limits = [-5.0, 5.0];  % mA


            % create a button for Export and Launch
            ExportSymptomPathwayBTN = uibutton(f,'Text','Export Item-grouped Pathways', 'Position', [10,m+80,200,25], 'ButtonPushedFcn', @(ExportSymptomPathwayBTN,event) ExportSymptomPathwayButtonPushed(ExportSymptomPathwayBTN));
            Launch_NetBlend = uibutton(f,'Text','Launch Network Blender', 'Position', [800,500,200,25], 'ButtonPushedFcn', @(Launch_NetBlend,event) Launch_NetBlendButtonPushed(Launch_NetBlend,slider_name));

            for i=1:length(slider_name)
                %slider_lab_name{i} = uilabel(f,'Text',slider_name{i},'Position',[20,j,500,22],'FontWeight','bold');
                slider_val_name{i} = uislider(f,'Limits',[0 100]);
                slider_val_name{i}.Position(1) = 280;
                slider_val_name{i}.Position(2) = k;
                slider_val_name{i}.Position(3) = 200;
                slider_text_box{i} = uieditfield(f,'numeric','Position',[500,l,50,22]);
                % ix = cellfun(@(x)isequal(x,app.tractset.subscore.labels{i,1}),app.tractset.M.clinical.labels);
                base_pred_lbl{i} = uilabel(f,'Text',slider_name{i},'Position',[20,m,180,25],'FontWeight','bold');

                fixed_weights{i} = uicheckbox(f,'Text', 'fixed', 'Position',[200,m,80,25]);

                %base_pred_dd{i} = uidropdown(f,'Items',horzcat('None',app.tractset.M.clinical.labels),'Value','None','Position',[100,m,80,25]);
                j = j-70;
                k = k-70;
                l = l-70;
                m = m-70;

                % update values already stored
                if ~isnan(app.tractset.subscore.weights(i))
                    slider_val_name{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_text_box{i}.Value=app.tractset.subscore.weights(i)*100;
                    slider_val_name{i}.Enable='on';
                    slider_text_box{i}.Enable='on';
                else
                    slider_val_name{i}.Enable='off';
                    slider_text_box{i}.Enable='off';
                    %fixed_weights{i}.Value = app.tractset.subscore.CheckedWeights{i};

                    %base_pred_dd{i}.Value=app.tractset.subscore.weightvarlabels{i};
                end

            end

            for i=1:length(slider_val_name)
                slider_val_name{i}.ValueChangedFcn = @(app,event) SliderChangeCurrentune(slider_text_box,slider_val_name,fixed_weights,i);
                slider_val_name{i}.ValueChangingFcn = @(app,event) SliderChangingCurrentune(event,slider_text_box,slider_val_name,fixed_weights,i);
                slider_text_box{i}.ValueChangedFcn = @(app,event) SliderChangeCurrentune_text(slider_text_box,slider_val_name,fixed_weights,i);
                fixed_weights{i}.ValueChangedFcn = @(app,event) FixedWeightcBoxChanged(fixed_weights,slider_val_name,Launch_NetBlend,i);

                %base_pred_dd{i}.ValueChangedFcn = @(app,event) base_pred_fcn(base_pred_dd,slider_text_box,slider_lab_name,slider_val_name,i);
            end


            PatientID = uieditfield(f,'text','Value','John Doe', 'Position',[190,m+5,150,25], "Editable","off", "ValueChangedFcn",@(PatientID,event) PatientIDChanged(PatientID));
            ChoosePatient = uibutton(f,'Text','Choose a patient', 'Position', [10,m+5,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) ChoosePatientButtonPushed(ChoosePatient,PatientID));
            PredictImprovement = uibutton(f,'Text','Predict Improvement', 'Position', [380,m+5,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) PredictImprovementButtonPushed(PredictImprovement,fixed_weights,slider_name));
            CurrentOptimizer = uibutton(f,'Text','Optimize Currents', 'Position', [380,m+5-40,150,25], 'ButtonPushedFcn', @(ChoosePatient,event) CurrentOptimizerButtonPushed(CurrentOptimizer,fixed_weights,slider_name));

            GenerateTrainTest = uibutton(f,'Text','Generate Train/Test sets for ANN', 'Position', [580,500,200,25], 'ButtonPushedFcn', @(GenerateTrainTest,event) GenerateTrainTestButtonPushed(GenerateTrainTest,PatientID));

            lower_lim = m - 10 + 60;

            %m = 682;

            % adjust the frame size
            frame.Position(2) = lower_lim;
            frame.Position(4) = 682 + 65 - lower_lim;

            function GenerateTrainTestButtonPushed(GenerateTrainTest, PatientID)
                % we will choose number of samples depending on the
                % electrode model within Network Blending

                % add a sanity check for current limits
                app.tractset.currentune.min_conc = ConcMin.Value;
                app.tractset.currentune.max_conc = ConcMax.Value;
                app.tractset.currentune.min_segm = SegmMin.Value;
                app.tractset.currentune.max_segm = SegmMax.Value;

                if strcmp(PatientID.Value, 'John Doe')
                    ea_warndlg("Choose a patient first.")
                    return
                end

                % for now, hardwired to run in native
                app.tractset.currentune.stimfolder = fullfile(app.tractset.currentune.patient, 'stimulations/native/NB_stim/');
                mkdir(app.tractset.currentune.stimfolder)

                % we need to pass the reconstruction file
                reconstruction_file = dir([app.tractset.currentune.patient,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);

                try
                    reconst = load([app.tractset.currentune.patient,filesep,'reconstruction',filesep,reconstruction_file.name]);
                catch
                    ea_warndlg('The reconstruction file was not found')
                    return
                end

                if length(reconst.reco.props) == 2
                    %sides = 2;  % both sides implanted
                    el_model_right = strrep(reconst.reco.props(1).elmodel, ' ','_');
                    el_model_left = strrep(reconst.reco.props(2).elmodel, ' ','_');
                elseif reconst.reco.mni.markers(1).head(1) > 0.0
                    %sides = 0; % right side
                    el_model_right = strrep(reconst.reco.props(1).elmodel, ' ','_');
                    el_model_left = -1;
                else
                    %sides = 1; % left side
                    el_model_right = -1;
                    el_model_left = strrep(reconst.reco.props(2).elmodel, ' ','_');
                end

                % call .py script
                system(['python', ' ', ea_getearoot, 'currentune/TrainTest_Generator.py ', ...
                    app.tractset.currentune.stimfolder(1:end-1), ' ', el_model_right, ' ', el_model_left, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
            end

            function ChoosePatientButtonPushed(ChoosePatient,PatientID)
                [selpath]=uigetdir('*.mat','Please Choose a Patient Folder');
                if strcmp(string(selpath),'0')
                    waitfor(msgbox('No patient folder was not chosen!'));
                    return;
                else
                    app.tractset.currentune.patient = selpath;
                    [folderpath,foldername,~] = fileparts(app.tractset.currentune.patient);
                    PatientID.Value = foldername;
                end
            end

            function ConcMinChanged(ConcMin)
                app.tractset.currentune.min_conc = ConcMin.Value;
            end

            function ConcMaxChanged(ConcMax)
                app.tractset.currentune.max_conc = ConcMax.Value;
            end

            function SegmMinChanged(SegmMin)
                app.tractset.currentune.min_segm = SegmMin.Value;
            end

            function SegmMaxChanged(SegmMax)
                app.tractset.currentune.max_segm = SegmMax.Value;
            end

            function PredictImprovementButtonPushed(PredictImprovement, fixed_weights, slider_name)
                % add a sanity check for current limits
                app.tractset.currentune.min_conc = ConcMin.Value;
                app.tractset.currentune.max_conc = ConcMax.Value;
                app.tractset.currentune.min_segm = SegmMin.Value;
                app.tractset.currentune.max_segm = SegmMax.Value;

                % for now, hardwired to run in native
                app.tractset.currentune.stimfolder = fullfile(app.tractset.currentune.patient, 'stimulations/native/NB_stim/');
                mkdir(app.tractset.currentune.stimfolder)

                % create a json with fixed symptoms and weights
                fixed_weight_flag = 0;
                for i = 1:length(fixed_weights)
                    if fixed_weights{i}.Value == 1
                        jsonDict.fixed_symptom_weights.(genvarname(slider_name{i})) = app.tractset.subscore.weights(i);
                        fixed_weight_flag = fixed_weight_flag + 1;
                    end
                end

                ea_warndlg("Compute PAM for the exported connectome and press OK")

                if fixed_weight_flag == 0
                    ea_warndlg("No symptoms were fixed, please choose at least one")
                    return
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
                else
                    % save where the FF file is
                    %[filepath,~,~] = fileparts(app.tractset.leadgroup);

                    json_file = [app.tractset.currentune.stimfolder,'Fixed_symptoms.json'];

                    jsonText = jsonencode(jsonDict);
                    fid = fopen(json_file, 'w');
                    fprintf(fid, '%s', jsonText)
                    fclose(fid);

                    % we can also run OSS-DBS directly from here, but not
                    % for now

                    % IMPORTANT: for now we predict only the right side (0)
                    system(['python', ' ', ea_getearoot, 'currentune/Improvement4Protocol.py ', ...
                        app.tractset.currentune.stimfolder(1:end-1), ' ', num2str(0), ' ', app.tractset.currentune.ActivationProfileDict, ' ', json_file]);	% 0 is right side, 1 is the left side here
                end

            end

            function Launch_NetBlendButtonPushed(Launch_NetBlend, fixed_weights, slider_name)

                % add a sanity check for current limits
                app.tractset.currentune.min_conc = ConcMin.Value;
                app.tractset.currentune.max_conc = ConcMax.Value;
                app.tractset.currentune.min_segm = SegmMin.Value;
                app.tractset.currentune.max_segm = SegmMax.Value;

                % create a json with fixed symptoms and weights
                fixed_weight_flag = 0;
                for i = 1:length(fixed_weights)
                    if fixed_weights{i}.Value == 1
                        jsonDict.fixed_symptom_weights.(genvarname(slider_name{i})) = app.tractset.subscore.weights(i);
                        fixed_weight_flag = fixed_weight_flag + 1;
                    end
                end

                % in this case, throw a warning and run a current
                % optimization only
                if fixed_weight_flag == length(fixed_weights)
                    ea_warndlg("All weights were fixed, only currents will be optimized")
                elseif fixed_weight_flag == 0
                    ea_warndlg("No symptoms were fixed")
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm)]);	% 0 is right side, 1 is the left side here
                else
                    % save where the FF file is
                    [filepath,~,~] = fileparts(app.tractset.leadgroup);
                    json_file = [filepath,filesep,'Fixed_symptoms.json'];

                    jsonText = jsonencode(jsonDict);
                    fid = fopen(json_file, 'w');
                    fprintf(fid, '%s', jsonText)
                    fclose(fid);

                    % call NetBlend
                    % pass the connectome and the location of json and ea_getearoot
                    system(['python', ' ', ea_getearoot, 'currentune/FFtoPython.py ', ...
                        ea_getearoot, ' ', num2str(app.tractset.currentune.min_conc), ' ', num2str(app.tractset.currentune.max_conc), ' ', num2str(app.tractset.currentune.min_segm), ' ', num2str(app.tractset.currentune.max_segm), ' ', json_file]);	% 0 is right side, 1 is the left side here

                end

                % add selection of a stimfolder, some parameters will be
                % taken from there

                % retrieve new weights for non-fixed. Disable their adjustment?

            end

            function FixedWeightcBoxChanged(fixed_weights,slider_val_name,Launch_NetBlend,i)
                %app.tractset.subscore.CheckedWeights{i} = fixed_weights{i}.Value;

                % initialize
                if all(isnan(app.tractset.subscore.weights))
                    app.tractset.subscore.weights(:) = 1 / size(app.tractset.subscore.weights,1);
                end


                N_fixed = 0;
                inx_nonfixed = [];
                for k = 1:length(fixed_weights)
                    app.tractset.currentune.CheckedWeights{k} = fixed_weights{k}.Value;
                    slider_val_name{k}.Enable = 1; % reinitialize
                    Launch_NetBlend.Enable = 1;
                    if fixed_weights{k}.Value == 1
                        N_fixed = N_fixed + 1;
                    else
                        inx_nonfixed = [inx_nonfixed,k];
                    end
                end

                % if all fixed, disable sliders
                if N_fixed == length(fixed_weights)
                    for k = 1:length(fixed_weights)
                        slider_val_name{k}.Enable = 0; % no weights to optimize
                    end
                    Launch_NetBlend.Enable = 0;
                    % if all but one fixed, disable only that slider
                elseif N_fixed == length(fixed_weights) - 1
                    slider_val_name{inx_nonfixed}.Enable = 0; % is only one value in this case
                    Launch_NetBlend.Enable = 0; % no weights to optimize
                end
            end

            function ExportSymptomPathwayButtonPushed(ExportSymptomPathwayBTN)
                [FileName,PathName]=uiputfile('*.json','File to save the symptom-tract dictionary...');
                app.tractset.currentune.ActivationProfileDict = fullfile(PathName,FileName);
                ea_export_symptoms_tracts(app.tractset,app.tractset.currentune.ActivationProfileDict);
            end

            function SliderChangeCurrentune(slider_text_box,slider_val_name,fixed_weights,i)
                %change value using sliders
                %remainingamount=1-(slider_val_name{i}.Value/100);

                % change limits to the remainingamount for non-fixed
                % symptoms
                remainingamount = 1;
                idx_bool = zeros(length(fixed_weights),1);
                for k = 1:length(fixed_weights)
                    if k == i || fixed_weights{k}.Value == 1
                        idx_bool(k) = 1;
                        remainingamount = remainingamount - app.tractset.subscore.weights(k);
                    end
                end
                idx_bool = logical(idx_bool);


                % enforce limits on adjusted if all other fixed
                if all(idx_bool)
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    slider_val_name{k}.Limits(2) = ceil(1 - ea_nansum(app.tractset.subscore.weights(nonadjusted)));
                else
                    for k = 1:length(idx_bool)
                        if ~idx_bool(k)
                            slider_val_name{k}.Limits(2) = ceil(remainingamount * 100);
                        end
                    end
                end

                otherentries=1:length(slider_val_name);
                %otherentries(i)=[];
                otherentries(idx_bool) = [];

                weights=app.tractset.subscore.weights;
                %                 if ~all(idx_bool)
                %                     weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                %
                %                     nonadjusted = 1:length(slider_val_name);
                %                     nonadjusted(i)=[];
                %                     weights(i)= remainingamount - ea_nansum(weights(nonadjusted));
                %                 end
                %                 %weights(i)=slider_val_name{i}.Value/100;

                if ~all(idx_bool)
                    % some weights are free for auto-adjustment
                    weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                    weights(i)=event.Value/100;
                else
                    % special case if all but one fixed
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    weights(i)= remainingamount - floor(ea_nansum(weights(nonadjusted)));
                end

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end

                app.draw;
            end

            function SliderChangingCurrentune(event,slider_text_box,slider_val_name,fixed_weights,i)


                %remainingamount=1-(event.Value/100);


                % change limits to the remainingamount for non-fixed
                % symptoms
                remainingamount = 1;
                idx_bool = zeros(length(fixed_weights),1);
                for k = 1:length(fixed_weights)
                    if k == i || fixed_weights{k}.Value == 1
                        idx_bool(k) = 1;
                        remainingamount = remainingamount - app.tractset.subscore.weights(k);
                    end
                end
                idx_bool = logical(idx_bool);

                % enforce limits on adjusted if all other fixed
                if all(idx_bool)
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    slider_val_name{k}.Limits(2) = ceil(1 - ea_nansum(app.tractset.subscore.weights(nonadjusted)));
                else
                    for k = 1:length(idx_bool)
                        if ~idx_bool(k)
                            slider_val_name{k}.Limits(2) = ceil(remainingamount * 100);
                        end
                    end
                end

                otherentries=1:length(slider_val_name);
                %otherentries(i)=[];
                otherentries(idx_bool) = [];
                weights=app.tractset.subscore.weights;

                if ~all(idx_bool)
                    % some weights are free for auto-adjustment
                    weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                    weights(i)=event.Value/100;
                else
                    % special case if all but one fixed
                    nonadjusted = 1:length(slider_val_name);
                    nonadjusted(i)=[];
                    weights(i)= remainingamount - floor(ea_nansum(weights(nonadjusted)));
                end
                %weights(i)=event.Value/100;


                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_val_name{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_text_box{slider}.Value = weights(slider)*100;
                    end
                end
            end


            function SliderChangeCurrentune_text(slider_text_box,slider_val_name,i)

                text_value = slider_text_box{i}.Value;
                remainingamount=1-(text_value/100);
                otherentries=1:length(slider_val_name);
                otherentries(i)=[];
                weights=app.tractset.subscore.weights;
                weights(otherentries)=weights(otherentries)*(remainingamount/ea_nansum(weights(otherentries)));
                weights(i)=slider_val_name{i}.Value/100;

                for slider=1:length(slider_val_name)
                    if ~isnan(weights(slider))
                        slider_text_box{slider}.Value=weights(slider)*100;
                        app.tractset.subscore.weights(slider) = [weights(slider)];
                        slider_val_name{slider}.Value = weights(slider)*100;
                    end
                end
                app.draw;
            end


        end

        % Button pushed function: RefineFiberColor
        function RefineFiberColorButtonPushed(app, event)
            ea_discfibers_refinefibercolor(app.tractset.drawobject, app.tractset.drawvals);
        end

        % Menu selected function: LoadSettingsMenu
        function LoadSettingsMenuSelected(app, event)

            % Load the settings
            [file,path] = uigetfile({"*.mat";'.'},...
                "Select settings .mat File");
            loaded_tractset = load(fullfile(path, file));

            % Update app settings
            app.tractset = ea_update_settings(app.tractset,loaded_tractset);

            % Update settings on GUI
            app.ea_explorer_tractset2UI

        end

        % Menu selected function: SaveSettingsMenu
        function SaveSettingsMenuSelected(app, event)

            %Let the user choose the file to save at
            [file,path,~] = uiputfile({'*.mat';'*.*'},"Save as");

            % Save the settings
            ea_save_settings(app.tractset,fullfile(path,file))

        end

        % Button pushed function: WeighttractsbyROIButton
        function WeighttractsbyROIButtonPushed(app, event)
            [roiFilename,PathName]=uigetfile('*.nii','Please Choose Nifti file...');
            if strcmp(string(roiFilename),'0')
                waitfor(msgbox('The Fiber Model was not chosen!'));
                return;
            end
            app.WeighttractsbyROIButton.Text = 'Loaded';
            app.tractset.roiintersectdata = {fullfile(PathName,roiFilename)};
            app.tractset.roithresh = app.ThresholdEditField_2.Value;
            app.tractset.activateby = 'roiintersect';
            app.draw;
        end

        % Value changed function: ThresholdEditField_2
        function ThresholdEditField_2ValueChanged(app, event)
            app.tractset.roithresh = app.ThresholdEditField_2.Value;
            app.tractset.activateby = 'roiintersect';
            app.draw;
        end

        % Value changed function: StimulationModel
        function StimulationModelValueChanged(app, event)
            value = app.StimulationModel.Value;
            app.tractset.statsettings.stimulationmodel=value;
            switch value
                case 'Electric Field'
                    app.EfieldmetricLabel.Visible=true;
                    app.Efieldmetric.Visible=true;
                    app.Efieldmetric.Enable=true;
                    app.tractset.statsettings.efieldmetric=app.Efieldmetric.Value;
                    app.StatFamilyDropDown.set('Value','Correlations')
                case 'Sigmoid Field'
                    app.EfieldmetricLabel.Visible=false;
                    app.Efieldmetric.Visible=false;                    
                    app.Efieldmetric.Value='Peak';
                    app.StatFamilyDropDown.set('Value','Correlations')                    
                case 'VTA'
                    app.EfieldmetricLabel.Visible=false;
                    app.Efieldmetric.Value='Peak';
                    app.Efieldmetric.Visible=false;
                    app.StatFamilyDropDown.set('Value','1-Sample Tests')
            end            
            app.setefieldsliders;
            app.ea_explorer_ui_enablerules;
            app.refreshstatsettings
        end

        % Value changed function: StatH0DropDown
        function StatH0DropDownValueChanged(app, event)
            app.StatH0DropDown.Value=event.Value;
            app.tractset.statsettings.H0 = event.Value;
            app.refreshstatsettings
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            value = app.ResolutionEditField.Value;
            app.tractset.resolution =  value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshinformationfromLeadGroupanalysisMenu = uimenu(app.ToolsMenu);
            app.RefreshinformationfromLeadGroupanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshinformationfromLeadGroupanalysisMenuSelected, true);
            app.RefreshinformationfromLeadGroupanalysisMenu.Text = 'Refresh information from Lead-Group analysis';
            app.RecreatevisualizationfigureMenu = uimenu(app.ToolsMenu);
            app.RecreatevisualizationfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @RecreatevisualizationfigureMenuSelected, true);
            app.RecreatevisualizationfigureMenu.Text = 'Recreate visualization figure';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.OptimizeParametersontrainingsetMenu = uimenu(app.ToolsMenu);
            app.OptimizeParametersontrainingsetMenu.MenuSelectedFcn = createCallbackFcn(app, @OptimizeParametersontrainingsetMenuSelected, true);
            app.OptimizeParametersontrainingsetMenu.Text = 'Optimize Parameters (on training set)';
            app.CrossvalidationsMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.CrossvalidationsMenu.MenuSelectedFcn = createCallbackFcn(app, @CrossvalidationsMenuSelected, true);
            app.CrossvalidationsMenu.Text = 'Crossvalidations';
            app.SimilaritiesDissimilaritiesMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.SimilaritiesDissimilaritiesMenu.MenuSelectedFcn = createCallbackFcn(app, @SimilaritiesDissimilaritiesMenuSelected, true);
            app.SimilaritiesDissimilaritiesMenu.Text = 'Similarities/Dissimilarities';
            app.CombinedMenu = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.CombinedMenu.MenuSelectedFcn = createCallbackFcn(app, @CombinedMenuSelected, true);
            app.CombinedMenu.Text = 'Combined';
            app.LoadPriorOptimization = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.LoadPriorOptimization.MenuSelectedFcn = createCallbackFcn(app, @LoadPriorOptimizationMenuSelected, true);
            app.LoadPriorOptimization.Text = 'Load Pior Optimization';
            app.LoadPriorOptimizationAndResume = uimenu(app.OptimizeParametersontrainingsetMenu);
            app.LoadPriorOptimizationAndResume.MenuSelectedFcn = createCallbackFcn(app, @LoadPriorOptimizationAndResumeMenuSelected, true);
            app.LoadPriorOptimizationAndResume.Text = 'Load Pior Optimization and Resume';
            app.LoadSettingsMenu = uimenu(app.ToolsMenu);
            app.LoadSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSettingsMenuSelected, true);
            app.LoadSettingsMenu.Text = 'Load Settings';
            app.SaveSettingsMenu = uimenu(app.ToolsMenu);
            app.SaveSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSettingsMenuSelected, true);
            app.SaveSettingsMenu.Text = 'Save Settings';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ConnectomeDropDownOpening2, true);
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditFieldLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ConnectomeSelectionPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 50;
            app.VmLabel = uilabel(app.ConnectomeSelectionPanel);
            app.VmLabel.Text = 'V/m';
            app.ResolutionEditFieldLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.ConnectomeSelectionPanel, 'numeric');
            app.ResolutionEditField.Limits = [0 Inf];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 0.5;
            app.mmLabel = uilabel(app.ConnectomeSelectionPanel);
            app.mmLabel.Text = 'mm';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)', 'Import Model'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Enable = 'off';
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Profile of Scores', 'Sum of Scores', 'Mean of Scores', 'Peak of Scores', 'Peak 5% of Scores', 'Histogram', 'z-scored Histogram'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Enable = 'off';
            app.BasePredictiononDropDown.Value = 'Profile of Scores';
            app.ModelNormalizationDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDownLabel.Enable = 'off';
            app.ModelNormalizationDropDownLabel.Text = 'Model Normalization';
            app.ModelNormalizationDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModelNormalizationDropDown.Items = {'None', 'z-score', 'van Albada 2007'};
            app.ModelNormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelNormalizationDropDownValueChanged, true);
            app.ModelNormalizationDropDown.Enable = 'off';
            app.ModelNormalizationDropDown.Value = 'None';
            app.BinsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BinsEditFieldLabel.Enable = 'off';
            app.BinsEditFieldLabel.Visible = 'off';
            app.BinsEditFieldLabel.Text = '#Bins';
            app.BinsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.BinsEditField.ValueChangedFcn = createCallbackFcn(app, @BinsEditFieldValueChanged, true);
            app.BinsEditField.Enable = 'off';
            app.BinsEditField.Visible = 'off';
            app.BinsEditField.Value = 15;
            app.FittoscoresCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.FittoscoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @FittoscoresCheckBoxValueChanged, true);
            app.FittoscoresCheckBox.Enable = 'off';
            app.FittoscoresCheckBox.Text = 'Fit to scores';
            app.ModeltypeDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.ModeltypeDropDown.Items = {'Constant', 'Linear', 'Quadratic'};
            app.ModeltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeltypeDropDownValueChanged, true);
            app.ModeltypeDropDown.Enable = 'off';
            app.ModeltypeDropDown.Value = 'Linear';
            app.MultitractWeightsButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.MultitractWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @MultitractWeightsButtonPushed, true);
            app.MultitractWeightsButton.Enable = 'off';
            app.MultitractWeightsButton.Text = 'Multitract Weights...';
            app.OpeninCleartuneButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.OpeninCleartuneButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninCleartuneButtonPushed, true);
            app.OpeninCleartuneButton.Enable = 'off';
            app.OpeninCleartuneButton.Text = 'Open in Cleartune';
            app.IterationsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.IterationsEditFieldLabel.Enable = 'off';
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Enable = 'off';
            app.NestedCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.NestedCheckBox.ValueChangedFcn = createCallbackFcn(app, @NestedCheckBoxValueChanged, true);
            app.NestedCheckBox.Text = 'Nested';
            app.LoadModelButton = uibutton(app.CrossvalidationPredictionPanel, 'state');
            app.LoadModelButton.ValueChangedFcn = createCallbackFcn(app, @LoadModelButtonValueChanged, true);
            app.LoadModelButton.Visible = 'off';
            app.LoadModelButton.Text = 'Load Model';
            app.SubcohortsDropDown_2Label = uilabel(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2Label.Visible = 'off';
            app.SubcohortsDropDown_2Label.Text = 'Subcohorts';
            app.SubcohortsDropDown_2 = uidropdown(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDown_2ValueChanged, true);
            app.SubcohortsDropDown_2.Visible = 'off';
            app.ImportedModelName = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.ImportedModelName.Enable = 'off';
            app.ImportedModelName.Visible = 'off';
            app.OpeninCurrentuneButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.OpeninCurrentuneButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninCurrentuneButtonPushed, true);
            app.OpeninCurrentuneButton.Enable = 'off';
            app.OpeninCurrentuneButton.Visible = 'off';
            app.OpeninCurrentuneButton.Text = 'Open in Currentune';
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleTractAnalysisTab = uitab(app.TabGroup);
            app.SingleTractAnalysisTab.Title = 'Single Tract Analysis';
            app.ColorBar = uiaxes(app.SingleTractAnalysisTab);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorBar.ButtonDownFcn = createCallbackFcn(app, @ColorBarButtonDown, true);
            app.ColorButtonPositive = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.levelEditFieldLabel = uilabel(app.SingleTractAnalysisTab);
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Value = 0.05;
            app.ShowAmountRightLabelNegative = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountRightLabelNegative.Enable = 'off';
            app.ShowAmountRightLabelNegative.Text = 'Right';
            app.ShowAmountLabelNegative = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLabelNegative.Enable = 'off';
            app.ShowAmountLabelNegative.Text = 'Show Amount';
            app.ShowAmountLeftLabelNegative = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLeftLabelNegative.Enable = 'off';
            app.ShowAmountLeftLabelNegative.Text = 'Left';
            app.ShowAmountRightLabelPositive = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountRightLabelPositive.Text = 'Right';
            app.ShowAmountLeftLabelPositive = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLeftLabelPositive.Text = 'Left';
            app.ShowAmountLabelPositive = uilabel(app.SingleTractAnalysisTab);
            app.ShowAmountLabelPositive.Text = 'Show Amount';
            app.ColorButtonNegative = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ShowPositiveFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveFibersCheckBoxValueChanged, true);
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowPositiveFibersCheckBox.Value = true;
            app.ShowNegativeFibersCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ShowNegativeFibersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeFibersCheckBoxValueChanged, true);
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.ExportasAtlasButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Enable = 'off';
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ConsiderSignificantFibersonlyCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.ConsiderSignificantFibersonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantFibersonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantFibersonlyCheckBox.Text = 'Consider Significant Fibers only';
            app.RefreshViewButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.SingleTractAnalysisTab);
            app.AutoRefreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRefreshCheckBoxValueChanged, true);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.MultTestStrategy = uidropdown(app.SingleTractAnalysisTab);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Value = 'Uncorrected';
            app.ShowAmountLeftEditFieldNegative = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountLeftEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldNegativeValueChanged, true);
            app.ShowAmountLeftEditFieldNegative.Enable = 'off';
            app.ShowAmountRightEditFieldNegative = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountRightEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldNegativeValueChanged, true);
            app.ShowAmountRightEditFieldNegative.Enable = 'off';
            app.ShowAmountLeftEditFieldPositive = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountLeftEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountLeftEditFieldPositiveValueChanged, true);
            app.ShowAmountRightEditFieldPositive = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountRightEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountRightEditFieldPositiveValueChanged, true);
            app.ShowAmountEditFieldPositive = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositiveValueChanged, true);
            app.ShowAmountEditFieldNegative = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ShowAmountEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegativeValueChanged, true);
            app.ShowAmountEditFieldNegative.Enable = 'off';
            app.ThresholdingDropDownLabel = uilabel(app.SingleTractAnalysisTab);
            app.ThresholdingDropDownLabel.Text = 'Thresholding';
            app.ThresholdingDropDown = uidropdown(app.SingleTractAnalysisTab);
            app.ThresholdingDropDown.Items = {'Percentage Relative to Peak', 'Percentage Relative to Amount', 'Fixed Amount', 'Fixed Fiber Value'};
            app.ThresholdingDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdingDropDownValueChanged, true);
            app.ThresholdingDropDown.Value = 'Percentage Relative to Peak';
            app.ExportModelButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Text = 'Export Model';
            app.RefineFiberColor = uibutton(app.SingleTractAnalysisTab, 'push');
            app.RefineFiberColor.ButtonPushedFcn = createCallbackFcn(app, @RefineFiberColorButtonPushed, true);
            app.RefineFiberColor.Text = 'Refine Fiber Color';
            app.WeighttractsbyROIButton = uibutton(app.SingleTractAnalysisTab, 'push');
            app.WeighttractsbyROIButton.ButtonPushedFcn = createCallbackFcn(app, @WeighttractsbyROIButtonPushed, true);
            app.WeighttractsbyROIButton.Text = 'Weight tracts by ROI';
            app.ThresholdEditField_2Label = uilabel(app.SingleTractAnalysisTab);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField_2 = uieditfield(app.SingleTractAnalysisTab, 'numeric');
            app.ThresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_2ValueChanged, true);
            app.ThresholdEditField_2.Value = 200;
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Enable = 'off';
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.VariableofInterestDropDown.Enable = 'off';
            app.ConnthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.ConnthresholdSlider.Limits = [0 50];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.InteractiveModelSetupPanel);
            app.EfieldthresholdSlider.Limits = [0 500];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 200;
            app.EfieldthresholdLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts are modulated > '; 'E-Field of  V/m'};
            app.Efieldmetric = uidropdown(app.InteractiveModelSetupPanel);
            app.Efieldmetric.Items = {'Peak', 'Sum', 'Mean'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Value = 'Peak';
            app.StimulationModel = uidropdown(app.InteractiveModelSetupPanel);
            app.StimulationModel.Items = {'Electric Field', 'Sigmoid Field', 'VTA'};
            app.StimulationModel.ValueChangedFcn = createCallbackFcn(app, @StimulationModelValueChanged, true);
            app.StimulationModel.Enable = 'off';
            app.StimulationModel.Value = 'Electric Field';
            app.StatH0DropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatH0DropDown.Items = {'Average', 'Zero'};
            app.StatH0DropDown.ValueChangedFcn = createCallbackFcn(app, @StatH0DropDownValueChanged, true);
            app.StatH0DropDown.Enable = 'off';
            app.StatH0DropDown.Value = 'Average';
            app.StatTestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatTestDropDown.Items = {'Spearman', 'Pearson'};
            app.StatTestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatTestDropDownValueChanged, true);
            app.StatTestDropDown.Enable = 'off';
            app.StatTestDropDown.Value = 'Spearman';
            app.StatFamilyDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.StatFamilyDropDown.Items = {'Correlations', '1-Sample Tests', '2-Sample Tests'};
            app.StatFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @StatFamilyDropDownValueChanged, true);
            app.StatFamilyDropDown.Enable = 'off';
            app.StatFamilyDropDown.Value = 'Correlations';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.StimulationModelLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StimulationModelLabel.Enable = 'off';
            app.StimulationModelLabel.Text = 'Stimulation Model:';
            app.EfieldmetricLabel = uilabel(app.InteractiveModelSetupPanel);
            app.EfieldmetricLabel.Enable = 'off';
            app.EfieldmetricLabel.Text = 'Metric:';
            app.VTAthreshold = uieditfield(app.InteractiveModelSetupPanel, 'text');
            app.VTAthreshold.Enable = 'off';
            app.VTAthreshold.Visible = 'off';
            app.VTAthreshold.Value = '200';
            app.StatH0Label = uilabel(app.InteractiveModelSetupPanel);
            app.StatH0Label.Enable = 'off';
            app.StatH0Label.Text = 'Against';
            app.StatTestLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatTestLabel.Enable = 'off';
            app.StatTestLabel.Text = 'Statistical Test';
            app.StatFamilyLabel = uilabel(app.InteractiveModelSetupPanel);
            app.StatFamilyLabel.Enable = 'off';
            app.StatFamilyLabel.Text = 'Test Family';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_explorer20(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end