classdef ea_set_lighting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightLightButton                matlab.ui.control.StateButton
        LeftLightButton                 matlab.ui.control.StateButton
        CeilingLightButton              matlab.ui.control.StateButton
        CamLightButton                  matlab.ui.control.StateButton
        LightingParametersLabel         matlab.ui.control.Label
        AmbientStrengthSlider           matlab.ui.control.Slider
        AmbientStrengthSliderLabel      matlab.ui.control.Label
        DiffuseStrengthSlider           matlab.ui.control.Slider
        DiffuseStrengthSliderLabel      matlab.ui.control.Label
        SpecularStrengthSlider          matlab.ui.control.Slider
        SpecularStrengthSliderLabel     matlab.ui.control.Label
        SpecularExponentSlider          matlab.ui.control.Slider
        SpecularExponentSliderLabel     matlab.ui.control.Label
        SpecularColorReflectanceSlider  matlab.ui.control.Slider
        SpecularColorReflectanceSliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        atlassurfs % atlassurfaces
        elrender % ea_trajectory objects
        RightLight
        LeftLight
        CeilingLight
        CamLight
        resultfig % figure to draw on
        patches % available patches in figure
        surfs % available surfaces in figure
        iscontact % patches that are electrodes (for specular)
    end
    
    methods (Access = private)
        
        function objvisible(app,atls)
            set(atls, 'Visible', 'on');
        end
        
        function results = objinvisible(app,atls)
            set(atls, 'Visible', 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, resultfig)
            app.UIFigure.Name='Set Lighting';
            
            app.RightLight=getappdata(resultfig,'RightLight');
            app.LeftLight=getappdata(resultfig,'LeftLight');
            app.CeilingLight=getappdata(resultfig,'CeilingLight');
            app.CamLight=getappdata(resultfig,'CamLight');
            
            app.RightLightButton.Value=app.RightLight.Visible;
            app.LeftLightButton.Value=app.LeftLight.Visible;
            app.CeilingLightButton.Value=app.CeilingLight.Visible;
            app.CamLightButton.Value=app.CamLight.Visible;
            
            app.atlassurfs=getappdata(resultfig,'atlassurfs');
            app.elrender=getappdata(resultfig,'el_render');
            app.resultfig=resultfig;
            if ~isempty(app.atlassurfs)
                onesurf=app.atlassurfs{1};
                app.SpecularColorReflectanceSlider.Value=onesurf.SpecularColorReflectance;
                app.SpecularExponentSlider.Value=onesurf.SpecularExponent;
                app.SpecularStrengthSlider.Value=onesurf.SpecularStrength;
                app.DiffuseStrengthSlider.Value=onesurf.DiffuseStrength;
                app.AmbientStrengthSlider.Value=onesurf.AmbientStrength;
            end
            app.patches = findall(app.resultfig,'Type','patch');
            app.iscontact = zeros(length(app.patches),1);
            for p=1:length(app.patches)
                thistag=app.patches(p).Tag;
                if contains(thistag,'Contact')
                    app.iscontact(p)=1;
                end
            end
            app.surfs = findall(app.resultfig,'Type','surf');
        end

        % Value changed function: CamLightButton
        function CamLightButtonValueChanged(app, event)
            value = app.CamLightButton.Value;
            app.CamLight.Visible=(value);
        end

        % Value changed function: RightLightButton
        function RightLightButtonValueChanged(app, event)
            value = app.RightLightButton.Value;
            app.RightLight.Visible=(value);
        end

        % Value changed function: CeilingLightButton
        function CeilingLightButtonValueChanged(app, event)
            value = app.CeilingLightButton.Value;
            app.CeilingLight.Visible=(value);
        end

        % Value changed function: LeftLightButton
        function LeftLightButtonValueChanged(app, event)
            value = app.LeftLightButton.Value;
            app.LeftLight.Visible=(value);
        end

        % Value changing function: AmbientStrengthSlider
        function AmbientStrengthSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: DiffuseStrengthSlider
        function DiffuseStrengthSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: SpecularStrengthSlider
        function SpecularStrengthSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: SpecularExponentSlider
        function SpecularExponentSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: SpecularColorReflectanceSlider
        function SpecularColorReflectanceSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: AmbientStrengthSlider
        function AmbientStrengthSliderValueChanged(app, event)
            value = app.AmbientStrengthSlider.Value;
            if ~isempty(app.patches)
                [app.patches.AmbientStrength]=deal(value);
            end
            if ~isempty(app.surfs)
                [app.surfs.AmbientStrength]=deal(value);
            end
        end

        % Value changed function: DiffuseStrengthSlider
        function DiffuseStrengthSliderValueChanged(app, event)
            value = app.DiffuseStrengthSlider.Value;
            if ~isempty(app.patches)
                [app.patches.DiffuseStrength]=deal(value);
            end
            if ~isempty(app.surfs)
                [app.surfs.DiffuseStrength]=deal(value);
            end
        end

        % Value changed function: SpecularStrengthSlider
        function SpecularStrengthSliderValueChanged(app, event)
            value = app.SpecularStrengthSlider.Value;
            if ~isempty(app.patches)
                [app.patches.SpecularStrength]=deal(value);
                [app.patches(logical(app.iscontact)).SpecularStrength]=deal(1);
            end
            if ~isempty(app.surfs)
                [app.surfs.SpecularStrength]=deal(value);
            end
        end

        % Value changed function: SpecularExponentSlider
        function SpecularExponentSliderValueChanged(app, event)
            value = app.SpecularExponentSlider.Value;
            if ~isempty(app.patches)
                [app.patches.SpecularExponent]=deal(value);
            end
            if ~isempty(app.surfs)
                [app.surfs.SpecularExponent]=deal(value);
            end
        end

        % Value changed function: SpecularColorReflectanceSlider
        function SpecularColorReflectanceSliderValueChanged(app, event)
            value = app.SpecularColorReflectanceSlider.Value;
            if ~isempty(app.patches)
                [app.patches.SpecularColorReflectance]=deal(value);
            end
            if ~isempty(app.surfs)
                [app.surfs.SpecularColorReflectance]=deal(value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpecularColorReflectanceSliderLabel = uilabel(app.UIFigure);
            app.SpecularColorReflectanceSliderLabel.Text = 'Specular Color Reflectance';
            app.SpecularColorReflectanceSlider = uislider(app.UIFigure);
            app.SpecularColorReflectanceSlider.Limits = [0 1];
            app.SpecularColorReflectanceSlider.ValueChangedFcn = createCallbackFcn(app, @SpecularColorReflectanceSliderValueChanged, true);
            app.SpecularColorReflectanceSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularColorReflectanceSliderValueChanging, true);
            app.SpecularExponentSliderLabel = uilabel(app.UIFigure);
            app.SpecularExponentSliderLabel.Text = 'Specular Exponent';
            app.SpecularExponentSlider = uislider(app.UIFigure);
            app.SpecularExponentSlider.Limits = [1 40];
            app.SpecularExponentSlider.ValueChangedFcn = createCallbackFcn(app, @SpecularExponentSliderValueChanged, true);
            app.SpecularExponentSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularExponentSliderValueChanging, true);
            app.SpecularExponentSlider.Value = 1;
            app.SpecularStrengthSliderLabel = uilabel(app.UIFigure);
            app.SpecularStrengthSliderLabel.Text = 'Specular Strength';
            app.SpecularStrengthSlider = uislider(app.UIFigure);
            app.SpecularStrengthSlider.Limits = [0 1];
            app.SpecularStrengthSlider.ValueChangedFcn = createCallbackFcn(app, @SpecularStrengthSliderValueChanged, true);
            app.SpecularStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @SpecularStrengthSliderValueChanging, true);
            app.DiffuseStrengthSliderLabel = uilabel(app.UIFigure);
            app.DiffuseStrengthSliderLabel.Text = 'Diffuse Strength';
            app.DiffuseStrengthSlider = uislider(app.UIFigure);
            app.DiffuseStrengthSlider.Limits = [0 1];
            app.DiffuseStrengthSlider.ValueChangedFcn = createCallbackFcn(app, @DiffuseStrengthSliderValueChanged, true);
            app.DiffuseStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @DiffuseStrengthSliderValueChanging, true);
            app.AmbientStrengthSliderLabel = uilabel(app.UIFigure);
            app.AmbientStrengthSliderLabel.Text = 'Ambient Strength';
            app.AmbientStrengthSlider = uislider(app.UIFigure);
            app.AmbientStrengthSlider.Limits = [0 1];
            app.AmbientStrengthSlider.ValueChangedFcn = createCallbackFcn(app, @AmbientStrengthSliderValueChanged, true);
            app.AmbientStrengthSlider.ValueChangingFcn = createCallbackFcn(app, @AmbientStrengthSliderValueChanging, true);
            app.LightingParametersLabel = uilabel(app.UIFigure);
            app.LightingParametersLabel.Text = 'Lighting Parameters';
            app.CamLightButton = uibutton(app.UIFigure, 'state');
            app.CamLightButton.ValueChangedFcn = createCallbackFcn(app, @CamLightButtonValueChanged, true);
            app.CamLightButton.Text = {'Cam'; 'Light'};
            app.CeilingLightButton = uibutton(app.UIFigure, 'state');
            app.CeilingLightButton.ValueChangedFcn = createCallbackFcn(app, @CeilingLightButtonValueChanged, true);
            app.CeilingLightButton.Text = {'Ceiling'; 'Light'};
            app.LeftLightButton = uibutton(app.UIFigure, 'state');
            app.LeftLightButton.ValueChangedFcn = createCallbackFcn(app, @LeftLightButtonValueChanged, true);
            app.LeftLightButton.Text = {'Left'; 'Light'};
            app.RightLightButton = uibutton(app.UIFigure, 'state');
            app.RightLightButton.ValueChangedFcn = createCallbackFcn(app, @RightLightButtonValueChanged, true);
            app.RightLightButton.Text = {'Right'; 'Light'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_set_lighting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end