classdef clinical_score_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CHANGE_SCORESTab                matlab.ui.container.Tab
        POSTOPEditField_5               matlab.ui.control.EditField
        PostopColumnStopEditField_7     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_5  matlab.ui.control.Label
        PostopColumnStartEditField_7    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_6  matlab.ui.control.Label
        PostopColumnStopEditField_8     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_6  matlab.ui.control.Label
        PostopColumnStartEditField_8    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_7  matlab.ui.control.Label
        ImportfromEXCELButton_9         matlab.ui.control.Button
        ImportfromEXCELButton_10        matlab.ui.control.Button
        GENERATEButton_3                matlab.ui.control.Button
        MotorScoreTypeDropDown_2Label   matlab.ui.control.Label
        MotorScoreTypeDropDown_2        matlab.ui.control.DropDown
        UPDRSTab                        matlab.ui.container.Tab
        CreateCustomTextArea            matlab.ui.control.TextArea
        CreateCustomClinicalScoresLabel  matlab.ui.control.Label
        GENERATEButton                  matlab.ui.control.Button
        ExportasExcelButton             matlab.ui.control.Button
        PostopColumnStopEditField_2     matlab.ui.control.EditField
        PostopColumnStopEditField_2Label  matlab.ui.control.Label
        PostopColumnStartEditField_2    matlab.ui.control.EditField
        PostopColumnStartEditField_2Label  matlab.ui.control.Label
        BaselineColumnStopEditField_2   matlab.ui.control.EditField
        BaselineColumnStopEditField_2Label  matlab.ui.control.Label
        BaselineColumnStartEditField_2  matlab.ui.control.EditField
        BaselineColumnStartEditField_9Label  matlab.ui.control.Label
        PostopColumnStopEditField       matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel  matlab.ui.control.Label
        PostopColumnStartEditField      matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel  matlab.ui.control.Label
        BaselineColumnStopEditField     matlab.ui.control.EditField
        BaselineColumnStopEditFieldLabel  matlab.ui.control.Label
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        BaselineColumnStartEditField    matlab.ui.control.EditField
        BaselineColumnStartEditFieldLabel  matlab.ui.control.Label
        BASELINEEditField               matlab.ui.control.EditField
        LEADGROUPIDEditFieldLabel       matlab.ui.control.Label
        LEADGROUPIDEditField            matlab.ui.control.EditField
        POSTOPEditField                 matlab.ui.control.EditField
        CheckBox                        matlab.ui.control.CheckBox
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        LateralityButtonGroup           matlab.ui.container.ButtonGroup
        BilateralButton                 matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        ImportfromEXCELButton           matlab.ui.control.Button
        ImportfromEXCELButton_2         matlab.ui.control.Button
        ImportfromEXCELButton_3         matlab.ui.control.Button
        ImportfromEXCELButton_4         matlab.ui.control.Button
        ThresholdTremorScoresCheckBox   matlab.ui.control.CheckBox
        MotorScoreTypeDropDownLabel     matlab.ui.control.Label
        MotorScoreTypeDropDown          matlab.ui.control.DropDown
        BDITab                          matlab.ui.container.Tab
        PostopColumnStopEditField_3     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_2  matlab.ui.control.Label
        PostopColumnStartEditField_3    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_2  matlab.ui.control.Label
        BaselineColumnStopEditField_3   matlab.ui.control.EditField
        BaselineColumnStopEditFieldLabel_2  matlab.ui.control.Label
        BaselineColumnStartEditField_3  matlab.ui.control.EditField
        BaselineColumnStartEditFieldLabel_2  matlab.ui.control.Label
        BASELINEEditField_2             matlab.ui.control.EditField
        POSTOPEditField_2               matlab.ui.control.EditField
        CheckBox_4                      matlab.ui.control.CheckBox
        GENERATEButton_2                matlab.ui.control.Button
        POSTOPEditField_4               matlab.ui.control.EditField
        CheckBox_6                      matlab.ui.control.CheckBox
        PostopColumnStopEditField_5     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_3  matlab.ui.control.Label
        PostopColumnStartEditField_5    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_4  matlab.ui.control.Label
        PostopColumnStopEditField_6     matlab.ui.control.EditField
        PostopColumnStopEditFieldLabel_4  matlab.ui.control.Label
        PostopColumnStartEditField_6    matlab.ui.control.EditField
        PostopColumnStartEditFieldLabel_5  matlab.ui.control.Label
        ImportfromEXCELButton_5         matlab.ui.control.Button
        ImportfromEXCELButton_6         matlab.ui.control.Button
        ImportfromEXCELButton_7         matlab.ui.control.Button
        ImportfromEXCELButton_8         matlab.ui.control.Button
        ScorecalculationDropDownLabel   matlab.ui.control.Label
        ScorecalculationDropDown        matlab.ui.control.DropDown
    end

  properties (Access = public)
        postop_checked_box
        medon_stimoff
        medon_stimon
        leadgroupid
        fname
        sheet_name
        post_fname
        baseline
        baseline_2
        postop
        postop_2
        baseline_checked_box
        postop2_checked_box
        baseline_start_bdi
        baseline_stop_bdi
        postop_start_bdi
        postop_stop_bdi
        postop_checked_bdi
        baseline_start_medoff
        baseline_stop_medoff
        baseline_start_medon
        baseline_stop_medon
        postop_start_medoff
        postop_stop_medoff
        postop_start_medon
        postop_stop_medon
        BASET
        POSTT
        BASET_medoff
        POSTT_medoff
        M
        cdata
        patients
        flag
        global_score
        brady
        rigidity
        axial
        tremor
        postop_start
        postop_stop
        create_custom
        create_custom_bdi
        custom_factors
        custom_bl_start_bdi
        custom_bl_stop_bdi
        custom_pop_start_bdi
        custom_pop_stop_bdi
        postop1_start_bdi
        postop1_stop_bdi
        GLOBAL_ABS_VALS
        GLOBAL_IMP_VALS
        GLOBAL_CLEANED_VALS
        BRADYKINESIA_ABS_VALS
        BRADYKINESIA_IMP_VALS
        BRADYKINESIA_CLEANED_VALS
        RIGIDITY_ABS_VALS
        RIGIDITY_CLEANED_VALS
        RIGIDITY_IMP_VALS
        AXIAL_ABS_VALS
        AXIAL_CLEANED_VALS
        AXIAL_IMP_VALS
        TREMOR_ABS_VALS
        TREMOR_CLEANED_VALS
        TREMOR_IMP_VALS
        CUSTOM_ABS_VALS
        CUSTOM_CLEANED_VALS
        CUSTOM_IMP_VALS
        somatotopy
        all_selected_nodes
        postop_bdi
        custom_bdi
        baseline_bdi
        bl_start
        bl_stop
        pp_start
        pp_stop
        
    end
    
    
    methods (Access = private)
        
        function score_generator(app,to_match,postopid,postop_range)            
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                baseline_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
            else
                baseline_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            end
            baseline_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
            baseline_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            
            for i=1:length(postop_range)
                postop_tab{i,1} = readtable(app.fname,'Sheet',app.sheet_name,'Range',postop_range{i,1});
                postop_mat{i,1} = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range{i,1});
                postop_tab_names{i,1} = postop_tab{i,1}.Properties.VariableNames;
                if all(isnan(postop_mat{i,:}(1,:)))
                   postop_mat{i,:}(1,:) = [];
                end
            end
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end

            for pt=1:length(app.patients)
                odir=[app.M.M.patient.list{pt},'/clinical/'];
                disp(odir)
                if ~exist(odir,'dir')
                    mkdir(odir);
                end
                for i=1:length(postopid)
                    app.BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
                    app.POSTT =array2table(postop_mat{i,:},'VariableNames',postop_tab_names{i,:});
                    BASET_pt = app.BASET(pt,:);
                    POSTT_pt = app.POSTT(pt,:);
                    identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                    motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                    if strcmp(app.MotorScoreTypeDropDown.Value,'UPDRS')
                        motor_flag = 'Motor_UPDRS';
                    elseif strcmp(app.MotorScoreTypeDropDown.Value,'MDS')
                        motor_flag = 'Motor_MDSUPDRS';
                    else
                        if isnan(motor_identifier{1,1})
                            motor_flag = 'Motor_UPDRS';
                        else
                            motor_flag = 'Motor_MDSUPDRS';
                        end
                        
                    end
                    postop_flag = postopid{i,:};
                    scores.(motor_flag).(postop_flag).Global.(app.baseline) = BASET_pt;
                    scores.(motor_flag).(postop_flag).Global.(postop_flag) = POSTT_pt;
                    scores.(motor_flag).(postop_flag).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                    scores.(motor_flag).(postop_flag).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                    app.GLOBAL_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Global.abs_improvements;
                    app.GLOBAL_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Global.perc_improvements;
                    GLOBAL_BASELINE.(postop_flag)(pt,:) = BASET_pt{:,end};
                    if strcmp(motor_flag,'Motor_UPDRS')
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                        end
                    else
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31|ToeTappingL'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31|ToeTappingL'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31|ToeTappingR'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31|ToeTappingR'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31|ToeTapping'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31|ToeTapping'));
                        end
                    end
                    
                    brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    brady_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    brady_preop.Total = nansum(brady_preop{:,1:end},2);
                    brady_postop.Total = nansum(brady_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Bradykinesia.(app.baseline) = brady_preop;
                    scores.(motor_flag).(postop_flag).Bradykinesia.(postop_flag) = brady_postop;
                    scores.(motor_flag).(postop_flag).Bradykinesia.abs_improvements = brady_preop.Total - brady_postop.Total;
                    scores.(motor_flag).(postop_flag).Bradykinesia.perc_improvements = ((brady_preop.Total-brady_postop.Total)./brady_preop.Total);
                    app.BRADYKINESIA_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Bradykinesia.abs_improvements;
                    app.BRADYKINESIA_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Bradykinesia.perc_improvements;
                    BRADYKINESIA_BASELINE.(postop_flag)(pt,:) = brady_preop{:,end};
                    
                    
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS22'));
                    end
                    rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    rigidity_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                    rigidity_postop.Total = nansum(rigidity_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Rigidity.(app.baseline) = rigidity_preop;
                    scores.(motor_flag).(postop_flag).Rigidity.(postop_flag) = rigidity_postop;
                    scores.(motor_flag).(postop_flag).Rigidity.abs_improvements = rigidity_preop.Total - rigidity_postop.Total;
                    scores.(motor_flag).(postop_flag).Rigidity.perc_improvements = ((rigidity_preop.Total-rigidity_postop.Total)./rigidity_preop.Total);
                    app.RIGIDITY_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Rigidity.abs_improvements;
                    app.RIGIDITY_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Rigidity.perc_improvements;
                    RIGIDITY_BASELINE.(postop_flag)(pt,:) = rigidity_preop{:,end};
                    
                    
                    if strcmp(motor_flag,'Motor_MDSUPDRS')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30|MDSFOG'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30|MDSFOG'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30'));
                    end
                    axial_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    axial_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    axial_preop.Total = nansum(axial_preop{:,1:end},2);
                    axial_postop.Total = nansum(axial_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Axial.(app.baseline) = axial_preop;
                    scores.(motor_flag).(postop_flag).Axial.(postop_flag) = axial_postop;
                    scores.(motor_flag).(postop_flag).Axial.abs_improvements = axial_preop.Total - axial_postop.Total;
                    scores.(motor_flag).(postop_flag).Axial.perc_improvements = ((axial_preop.Total-axial_postop.Total)./axial_preop.Total);
                    app.AXIAL_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Axial.abs_improvements;
                    app.AXIAL_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Axial.perc_improvements;
                    AXIAL_BASELINE.(postop_flag)(pt,:) = axial_preop{:,end};
                                         
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor'));
                    end
                    tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    tremor_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                    tremor_postop.Total = nansum(tremor_postop{:,1:end},2);
                    scores.(motor_flag).(postop_flag).Tremor.(app.baseline) = tremor_preop;
                    scores.(motor_flag).(postop_flag).Tremor.(postop_flag) = tremor_postop;
                    if app.ThresholdTremorScoresCheckBox.Value
                        if tremor_preop.Total < 2
                           scores.(motor_flag).(postop_flag).Tremor.abs_improvements = NaN; 
                           scores.(motor_flag).(postop_flag).Tremor.perc_improvements = NaN;
                        else
                           scores.(motor_flag).(postop_flag).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                           scores.(motor_flag).(postop_flag).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                        end
                    else
                      scores.(motor_flag).(postop_flag).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                      scores.(motor_flag).(postop_flag).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);  
                    end
                    app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Tremor.abs_improvements;
                    app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Tremor.perc_improvements;
                    TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
                    
                    if ~strcmp(app.create_custom,'')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,to_match));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames,to_match));
                        custom_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        custom_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        custom_preop.Total = nansum(custom_preop{:,1:end},2);
                        custom_postop.Total = nansum(custom_postop{:,1:end},2);
                        scores.(motor_flag).(postop_flag).Custom.(app.baseline) = custom_preop;
                        scores.(motor_flag).(postop_flag).Custom.(postop_flag) = custom_postop;
                        scores.(motor_flag).(postop_flag).Custom.abs_improvements = custom_preop.Total - custom_postop.Total;
                        scores.(motor_flag).(postop_flag).Custom.perc_improvements = ((custom_preop.Total-custom_postop.Total)./custom_preop.Total);
                        app.CUSTOM_ABS_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Custom.abs_improvements;
                        app.CUSTOM_IMP_VALS.(postop_flag)(pt,:) = scores.(motor_flag).(postop_flag).Custom.perc_improvements;
                        CUSTOM_BASELINE.(postop_flag)(pt,:) = custom_preop{:,end};
                    end
                end
                save([odir,'clinical_scores.mat'],'scores');
                clear scores;
            end
            for i=1:length(postopid)
                postop_flag = postopid{i,:};
                app.GLOBAL_CLEANED_VALS.(postop_flag) = ea_resid(GLOBAL_BASELINE.(postop_flag),app.GLOBAL_ABS_VALS.(postop_flag));
                app.BRADYKINESIA_CLEANED_VALS.(postop_flag) = ea_resid(BRADYKINESIA_BASELINE.(postop_flag),app.BRADYKINESIA_ABS_VALS.(postop_flag));
                app.RIGIDITY_CLEANED_VALS.(postop_flag) = ea_resid(RIGIDITY_BASELINE.(postop_flag),app.RIGIDITY_ABS_VALS.(postop_flag));
                app.AXIAL_CLEANED_VALS.(postop_flag) = ea_resid(AXIAL_BASELINE.(postop_flag),app.AXIAL_ABS_VALS.(postop_flag));
                app.TREMOR_CLEANED_VALS.(postop_flag) = ea_resid(TREMOR_BASELINE.(postop_flag),app.TREMOR_ABS_VALS.(postop_flag));
                if ~strcmp(app.create_custom,'')
                    app.CUSTOM_CLEANED_VALS.(postop_flag) = ea_resid(CUSTOM_BASELINE.(postop_flag),app.CUSTOM_ABS_VALS.(postop_flag));
                end
            end
            for i = 1:length(app.patients)
                load([app.M.M.patient.list{i},'/clinical/','clinical_scores.mat']);
                odir = [app.M.M.patient.list{i},'/clinical/'];
                for j=1:length(postopid)
                    postop_flag = postopid{j,:};
                    if isfield(scores,'Motor_UPDRS')
                        motor_flag = 'Motor_UPDRS';
                    else
                        motor_flag = 'Motor_MDSUPDRS';
                    end
                    scores.(motor_flag).(postop_flag).Global.cleaned_improvements = app.GLOBAL_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Bradykinesia.cleaned_improvements = app.BRADYKINESIA_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Rigidity.cleaned_improvements = app.RIGIDITY_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Axial.cleaned_improvements = app.AXIAL_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(motor_flag).(postop_flag).Tremor.cleaned_improvements = app.TREMOR_CLEANED_VALS.(postop_flag)(i,:);
                    if ~strcmp(app.create_custom,'')
                        scores.(motor_flag).(postop_flag).Custom.cleaned_improvements = app.CUSTOM_CLEANED_VALS.(postop_flag)(i,:);                       end
                end
                save([odir,'clinical_scores.mat'],'scores');
            end
            to_disp = ['*' 'Scores saved successfully for all patients' '*'];
            disp(to_disp)
        end

        function bdi_score_generator(app,flag)
            for i=1:length(flag)
                if flag(i) == 1
                    baseline_start_1 = app.baseline_start_bdi;
                    baseline_stop_1 = app.baseline_stop_bdi;
                    postop_start_1 = app.postop_start_bdi;
                    postop_stop_1 = app.postop_stop_bdi;
                    postop_1 = app.POSTOPEditField_2.Value;
                    baseline_1 = app.BASELINEEditField_2.Value;
                    if ischar(baseline_start_1) && ischar(baseline_stop_1)
                        baseline_range_1 = [baseline_start_1 ':' baseline_stop_1];
                    else
                        baseline_range_1 = [1,baseline_start_1,length(app.patients)+1,baseline_stop_1];
                    end
                    baseline_mat_1 = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range_1);
                    baseline_tab_1 = readtable(app.fname,'Sheet',app.sheet_name,'Range',baseline_range_1);
                    baseline_tab_names_1 = baseline_tab_1.Properties.VariableNames;
                    if all(isnan(baseline_mat_1(1,:)))
                        baseline_mat_1(1,:) = [];
                    end
                    
                    if ischar(postop_start_1) && ischar(postop_stop_1)
                        postop_range_1 = [postop_start_1 ':' postop_stop_1];
                    else
                        postop_range_1 = [1,postop_start_1,length(app.patients)+1,postop_stop_1];
                    end
                    if strcmp(app.post_fname,'')
                        postop_mat_1 = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range_1);
                        postop_tab_1 = readtable(app.fname,'Sheet',app.sheet_name,'Range',postop_range_1);
                    else
                        postop_mat_1 = readmatrix(app.post_fname,'Sheet',app.sheet_name,'Range',postop_range_1);
                        postop_tab_1 = readtable(app.post_fname,'Sheet',app.sheet_name,'Range',postop_range_1);
                    end
                    postop_tab_names_1 = postop_tab_1.Properties.VariableNames;
                    if all(isnan(postop_mat_1(1,:)))
                        postop_mat_1(1,:) = [];
                    end
                    BASET_1 =array2table(baseline_mat_1,'VariableNames',baseline_tab_names_1);
                    POSTT_1 =array2table(postop_mat_1,'VariableNames',postop_tab_names_1);
                end
                if flag(i) == 2
                    baseline_start_2 =  app.custom_bl_start_bdi;
                    baseline_stop_2 = app.custom_bl_stop_bdi;
                    postop_start_2 = app.custom_pop_start_bdi;
                    postop_stop_2 = app.custom_pop_stop_bdi;
                    postop_2 = app.POSTOPEditField_4.Value;
                    baseline_2 = 'Custom_Baseline';
                    if ischar(baseline_start_2) && ischar(baseline_stop_2)
                        baseline_range_2 = [baseline_start_2 ':' baseline_stop_2];
                    else
                        baseline_range_2 = [1,baseline_start_2,length(app.patients)+1,baseline_stop_2];
                    end
                    baseline_mat_2 = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range_2);
                    baseline_tab_2 = readtable(app.fname,'Sheet',app.sheet_name,'Range',baseline_range_2);
                    baseline_tab_names_2 = baseline_tab_2.Properties.VariableNames;
                    if all(isnan(baseline_mat_2(1,:)))
                        baseline_mat_2(1,:) = [];
                    end
                    
                    if ischar(postop_start_2) && ischar(postop_stop_2)
                        postop_range_2 = [postop_start_2 ':' postop_stop_2];
                    else
                        postop_range_2 = [1,postop_start_2,length(app.patients)+1,postop_stop_2];
                    end
                    if strcmp(app.post_fname,'')
                        postop_mat_2 = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range_2);
                        postop_tab_2 = readtable(app.fname,'Sheet',app.sheet_name,'Range',postop_range_2);
                    else
                        postop_mat_2 = readmatrix(app.post_fname,'Sheet',app.sheet_name,'Range',postop_range_2);
                        postop_tab_2 = readtable(app.post_fname,'Sheet',app.sheet_name,'Range',postop_range_2);
                    end
                    postop_tab_names_2 = postop_tab_2.Properties.VariableNames;
                    if all(isnan(postop_mat_2(1,:)))
                        postop_mat_2(1,:) = [];
                    end
                    
                    BASET_2 =array2table(baseline_mat_2,'VariableNames',baseline_tab_names_2);
                    POSTT_2 =array2table(postop_mat_2,'VariableNames',postop_tab_names_2);
                end
            end   
            for pt=1:length(app.patients)
                odir=[app.M.M.patient.list{pt},'/clinical/'];
                if ~exist(odir,'dir')
                    mkdir(odir);
                end
                for i=1:length(flag)
                %write out Total sub-scores
                    if flag(i) == 1
                        %write global scores
                        %only check if the last cell is total
                        BASET_pt = BASET_1(pt,:);
                        POSTT_pt = POSTT_1(pt,:);
                        total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                        if isempty(total_available)
                            %if you have to calculate the total, add up
                            %already total names do not 
                            %consider column names which are "total". MAYBE
                            %THIS CAN BE CHANGED?
                            exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                            exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                            global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                            global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                            global_preop.Total = nansum(global_preop{:,1:end},2);
                            global_postop.Total = nansum(global_postop{:,1:end},2);
                            scores.BDI.(postop_1).Global.(baseline_1) = global_preop;
                            scores.BDI.(postop_1).Global.(postop_1) = global_postop;
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                               scores.BDI.(postop_1).Global.abs_improvements =  global_preop.Total - global_postop.Total;
                               scores.BDI.(postop_1).Global.perc_improvements = ((global_preop.Total - global_postop.Total)./global_preop.Total); 
                            else
                               scores.BDI.(postop_1).Global.abs_improvements =  global_postop.Total - global_preop.Total;
                               scores.BDI.(postop_1).Global.perc_improvements = ((global_postop.Total - global_preop.Total)./global_preop.Total); 
                            end
                            GLOBAL_BASELINE(pt,:) = global_preop.Total;
                        else
                            scores.BDI.(postop_1).Global.(baseline_1) = BASET_pt;
                            scores.BDI.(postop_1).Global.(postop_1) = POSTT_pt;
                            scores.BDI.(postop_1).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                            scores.BDI.(postop_1).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                            GLOBAL_BASELINE(pt,:) = BASET_pt{:,end};
                        end
                        GLOBAL_ABS_VALS(pt,:) = scores.BDI.(postop_1).Global.abs_improvements;
                        
                        
                        %Write out Apathy sub-scores
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Apathy.*'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Apathy.*'));
                        apathy_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        apathy_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'Apathy.*Total|Total.*Apathy'));
                        if ~any(total_available)
                            apathy_preop.Total = nansum(apathy_preop{:,1:end},2);
                            apathy_postop.Total = nansum(apathy_postop{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).Apathy.abs_improvements = apathy_preop.Total - apathy_postop.Total;
                                scores.BDI.(postop_1).Apathy.perc_improvements = ((apathy_preop.Total-apathy_postop.Total)./apathy_preop.Total);
                            else
                                scores.BDI.(postop_1).Apathy.abs_improvements = apathy_postop.Total - apathy_preop.Total;
                                scores.BDI.(postop_1).Apathy.perc_improvements = ((apathy_postop.Total-apathy_preop.Total)./apathy_preop.Total);
                            end
                            APATHY_BASELINE(pt,:) = apathy_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).Apathy.abs_improvements = apathy_preop{:,end} - apathy_postop{:,end};
                                scores.BDI.(postop_1).Apathy.perc_improvements = ((apathy_preop{:,end}-apathy_postop{:,end})./apathy_preop{:,end});
                            else
                                scores.BDI.(postop_1).Apathy.abs_improvements = apathy_postop{:,end} - apathy_preop{:,end};
                                scores.BDI.(postop_1).Apathy.perc_improvements = ((apathy_postop{:,end}-apathy_preop{:,end})./apathy_preop{:,end});
                            end
                            APATHY_BASELINE(pt,:) = apathy_preop{:,end};
                        end
                        scores.BDI.(postop_1).Apathy.(app.baseline_bdi) = apathy_preop;
                        scores.BDI.(postop_1).Apathy.(postop_1) = apathy_postop;
                        APATHY_ABS_VALS(pt,:) = scores.BDI.(postop_1).Apathy.abs_improvements;
                        
                        
                        %Write out BIS
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*BIS.*'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*BIS.*'));
                        BIS_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        BIS_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'BIS.*Total|BIS.*Total'));
                        if ~any(total_available)
                            BIS_preop.Total = nansum(BIS_preop{:,1:end},2);
                            BIS_postop.Total = nansum(BIS_postop{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).BIS.abs_improvements = BIS_preop.Total - BIS_postop.Total;
                                scores.BDI.(postop_1).BIS.perc_improvements = ((BIS_preop.Total- BIS_postop.Total)./BIS_preop.Total);
                            else
                               scores.BDI.(postop_1).BIS.abs_improvements = BIS_postop.Total - BIS_preop.Total;
                               scores.BDI.(postop_1).BIS.perc_improvements = ((BIS_postop.Total- BIS_preop.Total)./BIS_preop.Total); 
                            end
                            BIS_BASELINE(pt,:) = BIS_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).BIS.abs_improvements = BIS_preop{:,end} - BIS_postop{:,end};
                                scores.BDI.(postop_1).BIS.perc_improvements = ((BIS_preop{:,end}-BIS_postop{:,end})./BIS_preop{:,end});
                            else
                               scores.BDI.(postop_1).BIS.abs_improvements = BIS_postop{:,end} - BIS_preop{:,end};
                                scores.BDI.(postop_1).BIS.perc_improvements = ((BIS_postop{:,end}-BIS_preop{:,end})./BIS_preop{:,end}); 
                            end
                            BIS_BASELINE(pt,:) = BIS_preop{:,end};
                        end
                        scores.BDI.(postop_1).BIS.(app.baseline_bdi) = BIS_preop;
                        scores.BDI.(postop_1).BIS.(postop_1) = BIS_postop;
                        BIS_ABS_VALS(pt,:) = scores.BDI.(postop_1).BIS.abs_improvements;
                        
                        
                        %Write out EQ
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*EQ.*'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*EQ.*'));
                        EQ_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        EQ_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'EQ.*Total|EQ.*Total'));
                        if ~any(total_available)
                            EQ_preop.Total = nansum(EQ_preop{:,1:end},2);
                            EQ_postop.Total = nansum(EQ_postop{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_preop.Total - EQ_postop.Total;
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_preop.Total- EQ_postop.Total)./EQ_preop.Total);
                            else
                               scores.BDI.(postop_1).EQ.abs_improvements = EQ_postop.Total - EQ_preop.Total;
                               scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_postop.Total- EQ_preop.Total)./EQ_preop.Total); 
                            end
                            EQ_BASELINE(pt,:) = EQ_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_preop{:,end} - EQ_postop{:,end};
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_preop{:,end}-EQ_postop{:,end})./EQ_preop{:,end});
                            else
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_postop{:,end} - EQ_preop{:,end};
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_postop{:,end}-EQ_preop{:,end})./EQ_preop{:,end}); 
                            end
                            EQ_BASELINE(pt,:) = EQ_preop{:,end};
                        end
                        scores.BDI.(postop_1).EQ.(app.baseline_bdi) = EQ_preop;
                        scores.BDI.(postop_1).EQ.(postop_1) = EQ_postop;
                        EQ_ABS_VALS(pt,:) = scores.BDI.(postop_1).EQ.abs_improvements;
                        
                        
                        %Write out QUIP
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*quip.*'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*quip.*'));
                        quip_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        quip_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        scores.BDI.(postop_1).QUIP.(app.baseline_bdi) = quip_preop;
                        scores.BDI.(postop_1).QUIP.(postop_1) = quip_postop;
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'quip.*Total|quip.*Total'));
                        if ~any(total_available)
                            quip_preop.Total = nansum(quip_preop{:,1:end},2);
                            quip_postop.Total = nansum(quip_postop{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).QUIP.abs_improvements = quip_preop.Total - quip_postop.Total;
                                scores.BDI.(postop_1).QUIP.perc_improvements = ((quip_preop.Total- quip_postop.Total)./quip_preop.Total);
                            else
                                scores.BDI.(postop_1).QUIP.abs_improvements = quip_postop.Total - quip_preop.Total;
                                scores.BDI.(postop_1).QUIP.perc_improvements = ((quip_postop.Total- quip_preop.Total)./quip_preop.Total);  
                            end
                            QUIP_BASELINE(pt,:) = quip_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).QUIP.abs_improvements = quip_preop{:,end} - quip_postop{:,end};
                                scores.BDI.(postop_1).QUIP.perc_improvements = ((quip_preop{:,end}-quip_postop{:,end})./quip_preop{:,end});
                            else
                                scores.BDI.(postop_1).QUIP.abs_improvements = quip_postop{:,end} - quip_preop{:,end};
                                scores.BDI.(postop_1).QUIP.perc_improvements = ((quip_postop{:,end}-quip_preop{:,end})./quip_preop{:,end});
                            end
                            QUIP_BASELINE(pt,:) = quip_preop{:,end};
                        end
                        QUIP_ABS_VALS(pt,:) = scores.BDI.(postop_1).QUIP.abs_improvements;
                        
                        %write out BDI scores
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*EQ.*'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*EQ.*'));
                        EQ_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        EQ_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'EQ.*Total|EQ.*Total'));
                        if ~any(total_available)
                            EQ_preop.Total = nansum(EQ_preop{:,1:end},2);
                            EQ_postop.Total = nansum(EQ_postop{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_preop.Total - EQ_postop.Total;
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_preop.Total- EQ_postop.Total)./EQ_preop.Total);
                            else
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_postop.Total - EQ_preop.Total;
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_postop.Total- EQ_preop.Total)./EQ_preop.Total);
                            end
                            EQ_BASELINE(pt,:) = EQ_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_preop{:,end} - EQ_postop{:,end};
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_preop{:,end}-EQ_postop{:,end})./EQ_preop{:,end});
                            else
                                scores.BDI.(postop_1).EQ.abs_improvements = EQ_postop{:,end} - EQ_preop{:,end};
                                scores.BDI.(postop_1).EQ.perc_improvements = ((EQ_postop{:,end}-EQ_preop{:,end})./EQ_preop{:,end});
                            end
                            EQ_BASELINE(pt,:) = EQ_preop{:,end};
                        end
                        scores.BDI.(postop_1).EQ.(app.baseline_bdi) = EQ_preop;
                        scores.BDI.(postop_1).EQ.(postop_1) = EQ_postop;
                        EQ_ABS_VALS(pt,:) = scores.BDI.(postop_1).EQ.abs_improvements;
                        
                        %Write out BDI
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*BDI.*'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*BDI.*'));
                        BDI_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                        BDI_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                        scores.BDI.(postop_1).BDI.(app.baseline_bdi) = BDI_preop;
                        scores.BDI.(postop_1).BDI.(postop_1) = BDI_postop;
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'BDI.*Total|BDI.*Total'));
                        if ~any(total_available)
                            BDI_preop.Total = nansum(quip_preop{:,1:end},2);
                            BDI_postop.Total = nansum(quip_postop{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).BDI.abs_improvements = BDI_preop.Total - BDI_postop.Total;
                                scores.BDI.(postop_1).BDI.perc_improvements = ((BDI_preop.Total- BDI_postop.Total)./BDI_preop.Total);
                            else
                                scores.BDI.(postop_1).BDI.abs_improvements = BDI_postop.Total - BDI_preop.Total;
                                scores.BDI.(postop_1).BDI.perc_improvements = ((BDI_postop.Total- BDI_preop.Total)./BDI_preop.Total); 
                            end
                            BDI_BASELINE(pt,:) = BDI_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_1).BDI.abs_improvements = BDI_preop{:,end} - BDI_postop{:,end};
                                scores.BDI.(postop_1).BDI.perc_improvements = ((BDI_preop{:,end}-BDI_postop{:,end})./BDI_preop{:,end});
                            else
                                scores.BDI.(postop_1).BDI.abs_improvements = BDI_postop{:,end} - BDI_preop{:,end};
                                scores.BDI.(postop_1).BDI.perc_improvements = ((BDI_postop{:,end}-BDI_preop{:,end})./BDI_preop{:,end});
                            end
                            BDI_BASELINE(pt,:) = BDI_preop{:,end};
                        end
                        BDI_ABS_VALS(pt,:) = scores.BDI.(postop_1).BDI.abs_improvements;
                        
                        if  length(flag) == 1 && flag == 1
                            save([odir,'clinical_scores.mat'],'scores')
                        end
                        count = 1;
                    end
                    if flag(i) == 2
                        BASET_pt = BASET_2(pt,:);
                        POSTT_pt = POSTT_2(pt,:);
                        scores.BDI.(postop_2).Custom.([postop_2 '_baseline']) = BASET_pt;
                        scores.BDI.(postop_2).Custom.(postop_2) = POSTT_pt;
                        total_available = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                        if ~any(total_available)
                            custom_preop.Total = nansum(BASET_pt{:,1:end},2);
                            custom_postop.Total = nansum(POSTT_pt{:,1:end},2);
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_2).Custom.abs_improvements = custom_preop.Total - custom_postop.Total;
                                scores.BDI.(postop_2).Custom.perc_improvements = ((custom_preop.Total- custom_postop.Total)./custom_preop.Total);
                            else
                               scores.BDI.(postop_2).Custom.abs_improvements = custom_postop.Total - custom_preop.Total;
                               scores.BDI.(postop_2).Custom.perc_improvements = ((custom_postop.Total- custom_preop.Total)./custom_preop.Total); 
                            end
                            CUSTOM_BASELINE(pt,:) = Custom_preop.Total;
                        else
                            if strcmp(app.ScorecalculationDropDown.Value,app.ScorecalculationDropDown.Items{1})
                                scores.BDI.(postop_2).Custom.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                                scores.BDI.(postop_2).Custom.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                            else
                                scores.BDI.(postop_2).Custom.abs_improvements = POSTT_pt{:,end} - BASET_pt{:,end};
                                scores.BDI.(postop_2).Custom.perc_improvements = ((POSTT_pt{:,end} - BASET_pt{:,end})./BASET_pt{:,end}); 
                            end
                            CUSTOM_BASELINE(pt,:) = BASET_pt{:,end};
                        end
                        CUSTOM_ABS_VALS(pt,:) = scores.BDI.(postop_2).Custom.abs_improvements;
                        
                        if length(flag) == 1 && flag == 2
                            save([odir,'clinical_scores.mat'],'scores')
                        end
                        if exist("count","var")
                            count = count+1;
                        else
                            count = 1;
                        end
                    end
                    if length(flag) == 2 && count == 2
                        save([odir,'clinical_scores.mat'],'scores')
                    end
                end
            end
            clear count;
            for i=1:length(app.patients)
                odir = ([app.M.M.patient.list{i},'/clinical/']);
                load([app.M.M.patient.list{i},'/clinical/','clinical_scores.mat'])
                for j=1:length(flag)
                    
                    if flag(j) == 1
                        GLOBAL_CLEANED_VALS = ea_resid(GLOBAL_BASELINE,GLOBAL_ABS_VALS);
                        APATHY_CLEANED_VALS = ea_resid(APATHY_BASELINE,APATHY_ABS_VALS);
                        BIS_CLEANED_VALS = ea_resid(BIS_BASELINE,BIS_ABS_VALS);
                        EQ_CLEANED_VALS = ea_resid(EQ_BASELINE,EQ_ABS_VALS);
                        QUIP_CLEANED_VALS = ea_resid(QUIP_BASELINE,QUIP_ABS_VALS);
                        BDI_CLEANED_VALS = ea_resid(BDI_BASELINE,BDI_ABS_VALS);
                        count = 1;
                        if length(flag) == 1 && flag(i) == 1
                            scores.BDI.(postop_1).Global.cleaned_improvements = GLOBAL_CLEANED_VALS(i,:);
                            scores.BDI.(postop_1).Apathy.cleaned_improvements = APATHY_CLEANED_VALS(i,:);
                            scores.BDI.(postop_1).BIS.cleaned_improvements = BIS_CLEANED_VALS(i,:);
                            scores.BDI.(postop_1).EQ.cleaned_improvements = EQ_CLEANED_VALS(i,:);
                            scores.BDI.(postop_1).QUIP.cleaned_improvements = QUIP_CLEANED_VALS(i,:);
                            scores.BDI.(postop_1).BDI.cleaned_improvements = BDI_CLEANED_VALS(i,:);
                            save([odir,'clinical_scores.mat'],'scores');
                            
                        end
                    end
                    if flag(j) == 2
                        CUSTOM_CLEANED_VALS = ea_resid(CUSTOM_BASELINE,CUSTOM_ABS_VALS);
                        if length(flag) == 1 && flag(i) == 2
                            scores.BDI.(postop_2).Custom.cleaned_improvements = CUSTOM_CLEANED_VALS(i,:);
                            save([odir,'clinical_scores.mat'],'scores');
                        end
                        if exist("count","var")
                            count = count+1;
                        else
                            count = 1;
                        end
                    end
                    if length(flag) == 2 && count == 2
                        scores.BDI.(postop_1).Global.cleaned_improvements = GLOBAL_CLEANED_VALS(i,:);
                        scores.BDI.(postop_1).Apathy.cleaned_improvements = APATHY_CLEANED_VALS(i,:);
                        scores.BDI.(postop_1).BIS.cleaned_improvements = BIS_CLEANED_VALS(i,:);
                        scores.BDI.(postop_1).EQ.cleaned_improvements = EQ_CLEANED_VALS(i,:);
                        scores.BDI.(postop_1).QUIP.cleaned_improvements = QUIP_CLEANED_VALS(i,:);
                        scores.BDI.(postop_1).BDI.cleaned_improvements = BDI_CLEANED_VALS(i,:);
                        scores.BDI.(postop_2).Custom.cleaned_improvements = CUSTOM_CLEANED_VALS(i,:);
                        save([odir,'clinical_scores.mat'],'scores');
                    end
               end
            end
            to_disp = ['*' 'Scores saved successfully for all patients ' '*'];
            disp(to_disp);
        end
        
        function baseline_generator(app,to_match,postopid,postop_range)
            baseline_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
            baseline_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            
            app.BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
            for pt=1:length(app.patients)
                odir=[app.M.M.patient.list{pt},'/clinical/'];
                if ~exist(odir,'dir')
                    mkdir(odir);
                end
                BASET_pt = app.BASET(pt,:);
                identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                
                if isnan(motor_identifier{1,1}) % UPDRS
                    motor_flag = 'Motor_UPDRS';
                else
                    motor_flag = 'Motor_MDSUPDRS';
                end   
                scores.(motor_flag).(postopid).Global.(postopid) = BASET_pt;
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Left|UPDRS31'));
                    
                elseif strcmp(app.somatotopy.Text,'Right')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6].*Right|UPDRS31'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS[2][3-6]|UPDRS31'));
                end
                
                brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                brady_preop.Total = nansum(brady_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Bradykinesia.(postopid) = brady_preop;
                
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Left'));
                elseif strcmp(app.somatotopy.Text,'Right')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22.*Right'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS22'));
                end
                rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Rigidity.(postopid) = rigidity_preop;
                
                hasMatch = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'UPDRS2[7-9]|UPDRS30'));
                axial_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch));
                axial_preop.Total = nansum(axial_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Axial.(postopid) = axial_preop;
                
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                elseif strcmp(app.somatotopy.Text,'Right')
                    
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                end
                tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Tremor.(postopid) = tremor_preop;
                if ~strcmp(app.create_custom,'')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames,to_match));
                    custom_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    custom_preop.Total = nansum(custom_preop{:,1:end},2);
                    scores.(motor_flag).(postopid).Custom.(postopid) = custom_preop;
                end
                save([odir,'clinical_scores.mat'],'scores');
                clear score;
            end
            to_disp = ['*' 'Scores saved successfully for all patients' '*'];
            disp(to_disp);
        end


        function  change_score_generator(app)
            
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, excel_sheet_pre, excel_sheet_post, sheet_name)
            app.fname = excel_sheet_pre;
            app.sheet_name = sheet_name;
            app.post_fname = excel_sheet_post;
            app.PostopColumnStartEditField.Enable = 'off';
            app.PostopColumnStopEditField.Enable = 'off';
            app.BaselineColumnStartEditField_2.Enable = 'off';
            app.BaselineColumnStopEditField_2.Enable = 'off';
            app.PostopColumnStartEditField_2.Enable = 'off';
            app.PostopColumnStopEditField_2.Enable = 'off';
           
            
            app.ImportfromEXCELButton_2.Enable = 'off';
            app.ImportfromEXCELButton_3.Enable = 'off';
            app.ImportfromEXCELButton_4.Enable = 'off';

        end

        % Callback function
        function MedoffStimoffCheckBoxValueChanged(app, event)
            app.medoff_stimoff = app.MedoffStimoffCheckBox.Value;
            
        end

        % Value changed function: BaselineColumnStartEditField
        function BaselineColumnStartEditFieldValueChanged(app, event)
            app.baseline_start_medoff = app.BaselineColumnStartEditField.Value;
            
        end

        % Value changed function: BaselineColumnStopEditField
        function BaselineColumnStopEditFieldValueChanged(app, event)
             app.baseline_stop_medoff = app.BaselineColumnStopEditField.Value;
            
        end

        % Callback function
        function MedoffStimonCheckBoxValueChanged(app, event)
            app.medoff_stimon = app.MedoffStimonCheckBox.Value;
            if app.medoff_stimon
               app.PostopColumnStartEditField.Enable = 'on';
               app.PostopColumnStopEditField.Enable = 'on'; 
            else
               app.PostopColumnStartEditField.Enable = 'off';
               app.PostopColumnStopEditField.Enable = 'off';  
               
            end
            
        end

        % Value changed function: PostopColumnStartEditField
        function PostopColumnStartEditFieldValueChanged(app, event)
            app.postop_start_medoff = app.PostopColumnStartEditField.Value;
            
        end

        % Value changed function: PostopColumnStopEditField
        function PostopColumnStopEditFieldValueChanged(app, event)
            app.postop_stop_medoff = app.PostopColumnStopEditField.Value;
            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.baseline_checked_box = app.CheckBox_2.Value;
            if app.baseline_checked_box
                app.BaselineColumnStartEditField_2.Enable = 'on';
                app.BaselineColumnStopEditField_2.Enable = 'on';
                app.ImportfromEXCELButton_3.Enable = 'on';
            else
               app.BaselineColumnStartEditField_2.Enable = 'off';
               app.BaselineColumnStopEditField_2.Enable = 'off';
               app.ImportfromEXCELButton_3.Enable = 'off';
            end
        end

        % Value changed function: BaselineColumnStartEditField_2
        function BaselineColumnStartEditField_2ValueChanged(app, event)
            app.baseline_start_medon = app.BaselineColumnStartEditField_2.Value;
             
        end

        % Value changed function: BaselineColumnStopEditField_2
        function BaselineColumnStopEditField_2ValueChanged(app, event)
            app.baseline_stop_medon = app.BaselineColumnStopEditField_2.Value;
            
        end

        % Value changed function: CheckBox_3
        function CheckBox_3ValueChanged(app, event)
            app.postop2_checked_box = app.CheckBox_3.Value;
            if app.postop2_checked_box
               app.PostopColumnStartEditField_2.Enable = 'on';
               app.PostopColumnStopEditField_2.Enable = 'on';
               app.ImportfromEXCELButton_4.Enable = 'on';
            else
               app.PostopColumnStartEditField_2.Enable = 'off';
               app.PostopColumnStopEditField_2.Enable = 'off'; 
               app.ImportfromEXCELButton_4.Enable = 'off';
            end
        end

        % Value changed function: PostopColumnStartEditField_2
        function PostopColumnStartEditField_2ValueChanged(app, event)
            app.postop_start_medon = app.PostopColumnStartEditField_2.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_2
        function PostopColumnStopEditField_2ValueChanged(app, event)
            app.postop_stop_medon = app.PostopColumnStopEditField_2.Value;
            
        end

        % Callback function
        function SCORESButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SCORESButtonGroup.SelectedObject;
            %if strcmp(selectedButton,'GLOBAL')
            %    disp("global")
            %end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            
            app.baseline = app.BASELINEEditField.Value;
            app.postop = app.POSTOPEditField.Value;
            app.baseline_2 = app.EditField.Value;
            app.postop_2 = app.EditField_2.Value;
            app.create_custom = app.CreateCustomTextArea.Value;
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            if ~app.baseline
                disp("Please provide atleast the baseline scores...")
            end
            
            app.M = load([pwd,'/LEAD_groupanalysis.mat']); % need to clarify this
            app.patients = app.M.M.patient.list;   
            app.M.M.guid = app.leadgroupid;
                       
            if ~strcmp(app.create_custom,'')
               factors = app.create_custom;
               if length(factors) == 1
                  to_match = [factors{1}];
               elseif length(factors) == 2
                  to_match = [factors{1} '|' factors{2}];
               elseif length(factors) == 3
                  to_match = [factors{1} '|' factors{2} '|' factors{3}];
               elseif length(factors) == 4
                  to_match = [factors{1} '|' factors{2} '|' factors{3} '|' factors{4}];
               else
                 to_match = [factors{1} '|' factors{2} '|' factors{3} '|' factors{4} '|' factors{5}];
               end
            end
            if strcmp(app.somatotopy.Text,'Left')
               to_match = [to_match '.*left'];
            elseif strcmp(app.somatotopy.Text,'Right')
                to_match = [to_match '.*right'];
            end

            if app.BaselineColumnStartEditField.Enable && ~app.PostopColumnStartEditField.Enable && ~app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField_2.Enable
                postopid = app.baseline;
                if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                   postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff]; 
                else
                    postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
                end
                if ~strcmp(app.create_custom,'')
                    baseline_generator(app,'',postopid,postop_range) 
                else
                   baseline_generator(app,to_match,postopid,postop_range) 
                end
            end
            
            if  app.PostopColumnStartEditField.Enable && ~app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField_2.Enable
                tic
                postopid{1,:} = app.postop;
                if ischar(app.postop_start_medoff) && ischar(app.postop_stop_medoff)
                    postop_range{1,:} = [app.postop_start_medoff ':' app.postop_stop_medoff];
                else
                    postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
                end
                
                if strcmp(app.create_custom,'')
                   score_generator(app,'',postopid,postop_range);
                else
                   score_generator(app,to_match,postopid,postop_range);
                end
                disp("Process Done **")
                toc
            end
            
            if app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField.Enable && ~app.PostopColumnStartEditField_2.Enable
                tic
                postopid{1,:} = app.baseline_2;
                if ischar(app.baseline_start_medon) && ischar(app.baseline_stop_medon)
                    postop_range{1,:} = [app.baseline_start_medon ':' app.baseline_stop_medon];
                else
                    postop_range{1,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
                end
                disp("Generating scores")
                if strcmp(app.create_custom,'')
                   score_generator(app,'',postopid,postop_range);
                else
                   score_generator(app,to_match,postopid,postop_range);
                end
                disp("Process Done **")
                toc
            end
            if app.PostopColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField.Enable && ~app.BaselineColumnStartEditField_2.Enable
                tic
                postopid{1,:} = app.postop_2;
                if ischar(app.postop_start_medon) && ischar(app.postop_stop_medon)
                    postop_range{1,:} = [app.postop_start_medon ':' app.postop_stop_medon];
                else
                    postop_range{1,:} = [1,app.postop_start_medon,length(app.patients)+1,app.postop_stop_medon];
                end
                disp("Generating scores")
                if strcmp(app.create_custom,'')
                   score_generator(app,'',postopid,postop_range);
                else
                   score_generator(app,to_match,postopid,postop_range);
                end
                disp("Process Done **")
                toc
            end
            
            if app.PostopColumnStartEditField.Enable && app.BaselineColumnStartEditField_2.Enable && ~app.PostopColumnStartEditField_2.Enable
               tic
               postopid{1,:} = app.postop;
               postopid{2,:} = app.baseline_2;
               if ischar(app.postop_start_medoff) && ischar(app.postop_stop_medoff)
                   postop_range{1,:} = [app.postop_start_medoff ':' app.postop_stop_medoff];
               else
                   postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
               end
               if ischar(app.baseline_start_medon) && ischar(app.baseline_stop_medon)
                   postop_range{2,:} = [app.baseline_start_medon ':' app.baseline_stop_medon];
               else
                   postop_range{2,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
               end
               disp("Generating scores")
               if strcmp(app.create_custom,'')
                   score_generator(app,'',postopid,postop_range);
               else
                   score_generator(app,to_match,'',postopid,postop_range);
               end
               disp("Process Done **")
               toc
           
            end   
            
            if app.PostopColumnStartEditField.Enable && app.BaselineColumnStartEditField_2.Enable && app.PostopColumnStartEditField_2.Enable
               tic
               postopid{1,:} = app.postop;
               postopid{2,:} = app.baseline_2;
               postopid{3,:} = app.postop_2;
               if ischar(app.postop_start_medoff) && ischar(app.postop_stop_medoff)
                   postop_range{1,:} = [app.postop_start_medoff ':' app.postop_stop_medoff];
               else
                   postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
               end
               if ischar(app.baseline_start_medon) && ischar(app.postop_stop_medon)
                   postop_range{2,:} = [app.baseline_start_medon ':' app.baseline_stop_medon];
               else
                   postop_range{2,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
               end
               if ischar(app.postop_start_medon) && ischar(app.postop_stop_medon)
                   postop_range{3,:} = [app.postop_start_medon ':' app.postop_stop_medon];
               else
                   postop_range{3,:} = [1,app.postop_start_medon,length(app.patients)+1,app.postop_stop_medon];
               end
               disp("Generating scores")
               if strcmp(app.create_custom,'')
                   score_generator(app,'',postopid,postop_range);
               else
                   score_generator(app,to_match,postopid,postop_range);
               end
               disp("Process Done **")
               toc
            end 
            disp("Process Done **")
            app.M.M.postopid = postopid;
            M = app.M.M;
            save([app.M.M.root,'/LEAD_groupanalysis.mat'],'M');   
            
                
        end

        % Callback function
        function GLOBALCheckBoxValueChanged(app, event)
            app.global_score = app.GLOBALCheckBox.Value;
            
        end

        % Callback function
        function AXIALCheckBoxValueChanged(app, event)
            app.axial = app.AXIALCheckBox.Value;
            
        end

        % Callback function
        function RIGIDITYCheckBoxValueChanged(app, event)
            app.rigidity = app.RIGIDITYCheckBox.Value;
            
        end

        % Callback function
        function BRADYKINESIACheckBoxValueChanged(app, event)
            app.brady = app.BRADYKINESIACheckBox.Value;
            
        end

        % Callback function
        function TREMORCheckBoxValueChanged(app, event)
            app.tremor = app.TREMORCheckBox.Value;
            
        end

        % Callback function
        function CREATECUSTOMSUBSCOREButtonPushed(app, event)
           
        end

        % Callback function
        function CreateCustomSubscoreEditFieldValueChanged(app, event)
            %app.create_custom = app.CreateCustomSubscoreEditField.Value;
        end

        % Value changed function: CreateCustomTextArea
        function CreateCustomTextAreaValueChanged(app, event)
            app.create_custom = app.CreateCustomTextArea.Value;
            
        end

        % Callback function
        function OKButtonPushed(app, event)
           
        end

        % Button pushed function: ExportasExcelButton
        function ExportasExcelButtonPushed(app, event)
            fields = fieldnames(app.GLOBAL_ABS_VALS);
            for i=1:length(fields)
                global_abs_table = array2table(app.GLOBAL_ABS_VALS.(fields{i}),'VariableNames',{['Global Absolute Improvements' '_' fields{i}]});
                global_imp_table = array2table(app.GLOBAL_IMP_VALS.(fields{i}),'VariableNames',{['Global Percentage Improvements' '_' fields{i}]});
                global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Global Cleaned Improvements' '_' fields{i}]});
                bradykinesia_abs_table = array2table(app.BRADYKINESIA_ABS_VALS.(fields{i}),'VariableNames',{['Bradykinesia Absolute Improvements' '_' fields{i}]});
                bradykinesia_imp_table = array2table(app.BRADYKINESIA_IMP_VALS.(fields{i}),'VariableNames',{['Bradykinesia Percentage Improvements' '_' fields{i}]});
                bradykinesia_cleaned_tables = array2table(app.BRADYKINESIA_CLEANED_VALS.(fields{i}),'VariableNames',{['Bradykinesia Cleaned Improvements' '_' fields{i}]});
                rigidity_abs_table = array2table(app.RIGIDITY_ABS_VALS.(fields{i}),'VariableNames',{['Rigidity Absolute Improvements' '_' fields{i}]});
                rigidity_imp_table = array2table(app.RIGIDITY_IMP_VALS.(fields{i}),'VariableNames',{['Rigidity Percentage Improvements' '_' fields{i}]});
                rigidity_cleaned_tables = array2table(app.RIGIDITY_CLEANED_VALS.(fields{i}),'VariableNames',{['Rigidity Cleaned Improvements' '_' fields{i}]});
                axial_abs_table = array2table(app.AXIAL_ABS_VALS.(fields{i}),'VariableNames',{['Axial Absolute Improvements' '_' fields{i}]});
                axial_imp_table = array2table(app.AXIAL_IMP_VALS.(fields{i}),'VariableNames',{['Axial Percentage Improvements' '_' fields{i}]});
                axial_cleaned_tables = array2table(app.AXIAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Axial Cleaned Improvements' '_' fields{i}]});
                tremor_abs_table = array2table(app.TREMOR_ABS_VALS.(fields{i}),'VariableNames',{['Tremor Absolute Improvements' '_' fields{i}]});
                tremor_imp_table = array2table(app.TREMOR_IMP_VALS.(fields{i}),'VariableNames',{['Tremor Percentage Improvements' '_' fields{i}]});
                tremor_cleaned_tables = array2table(app.TREMOR_CLEANED_VALS.(fields{i}),'VariableNames',{['Tremor Cleaned Improvements' '_' fields{i}]});
                if ~strcmp(app.create_custom,'')
                    custom_abs_table = array2table(app.CUSTOM_ABS_VALS.(fields{i}),'VariableNames',{['Custom Absolute Improvements' '_' fields{i}]});
                    custom_imp_table = array2table(app.CUSTOM_IMP_VALS.(fields{i}),'VariableNames',{['Custom Percentage Improvements' '_' fields{i}]});
                    custom_cleaned_tables = array2table(app.CUSTOM_CLEANED_VALS.(fields{i}),'VariableNames',{['Custom Cleaned Improvements' '_' fields{i}]});
                end
            end
            patient_list = array2table(app.M.M.patient.list,'VariableNames',{'Patients'});
            full_table = [patient_list,global_abs_table,global_imp_table,global_cleaned_tables];
            if ~strcmp(app.create_custom,'')
                subscores_table = [patient_list,bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables,custom_abs_table,custom_imp_table,custom_cleaned_tables];
            else
                subscores_table = [patient_list,bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables];
            end
           
            writetable(full_table,uiputfile('cleaned_clinical_scores.xlsx'));
            writetable(subscores_table,uiputfile('cleaned_clinical_subscores.xlsx'));


        end

        % Value changed function: BASELINEEditField
        function BASELINEEditFieldValueChanged(app, event)
            app.baseline = app.BASELINEEditField.Value;
            if app.baseline
                app.BaselineColumnStartEditField.Enable = 'on';
                app.BaselineColumnStopEditField.Enable = 'on';
            else
                app.BaselineColumnStartEditField.Enable = 'off';
                app.BaselineColumnStopEditField.Enable = 'off';
            end
        end

        % Value changed function: POSTOPEditField
        function POSTOPEditFieldValueChanged(app, event)
            app.postop = app.POSTOPEditField.Value;
            
        end

        % Value changed function: LEADGROUPIDEditField
        function LEADGROUPIDEditFieldValueChanged(app, event)
            app.leadgroupid = app.LEADGROUPIDEditField.Value;
            
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.postop_checked_box = app.CheckBox.Value;
            
            if app.postop_checked_box
               app.PostopColumnStartEditField.Enable = 'on';
               app.PostopColumnStopEditField.Enable = 'on'; 
               app.ImportfromEXCELButton_2.Enable = 'on';
            else
               app.PostopColumnStartEditField.Enable = 'off';
               app.PostopColumnStopEditField.Enable = 'off';  
               app.ImportfromEXCELButton_2.Enable = 'off';
               
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.baseline_2 = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.postop_2 = app.EditField_2.Value;
            
        end

        % Value changed function: BASELINEEditField_2
        function BASELINEEditField_2ValueChanged(app, event)
            app.baseline_bdi = app.BASELINEEditField_2.Value;
            
        end

        % Value changed function: BaselineColumnStartEditField_3
        function BaselineColumnStartEditField_3ValueChanged(app, event)
            app.baseline_start_bdi = app.BaselineColumnStartEditField_3.Value;
            
        end

        % Value changed function: BaselineColumnStopEditField_3
        function BaselineColumnStopEditField_3ValueChanged(app, event)
            app.baseline_stop_bdi = app.BaselineColumnStopEditField_3.Value;
            
        end

        % Value changed function: CheckBox_4
        function CheckBox_4ValueChanged(app, event)
            app.postop_checked_bdi = app.CheckBox_4.Value;
            
        end

        % Callback function
        function CreateCustomTextArea_2ValueChanged(app, event)
            app.create_custom_bdi = app.CreateCustomTextArea_2.Value;
            
        end

        % Callback function
        function SomatotopyDropDownValueChanged(app, event)
            %app.somatotopy = app.SomatotopyDropDown.Value;
            
        end

        % Selection changed function: LateralityButtonGroup
        function LateralityButtonGroupSelectionChanged(app, event)
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            
        end

        % Button pushed function: ImportfromEXCELButton
        function ImportfromEXCELButtonPushed(app, event)
            
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            lbl = uilabel(fig);
            lbl.Text = 'Please specify the Range of Baseline scores by using shift and drag. Press OK when done.';
            lbl.Position = [50 50 700 700];
            cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
      
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.baseline_start_medoff = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.baseline_stop_medoff = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
            
            
        end

        % Button pushed function: ImportfromEXCELButton_2
        function ImportfromEXCELButton_2Pushed(app, event)
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            lbl = uilabel(fig);
            lbl.Text = 'Please specify the Range of Postop scores by using shift and drag. Press OK when done.';
            lbl.Position = [50 50 700 700];
            cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.postop_start_medoff = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.postop_stop_medoff = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
             
        end

        % Button pushed function: ImportfromEXCELButton_3
        function ImportfromEXCELButton_3Pushed(app, event)
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            lbl = uilabel(fig);
            lbl.Text = 'Please specify the Range of Postop scores by using shift and drag. Press OK when done.';
            lbl.Position = [50 50 700 700];
            column_names = [tdata.Properties.VariableNames];
            cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.baseline_start_medon = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.baseline_stop_medon = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
             
        end

        % Button pushed function: ImportfromEXCELButton_4
        function ImportfromEXCELButton_4Pushed(app, event)
            fig = uifigure;
            lbl = uilabel(fig);
            lbl.Text = 'Please specify the Range of Postop scores by using shift and drag. Press OK when done.';
            lbl.Position = [50 50 700 700];
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            
            cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.postop_start_medon = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.postop_stop_medoff = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
             
        end

        % Value changed function: PostopColumnStartEditField_3
        function PostopColumnStartEditField_3ValueChanged(app, event)
            app.postop1_start_bdi = app.PostopColumnStartEditField_3.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_3
        function PostopColumnStopEditField_3ValueChanged(app, event)
            app.postop1_stop_bdi = app.PostopColumnStopEditField_3.Value;
            
        end

        % Value changed function: PostopColumnStartEditField_5
        function PostopColumnStartEditField_5ValueChanged(app, event)
            app.custom_bl_start_bdi = app.PostopColumnStartEditField_5.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_5
        function PostopColumnStopEditField_5ValueChanged(app, event)
            app.custom_bl_stop_bdi = app.PostopColumnStopEditField_5.Value;
            
        end

        % Value changed function: PostopColumnStartEditField_6
        function PostopColumnStartEditField_6ValueChanged(app, event)
            app.custom_pop_start_bdi = app.PostopColumnStartEditField_6.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_6
        function PostopColumnStopEditField_6ValueChanged(app, event)
            app.custom_pop_stop_bdi = app.PostopColumnStopEditField_6.Value;
            
        end

        % Button pushed function: GENERATEButton_2
        function GENERATEButton_2Pushed(app, event)

           app.baseline_bdi = app.BASELINEEditField_2.Value;
           app.postop_bdi = app.POSTOPEditField_2.Value;
           app.custom_bdi = app.POSTOPEditField_4.Value;
           
           if strcmp(app.baseline_start_bdi,'')
               disp("Please provide atleast the baseline scores...")
           end
            
           app.M = load([pwd,'/LEAD_groupanalysis.mat']); % need to clarify this
           app.patients = app.M.M.patient.list;   
           app.M.M.guid = app.leadgroupid;

           if app.CheckBox_4.Value && ~app.CheckBox_6.Value
               tic
               flag = [1];
               disp("Generating Scores")
               bdi_score_generator(app,flag)
               postopid{1,:} = app.POSTOPEditField_2.Value;
               toc;
           end
           if app.CheckBox_6.Value && ~app.CheckBox_4.Value
               tic
               flag = [2];
               disp("Generating Scores")
               bdi_score_generator(app,flag)
               postopid{1,:} = app.POSTOPEditField_2.Value;
               toc
           end
           if app.CheckBox_4.Value && app.CheckBox_6.Value
               tic
               flag = [1,2];
               disp("Generating Scores")
               bdi_score_generator(app,flag)
               postopid{1,:} = app.POSTOPEditField_2.Value;
               postopid{2,:} = app.POSTOPEditField_4.Value;
               toc
           end
           app.M.M.postopid = postopid;
           M = app.M.M;
           save([app.M.M.root,'/LEAD_groupanalysis.mat'],'M'); 
        end

        % Value changed function: POSTOPEditField_2
        function POSTOPEditField_2ValueChanged(app, event)
            app.postop_bdi = app.POSTOPEditField_2.Value;
            
        end

        % Value changed function: POSTOPEditField_4
        function POSTOPEditField_4ValueChanged(app, event)
            app.custom_bdi = app.POSTOPEditField_4.Value;
            
        end

        % Value changed function: CheckBox_6
        function CheckBox_6ValueChanged(app, event)
            value = app.CheckBox_6.Value;
            
        end

        % Button pushed function: ImportfromEXCELButton_5
        function ImportfromEXCELButton_5Pushed(app, event)
            fig = uifigure;
            tdata = readtable(app.fname,'Sheet',app.sheet_name);
            column_names = [tdata.Properties.VariableNames];
            lbl = uilabel(fig);
            lbl.Text = 'Please specify the Range of Baseline scores by using shift and drag. Press OK when done.';
            lbl.Position = [50 50 700 700];
            cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
            okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
            cbt.SelectionChangedFcn = @nodechange;
            okb.ButtonPushedFcn = @OKButtonPushed;
            for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
            end
      
            function nodechange(src,event)
                node = event.SelectedNodes;
                app.all_selected_nodes = src.SelectedNodes;
                return
            end
            function OKButtonPushed(src,event) 
                app.baseline_start_bdi = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
                app.baseline_stop_bdi = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
                close(fig)
            end
        end

        % Button pushed function: ImportfromEXCELButton_6
        function ImportfromEXCELButton_6Pushed(app, event)
           fig = uifigure;
           
           if strcmp(app.post_fname,'')
              tdata = readtable(app.fname,'Sheet',app.sheet_name);
           else
              tdata = readtable(app.post_fname,'Sheet',app.sheet_name);
           end
           column_names = [tdata.Properties.VariableNames];
           lbl = uilabel(fig);
           lbl.Text = 'Please specify the Range of Postop scores by using shift and drag. Press OK when done.';
           lbl.Position = [50 50 700 700];
           cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
           okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
           cbt.SelectionChangedFcn = @nodechange;
           okb.ButtonPushedFcn = @OKButtonPushed;
           for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
           end
      
           function nodechange(src,event)
               node = event.SelectedNodes;
               app.all_selected_nodes = src.SelectedNodes;
               return
           end
           function OKButtonPushed(src,event) 
               app.postop_start_bdi = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
               app.postop_stop_bdi = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
               close(fig)
           end 
        end

        % Button pushed function: ImportfromEXCELButton_7
        function ImportfromEXCELButton_7Pushed(app, event)
           fig = uifigure;
           tdata = readtable(app.fname,'Sheet',app.sheet_name);
           column_names = [tdata.Properties.VariableNames];
           lbl = uilabel(fig);
           lbl.Text = 'Please specify the Range of Custom Baseline scores by using shift and drag. Press OK when done.';
           lbl.Position = [50 50 700 700];
           cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
           okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
           cbt.SelectionChangedFcn = @nodechange;
           okb.ButtonPushedFcn = @OKButtonPushed;
           for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
           end
      
           function nodechange(src,event)
               node = event.SelectedNodes;
               app.all_selected_nodes = src.SelectedNodes;
               return
           end
           function OKButtonPushed(src,event) 
               app.custom_bl_start_bdi = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
               app.custom_bl_stop_bdi = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
               close(fig)
           end 
        end

        % Button pushed function: ImportfromEXCELButton_8
        function ImportfromEXCELButton_8Pushed(app, event)
           fig = uifigure;
           if strcmp(app.post_fname,'')
              tdata = readtable(app.fname,'Sheet',app.sheet_name);
           else
              tdata = readtable(app.post_fname,'Sheet',app.sheet_name); 
           end
           column_names = [tdata.Properties.VariableNames];
           lbl = uilabel(fig);
           lbl.Text = 'Please specify the Range of Custom Postop scores by using shift and drag. Press OK when done.';
           lbl.Position = [50 50 700 700];
           cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
           okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
           cbt.SelectionChangedFcn = @nodechange;
           okb.ButtonPushedFcn = @OKButtonPushed;
           for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
           end
      
           function nodechange(src,event)
               node = event.SelectedNodes;
               app.all_selected_nodes = src.SelectedNodes;
               return
           end
           function OKButtonPushed(src,event) 
               app.custom_pop_start_bdi = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
               app.custom_pop_stop_bdi = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
               close(fig)
           end  
        end

        % Callback function
        function ExportasExcelButton_2Pushed(app, event)
            global_abs_table = array2table(app.GLOBAL_ABS_VALS,'VariableNames',{'GLOBAL ABSOLUTE VALUES'});
            global_imp_table = array2table(app.GLOBAL_IMP_VALS,'VariableNames',{'GLOBAL IMPROVEMENT VALUES'});
            global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS,'VariableNames',{'GLOBAL CLEANED VALUES'});            
            full_table = [app.BASET,app.POSTT,global_abs_table,global_imp_table,global_cleaned_tables];
            writetable(full_table,uiputfile('cleaned_clinical_scores.xlsx'));
        end

        % Value changed function: ScorecalculationDropDown
        function ScorecalculationDropDownValueChanged(app, event)
            value = app.ScorecalculationDropDown.Value;
            
        end

        % Value changed function: ThresholdTremorScoresCheckBox
        function ThresholdTremorScoresCheckBoxValueChanged(app, event)
            value = app.ThresholdTremorScoresCheckBox.Value;
            
        end

        % Value changed function: MotorScoreTypeDropDown
        function MotorScoreTypeDropDownValueChanged(app, event)
            value = app.MotorScoreTypeDropDown.Value;
            
        end

        % Button pushed function: GENERATEButton_3
        function GENERATEButton_3Pushed(app, event)
           
           app.M = load([pwd,'/LEAD_groupanalysis.mat']); % need to clarify this
           app.patients = app.M.M.patient.list;   
           app.M.M.guid = app.leadgroupid;
           tic
           postop_flag = app.POSTOPEditField_5.Value;
           if strcmp(app.MotorScoreTypeDropDown_2.Value,'UPDRS')
               motor_flag = 'UPDRS';
           elseif strcmp(app.MotorScoreTypeDropDown_2.Value,'MDS')
               motor_flag = 'MDS_UPDRS';
           else
               motor_flag = 'BDI';
           end
           disp("Generating Scores")

           if ischar(app.bl_start) && ischar(app.bl_stop)
                baseline_range = [app.bl_start ':' app.bl_stop];
            else
                baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
           end 
           if ischar(app.pp_start) && ischar(app.pp_stop)
                postop_range = [app.pp_start ':' app.pp_stop];
            else
                postop_range = [1,app.pp_start,length(app.patients)+1,app.pp_stop];
           end
           baseline_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
           baseline_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',baseline_range);
           baseline_tab_names = baseline_tab.Properties.VariableNames;
           if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
           end
           BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
           
           postop_mat = readmatrix(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
           postop_tab = readtable(app.fname,'Sheet',app.sheet_name,'Range',postop_range);
           postop_tab_names = postop_tab.Properties.VariableNames;
           if all(isnan(postop_mat(1,:)))
                postop_mat(1,:) = [];
           end
           POSTT =array2table(postop_mat,'VariableNames',postop_tab_names);
           for pt = 1:length(app.patients)
               odir = ([app.M.M.patient.list{pt},'/clinical/']);
               
               BASET_pt = BASET(pt,:);
               POSTT_pt = POSTT(pt,:);
               scores.(motor_flag).(postop_flag).UPDRS.([postop_flag '_baseline']) = BASET_pt;
               scores.(motor_flag).(postop_flag).UPDRS.(postop_flag) = POSTT_pt;
               scores.(motor_flag).(postop_flag).UPDRS.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
               scores.(motor_flag).(postop_flag).UPDRS.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
               UPDRS_BASELINE(pt,:) = BASET_pt{:,end};
               UPDRS_ABS_VALS(pt,:) = scores.(motor_flag).(postop_flag).UPDRS.abs_improvements;
               save([odir,'clinical_scores.mat'],'scores');
           end
           UPDRS_cleaned_imprv = ea_resid(UPDRS_BASELINE,UPDRS_ABS_VALS);
           for pt = 1:length(app.patients)
               odir = ([app.M.M.patient.list{pt},'/clinical/']);
               load([app.M.M.patient.list{pt},'/clinical/','clinical_scores.mat'])
               scores.(motor_flag).(postop_flag).UPDRS.cleaned_improvements = UPDRS_cleaned_imprv(pt,:);
               save([odir,'clinical_scores.mat'],'scores')
           end
           postopid{1,:} = app.POSTOPEditField_5.Value;
           disp("Scores successfully saved")
           toc
           app.M.postopid = postopid;
           
        end

        % Value changed function: POSTOPEditField_5
        function POSTOPEditField_5ValueChanged(app, event)
            value = app.POSTOPEditField_5.Value;
            
        end

        % Value changed function: PostopColumnStartEditField_7
        function PostopColumnStartEditField_7ValueChanged(app, event)
            app.bl_start = app.PostopColumnStartEditField_7.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_7
        function PostopColumnStopEditField_7ValueChanged(app, event)
            app.bl_stop = app.PostopColumnStopEditField_7.Value;
            
        end

        % Value changed function: PostopColumnStartEditField_8
        function PostopColumnStartEditField_8ValueChanged(app, event)
            app.pp_start = app.PostopColumnStartEditField_8.Value;
            
        end

        % Value changed function: PostopColumnStopEditField_8
        function PostopColumnStopEditField_8ValueChanged(app, event)
            app.pp_stop = app.PostopColumnStopEditField_8.Value;
            
        end

        % Value changed function: MotorScoreTypeDropDown_2
        function MotorScoreTypeDropDown_2ValueChanged(app, event)
            value = app.MotorScoreTypeDropDown_2.Value;
            
        end

        % Button pushed function: ImportfromEXCELButton_9
        function ImportfromEXCELButton_9Pushed(app, event)
           fig = uifigure;
           if strcmp(app.post_fname,'')
              tdata = readtable(app.fname,'Sheet',app.sheet_name);
           else
              tdata = readtable(app.post_fname,'Sheet',app.sheet_name); 
           end
           column_names = [tdata.Properties.VariableNames];
           lbl = uilabel(fig);
           lbl.Text = 'Please specify the Range of Custom Baseline scores by using shift and drag. Press OK when done.';
           lbl.Position = [50 50 700 700];
           cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
           okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
           cbt.SelectionChangedFcn = @nodechange;
           okb.ButtonPushedFcn = @OKButtonPushed;
           for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
           end
      
           function nodechange(src,event)
               node = event.SelectedNodes;
               app.all_selected_nodes = src.SelectedNodes;
               
               return
           end
           function OKButtonPushed(src,event) 
               app.bl_start = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
               app.bl_stop = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
               close(fig)
           end  
        end

        % Button pushed function: ImportfromEXCELButton_10
        function ImportfromEXCELButton_10Pushed(app, event)
            fig = uifigure;
           if strcmp(app.post_fname,'')
              tdata = readtable(app.fname,'Sheet',app.sheet_name);
           else
              tdata = readtable(app.post_fname,'Sheet',app.sheet_name); 
           end
           column_names = [tdata.Properties.VariableNames];
           lbl = uilabel(fig);
           lbl.Text = 'Please specify the Range of Custom Postop scores by using shift and drag. Press OK when done.';
           lbl.Position = [50 50 700 700];
           cbt = uitree(fig,"Multiselect",'on','Position',[150 90 300 300]);
           okb = uibutton(fig,'push','Text','OK','Position',[475 50 50 20]);
           cbt.SelectionChangedFcn = @nodechange;
           okb.ButtonPushedFcn = @OKButtonPushed;
           for i=1:length(column_names)   
              category_name = ['p', num2str(i)];
              category_name = uitreenode(cbt,'Text',column_names{i});
           end
      
           function nodechange(src,event)
               node = event.SelectedNodes;
               app.all_selected_nodes = src.SelectedNodes;
               return
           end
           function OKButtonPushed(src,event) 
               app.pp_start = find(strcmpi(column_names,app.all_selected_nodes(1).Text));
               app.pp_stop = find(strcmpi(column_names,app.all_selected_nodes(end).Text));
               close(fig)
           end  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CHANGE_SCORESTab = uitab(app.TabGroup);
            app.CHANGE_SCORESTab.Title = 'CHANGE_SCORES';
            app.POSTOPEditField_5 = uieditfield(app.CHANGE_SCORESTab, 'text');
            app.POSTOPEditField_5.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditField_5ValueChanged, true);
            app.POSTOPEditField_5.Value = 'Custom';
            app.PostopColumnStopEditField_7 = uieditfield(app.CHANGE_SCORESTab, 'text');
            app.PostopColumnStopEditField_7.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_7ValueChanged, true);
            app.PostopColumnStopEditFieldLabel_5 = uilabel(app.CHANGE_SCORESTab);
            app.PostopColumnStopEditFieldLabel_5.Text = 'Baseline Column Stop';
            app.PostopColumnStartEditField_7 = uieditfield(app.CHANGE_SCORESTab, 'text');
            app.PostopColumnStartEditField_7.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_7ValueChanged, true);
            app.PostopColumnStartEditFieldLabel_6 = uilabel(app.CHANGE_SCORESTab);
            app.PostopColumnStartEditFieldLabel_6.Text = 'Baseline Column Start';
            app.PostopColumnStopEditField_8 = uieditfield(app.CHANGE_SCORESTab, 'text');
            app.PostopColumnStopEditField_8.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_8ValueChanged, true);
            app.PostopColumnStopEditFieldLabel_6 = uilabel(app.CHANGE_SCORESTab);
            app.PostopColumnStopEditFieldLabel_6.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_8 = uieditfield(app.CHANGE_SCORESTab, 'text');
            app.PostopColumnStartEditField_8.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_8ValueChanged, true);
            app.PostopColumnStartEditFieldLabel_7 = uilabel(app.CHANGE_SCORESTab);
            app.PostopColumnStartEditFieldLabel_7.Text = 'Postop Column Start';
            app.ImportfromEXCELButton_9 = uibutton(app.CHANGE_SCORESTab, 'push');
            app.ImportfromEXCELButton_9.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_9Pushed, true);
            app.ImportfromEXCELButton_9.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_9.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_10 = uibutton(app.CHANGE_SCORESTab, 'push');
            app.ImportfromEXCELButton_10.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_10Pushed, true);
            app.ImportfromEXCELButton_10.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_10.Text = 'Import from EXCEL';
            app.GENERATEButton_3 = uibutton(app.CHANGE_SCORESTab, 'push');
            app.GENERATEButton_3.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButton_3Pushed, true);
            app.GENERATEButton_3.Text = 'GENERATE';
            app.MotorScoreTypeDropDown_2Label = uilabel(app.CHANGE_SCORESTab);
            app.MotorScoreTypeDropDown_2Label.Text = 'Motor Score Type';
            app.MotorScoreTypeDropDown_2 = uidropdown(app.CHANGE_SCORESTab);
            app.MotorScoreTypeDropDown_2.Items = {'UPDRS', 'MDS', 'BDI'};
            app.MotorScoreTypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @MotorScoreTypeDropDown_2ValueChanged, true);
            app.MotorScoreTypeDropDown_2.Value = 'UPDRS';
            app.UPDRSTab = uitab(app.TabGroup);
            app.UPDRSTab.Title = 'UPDRS';
            app.CreateCustomTextArea = uitextarea(app.UPDRSTab);
            app.CreateCustomTextArea.ValueChangedFcn = createCallbackFcn(app, @CreateCustomTextAreaValueChanged, true);
            app.CreateCustomClinicalScoresLabel = uilabel(app.UPDRSTab);
            app.CreateCustomClinicalScoresLabel.Text = 'Create Custom Clinical Scores';
            app.GENERATEButton = uibutton(app.UPDRSTab, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.ExportasExcelButton = uibutton(app.UPDRSTab, 'push');
            app.ExportasExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasExcelButtonPushed, true);
            app.ExportasExcelButton.Text = 'Export as Excel';
            app.PostopColumnStopEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStopEditField_2.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_2ValueChanged, true);
            app.PostopColumnStopEditField_2Label = uilabel(app.UPDRSTab);
            app.PostopColumnStopEditField_2Label.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_2ValueChanged, true);
            app.PostopColumnStartEditField_2Label = uilabel(app.UPDRSTab);
            app.PostopColumnStartEditField_2Label.Text = 'Postop Column Start';
            app.BaselineColumnStopEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStopEditField_2.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStopEditField_2ValueChanged, true);
            app.BaselineColumnStopEditField_2Label = uilabel(app.UPDRSTab);
            app.BaselineColumnStopEditField_2Label.Text = 'Postop Column Stop';
            app.BaselineColumnStartEditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStartEditField_2.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStartEditField_2ValueChanged, true);
            app.BaselineColumnStartEditField_9Label = uilabel(app.UPDRSTab);
            app.BaselineColumnStartEditField_9Label.Text = 'Postop Column Start';
            app.PostopColumnStopEditField = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStopEditField.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditFieldValueChanged, true);
            app.PostopColumnStopEditFieldLabel = uilabel(app.UPDRSTab);
            app.PostopColumnStopEditFieldLabel.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField = uieditfield(app.UPDRSTab, 'text');
            app.PostopColumnStartEditField.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditFieldValueChanged, true);
            app.PostopColumnStartEditFieldLabel = uilabel(app.UPDRSTab);
            app.PostopColumnStartEditFieldLabel.Text = 'Postop Column Start';
            app.BaselineColumnStopEditField = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStopEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStopEditFieldValueChanged, true);
            app.BaselineColumnStopEditFieldLabel = uilabel(app.UPDRSTab);
            app.BaselineColumnStopEditFieldLabel.Text = 'Baseline Column Stop';
            app.CheckBox_3 = uicheckbox(app.UPDRSTab);
            app.CheckBox_3.ValueChangedFcn = createCallbackFcn(app, @CheckBox_3ValueChanged, true);
            app.CheckBox_3.Text = '';
            app.CheckBox_2 = uicheckbox(app.UPDRSTab);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.BaselineColumnStartEditField = uieditfield(app.UPDRSTab, 'text');
            app.BaselineColumnStartEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStartEditFieldValueChanged, true);
            app.BaselineColumnStartEditFieldLabel = uilabel(app.UPDRSTab);
            app.BaselineColumnStartEditFieldLabel.Text = 'Baseline Column Start';
            app.BASELINEEditField = uieditfield(app.UPDRSTab, 'text');
            app.BASELINEEditField.ValueChangedFcn = createCallbackFcn(app, @BASELINEEditFieldValueChanged, true);
            app.BASELINEEditField.Tooltip = {'Please specify an alphanumeric name WITHOUT any spaces or special characters'; ''};
            app.BASELINEEditField.Value = 'Baseline';
            app.LEADGROUPIDEditFieldLabel = uilabel(app.UPDRSTab);
            app.LEADGROUPIDEditFieldLabel.Text = 'LEAD-GROUP ID';
            app.LEADGROUPIDEditField = uieditfield(app.UPDRSTab, 'text');
            app.LEADGROUPIDEditField.ValueChangedFcn = createCallbackFcn(app, @LEADGROUPIDEditFieldValueChanged, true);
            app.LEADGROUPIDEditField.Value = 'analysis_one';
            app.POSTOPEditField = uieditfield(app.UPDRSTab, 'text');
            app.POSTOPEditField.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditFieldValueChanged, true);
            app.POSTOPEditField.Tooltip = {'Please specify an alpha numeric name WITHOUT any spaces or special characters.'; ''};
            app.POSTOPEditField.Value = 'Postop';
            app.CheckBox = uicheckbox(app.UPDRSTab);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.EditField = uieditfield(app.UPDRSTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {'Please specify an alpha numeric name WITHOUT any spaces or special characters.'; ''};
            app.EditField.Value = 'postop2';
            app.EditField_2 = uieditfield(app.UPDRSTab, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tooltip = {'Please specify an alpha numeric name WITHOUT any spaces or special characters.'; ''};
            app.EditField_2.Value = 'Postop3';
            app.LateralityButtonGroup = uibuttongroup(app.UPDRSTab);
            app.LateralityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LateralityButtonGroupSelectionChanged, true);
            app.LateralityButtonGroup.Title = 'Laterality';
            app.BilateralButton = uiradiobutton(app.LateralityButtonGroup);
            app.BilateralButton.Text = 'Bilateral';
            app.BilateralButton.Value = true;
            app.RightButton = uiradiobutton(app.LateralityButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftButton = uiradiobutton(app.LateralityButtonGroup);
            app.LeftButton.Text = 'Left';
            app.ImportfromEXCELButton = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButtonPushed, true);
            app.ImportfromEXCELButton.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_2 = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_2Pushed, true);
            app.ImportfromEXCELButton_2.Tooltip = {'If you would like to choose postops directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_2.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_3 = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_3Pushed, true);
            app.ImportfromEXCELButton_3.Tooltip = {'If you would like to choose postop directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_3.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_4 = uibutton(app.UPDRSTab, 'push');
            app.ImportfromEXCELButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_4Pushed, true);
            app.ImportfromEXCELButton_4.Tooltip = {'If you would like to choose postop directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_4.Text = 'Import from EXCEL';
            app.ThresholdTremorScoresCheckBox = uicheckbox(app.UPDRSTab);
            app.ThresholdTremorScoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThresholdTremorScoresCheckBoxValueChanged, true);
            app.ThresholdTremorScoresCheckBox.Text = 'Threshold Tremor Scores';
            app.MotorScoreTypeDropDownLabel = uilabel(app.UPDRSTab);
            app.MotorScoreTypeDropDownLabel.Text = 'Motor Score Type';
            app.MotorScoreTypeDropDown = uidropdown(app.UPDRSTab);
            app.MotorScoreTypeDropDown.Items = {'UPDRS', 'MDS', 'Mixed'};
            app.MotorScoreTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorScoreTypeDropDownValueChanged, true);
            app.MotorScoreTypeDropDown.Value = 'MDS';
            app.BDITab = uitab(app.TabGroup);
            app.BDITab.Title = 'BDI';
            app.PostopColumnStopEditField_3 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStopEditField_3.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_3ValueChanged, true);
            app.PostopColumnStopEditFieldLabel_2 = uilabel(app.BDITab);
            app.PostopColumnStopEditFieldLabel_2.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_3 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStartEditField_3.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_3ValueChanged, true);
            app.PostopColumnStartEditFieldLabel_2 = uilabel(app.BDITab);
            app.PostopColumnStartEditFieldLabel_2.Text = 'Postop Column Start';
            app.BaselineColumnStopEditField_3 = uieditfield(app.BDITab, 'text');
            app.BaselineColumnStopEditField_3.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStopEditField_3ValueChanged, true);
            app.BaselineColumnStopEditFieldLabel_2 = uilabel(app.BDITab);
            app.BaselineColumnStopEditFieldLabel_2.Text = 'Baseline Column Stop';
            app.BaselineColumnStartEditField_3 = uieditfield(app.BDITab, 'text');
            app.BaselineColumnStartEditField_3.ValueChangedFcn = createCallbackFcn(app, @BaselineColumnStartEditField_3ValueChanged, true);
            app.BaselineColumnStartEditFieldLabel_2 = uilabel(app.BDITab);
            app.BaselineColumnStartEditFieldLabel_2.Text = 'Baseline Column Start';
            app.BASELINEEditField_2 = uieditfield(app.BDITab, 'text');
            app.BASELINEEditField_2.ValueChangedFcn = createCallbackFcn(app, @BASELINEEditField_2ValueChanged, true);
            app.BASELINEEditField_2.Value = 'Baseline';
            app.POSTOPEditField_2 = uieditfield(app.BDITab, 'text');
            app.POSTOPEditField_2.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditField_2ValueChanged, true);
            app.POSTOPEditField_2.Value = 'Postop';
            app.CheckBox_4 = uicheckbox(app.BDITab);
            app.CheckBox_4.ValueChangedFcn = createCallbackFcn(app, @CheckBox_4ValueChanged, true);
            app.CheckBox_4.Text = '';
            app.GENERATEButton_2 = uibutton(app.BDITab, 'push');
            app.GENERATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButton_2Pushed, true);
            app.GENERATEButton_2.Text = 'GENERATE';
            app.POSTOPEditField_4 = uieditfield(app.BDITab, 'text');
            app.POSTOPEditField_4.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditField_4ValueChanged, true);
            app.POSTOPEditField_4.Value = 'Custom';
            app.CheckBox_6 = uicheckbox(app.BDITab);
            app.CheckBox_6.ValueChangedFcn = createCallbackFcn(app, @CheckBox_6ValueChanged, true);
            app.CheckBox_6.Text = '';
            app.PostopColumnStopEditField_5 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStopEditField_5.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_5ValueChanged, true);
            app.PostopColumnStopEditFieldLabel_3 = uilabel(app.BDITab);
            app.PostopColumnStopEditFieldLabel_3.Text = 'Baseline Column Stop';
            app.PostopColumnStartEditField_5 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStartEditField_5.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_5ValueChanged, true);
            app.PostopColumnStartEditFieldLabel_4 = uilabel(app.BDITab);
            app.PostopColumnStartEditFieldLabel_4.Text = 'Baseline Column Start';
            app.PostopColumnStopEditField_6 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStopEditField_6.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStopEditField_6ValueChanged, true);
            app.PostopColumnStopEditFieldLabel_4 = uilabel(app.BDITab);
            app.PostopColumnStopEditFieldLabel_4.Text = 'Postop Column Stop';
            app.PostopColumnStartEditField_6 = uieditfield(app.BDITab, 'text');
            app.PostopColumnStartEditField_6.ValueChangedFcn = createCallbackFcn(app, @PostopColumnStartEditField_6ValueChanged, true);
            app.PostopColumnStartEditFieldLabel_5 = uilabel(app.BDITab);
            app.PostopColumnStartEditFieldLabel_5.Text = 'Postop Column Start';
            app.ImportfromEXCELButton_5 = uibutton(app.BDITab, 'push');
            app.ImportfromEXCELButton_5.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_5Pushed, true);
            app.ImportfromEXCELButton_5.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_5.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_6 = uibutton(app.BDITab, 'push');
            app.ImportfromEXCELButton_6.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_6Pushed, true);
            app.ImportfromEXCELButton_6.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_6.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_7 = uibutton(app.BDITab, 'push');
            app.ImportfromEXCELButton_7.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_7Pushed, true);
            app.ImportfromEXCELButton_7.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_7.Text = 'Import from EXCEL';
            app.ImportfromEXCELButton_8 = uibutton(app.BDITab, 'push');
            app.ImportfromEXCELButton_8.ButtonPushedFcn = createCallbackFcn(app, @ImportfromEXCELButton_8Pushed, true);
            app.ImportfromEXCELButton_8.Tooltip = {'If you would like to choose baselines directly from the list of headers in your excel sheet, use this button.'};
            app.ImportfromEXCELButton_8.Text = 'Import from EXCEL';
            app.ScorecalculationDropDownLabel = uilabel(app.BDITab);
            app.ScorecalculationDropDownLabel.Text = 'Score calculation';
            app.ScorecalculationDropDown = uidropdown(app.BDITab);
            app.ScorecalculationDropDown.Items = {'Higher total scores indicate worse outcomes', 'Higher total scores indicate better outcomes'};
            app.ScorecalculationDropDown.ValueChangedFcn = createCallbackFcn(app, @ScorecalculationDropDownValueChanged, true);
            app.ScorecalculationDropDown.Value = 'Higher total scores indicate worse outcomes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clinical_score_generator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end