classdef ea_score_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        GenerateExampleExcelMenu        matlab.ui.container.Menu
        forUPDRSMenu                    matlab.ui.container.Menu
        forOtherScoretypesMenu          matlab.ui.container.Menu
        ExportCalculatedScoresasExcelMenu  matlab.ui.container.Menu
        forUPDRSMenu_2                  matlab.ui.container.Menu
        forCustomMenu                   matlab.ui.container.Menu
        ExportCleanedExcelforCustomScoresMenu  matlab.ui.container.Menu
        TransformBaselinescoresMenu     matlab.ui.container.Menu
        DividebyMaximumMenu             matlab.ui.container.Menu
        MaxMinNormalizationMenu         matlab.ui.container.Menu
        StandardizationMenu             matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        CUSTOMTab                       matlab.ui.container.Tab
        ThresholdscoreEditField         matlab.ui.control.NumericEditField
        ThresholdscoreEditFieldLabel    matlab.ui.control.Label
        OnlyCalculateGlobalChangeScoresCheckBox  matlab.ui.control.CheckBox
        CustomNameforClinicalMeasureEditField  matlab.ui.control.EditField
        CustomNameforClinicalMeasureEditFieldLabel  matlab.ui.control.Label
        BaselinePostopEditField         matlab.ui.control.Label
        ADDNEWVARIABLEButton            matlab.ui.control.Button
        OutputAverageofResultsCheckBox  matlab.ui.control.CheckBox
        ScoreTypeEditField              matlab.ui.control.EditField
        ScoreTypeEditFieldLabel         matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        ImportExcelSheetButton          matlab.ui.control.Button
        CALCULATECHANGESCORESCheckBox   matlab.ui.control.CheckBox
        POSTOPSTOPDropDown              matlab.ui.control.DropDown
        POSTOPSTARTDropDown             matlab.ui.control.DropDown
        BASELINESTOPDropDown            matlab.ui.control.DropDown
        BASELINESTARTDropDown           matlab.ui.control.DropDown
        GENERATEButton_3                matlab.ui.control.Button
        POSTOPEditField_5               matlab.ui.control.EditField
        UPDRSTab                        matlab.ui.container.Tab
        PostoperativeItemsListBox       matlab.ui.control.ListBox
        PostoperativeItemsListBoxLabel  matlab.ui.control.Label
        PreoperativeBaselineItemsListBox  matlab.ui.control.ListBox
        PreoperativeBaselineItemsListBoxLabel  matlab.ui.control.Label
        GUIDLabel                       matlab.ui.control.Label
        ThresholdTremorScoresatEditField  matlab.ui.control.NumericEditField
        ThresholdTremorScoresatEditFieldLabel  matlab.ui.control.Label
        ExcludeErrTremorPatientsCheckBox  matlab.ui.control.CheckBox
        ImportExcelSheetButton_2        matlab.ui.control.Button
        LateralityButtonGroup           matlab.ui.container.ButtonGroup
        LeftButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        BilateralButton                 matlab.ui.control.RadioButton
        PostopColumnStartEditFieldLabel  matlab.ui.control.Label
        GENERATEButton                  matlab.ui.control.Button
    end

  properties (Access = public)
        
        postop_checked_box
        medon_stimoff
        medon_stimon
        lead_file
        ex_excel
        fname       
        baseline
        baseline_2
        postop
        postop_2
        baseline_checked_box
        postop2_checked_box
        tdata
        baseline_start_medoff
        baseline_stop_medoff
        baseline_start_medon
        baseline_stop_medon
        postop_start_medoff
        postop_stop_medoff
        postop_start_medon
        postop_stop_medon
        BASET
        POSTT
        patients
        flag
        global_score
        brady
        rigidity
        axial
        tremor
        postop_start
        postop_stop
        hndl
        GLOBAL_ABS_VALS
        GLOBAL_AVG_VALS
        GLOBAL_IMP_VALS
        GLOBAL_CLEANED_VALS
        BRADYKINESIA_ABS_VALS
        BRADYKINESIA_IMP_VALS
        BRADYKINESIA_CLEANED_VALS
        RIGIDITY_ABS_VALS
        RIGIDITY_CLEANED_VALS
        RIGIDITY_IMP_VALS
        AXIAL_ABS_VALS
        AXIAL_CLEANED_VALS
        AXIAL_IMP_VALS
        TREMOR_ABS_VALS
        TREMOR_CLEANED_VALS
        TREMOR_IMP_VALS
        CUSTOM_ABS_VALS
        CUSTOM_CLEANED_VALS
        CUSTOM_IMP_VALS
        somatotopy
        bl_start
        bl_stop
        pp_start
        pp_stop
        var_name
        uil
        uil2
        another_lbl
        another_lbl2
        path_lbl
        selectedClm
        score_type
        var_names
        uit
        BaselineGuid
        PostopGuid
    end
    
    
    methods (Access = private)
        
        function score_generator(app,baseline_range,postop_range)  
            [~,subjid,~] = fileparts(app.patients);

            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            baseline_tab = readtable(app.fname,'Range',baseline_range);
            baseline_mat = readmatrix(app.fname,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            
            for i=1:length(postop_range)
                postop_tab{i,1} = readtable(app.fname,'Range',postop_range{i,1});
                postop_mat{i,1} = readmatrix(app.fname,'Range',postop_range{i,1});
                postop_tab_names{i,1} = postop_tab{i,1}.Properties.VariableNames;
                if all(isnan(postop_mat{i,:}(1,:)))
                   postop_mat{i,:}(1,:) = [];
                end
            end
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end

            for pt=1:length(app.patients)
                odir=fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
          
                if ~exist(odir,'dir')
                    mkdir(odir);
                end
                for i=1:length(postopid)
                    app.BASET = array2table(baseline_mat,'VariableNames',baseline_tab_names);
                    app.POSTT = array2table(postop_mat{i,:},'VariableNames',postop_tab_names{i,:});
                    BASET_pt = app.BASET(pt,:);
                    POSTT_pt = app.POSTT(pt,:);
                    identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                    motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                    if strcmp(app.MotorScoreTypeDropDown.Value,'UPDRS')
                        motor_flag = 'Motor_UPDRS';
                    elseif strcmp(app.MotorScoreTypeDropDown.Value,'MDS')
                        motor_flag = 'Motor_MDSUPDRS';
                    else
                        if isnan(motor_identifier{1,1})
                            motor_flag = 'Motor_UPDRS';
                        else
                            motor_flag = 'Motor_MDSUPDRS';
                        end
                        
                    end
                    postop_flag = postopid{i,:};                   
                    total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                    if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                        %if you have to calculate the total, add up
                        %already total names do not 
                        %consider column names which are "total". MAYBE
                        %THIS CAN BE CHANGED?
                        exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                        exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                        global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                        global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                        global_preop.Total = nansum(global_preop{:,1:end},2);
                        global_postop.Total = nansum(global_postop{:,1:end},2);
                        GLOBAL_BASELINE.(postop_flag)(pt,:) = global_preop{:,end};
                        
                        scores.(app.guid).(app.baseline).Global.RawValues = global_preop;
                        scores.(app.guid).(postop_flag).Global.RawValues = global_postop;
                        scores.(app.guid).(postop_flag).Global.abs_improvements = global_preop.Total - global_postop.Total;
                        scores.(app.guid).(postop_flag).Global.perc_improvements = (global_preop.Total - global_postop.Total)./global_preop.Total;
                    else
                        scores.(app.guid).(app.baseline).Global.RawValues = BASET_pt;
                        scores.(app.guid).(postop_flag).Global.RawValues = POSTT_pt;
                        scores.(app.guid).(postop_flag).Global.abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                        scores.(app.guid).(postop_flag).Global.perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                        GLOBAL_BASELINE.(postop_flag)(pt,:) = BASET_pt{:,end};
                    end
                    app.GLOBAL_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Global.abs_improvements;
                    app.GLOBAL_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Global.perc_improvements;
                    
                    if strcmp(motor_flag,'Motor_UPDRS')
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31'));
                        end
                    else
                        if strcmp(app.somatotopy.Text,'Left')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31|ToeTappingL'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31|ToeTappingL'));
                        elseif strcmp(app.somatotopy.Text,'Right')
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31|ToeTappingR'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31|ToeTappingR'));
                        else
                            hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31|ToeTapping'));
                            hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31|ToeTapping'));
                        end
                    end
                    
                    brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    brady_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    brady_preop.Total = nansum(brady_preop{:,1:end},2);
                    brady_postop.Total = nansum(brady_postop{:,1:end},2);
                    scores.(app.guid).(app.baseline).Bradykinesia.RawValues = brady_preop;
                    scores.(app.guid).(postop_flag).Bradykinesia.RawValues = brady_postop;
                   
                    scores.(app.guid).(postop_flag).Bradykinesia.abs_improvements = brady_preop.Total - brady_postop.Total;
                    scores.(app.guid).(postop_flag).Bradykinesia.perc_improvements = ((brady_preop.Total-brady_postop.Total)./brady_preop.Total);
                    app.BRADYKINESIA_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Bradykinesia.abs_improvements;
                    app.BRADYKINESIA_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Bradykinesia.perc_improvements;
                    BRADYKINESIA_BASELINE.(postop_flag)(pt,:) = brady_preop{:,end};
                    
                    
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*22'));
                    end
                    rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    rigidity_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                    rigidity_postop.Total = nansum(rigidity_postop{:,1:end},2);
                    scores.(app.guid).(app.baseline).Rigidity.RawValues = rigidity_preop;
                    scores.(app.guid).(postop_flag).Rigidity.RawValues = rigidity_postop;
                    
                    scores.(app.guid).(postop_flag).Rigidity.abs_improvements = rigidity_preop.Total - rigidity_postop.Total;
                    scores.(app.guid).(postop_flag).Rigidity.perc_improvements = ((rigidity_preop.Total-rigidity_postop.Total)./rigidity_preop.Total);
                    app.RIGIDITY_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Rigidity.abs_improvements;
                    app.RIGIDITY_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Rigidity.perc_improvements;
                    RIGIDITY_BASELINE.(postop_flag)(pt,:) = rigidity_preop{:,end};
                    
                    
                    if strcmp(motor_flag,'Motor_MDSUPDRS')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[1-2][7-9]|U.*30|MDSFOG'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[1-2][7-9]|U.*30|MDSFOG'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[1-2][7-9]|U.*30'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'U.*[1-2][7-9]|U.*30'));
                    end
                    axial_gait_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    axial_gait_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    axial_gait_preop.Total = nansum(axial_gait_preop{:,1:end},2);
                    axial_gait_postop.Total = nansum(axial_gait_postop{:,1:end},2);
                    scores.(app.guid).(app.baseline).Axial_Gait.RawValues = axial_gait_preop;
                    scores.(app.guid).(postop_flag).Axial_Gait.RawValues = axial_gait_postop;
                    
                    scores.(app.guid).(postop_flag).Axial_Gait.abs_improvements = axial_gait_preop.Total - axial_gait_postop.Total;
                    scores.(app.guid).(postop_flag).Axial_Gait.perc_improvements = ((axial_gait_preop.Total-axial_gait_postop.Total)./axial_gait_preop.Total);
                    app.AXIAL_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Axial_Gait.abs_improvements;
                    app.AXIAL_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Axial_Gait.perc_improvements;
                    AXIAL_BASELINE.(postop_flag)(pt,:) = axial_gait_preop{:,end};
                                         
                    if strcmp(app.somatotopy.Text,'Left')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Left'));
                    elseif strcmp(app.somatotopy.Text,'Right')
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor.*Right'));
                    else
                        hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                        hasMatch_postop = ~cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, 'tremor'));
                    end
                    tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                    tremor_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(hasMatch_postop));
                    tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                    tremor_postop.Total = nansum(tremor_postop{:,1:end},2);
                   
                    scores.(app.guid).(app.baseline).Tremor.RawValues = tremor_preop;
                    scores.(app.guid).(postop_flag).Tremor.RawValues = tremor_postop;
                    if app.ThresholdTremorScoresatEditField.Value
                        if tremor_preop.Total < app.ThresholdTremorScoresatEditField.Value 
                           tremor_preop.Total = NaN;
                           tremor_postop.Total = NaN;
                           scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = NaN; 
                           scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = NaN;
                           app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                           app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                           TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
                        else
                            scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = tremor_preop.Total - tremor_postop.Total;
                            scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                            app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                            app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                            TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
                        end
                    else
                        scores.(app.guid).(postop_flag).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                        scores.(app.guid).(postop_flag).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                        app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.abs_improvements;
                        app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.perc_improvements;
                        TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
                    end
                    
                end
                save(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']),'scores');
                clear scores;
            end
            for i=1:length(postopid)
                postop_flag = postopid{i,:};
                app.GLOBAL_CLEANED_VALS.(postop_flag) = ea_resid(GLOBAL_BASELINE.(postop_flag),app.GLOBAL_ABS_VALS.(postop_flag));
                app.BRADYKINESIA_CLEANED_VALS.(postop_flag) = ea_resid(BRADYKINESIA_BASELINE.(postop_flag),app.BRADYKINESIA_ABS_VALS.(postop_flag));
                app.RIGIDITY_CLEANED_VALS.(postop_flag) = ea_resid(RIGIDITY_BASELINE.(postop_flag),app.RIGIDITY_ABS_VALS.(postop_flag));
                app.AXIAL_CLEANED_VALS.(postop_flag) = ea_resid(AXIAL_BASELINE.(postop_flag),app.AXIAL_ABS_VALS.(postop_flag));
                app.TREMOR_CLEANED_VALS.(postop_flag) = ea_resid(TREMOR_BASELINE.(postop_flag),app.TREMOR_ABS_VALS.(postop_flag));    
            end
           
            
            for i = 1:length(app.patients)
                load(fullfile(app.lead_file.patient.list{i},'clinical',app.guid,[subjid{i},'_desc-clinicalScores.mat']));
                odir = fullfile(app.lead_file.patient.list{i},'clinical',app.guid);
                
                for j=1:length(postopid)
                    postop_flag = postopid{j,:};
                    scores.(app.guid).(postop_flag).Global.cleaned_improvements = app.GLOBAL_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(app.guid).(postop_flag).Bradykinesia.cleaned_improvements = app.BRADYKINESIA_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(app.guid).(postop_flag).Rigidity.cleaned_improvements = app.RIGIDITY_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(app.guid).(postop_flag).Axial_Gait.cleaned_improvements = app.AXIAL_CLEANED_VALS.(postop_flag)(i,:);
                    scores.(app.guid).(postop_flag).Tremor.cleaned_improvements = app.TREMOR_CLEANED_VALS.(postop_flag)(i,:);
                end
                save(fullfile(odir,[subjid{i},'_desc-clinicalScores.mat']),'scores');
            end
             to_disp = ['*' 'Scores saved successfully for all patients' '*'];
             disp(to_disp)

        end
        function baseline_generator(app,postop_range,flag)
            selection = 'Add to Scores Directory';
            [~,subjid,~] = fileparts(app.patients);
            baseline_mat = readmatrix(app.fname,'Range',postop_range);
            baseline_tab = readtable(app.fname,'Range',postop_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            
            app.BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
            for pt=1:length(app.patients)
                odir=fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
                if ~exist(odir,'dir')
                    mkdir(odir);
                elseif strcmp(selection,'Add to Scores Directory')
                   load(fullfile(app.lead_file.patient.list{pt},'clinical',[subjid{pt},'_desc-clinicalScores.mat']));
                end
                BASET_pt = app.BASET(pt,:);
                identifier = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'MDS*'));
                motor_identifier = BASET_pt(:,BASET_pt.Properties.VariableNames(identifier));
                
                if isnan(motor_identifier{1,1}) % UPDRS
                    motor_flag = 'Motor_UPDRS';
                else
                    motor_flag = 'Motor_MDSUPDRS';
                end   
                
                total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                    %total not available
                    exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                    global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                    global_preop.Total = nansum(global_preop{:,1:end},2);
                    scores.(motor_flag).(postopid).Global.(postopid) = global_preop;
                else
                    global_preop.Total = BASET_pt{:,end};
                    scores.(motor_flag).(postopid).Global.(postopid) = BASET_pt;
                    
                end
                
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Left|U.*31'));
                    
                elseif strcmp(app.somatotopy.Text,'Right')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6].*Right|U.*31'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*[2][3-6]|U.*31'));
                end
                
                brady_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                brady_preop.Total = nansum(brady_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Bradykinesia.(postopid) = brady_preop;
                
                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Left'));
                elseif strcmp(app.somatotopy.Text,'Right')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22.*Right'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*22'));
                end
                rigidity_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                rigidity_preop.Total = nansum(rigidity_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Rigidity.(postopid) = rigidity_preop;
                
                
                hasMatch = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'U.*2[7-9]|U.*30'));
                axial_gait_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch));
                axial_gait_preop.Total = nansum(axial_gait_preop{:,1:end},2);
                scores.(motor_flag).(postopid).Axial_Gait.(postopid) = axial_gait_preop;


                if strcmp(app.somatotopy.Text,'Left')
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Left'));
                elseif strcmp(app.somatotopy.Text,'Right')
                    
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor.*Right'));
                else
                    hasMatch_preop = ~cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, 'tremor'));
                end
                tremor_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(hasMatch_preop));
                tremor_preop.Total = nansum(tremor_preop{:,1:end},2);
                scores.(motor_flag).Postop.Tremor.(postopid) = tremor_preop;
                
                global_baseline_total(pt,:) = global_preop.Total;
                bradykinesia_baseline_total(pt,:) = brady_preop.Total;
                rigidity_baseline_total(pt,:) = rigidity_preop.Total;
                axial_baseline_total(pt,:) = axial_gait_preop.Total;
                tremor_baseline_total(pt,:) = tremor_preop.Total;
                save(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']),'scores');
                clear score;
            end
            for input_array = [global_baseline_total,bradykinesia_baseline_total,rigidity_baseline_total,axial_baseline_total,tremor_baseline_total]
                if isequaln(input_array,global_baseline_total)
                    name = 'Global';
                elseif isequaln(input_array,bradykinesia_baseline_total)
                    name = 'Bradykinesia';
                elseif isequaln(input_array,rigidity_baseline_total)
                    name = 'Rigidity';
                elseif isequaln(input_array,axial_baseline_total)
                    name = 'Axial';
                elseif isequaln(input_array,tremor_baseline_total)
                    name = 'Tremor';
                end
                gen_normalized_score(app,input_array,flag,name)
            end
            to_disp = ['*' 'Scores saved successfully for all patients' '*'];
            disp(to_disp);
        end
        
        function gen_normalized_score(app,input_array,flag,name)
           [~,subjid,~] = fileparts(app.patients);
           postopid = app.baseline;
           for i = 1:length(app.patients)
                load(fullfile(app.lead_file.patient.list{i},'clinical',[subjid{i},'_desc-clinicalScores.mat']));
                if isfield(scores,'Motor_UPDRS')
                    motor_flag = 'Motor_UPDRS';
                else
                    motor_flag = 'Motor_MDSUPDRS';
                end
                odir = fullfile(app.lead_file.patient.list{i},'clinical',app.guid);
                if  flag == 2
                    max_baseline = input_array(i)./max(input_array);
                    scores.(motor_flag).(postopid).(name).normalized_baseline = max_baseline;
                elseif flag == 3
                    min_baseline = min(input_array);
                    max_baseline = max(input_array);
                    scores.(motor_flag).(postopid).(name).normalized_baseline = (input_array(i) - min_baseline)/(max_baseline - min_baseline);
                else
                    mean_baseline = nansum(input_array)/length(input_array);
                    std_baseline = nanstd(input_array);
                    scores.(motor_flag).(postopid).(name).standardize_baseline = (input_array(i) - mean_baseline)./(std_baseline);
                end
                save(fullfile(odir,[subjid{i},'_desc-clinicalScores.mat']),'scores');
                clear score;
           end
        end
        
        function column_names = read_tab(app)
            
            alpha1 = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
            if length(app.tdata.Properties.VariableNames) < length(alpha1)
                for i=1:length(app.tdata.Properties.VariableNames)
                    column_names{i} = [alpha1(i) ' :' app.tdata.Properties.VariableNames{i}];
                end
            else
                for i=1:length(alpha1)
                    column_names{i} = [alpha1(i) ' :' app.tdata.Properties.VariableNames{i}];
                end
                j = 1;
                for i=length(alpha1)+1:2*length(alpha1)
                    column_names{i} = [alpha1(1),alpha1(j) ' :' app.tdata.Properties.VariableNames{i}];
                    j = j+1;
                end
                j = 1;
                for i=2*length(alpha1)+1:length(app.tdata.Properties.VariableNames)
                    column_names{i} = [alpha1(2),alpha1(j) ' :' app.tdata.Properties.VariableNames{i}];
                    j = j+1;
                end
            end
        end
        function custom_score_gen(app,baseline_range,postop_range,flag,selection)
            [~,subjid,~] = fileparts(app.patients);
            if ~isempty(app.GLOBAL_ABS_VALS)
                app.GLOBAL_ABS_VALS = [];
            end
            if ~isempty(app.GLOBAL_CLEANED_VALS)
               app.GLOBAL_CLEANED_VALS = [];
            end
            baseline_mat = readmatrix(app.fname,'Range',baseline_range);
            baseline_tab = readtable(app.fname,'Range',baseline_range);
            baseline_tab_names = baseline_tab.Properties.VariableNames;
            if all(isnan(baseline_mat(1,:)))
                baseline_mat(1,:) = [];
            end
            BASET =array2table(baseline_mat,'VariableNames',baseline_tab_names);
            if ~isempty(postop_range)
                %this would happen if you select ONLY the average scores
                %option.
                postop_mat = readmatrix(app.fname,'Range',postop_range);
                postop_tab = readtable(app.fname,'Range',postop_range);
                postop_tab_names = postop_tab.Properties.VariableNames;
                if all(isnan(postop_mat(1,:)))
                    postop_mat(1,:) = [];
                end
                POSTT =array2table(postop_mat,'VariableNames',postop_tab_names);
            end
            for pt = 1:length(app.patients)
                odir = fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
                if ~exist(odir,'dir')
                    ea_mkdir(odir)
                end
                if exist(odir,[subjid{pt},'_desc-clinicalScores.mat'],'file')
                    if strcmp(selection,'Add to Scores Directory')
                        load(fullfile(app.lead_file.patient.list{pt},'clinical',app.guid,[subjid{pt},'_desc-clinicalScores.mat']));
                    end
                end
                
                BASET_pt = BASET(pt,:);
                if ~isempty(postop_range)
                    POSTT_pt = POSTT(pt,:);
                end
                motor_flag = app.ScoreTypeEditField.Value;
                postop_flag = app.POSTOPEditField_5.Value;
                if flag == 1 || flag == 2
                    if size(BASET_pt,2) == 1 %if the user only provides one field
                        scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).([postop_flag '_baseline']) = BASET_pt;
                        scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).([postop_flag '_postop']) = POSTT_pt;
                        
                        if app.ThresholdscoreEditField.Value
                            if BASET_pt{:,end} < app.ThresholdscoreEditField.Value
                                POSTT_pt{:,end} = NaN;
                                BASET_pt{:,end} = NaN;
                                scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['abs_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['perc_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                            end
                        else
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                        end
                        UPDRS_BASELINE(pt,:) = BASET_pt{:,end};
                        UPDRS_ABS_VALUE(pt,:) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements;
                        app.GLOBAL_ABS_VALS(pt,:) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements;
                        if flag == 2 && ~app.OnlyCalculateGlobalChangeScoresCheckBox.Value
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).average_values = BASET_pt{:,end} - POSTT_pt{:,end};
                        end
                        app.GLOBAL_AVG_VALS(pt,:) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).average_values;
                    else
                        for i=1:size(BASET_pt,2)
                            if ~app.OnlyCalculateGlobalChangeScoresCheckBox.Value
                                if app.ThresholdscoreEditField.Value
                                    if BASET_pt{:,end} < app.ThresholdscoreEditField.Value
                                        POSTT_pt{:,end} = NaN;
                                        BASET_pt{:,end} = NaN;
                                        scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['abs_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                        scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['perc_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                    end
                                else
                                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                                end
                                scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).abs_improvements = BASET_pt{:,i} - POSTT_pt{:,i};
                                scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).perc_improvements = (BASET_pt{:,i} - POSTT_pt{:,i})./(BASET_pt{:,i});
                                UPDRS_BASELINE(pt,i) = BASET_pt{:,i};
                                UPDRS_ABS_VALUE(pt,i) = scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).abs_improvements;
                                app.GLOBAL_ABS_VALS(pt,i) = scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).abs_improvements;
                                POSTOP_BASELINE(pt,i) = POSTT_pt{:,i};
                                app.var_names{i} = baseline_tab_names{i};
                            end
                            
                        end
                        if flag == 2
                            baseline_mean = mean(UPDRS_BASELINE(pt,:),'omitnan');
                            postop_mean = mean(POSTOP_BASELINE(pt,:),'omitnan');
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).average_values = baseline_mean - postop_mean;
                            app.GLOBAL_AVG_VALS(pt,:) = baseline_mean - postop_mean;
                        end
                        total_available = regexpi(BASET_pt.Properties.VariableNames{end}, '.*Total|Total.*');
                        if isempty(total_available) || all(BASET_pt{:,end}==0) || all(BASET_pt{:,end}=='NaN')
                            exclude_preop = cellfun('isempty', regexpi(BASET_pt.Properties.VariableNames, '.*Total|Total.*'));
                            exclude_postop = cellfun('isempty', regexpi(POSTT_pt.Properties.VariableNames, '.*Total|Total.*'));
                            global_preop = BASET_pt(:,BASET_pt.Properties.VariableNames(exclude_preop));
                            global_postop = POSTT_pt(:,POSTT_pt.Properties.VariableNames(exclude_postop));
                            global_preop.Total = nansum(global_preop{:,1:end},2);
                            global_postop.Total = nansum(global_postop{:,1:end},2);
                            if app.ThresholdscoreEditField.Value
                                if global_preop.Total < app.ThresholdscoreEditField.Value
                                    global_postop.Total = NaN;
                                    global_preop.Total = NaN;
                                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['abs_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['perc_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                end
                            else
                                scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements = global_preop.Total - global_postop.Total;
                                scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).perc_improvements = (global_preop.Total - global_postop.Total)./global_preop.Total;
                            end
                            UPDRS_BASELINE(pt,i+1) = global_preop.Total;
                            UPDRS_ABS_VALUE(pt,i+1) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements;
                            app.GLOBAL_ABS_VALS(pt,i+1) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements;
                        else
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements = BASET_pt{:,end} - POSTT_pt{:,end};
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).perc_improvements = ((BASET_pt{:,end} - POSTT_pt{:,end})./BASET_pt{:,end});
                            if app.ThresholdscoreEditField.Value
                                if BASET_pt{:,end} < app.ThresholdscoreEditField.Value
                                    BASET_pt{:,end} = NaN;
                                    POSTT_pt{:,end} = NaN;
                                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['abs_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).(['perc_improvements_thresholded' num2str(app.ThresholdscoreEditField.Value)]) = NaN;
                                end
                            else

                                UPDRS_BASELINE(pt,i+1) = BASET_pt{:,end};
                                UPDRS_ABS_VALUE(pt,i+1) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements;
                                app.GLOBAL_ABS_VALS(pt,i+1) = scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).abs_improvements;
                            end
                        end
                        
                    end
                elseif flag == 4
                    for i=1:size(BASET_pt,2)
                        scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).absolute_values = BASET_pt{:,i};
                        UPDRS_BASELINE(pt,i) = BASET_pt{:,i};
                        app.GLOBAL_ABS_VALS(pt,i) = BASET_pt{:,i};
                        app.var_names{i} = baseline_tab_names{i};
                    end
                elseif flag == 3
                    for i=1:size(BASET_pt,2)
                        UPDRS_BASELINE(pt,i) = BASET_pt{:,i};
                    end
                    baseline_mean = mean(UPDRS_BASELINE(pt,:),'omitnan');
                    scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).average_values = baseline_mean;
                    app.GLOBAL_AVG_VALS(pt,:) = baseline_mean;
                end
                save(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']),'scores');
            end
    
            if flag == 1 || flag == 2
                
                for i=1:size(UPDRS_BASELINE,2)
                    UPDRS_cleaned_imprv(:,i) = ea_resid(UPDRS_BASELINE(:,i),UPDRS_ABS_VALUE(:,i));
                end
                for pt = 1:length(app.patients)
                    odir = (fullfile(app.lead_file.patient.list{pt},'clinical',app.guid));
                    load(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']));
                    for i=1:size(UPDRS_BASELINE,2)
                        if i == size(UPDRS_BASELINE,2)
                            scores.(motor_flag).(postop_flag).(app.CustomNameforClinicalMeasureEditField.Value).cleaned_improvements = UPDRS_cleaned_imprv(pt,i);
                            app.GLOBAL_CLEANED_VALS(pt,i) = UPDRS_cleaned_imprv(pt,i);
                        else
                            if ~app.OnlyCalculateGlobalChangeScoresCheckBox.Value
                                scores.(motor_flag).(postop_flag).(baseline_tab_names{i}).cleaned_improvements = UPDRS_cleaned_imprv(pt,i);
                                app.GLOBAL_CLEANED_VALS(pt,i) = UPDRS_cleaned_imprv(pt,i);
                            end
                        end
                    end
                    save(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']),'scores')
                end
                    
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, excel_sheet_pre, lead_group, handles)
            app.UIFigure.Name = 'Clinical Score Generator';
            if ~strcmp(excel_sheet_pre,'')
               app.fname = excel_sheet_pre; 
               app.tdata = readtable(app.fname);
            end
            app.lead_file = lead_group;
            app.patients = app.lead_file.patient.list;
            app.hndl = handles;
            app.GUIDLabel.Text = ['GUID: ' app.lead_file.guid];
            %other stuff in the custom tab
            app.uil = uilabel(app.CUSTOMTab,'Position',[40,212,108,22]);
            app.uil.FontSize = 12;
            app.uil.FontName = 'Helvetica';
            app.uil2 = uilabel(app.CUSTOMTab,'Position',[300,212,108,22]);
            app.uil2.FontSize = 12;
            app.uil2.FontName = 'Helvetica';
            app.uil.Text = 'BASELINE SCORES';
            app.uil2.Text = 'BASELINE SCORES';
            app.another_lbl = uilabel(app.CUSTOMTab,'Position',[40,180,106,22]);
            app.another_lbl.FontSize = 12;
            app.another_lbl.FontName = 'Helvetica';
            app.another_lbl2 = uilabel(app.CUSTOMTab,'Position',[300,180,106,22]);
            app.another_lbl2.FontSize = 12;
            app.another_lbl2.FontName = 'Helvetica';
            app.another_lbl.Text = 'POSTOP SCORES';
            app.another_lbl2.Text = 'POSTOP SCORES';
            app.uil.Enable = 'off';
            app.uil2.Enable = 'off';
            app.another_lbl.Enable = 'off';
            app.another_lbl2.Enable = 'off';
            app.ExportCalculatedScoresasExcelMenu.Enable = 'off';
        end

        % Callback function
        function MedoffStimoffCheckBoxValueChanged(app, event)
            app.medoff_stimoff = app.MedoffStimoffCheckBox.Value;
            
        end

        % Callback function
        function BaselineColumnStartEditFieldValueChanged(app, event)
            app.baseline_start_medoff = app.BaselineColumnStartEditField.Value;
            
        end

        % Callback function
        function BaselineColumnStopEditFieldValueChanged(app, event)
             app.baseline_stop_medoff = app.BaselineColumnStopEditField.Value;
            
        end

        % Callback function
        function MedoffStimonCheckBoxValueChanged(app, event)
            app.medoff_stimon = app.MedoffStimonCheckBox.Value;
            if app.medoff_stimon
               app.PostopColumnStartEditField.Enable = 'on';
               app.PostopColumnStopEditField.Enable = 'on'; 
            else
               app.PostopColumnStartEditField.Enable = 'off';
               app.PostopColumnStopEditField.Enable = 'off';  
               
            end
            
        end

        % Callback function
        function PostopColumnStartEditFieldValueChanged(app, event)
            app.postop_start_medoff = app.PostopColumnStartEditField.Value;
            
        end

        % Callback function
        function PostopColumnStopEditFieldValueChanged(app, event)
            app.postop_stop_medoff = app.PostopColumnStopEditField.Value;
            
        end

        % Callback function: not associated with a component
        function CheckBox_2ValueChanged(app, event)
            app.baseline_checked_box = app.CheckBox_2.Value;
            if app.baseline_checked_box
                app.BASELINESTARTDropDown_4.Enable = 'on';
                app.BASELINESTOPDropDown_4.Enable = 'on';
                app.BASELINESTARTDropDown_2Label_3.Enable = 'on';
                app.BASELINESTOPDropDown_2Label_3.Enable = 'on';
            else
               app.BASELINESTARTDropDown_4.Enable = 'off';
               app.BASELINESTOPDropDown_4.Enable = 'off';
               app.BASELINESTARTDropDown_2Label_3.Enable = 'off';
               app.BASELINESTOPDropDown_2Label_3.Enable = 'off';
            end
        end

        % Callback function
        function BaselineColumnStartEditField_2ValueChanged(app, event)
            app.baseline_start_medon = app.BaselineColumnStartEditField_2.Value;
             
        end

        % Callback function
        function BaselineColumnStopEditField_2ValueChanged(app, event)
            app.baseline_stop_medon = app.BaselineColumnStopEditField_2.Value;
            
        end

        % Callback function: not associated with a component
        function CheckBox_3ValueChanged(app, event)
            app.postop2_checked_box = app.CheckBox_3.Value;
            if app.CheckBox_3.Value
                app.BASELINESTARTDropDown_5.Enable = 'on';
                app.BASELINESTOPDropDown_5.Enable = 'on';
                app.BASELINESTARTDropDown_2Label_4.Enable = 'on';
                app.BASELINESTOPDropDown_2Label_4.Enable = 'on';
                
            else
               app.BASELINESTARTDropDown_5.Enable = 'off';
               app.BASELINESTOPDropDown_5.Enable = 'off';
               app.BASELINESTARTDropDown_2Label_4.Enable = 'off';
                app.BASELINESTOPDropDown_2Label_4.Enable = 'off';
            end
        end

        % Callback function
        function PostopColumnStartEditField_2ValueChanged(app, event)
            app.postop_start_medon = app.PostopColumnStartEditField_2.Value;
            
        end

        % Callback function
        function PostopColumnStopEditField_2ValueChanged(app, event)
            app.postop_stop_medon = app.PostopColumnStopEditField_2.Value;
            
        end

        % Callback function
        function SCORESButtonGroupSelectionChanged(app, event)
            %selectedButton = app.SCORESButtonGroup.SelectedObject;
            %if strcmp(selectedButton,'GLOBAL')
            %    disp("global")
            %end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            app.forUPDRSMenu_2.Enable = 'on';
            app.forUPDRSMenu.Enable = 'on';
            f = waitbar(0,'Generating Scores..');
            app.BusyLamp.Color = 'red';
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            app.patients = app.lead_file.patient.list;
            
            if ~app.baseline
                disp("Please provide atleast the baseline scores...")
            end
            already_done = 0;
            pt_cell = table2cell(app.tdata(:,1));
            if already_done == length(app.patients)
               fig = uifigure;
               msg = 'Saving these changes will overwrite previous changes.';
               title = 'Confirm Save';
               selection = uiconfirm(fig,msg,title,...
                   'Options',{'Overwrite','Add to Scores Directory','Cancel'},...
                   'DefaultOption',2,'CancelOption',3);
               close(fig)
           else
               selection = 'Overwrite';
            end
            
            waitbar(0.5,f,'Generating Scores..');
            baseline_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff]; 
            postop_range = [app.postop_start_medon ':' app.postop_stop_medon]; 
            score_generator(app,baseline_range,postop_range);

%             if app.BaselineStartDropDown.Enable && ~app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_5.Enable 
%                 postopid = app.baseline;
%                 if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
%                    postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff]; 
%                 else
%                    postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
%                 end
%                 flag = 1;
%                 baseline_generator(app,postopid,postop_range,flag) 
%             end
%             
%             if  app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_5.Enable
%                 tic
%                 postopid{1,:} = app.postop;
%                 %postop_range{1,:} = [1,37,350,71];
%                 postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
%                 disp("Generating Scores...")
%                 score_generator(app,postopid,postop_range,selection);
%                 disp("Process Done **")
%                 toc
%             end
%             
%             if app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_5.Enable
%                 tic
%                 postopid{1,:} = app.baseline_2;
%                 if ischar(app.baseline_start_medon) && ischar(app.baseline_stop_medon)
%                     postop_range{1,:} = [app.baseline_start_medon ':' app.baseline_stop_medon];
%                 else
%                     postop_range{1,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
%                 end
%                 disp("Generating Scores...")
%                 score_generator(app,postopid,postop_range,selection);
%                 disp("Process Done **")
%                 toc
%             end
%             if app.BASELINESTARTDropDown_5.Enable && ~app.BASELINESTARTDropDown_3.Enable && ~app.BASELINESTARTDropDown_4.Enable
%                 tic
%                 postopid{1,:} = app.postop_2;
%                 postop_range{1,:} = [1,app.postop_start_medon,length(app.patients)+1,app.postop_stop_medon];
%                 disp("Generating Scores...")
%                 score_generator(app,postopid,postop_range,selection);
%                 disp("Process Done **")
%                 toc
%             end
%             
%             if app.BASELINESTARTDropDown_3.Enable && app.BASELINESTARTDropDown_4.Enable && ~app.BASELINESTARTDropDown_5.Enable
%                tic
%                postopid{1,:} = app.postop;
%                postopid{2,:} = app.baseline_2;
%                postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
%                postop_range{2,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
%                disp("Generating scores...")
%                score_generator(app,postopid,postop_range,selection);
%                disp("Process Done **")
%                toc
%            
%             end   
%             
%             if app.BASELINESTARTDropDown_3.Enable && app.BASELINESTARTDropDown_4.Enable && app.BASELINESTARTDropDown_5.Enable
%                tic
%                postopid{1,:} = app.postop;
%                postopid{2,:} = app.baseline_2;
%                postopid{3,:} = app.postop_2;
%                postop_range{1,:} = [1,app.postop_start_medoff,length(app.patients)+1,app.postop_stop_medoff];
%                postop_range{2,:} = [1,app.baseline_start_medon,length(app.patients)+1,app.baseline_stop_medon];
%                postop_range{3,:} = [1,app.postop_start_medon,length(app.patients)+1,app.postop_stop_medon];
%                disp("Generating Scores...")
%                score_generator(app,'',postopid,postop_range,selection);
%                disp("Process Done **")
%                toc
%             end 
            waitbar(1,f,'Scores Generated Successfully!..');
            disp("Process Done **")
            setappdata(app.hndl.leadfigure,'M',app.lead_file);
            app.BusyLamp.Color = 'green';
            close(f)
                
        end

        % Callback function
        function GLOBALCheckBoxValueChanged(app, event)
            app.global_score = app.GLOBALCheckBox.Value;
            
        end

        % Callback function
        function AXIALCheckBoxValueChanged(app, event)
            app.axial = app.AXIALCheckBox.Value;
            
        end

        % Callback function
        function RIGIDITYCheckBoxValueChanged(app, event)
            app.rigidity = app.RIGIDITYCheckBox.Value;
            
        end

        % Callback function
        function BRADYKINESIACheckBoxValueChanged(app, event)
            app.brady = app.BRADYKINESIACheckBox.Value;
            
        end

        % Callback function
        function TREMORCheckBoxValueChanged(app, event)
            app.tremor = app.TREMORCheckBox.Value;
            
        end

        % Callback function
        function CREATECUSTOMSUBSCOREButtonPushed(app, event)
           
        end

        % Callback function
        function CreateCustomSubscoreEditFieldValueChanged(app, event)
            %app.create_custom = app.CreateCustomSubscoreEditField.Value;
        end

        % Callback function
        function CreateCustomTextAreaValueChanged(app, event)
            app.create_custom = app.CreateCustomTextArea.Value;
            
        end

        % Callback function
        function OKButtonPushed(app, event)
           
        end

        % Callback function: not associated with a component
        function BASELINEEditFieldValueChanged(app, event)
            app.baseline = app.BASELINEEditField.Value;
            
        end

        % Callback function: not associated with a component
        function POSTOPEditFieldValueChanged(app, event)
            app.postop = app.POSTOPEditField.Value;
            
        end

        % Callback function
        function LEADGROUPIDEditFieldValueChanged(app, event)
            app.leadgroupid = app.LEADGROUPIDEditField.Value;
            
        end

        % Callback function: not associated with a component
        function CheckBoxValueChanged(app, event)
            app.postop_checked_box = app.CheckBox.Value;
            if app.postop_checked_box
               app.BASELINESTARTDropDown_3.Enable = 'on';
               app.BASELINESTOPDropDown_3.Enable = 'on';
               app.BASELINESTARTDropDown_2Label_2.Enable = 'on';
               app.BASELINESTOPDropDown_2Label_2.Enable = 'on';
            else
               app.BASELINESTARTDropDown_3.Enable = 'off';
               app.BASELINESTOPDropDown_3.Enable = 'off';
               app.BASELINESTARTDropDown_2Label_2.Enable = 'off';
               app.BASELINESTOPDropDown_2Label_2.Enable = 'off';
            end
            
        end

        % Callback function: not associated with a component
        function EditFieldValueChanged(app, event)
            app.baseline_2 = app.EditField.Value;
            
        end

        % Callback function: not associated with a component
        function EditField_2ValueChanged(app, event)
            app.postop_2 = app.EditField_2.Value;
            
        end

        % Callback function
        function BASELINEEditField_2ValueChanged(app, event)
            
        end

        % Callback function
        function BaselineColumnStartEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function BaselineColumnStopEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function CheckBox_4ValueChanged(app, event)
            
        end

        % Callback function
        function CreateCustomTextArea_2ValueChanged(app, event)
           
        end

        % Callback function
        function SomatotopyDropDownValueChanged(app, event)
            %app.somatotopy = app.SomatotopyDropDown.Value;
            
        end

        % Selection changed function: LateralityButtonGroup
        function LateralityButtonGroupSelectionChanged(app, event)
            app.somatotopy = app.LateralityButtonGroup.SelectedObject;
            
        end

        % Callback function
        function ImportfromEXCELButton_2Pushed(app, event)

             
        end

        % Callback function
        function ImportfromEXCELButton_3Pushed(app, event)
          
        end

        % Callback function
        function ImportfromEXCELButton_4Pushed(app, event)
          
        end

        % Callback function
        function PostopColumnStartEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStopEditField_3ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStartEditField_5ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStopEditField_5ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStartEditField_6ValueChanged(app, event)
            
        end

        % Callback function
        function PostopColumnStopEditField_6ValueChanged(app, event)
            
        end

        % Callback function
        function GENERATEButton_2Pushed(app, event)
            
        end

        % Callback function
        function POSTOPEditField_2ValueChanged(app, event)
            
        end

        % Callback function
        function POSTOPEditField_4ValueChanged(app, event)
            
        end

        % Callback function
        function ImportfromEXCELButton_5Pushed(app, event)
            
        end

        % Callback function
        function ImportfromEXCELButton_6Pushed(app, event)
          
        end

        % Callback function
        function ImportfromEXCELButton_7Pushed(app, event)
          
        end

        % Callback function
        function ImportfromEXCELButton_8Pushed(app, event)
           
       
        end

        % Callback function
        function ExportasExcelButton_2Pushed(app, event)
            global_abs_table = array2table(app.GLOBAL_ABS_VALS,'VariableNames',{'GLOBAL ABSOLUTE VALUES'});
            global_imp_table = array2table(app.GLOBAL_IMP_VALS,'VariableNames',{'GLOBAL IMPROVEMENT VALUES'});
            global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS,'VariableNames',{'GLOBAL CLEANED VALUES'});            
            full_table = [app.BASET,app.POSTT,global_abs_table,global_imp_table,global_cleaned_tables];
            writetable(full_table,uiputfile('cleaned_clinical_scores.xlsx'));
        end

        % Button pushed function: GENERATEButton_3
        function GENERATEButton_3Pushed(app, event)
           app.ExportCalculatedScoresasExcelMenu.Enable = 'on';
           app.forOtherScoretypesMenu.Enable = 'on';
           app.forCustomMenu.Enable = 'on';
           app.patients = app.lead_file.patient.list;   
           app.BusyLamp.Color = 'red';
           pt_cell = table2cell(app.tdata(:,1));
           %SANITY CHECK
           [~,subjid,~] = fileparts(app.patients);
           already_done = 0;
           for i=1:length(app.patients)
               to_match_pt = [app.lead_file.root pt_cell{i,1}];
               if exist(fullfile(app.lead_file.patient.list{i},'clinical',app.guid,[subjid{i},'_desc-clinicalScores.mat'],'file'));
                  already_done = already_done + 1; %if you have it in the first patient's folder, assume it's there in all patients??
               end
               if ~strcmp(app.lead_file.patient.list{i},to_match_pt)
                   disp(['WARNING:' to_match_pt ' is not the same as the patient in the lead group file!'])
                   disp("Continuing as per lead group")
               end
           end
           tic
           if already_done == length(app.patients)
               fig = uifigure;
               msg = 'Saving these changes will overwrite previous changes.';
               title = 'Confirm Save';
               selection = uiconfirm(fig,msg,title,...
                   'Options',{'Overwrite','Add to Scores Directory','Cancel'},...
                   'DefaultOption',2,'CancelOption',3);
               close(fig)
           else
               selection = 'Overwrite';
           end
           f = waitbar(0,'Generating Scores..');
           if app.CALCULATECHANGESCORESCheckBox.Value && ~app.OutputAverageofResultsCheckBox.Value
               baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
               postop_range = [1,app.pp_start,length(app.patients)+1,app.pp_stop];
               chng_flag = 1;
               waitbar(0.5,f,'Generating Scores..');
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           elseif app.CALCULATECHANGESCORESCheckBox.Value && app.OutputAverageofResultsCheckBox.Value
               baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
               postop_range = [1,app.pp_start,length(app.patients)+1,app.pp_stop];
               chng_flag = 2;
               waitbar(0.5,f,'Generating Scores..');
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           elseif ~app.CALCULATECHANGESCORESCheckBox.Value && app.OutputAverageofResultsCheckBox.Value
               baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
               postop_range = [];
               chng_flag = 3;
               waitbar(0.5,f,'Generating Scores..');
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           elseif ~app.CALCULATECHANGESCORESCheckBox.Value && ~app.OutputAverageofResultsCheckBox.Value
               baseline_range = [1,app.bl_start,length(app.patients)+1,app.bl_stop];
               postop_range = [];
               chng_flag = 4;
               waitbar(0.5,f,'Generating Scores..');
               custom_score_gen(app,baseline_range,postop_range,chng_flag,selection);
           end
           waitbar(1,f,'Scores Generated Successfully!')
           close(f);
           disp("Scores successfully saved")
           app.BusyLamp.Color = 'green';
           toc
        end

        % Value changed function: POSTOPEditField_5
        function POSTOPEditField_5ValueChanged(app, event)
            value = app.POSTOPEditField_5.Value;
            
        end

        % Callback function
        function PostopColumnStartEditField_7ValueChanged(app, event)
            app.bl_start = app.PostopColumnStartEditField_7.Value;
            
        end

        % Callback function
        function PostopColumnStopEditField_7ValueChanged(app, event)
            app.bl_stop = app.PostopColumnStopEditField_7.Value;
            
        end

        % Callback function
        function PostopColumnStartEditField_8ValueChanged(app, event)
            app.pp_start = app.PostopColumnStartEditField_8.Value;
            
        end

        % Callback function
        function PostopColumnStopEditField_8ValueChanged(app, event)
            app.pp_stop = app.PostopColumnStopEditField_8.Value;
            
        end

        % Callback function
        function ImportfromEXCELButton_9Pushed(app, event)
            
        end

        % Callback function
        function ImportfromEXCELButton_10Pushed(app, event)
        
        end

        % Drop down opening function: BASELINESTARTDropDown
        function BASELINESTARTDropDownOpening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown.Items = column_names;
           
           app.BASELINESTARTDropDown.ValueChangedFcn = @(dd,event) selection(dd);
           function selection(dd)
                val = app.BASELINESTARTDropDown.Value;
                app.bl_start = find(strcmpi(column_names,val));
           end
           
        end

        % Drop down opening function: BASELINESTOPDropDown
        function BASELINESTOPDropDownOpening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown.Items = column_names; 
           app.BASELINESTOPDropDown.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown.Value;
                app.bl_stop = find(strcmpi(column_names,val));
                
           end
        end

        % Value changed function: BASELINESTARTDropDown
        function BASELINESTARTDropDownValueChanged(app, event)
            value = app.BASELINESTARTDropDown.Value;
        end

        % Drop down opening function: POSTOPSTARTDropDown
        function POSTOPSTARTDropDownOpening(app, event)
           column_names = read_tab(app);
           app.POSTOPSTARTDropDown.Items = column_names; 
           app.POSTOPSTARTDropDown.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.POSTOPSTARTDropDown.Value;
                app.pp_start = find(strcmpi(column_names,val));
           end 
        end

        % Drop down opening function: POSTOPSTOPDropDown
        function POSTOPSTOPDropDownOpening(app, event)
           column_names = read_tab(app);
           app.POSTOPSTOPDropDown.Items = column_names;
           app.POSTOPSTOPDropDown.ValueChangedFcn = @(dd,event) selection(dd);
           
         
           function selection(dd)
                val = app.POSTOPSTOPDropDown.Value;
                app.pp_stop = find(strcmpi(column_names,val));
           end
        end

        % Value changed function: CALCULATECHANGESCORESCheckBox
        function CALCULATECHANGESCORESCheckBoxValueChanged(app, event)
            value = app.CALCULATECHANGESCORESCheckBox.Value;
              if app.CALCULATECHANGESCORESCheckBox.Value || app.OutputAverageofResultsCheckBox.Value && app.CALCULATECHANGESCORESCheckBox.Value 
                  app.POSTOPSTARTDropDown.Enable = 'on';
                  app.POSTOPSTOPDropDown.Enable = 'on';
                  app.uil.Text = 'BASELINE START';
                  app.uil2.Text = 'BASELINE STOP';
                  app.another_lbl.Text = 'POSTOP START';
                  app.another_lbl2.Text = 'POSTOP STOP';
                  app.another_lbl.Enable = 'on';
                  app.another_lbl2.Enable = 'on';
              elseif ~app.CALCULATECHANGESCORESCheckBox.Value && app.OutputAverageofResultsCheckBox.Value
                  app.another_lbl.Enable = 'off';
                  app.another_lbl2.Enable = 'off';
                  app.POSTOPSTARTDropDown.Enable = 'off';
                  app.POSTOPSTOPDropDown.Enable = 'off';
                  app.uil.Text = 'START';
                  app.uil2.Text = 'STOP';
              elseif ~app.CALCULATECHANGESCORESCheckBox.Value && ~app.OutputAverageofResultsCheckBox.Value
                  app.uil.Text = 'START';
                  app.uil2.Text = 'STOP';
                  app.another_lbl.Enable = 'Off';
                  app.another_lbl2.Enable = 'Off';
                  app.POSTOPSTARTDropDown.Enable = 'Off';
                  app.POSTOPSTOPDropDown.Enable = 'Off';
              end
              
        end

        % Callback function: not associated with a component
        function BaselineStartDropDownOpening(app, event)
           column_names = read_tab(app);
           app.BaselineStartDropDown.Items = column_names; 
           app.BaselineStartDropDown.ValueChangedFcn = @(dd,event) selection(dd);
           
           function selection(dd)
                val = app.BaselineStartDropDown.Value;
                app.baseline_start_medoff = find(strcmpi(column_names,val)); 
           end
        end

        % Callback function: not associated with a component
        function bOpening(app, event)
           column_names = read_tab(app);
           app.b.Items = column_names; 
           app.b.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.b.Value;
                app.baseline_stop_medoff = find(strcmpi(column_names,val)); 
           end
        end

        % Callback function: not associated with a component
        function BASELINESTARTDropDown_3Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_3.Items = column_names; 
           app.BASELINESTARTDropDown_3.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTARTDropDown_3.Value;
                app.postop_start_medoff = find(strcmpi(column_names,val)); 
           end 
        end

        % Callback function: not associated with a component
        function BASELINESTOPDropDown_3Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_3.Items = column_names; 
           app.BASELINESTOPDropDown_3.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_3.Value;
                app.postop_stop_medoff = find(strcmpi(column_names,val)); 
           end
        end

        % Callback function: not associated with a component
        function BASELINESTARTDropDown_4Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_4.Items = column_names; 
           app.BASELINESTARTDropDown_4.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTARTDropDown_4.Value;
                app.baseline_start_medon = find(strcmpi(column_names,val)); 
           end 
        end

        % Callback function: not associated with a component
        function BASELINESTOPDropDown_4Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_4.Items = column_names; 
           app.BASELINESTOPDropDown_4.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_4.Value;
                app.baseline_stop_medon = find(strcmpi(column_names,val)); 
           end  
        end

        % Callback function: not associated with a component
        function BASELINESTARTDropDown_5Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTARTDropDown_5.Items = column_names; 
           app.BASELINESTARTDropDown_5.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTARTDropDown_5.Value;
                app.postop_start_medon = find(strcmpi(column_names,val)); 
           end 
        end

        % Callback function: not associated with a component
        function BASELINESTOPDropDown_5Opening(app, event)
           column_names = read_tab(app);
           app.BASELINESTOPDropDown_5.Items = column_names; 
           app.BASELINESTOPDropDown_5.ValueChangedFcn = @(dd,event) selection(dd);
         
           function selection(dd)
                val = app.BASELINESTOPDropDown_5.Value;
                app.postop_stop_medon = find(strcmpi(column_names,val)); 
           end 
        end

        % Callback function
        function ImportExampleExcelSheetButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx',app.lead_file.root);
            app.ex_excel = fullfile(path,file);
          
        end

        % Callback function
        function GENERATEEXCELButtonPushed(app, event)

            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];
            alpha1 = char(97:122);
            for i=1:length(example_excel.Properties.VariableNames)
                if i <= length(alpha1)
                    clm_names{i} = [alpha1(i) ':' example_excel.Properties.VariableNames];
                else
                    clm_names{i} = [alpha1(i),alpha1(i) ':' example_excel.Properties.VariableNames];
                end
            end
            excel_tab = array2table(pre_excel,'VariableNames',clm_names);
            [file,path] = writetable(excel_tab,uiputfile('clinical_scores.xlsx'));
            app.fname = fullfile(path,file);
            f = msgbox(['Example excel file save successfully at:' app.fname]);
        end

        % Callback function
        function ImportLeadGroupFileButtonPushed(app, event)
            
        end

        % Button pushed function: ImportExcelSheetButton
        function ImportExcelSheetButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            [file,path] = uigetfile('*.xlsx',app.lead_file.root);
            app.fname = fullfile(path,file);
            if ~isempty(app.path_lbl)
                app.path_lbl.Text = '';
                app.path_lbl.Text = ['Selected file:' app.fname];
            else
                app.path_lbl = uilabel(app.CUSTOMTab,'Text',['Selected file:' app.fname],'Position',[42,360,500,60]);
            end
            %app.ImportExcelSheetButton_2.Enable = 'off';
            app.CALCULATECHANGESCORESCheckBox.Enable = 'on';
            app.POSTOPEditField_5.Enable = 'on';
            app.BaselinePostopEditField.Enable = 'on';
            app.CustomNameforClinicalMeasureEditField.Enable = 'on';
            app.CustomNameforClinicalMeasureEditFieldLabel.Enable = 'on';
            app.uil.Enable = 'on';
            app.uil2.Enable = 'on';
            app.uil.Text = 'START';
            app.uil2.Text = 'STOP';
            app.BASELINESTARTDropDown.Enable = 'on';
            app.BASELINESTOPDropDown.Enable = 'on';
            app.OutputAverageofResultsCheckBox.Enable = 'on';
            app.GENERATEButton_3.Enable = 'on';
            app.ScoreTypeEditField.Enable = 'on';
            app.ScoreTypeEditFieldLabel.Enable = 'on';
            app.tdata = readtable(app.fname);
            app.BusyLamp.Color = 'green';
        end

        % Callback function
        function SheetNameEditFieldValueChanged(app, event)
          
        end

        % Callback function
        function ImportLeadGroupButtonPushed(app, event)
          
        end

        % Menu selected function: forUPDRSMenu
        function forUPDRSMenuSelected(app, event)
            app.ex_excel = [ea_getearoot, '/clinical_score_generator/example_excel.xlsx'];
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];           
            excel_tab = array2table(pre_excel,'VariableNames',example_excel.Properties.VariableNames);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Menu selected function: forUPDRSMenu_2
        function forUPDRSMenu_2Selected(app, event)
            fields = fieldnames(app.GLOBAL_ABS_VALS);
            for i=1:length(fields)
                global_abs_table = array2table(app.GLOBAL_ABS_VALS.(fields{i}),'VariableNames',{['Global Absolute Improvements' '_' fields{i}]});
                global_imp_table = array2table(app.GLOBAL_IMP_VALS.(fields{i}),'VariableNames',{['Global Percentage Improvements' '_' fields{i}]});
                global_cleaned_tables = array2table(app.GLOBAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Global Cleaned Improvements' '_' fields{i}]});
                bradykinesia_abs_table = array2table(app.BRADYKINESIA_ABS_VALS.(fields{i}),'VariableNames',{['Bradykinesia Absolute Improvements' '_' fields{i}]});
                bradykinesia_imp_table = array2table(app.BRADYKINESIA_IMP_VALS.(fields{i}),'VariableNames',{['Bradykinesia Percentage Improvements' '_' fields{i}]});
                bradykinesia_cleaned_tables = array2table(app.BRADYKINESIA_CLEANED_VALS.(fields{i}),'VariableNames',{['Bradykinesia Cleaned Improvements' '_' fields{i}]});
                rigidity_abs_table = array2table(app.RIGIDITY_ABS_VALS.(fields{i}),'VariableNames',{['Rigidity Absolute Improvements' '_' fields{i}]});
                rigidity_imp_table = array2table(app.RIGIDITY_IMP_VALS.(fields{i}),'VariableNames',{['Rigidity Percentage Improvements' '_' fields{i}]});
                rigidity_cleaned_tables = array2table(app.RIGIDITY_CLEANED_VALS.(fields{i}),'VariableNames',{['Rigidity Cleaned Improvements' '_' fields{i}]});
                axial_abs_table = array2table(app.AXIAL_ABS_VALS.(fields{i}),'VariableNames',{['Axial Absolute Improvements' '_' fields{i}]});
                axial_imp_table = array2table(app.AXIAL_IMP_VALS.(fields{i}),'VariableNames',{['Axial Percentage Improvements' '_' fields{i}]});
                axial_cleaned_tables = array2table(app.AXIAL_CLEANED_VALS.(fields{i}),'VariableNames',{['Axial Cleaned Improvements' '_' fields{i}]});
                tremor_abs_table = array2table(app.TREMOR_ABS_VALS.(fields{i}),'VariableNames',{['Tremor Absolute Improvements' '_' fields{i}]});
                tremor_imp_table = array2table(app.TREMOR_IMP_VALS.(fields{i}),'VariableNames',{['Tremor Percentage Improvements' '_' fields{i}]});
                tremor_cleaned_tables = array2table(app.TREMOR_CLEANED_VALS.(fields{i}),'VariableNames',{['Tremor Cleaned Improvements' '_' fields{i}]});
                
            end
            patient_list = array2table(app.lead_file.patient.list,'VariableNames',{'Patients'});
            full_table = [patient_list,global_abs_table,global_imp_table,global_cleaned_tables];
            subscores_table = [patient_list,bradykinesia_abs_table,bradykinesia_imp_table,bradykinesia_cleaned_tables,rigidity_abs_table,rigidity_imp_table,rigidity_cleaned_tables,axial_abs_table,axial_imp_table,axial_cleaned_tables,tremor_abs_table,tremor_imp_table,tremor_cleaned_tables];           
            writetable(full_table,uiputfile('calculated_clinical_scores.xlsx'));
            writetable(subscores_table,uiputfile('calculated_clinical_subscores.xlsx'));


        end

        % Button pushed function: ImportExcelSheetButton_2
        function ImportExcelSheetButton_2Pushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            app.fname = fullfile(path,file); 
            if exist(app.fname,'file')
                app.ImportExcelSheetButton_2.Text = file;
                app.tdata = readtable(app.fname);
                app.ThresholdTremorScoresatEditField.Enable = 'on';
                app.ThresholdTremorScoresatEditFieldLabel.Enable = 'on';
                app.LateralityButtonGroup.Enable = 'on';
                app.GENERATEButton.Enable = 'on';
                app.PreoperativeBaselineItemsListBox.Enable = 'on';
                app.PostoperativeItemsListBox.Enable = 'on';
                app.LateralityButtonGroup.Enable = 'on';
                app.ExcludeErrTremorPatientsCheckBox.Enable = 'on';
                column_names = read_tab(app);
                app.PreoperativeBaselineItemsListBox.Items = column_names;
                app.PostoperativeItemsListBox.Items = column_names;
                
            else
                ea_warndlg('Could not load file, please try again');
                %app.path_lbl = uilabel(app.UPDRSTab,'Text',app.fname,'Position',[40,330,500,100]);
            end
            
        end

        % Callback function
        function SheetNameEditField_2ValueChanged(app, event)
           
            
        end

        % Value changed function: ScoreTypeEditField
        function ScoreTypeEditFieldValueChanged(app, event)
            value = app.ScoreTypeEditField.Value;
            
        end

        % Value changed function: OutputAverageofResultsCheckBox
        function OutputAverageofResultsCheckBoxValueChanged(app, event)
            value = app.OutputAverageofResultsCheckBox.Value;
            if app.OutputAverageofResultsCheckBox.Value && ~app.CALCULATECHANGESCORESCheckBox.Value
                app.uil.Text = 'START';
                app.uil2.Text = 'STOP';
                app.uil.Position = [40,216,100,22];
                app.uil2.Position = [300,216,96,22];
                app.POSTOPSTARTDropDown.Enable = 'off';
                app.POSTOPSTOPDropDown.Enable = 'off';
                app.another_lbl.Enable = 'Off';
                app.another_lbl2.Enable = 'Off';
            elseif app.OutputAverageofResultsCheckBox.Value && app.CALCULATECHANGESCORESCheckBox.Value
                app.POSTOPSTARTDropDown.Enable = 'on';
                app.POSTOPSTOPDropDown.Enable = 'on';
                app.another_lbl.Enable = 'On';
                app.another_lbl2.Enable = 'On';
                app.another_lbl.Text = 'POSTOP START';
                app.another_lbl2.Text = 'POSTOP STOP';
            elseif ~app.OutputAverageofResultsCheckBox.Value && ~app.CALCULATECHANGESCORESCheckBox.Value  
                app.uil.Text = 'START';
                app.uil2.Text = 'STOP';
                app.another_lbl.Enable = 'Off';
                app.another_lbl2.Enable = 'Off';
                app.POSTOPSTARTDropDown.Enable = 'Off';
                app.POSTOPSTOPDropDown.Enable = 'Off';
                
            end
            
        end

        % Menu selected function: forOtherScoretypesMenu
        function forOtherScoretypesMenuSelected(app, event)
            app.ex_excel = [ea_getearoot, 'clinical_score_generator/example_excel.xlsx'];
            patient_tab{:} = app.lead_file.patient.list;
            example_excel = readtable(app.ex_excel);
            arr = zeros(length(app.lead_file.patient.list),(size(example_excel,2)-1));
            arr_cell = num2cell(arr);
            pre_excel = [patient_tab{1,1} arr_cell];
            clm_names{1} = 'PatientID';
            for i=2:length(example_excel.Properties.VariableNames)
                clm_names{i} = ['Var' num2str(i)];
            end
            
            excel_tab = array2table(pre_excel,'VariableNames',clm_names);
            [file,path] = uiputfile('clinical_scores.xlsx');
            writetable(excel_tab,fullfile(path,file));
            %app.fname = fullfile(path,file);
        end

        % Button pushed function: ADDNEWVARIABLEButton
        function ADDNEWVARIABLEButtonPushed(app, event)
              f = uifigure;
              f.Position(3) = 600;
              mylamp = uilamp(f,'Position',[560 390 100 22]);
              mylamp.Color = 'green';
              ptnames = app.lead_file.patient.list;
              set(f,'Name','Edit Regressor');
              patient_tab{:} = app.lead_file.patient.list;
              for i=1:length(patient_tab{1,1})
                 new_str = strsplit(patient_tab{1,1}{i,1},'/');
                 patient_list{i,:} = new_str{end};
              end
              contact_list = {'k0','k1','k2','k3','k8','k9','k10','k11','k12'};
              contact_pair_list = {'k1-2','k2-3','k3-4','k8-9','k9-10','k10-11'};
              %Variable name edit field
              var_field = uieditfield(f,'Value','NewVar','Position',[120 390 80 22],'ValueChangedFcn',@(var_field,event) text_changed(var_field));
              var_field_lbl = uilabel(f,'Text','Variable Name','Position',[30 390 80 22]);            
              %Score type edit field
              score_field = uieditfield(f,'Value','Custom','Position',[280 390 80 22],'ValueChangedFcn',@(score_field,event) score_text_changed(score_field));
              score_field_lbl = uilabel(f,'Text','Score Type','Position',[210 390 80 22]);            
               
              app.var_name = var_field.Value;
              app.score_type = score_field.Value;
              
              %drop down for different regressor types
              dd = uidropdown(f,'Items',{'Patient','Hemisphere','Contact Pair','Contact'},'Value','Patient','Position',[450 390 100 22],'ValueChangedFcn',@(dd,event) selection(dd,contact_list,contact_pair_list));
              %drop down label
              dd_lab = uilabel(f,"Text",'One Value Per','Position',[370 390 100 22]);
              btn = uibutton(f,'Text','Save','ButtonPushedFcn', @(btn,event) saveButtonPushed(btn,dd,contact_list,contact_pair_list));
              btn.Position = [275 50 50 20];
            
              %all function defintions
              function selection(dd,contact_list,contact_pair_list)
                if strcmp(dd.Value,'Patient')
                    table_names = {'Patient ID','Value'};
                    arr = nan(length(app.lead_file.patient.list),1);
                    arr_cell = num2cell(arr);
                    pre_excel = [patient_list arr_cell];
                    table_data = array2table(pre_excel,'VariableNames',table_names);
                    app.uit = uitable(f,'Data',table_data);
                    app.uit.Position = [100 80 400 300];
                    app.uit.ColumnEditable = [false true];
                elseif strcmp(dd.Value,'Hemisphere')
                    table_names = {'Patient_ID','Right Hemisphere','Left Hemisphere'};
                    arr = nan(length(app.lead_file.patient.list),2);
                    arr_cell = num2cell(arr);
                    pre_excel = [patient_list arr_cell];
                    table_data = array2table(pre_excel,'VariableNames',table_names);
                    app.uit = uitable(f,'Data',table_data);
                    app.uit.Position = [100 80 400 300];
                    app.uit.ColumnEditable = [false true true];
                elseif strcmp(dd.Value,'Contact')
                    table_names = [{'Patient ID'},contact_list];
                    arr = nan(length(app.lead_file.patient.list),length(contact_list));
                    arr_cell = num2cell(arr);
                    pre_excel = [patient_list arr_cell];
                    table_data = array2table(pre_excel,'VariableNames',table_names);
                    app.uit = uitable(f,'Data',table_data);
                    app.uit.Position = [100 80 400 300];
                    app.uit.ColumnEditable = true;
                else
                    table_names = [{'Patient ID'},contact_pair_list];
                    arr = nan(length(app.lead_file.patient.list),length(contact_pair_list));
                    arr_cell = num2cell(arr);
                    pre_excel = [patient_list arr_cell];
                    table_data = array2table(pre_excel,'VariableNames',table_names);
                    app.uit = uitable(f,'Data',table_data);
                    app.uit.Position = [100 80 400 300];
                    app.uit.ColumnEditable = true;
                end
                
                
            end
            function text_changed(var_field)
               app.var_name = var_field.Value;
            end
            function score_text_changed(score_field)
               app.score_type = score_field.Value;
            end
            function saveButtonPushed(app,dd,contact_list,contact_pair_list)
                  [~,subjid,~] = fileparts(app.patients);
                  msg = msgbox("Saving scores...");
                  t = app.uit.DisplayData;
                  for pt=1:length(app.lead_file.patient.list)
                      odir=fullfile(app.lead_file.patient.list{pt},'clinical',app.guid);
                      if exist(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']),'file')
                         load(fullfile(app.lead_file.patient.list{pt},'clinical',[subjid{pt},'_desc-clinicalScores.mat']));
                      end
                      if strcmp(dd.Value,'Patient')
                          scores.(app.score_type).Postop.(app.var_name).absolute_values = cell2mat(t{pt,2});
                      elseif strcmp(dd.Value,'Hemisphere')
                          scores.(app.score_type).Postop.(app.var_name).absolute_values_RightHem = cell2mat(t{pt,2});
                          scores.(app.score_type).Postop.(app.var_name).absolute_values_LeftHem = cell2mat(t{pt,3});
                      elseif strcmp(dd.Value,'Contact')
                          for j=1:length(contact_list)
                              scores.(app.score_type).Postop.(app.var_name).(['absolute_values_' contact_list{j}])= cell2mat(t{pt,j+1});
                          end
                      elseif strcmp(dd.Value,'Contact Pair')
                          for j=1:length(contact_pair_list)
                              scores.(app.score_type).Postop.(app.var_name).(['absolute_values_' contact_pair_list{j}])= cell2mat(t{pt,j+1});
                          end
                      end
                      save(fullfile(odir,[subjid{pt},'_desc-clinicalScores.mat']),'scores');
                      
                  end
                  close(msg);
                  close(f)
            end
        end

        % Value changed function: POSTOPSTARTDropDown
        function POSTOPSTARTDropDownValueChanged(app, event)
            value = app.POSTOPSTARTDropDown.Value;
            
        end

        % Callback function: not associated with a component
        function BaselineStartDropDownValueChanged(app, event)
            value = app.BaselineStartDropDown.Value;
            
        end

        % Menu selected function: forCustomMenu
        function forCustomMenuSelected(app, event)
            patient_list = array2table(app.lead_file.patient.list,'VariableNames',{'Patients'});
            full_table = patient_list;
            if ~isempty(app.GLOBAL_ABS_VALS)
                for i=1:length(app.var_names)
                    abs_var_names{i} = [app.var_names{i} '_absolute_improvement'];
                end
                if app.CALCULATECHANGESCORESCheckBox.Value && ~app.OutputAverageofResultsCheckBox.Value
                    if size(app.GLOBAL_ABS_VALS,2) - length(app.var_names)  == 1
                        abs_var_names{end+1} = 'Total';
                    end
                elseif app.CALCULATECHANGESCORESCheckBox.Value && app.OutputAverageofResultsCheckBox.Value
                        abs_var_names{end+1} = 'Average';
                        full_arr = [app.GLOBAL_ABS_VALS,app.GLOBAL_AVG_VALS];
                end
                if exist('full_arr','var')
                    global_abs_table = array2table(full_arr,'VariableNames',abs_var_names);
                else
                    global_abs_table = array2table(app.GLOBAL_ABS_VALS,'VariableNames',abs_var_names);
                end
                full_table = [full_table,global_abs_table];
            end
            if ~app.CALCULATECHANGESCORESCheckBox.Value && app.OutputAverageofResultsCheckBox.Value
                global_avg_table = array2table(app.GLOBAL_AVG_VALS,'VariableNames',{'Average Scores'});
                full_table = [full_table,global_avg_table];
            end
            if ~isempty(app.GLOBAL_CLEANED_VALS)
                for i=1:length(app.var_names)
                    cleaned_var_names{i} = [app.var_names{i} '_cleaned_improvement'];
                end
                global_cleaned_table = array2table(app.GLOBAL_CLEANED_VALS,'VariableNames',cleaned_var_names);
                full_table = [full_table,global_cleaned_table];
            end
            writetable(full_table,uiputfile('ex.xlsx'));
             
        end

        % Menu selected function: ExportCleanedExcelforCustomScoresMenu
        function ExportCleanedExcelforCustomScoresMenuSelected(app, event)
            fig = uifigure;
            app.selectedClm = {};
            pos_tab_left = fig.Position(1) - 400;
            pos_tab_right = fig.Position(3) - 80;
            tab = uitable(fig,'Data',app.tdata,'CellSelectionCallback',@(tab,event) cell_selection(app,event));
            tab.ColumnEditable = true;
            tab.Position(1) = pos_tab_left;
            tab.Position(2) = 40;
            tab.Position(3) = pos_tab_right;
            tab.Position(4) = 380;
            okb = uibutton(fig,"Text",'OK','ButtonPushedFcn',@(okb,event) okbuttonchose(app,event,fig));
            okb.Position(1) = 450;
            okb.Position(2) = 10;
            function cell_selection(app,event)
                app.selectedClm{end+1} = app.tdata.Properties.VariableNames{event.Indices(2)};
                msgbox(["Selected Column:" app.tdata.Properties.VariableNames{app.selectedClm(end)}])
            end
            function okbuttonchose(app,event,fig)
                vars = app.selectedClm;
                tdata_to_write = app.tdata(1:end,vars);
                writetable(tdata_to_write,uiputfile('cleaned_clinical_scores.xlsx'));
                close(fig);
            end
            
        end

        % Value changed function: ExcludeErrTremorPatientsCheckBox
        function ExcludeErrTremorPatientsCheckBoxValueChanged(app, event)
            value = app.ExcludeErrTremorPatientsCheckBox.Value;
            
        end

        % Menu selected function: DividebyMaximumMenu
        function DividebyMaximumMenuSelected(app, event)

            flag = 2;
            postopid = app.BASELINEEditField.Value;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
            else
                postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            end
            baseline_generator(app,postopid,postop_range,flag)

        end

        % Menu selected function: TransformBaselinescoresMenu
        function TransformBaselinescoresMenuSelected(app, event)
            
           
        end

        % Menu selected function: MaxMinNormalizationMenu
        function MaxMinNormalizationMenuSelected(app, event)
            flag = 3;
            postopid = app.BASELINEEditField.Value;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
            else
                postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            end
            baseline_generator(app,postopid,postop_range,flag)
        end

        % Menu selected function: StandardizationMenu
        function StandardizationMenuSelected(app, event)
            flag = 4;
            postopid = app.BASELINEEditField.Value;
            if ischar(app.baseline_start_medoff) && ischar(app.baseline_stop_medoff)
                postop_range = [app.baseline_start_medoff ':' app.baseline_stop_medoff];
            else
                postop_range = [1,app.baseline_start_medoff,length(app.patients)+1,app.baseline_stop_medoff];
            end
            baseline_generator(app,postopid,postop_range,flag)
            
        end

        % Value changed function: ThresholdTremorScoresatEditField
        function ThresholdTremorScoresatEditFieldValueChanged(app, event)
            value = app.ThresholdTremorScoresatEditField.Value;
            
        end

        % Value changed function: ThresholdscoreEditField
        function ThresholdscoreEditFieldValueChanged(app, event)
            value = app.ThresholdscoreEditField.Value;
            if app.ThresholdscoreEditField.Value
                if tremor_preop.Total < app.ThresholdTremorScoresatEditField.Value
                    tremor_preop.Total = NaN;
                    tremor_postop.Total = NaN;
                    scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = NaN;
                    scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = NaN;
                    app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                    app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                    TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
                else
                    scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = tremor_preop.Total - tremor_postop.Total;
                    scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]) = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                    app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['abs_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                    app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.(['perc_improvements_thresholded' num2str(app.ThresholdTremorScoresatEditField.Value)]);
                    TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
                end
            else
                scores.(app.guid).(postop_flag).Tremor.abs_improvements = tremor_preop.Total - tremor_postop.Total;
                scores.(app.guid).(postop_flag).Tremor.perc_improvements = ((tremor_preop.Total-tremor_postop.Total)./tremor_preop.Total);
                app.TREMOR_ABS_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.abs_improvements;
                app.TREMOR_IMP_VALS.(postop_flag)(pt,:) = scores.(app.guid).(postop_flag).Tremor.perc_improvements;
                TREMOR_BASELINE.(postop_flag)(pt,:) = tremor_preop{:,end};
            end
        end

        % Callback function: not associated with a component
        function SelectBaselinespecificGUIDDropDownValueChanged(app, event)
            
            all_guids = ['gs_',app.lead_file.guid];
            app.SelectBaselinespecificGUIDDropDown.Items = all_guids;
            value = app.SelectBaselinespecificGUIDDropDown.Value;
        end

        % Callback function: not associated with a component
        function SelectpostopspecificGUIDDropDownValueChanged(app, event)
            all_guids = ['gs_',app.lead_file.guid];
            app.SelectpostopspecificGUIDDropDown.Items = all_guids;
            value = app.SelectpostopspecificGUIDDropDown.Value;
            
        end

        % Value changed function: PreoperativeBaselineItemsListBox
        function PreoperativeBaselineItemsListBoxValueChanged(app, event)
            val = app.PreoperativeBaselineItemsListBox.Value;
            pattern = ':(.*?)[A-Za-z0-9]*';
            start_string = val{1};
            stop_string = val{end};
            app.baseline_start_medoff = regexprep(start_string,pattern,'');
            app.baseline_stop_medoff = regexprep(stop_string,pattern,'');
            app.baseline_start_medoff = app.baseline_start_medoff(~isspace(app.baseline_start_medoff));
            app.baseline_stop_medoff = app.baseline_stop_medoff(~isspace(app.baseline_stop_medoff));
        end

        % Value changed function: PostoperativeItemsListBox
        function PostoperativeItemsListBoxValueChanged(app, event)
            val = app.PostoperativeItemsListBox.Value;
            pattern = ':(.*?)[A-Za-z0-9]*';
            start_string = val{1};
            stop_string = val{end};
            app.postop_start_medon = regexprep(start_string,pattern,'');
            app.postop_stop_medon = regexprep(stop_string,pattern,'');
            app.postop_start_medoff = app.postop_start_medoff(~isspace(app.postop_start_medoff));
            app.postop_stop_medoff = app.postop_stop_medoff(~isspace(app.postop_stop_medoff));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.GenerateExampleExcelMenu = uimenu(app.ToolsMenu);
            app.GenerateExampleExcelMenu.Text = 'Generate Example Excel';
            app.forUPDRSMenu = uimenu(app.GenerateExampleExcelMenu);
            app.forUPDRSMenu.MenuSelectedFcn = createCallbackFcn(app, @forUPDRSMenuSelected, true);
            app.forUPDRSMenu.Text = '..for UPDRS';
            app.forOtherScoretypesMenu = uimenu(app.GenerateExampleExcelMenu);
            app.forOtherScoretypesMenu.MenuSelectedFcn = createCallbackFcn(app, @forOtherScoretypesMenuSelected, true);
            app.forOtherScoretypesMenu.Text = '...for Other Score types';
            app.ExportCalculatedScoresasExcelMenu = uimenu(app.ToolsMenu);
            app.ExportCalculatedScoresasExcelMenu.Text = 'Export Calculated Scores as Excel';
            app.forUPDRSMenu_2 = uimenu(app.ExportCalculatedScoresasExcelMenu);
            app.forUPDRSMenu_2.MenuSelectedFcn = createCallbackFcn(app, @forUPDRSMenu_2Selected, true);
            app.forUPDRSMenu_2.Text = '...for UPDRS';
            app.forCustomMenu = uimenu(app.ExportCalculatedScoresasExcelMenu);
            app.forCustomMenu.MenuSelectedFcn = createCallbackFcn(app, @forCustomMenuSelected, true);
            app.forCustomMenu.Text = '...for Custom';
            app.ExportCleanedExcelforCustomScoresMenu = uimenu(app.ToolsMenu);
            app.ExportCleanedExcelforCustomScoresMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportCleanedExcelforCustomScoresMenuSelected, true);
            app.ExportCleanedExcelforCustomScoresMenu.Text = 'Export Cleaned Excel for Custom Scores';
            app.TransformBaselinescoresMenu = uimenu(app.ToolsMenu);
            app.TransformBaselinescoresMenu.MenuSelectedFcn = createCallbackFcn(app, @TransformBaselinescoresMenuSelected, true);
            app.TransformBaselinescoresMenu.Text = 'Transform Baseline scores';
            app.DividebyMaximumMenu = uimenu(app.TransformBaselinescoresMenu);
            app.DividebyMaximumMenu.MenuSelectedFcn = createCallbackFcn(app, @DividebyMaximumMenuSelected, true);
            app.DividebyMaximumMenu.Text = 'Divide by Maximum';
            app.MaxMinNormalizationMenu = uimenu(app.TransformBaselinescoresMenu);
            app.MaxMinNormalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @MaxMinNormalizationMenuSelected, true);
            app.MaxMinNormalizationMenu.Text = 'Max-Min Normalization';
            app.StandardizationMenu = uimenu(app.TransformBaselinescoresMenu);
            app.StandardizationMenu.MenuSelectedFcn = createCallbackFcn(app, @StandardizationMenuSelected, true);
            app.StandardizationMenu.Text = 'Standardization';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CUSTOMTab = uitab(app.TabGroup);
            app.CUSTOMTab.Title = 'CUSTOM';
            app.POSTOPEditField_5 = uieditfield(app.CUSTOMTab, 'text');
            app.POSTOPEditField_5.ValueChangedFcn = createCallbackFcn(app, @POSTOPEditField_5ValueChanged, true);
            app.POSTOPEditField_5.Value = 'Custom';
            app.GENERATEButton_3 = uibutton(app.CUSTOMTab, 'push');
            app.GENERATEButton_3.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButton_3Pushed, true);
            app.GENERATEButton_3.Text = 'GENERATE';
            app.BASELINESTARTDropDown = uidropdown(app.CUSTOMTab);
            app.BASELINESTARTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTARTDropDownOpening, true);
            app.BASELINESTARTDropDown.ValueChangedFcn = createCallbackFcn(app, @BASELINESTARTDropDownValueChanged, true);
            app.BASELINESTARTDropDown.Tooltip = {'Select the Column name your baseline score start from. If you have only one score to select, cotinue to choose the same score in both the start and stop baseline columns.'};
            app.BASELINESTOPDropDown = uidropdown(app.CUSTOMTab);
            app.BASELINESTOPDropDown.DropDownOpeningFcn = createCallbackFcn(app, @BASELINESTOPDropDownOpening, true);
            app.BASELINESTOPDropDown.Tooltip = {'Select the Column name your baseline score stop at. If you have only one score to select, cotinue to choose the same score in both the start and stop baseline columns.'};
            app.POSTOPSTARTDropDown = uidropdown(app.CUSTOMTab);
            app.POSTOPSTARTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @POSTOPSTARTDropDownOpening, true);
            app.POSTOPSTARTDropDown.ValueChangedFcn = createCallbackFcn(app, @POSTOPSTARTDropDownValueChanged, true);
            app.POSTOPSTOPDropDown = uidropdown(app.CUSTOMTab);
            app.POSTOPSTOPDropDown.DropDownOpeningFcn = createCallbackFcn(app, @POSTOPSTOPDropDownOpening, true);
            app.POSTOPSTOPDropDown.Tooltip = {'Select the Column name your postop scores stop at. If you have only one score to select, cotinue to choose the same score in both the start and stop baseline columns.'};
            app.CALCULATECHANGESCORESCheckBox = uicheckbox(app.CUSTOMTab);
            app.CALCULATECHANGESCORESCheckBox.ValueChangedFcn = createCallbackFcn(app, @CALCULATECHANGESCORESCheckBoxValueChanged, true);
            app.CALCULATECHANGESCORESCheckBox.Tooltip = {'Check this box to calculate preop-postop improvements. If you have more than one score, the absolute value of each score will also be output.'};
            app.CALCULATECHANGESCORESCheckBox.Text = 'CALCULATE CHANGE SCORES';
            app.ImportExcelSheetButton = uibutton(app.CUSTOMTab, 'push');
            app.ImportExcelSheetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportExcelSheetButtonPushed, true);
            app.ImportExcelSheetButton.Text = 'Import Excel Sheet..';
            app.BusyLamp = uilamp(app.CUSTOMTab);
            app.ScoreTypeEditFieldLabel = uilabel(app.CUSTOMTab);
            app.ScoreTypeEditFieldLabel.Text = 'Score Type';
            app.ScoreTypeEditField = uieditfield(app.CUSTOMTab, 'text');
            app.ScoreTypeEditField.ValueChangedFcn = createCallbackFcn(app, @ScoreTypeEditFieldValueChanged, true);
            app.ScoreTypeEditField.Value = 'UPDRS';
            app.OutputAverageofResultsCheckBox = uicheckbox(app.CUSTOMTab);
            app.OutputAverageofResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputAverageofResultsCheckBoxValueChanged, true);
            app.OutputAverageofResultsCheckBox.Tooltip = {'Calculate the average of the scores you specified. You can specify a custom name for the average scores in the field below'};
            app.OutputAverageofResultsCheckBox.Text = 'Output Average of Results';
            app.ADDNEWVARIABLEButton = uibutton(app.CUSTOMTab, 'push');
            app.ADDNEWVARIABLEButton.ButtonPushedFcn = createCallbackFcn(app, @ADDNEWVARIABLEButtonPushed, true);
            app.ADDNEWVARIABLEButton.Text = 'ADD NEW VARIABLE';
            app.BaselinePostopEditField = uilabel(app.CUSTOMTab);
            app.BaselinePostopEditField.Text = 'Baseline/Postop Name';
            app.CustomNameforClinicalMeasureEditFieldLabel = uilabel(app.CUSTOMTab);
            app.CustomNameforClinicalMeasureEditFieldLabel.Text = 'Custom Name for Clinical Measure';
            app.CustomNameforClinicalMeasureEditField = uieditfield(app.CUSTOMTab, 'text');
            app.CustomNameforClinicalMeasureEditField.Value = 'Gait';
            app.OnlyCalculateGlobalChangeScoresCheckBox = uicheckbox(app.CUSTOMTab);
            app.OnlyCalculateGlobalChangeScoresCheckBox.Tooltip = {'Check this box to calculate preop-postop improvements. In this case, only the global change scores, i.e., across all variables will be output'};
            app.OnlyCalculateGlobalChangeScoresCheckBox.Text = 'Only Calculate Global Change Scores';
            app.ThresholdscoreEditFieldLabel = uilabel(app.CUSTOMTab);
            app.ThresholdscoreEditFieldLabel.Text = 'Threshold score ≥';
            app.ThresholdscoreEditField = uieditfield(app.CUSTOMTab, 'numeric');
            app.ThresholdscoreEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdscoreEditFieldValueChanged, true);
            app.UPDRSTab = uitab(app.TabGroup);
            app.UPDRSTab.Title = 'UPDRS';
            app.GENERATEButton = uibutton(app.UPDRSTab, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Enable = 'off';
            app.GENERATEButton.Text = 'GENERATE';
            app.PostopColumnStartEditFieldLabel = uilabel(app.UPDRSTab);
            app.PostopColumnStartEditFieldLabel.Text = '';
            app.LateralityButtonGroup = uibuttongroup(app.UPDRSTab);
            app.LateralityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LateralityButtonGroupSelectionChanged, true);
            app.LateralityButtonGroup.Enable = 'off';
            app.LateralityButtonGroup.Title = 'Laterality';
            app.BilateralButton = uiradiobutton(app.LateralityButtonGroup);
            app.BilateralButton.Text = 'Bilateral';
            app.BilateralButton.Value = true;
            app.RightButton = uiradiobutton(app.LateralityButtonGroup);
            app.RightButton.Text = 'Right';
            app.LeftButton = uiradiobutton(app.LateralityButtonGroup);
            app.LeftButton.Text = 'Left';
            app.ImportExcelSheetButton_2 = uibutton(app.UPDRSTab, 'push');
            app.ImportExcelSheetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportExcelSheetButton_2Pushed, true);
            app.ImportExcelSheetButton_2.Text = 'Import Excel Sheet';
            app.ExcludeErrTremorPatientsCheckBox = uicheckbox(app.UPDRSTab);
            app.ExcludeErrTremorPatientsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeErrTremorPatientsCheckBoxValueChanged, true);
            app.ExcludeErrTremorPatientsCheckBox.Enable = 'off';
            app.ExcludeErrTremorPatientsCheckBox.Text = 'Exclude Err. Tremor Patients';
            app.ThresholdTremorScoresatEditFieldLabel = uilabel(app.UPDRSTab);
            app.ThresholdTremorScoresatEditFieldLabel.Enable = 'off';
            app.ThresholdTremorScoresatEditFieldLabel.Text = 'Threshold Tremor Scores at';
            app.ThresholdTremorScoresatEditField = uieditfield(app.UPDRSTab, 'numeric');
            app.ThresholdTremorScoresatEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdTremorScoresatEditFieldValueChanged, true);
            app.ThresholdTremorScoresatEditField.Enable = 'off';
            app.GUIDLabel = uilabel(app.UPDRSTab);
            app.GUIDLabel.Text = 'GUID: ';
            app.PreoperativeBaselineItemsListBoxLabel = uilabel(app.UPDRSTab);
            app.PreoperativeBaselineItemsListBoxLabel.Text = 'Pre-operative / Baseline Items';
            app.PreoperativeBaselineItemsListBox = uilistbox(app.UPDRSTab);
            app.PreoperativeBaselineItemsListBox.Multiselect = 'on';
            app.PreoperativeBaselineItemsListBox.ValueChangedFcn = createCallbackFcn(app, @PreoperativeBaselineItemsListBoxValueChanged, true);
            app.PreoperativeBaselineItemsListBox.Enable = 'off';
            app.PreoperativeBaselineItemsListBox.Value = {'Item 1'};
            app.PostoperativeItemsListBoxLabel = uilabel(app.UPDRSTab);
            app.PostoperativeItemsListBoxLabel.Text = 'Post-operative Items';
            app.PostoperativeItemsListBox = uilistbox(app.UPDRSTab);
            app.PostoperativeItemsListBox.Multiselect = 'on';
            app.PostoperativeItemsListBox.ValueChangedFcn = createCallbackFcn(app, @PostoperativeItemsListBoxValueChanged, true);
            app.PostoperativeItemsListBox.Enable = 'off';
            app.PostoperativeItemsListBox.Value = {'Item 1'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_score_gen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end