classdef ea_networkmappingexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshInformationfromLeadGroupprojectMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        LeadGroupPathLabel              matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        ConnectomeDropDownLabel         matlab.ui.control.Label
        ConnectomeDropDown              matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        ResolutionDropDownLabel         matlab.ui.control.Label
        ResolutionDropDown              matlab.ui.control.DropDown
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        PatientsListBoxLabel            matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        VariableofInterestDropDown      matlab.ui.control.DropDown
        CovariatesListBox               matlab.ui.control.ListBox
        CovariatesCheckBox              matlab.ui.control.CheckBox
        ModelSetupDropDownLabel         matlab.ui.control.Label
        ModelSetupDropDown              matlab.ui.control.DropDown
        Corrtype                        matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        SubcohortsDropDown              matlab.ui.control.DropDown
        BusyLampLabel                   matlab.ui.control.Label
        BusyLamp                        matlab.ui.control.Lamp
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        ColorButtonPositive             matlab.ui.control.Button
        ColorButtonNegative             matlab.ui.control.Button
        ShowPositiveRegionsCheckBox     matlab.ui.control.CheckBox
        ShowNegativeRegionsCheckBox     matlab.ui.control.CheckBox
        ExportasAtlasButton             matlab.ui.control.Button
        ShowAmountSliderLabel           matlab.ui.control.Label
        ShowAmountSliderPositive        matlab.ui.control.Slider
        LeftSliderLabel                 matlab.ui.control.Label
        LeftSliderPositive              matlab.ui.control.Slider
        RightSliderLabel                matlab.ui.control.Label
        RightSliderPositive             matlab.ui.control.Slider
        LeftSlider_2Label               matlab.ui.control.Label
        LeftSliderNegative              matlab.ui.control.Slider
        ShowAmountSlider_2Label         matlab.ui.control.Label
        ShowAmountSliderNegative        matlab.ui.control.Slider
        RightSlider_2Label              matlab.ui.control.Label
        RightSliderNegative             matlab.ui.control.Slider
        ConsiderSignificantVoxelsonlyCheckBox  matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        levelEditFieldLabel             matlab.ui.control.Label
        levelEditField                  matlab.ui.control.NumericEditField
        MultTestStrategy                matlab.ui.control.DropDown
        ColorBar                        matlab.ui.control.UIAxes
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        TrainOnListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        PredictListBox                  matlab.ui.control.ListBox
        RunCVButton                     matlab.ui.control.Button
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        StrategyDropDownLabel           matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        kEditFieldLabel                 matlab.ui.control.Label
        kEditField                      matlab.ui.control.EditField
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        BasePredictiononDropDownLabel   matlab.ui.control.Label
        BasePredictiononDropDown        matlab.ui.control.DropDown
        MaskDropDownLabel               matlab.ui.control.Label
        MaskDropDown                    matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        networkmapping % associated ea_disctract object.
    end
    
    
    methods (Access = private)
        
        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.networkmapping.colorbar)
                if app.networkmapping.splitbygroup
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                else
                    app.ColorBar.Visible = 'on';
                    cb = app.networkmapping.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                end
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end
        
        function results = enablerules(app)
            app.isbusy(1);
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val='off';
            else
                val=ea_bool2onoff(isfield(app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)),ea_method2methodid(app.networkmapping)));
            end
            
            % enable controls:
            app.ModelSetupDropDown.Enable = val;
            app.ModelSetupDropDownLabel.Enable = val;
            
            
            app.MirrorSidesCheckBox.Enable = val;
            app.VariableofInterestDropDown.Enable = val;
            
            
            app.ShowPositiveRegionsCheckBox.Enable = val;
            app.ShowNegativeRegionsCheckBox.Enable = val;
            app.ShowAmountSliderPositive.Enable = val;
            app.LeftSliderPositive.Enable = val;
            app.RightSliderPositive.Enable = val;
            app.ColorButtonPositive.Enable = val;
            app.ShowNegativeRegionsCheckBox.Enable = val;
            
            app.ShowAmountSliderLabel.Enable = val;
            app.LeftSliderLabel.Enable = val;
            app.RightSliderLabel.Enable = val;
            app.VariableofInterestDropDownLabel.Enable = val;
            app.CovariatesCheckBox.Enable = val;
            
                app.ColorBar.Visible = val;
                if strcmp(val, 'off')
                    cla(app.ColorBar);
                end
                app.ExportasAtlasButton.Enable = val;
                cvEnable = val;
            
            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            
            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.networkmapping.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.networkmapping.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.networkmapping.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    app.TrainOnListBox.Items=app.networkmapping.M.patient.list;
                    app.PredictListBox.Items=app.networkmapping.M.patient.list;
                case 'Custom (Cohorts)'
                    if length(app.networkmapping.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.networkmapping.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.networkmapping.M.groups.group));
                    end
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                        app.PredictListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.networkmapping.setlabels;
                    app.PredictListBox.Items=app.networkmapping.setlabels;
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.networkmapping.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                    
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                otherwise
                    cvlEnable='off';
            end
            
            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            
            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;
            
            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson'})
                app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantVoxelsonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                app.ConsiderSignificantVoxelsonlyCheckBox.Enable = val;
                app.levelEditField.Enable = val;
                app.levelEditFieldLabel.Enable = val;
                app.MultTestStrategy.Enable = val;
            end
            
            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson','Bend'})
                app.CovariatesCheckBox.Enable = 'off';
                app.CovariatesCheckBox.Value = 0;
            end
            
            app.BasePredictiononDropDown.Enable = val;
            app.BasePredictiononDropDownLabel.Enable = val;
            
            if app.CovariatesCheckBox.Value
                app.CovariatesListBox.Enable = val;
            else
                app.CovariatesListBox.Enable = 'off';
            end
            
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.EfieldthresholdSlider.Enable = 'off';
                    app.EfieldthresholdLabel.Enable = 'off';
                    app.Efieldmetric.Enable = 'off';
                    app.Corrtype.Enable = 'off';
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores'};
                    if ~ismember(app.networkmapping.basepredictionon,lower(app.BasePredictiononDropDown.Items))
                        app.networkmapping.basepredictionon=lower(app.BasePredictiononDropDown.Value);
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.EfieldthresholdSlider.Enable = val;
                    app.EfieldthresholdLabel.Enable = val;
                    app.Efieldmetric.Enable = val;
                    app.Corrtype.Enable = val;
                    app.BasePredictiononDropDown.Items={'Mean of Scores','Sum of Scores','Peak of Scores','Peak 5% of Scores','Profile of Scores: Spearman','Profile of Scores: Pearson','Profile of Scores: Bend'};
            end
            
            app.PatientsListBox.Enable=val;
            
            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.networkmapping.setlabels];
            
            switch app.ShowNegativeRegionsCheckBox.Value
                case 0
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.ColorButtonNegative.Enable = 'off';
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    
                    app.ShowAmountSlider_2Label.Enable = 'off';
                    app.LeftSlider_2Label.Enable = 'off';
                    app.RightSlider_2Label.Enable = 'off';
                    
                case 1
                    app.ShowAmountSliderNegative.Enable = val;
                    app.ColorButtonNegative.Enable = val;
                    app.LeftSliderNegative.Enable = val;
                    app.RightSliderNegative.Enable = val;
                    
                    app.ShowAmountSlider_2Label.Enable = val;
                    app.LeftSlider_2Label.Enable = val;
                    app.RightSlider_2Label.Enable = val;
            end
            
            switch app.ShowPositiveRegionsCheckBox.Value
                case 0
                    app.ShowAmountSliderPositive.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                    app.LeftSliderPositive.Enable = 'off';
                    app.RightSliderPositive.Enable = 'off';
                    
                    app.ShowAmountSliderLabel.Enable = 'off';
                    app.LeftSliderLabel.Enable = 'off';
                    app.RightSliderLabel.Enable = 'off';
                case 1
                    app.ShowAmountSliderPositive.Enable = val;
                    app.ColorButtonPositive.Enable = val;
                    app.LeftSliderPositive.Enable = val;
                    app.RightSliderPositive.Enable = val;
                    
                    app.ShowAmountSliderLabel.Enable = val;
                    app.LeftSliderLabel.Enable = val;
                    app.RightSliderLabel.Enable = val;
            end


            % turn light back to green
            app.isbusy(0);
        end
        
        
        function results = networkmapping2UI(app)
            app.isbusy(1);
            
            app.ModelSetupDropDown.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';
            
            % derive everything from tractset obj:
            app.LeadGroupPathLabel.Text=app.networkmapping.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.networkmapping.leadgroup;
            app.AnalysisIDEditField.Value = app.networkmapping.ID;
            app.ModelSetupDropDown.Value = app.networkmapping.statmetric;
            
            app.Corrtype.Value=app.networkmapping.corrtype;
            app.ConsiderSignificantVoxelsonlyCheckBox.Value = app.networkmapping.showsignificantonly;
            app.levelEditField.Value = (app.networkmapping.alphalevel);
            app.MultTestStrategy.Value = app.networkmapping.multcompstrategy;
            app.BasePredictiononDropDown.Value = app.networkmapping.basepredictionon;
            if ~isempty(app.networkmapping.connectome) && ismember(app.networkmapping.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.networkmapping.connectome;
            end
            
            app.MirrorSidesCheckBox.Value = app.networkmapping.mirrorsides;
            
            app.PatientsListBox.Items=app.networkmapping.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.networkmapping.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            for set=1:length(app.networkmapping.setlabels)
                if isequal(app.networkmapping.patientselection,find(app.networkmapping.setselections{set}))
                    app.SubcohortsDropDown.Value=app.networkmapping.setlabels{set};
                end
            end
            if isequal(app.networkmapping.patientselection,1:length(app.networkmapping.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end
            
            app.PosthoccorrectforgroupCheckBox.Value = app.networkmapping.adjustforgroups;
            
            app.VariableofInterestDropDown.Items=app.networkmapping.M.clinical.labels;
            app.VariableofInterestDropDown.Value = app.networkmapping.responsevarlabel;
            
            setcovars(app);
            
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            %app.CovariatesListBox.Items(ix)=[];
            
            app.CovariatesListBox.Value=app.networkmapping.covarlabels;
            if ~isempty(app.networkmapping.results)
                app.CovariatesCheckBox.Value=~isempty(app.networkmapping.covarlabels);
            end
            
            
            app.ShowPositiveRegionsCheckBox.Value = app.networkmapping.posvisible;
            try
                app.ShowPositiveRegionsCheckBox.Text = ['Show Positive Regions (',num2str(sum(app.networkmapping.stats.pos.available)),')'];
            end
            
            app.ShowNegativeRegionsCheckBox.Value = app.networkmapping.negvisible;
            try
                app.ShowNegativeRegionsCheckBox.Text = ['Show Negative Regions (',num2str(sum(app.networkmapping.stats.neg.available)),')'];
            end
            
            % Amount sliders:
            app.ShowAmountSliderPositive.Value = mean(app.networkmapping.showposamount);
            try
                app.ShowAmountSliderLabel.Text = {'Show Amount', ['(',num2str(sum(app.networkmapping.stats.pos.shown)),')']};
            end
            
            app.ShowAmountSliderNegative.Value = mean(app.networkmapping.shownegamount);
            try
                app.ShowAmountSlider_2Label.Text = {'Show Amount', ['(',num2str(sum(app.networkmapping.stats.neg.shown)),')']};
            end
            
            app.LeftSliderPositive.Value = app.networkmapping.showposamount(2);
            try
                app.LeftSliderLabel.Text = {'Left', ['(',num2str(app.networkmapping.stats.pos.shown(2)),')']};
            end
            
            app.RightSliderPositive.Value = app.networkmapping.showposamount(1);
            try
                app.RightSliderLabel.Text = {'Right', ['(',num2str(app.networkmapping.stats.pos.shown(1)),')']};
            end
            
            app.LeftSliderNegative.Value = app.networkmapping.shownegamount(2);
            try
                app.LeftSlider_2Label.Text = {'Left', ['(',num2str(app.networkmapping.stats.neg.shown(2)),')']};
            end
            
            app.RightSliderNegative.Value = app.networkmapping.shownegamount(1);
            try
                app.RightSlider_2Label.Text = {'Right', ['(',num2str(app.networkmapping.stats.neg.shown(1)),')']};
            end
            
            app.LiveVisualizeCheckBox.Value = app.networkmapping.cvlivevisualize;
            
            % Color buttons:
            app.ColorButtonPositive.BackgroundColor = app.networkmapping.poscolor;
            app.ColorButtonNegative.BackgroundColor = app.networkmapping.negcolor;
            
            
            % refresh enablerules
            app.enablerules;
            
            app.isbusy(0);
        end
        
        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end
        
        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            if app.AutoRefreshCheckBox.Value || force
                app.isbusy(1);
                drawnow;
                
                app.networkmapping.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.networkmapping2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end
        
        function Vms = getvms(app)
            switch app.networkmapping.efieldmetric
                case 'Mean'
                    Vms='V/mm';
                otherwise
                    Vms='V/m';
            end
        end
        
        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.networkmapping.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.networkmapping.covars = app.networkmapping.M.clinical.vars(is);
                    app.networkmapping.covarlabels = app.networkmapping.M.clinical.labels(is);
                else % none selected
                    app.networkmapping.covars = {};
                    app.networkmapping.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getstimamp},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'Stimulation Amplitude'},app.networkmapping.covarlabels];
                end
                
                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getvtavolumes},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'VTA Volume'},app.networkmapping.covarlabels];
                end
                
                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getefieldmagnitudes},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'E-Field Magnitude Sum'},app.networkmapping.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getcohortregressor},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'Group / Cohort regressor'},app.networkmapping.covarlabels];
                end
            else
                app.networkmapping.covars = {};
                app.networkmapping.covarlabels = {};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Discriminative Fibers Explorer';
            
            % Add modlist (structural connectomes)
            [mdl]=ea_genmodlist;
            app.ConnectomeDropDown.Items=mdl;
            
            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            
            % Add Value to connthreshsliderlabel
            if ~exist('resultfig','var')
                resultfig = gcf;
            end
            
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('LEAD_groupanalysis.mat','Please Choose Lead-Group Analysis project...');
                lgpath = fullfile(PathName,FileName);
            end
            
            letsdraw=0; % flag to state whether to draw directly.
            varinfo=who('-file',lgpath); % check if file is a LEAD_groupanalysis matfile or a stored disctract object with var name 'tractset'
            if ismember('tractset',varinfo)
                D = load(lgpath);
                app.networkmapping = D.tractset; % load stored disctract object
                
                
                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.networkmapping.ID, ID)
                    app.networkmapping.ID = ID;
                end
                
                % Fix group folder in case it has been moved
                tractGroupFolder = strrep(app.networkmapping.leadgroup,'LEAD_groupanalysis.mat','');
                UIGroupFolder = strrep(lgpath,['disctracts', filesep, app.networkmapping.ID, '.mat'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.networkmapping.analysispath = strrep(app.networkmapping.analysispath, tractGroupFolder, UIGroupFolder);
                    app.networkmapping.leadgroup = strrep(app.networkmapping.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.networkmapping.M.root = strrep(app.networkmapping.M.root, tractGroupFolder, UIGroupFolder);
                    app.networkmapping.M.ui.groupdir = strrep(app.networkmapping.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                end
                
                app.networkmapping.resultfig = resultfig; % attach to figure
                if ~isempty(app.networkmapping.results)
                    if isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                        letsdraw=1;
                    end
                end
            else % init from M struct of LEAD group file
                app.networkmapping = ea_networkmapping;
                app.networkmapping.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
            end
            
            if ismember(app.networkmapping.M.ui.connectomename, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.networkmapping.M.ui.connectomename;
            end
            
            
            if letsdraw
                app.draw; % show its results
            else
                networkmapping2UI(app);
            end
            enablerules(app);
            app.isbusy(0);
            
        end

        % Callback function
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            app.networkmapping.splitbygroup = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            switch app.ModelSetupDropDown.Value
                case 'T-Tests / VTAs (Baldermann 2019)'
                    app.networkmapping.statmetric=1;
                    if app.networkmapping.connthreshold > 40
                        app.networkmapping.connthreshold = 20; % reset to a suitable value
                    end
                case 'Correlations / E-fields (Irmen 2020)'
                    app.networkmapping.statmetric=2;
                    % also make sure efieldmetric sliders are properly set:
                    value = app.Efieldmetric.Value;
                    app.networkmapping.efieldmetric=value;
                    switch lower(value)
                        case 'sum'
                            app.EfieldthresholdSlider.Limits=[0.5,5];
                            app.networkmapping.efieldthreshold = 2000; %(/1000) V/mm -> V/m
                        case 'mean'
                            app.EfieldthresholdSlider.Limits=[5,1000]; % in V/mm (!)
                            app.networkmapping.efieldthreshold = 100;
                        case 'peak'
                            app.EfieldthresholdSlider.Limits=[0.05,2.5];
                            app.networkmapping.efieldthreshold = 200; %(/1000) V/mm -> V/m
                        case 'peak 5%'
                            app.EfieldthresholdSlider.Limits=[0.05,2];
                            app.networkmapping.efieldthreshold = 150; %(/1000) V/mm -> V/m
                    end
            end
            networkmapping2UI(app);
            enablerules(app);
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val=0;
            else
                val=(isfield(app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)),ea_method2methodid(app.networkmapping)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Callback function
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.networkmapping.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
        end

        % Value changed function: VariableofInterestDropDown
        function VariableofInterestDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.networkmapping.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.networkmapping.M.clinical.labels);
            app.networkmapping.responsevar = app.networkmapping.M.clinical.vars{ix};
            app.networkmapping.responsevarlabel = app.networkmapping.M.clinical.labels{ix};
            app.draw;
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.networkmapping.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            enablerules(app);
        end

        % Value changed function: ShowPositiveRegionsCheckBox
        function ShowPositiveRegionsCheckBoxValueChanged(app, event)
            value = app.ShowPositiveRegionsCheckBox.Value;
            app.networkmapping.posvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ShowNegativeRegionsCheckBox
        function ShowNegativeRegionsCheckBoxValueChanged(app, event)
            value = app.ShowNegativeRegionsCheckBox.Value;
            app.networkmapping.negvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changing function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.networkmapping.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.LeftSliderPositive.Value = changingValue;
            app.RightSliderPositive.Value = changingValue;
        end

        % Value changing function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.networkmapping.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.LeftSliderNegative.Value = changingValue;
            app.RightSliderNegative.Value = changingValue;
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,value,'.mat'],'file')
                app.AnalysisIDEditField.Value = app.networkmapping.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end
            
            if exist([fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,app.networkmapping.ID,'.mat'],'file')
                movefile([fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,app.networkmapping.ID,'.mat'],...
                    [fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,value,'.mat']);
            end
            
            ht = getappdata(app.networkmapping.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.networkmapping.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,value,'.mat'], app.networkmapping.resultfig});
            end
            
            app.networkmapping.ID=value;
            app.networkmapping.save;
            
        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;
            app.draw;
            app.networkmapping2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Value changed function: LeftSliderPositive
        function LeftSliderPositiveValueChanged(app, event)
            app.networkmapping.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Value changed function: RightSliderPositive
        function RightSliderPositiveValueChanged(app, event)
            app.networkmapping.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Value changed function: LeftSliderNegative
        function LeftSliderNegativeValueChanged(app, event)
            app.networkmapping.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Value changed function: RightSliderNegative
        function RightSliderNegativeValueChanged(app, event)
            app.networkmapping.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.networkmapping.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.networkmapping.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.draw;
        end

        % Callback function
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.networkmapping.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.networkmapping.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            app.networkmapping.connectome = value;
            enablerules(app);
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val=0;
            else
                val=(isfield(app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)),ea_method2methodid(app.networkmapping)));
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.networkmapping.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Value changed function: ShowAmountSliderPositive
        function ShowAmountSliderPositiveValueChanged(app, event)
            value = app.ShowAmountSliderPositive.Value;
            app.draw;
        end

        % Value changed function: ShowAmountSliderNegative
        function ShowAmountSliderNegativeValueChanged(app, event)
            value = app.ShowAmountSliderNegative.Value;
            app.draw;
        end

        % Callback function
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.networkmapping.statmetric
                case 1
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case 2
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Callback function
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.networkmapping.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.networkmapping.customselection = [];
                    [I,Ihat,R0,R1,pperm]=app.networkmapping.lnopb;
                    I = I(:,1);
                    Ihat = Ihat{1};
                    cvs='lnopb';
                    sel=app.networkmapping.patientselection;
                    h1=ea_plothistperm([app.networkmapping.responsevarlabel,' [Permutation-Based Test]'],[R1;R0],{'Unpermuted prediction'},{1});
                    saveas(h1,[fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,app.networkmapping.ID,'_',app.networkmapping.responsevarlabel,'_',cvs,'_permtest.png']);
                case 'Leave-One-Patient-Out'
                    app.networkmapping.customselection = [];
                    [I,Ihat]=app.networkmapping.loocv;
                    cvs='loocv';
                    sel=app.networkmapping.patientselection;
                case 'Leave-One-Cohort-Out'
                    app.networkmapping.customselection = [];
                    [I,Ihat]=app.networkmapping.lococv;
                    cvs='lococv';
                    sel=app.networkmapping.patientselection;
                case 'k-fold (randomized)'
                    app.networkmapping.customselection = [];
                    [I,Ihat]=app.networkmapping.kfoldcv;
                    cvs='kfoldcv';
                    sel=app.networkmapping.patientselection;
                case 'Custom (Patients)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Custom (Cohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.networkmapping.M.patient.group, str2double(app.TrainOnListBox.Value)))';
                    test = find(ismember(app.networkmapping.M.patient.group,str2double(app.PredictListBox.Value)))';
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_cohs';
                    sel = test;
                case 'Custom (Subcohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    tsets = find(ismember(app.networkmapping.setlabels, app.TrainOnListBox.Value));
                    training=[];
                    for ts=1:length(tsets)
                        training = [training,find(app.networkmapping.setselections{tsets(ts)})];
                    end
                    training=unique(training);
                    tsets = find(ismember(app.networkmapping.setlabels, app.PredictListBox.Value));
                    test=[];
                    for ts=1:length(tsets)
                        test = [test,find(app.networkmapping.setselections{tsets(ts)})];
                    end
                    test=unique(test);
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_subcohs';
                    sel = test;
                case 'Custom (Sets)'
                    cvp.NumTestSets = 1;
                    % Generate cvpartition
                    rng(app.networkmapping.rngseed);
                    cvpKfold = cvpartition(length(app.networkmapping.M.patient.list), 'KFold', str2double(app.kEditField.Value));
                    % Calculate training and test indices based on the sets selected and the cvpartitions generated
                    trainSetInd = str2double(app.TrainOnListBox.Value');
                    testSetInd = str2double(app.PredictListBox.Value');
                    training = zeros(size(app.networkmapping.M.patient.list));
                    test = zeros(size(app.networkmapping.M.patient.list));
                    for i=1:length(trainSetInd)
                        training = training | cvpKfold.test(trainSetInd(i));
                    end
                    for i=1:length(testSetInd)
                        test = test | cvpKfold.test(testSetInd(i));
                    end
                    training = find(training);
                    test = find(test);
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_sets';
                    sel = test;
            end
            % check if groups exist:
            if length(unique(app.networkmapping.M.patient.group))>1 && app.PosthoccorrectforgroupCheckBox.Value
                Ihat=ea_resid(ea_cohortregressor(app.networkmapping.M.patient.group(sel)),Ihat);
                suffix=' [Corrected for Cohort]';
            else
                suffix='';
            end
            
            groupID = app.networkmapping.M.patient.group(sel);
            groupColors = app.networkmapping.M.groups.color(unique(groupID),:);
            groupColors = rgb2hsv(groupColors);
            groupColors(:,2) = groupColors(:,2)+((1-groupColors(:,2))/2);
            groupColors=hsv2rgb(groupColors);
            if exist('pperm', 'var')
                h=ea_corrplot(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],'Empirical',['Fiber-Score',suffix]},'permutation_spearman',groupID,[],pperm,groupColors);
            else
                h=ea_corrplot(I,(Ihat),{['Disc. Fiber prediction ',upper(cvs)],'Empirical',['Fiber-Score',suffix]},'permutation_spearman',groupID,[],[],groupColors);
            end
            saveas(h,[fileparts(app.networkmapping.leadgroup),filesep,'disctracts',filesep,app.networkmapping.ID,'_',app.networkmapping.responsevarlabel,'_',cvs,'.png']);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            app.enablerules;
            app.networkmapping2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isbusy(1,'saving');
            app.networkmapping.save;
            %             for tract=1:numel(app.networkmapping.drawobject)
            %                 delete(app.networkmapping.drawobject{tract});
            %             end
            app.isbusy(0);
            delete(app)
        end

        % Callback function
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.networkmapping.efieldmetric=value;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5];
                    app.networkmapping.efieldthreshold = 2000; %(/1000) V/mm -> V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[5,1000]; % in V/mm (!)
                    app.networkmapping.efieldthreshold = 100;
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,2.5];
                    app.networkmapping.efieldthreshold = 200; %(/1000) V/mm -> V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,2];
                    app.networkmapping.efieldthreshold = 150; %(/1000) V/mm -> V/m
            end
            
            app.enablerules;
            app.networkmapping2UI;
            
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val=0;
            else
                val=(isfield(app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)),ea_method2methodid(app.networkmapping)));
            end
            
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.networkmapping.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.networkmapping.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.networkmapping.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:app.networkmapping.Nsets]');
                        app.PredictListBox.Items=sprintfc('%d',[1:app.networkmapping.Nsets]');
                    end
            end
        end

        % Value changed function: CovariatesCheckBox
        function CovariatesCheckBoxValueChanged(app, event)
            value = app.CovariatesCheckBox.Value;
            app.enablerules;
            if ~value
                app.networkmapping.covars = {};
                app.networkmapping.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end
            
        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.networkmapping.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshInformationfromLeadGroupprojectMenu
        function RefreshInformationfromLeadGroupprojectMenuSelected(app, event)
            app.networkmapping.refreshlg;
            app.networkmapping2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: 
        % ConsiderSignificantVoxelsonlyCheckBox
        function ConsiderSignificantVoxelsonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantVoxelsonlyCheckBox.Value;
            app.networkmapping.showsignificantonly=value;
            app.draw;
        end

        % Button pushed function: ExportasAtlasButton
        function ExportasAtlasButtonPushed(app, event)
            disctract = app.networkmapping.fiberdrawn;
            disctract.info.Connectome = app.networkmapping.connectome;
            if app.ShowPositiveRegionsCheckBox.Value
                disctract.info.PosAmount = sum(app.networkmapping.stats.pos.shown);
                disctract.info.PosAmountRight = sum(app.networkmapping.stats.pos.shown(1));
                disctract.info.PosAmountLeft = sum(app.networkmapping.stats.pos.shown(2));
                disctract.info.PosPercent = app.ShowAmountSliderPositive.Value;
                disctract.info.PosPercentRight = app.RightSliderPositive.Value;
                disctract.info.PosPercentLeft = app.LeftSliderPositive.Value;
            end
            if app.ShowNegativeRegionsCheckBox.Value
                disctract.info.NegAmount = sum(app.networkmapping.stats.neg.shown);
                disctract.info.NegAmountRight = sum(app.networkmapping.stats.neg.shown(1));
                disctract.info.NegAmountLeft = sum(app.networkmapping.stats.neg.shown(2));
                disctract.info.NegPercent = app.ShowAmountSliderNegative.Value;
                disctract.info.NegPercentRight = app.RightSliderNegative.Value;
                disctract.info.NegPercentLeft = app.LeftSliderNegative.Value;
            end
            atlasname = app.networkmapping.ID;
            tractname = 'Tract';
            negativecolor = app.networkmapping.negcolor;
            positivecolor = app.networkmapping.poscolor;
            ea_discfibers_exportatlas(disctract, atlasname, tractname, negativecolor, positivecolor);
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.networkmapping2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.networkmapping.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.networkmapping.multcompstrategy=value;
            app.draw;
        end

        % Value changed function: Corrtype
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.networkmapping.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.networkmapping.showsignificantonly=0;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.networkmapping.cvlivevisualize=value;
        end

        % Value changed function: BasePredictiononDropDown
        function BasePredictiononDropDownValueChanged(app, event)
            value = app.BasePredictiononDropDown.Value;
            app.networkmapping.basepredictionon=lower(value);
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.networkmapping.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.networkmapping.setlabels{end+1}=setname;
                    app.networkmapping.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.networkmapping.setlabels{end+1}=setname;
                    app.networkmapping.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.networkmapping.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.networkmapping.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.enablerules;
        end

        % Menu selected function: 
        % SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.networkmapping.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.networkmapping.rngseed = str2double(rngseed{1});
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed2(app, event)
            app.networkmapping.leadgroup=app.LeadGroupPathLabel.Text;
            
            %% set up object properties:
            app.networkmapping.posvisible = app.ShowPositiveRegionsCheckBox.Value;
            app.networkmapping.negvisible = app.ShowNegativeRegionsCheckBox.Value;
            app.networkmapping.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.networkmapping.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.networkmapping.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.networkmapping.negcolor = app.ColorButtonNegative.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;
            
            [~, ix]=ismember(app.VariableofInterestDropDown.Value,app.networkmapping.M.clinical.labels);
            app.networkmapping.responsevar = app.networkmapping.M.clinical.vars{ix};
            app.networkmapping.responsevarlabel = app.networkmapping.M.clinical.labels{ix};
            
            setcovars(app);
            
            app.networkmapping.statmetric=app.ModelSetupDropDown.Value;
            
            app.networkmapping.connectome = app.ConnectomeDropDown.Value;
            app.networkmapping.mirrorsides = app.MirrorSidesCheckBox.Value;
            
            % calculate general setup
            app.networkmapping.calculate;
            
            % save dataset:
            app.networkmapping.save;
            
            enablerules(app);
            
            % draw results
            app.draw;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshInformationfromLeadGroupprojectMenu = uimenu(app.ToolsMenu);
            app.RefreshInformationfromLeadGroupprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshInformationfromLeadGroupprojectMenuSelected, true);
            app.RefreshInformationfromLeadGroupprojectMenu.Text = 'Refresh Information from Lead-Group project';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_voxel_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResolutionDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ResolutionDropDown.Items = {'2 mm', '1 mm', '0.5 mm'};
            app.ResolutionDropDown.Value = '2 mm';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestDropDownValueChanged, true);
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Value = {'Item 1'};
            app.CovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesCheckBoxValueChanged, true);
            app.CovariatesCheckBox.Text = 'Covariates';
            app.ModelSetupDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDown.Items = {'Correlations (R-map)', 'Weighted Average (A-map)', 'Combined (C-map)'};
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Value = 'Correlations (R-map)';
            app.Corrtype = uidropdown(app.InteractiveModelSetupPanel);
            app.Corrtype.Items = {'Spearman', 'Pearson', 'Bend', 'Skipped Spearman', 'Skipped Pearson'};
            app.Corrtype.ValueChangedFcn = createCallbackFcn(app, @CorrtypeValueChanged, true);
            app.Corrtype.Value = 'Spearman';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Value = 'Choose...';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.ColorButtonPositive = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Text = 'Color';
            app.ColorButtonNegative = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveRegionsCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowPositiveRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveRegionsCheckBoxValueChanged, true);
            app.ShowPositiveRegionsCheckBox.Text = 'Show Positive Regions';
            app.ShowPositiveRegionsCheckBox.Value = true;
            app.ShowNegativeRegionsCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowNegativeRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeRegionsCheckBoxValueChanged, true);
            app.ShowNegativeRegionsCheckBox.Text = 'Show Negative Regions';
            app.ExportasAtlasButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportasAtlasButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasAtlasButtonPushed, true);
            app.ExportasAtlasButton.Text = 'Export as Atlas';
            app.ShowAmountSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanged, true);
            app.ShowAmountSliderPositive.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderPositiveValueChanging, true);
            app.ShowAmountSliderPositive.MinorTicks = [];
            app.ShowAmountSliderPositive.Value = 25;
            app.LeftSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.LeftSliderPositive.ValueChangedFcn = createCallbackFcn(app, @LeftSliderPositiveValueChanged, true);
            app.LeftSliderPositive.MinorTicks = [];
            app.LeftSliderPositive.Value = 25;
            app.RightSliderLabel = uilabel(app.VisualizationThresholdsPanel);
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.VisualizationThresholdsPanel);
            app.RightSliderPositive.ValueChangedFcn = createCallbackFcn(app, @RightSliderPositiveValueChanged, true);
            app.RightSliderPositive.MinorTicks = [];
            app.RightSliderPositive.Value = 25;
            app.LeftSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.LeftSliderNegative.ValueChangedFcn = createCallbackFcn(app, @LeftSliderNegativeValueChanged, true);
            app.LeftSliderNegative.MinorTicks = [];
            app.LeftSliderNegative.Value = 25;
            app.ShowAmountSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.ShowAmountSliderNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanged, true);
            app.ShowAmountSliderNegative.ValueChangingFcn = createCallbackFcn(app, @ShowAmountSliderNegativeValueChanging, true);
            app.ShowAmountSliderNegative.MinorTicks = [];
            app.ShowAmountSliderNegative.Value = 25;
            app.RightSlider_2Label = uilabel(app.VisualizationThresholdsPanel);
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.VisualizationThresholdsPanel);
            app.RightSliderNegative.ValueChangedFcn = createCallbackFcn(app, @RightSliderNegativeValueChanged, true);
            app.RightSliderNegative.MinorTicks = [];
            app.RightSliderNegative.Value = 25;
            app.ConsiderSignificantVoxelsonlyCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ConsiderSignificantVoxelsonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantVoxelsonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantVoxelsonlyCheckBox.Text = 'Consider Significant Voxels only';
            app.RefreshViewButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.AutoRefreshCheckBox.Value = true;
            app.levelEditFieldLabel = uilabel(app.VisualizationThresholdsPanel);
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.VisualizationThresholdsPanel, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Value = 0.05;
            app.MultTestStrategy = uidropdown(app.VisualizationThresholdsPanel);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Value = 'FDR';
            app.ColorBar = uiaxes(app.VisualizationThresholdsPanel);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BasePredictiononDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDownLabel.Text = 'Base Prediction on ';
            app.BasePredictiononDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BasePredictiononDropDown.Items = {'Spearman Correlations', 'Pearson Correlations'};
            app.BasePredictiononDropDown.ValueChangedFcn = createCallbackFcn(app, @BasePredictiononDropDownValueChanged, true);
            app.BasePredictiononDropDown.Value = 'Spearman Correlations';
            app.MaskDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.MaskDropDown.Items = {'Gray Matter', 'Brain', 'Cortex & Cerebellum'};
            app.MaskDropDown.Value = 'Gray Matter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_networkmappingexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end