classdef ea_stimparams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        stimfig              matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        RightHemisphereTab   matlab.ui.container.Tab
        k16polR              matlab.ui.control.Image
        k15polR              matlab.ui.control.Image
        k14polR              matlab.ui.control.Image
        k13polR              matlab.ui.control.Image
        k12polR              matlab.ui.control.Image
        k11polR              matlab.ui.control.Image
        k10polR              matlab.ui.control.Image
        k9polR               matlab.ui.control.Image
        k8polR               matlab.ui.control.Image
        k7polR               matlab.ui.control.Image
        k6polR               matlab.ui.control.Image
        k5polR               matlab.ui.control.Image
        k4polR               matlab.ui.control.Image
        k3polR               matlab.ui.control.Image
        k2polR               matlab.ui.control.Image
        k1polR               matlab.ui.control.Image
        CpolR                matlab.ui.control.Image
        k16txtR              matlab.ui.control.Label
        k15txtR              matlab.ui.control.Label
        k14txtR              matlab.ui.control.Label
        k13txtR              matlab.ui.control.Label
        k12txtR              matlab.ui.control.Label
        k11txtR              matlab.ui.control.Label
        k10txtR              matlab.ui.control.Label
        k9txtR               matlab.ui.control.Label
        k16uR                matlab.ui.control.EditField
        k15uR                matlab.ui.control.EditField
        k14uR                matlab.ui.control.EditField
        k13uR                matlab.ui.control.EditField
        k12uR                matlab.ui.control.EditField
        k11uR                matlab.ui.control.EditField
        k10uR                matlab.ui.control.EditField
        k9uR                 matlab.ui.control.EditField
        k16imR               matlab.ui.control.EditField
        k15imR               matlab.ui.control.EditField
        k14imR               matlab.ui.control.EditField
        k13imR               matlab.ui.control.EditField
        k12imR               matlab.ui.control.EditField
        k11imR               matlab.ui.control.EditField
        k10imR               matlab.ui.control.EditField
        k9imR                matlab.ui.control.EditField
        kohmtext4R           matlab.ui.control.Label
        perctext4R           matlab.ui.control.Label
        kohmtext3R           matlab.ui.control.Label
        perctext3R           matlab.ui.control.Label
        text36               matlab.ui.control.Label
        Rs1am                matlab.ui.control.EditField
        Rs2am                matlab.ui.control.EditField
        Rs3am                matlab.ui.control.EditField
        Rs4am                matlab.ui.control.EditField
        Rs1va                matlab.ui.control.DropDown
        Rs2va                matlab.ui.control.DropDown
        Rs3va                matlab.ui.control.DropDown
        Rs4va                matlab.ui.control.DropDown
        pulseWidthTextbox_R  matlab.ui.control.EditField
        CuR                  matlab.ui.control.EditField
        usLabel_R            matlab.ui.control.Label
        pulseWidthLabel_R    matlab.ui.control.Label
        k8txtR               matlab.ui.control.Label
        k7txtR               matlab.ui.control.Label
        k6txtR               matlab.ui.control.Label
        k5txtR               matlab.ui.control.Label
        k4txtR               matlab.ui.control.Label
        k3txtR               matlab.ui.control.Label
        k2txtR               matlab.ui.control.Label
        k1txtR               matlab.ui.control.Label
        k8uR                 matlab.ui.control.EditField
        k7uR                 matlab.ui.control.EditField
        k6uR                 matlab.ui.control.EditField
        k5uR                 matlab.ui.control.EditField
        k4uR                 matlab.ui.control.EditField
        k3uR                 matlab.ui.control.EditField
        k2uR                 matlab.ui.control.EditField
        k1uR                 matlab.ui.control.EditField
        k8imR                matlab.ui.control.EditField
        k7imR                matlab.ui.control.EditField
        k6imR                matlab.ui.control.EditField
        k5imR                matlab.ui.control.EditField
        k4imR                matlab.ui.control.EditField
        k3imR                matlab.ui.control.EditField
        k2imR                matlab.ui.control.EditField
        k1imR                matlab.ui.control.EditField
        kohmtext2R           matlab.ui.control.Label
        perctext2R           matlab.ui.control.Label
        kohmtext1R           matlab.ui.control.Label
        perctext1R           matlab.ui.control.Label
        casetxtR             matlab.ui.control.Label
        LeftHemisphereTab    matlab.ui.container.Tab
        k16polL              matlab.ui.control.Image
        k15polL              matlab.ui.control.Image
        k14polL              matlab.ui.control.Image
        k13polL              matlab.ui.control.Image
        k12polL              matlab.ui.control.Image
        k11polL              matlab.ui.control.Image
        k10polL              matlab.ui.control.Image
        k9polL               matlab.ui.control.Image
        k8polL               matlab.ui.control.Image
        k7polL               matlab.ui.control.Image
        k6polL               matlab.ui.control.Image
        k5polL               matlab.ui.control.Image
        k4polL               matlab.ui.control.Image
        k3polL               matlab.ui.control.Image
        k2polL               matlab.ui.control.Image
        k1polL               matlab.ui.control.Image
        CpolL                matlab.ui.control.Image
        k16txtL              matlab.ui.control.Label
        k15txtL              matlab.ui.control.Label
        k14txtL              matlab.ui.control.Label
        k13txtL              matlab.ui.control.Label
        k12txtL              matlab.ui.control.Label
        k11txtL              matlab.ui.control.Label
        k10txtL              matlab.ui.control.Label
        k9txtL               matlab.ui.control.Label
        k16uL                matlab.ui.control.EditField
        k15uL                matlab.ui.control.EditField
        k14uL                matlab.ui.control.EditField
        k13uL                matlab.ui.control.EditField
        k12uL                matlab.ui.control.EditField
        k11uL                matlab.ui.control.EditField
        k10uL                matlab.ui.control.EditField
        k9uL                 matlab.ui.control.EditField
        k16imL               matlab.ui.control.EditField
        k15imL               matlab.ui.control.EditField
        k14imL               matlab.ui.control.EditField
        k13imL               matlab.ui.control.EditField
        k12imL               matlab.ui.control.EditField
        k11imL               matlab.ui.control.EditField
        k10imL               matlab.ui.control.EditField
        k9imL                matlab.ui.control.EditField
        kohmtext4L           matlab.ui.control.Label
        perctext4L           matlab.ui.control.Label
        kohmtext3L           matlab.ui.control.Label
        perctext3L           matlab.ui.control.Label
        text50               matlab.ui.control.Label
        Ls1am                matlab.ui.control.EditField
        Ls2am                matlab.ui.control.EditField
        Ls3am                matlab.ui.control.EditField
        Ls4am                matlab.ui.control.EditField
        Ls1va                matlab.ui.control.DropDown
        Ls2va                matlab.ui.control.DropDown
        Ls3va                matlab.ui.control.DropDown
        Ls4va                matlab.ui.control.DropDown
        pulseWidthTextbox_L  matlab.ui.control.EditField
        CuL                  matlab.ui.control.EditField
        casetxtL             matlab.ui.control.Label
        usLabel_L            matlab.ui.control.Label
        k8txtL               matlab.ui.control.Label
        k7txtL               matlab.ui.control.Label
        k6txtL               matlab.ui.control.Label
        k5txtL               matlab.ui.control.Label
        k4txtL               matlab.ui.control.Label
        k3txtL               matlab.ui.control.Label
        k2txtL               matlab.ui.control.Label
        k1txtL               matlab.ui.control.Label
        k8uL                 matlab.ui.control.EditField
        k7uL                 matlab.ui.control.EditField
        k6uL                 matlab.ui.control.EditField
        k5uL                 matlab.ui.control.EditField
        k4uL                 matlab.ui.control.EditField
        k3uL                 matlab.ui.control.EditField
        k2uL                 matlab.ui.control.EditField
        k1uL                 matlab.ui.control.EditField
        k8imL                matlab.ui.control.EditField
        k7imL                matlab.ui.control.EditField
        k6imL                matlab.ui.control.EditField
        k5imL                matlab.ui.control.EditField
        k4imL                matlab.ui.control.EditField
        k3imL                matlab.ui.control.EditField
        k2imL                matlab.ui.control.EditField
        k1imL                matlab.ui.control.EditField
        kohmtext2L           matlab.ui.control.Label
        perctext2L           matlab.ui.control.Label
        kohmtext1L           matlab.ui.control.Label
        perctext1L           matlab.ui.control.Label
        pulseWidthLabel_L    matlab.ui.control.Label
        addStimSet           matlab.ui.control.CheckBox
        estimateInTemplate   matlab.ui.control.CheckBox
        settings             matlab.ui.control.Button
        betawarning          matlab.ui.control.Label
        saveparams           matlab.ui.control.Button
        nextpt               matlab.ui.control.Button
        prevpt               matlab.ui.control.Button
        stimlabel            matlab.ui.control.DropDown
        stimlab              matlab.ui.control.Label
        text13               matlab.ui.control.Label
        stimulate            matlab.ui.control.Button
        modelselect          matlab.ui.control.DropDown
        headertxt            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ea_stimparams_OpeningFcn(app, varargin)
            % --- Executes just before ea_stimparams is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.stimfig, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app);
            
            % Change name
            set(handles.stimfig,'Name','Stimulation Parameters');
            
            % store input arguments in figure to make it available to subroutines.
            elstruct = varargin{1};
            resultfig = varargin{2};
            options = varargin{3};
            
            setappdata(handles.stimfig,'elstruct',elstruct);
            
            if options.native
                set(handles.estimateInTemplate,'Visible','off');
            end
            
            set(handles.estimateInTemplate,'Value',ea_getprefs('vatsettings.estimateInTemplate'));
            
            if strcmp(options.leadprod, 'group')
                groupmode=1;
                M=getappdata(resultfig,'M');
                try % priorly loaded stim params.
                    gS=M.S;
                    setappdata(handles.stimfig,'gS',gS);
                    gSv.vatmodel=M.vatmodel;
                    setappdata(handles.stimfig,'gSv',gSv);
                end
                set(handles.prevpt,'visible','on');
                set(handles.nextpt,'visible','on');
                set(handles.saveparams,'visible','on');
            
                set(handles.stimlabel,'visible','off');
                set(handles.stimlab,'visible','off');
                set(handles.stimulate,'visible','off');
                if length(elstruct) == 1
                    set(handles.prevpt,'enable','off');
                    set(handles.nextpt,'enable','off');
                end
            
                % UI adaption
                set(handles.stimfig, 'Position', handles.stimfig.Position - [0 0 0 80]);
                set(handles.headertxt, 'Position', handles.headertxt.Position - [0 18 0 0]);
            else
                groupmode=0;
            
                set(handles.headertxt, 'String', ['Patient: ', elstruct(1).name]);
            
                set(handles.prevpt,'visible','off');
                set(handles.nextpt,'visible','off');
                set(handles.saveparams,'visible','off');
            
                set(handles.stimlabel,'visible','on');
                set(handles.stimlab,'visible','on');
                set(handles.stimulate,'visible','on');
            end
            
            setappdata(handles.stimfig,'groupmode',groupmode);
            setappdata(handles.stimfig,'actpt',1);
            
            setappdata(handles.stimfig,'elstruct',elstruct);
            setappdata(handles.stimfig,'resultfig',resultfig);
            setappdata(handles.stimfig,'options',options);
            
            stimparams=getappdata(resultfig,'stimparams'); % get info from resultfig.
            if isempty(stimparams)
                stimparams = struct();
            end
            setappdata(handles.stimfig,'stimparams',stimparams); % store stimulation settings from resultfig to stim (this) fig for subroutines.
            
            % setup modelselect popup
            if strcmp(options.leadprod, 'group')
                isdirected=0; % for now allow everything in lead group
            else
                e=load(fullfile(ea_getearoot,'templates','electrode_models',options.elspec.matfname));
                if isfield(e.electrode,'isdirected')
                    isdirected=e.electrode.isdirected;
                else
                    isdirected=0;
                end
            end
            
            % Hide OSS-DBS option in case non-dev env or elmodel not available
            if strcmp(options.leadprod, 'dbs')
                funcs = ea_regexpdir(ea_getearoot, 'ea_genvat_.*\.m$', 0);
                funcs = regexp(funcs, '(ea_genvat_.*)(?=\.m)', 'match', 'once');
                [names, supportDirected, supportNativeSpaceEstimation] = cellfun(@(x) eval([x, '(''prompt'');']), funcs, 'Uni', 0);
                if isdirected
                    funcs = funcs(cell2mat(supportDirected));
                    names = names(cell2mat(supportDirected));
                end
                if options.native
                    funcs = funcs(cell2mat(supportNativeSpaceEstimation));
                    names = names(cell2mat(supportNativeSpaceEstimation));
                end
                if ~ismember(options.elmodel,ea_ossdbs_elmodel)
                    ossdbsInd = find(contains(names,'OSS-DBS'));
                    funcs(ossdbsInd) = [];
                    names(ossdbsInd) = [];
                end
            else % Call in lead 'group'
                funcs = getappdata(resultfig, 'genvatfunctions');
                names = getappdata(resultfig, 'vatfunctionnames');
            end
            
            setappdata(gcf, 'genvatfunctions', funcs);
            app.modelselect.Items = names;
            handles.modelselect.Value = find(contains(names, app.modelselect.Value));

            pos=get(handles.stimfig,'position');
            set(handles.stimfig,'position',[51,51,pos(3),pos(4)]);
            
            ea_refreshguisp(handles,options);
            status = getappdata(handles.stimfig, 'Status');
            if ~isempty(status) && strcmp(status, 'Cancelled')
                delete(handles.stimfig);
                return;
            end
            
            if ~strcmp(options.leadprod, 'group')
                label =handles.stimlabel.String{handles.stimlabel.Value};
                label(strfind(label, ' ')) = '';
                stimDir = fullfile(options.subj.stimDir, ea_nt(options), label);
                filePrefix = ['sub-', options.subj.subjId, '_sim-'];
                visualizeVAT = 1;
            
                stimParams = ea_regexpdir(stimDir, 'stimparameters\.mat$', 0);
                S = ea_loadstimulation(stimParams{1});
                modelLabel = ea_simModel2Label(S.model);
            
                if visualizeVAT
                    if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = vatvolume;
                        if exist('vatgrad','var')
                            vatgradtemp(1) = vatgrad;
                        end
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = vatvolume;
                        if exist('vatgrad','var')
                            vatgradtemp(2) = vatgrad;
                            vatgrad = vatgradtemp;
                        end
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat'])
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = vatvolume;
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                        load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                        %For consistency, left is always on 2nd element of stimparams
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = vatvolume;
                    else
                        if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            stimparams(1,1).VAT.VAT = vatfv;
                            stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            stimparams(1,2).VAT.VAT = vatfv;
                            stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii'])
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            stimparams(1,1).VAT.VAT = vatfv;
                            stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                            nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                            vatfv = ea_niiVAT2fvVAT(nii);
                            %For consistency, left is always on 2nd element of stimparams
                            stimparams(1,2).VAT.VAT = vatfv;
                            stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        else
                            visualizeVAT = 0;
                        end
                    end
            
                    if isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat']) ...
                            && isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                    elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'])
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                    elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                    end
            
                    if visualizeVAT
                        setappdata(handles.stimfig,'stimparams',stimparams);
                        resultfig = getappdata(handles.stimfig,'resultfig');
                        PL=getappdata(resultfig,'PL');
                        for group=1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
                        if exist('vatgrad')
                            setappdata(resultfig,'vatgrad',vatgrad);
                        end
                        setappdata(resultfig,'stimparams',stimparams(1,:));
                        S=ea_loadstimulation(options,label);
                        setappdata(resultfig,'curS',S(1))
                        options.writeoutstats = 1;
                        ea_calc_vatstats(resultfig,options);
                    end
                end
            end
            
            % Choose default command line output for ea_stimparams
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: CuL
        function CuL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Ls',num2str(S.active(2))]).case.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: Ls1am
        function Ls1am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=1;
            S.Ls1.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls2am
        function Ls2am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=2;
            S.Ls2.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls3am
        function Ls3am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=3;
            S.Ls3.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls4am
        function Ls4am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=4;
            S.Ls4.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: CuR
        function CuR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).case.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: Rs1am
        function Rs1am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=1;
            S.Rs1.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs2am
        function Rs2am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=2;
            S.Rs2.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs3am
        function Rs3am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=3;
            S.Rs3.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs4am
        function Rs4am_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=4;
            S.Rs4.amp=str2double(get(hObject,'String'));
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: addStimSet
        function addStimSet_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            if hObject.Value
                options = getappdata(handles.stimfig, 'options');
                stimLabel = getappdata(handles.stimfig, 'stimlabel');
            
                if strcmp(options.leadprod, 'dbs')
                    patdir = [options.root, options.patientname];
                    numContacts = options.elspec.numContacts;
                else % Special case when call from Lead Group
                    actpt = getappdata(handles.stimfig, 'actpt');
                    resultfig = getappdata(handles.stimfig, 'resultfig');
                    M = getappdata(resultfig, 'M');
                    patdir = M.patient.list{actpt};
                    numContacts = size(M.elstruct(actpt).coords_mm{1}, 1);
                end
            
                stimFolder = fullfile(patdir ,'stimulations', ea_nt(~handles.estimateInTemplate.Value), stimLabel);
                ea_mkdir(stimFolder);
                ea_addStimSet(numContacts, stimFolder, hObject);
            
                % Set stimSetMode flag for Lead Group
                if hObject.Value && strcmp(options.leadprod, 'group')
                    M.ui.stimSetMode = 1;
                    setappdata(resultfig, 'M');
                end
            end
        end

        % Value changed function: k1imR
        function k1imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k1.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k1uR
        function k1uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k1.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k3imL
        function k3imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k3.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k3uL
        function k3uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k3.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k4imL
        function k4imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k4.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k4uL
        function k4uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k4.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k5imL
        function k5imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k5.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k5uL
        function k5uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k5.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k6imL
        function k6imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k6.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k6uL
        function k6uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k6.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k7imL
        function k7imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k7.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k7uL
        function k7uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k7.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k8imL
        function k8imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k8.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k8uL
        function k8uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k8.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k2imR
        function k2imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k2.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k2uR
        function k2uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k2.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k3imR
        function k3imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k3.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k3uR
        function k3uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k3.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k4imR
        function k4imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k4.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k4uR
        function k4uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k4.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k5imR
        function k5imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k5.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k5uR
        function k5uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k5.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k6imR
        function k6imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k6.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k6uR
        function k6uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k6.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k7imR
        function k7imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k7.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k7uR
        function k7uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k7.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k8imR
        function k8imR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k8.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k8uR
        function k8uR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k8.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k1imL
        function k1imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k1.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k1uL
        function k1uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k1.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k2imL
        function k2imL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k2.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k2uL
        function k2uL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k2.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Button pushed function: nextpt
        function nextpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            S=getappdata(handles.stimfig,'S');
            gS=getappdata(handles.stimfig,'gS');
            
            gSv=getappdata(handles.stimfig,'gSv');
            
            actpt=getappdata(handles.stimfig,'actpt');
            elstruct=getappdata(handles.stimfig,'elstruct');
            options=getappdata(handles.stimfig,'options');
            
            %ensure active patient is non empty
            if isempty(actpt)
                actpt=1;
            end
            
            if isempty(gS)
                clear gS
            end
            
            S=ea_activecontacts(S);
            try
                gS(actpt)=S;
            catch
                S.sources=1:4;
                S.volume=[0,0];
                gS(actpt)=S;
            end
            setappdata(handles.stimfig,'gS',gS);
            
            if (actpt+1)>length(elstruct)
                setto=1;
            else
                setto=actpt+1;
            end
            try
                setappdata(handles.stimfig,'S',gS(setto));
            catch % S for this next pt not defined yet.
                setappdata(handles.stimfig,'S',[]);
            end
            setappdata(handles.stimfig,'actpt',setto);
            
            ea_refreshguisp(handles,options);
        end

        % Button pushed function: prevpt
        function prevpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            gS=getappdata(handles.stimfig,'gS');
            actpt=getappdata(handles.stimfig,'actpt');
            elstruct=getappdata(handles.stimfig,'elstruct');
            options=getappdata(handles.stimfig,'options');
            
            %ensure active patient is non empty
            if isempty(actpt)
                actpt=1;
            end
            
            if isempty(gS)
                clear gS
            end
            
            S=ea_activecontacts(S);
            gS(actpt)=S;
            setappdata(handles.stimfig,'gS',gS);
            
            if (actpt-1)<1
                setto=length(elstruct);
            else
                setto=actpt-1;
            end
            
            try
                setappdata(handles.stimfig,'S',gS(setto));
            catch
                setappdata(handles.stimfig,'S',[]);
            end
            setappdata(handles.stimfig,'actpt',setto);
            
            ea_refreshguisp(handles,options);
        end

        % Value changed function: pulseWidthTextbox_L
        function pulseWidthTextbox_L_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Ls',num2str(S.active(2))]).pulseWidth = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: pulseWidthTextbox_R
        function pulseWidthTextbox_R_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).pulseWidth = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Button pushed function: saveparams
        function saveparams_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S = getappdata(handles.stimfig, 'S');
            gS = getappdata(handles.stimfig, 'gS');
            actpt = getappdata(handles.stimfig, 'actpt');
            elstruct = getappdata(handles.stimfig, 'elstruct');
            options = getappdata(handles.stimfig, 'options');
            
            if isempty(gS)
                clear gS
            end
            
            S = ea_activecontacts(S);
            
            try
                gS(actpt)=S;
            catch
                S.sources=1:4;
                S.volume=[0,0];
                gS(actpt)=S;
            end
            setappdata(handles.stimfig, 'gS', gS);
            
            gSv = getappdata(handles.stimfig, 'gSv');
            lgfig = getappdata(handles.stimfig, 'resultfig');
            
            setappdata(lgfig, 'S', gS);
            setappdata(lgfig, 'vatmodel', gSv.vatmodel);
            setstimbutton = lgfig.findobj('Tag','setstimparamsbutton');
            if ~isempty(gS)
                set(setstimbutton,'BackgroundColor',[0.1;0.8;0.1]);
            else
                set(setstimbutton,'BackgroundColor',[0.93,0.93,0.93]);
            end
            
            ea_setprefs('vatsettings.estimateInTemplate',get(handles.estimateInTemplate,'Value'));
            
            close(handles.stimfig);
        end

        % Button pushed function: settings
        function settings_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            models=get(handles.modelselect,'String');
            try
                model=models{get(handles.modelselect,'Value')};
            catch
                set(handles.modelselect,'Value',1);
                model=models{1};
            end
            
            switch model
                case 'SimBio/FieldTrip (see Horn 2017)'
                    ea_vatsettings_horn;
                case 'Dembek 2017'
                    ea_vatsettings_dembek;
                case 'Fastfield (Baniasadi 2020)'
                    ea_vatsettings_fastfield;
                case 'OSS-DBS (Butenko 2020)'
                    ea_vatsettings_butenko(handles);
            end
        end

        % Close request function: stimfig
        function stimfig_CloseRequestFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            ea_setprefs('vatsettings.estimateInTemplate',get(handles.estimateInTemplate,'Value'));
            delete(hObject);
        end

        % Button pushed function: stimulate
        function stimulate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            ea_busyaction('on',handles.stimfig,'stim');
            elstruct = getappdata(handles.stimfig,'elstruct');
            resultfig = getappdata(handles.stimfig,'resultfig');
            options = getappdata(handles.stimfig,'options');
            % refresh prefs:
            options.prefs = ea_prefs;
            setappdata(resultfig,'options',options);
            setappdata(handles.stimfig,'options',options);
            S = getappdata(handles.stimfig,'S');
            S = ea_activecontacts(S);
            
            options = getappdata(resultfig,'options'); % selected atlas could have refreshed.
            options.orignative = options.native;
            
            if handles.estimateInTemplate.Visible % only allowed for specific VTA functions when elvis opened in template space.
                options.native = ~handles.estimateInTemplate.Value;
            end
            
            if ~isfield(options.subj, 'norm') && options.native
                ea_cprintf('CmdWinWarnings', 'Calculating VTA in template space since patient folder %s is incomplete.\n', options.subj.subjId);
                options.native = 0;
            end
            
            ea_savestimulation(S,options);
            setappdata(handles.stimfig,'S',S);
            
            if isfield(elstruct,'group') % group analysis, more than one electrode set
                % this should not happen, in this case the stim button is
                % hidden.
                keyboard
            end
            
            % assign correct .m-file to function.
            genvatfunctions = getappdata(handles.stimfig,'genvatfunctions');
            ea_genvat = eval(['@',genvatfunctions{handles.modelselect.Value}]);
            stimname = S.label;
            
            for el = 1:length(elstruct)
                % Load stim coordinates
                if options.native % Reload native space coordinates
                    coords = ea_load_reconstruction(options);
                else
                    coords = elstruct(el).coords_mm;
                end
            
                if strcmp(S.model, 'OSS-DBS (Butenko 2020)') % For OSS-DBS, side iteration is within the genvat function
                    % Set stimSetMode flag to options
                    % (avoid additional parameter or setting appdata, to make it scriptable)
                    if handles.addStimSet.Value
                        options.stimSetMode = 1;
                    else
                        options.stimSetMode = 0;
                    end
                    if options.prefs.machine.vatsettings.butenko_calcPAM
                        feval(ea_genvat,getappdata(handles.stimfig,'S'),options,handles.stimfig);
                        ea_busyaction('off',handles.stimfig,'stim');
                        return;
                    else
                        [~, stimparams] = feval(ea_genvat,getappdata(handles.stimfig,'S'),options,handles.stimfig);
                        flix = 1;
                    end
                else
                    stimparams = struct();
                    for iside = 1:length(options.sides)
                        side = options.sides(iside);
                        [vatfv, vatvolume] = feval(ea_genvat,coords,getappdata(handles.stimfig,'S'),side,options,stimname,handles.stimfig);
                        stimparams(1,side).VAT(el).VAT = vatfv;
                        stimparams(1,side).volume = vatvolume;
                        flix = 1;
                    end
                end
            end
            
            options.native = options.orignative;
            PL = getappdata(resultfig, 'PL');
            for group = 1:length(PL)
                ea_deletePL(PL(group));
            end
            clear PL
            
            for group = flix
                setappdata(resultfig,'stimparams',stimparams(group,:));
                setappdata(resultfig,'curS',S(group));
            
                if ~exist('hmchanged','var')
                    hmchanged = 1;
                end
                ea_calc_vatstats(resultfig,options,hmchanged);
            
                try % TODO: fix dir
                    copyfile([options.root,options.patientname,filesep,'ea_pm.nii'],[options.root,options.patientname,filesep,'ea_pm_group_',num2str(group),'.nii']);
                end
            
                try
                    PL(group) = getappdata(resultfig,'PL');
                catch
                    keyboard
                end
            end
            setappdata(resultfig,'PL',PL);
            
            ea_busyaction('off',handles.stimfig,'stim');
        end

        % Window key release function: stimfig
        function stimfigWindowKeyRelease(app, event)
            if ~isempty(event.Source.CurrentObject)
                key = event.Key;
                sourceTag = event.Source.CurrentObject.Tag;
                if key == "return" && ismember(sourceTag, {'Rs1am','Rs2am','Rs3am','Rs4am','Ls1am','Ls2am','Ls3am','Ls4am'})
                    [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
                    S=getappdata(handles.stimfig,'S');
                    options=getappdata(handles.stimfig,'options');
                    if sourceTag(1) == "R"
                        S.active(1)=str2double(sourceTag(3));
                    elseif sourceTag(1) == "L"
                        S.active(2)=str2double(sourceTag(3));
                    end
                    S.(sourceTag(1:3)).amp=str2double(event.Source.CurrentObject.Value);
                    
                    setappdata(handles.stimfig,'S',S);
                    ea_refreshguisp(handles,options);
                end
            end
        end

        % Window button up function: stimfig
        function stimfigWindowButtonUp(app, event)
            if ~isempty(event.Source.CurrentObject)
                sourceTag = event.Source.CurrentObject.Tag;
                if event.Source.SelectionType == "normal" && ismember(sourceTag, {'Rs1am','Rs2am','Rs3am','Rs4am','Ls1am','Ls2am','Ls3am','Ls4am'})
                    [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
                    S=getappdata(handles.stimfig,'S');
                    options=getappdata(handles.stimfig,'options');
                    if sourceTag(1) == "R"
                        S.active(1)=str2double(sourceTag(3));
                    elseif sourceTag(1) == "L"
                        S.active(2)=str2double(sourceTag(3));
                    end
                    S.(sourceTag(1:3)).amp=str2double(event.Source.CurrentObject.Value);
                    
                    setappdata(handles.stimfig,'S',S);
                    ea_refreshguisp(handles,options);
                end
            end
        end

        % Value changed function: k9uR
        function k9uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k9.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k10uR
        function k10uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k10.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k11uR
        function k11uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k11.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k12uR
        function k12uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k12.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k13uR
        function k13uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k13.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k14uR
        function k14uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k14.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k15uR
        function k15uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k15.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k16uR
        function k16uRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Rs',num2str(S.active(1))]).k16.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k9imR
        function k9imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k9.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k10imR
        function k10imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k10.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k11imR
        function k11imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k11.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k12imR
        function k12imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k12.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k13imR
        function k13imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k13.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k14imR
        function k14imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k14.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k15imR
        function k15imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k15.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k16imR
        function k16imRValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');

            S.(['Rs',num2str(S.active(1))]).k16.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k9uL
        function k9uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k9.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k10uL
        function k10uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k10.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k11uL
        function k11uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k11.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k12uL
        function k12uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k12.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k13uL
        function k13uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k13.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k14uL
        function k14uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k14.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k15uL
        function k15uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k15.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k16uL
        function k16uLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k16.perc = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options,hObject);
        end

        % Value changed function: k9imL
        function k9imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k9.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k10imL
        function k10imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k10.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k11imL
        function k11imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k11.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k12imL
        function k12imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k12.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k13imL
        function k13imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k13.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k14imL
        function k14imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k14.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k15imL
        function k15imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k15.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: k16imL
        function k16imLValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            
            S.(['Ls',num2str(S.active(2))]).k16.imp = str2double(hObject.String);
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: modelselect
        function modelselectValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
            
            % Set model
            S = getappdata(handles.stimfig, 'S');
            S.model = app.modelselect.Value;
            setappdata(handles.stimfig, 'S', S);
            
            % Handle special case for call from LeadGroup
            groupmode=getappdata(handles.stimfig,'groupmode');

            if groupmode
                gSv=getappdata(handles.stimfig,'gSv');

                if (strcmp(gSv.vatmodel, 'SimBio/FieldTrip (see Horn 2017)') && strcmp(S.model, 'OSS-DBS (Butenko 2020)')) || ...
                   (strcmp(gSv.vatmodel, 'OSS-DBS (Butenko 2020)') && strcmp(S.model, 'SimBio/FieldTrip (see Horn 2017)'))
                    % Keep setting when switch between OSS-DBS and SimBio
                    gS = getappdata(handles.stimfig,'gS');
                    for i = 1:length(gS)
                        gS(i).model = S.model;
                    end
                    setappdata(handles.stimfig, 'gS', gS);
                    gSv.vatmodel = S.model;
                    setappdata(handles.stimfig, 'gSv', gSv);
                else
                    choice = uiconfirm(app.stimfig, "Changing VAT model will delete stimulation parameters of all patients! Continue?", ...
                        "Warning", "Options", ["Yes, sure","No"], "DefaultOption", 2, "Icon", "warning");
                
                    switch choice
                        case 'No'
                            % Keep current model
                            app.modelselect.Value = gSv.vatmodel;
                            return
                        case 'Yes, sure'
                            setappdata(handles.stimfig, 'gS', []);
                
                            % Set new model
                            gSv.vatmodel = S.model;
                            setappdata(handles.stimfig, 'gSv', gSv);
                
                            % Clear stimulation parameters
                            setappdata(handles.stimfig, 'S', []);
                    end
                end
            end
            
            % Refresh GUI
            options = getappdata(handles.stimfig,'options');
            ea_refreshguisp(handles,options);
            
            % Save stimulation parameters
            if ~groupmode
                S = getappdata(handles.stimfig,'S');
                ea_savestimulation(S,options);
            end
        end

        % Value changed function: stimlabel
        function stimlabelValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S = getappdata(handles.stimfig,'S');
            options = getappdata(handles.stimfig,'options');
            sel = get(handles.stimlabel,'String');
            sel = sel{get(handles.stimlabel,'Value')};
            if startsWith(sel,' => ') % command, not entry
                switch sel(5:end)
                    case 'New stimulation'
                        resultfig = getappdata(handles.stimfig,'resultfig');
            
                        PL = getappdata(resultfig, 'PL');
                        for group = 1:length(PL)
                            ea_deletePL(PL(group));
                        end
                        clear PL
            
                        ea_savestimulation(S,options);
                        S = []; % this will create the prompt to generate a new S.
                        options.gen_newstim = 1;
                        setappdata(handles.stimfig,'options',options);
                        setappdata(handles.stimfig,'S',S);
                        ea_refreshguisp(handles,options);
                        S = getappdata(handles.stimfig,'S');
                        ea_savestimulation(S,options);
                        options.gen_newstim = 0; % reset new stim flag
                        setappdata(handles.stimfig,'options',options);
                    case 'Rename stimulation'
                        label = getappdata(handles.stimfig,'stimlabel');
            
                        [~,ix] = ismember(label,get(handles.stimlabel,'String'));
                        set(handles.stimlabel,'Value',ix);
                        stimc = inputdlg('Please enter a label for this stimulation','Stimulation Label',1,{label});
                        figure(app.stimfig);
                        if isempty(stimc) || strcmp(label, stimc{1})
                            return;
                        end
                        if isfolder([options.subj.stimDir,filesep,ea_nt(0),label])
                            movefile([options.subj.stimDir,filesep,ea_nt(0),label],[options.subj.stimDir,filesep,ea_nt(0),stimc{1}]);
                        end
                        if isfolder([options.subj.stimDir,filesep,ea_nt(1),label])
                            movefile([options.subj.stimDir,filesep,ea_nt(1),label],[options.subj.stimDir,filesep,ea_nt(1),stimc{1}]);
                        end
                        slabelc = get(handles.stimlabel,'String');
                        slabelc{ix} = stimc{1};
                        set(handles.stimlabel,'String',slabelc);
                        S.label = stimc{1};
                        setappdata(handles.stimfig,'S',S);
                        setappdata(handles.stimfig,'stimlabel',S.label);
                        ea_refreshguisp(handles,options);
                        ea_savestimulation(S,options);
                    case 'Delete stimulation'
                        answ = uiconfirm(app.stimfig, ...
                            ['Are you sure you wish to delete the stimulation parameter: <b>', S.label, '</b>?'], ...
                            'Delete stimulation parameter', ...
                            'Options', {'Sure', 'No'}, 'DefaultOption', 2, ...
                            'Interpreter', 'html');
                        if strcmp(answ,'No')
                            app.stimlabel.Value = S.label;
                        else % truly delete Stimulation parameters
                            resultfig = getappdata(handles.stimfig,'resultfig');
                            PL = getappdata(resultfig, 'PL');
                            for group = 1:length(PL)
                                ea_deletePL(PL(group));
                            end
                            clear PL
                            ea_delete([options.subj.stimDir,filesep,ea_nt(0),S.label]);
                            ea_delete([options.subj.stimDir,filesep,ea_nt(1),S.label]);
                            S = []; % this will create the prompt to generate a new S.
                            setappdata(handles.stimfig,'S',S);
                            set(handles.stimlabel,'Value',1);
                            setappdata(handles.stimfig,'stimlabel','');
                            options.gen_newstim = 0;
                            setappdata(handles.stimfig,'S',S);
                            ea_refreshguisp(handles,options);
                            status = getappdata(handles.stimfig, 'Status');
                            if ~isempty(status) && strcmp(status, 'Cancelled')
                                delete(handles.stimfig);
                                return;
                            end
                            S = getappdata(handles.stimfig,'S');
                            ea_savestimulation(S,options);
                        end
                end
            else
                label = handles.stimlabel.String{handles.stimlabel.Value};
                label(strfind(label,' ')) = '';
                S = ea_loadstimulation(options,label);
                S.label = label;
                setappdata(handles.stimfig,'S',S);
                setappdata(handles.stimfig,'stimlabel',S.label);
                setappdata(handles.stimfig,'S',S);
                ea_refreshguisp(handles,options);
            
                %% stuff by Till for visualizing VATs by selecting them from the stimlabel list
                % tries to load .mat-files which are now created by ea_genvat_horn.m
                % and contain the VAT as well as the quiver. In case no .mat-files are
                % available the vat_xxx.nii is loaded and visualized
            
                stimDir = fullfile(options.subj.stimDir, ea_nt(options), label);
                filePrefix = ['sub-', options.subj.subjId, '_sim-'];
            
                stimParams = ea_regexpdir(stimDir, 'stimparameters\.mat$', 0);
                S = ea_loadstimulation(stimParams{1});
                modelLabel = ea_simModel2Label(S.model);
            
                visualizeVAT = 1;
                stimparams = struct();
                if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                    stimparams(1,1).VAT.VAT = vatfv;
                    stimparams(1,1).volume = vatvolume;
                    if exist('vatgrad','var')
                        vatgradtemp(1) = vatgrad;
                    end
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                    stimparams(1,2).VAT.VAT = vatfv;
                    stimparams(1,2).volume = vatvolume;
                    if exist('vatgrad','var')
                        vatgradtemp(2) = vatgrad;
                        vatgrad = vatgradtemp;
                    end
                elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat'])
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.mat']);
                    stimparams(1,1).VAT.VAT = vatfv;
                    stimparams(1,1).volume = vatvolume;
                elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat'])
                    load([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.mat']);
                    stimparams(1,2).VAT.VAT = vatfv;
                    stimparams(1,2).volume = vatvolume;
                else
                    if isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']) && isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii'])
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-R.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,1).VAT.VAT = vatfv;
                        stimparams(1,1).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                    elseif isfile([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii'])
                        nii = ea_load_nii([stimDir, filesep, filePrefix, 'binary_model-', modelLabel, '_hemi-L.nii']);
                        vatfv = ea_niiVAT2fvVAT(nii);
                        stimparams(1,2).VAT.VAT = vatfv;
                        stimparams(1,2).volume = sum(nii.img(:))*nii.voxsize(1)*nii.voxsize(2)*nii.voxsize(3);
                    else
                        visualizeVAT = 0;
                    end
                end
            
                visualizeFiberActivation = 1;
                if isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat']) ...
                        && isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'])
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-R.mat'], resultfig);
                elseif isfile([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'])
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    ea_fiberactivation_viz([stimDir, filesep, filePrefix, 'fiberactivation_model-', modelLabel, '_hemi-L.mat'], resultfig);
                else
                    visualizeFiberActivation = 0;
                end
            
                if visualizeVAT
                    setappdata(handles.stimfig,'stimparams',stimparams);
                    resultfig = getappdata(handles.stimfig,'resultfig');
                    PL=getappdata(resultfig,'PL');
                    for group=1:length(PL)
                        ea_deletePL(PL(group));
                    end
                    clear PL
                    if exist('vatgrad')
                        setappdata(resultfig,'vatgrad',vatgrad);
                    end
                    setappdata(resultfig,'stimparams',stimparams(1,:));
                    setappdata(resultfig,'curS',S(1))
                    options.writeoutstats = 1;
                    ea_calc_vatstats(resultfig,options);
                end
            
                if ~visualizeVAT && ~visualizeFiberActivation
                    fprintf('\n');
                    warning('off', 'backtrace');
                    warning('Nothing to be visualized, please rerun stimulation!!');
                    warning('on', 'backtrace');
                    fprintf('\n');
                end
            end
        end

        % Value changed function: Rs1va
        function Rs1vaValueChanged(app, event)
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=1;
            S.Rs1.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs2va
        function Rs2vaValueChanged(app, event)
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=2;
            S.Rs2.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs3va
        function Rs3vaValueChanged(app, event)
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=3;
            S.Rs3.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Rs4va
        function Rs4vaValueChanged(app, event)
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(1)=4;
            S.Rs4.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls1va
        function Ls1vaValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=1;
            S.Ls1.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls2va
        function Ls2vaValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=2;
            S.Ls2.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls3va
        function Ls3vaValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=3;
            S.Ls3.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end

        % Value changed function: Ls4va
        function Ls4vaValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);

            S=getappdata(handles.stimfig,'S');
            options=getappdata(handles.stimfig,'options');
            S.active(2)=4;
            S.Ls4.va=get(hObject,'Value');
            
            setappdata(handles.stimfig,'S',S);
            ea_refreshguisp(handles,options);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.stimfig = uifigure('Visible', 'off');
            colormap(app.stimfig, 'parula');
            app.stimfig.Name = 'ea_stimparams';
            app.stimfig.CloseRequestFcn = createCallbackFcn(app, @stimfig_CloseRequestFcn, true);
            app.stimfig.WindowButtonUpFcn = createCallbackFcn(app, @stimfigWindowButtonUp, true);
            app.stimfig.WindowKeyReleaseFcn = createCallbackFcn(app, @stimfigWindowKeyRelease, true);
            app.stimfig.Tag = 'stimfig';
            app.headertxt = uilabel(app.stimfig);
            app.headertxt.Tag = 'headertxt';
            app.headertxt.Text = 'Patient:';
            app.modelselect = uidropdown(app.stimfig);
            app.modelselect.Items = {'Maedler 2012', 'Kuncel 2008', 'SimBio/FieldTrip (see Horn 2017)'};
            app.modelselect.ValueChangedFcn = createCallbackFcn(app, @modelselectValueChanged, true);
            app.modelselect.Tag = 'modelselect';
            app.modelselect.Value = 'SimBio/FieldTrip (see Horn 2017)';
            app.stimulate = uibutton(app.stimfig, 'push');
            app.stimulate.ButtonPushedFcn = createCallbackFcn(app, @stimulate_Callback, true);
            app.stimulate.Tag = 'stimulate';
            app.stimulate.Text = 'Visualize';
            app.text13 = uilabel(app.stimfig);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Model:';
            app.stimlab = uilabel(app.stimfig);
            app.stimlab.Tag = 'stimlab';
            app.stimlab.Text = 'Stimulation:';
            app.stimlabel = uidropdown(app.stimfig);
            app.stimlabel.Items = {};
            app.stimlabel.ValueChangedFcn = createCallbackFcn(app, @stimlabelValueChanged, true);
            app.stimlabel.Tag = 'stimlabel';
            app.stimlabel.Value = {};
            app.prevpt = uibutton(app.stimfig, 'push');
            app.prevpt.ButtonPushedFcn = createCallbackFcn(app, @prevpt_Callback, true);
            app.prevpt.Tag = 'prevpt';
            app.prevpt.Text = {'Previous'; 'patient'};
            app.nextpt = uibutton(app.stimfig, 'push');
            app.nextpt.ButtonPushedFcn = createCallbackFcn(app, @nextpt_Callback, true);
            app.nextpt.Tag = 'nextpt';
            app.nextpt.Text = {'Next'; 'patient'};
            app.saveparams = uibutton(app.stimfig, 'push');
            app.saveparams.ButtonPushedFcn = createCallbackFcn(app, @saveparams_Callback, true);
            app.saveparams.Tag = 'saveparams';
            app.saveparams.Text = 'Save';
            app.betawarning = uilabel(app.stimfig);
            app.betawarning.Tag = 'betawarning';
            app.betawarning.Text = 'Beta';
            app.settings = uibutton(app.stimfig, 'push');
            app.settings.ButtonPushedFcn = createCallbackFcn(app, @settings_Callback, true);
            app.settings.Tag = 'settings';
            app.settings.Text = 'Settings';
            app.estimateInTemplate = uicheckbox(app.stimfig);
            app.estimateInTemplate.Tag = 'estimateInTemplate';
            app.estimateInTemplate.Tooltip = 'You may choose to directly solve the VTA in template space which may potentially be less accurate';
            app.estimateInTemplate.Text = 'Estimate in template space';
            app.addStimSet = uicheckbox(app.stimfig);
            app.addStimSet.ValueChangedFcn = createCallbackFcn(app, @addStimSet_Callback, true);
            app.addStimSet.Tag = 'addStimSet';
            app.addStimSet.Tooltip = 'Add a set of current stimulation amplitudes in Current_protocols_[0|1].csv';
            app.addStimSet.Text = 'Add StimSet';
            app.TabGroup = uitabgroup(app.stimfig);
            app.RightHemisphereTab = uitab(app.TabGroup);
            app.RightHemisphereTab.Title = 'Right Hemisphere';
            app.casetxtR = uilabel(app.RightHemisphereTab);
            app.casetxtR.Tag = 'caseRtxt';
            app.casetxtR.Text = 'Case';
            app.perctext1R = uilabel(app.RightHemisphereTab);
            app.perctext1R.Tag = 'perctext1R';
            app.perctext1R.Text = '%';
            app.kohmtext1R = uilabel(app.RightHemisphereTab);
            app.kohmtext1R.Tag = 'kohmtext1R';
            app.kohmtext1R.Text = 'kΩ';
            app.perctext2R = uilabel(app.RightHemisphereTab);
            app.perctext2R.Tag = 'perctext2R';
            app.perctext2R.Text = '%';
            app.kohmtext2R = uilabel(app.RightHemisphereTab);
            app.kohmtext2R.Tag = 'kohmtext2R';
            app.kohmtext2R.Text = 'kΩ';
            app.k1imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k1imR.ValueChangedFcn = createCallbackFcn(app, @k1imR_Callback, true);
            app.k1imR.Tag = 'k1imR';
            app.k1imR.Value = '1';
            app.k2imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k2imR.ValueChangedFcn = createCallbackFcn(app, @k2imR_Callback, true);
            app.k2imR.Tag = 'k2imR';
            app.k2imR.Value = '1';
            app.k3imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k3imR.ValueChangedFcn = createCallbackFcn(app, @k3imR_Callback, true);
            app.k3imR.Tag = 'k3imR';
            app.k3imR.Value = '1';
            app.k4imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k4imR.ValueChangedFcn = createCallbackFcn(app, @k4imR_Callback, true);
            app.k4imR.Tag = 'k4imR';
            app.k4imR.Value = '1';
            app.k5imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k5imR.ValueChangedFcn = createCallbackFcn(app, @k5imR_Callback, true);
            app.k5imR.Tag = 'k5imR';
            app.k5imR.Value = '1';
            app.k6imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k6imR.ValueChangedFcn = createCallbackFcn(app, @k6imR_Callback, true);
            app.k6imR.Tag = 'k6imR';
            app.k6imR.Value = '1';
            app.k7imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k7imR.ValueChangedFcn = createCallbackFcn(app, @k7imR_Callback, true);
            app.k7imR.Tag = 'k7imR';
            app.k7imR.Value = '1';
            app.k8imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k8imR.ValueChangedFcn = createCallbackFcn(app, @k8imR_Callback, true);
            app.k8imR.Tag = 'k8imR';
            app.k8imR.Value = '1';
            app.k1uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k1uR.ValueChangedFcn = createCallbackFcn(app, @k1uR_Callback, true);
            app.k1uR.Tag = 'k1uR';
            app.k1uR.Value = '0';
            app.k2uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k2uR.ValueChangedFcn = createCallbackFcn(app, @k2uR_Callback, true);
            app.k2uR.Tag = 'k2uR';
            app.k2uR.Value = '0';
            app.k3uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k3uR.ValueChangedFcn = createCallbackFcn(app, @k3uR_Callback, true);
            app.k3uR.Tag = 'k3uR';
            app.k3uR.Value = '0';
            app.k4uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k4uR.ValueChangedFcn = createCallbackFcn(app, @k4uR_Callback, true);
            app.k4uR.Tag = 'k4uR';
            app.k4uR.Value = '0';
            app.k5uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k5uR.ValueChangedFcn = createCallbackFcn(app, @k5uR_Callback, true);
            app.k5uR.Tag = 'k5uR';
            app.k5uR.Value = '0';
            app.k6uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k6uR.ValueChangedFcn = createCallbackFcn(app, @k6uR_Callback, true);
            app.k6uR.Tag = 'k6uR';
            app.k6uR.Value = '0';
            app.k7uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k7uR.ValueChangedFcn = createCallbackFcn(app, @k7uR_Callback, true);
            app.k7uR.Tag = 'k7uR';
            app.k7uR.Value = '0';
            app.k8uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k8uR.ValueChangedFcn = createCallbackFcn(app, @k8uR_Callback, true);
            app.k8uR.Tag = 'k8uR';
            app.k8uR.Value = '0';
            app.k1txtR = uilabel(app.RightHemisphereTab);
            app.k1txtR.Tag = 'k1txtR';
            app.k1txtR.Text = 'K1';
            app.k2txtR = uilabel(app.RightHemisphereTab);
            app.k2txtR.Tag = 'k2txtR';
            app.k2txtR.Text = 'K2';
            app.k3txtR = uilabel(app.RightHemisphereTab);
            app.k3txtR.Tag = 'k3txtR';
            app.k3txtR.Text = 'K3';
            app.k4txtR = uilabel(app.RightHemisphereTab);
            app.k4txtR.Tag = 'k4txtR';
            app.k4txtR.Text = 'K4';
            app.k5txtR = uilabel(app.RightHemisphereTab);
            app.k5txtR.Tag = 'k5txtR';
            app.k5txtR.Text = 'K5';
            app.k6txtR = uilabel(app.RightHemisphereTab);
            app.k6txtR.Tag = 'k6txtR';
            app.k6txtR.Text = 'K6';
            app.k7txtR = uilabel(app.RightHemisphereTab);
            app.k7txtR.Tag = 'k7txtR';
            app.k7txtR.Text = 'K7';
            app.k8txtR = uilabel(app.RightHemisphereTab);
            app.k8txtR.Tag = 'k8txtR';
            app.k8txtR.Text = 'K8';
            app.pulseWidthLabel_R = uilabel(app.RightHemisphereTab);
            app.pulseWidthLabel_R.Tag = 'pulseWidthLabel_R';
            app.pulseWidthLabel_R.Visible = 'off';
            app.pulseWidthLabel_R.Text = 'Pulse width';
            app.usLabel_R = uilabel(app.RightHemisphereTab);
            app.usLabel_R.Tag = 'usLabel_R';
            app.usLabel_R.Visible = 'off';
            app.usLabel_R.Text = 'μs';
            app.CuR = uieditfield(app.RightHemisphereTab, 'text');
            app.CuR.ValueChangedFcn = createCallbackFcn(app, @CuR_Callback, true);
            app.CuR.Tag = 'CuR';
            app.CuR.Value = '100';
            app.pulseWidthTextbox_R = uieditfield(app.RightHemisphereTab, 'text');
            app.pulseWidthTextbox_R.ValueChangedFcn = createCallbackFcn(app, @pulseWidthTextbox_R_Callback, true);
            app.pulseWidthTextbox_R.Tag = 'pulseWidthTextbox_R';
            app.pulseWidthTextbox_R.Visible = 'off';
            app.pulseWidthTextbox_R.Value = '60';
            app.Rs4va = uidropdown(app.RightHemisphereTab);
            app.Rs4va.Items = {'V', 'mA'};
            app.Rs4va.ValueChangedFcn = createCallbackFcn(app, @Rs4vaValueChanged, true);
            app.Rs4va.Tag = 'Rs4va';
            app.Rs4va.Value = 'V';
            app.Rs3va = uidropdown(app.RightHemisphereTab);
            app.Rs3va.Items = {'V', 'mA'};
            app.Rs3va.ValueChangedFcn = createCallbackFcn(app, @Rs3vaValueChanged, true);
            app.Rs3va.Tag = 'Rs3va';
            app.Rs3va.Value = 'V';
            app.Rs2va = uidropdown(app.RightHemisphereTab);
            app.Rs2va.Items = {'V', 'mA'};
            app.Rs2va.ValueChangedFcn = createCallbackFcn(app, @Rs2vaValueChanged, true);
            app.Rs2va.Tag = 'Rs2va';
            app.Rs2va.Value = 'V';
            app.Rs1va = uidropdown(app.RightHemisphereTab);
            app.Rs1va.Items = {'V', 'mA'};
            app.Rs1va.ValueChangedFcn = createCallbackFcn(app, @Rs1vaValueChanged, true);
            app.Rs1va.Tag = 'Rs1va';
            app.Rs1va.Value = 'V';
            app.Rs4am = uieditfield(app.RightHemisphereTab, 'text');
            app.Rs4am.ValueChangedFcn = createCallbackFcn(app, @Rs4am_Callback, true);
            app.Rs4am.Tag = 'Rs4am';
            app.Rs4am.Value = '0';
            app.Rs3am = uieditfield(app.RightHemisphereTab, 'text');
            app.Rs3am.ValueChangedFcn = createCallbackFcn(app, @Rs3am_Callback, true);
            app.Rs3am.Tag = 'Rs3am';
            app.Rs3am.Value = '0';
            app.Rs2am = uieditfield(app.RightHemisphereTab, 'text');
            app.Rs2am.ValueChangedFcn = createCallbackFcn(app, @Rs2am_Callback, true);
            app.Rs2am.Tag = 'Rs2am';
            app.Rs2am.Value = '0';
            app.Rs1am = uieditfield(app.RightHemisphereTab, 'text');
            app.Rs1am.ValueChangedFcn = createCallbackFcn(app, @Rs1am_Callback, true);
            app.Rs1am.Tag = 'Rs1am';
            app.Rs1am.Value = '0';
            app.text36 = uilabel(app.RightHemisphereTab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Sources';
            app.perctext3R = uilabel(app.RightHemisphereTab);
            app.perctext3R.Tag = 'perctext3R';
            app.perctext3R.Text = '%';
            app.kohmtext3R = uilabel(app.RightHemisphereTab);
            app.kohmtext3R.Tag = 'kohmtext3R';
            app.kohmtext3R.Text = 'kΩ';
            app.perctext4R = uilabel(app.RightHemisphereTab);
            app.perctext4R.Tag = 'perctext4R';
            app.perctext4R.Text = '%';
            app.kohmtext4R = uilabel(app.RightHemisphereTab);
            app.kohmtext4R.Tag = 'kohmtext4R';
            app.kohmtext4R.Text = 'kΩ';
            app.k9imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k9imR.ValueChangedFcn = createCallbackFcn(app, @k9imRValueChanged, true);
            app.k9imR.Tag = 'k9imR';
            app.k9imR.Value = '1';
            app.k10imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k10imR.ValueChangedFcn = createCallbackFcn(app, @k10imRValueChanged, true);
            app.k10imR.Tag = 'k10imR';
            app.k10imR.Value = '1';
            app.k11imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k11imR.ValueChangedFcn = createCallbackFcn(app, @k11imRValueChanged, true);
            app.k11imR.Tag = 'k11imR';
            app.k11imR.Value = '1';
            app.k12imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k12imR.ValueChangedFcn = createCallbackFcn(app, @k12imRValueChanged, true);
            app.k12imR.Tag = 'k12imR';
            app.k12imR.Value = '1';
            app.k13imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k13imR.ValueChangedFcn = createCallbackFcn(app, @k13imRValueChanged, true);
            app.k13imR.Tag = 'k13imR';
            app.k13imR.Value = '1';
            app.k14imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k14imR.ValueChangedFcn = createCallbackFcn(app, @k14imRValueChanged, true);
            app.k14imR.Tag = 'k14imR';
            app.k14imR.Value = '1';
            app.k15imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k15imR.ValueChangedFcn = createCallbackFcn(app, @k15imRValueChanged, true);
            app.k15imR.Tag = 'k15imR';
            app.k15imR.Value = '1';
            app.k16imR = uieditfield(app.RightHemisphereTab, 'text');
            app.k16imR.ValueChangedFcn = createCallbackFcn(app, @k16imRValueChanged, true);
            app.k16imR.Tag = 'k16imR';
            app.k16imR.Value = '1';
            app.k9uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k9uR.ValueChangedFcn = createCallbackFcn(app, @k9uRValueChanged, true);
            app.k9uR.Tag = 'k9uR';
            app.k9uR.Value = '0';
            app.k10uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k10uR.ValueChangedFcn = createCallbackFcn(app, @k10uRValueChanged, true);
            app.k10uR.Tag = 'k10uR';
            app.k10uR.Value = '0';
            app.k11uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k11uR.ValueChangedFcn = createCallbackFcn(app, @k11uRValueChanged, true);
            app.k11uR.Tag = 'k11uR';
            app.k11uR.Value = '0';
            app.k12uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k12uR.ValueChangedFcn = createCallbackFcn(app, @k12uRValueChanged, true);
            app.k12uR.Tag = 'k12uR';
            app.k12uR.Value = '0';
            app.k13uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k13uR.ValueChangedFcn = createCallbackFcn(app, @k13uRValueChanged, true);
            app.k13uR.Tag = 'k13uR';
            app.k13uR.Value = '0';
            app.k14uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k14uR.ValueChangedFcn = createCallbackFcn(app, @k14uRValueChanged, true);
            app.k14uR.Tag = 'k14uR';
            app.k14uR.Value = '0';
            app.k15uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k15uR.ValueChangedFcn = createCallbackFcn(app, @k15uRValueChanged, true);
            app.k15uR.Tag = 'k15uR';
            app.k15uR.Value = '0';
            app.k16uR = uieditfield(app.RightHemisphereTab, 'text');
            app.k16uR.ValueChangedFcn = createCallbackFcn(app, @k16uRValueChanged, true);
            app.k16uR.Tag = 'k16uR';
            app.k16uR.Value = '0';
            app.k9txtR = uilabel(app.RightHemisphereTab);
            app.k9txtR.Tag = 'k9txtR';
            app.k9txtR.Text = 'K9';
            app.k10txtR = uilabel(app.RightHemisphereTab);
            app.k10txtR.Tag = 'k10txtR';
            app.k10txtR.Text = 'K10';
            app.k11txtR = uilabel(app.RightHemisphereTab);
            app.k11txtR.Tag = 'k11txtR';
            app.k11txtR.Text = 'K11';
            app.k12txtR = uilabel(app.RightHemisphereTab);
            app.k12txtR.Tag = 'k12txtR';
            app.k12txtR.Text = 'K12';
            app.k13txtR = uilabel(app.RightHemisphereTab);
            app.k13txtR.Tag = 'k13txtR';
            app.k13txtR.Text = 'K13';
            app.k14txtR = uilabel(app.RightHemisphereTab);
            app.k14txtR.Tag = 'k14txtR';
            app.k14txtR.Text = 'K14';
            app.k15txtR = uilabel(app.RightHemisphereTab);
            app.k15txtR.Tag = 'k15txtR';
            app.k15txtR.Text = 'K15';
            app.k16txtR = uilabel(app.RightHemisphereTab);
            app.k16txtR.Tag = 'k16txtR';
            app.k16txtR.Text = 'K16';
            app.CpolR = uiimage(app.RightHemisphereTab);
            app.CpolR.Tag = 'CpolR';
            app.CpolR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k1polR = uiimage(app.RightHemisphereTab);
            app.k1polR.Tag = 'k1polR';
            app.k1polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k2polR = uiimage(app.RightHemisphereTab);
            app.k2polR.Tag = 'k2polR';
            app.k2polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k3polR = uiimage(app.RightHemisphereTab);
            app.k3polR.Tag = 'k3polR';
            app.k3polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k4polR = uiimage(app.RightHemisphereTab);
            app.k4polR.Tag = 'k4polR';
            app.k4polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k5polR = uiimage(app.RightHemisphereTab);
            app.k5polR.Tag = 'k5polR';
            app.k5polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k6polR = uiimage(app.RightHemisphereTab);
            app.k6polR.Tag = 'k6polR';
            app.k6polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k7polR = uiimage(app.RightHemisphereTab);
            app.k7polR.Tag = 'k7polR';
            app.k7polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k8polR = uiimage(app.RightHemisphereTab);
            app.k8polR.Tag = 'k8polR';
            app.k8polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k9polR = uiimage(app.RightHemisphereTab);
            app.k9polR.Tag = 'k9polR';
            app.k9polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k10polR = uiimage(app.RightHemisphereTab);
            app.k10polR.Tag = 'k10polR';
            app.k10polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k11polR = uiimage(app.RightHemisphereTab);
            app.k11polR.Tag = 'k11polR';
            app.k11polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k12polR = uiimage(app.RightHemisphereTab);
            app.k12polR.Tag = 'k12polR';
            app.k12polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k13polR = uiimage(app.RightHemisphereTab);
            app.k13polR.Tag = 'k13polR';
            app.k13polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k14polR = uiimage(app.RightHemisphereTab);
            app.k14polR.Tag = 'k14polR';
            app.k14polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k15polR = uiimage(app.RightHemisphereTab);
            app.k15polR.Tag = 'k15polR';
            app.k15polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k16polR = uiimage(app.RightHemisphereTab);
            app.k16polR.Tag = 'k16polR';
            app.k16polR.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.LeftHemisphereTab = uitab(app.TabGroup);
            app.LeftHemisphereTab.Title = 'Left Hemisphere';
            app.pulseWidthLabel_L = uilabel(app.LeftHemisphereTab);
            app.pulseWidthLabel_L.Tag = 'pulseWidthLabel_L';
            app.pulseWidthLabel_L.Visible = 'off';
            app.pulseWidthLabel_L.Text = 'Pulse width';
            app.perctext1L = uilabel(app.LeftHemisphereTab);
            app.perctext1L.Tag = 'perctext1L';
            app.perctext1L.Text = '%';
            app.kohmtext1L = uilabel(app.LeftHemisphereTab);
            app.kohmtext1L.Tag = 'kohmtext1L';
            app.kohmtext1L.Text = 'kΩ';
            app.perctext2L = uilabel(app.LeftHemisphereTab);
            app.perctext2L.Tag = 'perctext2L';
            app.perctext2L.Text = '%';
            app.kohmtext2L = uilabel(app.LeftHemisphereTab);
            app.kohmtext2L.Tag = 'kohmtext2L';
            app.kohmtext2L.Text = 'kΩ';
            app.k1imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k1imL.ValueChangedFcn = createCallbackFcn(app, @k1imL_Callback, true);
            app.k1imL.Tag = 'k1imL';
            app.k1imL.Value = '1';
            app.k2imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k2imL.ValueChangedFcn = createCallbackFcn(app, @k2imL_Callback, true);
            app.k2imL.Tag = 'k2imL';
            app.k2imL.Value = '1';
            app.k3imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k3imL.ValueChangedFcn = createCallbackFcn(app, @k3imL_Callback, true);
            app.k3imL.Tag = 'k3imL';
            app.k3imL.Value = '1';
            app.k4imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k4imL.ValueChangedFcn = createCallbackFcn(app, @k4imL_Callback, true);
            app.k4imL.Tag = 'k4imL';
            app.k4imL.Value = '1';
            app.k5imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k5imL.ValueChangedFcn = createCallbackFcn(app, @k5imL_Callback, true);
            app.k5imL.Tag = 'k5imL';
            app.k5imL.Value = '1';
            app.k6imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k6imL.ValueChangedFcn = createCallbackFcn(app, @k6imL_Callback, true);
            app.k6imL.Tag = 'k6imL';
            app.k6imL.Value = '1';
            app.k7imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k7imL.ValueChangedFcn = createCallbackFcn(app, @k7imL_Callback, true);
            app.k7imL.Tag = 'k7imL';
            app.k7imL.Value = '1';
            app.k8imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k8imL.ValueChangedFcn = createCallbackFcn(app, @k8imL_Callback, true);
            app.k8imL.Tag = 'k8imL';
            app.k8imL.Value = '1';
            app.k1uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k1uL.ValueChangedFcn = createCallbackFcn(app, @k1uL_Callback, true);
            app.k1uL.Tag = 'k1uL';
            app.k1uL.Value = '0';
            app.k2uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k2uL.ValueChangedFcn = createCallbackFcn(app, @k2uL_Callback, true);
            app.k2uL.Tag = 'k2uL';
            app.k2uL.Value = '0';
            app.k3uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k3uL.ValueChangedFcn = createCallbackFcn(app, @k3uL_Callback, true);
            app.k3uL.Tag = 'k3uL';
            app.k3uL.Value = '0';
            app.k4uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k4uL.ValueChangedFcn = createCallbackFcn(app, @k4uL_Callback, true);
            app.k4uL.Tag = 'k4uL';
            app.k4uL.Value = '0';
            app.k5uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k5uL.ValueChangedFcn = createCallbackFcn(app, @k5uL_Callback, true);
            app.k5uL.Tag = 'k5uL';
            app.k5uL.Value = '0';
            app.k6uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k6uL.ValueChangedFcn = createCallbackFcn(app, @k6uL_Callback, true);
            app.k6uL.Tag = 'k6uL';
            app.k6uL.Value = '0';
            app.k7uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k7uL.ValueChangedFcn = createCallbackFcn(app, @k7uL_Callback, true);
            app.k7uL.Tag = 'k7uL';
            app.k7uL.Value = '0';
            app.k8uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k8uL.ValueChangedFcn = createCallbackFcn(app, @k8uL_Callback, true);
            app.k8uL.Tag = 'k8uL';
            app.k8uL.Value = '0';
            app.k1txtL = uilabel(app.LeftHemisphereTab);
            app.k1txtL.Tag = 'k1txtL';
            app.k1txtL.Text = 'K1';
            app.k2txtL = uilabel(app.LeftHemisphereTab);
            app.k2txtL.Tag = 'k2txtL';
            app.k2txtL.Text = 'K2';
            app.k3txtL = uilabel(app.LeftHemisphereTab);
            app.k3txtL.Tag = 'k3txtL';
            app.k3txtL.Text = 'K3';
            app.k4txtL = uilabel(app.LeftHemisphereTab);
            app.k4txtL.Tag = 'k4txtL';
            app.k4txtL.Text = 'K4';
            app.k5txtL = uilabel(app.LeftHemisphereTab);
            app.k5txtL.Tag = 'k5txtL';
            app.k5txtL.Text = 'K5';
            app.k6txtL = uilabel(app.LeftHemisphereTab);
            app.k6txtL.Tag = 'k6txtL';
            app.k6txtL.Text = 'K6';
            app.k7txtL = uilabel(app.LeftHemisphereTab);
            app.k7txtL.Tag = 'k7txtL';
            app.k7txtL.Text = 'K7';
            app.k8txtL = uilabel(app.LeftHemisphereTab);
            app.k8txtL.Tag = 'k8txtL';
            app.k8txtL.Text = 'K8';
            app.usLabel_L = uilabel(app.LeftHemisphereTab);
            app.usLabel_L.Tag = 'usLabel_L';
            app.usLabel_L.Visible = 'off';
            app.usLabel_L.Text = 'μs';
            app.casetxtL = uilabel(app.LeftHemisphereTab);
            app.casetxtL.Tag = 'caseLtxt';
            app.casetxtL.Text = 'Case';
            app.CuL = uieditfield(app.LeftHemisphereTab, 'text');
            app.CuL.ValueChangedFcn = createCallbackFcn(app, @CuL_Callback, true);
            app.CuL.Tag = 'CuL';
            app.CuL.Value = '100';
            app.pulseWidthTextbox_L = uieditfield(app.LeftHemisphereTab, 'text');
            app.pulseWidthTextbox_L.ValueChangedFcn = createCallbackFcn(app, @pulseWidthTextbox_L_Callback, true);
            app.pulseWidthTextbox_L.Tag = 'pulseWidthTextbox_L';
            app.pulseWidthTextbox_L.Visible = 'off';
            app.pulseWidthTextbox_L.Value = '60';
            app.Ls4va = uidropdown(app.LeftHemisphereTab);
            app.Ls4va.Items = {'V', 'mA'};
            app.Ls4va.ValueChangedFcn = createCallbackFcn(app, @Ls4vaValueChanged, true);
            app.Ls4va.Tag = 'Ls4va';
            app.Ls4va.Value = 'V';
            app.Ls3va = uidropdown(app.LeftHemisphereTab);
            app.Ls3va.Items = {'V', 'mA'};
            app.Ls3va.ValueChangedFcn = createCallbackFcn(app, @Ls3vaValueChanged, true);
            app.Ls3va.Tag = 'Ls3va';
            app.Ls3va.Value = 'V';
            app.Ls2va = uidropdown(app.LeftHemisphereTab);
            app.Ls2va.Items = {'V', 'mA'};
            app.Ls2va.ValueChangedFcn = createCallbackFcn(app, @Ls2vaValueChanged, true);
            app.Ls2va.Tag = 'Ls2va';
            app.Ls2va.Value = 'V';
            app.Ls1va = uidropdown(app.LeftHemisphereTab);
            app.Ls1va.Items = {'V', 'mA'};
            app.Ls1va.ValueChangedFcn = createCallbackFcn(app, @Ls1vaValueChanged, true);
            app.Ls1va.Tag = 'Ls1va';
            app.Ls1va.Value = 'V';
            app.Ls4am = uieditfield(app.LeftHemisphereTab, 'text');
            app.Ls4am.ValueChangedFcn = createCallbackFcn(app, @Ls4am_Callback, true);
            app.Ls4am.Tag = 'Ls4am';
            app.Ls4am.Value = '0';
            app.Ls3am = uieditfield(app.LeftHemisphereTab, 'text');
            app.Ls3am.ValueChangedFcn = createCallbackFcn(app, @Ls3am_Callback, true);
            app.Ls3am.Tag = 'Ls3am';
            app.Ls3am.Value = '0';
            app.Ls2am = uieditfield(app.LeftHemisphereTab, 'text');
            app.Ls2am.ValueChangedFcn = createCallbackFcn(app, @Ls2am_Callback, true);
            app.Ls2am.Tag = 'Ls2am';
            app.Ls2am.Value = '0';
            app.Ls1am = uieditfield(app.LeftHemisphereTab, 'text');
            app.Ls1am.ValueChangedFcn = createCallbackFcn(app, @Ls1am_Callback, true);
            app.Ls1am.Tag = 'Ls1am';
            app.Ls1am.Value = '0';
            app.text50 = uilabel(app.LeftHemisphereTab);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Sources';
            app.perctext3L = uilabel(app.LeftHemisphereTab);
            app.perctext3L.Tag = 'perctext3L';
            app.perctext3L.Text = '%';
            app.kohmtext3L = uilabel(app.LeftHemisphereTab);
            app.kohmtext3L.Tag = 'kohmtext3L';
            app.kohmtext3L.Text = 'kΩ';
            app.perctext4L = uilabel(app.LeftHemisphereTab);
            app.perctext4L.Tag = 'perctext4L';
            app.perctext4L.Text = '%';
            app.kohmtext4L = uilabel(app.LeftHemisphereTab);
            app.kohmtext4L.Tag = 'kohmtext4L';
            app.kohmtext4L.Text = 'kΩ';
            app.k9imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k9imL.ValueChangedFcn = createCallbackFcn(app, @k9imLValueChanged, true);
            app.k9imL.Tag = 'k9imL';
            app.k9imL.Value = '1';
            app.k10imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k10imL.ValueChangedFcn = createCallbackFcn(app, @k10imLValueChanged, true);
            app.k10imL.Tag = 'k10imL';
            app.k10imL.Value = '1';
            app.k11imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k11imL.ValueChangedFcn = createCallbackFcn(app, @k11imLValueChanged, true);
            app.k11imL.Tag = 'k11imL';
            app.k11imL.Value = '1';
            app.k12imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k12imL.ValueChangedFcn = createCallbackFcn(app, @k12imLValueChanged, true);
            app.k12imL.Tag = 'k12imL';
            app.k12imL.Value = '1';
            app.k13imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k13imL.ValueChangedFcn = createCallbackFcn(app, @k13imLValueChanged, true);
            app.k13imL.Tag = 'k13imL';
            app.k13imL.Value = '1';
            app.k14imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k14imL.ValueChangedFcn = createCallbackFcn(app, @k14imLValueChanged, true);
            app.k14imL.Tag = 'k14imL';
            app.k14imL.Value = '1';
            app.k15imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k15imL.ValueChangedFcn = createCallbackFcn(app, @k15imLValueChanged, true);
            app.k15imL.Tag = 'k15imL';
            app.k15imL.Value = '1';
            app.k16imL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k16imL.ValueChangedFcn = createCallbackFcn(app, @k16imLValueChanged, true);
            app.k16imL.Tag = 'k16imL';
            app.k16imL.Value = '1';
            app.k9uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k9uL.ValueChangedFcn = createCallbackFcn(app, @k9uLValueChanged, true);
            app.k9uL.Tag = 'k9uL';
            app.k9uL.Value = '0';
            app.k10uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k10uL.ValueChangedFcn = createCallbackFcn(app, @k10uLValueChanged, true);
            app.k10uL.Tag = 'k10uL';
            app.k10uL.Value = '0';
            app.k11uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k11uL.ValueChangedFcn = createCallbackFcn(app, @k11uLValueChanged, true);
            app.k11uL.Tag = 'k11uL';
            app.k11uL.Value = '0';
            app.k12uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k12uL.ValueChangedFcn = createCallbackFcn(app, @k12uLValueChanged, true);
            app.k12uL.Tag = 'k12uL';
            app.k12uL.Value = '0';
            app.k13uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k13uL.ValueChangedFcn = createCallbackFcn(app, @k13uLValueChanged, true);
            app.k13uL.Tag = 'k13uL';
            app.k13uL.Value = '0';
            app.k14uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k14uL.ValueChangedFcn = createCallbackFcn(app, @k14uLValueChanged, true);
            app.k14uL.Tag = 'k14uL';
            app.k14uL.Value = '0';
            app.k15uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k15uL.ValueChangedFcn = createCallbackFcn(app, @k15uLValueChanged, true);
            app.k15uL.Tag = 'k15uL';
            app.k15uL.Value = '0';
            app.k16uL = uieditfield(app.LeftHemisphereTab, 'text');
            app.k16uL.ValueChangedFcn = createCallbackFcn(app, @k16uLValueChanged, true);
            app.k16uL.Tag = 'k16uL';
            app.k16uL.Value = '0';
            app.k9txtL = uilabel(app.LeftHemisphereTab);
            app.k9txtL.Tag = 'k9txtL';
            app.k9txtL.Text = 'K9';
            app.k10txtL = uilabel(app.LeftHemisphereTab);
            app.k10txtL.Tag = 'k10txtL';
            app.k10txtL.Text = 'K10';
            app.k11txtL = uilabel(app.LeftHemisphereTab);
            app.k11txtL.Tag = 'k11txtL';
            app.k11txtL.Text = 'K11';
            app.k12txtL = uilabel(app.LeftHemisphereTab);
            app.k12txtL.Tag = 'k12txtL';
            app.k12txtL.Text = 'K12';
            app.k13txtL = uilabel(app.LeftHemisphereTab);
            app.k13txtL.Tag = 'k13txtL';
            app.k13txtL.Text = 'K13';
            app.k14txtL = uilabel(app.LeftHemisphereTab);
            app.k14txtL.Tag = 'k14txtL';
            app.k14txtL.Text = 'K14';
            app.k15txtL = uilabel(app.LeftHemisphereTab);
            app.k15txtL.Tag = 'k15txtL';
            app.k15txtL.Text = 'K15';
            app.k16txtL = uilabel(app.LeftHemisphereTab);
            app.k16txtL.Tag = 'k16txtL';
            app.k16txtL.Text = 'K16';
            app.CpolL = uiimage(app.LeftHemisphereTab);
            app.CpolL.Tag = 'CpolL';
            app.CpolL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k1polL = uiimage(app.LeftHemisphereTab);
            app.k1polL.Tag = 'k1polL';
            app.k1polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k2polL = uiimage(app.LeftHemisphereTab);
            app.k2polL.Tag = 'k2polL';
            app.k2polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k3polL = uiimage(app.LeftHemisphereTab);
            app.k3polL.Tag = 'k3polL';
            app.k3polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k4polL = uiimage(app.LeftHemisphereTab);
            app.k4polL.Tag = 'k4polL';
            app.k4polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k5polL = uiimage(app.LeftHemisphereTab);
            app.k5polL.Tag = 'k5polL';
            app.k5polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k6polL = uiimage(app.LeftHemisphereTab);
            app.k6polL.Tag = 'k6polL';
            app.k6polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k7polL = uiimage(app.LeftHemisphereTab);
            app.k7polL.Tag = 'k7polL';
            app.k7polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k8polL = uiimage(app.LeftHemisphereTab);
            app.k8polL.Tag = 'k8polL';
            app.k8polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k9polL = uiimage(app.LeftHemisphereTab);
            app.k9polL.Tag = 'k9polL';
            app.k9polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k10polL = uiimage(app.LeftHemisphereTab);
            app.k10polL.Tag = 'k10polL';
            app.k10polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k11polL = uiimage(app.LeftHemisphereTab);
            app.k11polL.Tag = 'k11polL';
            app.k11polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k12polL = uiimage(app.LeftHemisphereTab);
            app.k12polL.Tag = 'k12polL';
            app.k12polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k13polL = uiimage(app.LeftHemisphereTab);
            app.k13polL.Tag = 'k13polL';
            app.k13polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k14polL = uiimage(app.LeftHemisphereTab);
            app.k14polL.Tag = 'k14polL';
            app.k14polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k15polL = uiimage(app.LeftHemisphereTab);
            app.k15polL.Tag = 'k15polL';
            app.k15polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            app.k16polL = uiimage(app.LeftHemisphereTab);
            app.k16polL.Tag = 'k16polL';
            app.k16polL.ImageSource = fullfile(pathToMLAPP, 'icons', 'empty1.png');
            % Show the figure after all components are created
            app.stimfig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_stimparams(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.stimfig)

                % Execute the startup function
                runStartupFcn(app, @(app)ea_stimparams_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.stimfig)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.stimfig)
        end
    end
end