classdef ea_vatsettings_butenko < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OSSDBSSettingsUIFigure        matlab.ui.Figure
        SettingsInfoButton            matlab.ui.control.Button
        VATLabel                      matlab.ui.control.Label
        PAMLabel                      matlab.ui.control.Label
        Image3_2                      matlab.ui.control.Image
        AdvancedSettingsLabel         matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        SaveButton                    matlab.ui.control.Button
        QuestionsandsuggestionskonstantinmgtugmailcomLabel  matlab.ui.control.Label
        ZimmermannPayonkButenkoetalLabel  matlab.ui.control.Label
        OnstandbyLamp                 matlab.ui.control.Lamp
        OnstandbyLampLabel            matlab.ui.control.Label
        OSSDBSadvancebrainstimulationmodelingLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        PWAdaptiveCheckBox            matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        EstimateStimulationVolumeButton  matlab.ui.control.StateButton
        RemoveLeadCheckBox            matlab.ui.control.CheckBox
        EthresholdEditField           matlab.ui.control.NumericEditField
        EthresholdLabel               matlab.ui.control.Label
        VmLabel                       matlab.ui.control.Label
        Image4                        matlab.ui.control.Image
        Panel_3                       matlab.ui.container.Panel
        SmLabel                       matlab.ui.control.Label
        ConductivityButtonGroup       matlab.ui.container.ButtonGroup
        HomogeneousButton             matlab.ui.control.RadioButton
        ColeCole3Button               matlab.ui.control.RadioButton
        ColeCole4Button               matlab.ui.control.RadioButton
        EncapsulationButtonGroup      matlab.ui.container.ButtonGroup
        NoneButton                    matlab.ui.control.RadioButton
        AcuteButton                   matlab.ui.control.RadioButton
        ChronicButton                 matlab.ui.control.RadioButton
        UseAdaptiveMeshRefinementCheckBox  matlab.ui.control.CheckBox
        AnisotropyModelButtonGroup    matlab.ui.container.ButtonGroup
        PatientSpecificButton         matlab.ui.control.RadioButton
        NormativeButton               matlab.ui.control.RadioButton
        IsotropicButton               matlab.ui.control.RadioButton
        SegmentationModelButtonGroup  matlab.ui.container.ButtonGroup
        AtlasBasedButton              matlab.ui.control.RadioButton
        SynthSegButton                matlab.ui.control.RadioButton
        SPMButton                     matlab.ui.control.RadioButton
        Panel_2                       matlab.ui.container.Panel
        pPAMButton                    matlab.ui.control.Button
        Image5                        matlab.ui.control.Image
        SymmetricBiphasicCheckBox     matlab.ui.control.CheckBox
        AxonsintersectedbyleadareButtonGroup  matlab.ui.container.ButtonGroup
        activatednearactivecontactsButton  matlab.ui.control.RadioButton
        activatedButton               matlab.ui.control.RadioButton
        damagednonactiveButton        matlab.ui.control.RadioButton
        CustomButton                  matlab.ui.control.Button
        AxonDiamLengthDropDown        matlab.ui.control.DropDown
        AxonDiamLengthLabel           matlab.ui.control.Label
        mmLabel                       matlab.ui.control.Label
        AxonlengthEditField           matlab.ui.control.NumericEditField
        EstimatePathwayActivationButton  matlab.ui.control.StateButton
        sLabel                        matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        CableModelDropDown            matlab.ui.control.DropDown
        CableModelLabel               matlab.ui.control.Label
        PulseTypeDropDown             matlab.ui.control.DropDown
        PulseTypeLabel                matlab.ui.control.Label
        ConnectomeDropDown            matlab.ui.control.DropDown
        ConnectomeLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        butenko_ethresh = 200.0;
        butenko_adaptive_ethresh = 0;
        butenko_fiberDiameter = [3.0];
        butenko_calcAxonActivation = 0;  % should use butenko_calcPAM instead
        butenko_axonLength = [11.1];
        butenko_connectome
        butenko_interactive = 0;  % no OSS-DBS v2 GUI
        butenko_useTensorData = 0;
        butenko_tensorFileName = 'no dti';
        butenko_tensorScalingMethod = 'NormMapping';
        cond_model = 'ColeCole4';

        % new properties
        butenko_calcVAT = 1;
        butenko_calcPAM = 0;
        butenko_prob_PAM = 0;
        
        butenko_removeElectrode = 1;

        butenko_axonModel = 'McNeal1976';
        butenko_signalType = 'Train';
        butenko_pulseWidth = 60.0;
        butenko_biphasic = 0;
        butenko_intersectStatus = 'damaged';

        butenko_segmAlg = 'SynthSeg';
        butenko_tensorData
        butenko_AdaptiveRef = 0;
        butenko_encapsulation = 'None';

        connectomes
        multitract = 0;

        handles % handles of stimfig to update the GUI
    end
    
    methods (Access = private)
        
        function results = enablerules(app)
            if app.EstimatePathwayActivationButton.Value
                app.ConnectomeDropDown.Enable = "on";
                app.CableModelDropDown.Enable = "on";
                app.AxonDiamLengthDropDown.Enable = "on";
                app.AxonlengthEditField.Enable = "on";
                app.PulseTypeDropDown.Enable = "on";
                app.SymmetricBiphasicCheckBox.Enable = "on";
                app.pPAMButton.Enable = "on";
                %app.WidthEditField.Enable = "on";  % via Stim GUI now
            else
                app.ConnectomeDropDown.Enable = "off";
                app.CableModelDropDown.Enable = "off";
                app.AxonDiamLengthDropDown.Enable = "off";
                app.AxonlengthEditField.Enable = "off";
                app.PulseTypeDropDown.Enable = "off";
                app.SymmetricBiphasicCheckBox.Enable = "off";
                app.WidthEditField.Enable = "off";
                app.pPAMButton.Enable = "off";
            end

            if app.butenko_adaptive_ethresh
                app.EthresholdEditField.Enable = 0;
            else
                app.EthresholdEditField.Enable = 1;
            end

        end

        function load_global_settings(app)

            % settings from the previously open "Settings" session
            prefs=ea_prefs('');
            app.butenko_ethresh = prefs.machine.vatsettings.butenko_ethresh;
            app.EthresholdEditField.Value = app.butenko_ethresh;
            try
                app.butenko_adaptive_ethresh = prefs.machine.vatsettings.butenko_adaptive_ethresh;
                if app.butenko_adaptive_ethresh
                    app.EthresholdEditField.Enable = 0;
                end
            catch
                app.butenko_adaptive_ethresh = 0;
            end

            % do not load the previous state for prob PAM
            %try 
            %    app.butenko_prob_PAM = prefs.machine.vatsettings.butenko_prob_PAM;
            %catch
            %    app.butenko_prob_PAM = 0;
            %end

            app.PWAdaptiveCheckBox.Value = app.butenko_adaptive_ethresh;

            app.butenko_calcVAT = prefs.machine.vatsettings.butenko_calcVAT;
            app.EstimateStimulationVolumeButton.Value = app.butenko_calcVAT;

            app.butenko_calcAxonActivation = prefs.machine.vatsettings.butenko_calcAxonActivation;  % should use butenko_calcPAM instead
            app.butenko_calcPAM = prefs.machine.vatsettings.butenko_calcAxonActivation;
            app.EstimatePathwayActivationButton.Value = app.butenko_calcPAM;

            if app.butenko_calcPAM
                app.butenko_fiberDiameter = prefs.machine.vatsettings.butenko_fiberDiameter;

                % custom diameters might be not defined in the drop down,
                % so just switch the displayed value
                try
                    app.AxonDiamLengthDropDown.Value = [num2str(app.butenko_fiberDiameter(1),'%.1f'), ' µm'];
                catch
                    app.AxonDiamLengthDropDown.Value = '3.0 µm';
                end
                
                app.butenko_axonLength = prefs.machine.vatsettings.butenko_axonLength;
                app.AxonlengthEditField.Value = app.butenko_axonLength(1);

                app.butenko_connectome = prefs.machine.vatsettings.butenko_connectome;
                app.ConnectomeDropDown.Value = app.butenko_connectome;
        
                app.butenko_axonModel = prefs.machine.vatsettings.butenko_axonModel;
                app.CableModelDropDown.Value = app.butenko_axonModel;
    
                app.butenko_signalType = prefs.machine.vatsettings.butenko_signalType;
                app.PulseTypeDropDown.Value = app.butenko_signalType;
    
                app.butenko_pulseWidth = prefs.machine.vatsettings.butenko_pulseWidth;
                app.WidthEditField.Value = app.butenko_pulseWidth;
    
                app.butenko_biphasic = prefs.machine.vatsettings.butenko_biphasic;
                app.SymmetricBiphasicCheckBox.Value = app.butenko_biphasic;

                app.butenko_intersectStatus = prefs.machine.vatsettings.butenko_intersectStatus;
                switch app.butenko_intersectStatus
                    case 'damaged'
                        app.AxonsintersectedbyleadareButtonGroup.SelectedObject = app.damagednonactiveButton;
                    case 'activated'
                        app.AxonsintersectedbyleadareButtonGroup.SelectedObject = app.activatedButton;
                    case 'activated_at_active_contacts'
                        app.AxonsintersectedbyleadareButtonGroup.SelectedObject = app.activatednearactivecontactsButton;   
                end
            end
        
            app.butenko_removeElectrode = prefs.machine.vatsettings.butenko_removeElectrode;
            app.RemoveLeadCheckBox.Value = app.butenko_removeElectrode;

            if strcmp(prefs.machine.vatsettings.butenko_tensorData,'no dti') || isempty(prefs.machine.vatsettings.butenko_tensorData)
                app.butenko_useTensorData = 0;
                app.butenko_tensorFileName = 'no dti';
                app.AnisotropyModelButtonGroup.SelectedObject = app.IsotropicButton;
            else 
                app.butenko_useTensorData = 1;
                app.butenko_tensorFileName = prefs.machine.vatsettings.butenko_tensorData;
                app.butenko_tensorData = prefs.machine.vatsettings.butenko_tensorData;
                % also switch the radio buttons
                if strcmp(app.butenko_tensorFileName,'IITMeanTensor.nii.gz') || strcmp(app.butenko_tensorFileName,'FSL_HCP1065_tensor_1mm_5D_NIFTI_order_2009a.nii.gz') 
                    app.AnisotropyModelButtonGroup.SelectedObject = app.NormativeButton;
                else
                    app.AnisotropyModelButtonGroup.SelectedObject = app.PatientSpecificButton;
                end
            end

            if ~isfield(prefs.machine.vatsettings,'butenko_cond_model') || isempty(prefs.machine.vatsettings.butenko_cond_model)
                prefs.machine.vatsettings.butenko_cond_model = 'ColeCole4';
            end
            app.cond_model = prefs.machine.vatsettings.butenko_cond_model;

            switch app.cond_model
                case 'ColeCole4'
                    app.ConductivityButtonGroup.SelectedObject = app.ColeCole4Button;
                case 'ColeCole3'
                    app.ConductivityButtonGroup.SelectedObject = app.ColeCole3Button;
                case 'Homogeneous'
                    app.ConductivityButtonGroup.SelectedObject = app.HomogeneousButton;
            end

            app.butenko_segmAlg = prefs.machine.vatsettings.butenko_segmAlg;
            switch app.butenko_segmAlg
                case 'SPM'
                    app.SegmentationModelButtonGroup.SelectedObject = app.SPMButton;
                case 'SynthSeg'
                    app.SegmentationModelButtonGroup.SelectedObject = app.SynthSegButton;
                case 'Atlas Based'
                    app.SegmentationModelButtonGroup.SelectedObject = app.AtlasBasedButton;
            end

            app.butenko_AdaptiveRef = prefs.machine.vatsettings.butenko_AdaptiveRef;
            
            app.butenko_encapsulation = prefs.machine.vatsettings.butenko_encapsulation;
            switch app.butenko_encapsulation
                case 'Gray matter'
                    app.EncapsulationButtonGroup.SelectedObject = app.ChronicButton;
                case 'Blood'
                    app.EncapsulationButtonGroup.SelectedObject = app.AcuteButton;
                case 'None'
                    app.EncapsulationButtonGroup.SelectedObject = app.NoneButton;
            end
        end

        function load_previously_ran_settings(app, last_settings)
            % settings taken from oss-dbs_parameters.mat

            app.butenko_ethresh = last_settings.settings.Activation_threshold_VTA;
            app.butenko_adaptive_ethresh = last_settings.settings.adaptive_threshold;
            if app.butenko_adaptive_ethresh
                app.EthresholdEditField.Enable = 0;
            end
            app.EthresholdEditField.Value = app.butenko_ethresh;
            app.PWAdaptiveCheckBox.Value = app.butenko_adaptive_ethresh;

            app.butenko_calcVAT = last_settings.settings.exportVAT;
            app.EstimateStimulationVolumeButton.Value = app.butenko_calcVAT;

            app.butenko_calcAxonActivation = last_settings.settings.calcAxonActivation;  % should use butenko_calcPAM instead
            app.butenko_calcPAM = last_settings.settings.calcAxonActivation;
            app.EstimatePathwayActivationButton.Value = app.butenko_calcPAM;
            if app.butenko_calcPAM
                app.butenko_fiberDiameter = last_settings.settings.fiberDiameter;

                % custom diameters might be not defined in the drop down,
                % so just switch the displayed value
                try
                    app.AxonDiamLengthDropDown.Value = [num2str(app.butenko_fiberDiameter(1),'%.1f'), ' µm'];
                catch
                    app.AxonDiamLengthDropDown.Value = '3.0 µm';
                end
                
                app.butenko_axonLength = last_settings.settings.axonLength;
                app.AxonlengthEditField.Value = app.butenko_axonLength(1);

                app.butenko_connectome = last_settings.settings.connectome;
                app.ConnectomeDropDown.Value = app.butenko_connectome;
        
                app.butenko_axonModel = last_settings.settings.neuronModel;
                app.CableModelDropDown.Value = app.butenko_axonModel;
    
                app.butenko_signalType = last_settings.settings.signalType;
                app.PulseTypeDropDown.Value = app.butenko_signalType;
    
                app.butenko_pulseWidth = last_settings.settings.pulseWidth;
                app.WidthEditField.Value = app.butenko_pulseWidth;
    
                app.butenko_biphasic = last_settings.settings.biphasic;
                app.SymmetricBiphasicCheckBox.Value = app.butenko_biphasic;

                app.butenko_intersectStatus = last_settings.settings.butenko_intersectStatus;
                switch app.butenko_intersectStatus
                    case 'damaged'
                        app.AxonsintersectedbyleadareButtonGroup.SelectedObject = app.damagednonactiveButton;
                    case 'activated'
                        app.AxonsintersectedbyleadareButtonGroup.SelectedObject = app.activatedButton;
                    case 'activated_at_active_contacts'
                        app.AxonsintersectedbyleadareButtonGroup.SelectedObject = app.activatednearactivecontactsButton;   
                end
            end
        
            app.butenko_removeElectrode = last_settings.settings.removeElectrode;
            app.RemoveLeadCheckBox.Value = app.butenko_removeElectrode;

            if isempty(last_settings.settings.butenko_tensorData) || strcmp(last_settings.settings.DTI_data_name, 'no dti')
                app.butenko_useTensorData = 0;
                app.butenko_tensorFileName = 'no dti';
                app.AnisotropyModelButtonGroup.SelectedObject = app.IsotropicButton;
            else 
                app.butenko_useTensorData = 1;
                app.butenko_tensorFileName = last_settings.settings.butenko_tensorData;
                app.butenko_tensorData = last_settings.settings.butenko_tensorData;
                % also switch the radio buttons
                if strcmp(app.butenko_tensorFileName,'IITMeanTensor.nii.gz') || strcmp(app.butenko_tensorFileName,'FSL_HCP1065_tensor_1mm_5D_NIFTI_order_2009a.nii.gz') 
                    app.AnisotropyModelButtonGroup.SelectedObject = app.NormativeButton;
                else
                    app.AnisotropyModelButtonGroup.SelectedObject = app.PatientSpecificButton;
                end
            end

            app.butenko_segmAlg = last_settings.settings.butenko_segmAlg;
            switch app.butenko_segmAlg
                case 'SPM'
                    app.SegmentationModelButtonGroup.SelectedObject = app.SPMButton;
                case 'SynthSeg'
                    app.SegmentationModelButtonGroup.SelectedObject = app.SynthSegButton;
                case 'Atlas Based'
                    app.SegmentationModelButtonGroup.SelectedObject = app.AtlasBasedButton;
            end

            app.butenko_AdaptiveRef = last_settings.settings.AdaptiveRef;
            
            app.butenko_encapsulation = last_settings.settings.encapsulationType;
            switch app.butenko_encapsulation
                case 'Gray matter'
                    app.EncapsulationButtonGroup.SelectedObject = app.ChronicButton;
                case 'Blood'
                    app.EncapsulationButtonGroup.SelectedObject = app.AcuteButton;
                case 'None'
                    app.EncapsulationButtonGroup.SelectedObject = app.NoneButton;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, handles)
            if exist('handles', 'var')
                app.handles = handles;
            end

            prefs=ea_prefs('');
            app.connectomes = ea_genmodlist([], [], [], 'dmri');

            % Check multi-tract connectome
            multiTractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
            multiTractConn = unique(cellfun(@fileparts, ea_regexpdir(multiTractConnFolder,'\.mat$'), 'Uni', 0));
            multiTractConn = strrep(multiTractConn, [multiTractConnFolder,filesep], 'Multi-Tract: ')';
            
            app.connectomes = [app.connectomes, multiTractConn];

            if isempty(app.connectomes)
                ea_cprintf('CmdWinWarnings', 'No connectome found!\nPlease check the folder defined in ''prefs.lc.datadir'' in your Lead-DBS preferences.\nFall back to VTA calculation now.\n');
                app.butenko_calcVAT = 1;
                app.butenko_calcPAM = 0;
                app.butenko_calcAxonActivation = 0;
                app.EstimatePathwayActivationButton.Enable = 0;
                app.EstimatePathwayActivationButton.Value = 0;
                app.EstimateStimulationVolumeButton.Enable = 1;
                app.EstimateStimulationVolumeButton.Value = 1;
                ea_setprefs('vatsettings.butenko_calcVAT', 1);
                ea_setprefs('vatsettings.butenko_calcAxonActivation', 0);
                ea_setprefs('vatsettings.butenko_calcPAM', 0);

                app.ConnectomeDropDown.Value = 'No connectome found!';
                app.ConnectomeDropDown.Enable = 0;
            else
                if isempty(prefs.machine.vatsettings.butenko_connectome)
                    prefs.machine.vatsettings.butenko_connectome = app.connectomes{1};
                end
                connIdx = find(ismember(app.connectomes,prefs.machine.vatsettings.butenko_connectome));

                app.ConnectomeDropDown.Items = app.connectomes;

                % change the DropDown value
                if ~isempty(connIdx)
                    app.ConnectomeDropDown.Value = app.connectomes{connIdx};
                    app.butenko_connectome = app.connectomes{connIdx};
                else
                    ea_cprintf('CmdWinWarnings', 'Previously selected connectome "%s" not found!\nFall back to "%s".\n', prefs.machine.vatsettings.butenko_connectome, app.connectomes{1});
                    app.ConnectomeDropDown.Value = app.connectomes{1};
                    app.butenko_connectome = app.ConnectomeDropDown.Value;
                    ea_setprefs('vatsettings.butenko_connectome', app.ConnectomeDropDown.Value);
                end

                if startsWith(app.butenko_connectome, 'Multi-Tract: ')
                    app.CustomButton.Enable = "on";
                    app.multitract = 1;
                end
              
            end

            app.load_global_settings()
            app.enablerules;
        end

        % Selection changed function: SegmentationModelButtonGroup
        function SegmentationModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.SegmentationModelButtonGroup.SelectedObject;
            app.butenko_segmAlg = app.SegmentationModelButtonGroup.SelectedObject.Text;
            app.enablerules;
        end

        % Selection changed function: AnisotropyModelButtonGroup
        function AnisotropyModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnisotropyModelButtonGroup.SelectedObject;
            switch app.AnisotropyModelButtonGroup.SelectedObject.Text
                case 'Isotropic'
                    app.butenko_useTensorData = 0;
                    app.butenko_tensorFileName = 'no dti';
                case 'Normative'
                    app.butenko_useTensorData = 1;
                    %app.butenko_tensorFileName = 'FSL_HCP1065_tensor_1mm_5D_NIFTI_order_2009a.nii.gz';
                    app.butenko_tensorFileName = 'IITMeanTensor.nii.gz';
                case 'Patient-Specific'
                    app.butenko_useTensorData = 1;
                    app.butenko_tensorFileName = ea_smart_BIDS_function_to_find_dwi;
            end
            app.butenko_tensorData = app.butenko_tensorFileName;
            app.enablerules;
        end

        % Value changed function: EthresholdEditField
        function EthresholdEditFieldValueChanged(app, event)
            app.butenko_ethresh = app.EthresholdEditField.Value;
        end

        % Value changed function: RemoveLeadCheckBox
        function RemoveLeadCheckBoxValueChanged(app, event)
            app.butenko_removeElectrode = app.RemoveLeadCheckBox.Value;
        end

        % Value changed function: EstimateStimulationVolumeButton
        function EstimateStimulationVolumeButtonValueChanged(app, event)
            if strcmp(app.ConnectomeDropDown.Value, 'No connectome found!')
                app.EstimateStimulationVolumeButton.Value = 1;
                return;
            end
            app.butenko_calcVAT = app.EstimateStimulationVolumeButton.Value;
            if app.EstimateStimulationVolumeButton.Value
                app.EstimatePathwayActivationButton.Value = 0;
                app.butenko_calcPAM = 0;
                app.butenko_calcAxonActivation = 0;
            else
                app.EstimatePathwayActivationButton.Value = 1;
                app.butenko_calcPAM = 1;
                app.butenko_calcAxonActivation = 1;
            end
            app.enablerules;
        end

        % Value changed function: EstimatePathwayActivationButton
        function EstimatePathwayActivationButtonValueChanged(app, event)
            app.butenko_calcPAM = app.EstimatePathwayActivationButton.Value;
            app.butenko_calcAxonActivation = app.butenko_calcPAM;
            if app.EstimatePathwayActivationButton.Value
                app.EstimateStimulationVolumeButton.Value = 0;
                app.butenko_calcVAT = 0;
            else
                app.EstimateStimulationVolumeButton.Value = 1;
                app.butenko_calcVAT = 1;
            end
            app.enablerules;
        end

        % Value changed function: AxonDiamLengthDropDown
        function AxonDiamLengthDropDownValueChanged(app, event)

            % set to all pathways
            if app.multitract == 1
                TractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
                connectome = strrep(app.butenko_connectome, 'Multi-Tract: ', '');
                connFolder = [TractConnFolder, filesep, connectome];
                tractNames = strrep(ea_regexpdir(connFolder, '\.mat$', 0), [connFolder, filesep], '');
                excludeStr = {'ADJ', 'merged_pathway'};
                tractNames = tractNames(~contains(tractNames, excludeStr));
                tractNames = strrep(tractNames, '.mat', '');
                app.butenko_fiberDiameter = ones(numel(tractNames),1)*str2double(app.AxonDiamLengthDropDown.Value(1:end-2));
            else
                app.butenko_fiberDiameter = [str2double(app.AxonDiamLengthDropDown.Value(1:end-2))];
            end

            app.enablerules;
        end

        % Value changed function: AxonlengthEditField
        function AxonlengthEditFieldValueChanged(app, event)

            % set to all pathways
            if app.multitract == 1
                TractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
                connectome = strrep(app.butenko_connectome, 'Multi-Tract: ', '');
                connFolder = [TractConnFolder, filesep, connectome];
                tractNames = strrep(ea_regexpdir(connFolder, '\.mat$', 0), [connFolder, filesep], '');
                excludeStr = {'ADJ', 'merged_pathway'};
                tractNames = tractNames(~contains(tractNames, excludeStr));
                tractNames = strrep(tractNames, '.mat', '');
                app.butenko_axonLength = ones(numel(tractNames),1) * app.AxonlengthEditField.Value;
            else
                app.butenko_axonLength = [app.AxonlengthEditField.Value];
            end

            app.enablerules;
        end

        % Value changed function: PulseTypeDropDown
        function PulseTypeDropDownValueChanged(app, event)
            app.butenko_signalType = app.PulseTypeDropDown.Value;
            
        end

        % Value changed function: SymmetricBiphasicCheckBox
        function SymmetricBiphasicCheckBoxValueChanged(app, event)
            app.butenko_biphasic = app.SymmetricBiphasicCheckBox.Value;
            
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.butenko_pulseWidth= app.WidthEditField.Value;
            
        end

        % Selection changed function: AxonsintersectedbyleadareButtonGroup
        function AxonsintersectedbyleadareButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxonsintersectedbyleadareButtonGroup.SelectedObject;
            switch app.AxonsintersectedbyleadareButtonGroup.SelectedObject.Text
                case 'damaged (non-active)'
                    app.butenko_intersectStatus = 'damaged';
                case 'activated'
                    app.butenko_intersectStatus = 'activated';
                case 'activated near active contacts'
                    app.butenko_intersectStatus = 'activated_at_active_contacts';
            end
            app.enablerules;
        end

        % Value changed function: CableModelDropDown
        function CableModelDropDownValueChanged(app, event)
            app.butenko_axonModel = app.CableModelDropDown.Value;
            app.enablerules;
        end

        % Button pushed function: CustomButton
        function CustomButtonPushed(app, event)

            % if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
            %     set(hObject,'BackgroundColor','white');
            % end

            ea_axonact_connsetting(app.butenko_connectome, app.OSSDBSSettingsUIFigure);
%             settings_saved = getappdata(app.OSSDBSSettingsUIFigure, 'settings_saved');
%             waitfor(app.OSSDBSSettingsUIFigure,'settings_saved', 1)
            app.butenko_axonLength = getappdata(app.OSSDBSSettingsUIFigure, 'axonLength');
            app.butenko_fiberDiameter = getappdata(app.OSSDBSSettingsUIFigure, 'fiberDiameter');
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            app.butenko_connectome = app.ConnectomeDropDown.Value;

            if startsWith(app.butenko_connectome, 'Multi-Tract: ')
                app.multitract = 1;
                app.CustomButton.Enable = "on";

                % if changed, always reset to values showed in the main
                % settings window
                TractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
                connectome = strrep(app.butenko_connectome, 'Multi-Tract: ', '');
                connFolder = [TractConnFolder, filesep, connectome];
                tractNames = strrep(ea_regexpdir(connFolder, '\.mat$', 0), [connFolder, filesep], '');
                excludeStr = {'ADJ', 'merged_pathway'};
                tractNames = tractNames(~contains(tractNames, excludeStr));
                tractNames = strrep(tractNames, '.mat', '');
                app.butenko_fiberDiameter = ones(numel(tractNames),1)*str2double(app.AxonDiamLengthDropDown.Value(1:end-2));
                app.butenko_axonLength = ones(numel(tractNames),1) * app.AxonlengthEditField.Value;
            else
                app.multitract = 0;
                app.CustomButton.Enable = "off";
                % if changed, always reset to values showed in the main
                % settings window
                app.butenko_fiberDiameter = [str2double(app.AxonDiamLengthDropDown.Value(1:end-2))];
                app.butenko_axonLength = [app.AxonlengthEditField.Value];
                % if length(app.butenko_fiberDiameter) > 1
                %     app.butenko_fiberDiameter = [app.butenko_fiberDiameter(1)];
                % end
                % if length(app.butenko_axonLength) > 1
                %     app.butenko_axonLength = [app.butenko_axonLength(1)];
                % end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            prefs=ea_prefs('');
            vatsettings = prefs.machine.vatsettings;

            % explicitly update all relevant fields
            vatsettings.butenko_ethresh = app.butenko_ethresh;
            vatsettings.butenko_adaptive_ethresh = app.butenko_adaptive_ethresh;
            vatsettings.butenko_calcAxonActivation = app.butenko_calcAxonActivation;  % should use butenko_calcPAM instead
            vatsettings.butenko_connectome = app.butenko_connectome;
            vatsettings.butenko_interactive = 0;  % no OSS-DBS v2 GUI
            vatsettings.butenko_useTensorData = app.butenko_useTensorData;
            vatsettings.butenko_tensorFileName = app.butenko_tensorFileName;
            vatsettings.butenko_tensorScalingMethod = app.butenko_tensorScalingMethod;
            vatsettings.butenko_cond_model = app.cond_model;
    
            % new properties
            vatsettings.butenko_calcVAT = app.butenko_calcVAT ;
            vatsettings.butenko_calcPAM = app.butenko_calcPAM;
            vatsettings.butenko_prob_PAM = app.butenko_prob_PAM;
            
            vatsettings.butenko_removeElectrode = app.butenko_removeElectrode;
    
            vatsettings.butenko_axonModel = app.butenko_axonModel;
            vatsettings.butenko_signalType = app.butenko_signalType;
            vatsettings.butenko_pulseWidth = app.butenko_pulseWidth;
            vatsettings.butenko_biphasic = app.butenko_biphasic;
            vatsettings.butenko_intersectStatus = app.butenko_intersectStatus;
    
            vatsettings.butenko_segmAlg = app.butenko_segmAlg;
            vatsettings.butenko_tensorData = app.butenko_tensorData;
            vatsettings.butenko_AdaptiveRef = app.butenko_AdaptiveRef;
            vatsettings.butenko_encapsulation = app.butenko_encapsulation;

            % special case where we chose multitract but did not adjust
            % morphology
            if app.multitract == 1 && (length(app.butenko_axonLength) == 1 || length(app.butenko_fiberDiameter) == 1)
                TractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
                connectome = strrep(app.butenko_connectome, 'Multi-Tract: ', '');
                connFolder = [TractConnFolder, filesep, connectome];
                tractNames = strrep(ea_regexpdir(connFolder, '\.mat$', 0), [connFolder, filesep], '');
                excludeStr = {'ADJ', 'merged_pathway'};
                tractNames = tractNames(~contains(tractNames, excludeStr));
                tractNames = strrep(tractNames, '.mat', '');
                app.butenko_fiberDiameter = ones(numel(tractNames),1)*str2double(app.AxonDiamLengthDropDown.Value(1:end-2));
                app.butenko_axonLength = ones(numel(tractNames),1) * app.AxonlengthEditField.Value;
            end

            vatsettings.butenko_fiberDiameter = app.butenko_fiberDiameter;
            vatsettings.butenko_axonLength = app.butenko_axonLength;


            ea_setprefs('vatsettings',vatsettings);

            if ~isempty(app.handles) && ishandle(app.handles.stimfig)
                options = getappdata(app.handles.stimfig, 'options');
                ea_refreshguisp(app.handles, options);
            end

            delete(app);
        end

        % Selection changed function: EncapsulationButtonGroup
        function EncapsulationButtonGroupSelectionChanged(app, event)
            switch app.EncapsulationButtonGroup.SelectedObject.Text
                case 'Chronic'
                    app.butenko_encapsulation = 'Gray matter';
                case 'Acute'
                    app.butenko_encapsulation = 'Blood';
                case 'None'
                    app.butenko_encapsulation = 'None';
            end
            app.enablerules;
        end

        % Button pushed function: SettingsInfoButton
        function SettingsInfoButtonPushed(app, event)
            info1 = [ea_getearoot, 'ext_libs/OSS-DBS/OSSSettings_pt1.png'];
            info2 = [ea_getearoot, 'ext_libs/OSS-DBS/OSSSettings_pt2.png'];
            if ispc
                winopen(info2);
                winopen(info1);
            elseif ismac
                system(['open ', info2]);
                system(['open ', info1]);
            else
                if ~system('which xdg-open >/dev/null 2>&1')
                    system(['xdg-open ', info2]);
                    system(['xdg-open ', info1]);
                elseif ~system('which display >/dev/null 2>&1')
                    system(['display -resize 35% ', info2, ' &']);
                    system(['display -resize 35% ', info1, ' &']);
                else
                    figure('NumberTitle', 'off', 'MenuBar', 'none');
                    imshow(info2);
                    figure('NumberTitle', 'off', 'MenuBar', 'none');
                    imshow(info1);
                end
            end
        end

        % Close request function: OSSDBSSettingsUIFigure
        function OSSDBSSettingsUIFigureCloseRequest(app, event)
            answer = uiconfirm(app.OSSDBSSettingsUIFigure, 'Do you want to save the setting?', ...
                '', 'Options', {'Yes', 'No', 'Cancel'}, 'DefaultOption', 1);
            switch answer
                case 'Yes'
                    app.SaveButtonPushed;
                    delete(app);
                case 'No'
                    delete(app);
            end
        end

        % Value changed function: PWAdaptiveCheckBox
        function PWAdaptiveCheckBoxValueChanged(app, event)
            app.butenko_adaptive_ethresh = app.PWAdaptiveCheckBox.Value;
            if app.PWAdaptiveCheckBox.Value
                app.EthresholdEditField.Enable = 0;
            else
                app.EthresholdEditField.Enable = 1;
            end
        end

        % Button pushed function: pPAMButton
        function pPAMButtonPushed(app, event)
            % if the button was pushed, we assume that pPAM will be used
            app.butenko_prob_PAM = 1;
            ea_ppam;
        end

        % Selection changed function: ConductivityButtonGroup
        function ConductivityButtonGroupSelectionChanged(app, event)
            selectedButton = app.ConductivityButtonGroup.SelectedObject;
            switch app.ConductivityButtonGroup.SelectedObject.Text
                case 'ColeCole4'
                    app.cond_model = 'ColeCole4';
                case 'ColeCole3'
                    app.cond_model = 'ColeCole3';
                case 'Homogeneous'
                    app.cond_model = 'Homogeneous';
            end
            app.enablerules;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OSSDBSSettingsUIFigure = uifigure('Visible', 'off');
            app.OSSDBSSettingsUIFigure.Name = 'OSS-DBS Settings';
            app.OSSDBSSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OSSDBSSettingsUIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.OSSDBSSettingsUIFigure);
            app.ConnectomeLabel = uilabel(app.Panel_2);
            app.ConnectomeLabel.Text = 'Connectome:';
            app.ConnectomeDropDown = uidropdown(app.Panel_2);
            app.ConnectomeDropDown.Items = {'No connectome found!'};
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Enable = 'off';
            app.ConnectomeDropDown.Value = 'No connectome found!';
            app.PulseTypeLabel = uilabel(app.Panel_2);
            app.PulseTypeLabel.Text = 'Pulse Type:';
            app.PulseTypeDropDown = uidropdown(app.Panel_2);
            app.PulseTypeDropDown.Items = {'Train', 'Triangle', 'Ramp-Up', 'Ramp-Down'};
            app.PulseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseTypeDropDownValueChanged, true);
            app.PulseTypeDropDown.Enable = 'off';
            app.PulseTypeDropDown.Value = 'Train';
            app.CableModelLabel = uilabel(app.Panel_2);
            app.CableModelLabel.Text = 'Cable Model:';
            app.CableModelDropDown = uidropdown(app.Panel_2);
            app.CableModelDropDown.Items = {'McNeal1976', 'MRG2002', 'MRG2002_DS'};
            app.CableModelDropDown.ValueChangedFcn = createCallbackFcn(app, @CableModelDropDownValueChanged, true);
            app.CableModelDropDown.Enable = 'off';
            app.CableModelDropDown.Value = 'McNeal1976';
            app.WidthEditFieldLabel = uilabel(app.Panel_2);
            app.WidthEditFieldLabel.Text = 'Width:';
            app.WidthEditField = uieditfield(app.Panel_2, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Enable = 'off';
            app.WidthEditField.Value = 60;
            app.sLabel = uilabel(app.Panel_2);
            app.sLabel.Text = 'µs';
            app.EstimatePathwayActivationButton = uibutton(app.Panel_2, 'state');
            app.EstimatePathwayActivationButton.ValueChangedFcn = createCallbackFcn(app, @EstimatePathwayActivationButtonValueChanged, true);
            app.EstimatePathwayActivationButton.Text = {'Estimate '; 'Pathway Activation'};
            app.AxonlengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.AxonlengthEditField.ValueChangedFcn = createCallbackFcn(app, @AxonlengthEditFieldValueChanged, true);
            app.AxonlengthEditField.Enable = 'off';
            app.AxonlengthEditField.Value = 11.1;
            app.mmLabel = uilabel(app.Panel_2);
            app.mmLabel.Text = 'mm';
            app.AxonDiamLengthLabel = uilabel(app.Panel_2);
            app.AxonDiamLengthLabel.Text = {'Axon Diam:'; ''; 'Length:'};
            app.AxonDiamLengthDropDown = uidropdown(app.Panel_2);
            app.AxonDiamLengthDropDown.Items = {'2.0 µm', '3.0 µm', '5.7 µm', '7.3 µm', '8.7 µm', '10.0 µm', '12.8 µm'};
            app.AxonDiamLengthDropDown.ValueChangedFcn = createCallbackFcn(app, @AxonDiamLengthDropDownValueChanged, true);
            app.AxonDiamLengthDropDown.Enable = 'off';
            app.AxonDiamLengthDropDown.Value = '3.0 µm';
            app.CustomButton = uibutton(app.Panel_2, 'push');
            app.CustomButton.ButtonPushedFcn = createCallbackFcn(app, @CustomButtonPushed, true);
            app.CustomButton.Enable = 'off';
            app.CustomButton.Tooltip = {'Provide specific axon parameters for each pathway'};
            app.CustomButton.Text = 'Custom';
            app.AxonsintersectedbyleadareButtonGroup = uibuttongroup(app.Panel_2);
            app.AxonsintersectedbyleadareButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxonsintersectedbyleadareButtonGroupSelectionChanged, true);
            app.AxonsintersectedbyleadareButtonGroup.Title = 'Axons intersected by lead are';
            app.damagednonactiveButton = uiradiobutton(app.AxonsintersectedbyleadareButtonGroup);
            app.damagednonactiveButton.Text = 'damaged (non-active)';
            app.damagednonactiveButton.Value = true;
            app.activatedButton = uiradiobutton(app.AxonsintersectedbyleadareButtonGroup);
            app.activatedButton.Text = 'activated';
            app.activatednearactivecontactsButton = uiradiobutton(app.AxonsintersectedbyleadareButtonGroup);
            app.activatednearactivecontactsButton.Text = 'activated near active contacts';
            app.SymmetricBiphasicCheckBox = uicheckbox(app.Panel_2);
            app.SymmetricBiphasicCheckBox.ValueChangedFcn = createCallbackFcn(app, @SymmetricBiphasicCheckBoxValueChanged, true);
            app.SymmetricBiphasicCheckBox.Enable = 'off';
            app.SymmetricBiphasicCheckBox.Text = 'Symmetric Biphasic';
            app.Image5 = uiimage(app.Panel_2);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'icons', 'pam4.png');
            app.pPAMButton = uibutton(app.Panel_2, 'push');
            app.pPAMButton.ButtonPushedFcn = createCallbackFcn(app, @pPAMButtonPushed, true);
            app.pPAMButton.Enable = 'off';
            app.pPAMButton.Text = 'pPAM';
            app.Panel_3 = uipanel(app.OSSDBSSettingsUIFigure);
            app.SegmentationModelButtonGroup = uibuttongroup(app.Panel_3);
            app.SegmentationModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentationModelButtonGroupSelectionChanged, true);
            app.SegmentationModelButtonGroup.Title = 'Segmentation Model';
            app.SPMButton = uiradiobutton(app.SegmentationModelButtonGroup);
            app.SPMButton.Text = 'SPM';
            app.SPMButton.Value = true;
            app.SynthSegButton = uiradiobutton(app.SegmentationModelButtonGroup);
            app.SynthSegButton.Text = 'SynthSeg';
            app.AtlasBasedButton = uiradiobutton(app.SegmentationModelButtonGroup);
            app.AtlasBasedButton.Text = 'Atlas Based';
            app.AnisotropyModelButtonGroup = uibuttongroup(app.Panel_3);
            app.AnisotropyModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnisotropyModelButtonGroupSelectionChanged, true);
            app.AnisotropyModelButtonGroup.Title = 'Anisotropy Model';
            app.IsotropicButton = uiradiobutton(app.AnisotropyModelButtonGroup);
            app.IsotropicButton.Text = 'Isotropic';
            app.IsotropicButton.Value = true;
            app.NormativeButton = uiradiobutton(app.AnisotropyModelButtonGroup);
            app.NormativeButton.Text = 'Normative';
            app.PatientSpecificButton = uiradiobutton(app.AnisotropyModelButtonGroup);
            app.PatientSpecificButton.Enable = 'off';
            app.PatientSpecificButton.Text = 'Patient-Specific';
            app.UseAdaptiveMeshRefinementCheckBox = uicheckbox(app.Panel_3);
            app.UseAdaptiveMeshRefinementCheckBox.Enable = 'off';
            app.UseAdaptiveMeshRefinementCheckBox.Text = 'Use Adaptive Mesh Refinement';
            app.EncapsulationButtonGroup = uibuttongroup(app.Panel_3);
            app.EncapsulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EncapsulationButtonGroupSelectionChanged, true);
            app.EncapsulationButtonGroup.Title = 'Encapsulation';
            app.ChronicButton = uiradiobutton(app.EncapsulationButtonGroup);
            app.ChronicButton.Text = 'Chronic';
            app.AcuteButton = uiradiobutton(app.EncapsulationButtonGroup);
            app.AcuteButton.Text = 'Acute';
            app.NoneButton = uiradiobutton(app.EncapsulationButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.ConductivityButtonGroup = uibuttongroup(app.Panel_3);
            app.ConductivityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConductivityButtonGroupSelectionChanged, true);
            app.ConductivityButtonGroup.Title = 'Conductivity';
            app.ColeCole4Button = uiradiobutton(app.ConductivityButtonGroup);
            app.ColeCole4Button.Text = 'ColeCole4';
            app.ColeCole4Button.Value = true;
            app.ColeCole3Button = uiradiobutton(app.ConductivityButtonGroup);
            app.ColeCole3Button.Text = 'ColeCole3';
            app.HomogeneousButton = uiradiobutton(app.ConductivityButtonGroup);
            app.HomogeneousButton.Text = 'Homogeneous';
            app.SmLabel = uilabel(app.Panel_3);
            app.SmLabel.Text = '(0.2 S/m)';
            app.Image4 = uiimage(app.OSSDBSSettingsUIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'icons', 'long_blank.png');
            app.Panel = uipanel(app.OSSDBSSettingsUIFigure);
            app.VmLabel = uilabel(app.Panel);
            app.VmLabel.Text = 'V/m';
            app.EthresholdLabel = uilabel(app.Panel);
            app.EthresholdLabel.Text = '||E||-threshold';
            app.EthresholdEditField = uieditfield(app.Panel, 'numeric');
            app.EthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @EthresholdEditFieldValueChanged, true);
            app.EthresholdEditField.Tooltip = {'Electric field magnitude, above which the tissue is "activated"'};
            app.EthresholdEditField.Value = 200;
            app.RemoveLeadCheckBox = uicheckbox(app.Panel);
            app.RemoveLeadCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveLeadCheckBoxValueChanged, true);
            app.RemoveLeadCheckBox.Text = 'Remove Lead';
            app.RemoveLeadCheckBox.Value = true;
            app.EstimateStimulationVolumeButton = uibutton(app.Panel, 'state');
            app.EstimateStimulationVolumeButton.ValueChangedFcn = createCallbackFcn(app, @EstimateStimulationVolumeButtonValueChanged, true);
            app.EstimateStimulationVolumeButton.Text = {'Estimate '; 'Stimulation Volume'};
            app.EstimateStimulationVolumeButton.Value = true;
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'vta.png');
            app.PWAdaptiveCheckBox = uicheckbox(app.Panel);
            app.PWAdaptiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @PWAdaptiveCheckBoxValueChanged, true);
            app.PWAdaptiveCheckBox.Text = 'PW-Adaptive';
            app.OSSDBSadvancebrainstimulationmodelingLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.OSSDBSadvancebrainstimulationmodelingLabel.Text = 'OSS-DBS: advance brain stimulation modeling';
            app.OnstandbyLampLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.OnstandbyLampLabel.Visible = 'off';
            app.OnstandbyLampLabel.Text = 'On stand-by';
            app.OnstandbyLamp = uilamp(app.OSSDBSSettingsUIFigure);
            app.OnstandbyLamp.Visible = 'off';
            app.ZimmermannPayonkButenkoetalLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.ZimmermannPayonkButenkoetalLabel.Text = 'Zimmermann, Payonk, Butenko et al';
            app.QuestionsandsuggestionskonstantinmgtugmailcomLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.QuestionsandsuggestionskonstantinmgtugmailcomLabel.Text = 'Questions and suggestions konstantinmgtu@gmail.com';
            app.SaveButton = uibutton(app.OSSDBSSettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image3 = uiimage(app.OSSDBSSettingsUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'icons', 'blank.png');
            app.AdvancedSettingsLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.AdvancedSettingsLabel.Text = ' Advanced Settings';
            app.Image3_2 = uiimage(app.OSSDBSSettingsUIFigure);
            app.Image3_2.ImageSource = fullfile(pathToMLAPP, 'icons', 'blank.png');
            app.PAMLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.PAMLabel.Text = ' PAM';
            app.VATLabel = uilabel(app.OSSDBSSettingsUIFigure);
            app.VATLabel.Text = ' VAT';
            app.SettingsInfoButton = uibutton(app.OSSDBSSettingsUIFigure, 'push');
            app.SettingsInfoButton.ButtonPushedFcn = createCallbackFcn(app, @SettingsInfoButtonPushed, true);
            app.SettingsInfoButton.Tooltip = {'Show figures that explain the setting options on the GUI'};
            app.SettingsInfoButton.Text = 'Settings Info';
            % Show the figure after all components are created
            app.OSSDBSSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_vatsettings_butenko(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OSSDBSSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OSSDBSSettingsUIFigure)
        end
    end
end