classdef ea_calculate_dbsmapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveSettingsButton           matlab.ui.control.Button
        FiberfilteringCalculationMethodSwitch  matlab.ui.control.Switch
        FiberfilteringCalculationMethodSwitchLabel  matlab.ui.control.Label
        CalculateinDropDown          matlab.ui.control.DropDown
        CalculateinDropDownLabel     matlab.ui.control.Label
        ResolutionDropDown           matlab.ui.control.DropDown
        ResolutionDropDownLabel      matlab.ui.control.Label
        VmLabel                      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditField_3Label    matlab.ui.control.Label
        ConnectomeDropDown           matlab.ui.control.DropDown
        ConnectomeDropDown_2Label    matlab.ui.control.Label
        BiophysicalModelFamilyButtonGroup  matlab.ui.container.ButtonGroup
        PathwayActionModelingButton  matlab.ui.control.RadioButton
        StimulationVolumesButton     matlab.ui.control.RadioButton
        ToolsButtonGroup             matlab.ui.container.ButtonGroup
        NetworkmappingButton         matlab.ui.control.RadioButton
        FiberfilteringButton         matlab.ui.control.RadioButton
        SweetspotButton              matlab.ui.control.RadioButton
        CalculationSettingsPanel     matlab.ui.container.Panel
    end

    
    properties (Access = public)
        voxtractobj % tractset like object which will save the calculate settings!
    end
    
    
    methods (Access = public)
        
        function results = enable_rules(app)
            selectedTool = app.ToolsButtonGroup.SelectedObject;
            if strcmp(selectedTool.Text,'Sweetspot')
                app.ConnectomeDropDown_2Label.Enable = 'off';
                app.ConnectomeDropDown.Enable = 'off';
                app.ResolutionDropDownLabel.Enable = 'off';
                app.ResolutionDropDown.Enable = 'off';
                app.CalculateinDropDownLabel.Enable = 'off';
                app.CalculateinDropDown.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                app.PathwayActionModelingButton.Enable = 'off';
                app.StimulationVolumesButton.Enable = 'off';
            elseif strcmp(selectedTool.Text,'Fiberfiltering')
                app.ConnectomeDropDown_2Label.Enable = 'on';
                app.ConnectomeDropDown.Enable = 'on';
                [mdl,sf]=ea_genmodlist;
                dmri_connectomenames = mdl(sf==1);
                dmri_mtconnectomenames = mdl(sf==3);
                app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
                app.ResolutionDropDownLabel.Enable = 'off';
                app.ResolutionDropDown.Enable = 'off';
                app.CalculateinDropDownLabel.Enable = 'on';
                app.CalculateinDropDown.Enable = 'on';
                app.FiberfilteringCalculationMethodSwitch.Enable = 'on';
                app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'on';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'on';
                app.PathwayActionModelingButton.Enable = 'on';
                app.StimulationVolumesButton.Enable = 'on';
            elseif strcmp(selectedTool.Text,'Networkmapping')
                app.ConnectomeDropDown_2Label.Enable = 'on';
                app.ConnectomeDropDown.Enable = 'on';
                [mdl,sf]=ea_genmodlist;
                app.ConnectomeDropDown.Items=mdl;
                [~,ix]=ismember(app.ConnectomeDropDown.Value,mdl);
                switch sf(ix)
                    case 1 % structural connectome selected
                        app.ResolutionDropDown.Enable = 'on';
                        app.ResolutionDropDownLabel.Enable = 'on';
                    case 2 % functional connectome selected
                        app.ResolutionDropDown.Enable = 'off';
                        app.ResolutionDropDown.Value = '2 mm';
                end
                app.CalculateinDropDownLabel.Enable = 'off';
                app.CalculateinDropDown.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
                app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                app.PathwayActionModelingButton.Enable = 'off';
                app.StimulationVolumesButton.Enable = 'off';
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, voxtract)
            if ~exist('voxtract','var')
                ea_error('Something went wrong! try again!');
            else
                app.voxtractobj = voxtract;
            end
            app.SaveSettingsButton.BackgroundColor = [0 1 0];
            %check if something has been saved
            app.UIFigure.Name = 'Calculate Settings';
            initialize_settings(app)
            function initialize_settings(app)
                %tools button group
                if app.voxtractobj.calcsettings.selectedTool == 1
                    app.SweetspotButton.Value = 1;
                elseif app.voxtractobj.calcsettings.selectedTool == 2
                    app.FiberfilteringButton.Value = 1;
                elseif app.voxtractobj.calcsettings.selectedTool == 3
                    app.NetworkmappingButton.Value = 1;
                end
                enable_rules(app);
                %connectome
                if ~isempty(app.voxtractobj.calcsettings.connectome)
                    app.ConnectomeDropDown.Value = app.voxtractobj.calcsettings.connectome;
                end
                %threshold
                app.ThresholdEditField.Value = app.voxtractobj.calcsettings.calcthreshold;
                %resolution
                app.ResolutionDropDown.Value = app.voxtractobj.calcsettings.resolution;
                %calculate in
                if app.voxtractobj.calcsettings.calcspace == 1
                    app.CalculateinDropDown.Value = 'Template Space';
                elseif app.voxtractobj.calcsettings.calcspace == 2
                    app.CalculateinDropDown.Value = 'Native Space';
                end
                %Fiberfiltering calculation method
                if app.voxtractobj.calcsettings.calcmethod == 1
                    app.FiberfilteringCalculationMethodSwitch.Value = 'E-field Based Method';
                elseif app.voxtractobj.calcsettings.calcmethod == 2
                    app.FiberfilteringCalculationMethodSwitch.Value = 'Fiber Based Method';
                end
                %biophysical model family
                if app.voxtractobj.calcsettings.switch_connectivity == 1
                    if app.voxtractobj.calcsettings.connectivity_type == 1
                        app.BiophysicalModelFamilyButtonGroup.SelectedObject.Text = 'Stimulation Volumes';
                    elseif app.voxtractobj.calcsettings.connectivity_type == 2
                        app.BiophysicalModelFamilyButtonGroup.SelectedObject.Text = 'Pathway Action Modeling';
                    end
                end
            end

        end

        % Selection changed function: ToolsButtonGroup
        function ToolsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToolsButtonGroup.SelectedObject;
            enable_rules(app);
           
            
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            selectedButton = app.ToolsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Sweetspot')
                selectedTool = 1;
            elseif strcmp(selectedButton.Text,'Fiberfiltering')
                selectedTool = 2;
            elseif strcmp(selectedButton.Text,'Networkmapping')
                selectedTool = 3;
            end
            app.voxtractobj.calcsettings.selectedTool = selectedTool;
            app.voxtractobj.calcsettings.connectome = app.ConnectomeDropDown.Value;
            
            %app.voxtractobj.calcsettings.calcthreshold = app.ThresholdEditField.Value;
            app.voxtractobj.calcsettings.space = app.CalculateinDropDown.Value;
            app.voxtractobj.calcsettings.calculateUsing = app.FiberfilteringCalculationMethodSwitch.Value;
            app.voxtractobj.calcsettings.connectivityModel = app.BiophysicalModelFamilyButtonGroup.SelectedObject;
            setappdata(app.voxtractobj.resultfig, 'voxtract', app.voxtractobj);
            app.SaveSettingsButton.BackgroundColor = [1 1 1];
            delete(app);
        end

        % Value changed function: FiberfilteringCalculationMethodSwitch
        function FiberfilteringCalculationMethodSwitchValueChanged(app, event)
            value = app.FiberfilteringCalculationMethodSwitch.Value;
            if strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'E-field Based Method')
                app.CalculateinDropDown.Value = 'Template Space';
                app.CalculateinDropDown.Enable = 'off';
                app.voxtractobj.calcsettings.calcspace = 1;
                app.voxtractobj.calcsettings.calmethod = 1;
            elseif strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'Fiber Based Method')
                app.CalculateinDropDown.Value = 'Native Space';
                app.CalculateinDropDown.Enable = 'off';
                app.voxtractobj.calcsettings.calcspace = 2;
                app.voxtractobj.calcsettings.calcmethod = 2;
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.voxtractobj.calcsettings.calcthreshold = value; %check this!!!
        end

        % Selection changed function: BiophysicalModelFamilyButtonGroup
        function BiophysicalModelFamilyButtonGroupSelectionChanged(app, event)
            selectedButton = app.BiophysicalModelFamilyButtonGroup.SelectedObject;
            app.voxtractobj.calcsettings.switch_connectivity = 1;
            if strcmp(selectedButton,'Stimulation Vol')
                app.voxtractobj.calcsettings.connectivity_type = 1;
            elseif strcmp(selectedButton,'PAM')
                app.voxtractobj.calcsettings.connectivity_type = 2;
            end
           %TODO - Highly interlinked with the rest of the stats button, so
           %you need to coordinate!
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            value = app.ResolutionDropDown.Value;
            app.voxtractobj.calcsettings.resolution = value;
        end

        % Value changed function: CalculateinDropDown
        function CalculateinDropDownValueChanged(app, event)
            value = app.CalculateinDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculationSettingsPanel = uipanel(app.UIFigure);
            app.CalculationSettingsPanel.Title = 'Calculation Settings';
            app.ToolsButtonGroup = uibuttongroup(app.UIFigure);
            app.ToolsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToolsButtonGroupSelectionChanged, true);
            app.ToolsButtonGroup.Title = 'Tools';
            app.SweetspotButton = uiradiobutton(app.ToolsButtonGroup);
            app.SweetspotButton.Text = 'Sweetspot';
            app.SweetspotButton.Value = true;
            app.FiberfilteringButton = uiradiobutton(app.ToolsButtonGroup);
            app.FiberfilteringButton.Text = 'Fiberfiltering';
            app.NetworkmappingButton = uiradiobutton(app.ToolsButtonGroup);
            app.NetworkmappingButton.Text = 'Networkmapping';
            app.BiophysicalModelFamilyButtonGroup = uibuttongroup(app.UIFigure);
            app.BiophysicalModelFamilyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BiophysicalModelFamilyButtonGroupSelectionChanged, true);
            app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
            app.BiophysicalModelFamilyButtonGroup.Title = 'Biophysical Model Family';
            app.StimulationVolumesButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.StimulationVolumesButton.Enable = 'off';
            app.StimulationVolumesButton.Text = 'Stimulation Volumes';
            app.StimulationVolumesButton.Value = true;
            app.PathwayActionModelingButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.PathwayActionModelingButton.Enable = 'off';
            app.PathwayActionModelingButton.Text = 'Pathway Action Modeling';
            app.ConnectomeDropDown_2Label = uilabel(app.UIFigure);
            app.ConnectomeDropDown_2Label.Enable = 'off';
            app.ConnectomeDropDown_2Label.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.UIFigure);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.Enable = 'off';
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditField_3Label = uilabel(app.UIFigure);
            app.ThresholdEditField_3Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 200;
            app.VmLabel = uilabel(app.UIFigure);
            app.VmLabel.Text = 'V/m';
            app.ResolutionDropDownLabel = uilabel(app.UIFigure);
            app.ResolutionDropDownLabel.Enable = 'off';
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.UIFigure);
            app.ResolutionDropDown.Items = {'2 mm', '1 mm', '0.5 mm'};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Enable = 'off';
            app.ResolutionDropDown.Value = '2 mm';
            app.CalculateinDropDownLabel = uilabel(app.UIFigure);
            app.CalculateinDropDownLabel.Enable = 'off';
            app.CalculateinDropDownLabel.Text = 'Calculate in...';
            app.CalculateinDropDown = uidropdown(app.UIFigure);
            app.CalculateinDropDown.Items = {'Template Space', 'Native Space'};
            app.CalculateinDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateinDropDownValueChanged, true);
            app.CalculateinDropDown.Enable = 'off';
            app.CalculateinDropDown.Value = 'Template Space';
            app.FiberfilteringCalculationMethodSwitchLabel = uilabel(app.UIFigure);
            app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
            app.FiberfilteringCalculationMethodSwitchLabel.Text = 'Fiberfiltering Calculation Method';
            app.FiberfilteringCalculationMethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.FiberfilteringCalculationMethodSwitch.Items = {'E-field Based Method', 'Fiber Based Method'};
            app.FiberfilteringCalculationMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @FiberfilteringCalculationMethodSwitchValueChanged, true);
            app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
            app.FiberfilteringCalculationMethodSwitch.Value = 'E-field Based Method';
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_calculate_dbsmapping(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end