classdef ea_explorer_lite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ToolsMenu                    matlab.ui.container.Menu
        RecreatevisualizationfigureMenu  matlab.ui.container.Menu
        AnalysisIDEditField          matlab.ui.control.EditField
        AnalysisIDEditFieldLabel     matlab.ui.control.Label
        FastRenderCheckBox           matlab.ui.control.CheckBox
        generationPanel              matlab.ui.container.Panel
        mmLabel                      matlab.ui.control.Label
        ResolutionEditField          matlab.ui.control.NumericEditField
        ResolutionEditFieldLabel     matlab.ui.control.Label
        VmLabel                      matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        ConnectomeDropDown           matlab.ui.control.DropDown
        ConnectomeDropDownLabel      matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        dataPanel                    matlab.ui.container.Panel
        SubcohortsDropDown           matlab.ui.control.DropDown
        SubcohortsDropDownLabel      matlab.ui.control.Label
        VariableDropDown             matlab.ui.control.DropDown
        VariableDropDownLabel        matlab.ui.control.Label
        PatientsListBox              matlab.ui.control.ListBox
        PatientsListBoxLabel         matlab.ui.control.Label
        MirrorSidesCheckBox          matlab.ui.control.CheckBox
        stimstatsPanel               matlab.ui.container.Panel
        Connthreshold                matlab.ui.control.EditField
        Efieldthreshold              matlab.ui.control.EditField
        StatFamilyLabel              matlab.ui.control.Label
        StatTestLabel                matlab.ui.control.Label
        StatH0Label                  matlab.ui.control.Label
        EfieldmetricLabel            matlab.ui.control.Label
        StimulationModelLabel        matlab.ui.control.Label
        StatFamilyDropDown           matlab.ui.control.DropDown
        StatTestDropDown             matlab.ui.control.DropDown
        StatH0DropDown               matlab.ui.control.DropDown
        StimulationModel             matlab.ui.control.DropDown
        Efieldmetric                 matlab.ui.control.DropDown
        EfieldthresholdLabel         matlab.ui.control.Label
        EfieldthresholdSlider        matlab.ui.control.Slider
        ConnthresholdLabel           matlab.ui.control.Label
        ConnthresholdSlider          matlab.ui.control.Slider
        threshvisPanel               matlab.ui.container.Panel
        FiberLampON                  matlab.ui.control.Lamp
        VoxelLampON                  matlab.ui.control.Lamp
        FibersSwitch                 matlab.ui.control.Switch
        FibersSwitchLabel            matlab.ui.control.Label
        VoxelsSwitch                 matlab.ui.control.Switch
        VoxelsSwitchLabel            matlab.ui.control.Label
        SaveResultsButton            matlab.ui.control.Button
        LoadResultsButton            matlab.ui.control.Button
        ExportResultsButton          matlab.ui.control.Button
        ThresholdingDropDown         matlab.ui.control.DropDown
        ThresholdingDropDownLabel    matlab.ui.control.Label
        ColorButtonPositive          matlab.ui.control.Button
        levelEditFieldLabel          matlab.ui.control.Label
        levelEditField               matlab.ui.control.NumericEditField
        ShowAmountLabelNegative      matlab.ui.control.Label
        ShowAmountLabelPositive      matlab.ui.control.Label
        ColorButtonNegative          matlab.ui.control.Button
        ShowPositiveCheckBox         matlab.ui.control.CheckBox
        ShowNegativeCheckBox         matlab.ui.control.CheckBox
        ShowonlysignificantCheckBox  matlab.ui.control.CheckBox
        MultTestStrategy             matlab.ui.control.DropDown
        ShowAmountEditFieldPositive  matlab.ui.control.NumericEditField
        ShowAmountEditFieldNegative  matlab.ui.control.NumericEditField
        CalculateVisualizeButton     matlab.ui.control.Button
        ColorBar                     matlab.ui.control.UIAxes
        BusyLamp                     matlab.ui.control.Lamp
        BusyLampLabel                matlab.ui.control.Label
    end


    properties (Access = public)
        tractset % associated ea_explorerclass object.
    end

    methods (Access = private)
        function drawcb(app)
            app.isbusy(1);
                if app.tractset.statsettings.doFibers
                    cb = app.tractset.colorbar.fibers;
                else
                    cb = app.tractset.colorbar.voxels;
                end
                if ~isempty(cb)
                    app.ColorBar.Visible = 'on';
                    % ea_plot_colorbar(cb.cmap, [], 'h', '', cb.tick, cb.ticklabel, app.ColorBar);
                    ea_plot_colorbar(cb.cmap, [], 'h', '', [], [], app.ColorBar);
                    % app.ColorBar.Visible = 'on';
                    % cb = app.tractset.colorbar.fibers;
                    % ea_plot_colorbar(cb.cmap, [], 'h', '', cb.tick, cb.ticklabel, app.ColorBar);
                else
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                end
                app.isbusy(0);
        end

        function isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
            drawnow
        end


        function setefieldsliders(app)
            %% Set slider limits
            switch app.tractset.statsettings.stimulationmodel
                case 'Sigmoid Field'
                    if app.tractset.statsettings.efieldthreshold > 1
                        app.tractset.statsettings.efieldthreshold = ea_SigmoidFromEfield(app.tractset.statsettings.efieldthreshold);
                    end
                    app.EfieldthresholdSlider.Limits=[round(ea_SigmoidFromEfield(app.tractset.calcthreshold),2),1]; % probability activation
                case 'Electric Field'
                    app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                    if app.tractset.statsettings.efieldthreshold < 1
                        app.tractset.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.tractset.statsettings.efieldthreshold);
                    end
                case 'VTA'
                    app.EfieldthresholdSlider.Limits=[app.tractset.calcthreshold,500];
                    if app.tractset.statsettings.efieldthreshold < 1
                        app.tractset.statsettings.efieldthreshold = ea_EfieldFromSigmoid(app.tractset.statsettings.efieldthreshold);
                    end
            end
            %% Check if threshold is outside the limits
            if app.tractset.statsettings.efieldthreshold < app.EfieldthresholdSlider.Limits(1)
                app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(1);
            elseif app.tractset.statsettings.efieldthreshold > app.EfieldthresholdSlider.Limits(2)
                app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Limits(2);
            end
            app.EfieldthresholdSlider.Value=app.tractset.statsettings.efieldthreshold;
            app.Efieldthreshold.Value=num2str(app.tractset.statsettings.efieldthreshold);
            %% Set labels
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.2f',app.EfieldthresholdSlider.Value)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
            end

        end
        function result = ea_isnan(app,input_array,flag)
            if size(input_array,2) > 1
                if strcmp(flag,'any')
                    op = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    op = all(isnan(input_array));
                end
                if length(find(op)) > 1
                    result = 1;
                else
                    result = 0;

                end
            else
                if strcmp(flag,'any')
                    result = any(isnan(input_array));
                elseif strcmp(flag,'all')
                    result = all(isnan(input_array));
                end
            end
        end

        function ea_explorer_tractset2UI(app,flag)
            app.isbusy(1);
            %% derive info from tractset obj:
            % Basic Info and Generation
            app.AnalysisIDEditField.Value = app.tractset.ID;
            if ~isempty(app.tractset.connectome) && ismember(app.tractset.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.tractset.connectome;
            end
            app.MirrorSidesCheckBox.Value = app.tractset.mirrorsides;
            app.ResolutionEditField.Value = app.tractset.resolution;
            app.ThresholdEditField.Value = app.tractset.calcthreshold;
            % Patients
            app.PatientsListBox.Items=app.tractset.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...'},{'All Patients'},{'Set from Selection'},{'Inverse Set from Selection'},...
                app.tractset.setlabels];
            for set=1:length(app.tractset.setlabels)
                if isequal(app.tractset.patientselection,find(app.tractset.setselections{set}))
                    app.SubcohortsDropDown.Value=app.tractset.setlabels{set};
                end
            end
            if isequal(app.tractset.patientselection,1:length(app.tractset.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end
            % Clinical Variables
            app.VariableDropDown.Items = app.tractset.M.clinical.labels;
            if ismember(app.tractset.responsevarlabel,app.tractset.M.clinical.labels)
                app.VariableDropDown.Value = app.tractset.responsevarlabel;
            else
                app.VariableDropDown.Value = app.tractset.M.clinical.labels{1};
            end
            % set stimstats GUI
            app.pullstatsettings;
            if ~isempty(app.tractset.statsettings.efieldthreshold)
                app.setefieldsliders;
                app.EfieldthresholdSlider.Value = app.tractset.statsettings.efieldthreshold;
                app.Efieldthreshold.Value = num2str(app.tractset.statsettings.efieldthreshold);
            end
            % set threshvis GUI
            app.pullthresholdingsettings;
            %% Crosspred shit fix later
            % refresh enablerules
            app.isbusy(0);
        end

        function ea_explorer_ui_enablerules(app,flag)
            app.isbusy(1);
            %% set enablestate according to app.tractset.modulestate
            for i=1:numel(app.generationPanel.Children)
                if isprop(app.generationPanel.Children(i),'Enable')
                    app.generationPanel.Children(i).Enable = app.tractset.modulestate.generation;
                elseif isprop(app.generationPanel.Children(i),'Visible')
                    app.generationPanel.Children(i).Visible = app.tractset.modulestate.generation;
                end
            end
            for i=1:numel(app.dataPanel.Children)
                if isprop(app.dataPanel.Children(i),'Enable')
                    app.dataPanel.Children(i).Enable = app.tractset.modulestate.data;
                elseif isprop(app.dataPanel.Children(i),'Visible')
                    app.dataPanel.Children(i).Visible = app.tractset.modulestate.data;
                end
            end
            for i=1:numel(app.stimstatsPanel.Children)
                if isprop(app.stimstatsPanel.Children(i),'Enable')
                    app.stimstatsPanel.Children(i).Enable = app.tractset.modulestate.stimstats;
                elseif isprop(app.stimstatsPanel.Children(i),'Visible')
                    app.stimstatsPanel.Children(i).Visible = app.tractset.modulestate.stimstats;
                end
            end
            for i=1:numel(app.threshvisPanel.Children)
                if isprop(app.threshvisPanel.Children(i),'Enable')
                    app.threshvisPanel.Children(i).Enable = app.tractset.modulestate.threshvis;
                elseif isprop(app.threshvisPanel.Children(i),'Visible')
                    app.threshvisPanel.Children(i).Visible = app.tractset.modulestate.threshvis;
                end
            end
            %% Stimulation Setup GUI
            switch app.StatFamilyDropDown.Value
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
            end

            app.ColorBar.Visible = app.tractset.modulestate.threshvis;
            if ~app.tractset.modulestate.threshvis
                cla(app.ColorBar);
            end
            app.setVisualizeButtonColors;
            % turn light back to green
            app.isbusy(0);
        end

        %% Push statsettings from GUI to tractset
        function pushstatsettings(app)
            app.tractset.statsettings.stimulationmodel = app.StimulationModel.Value;
            app.tractset.statsettings.efieldmetric = app.Efieldmetric.Value; % if statmetric == ;Correlations / E-fields (Irmen 2020)p, efieldmetric can calculate sum, mean or peak along tracts
            app.tractset.statsettings.efieldthreshold = app.EfieldthresholdSlider.Value;
            app.tractset.statsettings.connthreshold = app.ConnthresholdSlider.Value;
            app.tractset.statsettings.statfamily = app.StatFamilyDropDown.Value; % the
            app.tractset.statsettings.stattest = app.StatTestDropDown.Value;
            app.tractset.statsettings.H0 = app.StatH0DropDown.Value;
            app.tractset.statsettings.doVoxels = app.VoxelsSwitch.Value;
            app.tractset.statsettings.doFibers = app.FibersSwitch.Value;
            app.setVisualizeButtonColors;
        end
        %% Push thresholding from GUI to tractset
        function pushthresholdingsettings(app)
            app.tractset.thresholding.showsignificantonly = app.ShowonlysignificantCheckBox.Value;
            app.tractset.thresholding.alphalevel = app.levelEditField.Value;
            app.tractset.thresholding.multcompstrategy = app.MultTestStrategy.Value;
            app.tractset.thresholding.threshstrategy = app.ThresholdingDropDown.Value;
            app.tractset.thresholding.posvisible = app.ShowPositiveCheckBox.Value;
            app.tractset.thresholding.negvisible = app.ShowNegativeCheckBox.Value;
            app.tractset.thresholding.showposamount = [app.ShowAmountEditFieldPositive.Value,app.ShowAmountEditFieldPositive.Value];
            app.tractset.thresholding.shownegamount = [app.ShowAmountEditFieldNegative.Value,app.ShowAmountEditFieldNegative.Value];
            app.tractset.thresholding.poscolor = app.ColorButtonPositive.BackgroundColor;
            app.tractset.thresholding.negcolor = app.ColorButtonNegative.BackgroundColor;
            app.setVisualizeButtonColors;
        end
        %% Pull statsettings from input or tractset to GUI
        function pullstatsettings(app,mystatsettings)
            if exist('mystatsettings','var')
                app.tractset.statsettings = mystatsettings;
            end
            app.enforcestatrules;
            drawnow
        end
        %% Enforce Rules for stat behavior
        function enforcestatrules(app)
            warning('on','all')
            app.StimulationModel.Value=app.tractset.statsettings.stimulationmodel;
            %% Enforce Rules Based on the Model
            testidx=cellfun(@ismember,app.tractset.stattests.compatibility,repmat({app.tractset.statsettings.stimulationmodel},size(app.tractset.stattests.compatibility)),'UniformOutput',false);
            testidx=cellfun(@any,testidx);
            tmptests =app.tractset.stattests(testidx,:);
            if ~any(app.tractset.statsettings.statfamily==tmptests.type)
                %set family to default
                app.tractset.statsettings.statfamily = tmptests.type(1);
                app.StatFamilyDropDown.Items = unique(tmptests.type);
                app.StatFamilyDropDown.Value= char(tmptests.type(1));
                % set tests to default
                % app.tractset.statsettings.statest=tmptests.name(1);
                % app.StatTestDropDown.Items=tmptests.name;
                % app.StatTestDropDown.Value=tmptests.name(1);
                warning('off','backtrace')
                warning('Test Family reset according to Stimulation Model!')
                warning('on','backtrace')
            else % set family in GUI
                app.StatFamilyDropDown.Items = unique(tmptests.type);
                app.StatFamilyDropDown.Value=char(app.tractset.statsettings.statfamily);
            end
            %% Enforce Rules Based on Test Family
            tmptests=tmptests(app.tractset.statsettings.statfamily==tmptests.type,:);
            if ~any(app.tractset.statsettings.stattest==tmptests.name)
                app.tractset.statsettings.statest=tmptests.name(1);
                app.StatTestDropDown.Items=tmptests.name;
                app.StatTestDropDown.Value=char(tmptests.name(1));
                warning('off','backtrace')
                warning('Statistical Test set to Default for this Test Family!')
                warning('on','backtrace')
            else
                app.StatTestDropDown.Items=tmptests.name;
                app.StatTestDropDown.Value=char(app.tractset.statsettings.stattest);
            end
            %%
            app.StatH0DropDown.Value=app.tractset.statsettings.H0;
            switch app.tractset.statsettings.statfamily
                case '1-Sample Tests'
                    app.StatH0DropDown.Enable = true;
                otherwise
                    app.StatH0DropDown.Enable = false;
            end
            %% Switches for Voxels/Fibers
            app.VoxelsSwitch.Value = app.tractset.statsettings.doVoxels;
            app.FibersSwitch.Value = app.tractset.statsettings.doFibers;
            app.VoxelLampON.Visible = app.tractset.statsettings.doVoxels;
            app.FiberLampON.Visible = app.tractset.statsettings.doFibers;
            drawnow
            app.drawcb
        end

        %% Pull thresholding from input or tractset to GUI
        function pullthresholdingsettings(app,mythresholding)
            if exist('mythresholding','var')
                tmpthresholding=mythresholding;
            else
                tmpthresholding=app.tractset.thresholding;
            end
            app.ShowonlysignificantCheckBox.Value = tmpthresholding.showsignificantonly;
            app.levelEditField.Value = tmpthresholding.alphalevel;
            app.MultTestStrategy.Value = tmpthresholding.multcompstrategy;
            app.ThresholdingDropDown.Value = tmpthresholding.threshstrategy;
            app.ShowPositiveCheckBox.Value = tmpthresholding.posvisible;
            app.ShowNegativeCheckBox.Value = tmpthresholding.negvisible;
            app.ShowAmountEditFieldPositive.Value = tmpthresholding.showposamount(1);
            app.ShowAmountEditFieldNegative.Value = tmpthresholding.shownegamount(1);
            app.ColorButtonPositive.BackgroundColor = tmpthresholding.poscolor;
            app.ColorButtonNegative.BackgroundColor = tmpthresholding.negcolor;
            setthresholding(app)
            drawnow
        end
        %% Set thresholding labels
        function setthresholding(app)
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountLabelPositive.Text = 'n Vx/Fib';
                    app.ShowAmountLabelNegative.Text = 'n Vx/Fib';
                case 'Fixed Fiber Value' % edit fields
                    app.ShowAmountLabelPositive.Text = 'min. Value';
                    app.ShowAmountLabelNegative.Text = 'max. Value.';
                case 'Percentage Relative to Amount'
                    app.ShowAmountLabelPositive.Text = '% of Vx/Fib';
                    app.ShowAmountLabelNegative.Text = '% of Vx/Fib';
                case 'Percentage Relative to Peak'
                    app.ShowAmountLabelPositive.Text = '% of peak Value';
                    app.ShowAmountLabelNegative.Text = '% of peak Value';
            end
            drawnow
        end

        function setVisualizeButtonColors(app)
            if ~isfield(app.tractset.recentmodel,'statsettings') || ~isequal(app.tractset.statsettings,app.tractset.recentmodel.statsettings)
                app.CalculateVisualizeButton.Text = 'Calculate & Visualize';
                app.CalculateVisualizeButton.BackgroundColor = [1.0000    0.7882    0.0549];
            elseif isequal(app.tractset.statsettings,app.tractset.recentmodel.statsettings)
                app.CalculateVisualizeButton.Text = 'Visualize';
                app.CalculateVisualizeButton.BackgroundColor = [0.4588    0.9765    0.3020];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            warning('on','all')
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('*.mat','Please Choose Lead-Group Analysis project...');
                if isnumeric(FileName) && FileName == 0
                    ea_cprintf('CmdWinErrors', 'No group analysis selected!\n');
                    delete(app);
                    return;
                else
                    lgpath = fullfile(PathName,FileName);
                end
            elseif ~isfile(lgpath)
                ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
                delete(app);
                return;
            end
            lgpath = GetFullPath(lgpath);

            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Opening application');
            d.Value = 0;
            app.isbusy(1,'Loading');
            app.UIFigure.Name='Explorer_lite';

            % Add modlist (structural connectomes)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];

            % Add standard colors for tracts
            app.ColorButtonPositive.BackgroundColor='r';
            app.ColorButtonNegative.BackgroundColor='b';
            d.Value = 0.33;
            % Add Value to connthreshsliderlabel

            letsdraw=0; % flag to state whether to draw directly.

            varinfo=who('-file',lgpath); % check if file is a group analysis matfile or a stored disctract object with var name 'tractset'

            if ismember('tractset',varinfo)
                D = load(lgpath,'-mat');
                app.tractset = D.tractset; % load stored disctract object
                app.tractset.analysispath=lgpath;

                app.tractset.activateby={};

                % Fix tractset ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.tractset.ID, ID)
                    app.tractset.ID = ID;
                end

                % Fix group folder in case it has been moved
                tractGroupFolder = regexprep(app.tractset.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['explorer', filesep, app.tractset.ID, '.explorer'],'');
                if ~strcmp(tractGroupFolder, UIGroupFolder)
                    app.tractset.analysispath = strrep(app.tractset.analysispath, tractGroupFolder, UIGroupFolder);
                    app.tractset.leadgroup = strrep(app.tractset.leadgroup, tractGroupFolder, UIGroupFolder);
                    app.tractset.M.root = strrep(app.tractset.M.root, tractGroupFolder, UIGroupFolder);
                    if isfield(app.tractset.M,'ui')
                        app.tractset.M.ui.groupdir = strrep(app.tractset.M.ui.groupdir, tractGroupFolder, UIGroupFolder);
                    end
                end
                d.Value = 0.50;
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                app.tractset.resultfig = resultfig; % attach to figure
                if ~isempty(app.tractset.results)
                    if isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                        letsdraw=1;
                    end
                end
                %initialize for legacy support
                if ~isprop(app.tractset,'multi_pathways')
                    app.tractset.multipath.multi_pathways = 0;
                end
            else % init from M struct of LEAD group file
                app.tractset = ea_explorerclass;
                if ~exist('resultfig', 'var')
                    resultfig = ea_mnifigure;
                end
                try
                    app.tractset.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
                catch ME
                    close(resultfig);
                    app.UIFigureCloseRequest;
                    rethrow(ME);
                end
            end
            if isfield(app.tractset.M,'ui')
                if ismember(app.tractset.M.ui.connectomename, app.ConnectomeDropDown.Items)
                    app.ConnectomeDropDown.Value = app.tractset.M.ui.connectomename;
                end
            end

            %% Show Resultfig next to GUI
            appPos = app.UIFigure.Position;
            screensize = get(0,'screensize');
            figPos = [appPos(1)+appPos(3),...
                appPos(2),...
                screensize(3)-(appPos(1)+appPos(3)+10)-50,...
                appPos(4)];
            set(app.tractset.resultfig,'Position',figPos)
            clear appPos screensize figPos
            %%
            d.Value = 0.75;
            app.tractset.stattests = ea_explorer_statlist;
            app.enforcestatrules;
            app.ea_explorer_ui_enablerules;
            app.ea_explorer_tractset2UI;

            app.isbusy(0);
            d.Value = 1.00;
            close(d);
            hint={'Welcome to the Lead-DBS Fiber Filtering Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create discriminative tract models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training & cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','FiberFilteringShowUsageHint','Fiber Filtering Explorer',hint,'Makes Sense');

        end

        % Value changed function: StatFamilyDropDown
        function StatFamilyDropDownValueChanged(app, event)
            app.StatFamilyDropDown.Value = event.Value;
            app.tractset.statsettings.statfamily = event.Value;
            app.enforcestatrules;
            %% Inform StatTestItems
            % app.StatTestDropDown.Items = app.tractset.stattests.name(app.tractset.stattests.type==event.Value);
            % app.StatTestDropDown.Value = app.StatTestDropDown.Items(1);
            % switch event.Value
            %     case '1-Sample Tests'
            %         app.StatH0DropDown.Enable = true;
            %     otherwise
            %         app.StatH0DropDown.Enable = false;
            % end
            % % app.ea_explorer_tractset2UI;
            % app.ea_explorer_ui_enablerules;
            % if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
            %     val=0;
            % else
            %     val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            % end
            % if val % already calculated
            %
            % end
            app.pushstatsettings
        end

        % Value changing function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',changingValue),' V/m']};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.1f',changingValue)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',changingValue),' V/m']};
            end
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            [~, ix]=ismember(app.VariableDropDown.Value,app.tractset.M.clinical.labels);
            app.tractset.responsevar = app.tractset.M.clinical.vars{ix};
            app.tractset.responsevarlabel = app.tractset.M.clinical.labels{ix};
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.isbusy(1,'Calculating Activation')
            % set up object properties:
            app.tractset.calcthreshold = app.ThresholdEditField.Value;
            app.tractset.resolution = app.ResolutionEditField.Value;
            app.tractset.connectome = app.ConnectomeDropDown.Value;
            % calculate general setup
            app.tractset.calculate;
            % change GUI
            app.tractset.modulestate.generation=0;
            app.tractset.modulestate.data=1;
            app.tractset.modulestate.stimstats=1;
            app.tractset.modulestate.threshvis=1;
            app.tractset.modulestate.crosspred=0;
            app.ea_explorer_ui_enablerules;
            % save dataset:
            app.isbusy(1,'Calculating Data')
            app.tractset.save;
            app.isbusy(0);
        end

        % Value changed function: ShowPositiveCheckBox
        function ShowPositiveCheckBoxValueChanged(app, event)
            value = app.ShowPositiveCheckBox.Value;
            app.tractset.thresholding.posvisible = value;
            app.ea_explorer_ui_enablerules;


        end

        % Value changed function: ShowNegativeCheckBox
        function ShowNegativeCheckBoxValueChanged(app, event)
            value = app.ShowNegativeCheckBox.Value;
            app.tractset.thresholding.negvisible = value;
            app.ea_explorer_ui_enablerules;


        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,value,'.explorer'],'file')
                app.AnalysisIDEditField.Value = app.tractset.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end
            if exist([fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,app.tractset.ID,'.explorer'],'file')
                movefile([fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,app.tractset.ID,'.explorer'],...
                    [fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,value,'.explorer']);
            end

            ht = getappdata(app.tractset.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore discriminative fibertract ', app.tractset.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore discriminative fibertract ', value],...
                    'TooltipString', ['Explore discriminative fibertract ', value], ...
                    'ClickedCallback', {@ea_add_discfiber, [fileparts(app.tractset.leadgroup),filesep,'explorer',filesep,value,'.explorer'], app.tractset.resultfig});
            end

            app.tractset.ID=value;
            app.tractset.save;
        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;

            app.ea_explorer_tractset2UI;
        end

        % Button pushed function: ColorButtonPositive
        function ColorButtonPositivePushed(app, event)
            c=uisetcolor;
            app.ColorButtonPositive.BackgroundColor=c;
            app.tractset.thresholding.poscolor = app.ColorButtonPositive.BackgroundColor;

        end

        % Button pushed function: ColorButtonNegative
        function ColorButtonNegativePushed(app, event)
            c=uisetcolor;
            app.ColorButtonNegative.BackgroundColor=c;
            app.tractset.thresholding.negcolor = app.ColorButtonNegative.BackgroundColor;

        end

        % Value changed function: EfieldthresholdSlider
        function EfieldthresholdSliderValueChanged(app, event)
            changingValue = event.Value;
            app.EfieldthresholdSlider.Value = changingValue;
            app.tractset.statsettings.efieldthreshold = changingValue;
            app.Efieldthreshold.Value = num2str(changingValue);
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.2f',app.EfieldthresholdSlider.Value)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
            end
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            %if the selected connectomes have multiple .mat files inside
            %it, then it is a multi tract file and
            %app.tractset.multipath.multi_pathways = 1
            if exist(fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dmri_multitract folder
                if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
                    warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
                    app.tractset.multipath.multi_pathways = 0;
                else
                    %if it only exists in the multitract pathway, then
                    %check once more for the multiple .mat files and
                    %set app.tractset.multipath.multi_pathways = 1;
                    mt_connectome = fullfile(ea_getconnectomebase('dMRI_multitract'),app.ConnectomeDropDown.Value);
                    mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
                    mt_files = {mt_connectome_dir.name};
                    if length(mt_files) > 1
                        app.tractset.multipath.multi_pathways = 1;
                    else
                        app.tractset.multipath.multi_pathways = 0;
                    end
                end
            else
                app.tractset.multipath.multi_pathways = 0;
            end
            app.tractset.connectome = value;
            app.ea_explorer_ui_enablerules;
            if ~isfield(app.tractset.results,ea_conn2connid(app.tractset.connectome))
                val=0;
            else
                val=(isfield(app.tractset.results.(ea_conn2connid(app.tractset.connectome)),ea_explorer_method2methodid(app.tractset)));
            end
            if val % already calculated

            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.tractset.mirrorsides = app.MirrorSidesCheckBox.Value;

        end

        % Value changing function: ConnthresholdSlider
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = round(event.Value);
            nVal=round((changingValue/100)*numel(app.tractset.patientselection));
            switch app.StimulationModel.Value
                case 'VTA'
                    bubbles='VTAs';
                case 'Electric Field'
                    bubbles='Efields';
                case 'Sigmoid Field'
                    bubbles='Sigmoids';
            end
            switch app.StatFamilyDropDown.Value
                case '2-Sample Tests'
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.0f',changingValue),'% (n=',sprintf('%.0f',nVal),')'],['and < ',sprintf('%.1f',100-changingValue),'% of ',bubbles]};
                otherwise
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.0f',changingValue),'% (n=',sprintf('%.0f',nVal),')'],['of ',bubbles]};
            end
        end

        % Value changed function: ConnthresholdSlider
        function ConnthresholdSliderValueChanged(app, event)
            changingValue = round(event.Value);
            nVal=round((changingValue/100)*numel(app.tractset.patientselection));
            app.tractset.statsettings.connthreshold = changingValue;
            app.Connthreshold.Value = num2str(changingValue);
            switch app.StimulationModel.Value
                case 'VTA'
                    bubbles='VTAs';
                case 'Electric Field'
                    bubbles='Efields';
                case 'Sigmoid Field'
                    bubbles='Sigmoids';
            end
            switch app.StatFamilyDropDown.Value
                case '2-Sample Tests'
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.0f',changingValue),'% (n=',sprintf('%.0f',nVal),')'],['and < ',sprintf('%.1f',100-changingValue),'% of ',bubbles]};
                otherwise
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.0f',changingValue),'% (n=',sprintf('%.0f',nVal),')'],['of ',bubbles]};
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('event', 'var')
                answer = questdlg("Save Changes?",'Close Discriminative Fiber Toolbox','Yes','No','Yes');
            else
                answer = 'Yes';
            end

            if strcmp(answer,'Yes') && ~isempty(app.tractset)
                app.isbusy(1,'saving');
                app.tractset.save;
            end

            if isprop(app.tractset, 'drawobject') && iscell(app.tractset.drawobject)
                for tract=1:numel(app.tractset.drawobject)
                    try
                        delete(app.tractset.drawobject{tract});
                    end
                end
            end

            if isprop(app.tractset, 'resultfig') && isvalid(app.tractset.resultfig) && startsWith(app.tractset.resultfig.Name, 'No Patient Selected')
                close(app.tractset.resultfig);
            end

            app.isbusy(0);
            delete(app);
        end

        % Value changed function: Efieldmetric
        function EfieldmetricValueChanged(app, event)
            value = event.Value;
            app.Efieldmetric.Value = value;
            app.tractset.statsettings.efieldmetric=value;

            app.setefieldsliders;
            app.ea_explorer_ui_enablerules;
            app.ea_explorer_tractset2UI;
        end

        % Button pushed function: CalculateVisualizeButton
        function CalculateVisualizeButtonPushed(app, event)
            app.pushstatsettings;
            app.pushthresholdingsettings;
            if ~isfield(app.tractset.recentmodel,'statsettings') || ~isequal(app.tractset.statsettings,app.tractset.recentmodel.statsettings)
                app.isbusy(1,'Clearing Scene')
                ea_explorer_cleanfigure(app.tractset)
                app.isbusy(1,'Running Statistics')
                app.tractset.recalculate;
                app.isbusy(1,'Visualization')
                app.tractset.visualize;
            else    %if ~isequal(app.tractset.thresholding,app.tractset.recentmodel.thresholding)
                app.isbusy(1,'Clearing Scene')
                ea_explorer_cleanfigure(app.tractset)
                app.isbusy(1,'Visualization')
                app.tractset.visualize;
            end
            app.setVisualizeButtonColors;
            app.drawcb
            app.isbusy(0)
        end

        % Value changed function: ShowonlysignificantCheckBox
        function ShowonlysignificantCheckBoxValueChanged(app, event)
            value = app.ShowonlysignificantCheckBox.Value;
            app.tractset.thresholding.showsignificantonly=value;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.tractset.thresholding.alphalevel=(value);
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.tractset.thresholding.multcompstrategy=value;
        end

        % Value changed function: StatTestDropDown
        function StatTestDropDownValueChanged(app, event)
            app.tractset.statsettings.stattest=event.Value;
            app.pushstatsettings
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.tractset.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.tractset.setlabels{end+1}=setname;
                    app.tractset.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.tractset.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.tractset.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.tractset.patientselection = ix;

            % always reset to "Choose..."
            app.ea_explorer_ui_enablerules;
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.tractset.calcthreshold = value;


            hint={'Note that changing this value will not have an effect on values already calculated.'
                'You would need to repeat the calculation for each connectome of interest for changes to have an effect.'};
            uigetpref('LeadDBS','FiberFilteringShowCalcThreshHint','Fiber Filtering Explorer',hint,'Makes Sense');


        end

        % Button down function: ColorBar
        function ColorBarButtonDown(app, event)

        end

        % Drop down opening function: ConnectomeDropDown
        function ConnectomeDropDownOpening2(app, event)
            [mdl,sf]=ea_genmodlist;
            dmri_connectomenames = mdl(sf==1);
            dmri_mtconnectomenames = mdl(sf==3);
            app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
        end

        % Value changed function: ShowAmountEditFieldNegative
        function ShowAmountEditFieldNegativeValueChanged(app, event)
            value = app.ShowAmountEditFieldNegative.Value;
            app.tractset.thresholding.shownegamount = [app.ShowAmountEditFieldNegative.Value, app.ShowAmountEditFieldNegative.Value];

        end

        % Value changed function: ShowAmountEditFieldPositive
        function ShowAmountEditFieldPositiveValueChanged(app, event)
            value = app.ShowAmountEditFieldPositive.Value;
            app.tractset.thresholding.showposamount = [app.ShowAmountEditFieldPositive.Value, app.ShowAmountEditFieldPositive.Value];
        end

        % Value changed function: ThresholdingDropDown
        function ThresholdingDropDownValueChanged(app, event)
            app.tractset.thresholding.threshstrategy=app.ThresholdingDropDown.Value;
            app.setthresholding;
            app.ShowAmountEditFieldPositive.Enable = 'on';
            app.ShowAmountEditFieldNegative.Enable = 'on';
            switch app.ThresholdingDropDown.Value
                case 'Fixed Amount'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                case 'Fixed Fiber Value'
                    app.ShowAmountEditFieldPositive.Value = 0;
                    app.ShowAmountEditFieldNegative.Value = 0;
                otherwise
                    app.ShowAmountEditFieldPositive.Value = 100;
                    app.ShowAmountEditFieldNegative.Value = 100;
            end
            app.tractset.thresholding.showposamount=[app.ShowAmountEditFieldPositive.Value,app.ShowAmountEditFieldPositive.Value];
            app.tractset.thresholding.shownegamount=[app.ShowAmountEditFieldNegative.Value,app.ShowAmountEditFieldNegative.Value];
        end

        % Menu selected function: RecreatevisualizationfigureMenu
        function RecreatevisualizationfigureMenuSelected(app, event)
            if isvalid(app.tractset.resultfig)
                close(app.tractset.resultfig);
            end
            app.tractset.resultfig = ea_mnifigure;
            %% Show Resultfig next to GUI
            appPos = app.UIFigure.Position;
            screensize = get(0,'screensize');
            figPos = [appPos(1)+appPos(3),...
                appPos(2),...
                screensize(3)-(appPos(1)+appPos(3)+10)-50,...
                appPos(4)];
            set(app.tractset.resultfig,'Position',figPos)
            clear appPos screensize figPos
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            app.isbusy(1,'Saving Model')
            previousmodels = fieldnames(app.tractset.storedmodels);
            count=1;
            modelname = ['mymodel_' num2str(count)];
            while any(ismember(previousmodels,modelname))
                count=count+1;
                modelname = ['mymodel_' num2str(count)];
            end
            modelname = inputdlg('Name to save this Model:','Provide Modelname',[1 50],{modelname});
            app.tractset.storedmodels.(modelname{1}) = app.tractset.recentmodel;
            app.tractset.save;
            app.isbusy(0)
        end

        % Value changed function: StimulationModel
        function StimulationModelValueChanged(app, event)
            app.StimulationModel.Value = event.Value;
            app.tractset.statsettings.stimulationmodel=event.Value;
            switch event.Value
                case 'Electric Field'
                    app.EfieldmetricLabel.Visible=true;
                    app.Efieldmetric.Visible=true;
                    app.Efieldmetric.Enable=true;
                    app.tractset.statsettings.efieldmetric=app.Efieldmetric.Value;
                case 'Sigmoid Field'
                    app.EfieldmetricLabel.Visible=false;
                    app.Efieldmetric.Visible=false;
                    app.Efieldmetric.Value='Peak';
                case 'VTA'
                    app.EfieldmetricLabel.Visible=false;
                    app.Efieldmetric.Visible=false;
                    app.Efieldmetric.Value='Peak';
            end
            app.enforcestatrules;
            app.setefieldsliders;
            app.ea_explorer_ui_enablerules;
            app.pushstatsettings
        end

        % Value changed function: StatH0DropDown
        function StatH0DropDownValueChanged(app, event)
            app.StatH0DropDown.Value=event.Value;
            app.tractset.statsettings.H0 = event.Value;
            app.pushstatsettings
        end

        % Value changed function: ResolutionEditField
        function ResolutionEditFieldValueChanged(app, event)
            app.tractset.resolution =  app.ResolutionEditField.Value;
        end

        % Value changed function: FastRenderCheckBox
        function FastRenderCheckBoxValueChanged(app, event)
            app.tractset.fastrender = app.FastRenderCheckBox.Value;
        end

        % Button pushed function: LoadResultsButton
        function LoadResultsButtonPushed(app, event)
            app.isbusy(1,'Loading Model')
            loadapp = ea_explorer_loadmodel(fieldnames(app.tractset.storedmodels));
            disp('Waiting for input...')
            while isempty(loadapp.selectedmodel)
                pause(.5)
            end
            selectedmodel = loadapp.selectedmodel;
            loadapp.delete;
            % listdlg('ListString',fieldnames(app.tractset.storedmodels));
            app.tractset.recentmodel = app.tractset.storedmodels.(selectedmodel);
            app.pullstatsettings(app.tractset.recentmodel.statsettings);
            app.pullthresholdingsettings(app.tractset.recentmodel.thresholding);
            app.pushstatsettings
            app.pushthresholdingsettings
            ea_explorer_cleanfigure(app.tractset)
            app.isbusy(1,'Visualization')
            app.tractset.visualize;
            app.isbusy(0);
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            app.isbusy(1,'Exporting Results')
            [explorerfolder,explorerfile] = fileparts(app.tractset.analysispath);
            storeddir = [explorerfolder,filesep,explorerfile,'_storedAnalysis',filesep];
            if ~exist(storeddir,'dir')
                mkdir(storeddir)
            end
            storednames = dir(storeddir);
            storednames(1:2) = [];
            storednames={storednames.name};
            %%
            doesexist = 1;
            questionnumber =1;
            while doesexist
                questiontexts = {'Name Results:','Name already exists, please choose different Name:'};
                resultsname = inputdlg(questiontexts{questionnumber},'Provide Name',[1 50]);
                if ~any(ismember(storednames,resultsname))
                    doesexist = 0;
                else
                    questionnumber=2;
                end
            end
            resultsname=resultsname{1};
            if ~exist([storeddir,resultsname,filesep],'dir')
                mkdir([storeddir,resultsname,filesep])
            end
            mymodel=app.tractset.recentmodel;
            save([storeddir,resultsname,filesep,resultsname,'_wholemodel.mat'],'mymodel');
            %% export niftis
            if mymodel.statsettings.doVoxels
                app.isbusy(1,'Exporting Niftis')
                for i = 1:numel(mymodel.voxels.vals)
                    if i==1
                        side='right';
                    elseif i==2
                        side='left';
                    else
                        side=num2str(i);
                    end
                    % export vals
                    nii = app.tractset.results.space{i};
                    nii.img(:)=nan;
                    nii.fname = [storeddir,resultsname,filesep,resultsname,'_vals_' side '.nii'];
                    nii.img(:)=mymodel.voxels.vals{i};
                    ea_write_nii(nii)
                    % export pvals
                    nii = app.tractset.results.space{i};
                    nii.img(:)=nan;
                    nii.fname = [storeddir,resultsname,filesep,resultsname,'_pvals_' side '.nii'];
                    nii.img(:)=mymodel.voxels.pvals{i};
                    ea_write_nii(nii)
                    % % export sigvals
                    % nii = app.tractset.results.space{i};
                    % nii.img(:)=nan;
                    % nii.fname = [storeddir,resultsname,filesep,resultsname,'_sigvals_' side '.nii'];
                    % nii.img(:)=mymodel.voxels.sigvals{i};
                    % ea_write_nii(nii)
                end
            end
            %% export fibers
            if mymodel.statsettings.doFibers
                app.isbusy(1,'Exporting Fibers')
                for i = 1:numel(mymodel.fibers.vals)
                    if i==1
                        side='right';
                    elseif i==2
                        side='left';
                    else
                        side=num2str(i);
                    end
                    % export vals
                    fibcell = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).fibcell{i};
                    fibvals = mymodel.fibers.vals{i};
                    fibcell=fibcell(~isnan(fibvals));
                    fibvals=fibvals(~isnan(fibvals));
                    fibers=vertcat(fibcell{:});
                    idx=cellfun('size',fibcell,1);
                    fibers(:,4)=repelem([1:numel(idx)]',idx);
                    fibers(:,5)=repelem(fibvals,idx);
                    save([storeddir,resultsname,filesep,resultsname,'_vals_' side '.mat'],'fibers','idx')
                    % export pvals
                    fibcell = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).fibcell{i};
                    fibvals = mymodel.fibers.pvals{i};
                    fibcell=fibcell(~isnan(fibvals));
                    fibvals=fibvals(~isnan(fibvals));
                    fibers=vertcat(fibcell{:});
                    idx=cellfun('size',fibcell,1);
                    fibers(:,4)=repelem([1:numel(idx)]',idx);
                    fibers(:,5)=repelem(fibvals,idx);
                    save([storeddir,resultsname,filesep,resultsname,'_pvals_' side '.mat'],'fibers','idx')
                    % % export sigvals
                    % fibcell = app.tractset.results.(ea_conn2connid(app.tractset.connectome)).fibcell{i};
                    % fibvals = mymodel.fibers.sigvals{i};
                    % fibcell=fibcell(~isnan(fibvals));
                    % fibvals=fibvals(~isnan(fibvals));
                    % fibers=vertcat(fibcell{:});
                    % idx=cellfun('size',fibcell,1);
                    % fibers(:,4)=repelem([1:numel(idx)]',idx);
                    % fibers(:,5)=repelem(fibvals,idx);
                    % save([storeddir,resultsname,filesep,resultsname,'_sigvals_' side '.mat'],'fibers','idx')
                end
            end
            app.isbusy(0)
        end

        % Value changed function: VoxelsSwitch
        function VoxelsSwitchValueChanged(app, event)
            app.tractset.statsettings.doVoxels = app.VoxelsSwitch.Value;
            app.VoxelLampON.Visible = app.tractset.statsettings.doVoxels;
            app.setVisualizeButtonColors;
            drawnow
        end

        % Value changed function: FibersSwitch
        function FibersSwitchValueChanged(app, event)
            app.tractset.statsettings.doFibers = app.FibersSwitch.Value;
            app.FiberLampON.Visible = app.tractset.statsettings.doFibers;
            app.setVisualizeButtonColors;
            drawnow
        end

        % Value changed function: Connthreshold
        function ConnthresholdValueChanged(app, event)
            changingValue=round(str2num(app.Connthreshold.Value));
            nVal=round((changingValue/100)*numel(app.tractset.patientselection));
            if changingValue < app.ConnthresholdSlider.Limits(1)
                changingValue=app.ConnthresholdSlider.Limits(1);
                app.Connthreshold.Value = num2str(changingValue);
            end
            if changingValue > app.ConnthresholdSlider.Limits(2)
                changingValue=app.ConnthresholdSlider.Limits(2);
                app.Connthreshold.Value = num2str(changingValue);
            end
            app.tractset.statsettings.connthreshold = changingValue;
            app.ConnthresholdSlider.Value = changingValue;
            switch app.StimulationModel.Value
                case 'VTA'
                    bubbles='VTAs';
                case 'Electric Field'
                    bubbles='Efields';
                case 'Sigmoid Field'
                    bubbles='Sigmoids';
            end
            switch app.StatFamilyDropDown.Value
                case '2-Sample Tests'
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.0f',changingValue),'% (n=',sprintf('%.0f',nVal),')'],['and < ',sprintf('%.1f',100-changingValue),'% of ',bubbles]};
                otherwise
                    app.ConnthresholdLabel.Text = {['Connected to > ',sprintf('%.0f',changingValue),'% (n=',sprintf('%.0f',nVal),')'],['of ',bubbles]};
            end
        end

        % Value changed function: Efieldthreshold
        function EfieldthresholdValueChanged(app, event)
            changingValue=str2num(app.Efieldthreshold.Value);
            if changingValue < app.EfieldthresholdSlider.Limits(1)
                changingValue=app.EfieldthresholdSlider.Limits(1);
                app.Efieldthreshold.Value = num2str(changingValue);
            end
            if changingValue > app.EfieldthresholdSlider.Limits(2)
                changingValue=app.EfieldthresholdSlider.Limits(2);
                app.Efieldthreshold.Value = num2str(changingValue);
            end
            app.EfieldthresholdSlider.Value = changingValue;
            app.tractset.statsettings.efieldthreshold = changingValue;
            switch app.StimulationModel.Value
                case 'Electric Field'
                    app.EfieldthresholdLabel.Text={['Connected if ',lower(app.tractset.statsettings.efieldmetric),' E-Field'] ['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
                case 'Sigmoid Field'
                    app.EfieldthresholdLabel.Text={['Connected if probability'] ['> ',sprintf('%.2f',app.EfieldthresholdSlider.Value)]};
                case 'VTA'
                    app.EfieldthresholdLabel.Text={['Threshold for VTA Binarization'],['> ',sprintf('%.1f',app.EfieldthresholdSlider.Value),' V/m']};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RecreatevisualizationfigureMenu = uimenu(app.ToolsMenu);
            app.RecreatevisualizationfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @RecreatevisualizationfigureMenuSelected, true);
            app.RecreatevisualizationfigureMenu.Text = 'Recreate visualization figure';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.threshvisPanel = uipanel(app.UIFigure);
            app.threshvisPanel.Title = 'Thresholds & Visualization';
            app.ColorBar = uiaxes(app.threshvisPanel);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorBar.XTick = [0 0.5 1];
            app.ColorBar.XTickLabel = '';
            app.ColorBar.YTickLabel = '';
            app.ColorBar.ButtonDownFcn = createCallbackFcn(app, @ColorBarButtonDown, true);
            app.CalculateVisualizeButton = uibutton(app.threshvisPanel, 'push');
            app.CalculateVisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVisualizeButtonPushed, true);
            app.CalculateVisualizeButton.Enable = 'off';
            app.CalculateVisualizeButton.Text = 'Calculate & Visualize';
            app.ShowAmountEditFieldNegative = uieditfield(app.threshvisPanel, 'numeric');
            app.ShowAmountEditFieldNegative.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldNegativeValueChanged, true);
            app.ShowAmountEditFieldNegative.Enable = 'off';
            app.ShowAmountEditFieldNegative.Value = 100;
            app.ShowAmountEditFieldPositive = uieditfield(app.threshvisPanel, 'numeric');
            app.ShowAmountEditFieldPositive.ValueChangedFcn = createCallbackFcn(app, @ShowAmountEditFieldPositiveValueChanged, true);
            app.ShowAmountEditFieldPositive.Enable = 'off';
            app.ShowAmountEditFieldPositive.Value = 100;
            app.MultTestStrategy = uidropdown(app.threshvisPanel);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Enable = 'off';
            app.MultTestStrategy.Value = 'Uncorrected';
            app.ShowonlysignificantCheckBox = uicheckbox(app.threshvisPanel);
            app.ShowonlysignificantCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowonlysignificantCheckBoxValueChanged, true);
            app.ShowonlysignificantCheckBox.Enable = 'off';
            app.ShowonlysignificantCheckBox.Text = 'Show only significant ';
            app.ShowonlysignificantCheckBox.Value = true;
            app.ShowNegativeCheckBox = uicheckbox(app.threshvisPanel);
            app.ShowNegativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeCheckBoxValueChanged, true);
            app.ShowNegativeCheckBox.Enable = 'off';
            app.ShowNegativeCheckBox.Text = 'Show Negative';
            app.ShowNegativeCheckBox.Value = true;
            app.ShowPositiveCheckBox = uicheckbox(app.threshvisPanel);
            app.ShowPositiveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveCheckBoxValueChanged, true);
            app.ShowPositiveCheckBox.Enable = 'off';
            app.ShowPositiveCheckBox.Text = 'Show Positive';
            app.ShowPositiveCheckBox.Value = true;
            app.ColorButtonNegative = uibutton(app.threshvisPanel, 'push');
            app.ColorButtonNegative.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePushed, true);
            app.ColorButtonNegative.Enable = 'off';
            app.ColorButtonNegative.Text = 'Color';
            app.ShowAmountLabelPositive = uilabel(app.threshvisPanel);
            app.ShowAmountLabelPositive.Enable = 'off';
            app.ShowAmountLabelPositive.Text = '% of Fibers';
            app.ShowAmountLabelNegative = uilabel(app.threshvisPanel);
            app.ShowAmountLabelNegative.Enable = 'off';
            app.ShowAmountLabelNegative.Text = '% of Fibers';
            app.levelEditField = uieditfield(app.threshvisPanel, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Enable = 'off';
            app.levelEditField.Value = 0.05;
            app.levelEditFieldLabel = uilabel(app.threshvisPanel);
            app.levelEditFieldLabel.Enable = 'off';
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.ColorButtonPositive = uibutton(app.threshvisPanel, 'push');
            app.ColorButtonPositive.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePushed, true);
            app.ColorButtonPositive.Enable = 'off';
            app.ColorButtonPositive.Text = 'Color';
            app.ThresholdingDropDownLabel = uilabel(app.threshvisPanel);
            app.ThresholdingDropDownLabel.Enable = 'off';
            app.ThresholdingDropDownLabel.Text = 'Thresholding';
            app.ThresholdingDropDown = uidropdown(app.threshvisPanel);
            app.ThresholdingDropDown.Items = {'Percentage Relative to Peak', 'Percentage Relative to Amount', 'Fixed Amount', 'Fixed Fiber Value'};
            app.ThresholdingDropDown.ValueChangedFcn = createCallbackFcn(app, @ThresholdingDropDownValueChanged, true);
            app.ThresholdingDropDown.Enable = 'off';
            app.ThresholdingDropDown.Value = 'Percentage Relative to Amount';
            app.ExportResultsButton = uibutton(app.threshvisPanel, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Enable = 'off';
            app.ExportResultsButton.Text = 'Export Results';
            app.LoadResultsButton = uibutton(app.threshvisPanel, 'push');
            app.LoadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadResultsButtonPushed, true);
            app.LoadResultsButton.Enable = 'off';
            app.LoadResultsButton.Text = 'Load Results';
            app.SaveResultsButton = uibutton(app.threshvisPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.VoxelsSwitchLabel = uilabel(app.threshvisPanel);
            app.VoxelsSwitchLabel.Enable = 'off';
            app.VoxelsSwitchLabel.Text = 'Voxels';
            app.VoxelsSwitch = uiswitch(app.threshvisPanel, 'slider');
            app.VoxelsSwitch.ItemsData = [0 1];
            app.VoxelsSwitch.ValueChangedFcn = createCallbackFcn(app, @VoxelsSwitchValueChanged, true);
            app.VoxelsSwitch.Enable = 'off';
            app.VoxelsSwitch.Value = 1;
            app.FibersSwitchLabel = uilabel(app.threshvisPanel);
            app.FibersSwitchLabel.Enable = 'off';
            app.FibersSwitchLabel.Text = 'Fibers';
            app.FibersSwitch = uiswitch(app.threshvisPanel, 'slider');
            app.FibersSwitch.ItemsData = [0 1];
            app.FibersSwitch.ValueChangedFcn = createCallbackFcn(app, @FibersSwitchValueChanged, true);
            app.FibersSwitch.Enable = 'off';
            app.FibersSwitch.Value = 1;
            app.VoxelLampON = uilamp(app.threshvisPanel);
            app.FiberLampON = uilamp(app.threshvisPanel);
            app.stimstatsPanel = uipanel(app.UIFigure);
            app.stimstatsPanel.Title = 'Stimulation Modeling & Statistics';
            app.ConnthresholdSlider = uislider(app.stimstatsPanel);
            app.ConnthresholdSlider.Limits = [0 50];
            app.ConnthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanged, true);
            app.ConnthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ConnthresholdSliderValueChanging, true);
            app.ConnthresholdSlider.Enable = 'off';
            app.ConnthresholdSlider.Value = 20;
            app.ConnthresholdLabel = uilabel(app.stimstatsPanel);
            app.ConnthresholdLabel.Enable = 'off';
            app.ConnthresholdLabel.Text = {'Tracts should be connected '; 'to at least % of VTAs'};
            app.EfieldthresholdSlider = uislider(app.stimstatsPanel);
            app.EfieldthresholdSlider.Limits = [0 500];
            app.EfieldthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanged, true);
            app.EfieldthresholdSlider.ValueChangingFcn = createCallbackFcn(app, @EfieldthresholdSliderValueChanging, true);
            app.EfieldthresholdSlider.Enable = 'off';
            app.EfieldthresholdSlider.Value = 200;
            app.EfieldthresholdLabel = uilabel(app.stimstatsPanel);
            app.EfieldthresholdLabel.Enable = 'off';
            app.EfieldthresholdLabel.Text = {'Tracts are modulated > '; 'E-Field of  V/m'};
            app.Efieldmetric = uidropdown(app.stimstatsPanel);
            app.Efieldmetric.Items = {'Peak', 'Sum', 'Mean'};
            app.Efieldmetric.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricValueChanged, true);
            app.Efieldmetric.Enable = 'off';
            app.Efieldmetric.Value = 'Peak';
            app.StimulationModel = uidropdown(app.stimstatsPanel);
            app.StimulationModel.Items = {'Electric Field', 'Sigmoid Field', 'VTA'};
            app.StimulationModel.ValueChangedFcn = createCallbackFcn(app, @StimulationModelValueChanged, true);
            app.StimulationModel.Enable = 'off';
            app.StimulationModel.Value = 'Electric Field';
            app.StatH0DropDown = uidropdown(app.stimstatsPanel);
            app.StatH0DropDown.Items = {'Average', 'Zero'};
            app.StatH0DropDown.ValueChangedFcn = createCallbackFcn(app, @StatH0DropDownValueChanged, true);
            app.StatH0DropDown.Enable = 'off';
            app.StatH0DropDown.Value = 'Average';
            app.StatTestDropDown = uidropdown(app.stimstatsPanel);
            app.StatTestDropDown.Items = {'Spearman', 'Pearson'};
            app.StatTestDropDown.ValueChangedFcn = createCallbackFcn(app, @StatTestDropDownValueChanged, true);
            app.StatTestDropDown.Enable = 'off';
            app.StatTestDropDown.Value = 'Spearman';
            app.StatFamilyDropDown = uidropdown(app.stimstatsPanel);
            app.StatFamilyDropDown.Items = {'Correlations', '1-Sample Tests', '2-Sample Tests'};
            app.StatFamilyDropDown.ValueChangedFcn = createCallbackFcn(app, @StatFamilyDropDownValueChanged, true);
            app.StatFamilyDropDown.Enable = 'off';
            app.StatFamilyDropDown.Value = 'Correlations';
            app.StimulationModelLabel = uilabel(app.stimstatsPanel);
            app.StimulationModelLabel.Enable = 'off';
            app.StimulationModelLabel.Text = 'Stimulation Model';
            app.EfieldmetricLabel = uilabel(app.stimstatsPanel);
            app.EfieldmetricLabel.Enable = 'off';
            app.EfieldmetricLabel.Text = 'Metric';
            app.StatH0Label = uilabel(app.stimstatsPanel);
            app.StatH0Label.Enable = 'off';
            app.StatH0Label.Text = 'Against';
            app.StatTestLabel = uilabel(app.stimstatsPanel);
            app.StatTestLabel.Enable = 'off';
            app.StatTestLabel.Text = 'Statistical Test';
            app.StatFamilyLabel = uilabel(app.stimstatsPanel);
            app.StatFamilyLabel.Enable = 'off';
            app.StatFamilyLabel.Text = 'Test Family';
            app.Efieldthreshold = uieditfield(app.stimstatsPanel, 'text');
            app.Efieldthreshold.ValueChangedFcn = createCallbackFcn(app, @EfieldthresholdValueChanged, true);
            app.Efieldthreshold.Value = '200';
            app.Connthreshold = uieditfield(app.stimstatsPanel, 'text');
            app.Connthreshold.InputType = 'alphanumerics';
            app.Connthreshold.ValueChangedFcn = createCallbackFcn(app, @ConnthresholdValueChanged, true);
            app.Connthreshold.Value = '20';
            app.dataPanel = uipanel(app.UIFigure);
            app.dataPanel.Title = 'Data Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.dataPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.dataPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.dataPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableDropDownLabel = uilabel(app.dataPanel);
            app.VariableDropDownLabel.Enable = 'off';
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.dataPanel);
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Enable = 'off';
            app.SubcohortsDropDownLabel = uilabel(app.dataPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.dataPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.generationPanel = uipanel(app.UIFigure);
            app.generationPanel.Title = 'Connectome Selection';
            app.CalculateButton = uibutton(app.generationPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ConnectomeDropDownLabel = uilabel(app.generationPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.generationPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ConnectomeDropDownOpening2, true);
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.ThresholdEditFieldLabel = uilabel(app.generationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.generationPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 50;
            app.VmLabel = uilabel(app.generationPanel);
            app.VmLabel.Text = 'V/m';
            app.ResolutionEditFieldLabel = uilabel(app.generationPanel);
            app.ResolutionEditFieldLabel.Text = 'Resolution';
            app.ResolutionEditField = uieditfield(app.generationPanel, 'numeric');
            app.ResolutionEditField.Limits = [0 Inf];
            app.ResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionEditFieldValueChanged, true);
            app.ResolutionEditField.Value = 0.5;
            app.mmLabel = uilabel(app.generationPanel);
            app.mmLabel.Text = 'mm';
            app.FastRenderCheckBox = uicheckbox(app.UIFigure);
            app.FastRenderCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastRenderCheckBoxValueChanged, true);
            app.FastRenderCheckBox.Enable = 'off';
            app.FastRenderCheckBox.Visible = 'off';
            app.FastRenderCheckBox.Text = 'Fast Render';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_tract_analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_explorer_lite(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end