classdef ea_networkmappingexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ToolsMenu                       matlab.ui.container.Menu
        RefreshInformationfromLeadGroupprojectMenu  matlab.ui.container.Menu
        RecreatevisualizationfigureMenu  matlab.ui.container.Menu
        SetseedforrandomnumbergeneratorMenu  matlab.ui.container.Menu
        ExportFingerprintsAsNIfTIFilesMenu  matlab.ui.container.Menu
        CrossvalidationPredictionPanel  matlab.ui.container.Panel
        ShowImportedModelCheckBox       matlab.ui.control.CheckBox
        ImportedModelShow               matlab.ui.control.ListBox
        SubcohortsDropDown_2            matlab.ui.control.DropDown
        SubcohortsDropDown_2Label       matlab.ui.control.Label
        LoadModelButton                 matlab.ui.control.Button
        iterationsEditField             matlab.ui.control.NumericEditField
        iterationsEditFieldLabel        matlab.ui.control.Label
        ExportModelsasNIfTICheckBox     matlab.ui.control.CheckBox
        MaskDropDown                    matlab.ui.control.DropDown
        MaskDropDownLabel               matlab.ui.control.Label
        BaseSimilarityonDropDown        matlab.ui.control.DropDown
        BaseSimilarityonDropDownLabel   matlab.ui.control.Label
        LiveVisualizeCheckBox           matlab.ui.control.CheckBox
        kEditField                      matlab.ui.control.EditField
        kEditFieldLabel                 matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        StrategyDropDownLabel           matlab.ui.control.Label
        PosthoccorrectforgroupCheckBox  matlab.ui.control.CheckBox
        RunCVButton                     matlab.ui.control.Button
        PredictListBox                  matlab.ui.control.ListBox
        PredictListBoxLabel             matlab.ui.control.Label
        TrainOnListBox                  matlab.ui.control.ListBox
        TrainOnListBoxLabel             matlab.ui.control.Label
        VisualizationThresholdsPanel    matlab.ui.container.Panel
        ExportModelButton               matlab.ui.control.Button
        ColorButtonPositivePeak         matlab.ui.control.Button
        ColorButtonNegativePeak         matlab.ui.control.Button
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        VisualizationDropDown           matlab.ui.control.DropDown
        VisualizationDropDownLabel      matlab.ui.control.Label
        RHCheckBox                      matlab.ui.control.CheckBox
        LHCheckBox                      matlab.ui.control.CheckBox
        MultTestStrategy                matlab.ui.control.DropDown
        levelEditField                  matlab.ui.control.NumericEditField
        levelEditFieldLabel             matlab.ui.control.Label
        AutoRefreshCheckBox             matlab.ui.control.CheckBox
        RefreshViewButton               matlab.ui.control.Button
        ConsiderSignificantVoxelsonlyCheckBox  matlab.ui.control.CheckBox
        ExportasNIfTIButton             matlab.ui.control.Button
        ShowNegativeRegionsCheckBox     matlab.ui.control.CheckBox
        ShowPositiveRegionsCheckBox     matlab.ui.control.CheckBox
        ColorButtonNegativeBase         matlab.ui.control.Button
        ColorButtonPositiveBase         matlab.ui.control.Button
        ColorBar                        matlab.ui.control.UIAxes
        BusyLamp                        matlab.ui.control.Lamp
        BusyLampLabel                   matlab.ui.control.Label
        InteractiveModelSetupPanel      matlab.ui.container.Panel
        SubcohorttotestagainstDropDown  matlab.ui.control.DropDown
        SubcohorttotestagainstDropDownLabel  matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        VariableofCertaintyVOCDropDown  matlab.ui.control.DropDown
        VariableofCertaintyVOCDropDownLabel  matlab.ui.control.Label
        NormalizeFingerprintsCheckBox   matlab.ui.control.CheckBox
        SmoothFingerprintsCheckBox      matlab.ui.control.CheckBox
        SubcohortsDropDown              matlab.ui.control.DropDown
        SubcohortsDropDownLabel         matlab.ui.control.Label
        Corrtype                        matlab.ui.control.DropDown
        ModelSetupDropDown              matlab.ui.control.DropDown
        ModelSetupDropDownLabel         matlab.ui.control.Label
        CleanVOIfromthefollowingcovariatesCheckBox  matlab.ui.control.CheckBox
        CovariatesListBox               matlab.ui.control.ListBox
        VariableofInterestVOIDropDown   matlab.ui.control.DropDown
        VariableofInterestVOIDropDownLabel  matlab.ui.control.Label
        PatientsListBox                 matlab.ui.control.ListBox
        PatientsListBoxLabel            matlab.ui.control.Label
        MirrorSidesCheckBox             matlab.ui.control.CheckBox
        ConnectomeSelectionPanel        matlab.ui.container.Panel
        ResolutionDropDown              matlab.ui.control.DropDown
        ResolutionDropDownLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        ConnectomeDropDown              matlab.ui.control.DropDown
        ConnectomeDropDownLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LeadGroupPathLabel              matlab.ui.control.Label
        AnalysisIDEditField             matlab.ui.control.EditField
        AnalysisIDEditFieldLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        networkmapping % associated ea_disctract object.
    end
    
    
    methods (Access = private)
        
        function results = drawcb(app)
            app.isbusy(1);
            if ~isempty(app.networkmapping.colorbar)
                if app.networkmapping.splitbygroup
                    app.ColorBar.Visible = 'off';
                    cla(app.ColorBar);
                else
                    app.ColorBar.Visible = 'on';
                    cb = app.networkmapping.colorbar;
                    ea_plot_colorbar(cb.cmap{1}, [], 'h', '', cb.tick{1}, cb.ticklabel{1}, app.ColorBar);
                end
            else
                app.ColorBar.Visible = 'off';
                cla(app.ColorBar);
            end
            app.isbusy(0);
        end
        
        function results = enablerules(app)
            app.isbusy(1);
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val='off';
            else
                val='on';
            end
            
            [mdl,sf]=ea_genmodlist;
            [~,ix]=ismember(app.ConnectomeDropDown.Value,mdl);
            switch sf(ix)
                case 1 % structural connectome selected
                    app.ResolutionDropDown.Enable = 'on';
                case 2 % functional connectome selected
                    app.ResolutionDropDown.Enable = 'off';
                    app.ResolutionDropDown.Value = '2 mm';
            end
            
            % enable controls:
            app.ModelSetupDropDown.Enable = val;
            app.ModelSetupDropDownLabel.Enable = val;
            
            
            app.MirrorSidesCheckBox.Enable = val;

            app.VariableofInterestVOIDropDown.Enable = val;
  
            
            app.ShowPositiveRegionsCheckBox.Enable = val;
            app.ShowNegativeRegionsCheckBox.Enable = val;
            
            
            app.ShowNegativeRegionsCheckBox.Enable = val;
            
            
            app.VariableofInterestVOIDropDownLabel.Enable = val;
            app.CleanVOIfromthefollowingcovariatesCheckBox.Enable = val;
            
            app.SmoothFingerprintsCheckBox.Enable = val;

            app.NormalizeFingerprintsCheckBox.Enable = val;
            app.ColorBar.Visible = val;
            if strcmp(val, 'off')
                cla(app.ColorBar);
            end
            app.ExportasNIfTIButton.Enable = val;
            cvEnable = val;
            
            % CV Panel:
            app.StrategyDropDown.Enable = cvEnable;
            app.StrategyDropDownLabel.Enable = cvEnable;
            app.kEditField.Enable = cvEnable;
            app.kEditFieldLabel.Enable = cvEnable;
            app.RunCVButton.Enable = cvEnable;
            app.PosthoccorrectforgroupCheckBox.Enable = cvEnable;
            app.ExportModelsasNIfTICheckBox.Enable = cvEnable;
            
            switch app.StrategyDropDown.Value
                case 'k-fold (randomized)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = 'k:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.networkmapping.kfold);
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# perms:';
                    app.kEditField.Tooltip = '';
                    app.kEditField.Value = num2str(app.networkmapping.Nperm);
                case 'Custom (Sets)'
                    app.kEditField.Visible = 'on';
                    app.kEditFieldLabel.Visible = 'on';
                    app.kEditFieldLabel.Text = '# sets:';
                    app.kEditField.Tooltip = 'Randomly split the data into N sets';
                    app.kEditField.Value = num2str(app.networkmapping.Nsets);
                otherwise
                    app.kEditField.Visible = 'off';
                    app.kEditFieldLabel.Visible = 'off';
                    app.kEditField.Tooltip = '';
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    app.TrainOnListBox.Items=app.networkmapping.M.patient.list;
                    app.PredictListBox.Items=app.networkmapping.M.patient.list;
                case 'Custom (Cohorts)'
                    if length(app.networkmapping.M.groups.group)>1
                        app.TrainOnListBox.Items=sprintfc('%d',(app.networkmapping.M.groups.group));
                        app.PredictListBox.Items=sprintfc('%d',(app.networkmapping.M.groups.group));
                    end
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                        app.PredictListBox.Items=sprintfc('%d',[1:str2double(app.kEditField.Value)]');
                    end
                case 'Custom (Subcohorts)'
                    app.TrainOnListBox.Items=app.networkmapping.setlabels;
                    app.PredictListBox.Items=app.networkmapping.setlabels;
                case 'Import Model'
                    app.PredictListBox.Items = app.networkmapping.M.patient.list; 
            end
            
            switch app.StrategyDropDown.Value
                case 'Custom (Patients)'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                case 'Custom (Cohorts)'
                    if length(app.networkmapping.M.groups.group)>1
                        cvlEnable=cvEnable;
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                    
                case 'Custom (Sets)'
                    if str2double(app.kEditField.Value)>1
                        if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                            app.RunCVButton.Enable = 'off';
                        end
                        cvlEnable=cvEnable;
                    else
                        app.RunCVButton.Enable = 'off';
                        cvlEnable='off';
                    end
                case 'Custom (Subcohorts)'
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                    cvlEnable='on';
                case 'Import Model'
                    cvlEnable=cvEnable;
                    if isempty(app.TrainOnListBox.Value) || isempty(app.PredictListBox.Value)
                        app.RunCVButton.Enable = 'off';
                    end
                otherwise
                    cvlEnable='off';
            end
            
            app.VisualizationDropDown.Enable = val;
            app.VisualizationDropDownLabel.Enable = val;
            
            switch app.VisualizationDropDown.Value
                case 'Regions'
                    app.ModelDropDown.Enable = 'off';
                    app.ModelDropDownLabel.Enable = 'off';
                    app.LHCheckBox.Enable = 'off';
                    app.RHCheckBox.Enable = 'off';
                otherwise
                    app.ModelDropDown.Enable = val;
                    app.ModelDropDownLabel.Enable = val;
                    app.LHCheckBox.Enable = val;
                    app.RHCheckBox.Enable = val;
            end
            
            
            % CV Listboxes
            app.PredictListBox.Enable = cvlEnable;
            app.PredictListBoxLabel.Enable = cvlEnable;
            app.TrainOnListBox.Enable = cvlEnable;
            app.TrainOnListBoxLabel.Enable = cvlEnable;
            
            app.AutoRefreshCheckBox.Enable = val;
            app.RefreshViewButton.Enable = val;
            app.LiveVisualizeCheckBox.Enable = val;
            switch app.ModelSetupDropDown.Value
                case{'Weighted Average (Horn 2017)','Thresholded N-map (Boes 2015)','One Sample T-Test','Two Sample T-Test','Database Lookup'}
                    app.Corrtype.Enable = "off";
                otherwise
                    app.Corrtype.Enable = val;
            end
            
            app.SubcohortsDropDown_2.Items=[app.networkmapping.setlabels];

            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson'})
                app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'off';
                app.ConsiderSignificantVoxelsonlyCheckBox.Value = 0;
                app.levelEditField.Enable = 'off';
                app.levelEditFieldLabel.Enable = 'off';
                app.MultTestStrategy.Enable = 'off';
            else
                if ismember(app.ModelSetupDropDown.Value,{'Weighted Average (Horn 2017)'})
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'off';
                    app.ConsiderSignificantVoxelsonlyCheckBox.Value = 0;
                    app.levelEditField.Enable = 'off';
                    app.levelEditFieldLabel.Enable = 'off';
                    app.MultTestStrategy.Enable = 'off';
                else
                    app.ConsiderSignificantVoxelsonlyCheckBox.Enable = val;
                    app.levelEditField.Enable = val;
                    app.levelEditFieldLabel.Enable = val;
                    app.MultTestStrategy.Enable = val;
                end
            end
            switch app.ModelSetupDropDown.Value
                case {'Database Lookup','Weighted R2-Map'}
                    app.VariableofCertaintyVOCDropDown.Enable='on';
                    app.VariableofCertaintyVOCDropDownLabel.Enable='on';
                    app.ThresholdEditField.Enable='off';
                    app.ThresholdEditFieldLabel.Enable='off';
                    app.SubcohorttotestagainstDropDown.Enable='off';
                    app.SubcohorttotestagainstDropDownLabel.Enable='off';
                    app.SubcohorttotestagainstDropDown.Visible='off';
                    app.SubcohorttotestagainstDropDownLabel.Visible='off';
                case 'Thresholded N-map (Boes 2015)'
                    app.ThresholdEditField.Enable='on';
                    app.ThresholdEditFieldLabel.Enable='on';
                    app.VariableofCertaintyVOCDropDown.Enable='off';
                    app.VariableofCertaintyVOCDropDownLabel.Enable='off';
                    app.SubcohorttotestagainstDropDown.Enable='off';
                    app.SubcohorttotestagainstDropDownLabel.Enable='off';
                    app.SubcohorttotestagainstDropDown.Visible='off';
                    app.SubcohorttotestagainstDropDownLabel.Visible='off';
                case 'Two Sample T-Test'
                    app.SubcohorttotestagainstDropDown.Enable='on';
                    app.SubcohorttotestagainstDropDownLabel.Enable='on';
                    app.SubcohorttotestagainstDropDown.Visible='on';
                    app.SubcohorttotestagainstDropDownLabel.Visible='on';
                    app.VariableofCertaintyVOCDropDown.Enable='off';
                    app.VariableofCertaintyVOCDropDownLabel.Enable='off';
                    app.ThresholdEditField.Enable='off';
                    app.ThresholdEditFieldLabel.Enable='off';
                otherwise
                    app.VariableofCertaintyVOCDropDown.Enable='off';
                    app.VariableofCertaintyVOCDropDownLabel.Enable='off';
                    app.ThresholdEditField.Enable='off';
                    app.ThresholdEditFieldLabel.Enable='off';
                    app.SubcohorttotestagainstDropDown.Enable='off';
                    app.SubcohorttotestagainstDropDownLabel.Enable='off';
                    app.SubcohorttotestagainstDropDown.Visible='off';
                    app.SubcohorttotestagainstDropDownLabel.Visible='off';
            end
            
            
            if ismember(app.Corrtype.Value,{'Skipped Spearman','Skipped Pearson','Bend'})
                app.CleanVOIfromthefollowingcovariatesCheckBox.Enable = 'off';
                app.CleanVOIfromthefollowingcovariatesCheckBox.Value = 0;
            end
            
            app.BaseSimilarityonDropDown.Enable = val;
            app.BaseSimilarityonDropDownLabel.Enable = val;
            
            app.MaskDropDown.Enable = val;
            app.MaskDropDownLabel.Enable = val;
            
            if app.CleanVOIfromthefollowingcovariatesCheckBox.Value
                app.CovariatesListBox.Enable = val;
            else
                app.CovariatesListBox.Enable = 'off';
            end
                    
            
            app.PatientsListBox.Enable=val;
            
            app.SubcohortsDropDown.Enable=val;
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.networkmapping.setlabels];
            app.SubcohorttotestagainstDropDown.Items=app.networkmapping.setlabels;
            
            switch app.ShowNegativeRegionsCheckBox.Value
                case 0
                    app.ColorButtonNegativeBase.Enable = 'off';
                    app.ColorButtonNegativePeak.Enable = 'off';


                case 1
                    app.ColorButtonNegativeBase.Enable = val;
                    app.ColorButtonNegativePeak.Enable = val;


            end
            
            switch app.ShowPositiveRegionsCheckBox.Value
                case 0
                    app.ColorButtonPositiveBase.Enable = 'off';
                    app.ColorButtonPositivePeak.Enable = 'off';


                case 1
                    app.ColorButtonPositiveBase.Enable = val;
                    app.ColorButtonPositivePeak.Enable = val;


            end


            % turn light back to green
            app.isbusy(0);
        end
        
        
        function results = networkmapping2UI(app)
            app.isbusy(1);
            
            app.ModelSetupDropDown.Enable = 'on';
            app.ModelSetupDropDownLabel.Enable = 'on';
            app.ConnectomeDropDown.Enable = 'on';
            app.ConnectomeDropDownLabel.Enable = 'on';
            app.CalculateButton.Enable = 'on';


            % derive everything from networkmapping obj:
            
            if isfield(app.networkmapping.M,'pseudoM') % custom scripting input
                app.CovariatesListBox.Items=['Group / Cohort regressor',app.networkmapping.M.clinical.labels];
            else % regular case
                app.CovariatesListBox.Items=['Group / Cohort regressor','Stimulation Amplitude','E-Field Magnitude Sum',app.networkmapping.M.clinical.labels];
            end
            
            app.LeadGroupPathLabel.Text=app.networkmapping.leadgroup;
            app.LeadGroupPathLabel.Tooltip=app.networkmapping.leadgroup;
            app.AnalysisIDEditField.Value = app.networkmapping.ID;
            try
                app.ModelSetupDropDown.Value = app.networkmapping.statmetric;
            catch
                app.networkmapping.statmetric = app.ModelSetupDropDown.Value;
            end
            
            app.Corrtype.Value=app.networkmapping.corrtype;
            app.ConsiderSignificantVoxelsonlyCheckBox.Value = app.networkmapping.showsignificantonly;
            app.levelEditField.Value = (app.networkmapping.alphalevel);
            app.MultTestStrategy.Value = app.networkmapping.multcompstrategy;
            app.BaseSimilarityonDropDown.Value = app.networkmapping.basepredictionon;

            app.SmoothFingerprintsCheckBox.Value = app.networkmapping.smooth_fp;
            app.NormalizeFingerprintsCheckBox.Value = app.networkmapping.normalize_fp;


            if ~isempty(app.networkmapping.connectome) && ismember(app.networkmapping.connectome, app.ConnectomeDropDown.Items)
                app.ConnectomeDropDown.Value = app.networkmapping.connectome;
            end
            
            app.MirrorSidesCheckBox.Value = app.networkmapping.mirrorsides;
            
            app.PatientsListBox.Items=app.networkmapping.M.patient.list;
            app.PatientsListBox.Value=app.PatientsListBox.Items(app.networkmapping.patientselection);
            app.SubcohortsDropDown.Value='Choose...';
            app.SubcohortsDropDown.Items=[{'Choose...', 'All Patients', 'Create Subcohort from Selection', 'Create Subcohort from Inverse Selection'},app.networkmapping.setlabels];

            for set=1:length(app.networkmapping.setlabels)
                if isequal(app.networkmapping.patientselection,find(app.networkmapping.setselections{set}))
                    app.SubcohortsDropDown.Value=app.networkmapping.setlabels{set};
                end
            end
            if isequal(app.networkmapping.patientselection,1:length(app.networkmapping.M.patient.list))
                app.SubcohortsDropDown.Value='All Patients';
            end
            
            app.PosthoccorrectforgroupCheckBox.Value = app.networkmapping.adjustforgroups;
            
            app.VariableofInterestVOIDropDown.Items=app.networkmapping.M.clinical.labels;
            app.VariableofInterestVOIDropDown.Value = app.networkmapping.responsevarlabel;
            

            app.VariableofCertaintyVOCDropDown.Items=[{'None'},app.networkmapping.M.clinical.labels];
            try
                app.VariableofCertaintyVOCDropDown.Value = app.networkmapping.certainvarlabel;
            catch
                app.networkmapping.certainvarlabel =app.VariableofCertaintyVOCDropDown.Value;
            end

            setcovars(app);
            
            [~,ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.CovariatesListBox.Items);
            %app.CovariatesListBox.Items(ix)=[];
            
            app.CovariatesListBox.Value=app.networkmapping.covarlabels;
            if ~isempty(app.networkmapping.results)
                app.CleanVOIfromthefollowingcovariatesCheckBox.Value=~isempty(app.networkmapping.covarlabels);
            end
            
            app.ExportModelsasNIfTICheckBox.Value=app.networkmapping.exportmodelsAsNifti;
            
            app.ShowPositiveRegionsCheckBox.Value = app.networkmapping.posvisible;
            try
                app.ShowPositiveRegionsCheckBox.Text = ['Show Positive Regions (',num2str(sum(app.networkmapping.stats.pos.available)),')'];
            end
            
            app.ShowNegativeRegionsCheckBox.Value = app.networkmapping.negvisible;
            try
                app.ShowNegativeRegionsCheckBox.Text = ['Show Negative Regions (',num2str(sum(app.networkmapping.stats.neg.available)),')'];
            end
            
            app.VisualizationDropDown.Value = app.networkmapping.vizmode;
            app.ModelDropDown.Value = app.networkmapping.model;
            app.RHCheckBox.Value = app.networkmapping.modelRH;
            app.LHCheckBox.Value = app.networkmapping.modelLH;


            app.LiveVisualizeCheckBox.Value = app.networkmapping.cvlivevisualize;
            
            % Color buttons:
            app.ColorButtonPositiveBase.BackgroundColor = app.networkmapping.posBaseColor;
            app.ColorButtonNegativeBase.BackgroundColor = app.networkmapping.negBaseColor;
            app.ColorButtonPositivePeak.BackgroundColor = app.networkmapping.posPeakColor;
            app.ColorButtonNegativePeak.BackgroundColor = app.networkmapping.negPeakColor;
            
            
            % refresh enablerules
            app.enablerules;
            
            app.isbusy(0);
        end
        
        function results = isbusy(app,business,msg)
            if business == 1 % red
                app.BusyLamp.Color = [1.00,0.41,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Busy';
                end
            elseif business == 0 % green
                app.BusyLamp.Color = [0.41,1.00,0.16];
                app.BusyLampLabel.Text = '';
            elseif business == 2 % yellow
                app.BusyLamp.Color = [1.00,0.8,0.16];
                if exist('msg','var')
                    app.BusyLampLabel.Text = msg;
                else
                    app.BusyLampLabel.Text = 'Not Refreshed';
                end
            end
        end
        
        function results = draw(app,force)
            if ~exist('force','var')
                force=0;
            end
            app.RefreshViewButton.BackgroundColor = [0.96,0.96,0.96]; % standard grey
            if app.AutoRefreshCheckBox.Value || force
                app.isbusy(1);
                drawnow;
                
                app.networkmapping.draw;
                app.drawcb;
                app.isbusy(0);
                drawnow;
                app.networkmapping2UI;
            else
                app.isbusy(2);
                app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
                drawnow;
            end
        end
        
        function Vms = getvms(app)
            switch app.networkmapping.efieldmetric
                case 'Mean'
                    Vms='V/mm';
                otherwise
                    Vms='V/m';
            end
        end
        
        function ts = setcovars(app) % transfer covariates from app to tractset
            if app.CleanVOIfromthefollowingcovariatesCheckBox.Value % include covariates at all
                [is]=ismember(app.networkmapping.M.clinical.labels,app.CovariatesListBox.Value);
                if any(is)
                    app.networkmapping.covars = app.networkmapping.M.clinical.vars(is);
                    app.networkmapping.covarlabels = app.networkmapping.M.clinical.labels(is);
                else % none selected
                    app.networkmapping.covars = {};
                    app.networkmapping.covarlabels = {};
                end
                % Check whether Amplitude or VTA volumes should be
                % included, as well.
                if ismember('Stimulation Amplitude',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getstimamp},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'Stimulation Amplitude'},app.networkmapping.covarlabels];
                end
                
                if ismember('VTA Volume',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getvtavolumes},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'VTA Volume'},app.networkmapping.covarlabels];
                end
                
                if ismember('E-Field Magnitude Sum',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getefieldmagnitudes},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'E-Field Magnitude Sum'},app.networkmapping.covarlabels];
                end
                if ismember('Group / Cohort regressor',app.CovariatesListBox.Value)
                    app.networkmapping.covars = [{app.networkmapping.getcohortregressor},app.networkmapping.covars];
                    app.networkmapping.covarlabels = [{'Group / Cohort regressor'},app.networkmapping.covarlabels];
                end
            else
                app.networkmapping.covars = {};
                app.networkmapping.covarlabels = {};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, lgpath, resultfig)
            app.isbusy(1,'Loading');
            app.UIFigure.Name='DBS Network Mapping Explorer';

            % Add modlist (structural connectomes)
            [mdl]=ea_genmodlist;
            app.ConnectomeDropDown.Items=mdl;
            
            % Add standard colors for tracts
            app.ColorButtonPositiveBase.BackgroundColor='r';
            app.ColorButtonNegativeBase.BackgroundColor='b';
            
            % use supplied information to setup analysis
            if ~exist('lgpath','var')
                [FileName,PathName]=uigetfile('*.mat','Please Choose Lead-Group Analysis project...');
                lgpath = fullfile(PathName,FileName);
            elseif ~isfile(lgpath)
                ea_cprintf('CmdWinErrors', 'Analysis file doesn''t exist!\n');
                delete(app);
                return;
            end

            if ~exist('resultfig','var')
                resultfig = ea_mnifigure; % create new figure.
            end

            letsdraw=0; % flag to state whether to draw directly.
            lgpath = GetFullPath(lgpath);
            varinfo=who('-file',lgpath); % check if file is a group analysis mat file or a stored disctract object with var name 'tractset'
            if ismember('networkmapping',varinfo)
                D = load(lgpath,'-mat');
                
                app.networkmapping = D.networkmapping; % load stored disctract object
                
                
                % Fix networkmapping ID in case it has been renamed
                [~, ID] = fileparts(lgpath);
                if ~strcmp(app.networkmapping.ID, ID)
                    app.networkmapping.ID = ID;
                end
                
                % Fix group folder in case it has been moved
                networkGroupFolder = regexprep(app.networkmapping.leadgroup, ['(?<=\',filesep,')[\w-]+\.mat$'], '');
                UIGroupFolder = strrep(lgpath,['networkmapping', filesep, app.networkmapping.ID, '.netmap'],'');
                if ~strcmp(networkGroupFolder, UIGroupFolder)
                    app.networkmapping.analysispath = strrep(app.networkmapping.analysispath, networkGroupFolder, UIGroupFolder);
                    app.networkmapping.leadgroup = strrep(app.networkmapping.leadgroup, networkGroupFolder, UIGroupFolder);
                    app.networkmapping.M.root = strrep(app.networkmapping.M.root, networkGroupFolder, UIGroupFolder);
                end
                
                app.networkmapping.resultfig = resultfig; % attach to figure
                if ~isempty(app.networkmapping.results)
                    if isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                        letsdraw=1;
                    end
                end
            else % init from M struct of LEAD group file
                app.networkmapping = ea_networkmapping;
                try
                    app.networkmapping.initialize(lgpath,resultfig); % initialize which could lead to drawing in case of loaded obj.
                catch ME
                    close(resultfig);
                    app.UIFigureCloseRequest;
                    rethrow(ME);
                end
                if isfield(app.networkmapping.M,'ui')
                    if ismember(app.networkmapping.M.ui.connectomename, app.ConnectomeDropDown.Items)
                        app.ConnectomeDropDown.Value = app.networkmapping.M.ui.connectomename;
                    end
                end
            end
            
            networkmapping2UI(app);
            enablerules(app);
            
            if letsdraw
                app.draw; % show its results
            end
            app.isbusy(0);
            
            hint={'Welcome to the Lead-DBS Network Mapping Explorer!'
                'We are excited about your interest in this tool.'
                ''
                'While the explorer can be a powerful tool, the intended use-case is to'
                'create network mapping models on *part* (e.g. 70%) of your data only.'
                'You can do so by selecting only a subset of patients in the lower right.'
                'Then, the aim is to cross-validate'
                'results using boot-strapping methods (see lower panel).'
                ''
                'This step can be seen as a model-selection step.'
                ''
                'Once you are convinced that the model you created is robust and'
                'yields meaningful predictions within the training/cross-validation set,'
                '(e.g. using leave-one-out or k-fold CV routines in the lower left)'
                'it is intended to finally test the model on the remaining (e.g. 30%)'
                'test set of your data (e.g. using the Custom Strategies in the lower left).'
                ''
                'Please also consider choices of normative connectomes by reading up'
                'the original work and make sure to appropriately cite authors'
                'that acquired or constructed these datasets.'
                ''
                'If unsure, please consult the manual or use our Slack help-channel.'
                'Furthermore, we would like to encourage everyone to explore/adapt'
                'their own code & analyses tailored to the studies specific needs.'
                ''
                'In case of code changes that could be useful for others, as well,'
                'consider contributing them via a pull-request on github.'};
            uigetpref('LeadDBS','NetworkMappingShowUsageHint','DBS network mapping explorer',hint,'Makes Sense');
            
        end

        % Callback function
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            app.networkmapping.splitbygroup = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ModelSetupDropDown
        function ModelSetupDropDownValueChanged(app, event)
            value=app.ModelSetupDropDown.Value;
            app.networkmapping.statmetric = value;
            app.enablerules;
            app.draw;
        end

        % Callback function
        function EfieldthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EfieldthresholdLabel.Text={['Tracts connected if ',lower(app.networkmapping.efieldmetric),' E-Field Magnitude'] ['they traverse is > ',sprintf('%.2f',changingValue),' ',app.getvms]};
        end

        % Value changed function: VariableofInterestVOIDropDown
        function VariableofInterestVOIDropDownValueChanged(app, event)
            app.CovariatesListBox.Items=['Stimulation Amplitude','VTA Volume',app.networkmapping.M.clinical.labels];
            [~,ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.CovariatesListBox.Items);
            app.CovariatesListBox.Items(ix)=[];
            
            [~, ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.networkmapping.M.clinical.labels);
            app.networkmapping.responsevar = app.networkmapping.M.clinical.vars{ix};
            app.networkmapping.responsevarlabel = app.networkmapping.M.clinical.labels{ix};
            app.draw;
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            
        end

        % Callback function
        function IncludeallpatientsCheckBoxValueChanged(app, event)
            value = app.IncludeallpatientsCheckBox.Value;
            switch value
                case 1
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.networkmapping.patientselection = 1:length(app.PatientsListBox.Items);
                case 0
            end
            enablerules(app);
        end

        % Value changed function: ShowPositiveRegionsCheckBox
        function ShowPositiveRegionsCheckBoxValueChanged(app, event)
            value = app.ShowPositiveRegionsCheckBox.Value;
            app.networkmapping.posvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Value changed function: ShowNegativeRegionsCheckBox
        function ShowNegativeRegionsCheckBoxValueChanged(app, event)
            value = app.ShowNegativeRegionsCheckBox.Value;
            app.networkmapping.negvisible = value;
            enablerules(app);
            app.draw;
            
        end

        % Callback function
        function ShowAmountSliderPositiveValueChanging(app, event)
            changingValue = event.Value;
            app.networkmapping.showposamount = app.ShowAmountSliderPositive.Value;
        end

        % Callback function
        function ShowAmountSliderNegativeValueChanging(app, event)
            changingValue = event.Value;
            app.networkmapping.shownegamount = [app.ShowAmountSliderNegative.Value];
        end

        % Value changing function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,' ','_');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'(','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,')','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'-','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'.','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'"','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'~','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'#','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'^','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'|','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'$','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'%','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'&','');
            %
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'*','');
            %             app.AnalysisIDEditFieldValueChanging.Value=strrep(app.AnalysisIDEditFieldValueChanging.Value,'!','');
        end

        % Value changed function: AnalysisIDEditField
        function AnalysisIDEditFieldValueChanged(app, event)
            value = app.AnalysisIDEditField.Value;
            if exist([fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,value,'.netmap'],'file')
                app.AnalysisIDEditField.Value = app.networkmapping.ID;
                msgbox('A tract with this name already exists. Please choose a different name.')
                return
            end
            
            if exist([fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,app.networkmapping.ID,'.netmap'],'file')
                movefile([fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,app.networkmapping.ID,'.netmap'],...
                    [fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,value,'.netmap']);
            end
            
            ht = getappdata(app.networkmapping.resultfig, 'ht');
            if ~isempty(ht)
                Tags = arrayfun(@(tool) tool.Tag, ht.Children, 'Uni', 0);
                pushToolInd = find(contains(Tags,['Explore DBS network mapping analysis ', app.networkmapping.ID]),1);
                set(ht.Children(pushToolInd), 'Tag', ['Explore DBS network mapping analysis ', value],...
                    'TooltipString', ['Explore DBS network mapping analysis ', value], ...
                    'ClickedCallback', {@ea_add_networkmapping, [fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,value,'.netmap'], app.networkmapping.resultfig});
            end
            
            app.networkmapping.ID=value;
            app.networkmapping.save;
            
        end

        % Value changed function: PatientsListBox
        function PatientsListBoxValueChanged(app, event)
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;
            app.draw;
            app.networkmapping2UI;
        end

        % Value changed function: CovariatesListBox
        function CovariatesListBoxValueChanged(app, event)
            setcovars(app);
            app.draw;
        end

        % Callback function
        function LeftSliderPositiveValueChanged(app, event)
            app.networkmapping.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Callback function
        function RightSliderPositiveValueChanged(app, event)
            app.networkmapping.showposamount = [app.RightSliderPositive.Value, app.LeftSliderPositive.Value];
            app.draw;
        end

        % Callback function
        function LeftSliderNegativeValueChanged(app, event)
            app.networkmapping.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Callback function
        function RightSliderNegativeValueChanged(app, event)
            app.networkmapping.shownegamount = [app.RightSliderNegative.Value, app.LeftSliderNegative.Value];
            app.draw;
        end

        % Button pushed function: ColorButtonPositiveBase
        function ColorButtonPositiveBasePushed(app, event)
            c=ea_uisetcolor;
            app.ColorButtonPositiveBase.BackgroundColor=c;
            app.networkmapping.posBaseColor = app.ColorButtonPositiveBase.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegativeBase
        function ColorButtonNegativeBasePushed(app, event)
            c=ea_uisetcolor;
            app.ColorButtonNegativeBase.BackgroundColor=c;
            app.networkmapping.negBaseColor = app.ColorButtonNegativeBase.BackgroundColor;
            app.draw;
        end

        % Callback function
        function EfieldthresholdSliderValueChanged(app, event)
            if strcmp(app.Efieldmetric.Value,'Mean')
                app.networkmapping.efieldthreshold = app.EfieldthresholdSlider.Value;
            else
                app.networkmapping.efieldthreshold = app.EfieldthresholdSlider.Value*1000;
            end
            app.draw;
        end

        % Value changed function: ConnectomeDropDown
        function ConnectomeDropDownValueChanged(app, event)
            value = app.ConnectomeDropDown.Value;
            app.networkmapping.connectome = value;
            enablerules(app);
            val=1;
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val=0;
            end
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: MirrorSidesCheckBox
        function MirrorSidesCheckBoxValueChanged(app, event)
            value = app.MirrorSidesCheckBox.Value;
            app.networkmapping.mirrorsides = app.MirrorSidesCheckBox.Value;
            app.draw;
        end

        % Callback function
        function ShowAmountSliderPositiveValueChanged(app, event)
            value = app.ShowAmountSliderPositive.Value;
            app.draw;
        end

        % Callback function
        function ShowAmountSliderNegativeValueChanged(app, event)
            value = app.ShowAmountSliderNegative.Value;
            app.draw;
        end

        % Callback function
        function ConnthresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            switch app.networkmapping.statmetric
                case 1
                    bubbles='VTAs';
                    app.ConnthresholdLabel.Text = {['Tracts connected to > ',sprintf('%.1f',changingValue),' %'],['and < ',sprintf('%.1f',100-changingValue),' % of ',bubbles]};
                case 2
                    bubbles='E-Fields';
                    app.ConnthresholdLabel.Text = ['Tracts connected to > ',sprintf('%.1f',changingValue),' % of ',bubbles];
            end
        end

        % Callback function
        function ConnthresholdSliderValueChanged(app, event)
            value = app.ConnthresholdSlider.Value;
            app.networkmapping.connthreshold = value;
            app.draw;
        end

        % Button pushed function: RunCVButton
        function RunCVButtonPushed(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.networkmapping.customselection = [];
                    [I,Ihat,R0,R1,pperm]=app.networkmapping.lnopb;
                    I = I(:,1);
                    Ihat = Ihat{1};
                    cvs='lnopb';
                    sel=app.networkmapping.patientselection;
                    h1=ea_plothistperm([app.networkmapping.responsevarlabel,' [Permutation-Based Test]'],[R1;R0],{'Unpermuted prediction'},{1});
                    saveas(h1,[fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,app.networkmapping.ID,'_',app.networkmapping.responsevarlabel,'_',cvs,'_permtest.png']);
                case 'Leave-One-Patient-Out'
                    app.networkmapping.customselection = [];
                    [I,Ihat]=app.networkmapping.loocv;
                    cvs='loocv';
                    sel=app.networkmapping.patientselection;
                case 'Leave-One-Cohort-Out'
                    app.networkmapping.customselection = [];
                    [I,Ihat]=app.networkmapping.lococv;
                    cvs='lococv';
                    sel=app.networkmapping.patientselection;
                case 'k-fold (randomized)'
                    app.networkmapping.customselection = [];
                    app.networkmapping.kiter = app.iterationsEditField.Value;
                    [I,Ihat]=app.networkmapping.kfoldcv;
                    cvs='kfoldcv';
                    sel=app.networkmapping.patientselection;
                case 'Custom (Patients)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
                case 'Custom (Cohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.networkmapping.M.patient.group, str2double(app.TrainOnListBox.Value)))';
                    test = find(ismember(app.networkmapping.M.patient.group,str2double(app.PredictListBox.Value)))';
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_cohs';
                    sel = test;
                case 'Custom (Subcohorts)'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    tsets = find(ismember(app.networkmapping.setlabels, app.TrainOnListBox.Value));
                    training=[];
                    for ts=1:length(tsets)
                        training = [training,find(app.networkmapping.setselections{tsets(ts)})];
                    end
                    training=unique(training);
                    tsets = find(ismember(app.networkmapping.setlabels, app.PredictListBox.Value));
                    test=[];
                    for ts=1:length(tsets)
                        test = [test,find(app.networkmapping.setselections{tsets(ts)})];
                    end
                    test=unique(test);
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_subcohs';
                    sel = test;
                case 'Custom (Sets)'
                    cvp.NumTestSets = 1;
                    % Generate cvpartition
                    rng(app.networkmapping.rngseed);
                    cvpKfold = cvpartition(length(app.networkmapping.M.patient.list), 'KFold', str2double(app.kEditField.Value));
                    % Calculate training and test indices based on the sets selected and the cvpartitions generated
                    trainSetInd = str2double(app.TrainOnListBox.Value');
                    testSetInd = str2double(app.PredictListBox.Value');
                    training = zeros(size(app.networkmapping.M.patient.list));
                    test = zeros(size(app.networkmapping.M.patient.list));
                    for i=1:length(trainSetInd)
                        training = training | cvpKfold.test(trainSetInd(i));
                    end
                    for i=1:length(testSetInd)
                        test = test | cvpKfold.test(testSetInd(i));
                    end
                    training = find(training);
                    test = find(test);
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = ismember(app.networkmapping.customselection, training);
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_sets';
                    sel = test;
                case 'Import Model'
                    cvp.NumTestSets = 1;
                    % training and test indices from the items list
                    training = find(ismember(app.TrainOnListBox.Items,app.TrainOnListBox.Value));
                    test = find(ismember(app.PredictListBox.Items,app.PredictListBox.Value));
                    % Patient selected based on the training and test indices
                    app.networkmapping.customselection = unique([training, test]);
                    % Construct cvp struct
                    cvp.training{1} = 1;
                    cvp.test{1} = ismember(app.networkmapping.customselection, test);
                    [I, Ihat]=app.networkmapping.crossval(cvp);
                    cvs = 'custom_pts';
                    sel = test;
            end
            % check if groups exist:
            if length(unique(app.networkmapping.M.patient.group))>1 && app.PosthoccorrectforgroupCheckBox.Value
                Ihat=ea_resid(ea_cohortregressor(app.networkmapping.M.patient.group(sel)),Ihat);
                suffix=' [Corrected for Cohort]';
            else
                suffix='';
            end
            
            groupID = app.networkmapping.M.patient.group(sel);
            groupColors = app.networkmapping.M.groups.color(unique(groupID),:);
            groupColors = rgb2hsv(groupColors);
            groupColors(:,2) = groupColors(:,2)+((1-groupColors(:,2))/2);
            groupColors=hsv2rgb(groupColors);
            if exist('pperm', 'var')    
                h=ea_corrplot(I,Ihat,pperm,{['Network mapping prediction ',upper(cvs)],'Empirical',['Network-Score',suffix]},groupID,[],groupColors);
            else
                h=ea_corrplot(I,Ihat,'permutation',{['Network mapping prediction ',upper(cvs)],'Empirical',['Network-Score',suffix]},groupID,[],groupColors);
            end
            saveas(h,[fileparts(app.networkmapping.leadgroup),filesep,'networkmapping',filesep,app.networkmapping.ID,'_',app.networkmapping.responsevarlabel,'_',cvs,'.png']);
        end

        % Value changed function: StrategyDropDown
        function StrategyDropDownValueChanged(app, event)
            if strcmp(app.StrategyDropDown.Value,'k-fold (randomized)')
                app.iterationsEditField.Visible = 'on';
                app.iterationsEditFieldLabel.Visible = 'on';
            else
                app.iterationsEditField.Visible = 'off';
                app.iterationsEditFieldLabel.Visible = 'off';
            end

            if strcmp(app.StrategyDropDown.Value,'Import Model')
                app.LoadModelButton.Visible = 'on';
                app.networkmapping.useExternalModel = true;
                app.SubcohortsDropDown_2.Visible = 'on';
                app.SubcohortsDropDown_2Label.Visible = 'on';
                app.TrainOnListBox.Visible = 'off';
                app.ImportedModelShow.Visible = 'on';
                app.LiveVisualizeCheckBox.Visible = 'off';
                app.ShowImportedModelCheckBox.Visible = 'on';
            else
                app.LoadModelButton.Visible = 'off';
                app.networkmapping.useExternalModel = false;
                app.SubcohortsDropDown_2.Visible = 'off';
                app.SubcohortsDropDown_2Label.Visible = 'off';
                app.TrainOnListBox.Visible = 'on';
                app.ImportedModelShow.Visible = 'off';
                app.LiveVisualizeCheckBox.Visible = 'on';
                app.ShowImportedModelCheckBox.Visible = 'off';
            end        

            app.enablerules;
            app.networkmapping2UI;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if exist('event', 'var')
                answer = questdlg("Save Changes?", 'Close Network Mapping Explorer','Yes','No','Yes');
            else
                answer = 'Yes';
            end

            if strcmp(answer, 'Yes') && ~isempty(app.networkmapping)
                app.isbusy(1,'saving');
                app.networkmapping.save;
            end

            if iscell(app.networkmapping.drawobject)
                for s=1:numel(app.networkmapping.drawobject)
                    for ins=1:numel(app.networkmapping.drawobject{s})
                        try delete(app.networkmapping.drawobject{s}{ins}.toggleH); end
                        try delete(app.networkmapping.drawobject{s}{ins}.patchH); end
                        try delete(app.networkmapping.drawobject{s}{ins}); end
                    end
                end
            end

            if isvalid(app.networkmapping.resultfig) && startsWith(app.networkmapping.resultfig.Name, 'No Patient Selected')
                close(app.networkmapping.resultfig);
            end

            app.isbusy(0);
            delete(app);
        end

        % Callback function
        function EfieldmetricValueChanged(app, event)
            value = app.Efieldmetric.Value;
            app.networkmapping.efieldmetric=value;
            switch lower(value)
                case 'sum'
                    app.EfieldthresholdSlider.Limits=[0.5,5];
                    app.networkmapping.efieldthreshold = 2000; %(/1000) V/mm -> V/m
                case 'mean'
                    app.EfieldthresholdSlider.Limits=[5,1000]; % in V/mm (!)
                    app.networkmapping.efieldthreshold = 100;
                case 'peak'
                    app.EfieldthresholdSlider.Limits=[0.05,2.5];
                    app.networkmapping.efieldthreshold = 200; %(/1000) V/mm -> V/m
                case 'peak 5%'
                    app.EfieldthresholdSlider.Limits=[0.05,2];
                    app.networkmapping.efieldthreshold = 150; %(/1000) V/mm -> V/m
            end
            
            app.enablerules;
            app.networkmapping2UI;
            
            if ~isfield(app.networkmapping.results,ea_conn2connid(app.networkmapping.connectome))
                val=0;
            else
                val=(isfield(app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)),ea_method2methodid(app.networkmapping)));
            end
            
            if val % already calculated
                app.draw;
            end
        end

        % Value changed function: kEditField
        function kEditFieldValueChanged(app, event)
            switch app.StrategyDropDown.Value
                case 'Leave-Nothing-Out (Permutation-Based)'
                    app.networkmapping.Nperm = str2double(app.kEditField.Value);
                case 'k-fold (randomized)'
                    app.networkmapping.kfold = str2double(app.kEditField.Value);
                case 'Custom (Sets)'
                    app.networkmapping.Nsets = str2double(app.kEditField.Value);
                    if str2double(app.kEditField.Value)>1
                        app.TrainOnListBox.Items=sprintfc('%d',[1:app.networkmapping.Nsets]');
                        app.PredictListBox.Items=sprintfc('%d',[1:app.networkmapping.Nsets]');
                    end
            end
        end

        % Value changed function: 
        % CleanVOIfromthefollowingcovariatesCheckBox
        function CleanVOIfromthefollowingcovariatesCheckBoxValueChanged(app, event)
            value = app.CleanVOIfromthefollowingcovariatesCheckBox.Value;
            app.enablerules;
            if ~value
                app.networkmapping.covars = {};
                app.networkmapping.covarlabels = {};
                app.draw;
            else
                setcovars(app);
                app.draw;
            end
            
        end

        % Value changed function: PosthoccorrectforgroupCheckBox
        function PosthoccorrectforgroupCheckBoxValueChanged(app, event)
            value = app.PosthoccorrectforgroupCheckBox.Value;
            app.networkmapping.adjustforgroups=value;
        end

        % Menu selected function: 
        % RefreshInformationfromLeadGroupprojectMenu
        function RefreshInformationfromLeadGroupprojectMenuSelected(app, event)
            app.networkmapping.refreshlg;
            app.networkmapping2UI;
        end

        % Button pushed function: RefreshViewButton
        function RefreshViewButtonPushed(app, event)
            app.draw(1); % draw with force = 1.
        end

        % Value changed function: ConsiderSignificantVoxelsonlyCheckBox
        function ConsiderSignificantVoxelsonlyCheckBoxValueChanged(app, event)
            value = app.ConsiderSignificantVoxelsonlyCheckBox.Value;
            app.networkmapping.showsignificantonly=value;
            app.draw;
        end

        % Button pushed function: ExportasNIfTIButton
        function ExportasNIfTIButtonPushed(app, event)
            [file,path]=uiputfile('*.nii','Save Model as...','model.nii');
            if ~isequal(file, 0)
                res=app.networkmapping.draw;
                res.fname=fullfile(path,file);
                ea_write_nii(res);
                if isfield(res,'cifti') % also export cifti file
                    cifti_write(res.cifti,fullfile(path,strrep(file,'.nii','.dscalar.nii')));
                end
            end
        end

        % Callback function
        function CPSetsSetsEditFieldValueChanged(app, event)
            value = app.CPSetsSetsEditField.Value;
            app.networkmapping2UI;
        end

        % Value changed function: TrainOnListBox
        function TrainOnListBoxValueChanged(app, event)
            value = app.TrainOnListBox.Value;
            inverse = app.PredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.PredictListBox.Value=inverse;
            app.enablerules;
        end

        % Callback function
        function CPCohsTrainOnListBoxValueChanged(app, event)
            value = app.CPCohsTrainOnListBox.Value;
            inverse = app.CPCohsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPCohsPredictListBox.Value=inverse;
        end

        % Callback function
        function CPSetsTrainOnListBoxValueChanged(app, event)
            value = app.CPSetsTrainOnListBox.Value;
            inverse = app.CPSetsPredictListBox.Items;
            [~, ix] = ismember(value,inverse);
            inverse(ix)=[];
            app.CPSetsPredictListBox.Value=inverse;
        end

        % Value changed function: PredictListBox
        function PredictListBoxValueChanged(app, event)
            value = app.PredictListBox.Value;
            app.enablerules;
        end

        % Value changed function: levelEditField
        function levelEditFieldValueChanged(app, event)
            value = app.levelEditField.Value;
            app.networkmapping.alphalevel=(value);
            app.draw;
        end

        % Value changed function: MultTestStrategy
        function MultTestStrategyValueChanged(app, event)
            value = app.MultTestStrategy.Value;
            app.networkmapping.multcompstrategy=value;
            app.draw;
        end

        % Value changed function: Corrtype
        function CorrtypeValueChanged(app, event)
            value = app.Corrtype.Value;
            app.networkmapping.corrtype=value;
            switch value
                case {'Skipped Pearson','Skipped Spearman'}
                    app.networkmapping.showsignificantonly=0;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: LiveVisualizeCheckBox
        function LiveVisualizeCheckBoxValueChanged(app, event)
            value = app.LiveVisualizeCheckBox.Value;
            app.networkmapping.cvlivevisualize=value;
        end

        % Value changed function: BaseSimilarityonDropDown
        function BaseSimilarityonDropDownValueChanged(app, event)
            value = app.BaseSimilarityonDropDown.Value;
            app.networkmapping.basepredictionon=value;
        end

        % Value changed function: SubcohortsDropDown
        function SubcohortsDropDownValueChanged(app, event)
            value = app.SubcohortsDropDown.Value;
            switch value
                case 'All Patients'
                    app.PatientsListBox.Value=app.PatientsListBox.Items;
                    app.networkmapping.patientselection = 1:length(app.PatientsListBox.Items);
                case 'Create Subcohort from Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.networkmapping.setlabels{end+1}=setname;
                    app.networkmapping.setselections{end+1}=setidx;
                case 'Create Subcohort from Inverse Selection'
                    setname=inputdlg('Specify Set Name','Create New Set');
                    setname=setname{1};
                    setidx=~ismember(app.PatientsListBox.Items,app.PatientsListBox.Value);
                    app.networkmapping.setlabels{end+1}=setname;
                    app.networkmapping.setselections{end+1}=setidx;
                case 'Choose...' % do nothing
                otherwise
                    [~,whichset]=ismember(value,app.networkmapping.setlabels);
                    app.PatientsListBox.Value=app.PatientsListBox.Items(app.networkmapping.setselections{whichset});
            end
            % draw:
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;
            app.draw;
            % always reset to "Choose..."
            app.enablerules;
        end

        % Menu selected function: SetseedforrandomnumbergeneratorMenu
        function SetseedforrandomnumbergeneratorMenuSelected(app, event)
            rngseed = app.networkmapping.rngseed;
            if ischar(rngseed) && strcmp(rngseed, 'default')
                rngseed = 0;
            end
            rngseed = inputdlg('Input seed for random number generator:', 'Random Seed', [1 40], {num2str(rngseed)});
            if ~isempty(rngseed)
                app.networkmapping.rngseed = str2double(rngseed{1});
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed2(app, event)
            app.networkmapping.leadgroup=app.LeadGroupPathLabel.Text;
            
            %% set up object properties:
            app.networkmapping.posvisible = app.ShowPositiveRegionsCheckBox.Value;
            app.networkmapping.negvisible = app.ShowNegativeRegionsCheckBox.Value;
            
            app.networkmapping.posBaseColor = app.ColorButtonPositiveBase.BackgroundColor;
            app.networkmapping.negBaseColor = app.ColorButtonNegativeBase.BackgroundColor;
            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;
            
            [~, ix]=ismember(app.VariableofInterestVOIDropDown.Value,app.networkmapping.M.clinical.labels);
            app.networkmapping.responsevar = app.networkmapping.M.clinical.vars{ix};
            app.networkmapping.responsevarlabel = app.networkmapping.M.clinical.labels{ix};
            
            setcovars(app);
            
            app.networkmapping.statmetric=app.ModelSetupDropDown.Value;
            
            app.networkmapping.connectome = app.ConnectomeDropDown.Value;
            app.networkmapping.mirrorsides = app.MirrorSidesCheckBox.Value;
            
            % calculate general setup
            app.networkmapping.calculate;
            
            % save dataset:
            app.networkmapping.save;
            
            enablerules(app);
            
            % draw results
            app.draw;
        end

        % Value changed function: VisualizationDropDown
        function VisualizationDropDownValueChanged(app, event)
            value = app.VisualizationDropDown.Value;
            app.networkmapping.vizmode = value;
            if strcmp(value,'Surface (Surfice)')
                app.AutoRefreshCheckBox.Value = false;
            end
            app.enablerules;
            app.draw;
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            app.networkmapping.model = value;
            app.draw;
        end

        % Value changed function: LHCheckBox
        function LHCheckBoxValueChanged(app, event)
            value = app.LHCheckBox.Value;
            app.networkmapping.modelLH = value;
            app.draw;
        end

        % Value changed function: RHCheckBox
        function RHCheckBoxValueChanged(app, event)
            value = app.RHCheckBox.Value;
            app.networkmapping.modelRH = value;
            app.draw;
        end

        % Value changed function: MaskDropDown
        function MaskDropDownValueChanged(app, event)
            value = app.MaskDropDown.Value;
            switch value
                case 'Custom Equation'
                    eq=inputdlg('Enter equation (X = Image to be masked):','Equation',1,{'X>ea_nanmean(X)+ea_nanstd(X)'});
                    app.networkmapping.cvmask=['=Eq:',eq{1}];
                case 'Custom Image'
                    [file,path]=uigetfile({'*.nii','*.gz'},'Please Choose Mask Image File...');
                    app.networkmapping.cvmask=['=Im:',fullfile(path,file)];
                otherwise
                    app.networkmapping.cvmask=value;
            end
        end

        % Button pushed function: ColorButtonPositivePeak
        function ColorButtonPositivePeakPushed(app, event)
            c=ea_uisetcolor;
            app.ColorButtonPositivePeak.BackgroundColor=c;
            app.networkmapping.posPeakColor = app.ColorButtonPositivePeak.BackgroundColor;
            app.draw;
        end

        % Button pushed function: ColorButtonNegativePeak
        function ColorButtonNegativePeakPushed(app, event)
            c=ea_uisetcolor;
            app.ColorButtonNegativePeak.BackgroundColor=c;
            app.networkmapping.negPeakColor = app.ColorButtonNegativePeak.BackgroundColor;
            app.draw;
        end

        % Menu selected function: ExportFingerprintsAsNIfTIFilesMenu
        function ExportFingerprintsAsNIfTIFilesMenuSelected(app, event)
            f=uigetdir();

            if contains(app.networkmapping.connectome, ' > ')
                % For functional connectome, should use the spacedef provided by the connectome itself
                res = app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)).space;
            else
                res = ea_load_nii([ea_getearoot,'templates',filesep,'spacedefinitions',filesep,app.networkmapping.outputspace,'.nii.gz']);
            end
            res.dt=[16,1];
            
            for pt=1:length(app.networkmapping.M.patient.list)
                [~,res.fname]=fileparts(app.networkmapping.M.patient.list{pt});
                res.fname=fullfile(f,[res.fname,'.nii']);
                res.img(:)=app.networkmapping.results.(ea_conn2connid(app.networkmapping.connectome)).connval(pt,:);
                ea_write_nii(res);
            end
            
        end

        % Value changed function: ExportModelsasNIfTICheckBox
        function ExportModelsasNIfTICheckBoxValueChanged(app, event)
            value = app.ExportModelsasNIfTICheckBox.Value;
            app.networkmapping.exportmodelsAsNifti = value;
        end

        % Menu selected function: RecreatevisualizationfigureMenu
        function RecreatevisualizationfigureMenuSelected(app, event)
            if isvalid(app.networkmapping.resultfig)
                close(app.networkmapping.resultfig);
            end
            app.networkmapping.resultfig = ea_mnifigure;
            app.draw(1);
        end

        % Value changed function: NormalizeFingerprintsCheckBox
        function NormalizeFingerprintsCheckBoxValueChanged(app, event)
            app.networkmapping.normalize_fp=app.NormalizeFingerprintsCheckBox.Value;
            app.draw;
        end

        % Value changed function: SmoothFingerprintsCheckBox
        function SmoothFingerprintsCheckBoxValueChanged(app, event)
            app.networkmapping.smooth_fp=app.SmoothFingerprintsCheckBox.Value;
            app.draw;
        end

        % Value changed function: VariableofCertaintyVOCDropDown
        function VariableofCertaintyVOCDropDownValueChanged(app, event)
            value = app.VariableofCertaintyVOCDropDown.Value;
            if strcmp(value,'None')
                app.networkmapping.certainvarlabel='None';
                app.networkmapping.certainvar=nan;
            else
                [~, ix]=ismember(app.VariableofCertaintyVOCDropDown.Value,app.networkmapping.M.clinical.labels);
                app.networkmapping.certainvar = app.networkmapping.M.clinical.vars{ix};
                app.networkmapping.certainvarlabel = app.networkmapping.M.clinical.labels{ix};
            end
            app.draw;
        end

        % Value changed function: SubcohorttotestagainstDropDown
        function SubcohorttotestagainstDropDownValueChanged(app, event)
            value = app.SubcohorttotestagainstDropDown.Value;
            [~,whichset]=ismember(value,app.networkmapping.setlabels);
            app.networkmapping.testagainst_patientselection = app.networkmapping.setselections{whichset};
            app.draw;
            app.enablerules;
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.networkmapping.statthresh=value;
            app.draw;
        end

        % Button pushed function: ExportModelButton
        function ExportModelButtonPushed(app, event)
            [FileName,PathName]=uiputfile('*.mat','File to save the Network Model...');
            ExternalModelFile = fullfile(PathName,FileName);
            ea_save_networkmapping_model(app.networkmapping, ExternalModelFile)
        end

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            [FileName,PathName]=uigetfile('*.mat','Please Choose a Network Model for the selected connectome...');
            if strcmp(string(FileName),'0')
                waitfor(msgbox('The Network Model was not chosen!'));
                return;
            end

            app.TrainOnListBox.Items = string(FileName); % this does not update on-the-fly
            app.networkmapping.ExternalModelFile = fullfile(PathName,FileName);
            app.ImportedModelShow.Items = string(FileName);
            value = app.ShowImportedModelCheckBox.Value;
            app.networkmapping.visualizeExternalModel=value;
        end

        % Value changed function: SubcohortsDropDown_2
        function SubcohortsDropDown_2ValueChanged(app, event)
            value = app.SubcohortsDropDown_2.Value;
            [~,whichset]=ismember(value,app.networkmapping.setlabels);
            app.PredictListBox.Value=app.PatientsListBox.Items(app.networkmapping.setselections{whichset});

            [~, ix]=find(ismember(app.PatientsListBox.Items,app.PatientsListBox.Value));
            app.networkmapping.patientselection = ix;

            app.enablerules;
        end

        % Value changed function: ShowImportedModelCheckBox
        function ShowImportedModelCheckBoxValueChanged(app, event)
            value = app.ShowImportedModelCheckBox.Value;
            app.networkmapping.visualizeExternalModel=value;
            if value == 1
                app.draw(1)
            else
               app.isbusy(2); 
               app.RefreshViewButton.BackgroundColor = [1,0.8,0.16]; % yellow
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.RefreshInformationfromLeadGroupprojectMenu = uimenu(app.ToolsMenu);
            app.RefreshInformationfromLeadGroupprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshInformationfromLeadGroupprojectMenuSelected, true);
            app.RefreshInformationfromLeadGroupprojectMenu.Text = 'Refresh Information from Lead-Group project';
            app.RecreatevisualizationfigureMenu = uimenu(app.ToolsMenu);
            app.RecreatevisualizationfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @RecreatevisualizationfigureMenuSelected, true);
            app.RecreatevisualizationfigureMenu.Text = 'Recreate visualization figure';
            app.SetseedforrandomnumbergeneratorMenu = uimenu(app.ToolsMenu);
            app.SetseedforrandomnumbergeneratorMenu.MenuSelectedFcn = createCallbackFcn(app, @SetseedforrandomnumbergeneratorMenuSelected, true);
            app.SetseedforrandomnumbergeneratorMenu.Text = 'Set seed for random number generator';
            app.ExportFingerprintsAsNIfTIFilesMenu = uimenu(app.ToolsMenu);
            app.ExportFingerprintsAsNIfTIFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFingerprintsAsNIfTIFilesMenuSelected, true);
            app.ExportFingerprintsAsNIfTIFilesMenu.Text = 'Export Fingerprints As NIfTI Files';
            app.AnalysisIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnalysisIDEditFieldLabel.Text = 'Analysis ID';
            app.AnalysisIDEditField = uieditfield(app.UIFigure, 'text');
            app.AnalysisIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanged, true);
            app.AnalysisIDEditField.ValueChangingFcn = createCallbackFcn(app, @AnalysisIDEditFieldValueChanging, true);
            app.AnalysisIDEditField.Value = 'new_voxel_analysis';
            app.LeadGroupPathLabel = uilabel(app.UIFigure);
            app.LeadGroupPathLabel.Text = 'Lead-Group Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '<';
            app.ConnectomeSelectionPanel = uipanel(app.UIFigure);
            app.ConnectomeSelectionPanel.Title = 'Connectome Selection';
            app.ConnectomeDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.ValueChangedFcn = createCallbackFcn(app, @ConnectomeDropDownValueChanged, true);
            app.ConnectomeDropDown.Value = '';
            app.CalculateButton = uibutton(app.ConnectomeSelectionPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResolutionDropDownLabel = uilabel(app.ConnectomeSelectionPanel);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.ConnectomeSelectionPanel);
            app.ResolutionDropDown.Items = {'2 mm', '1 mm', '0.5 mm'};
            app.ResolutionDropDown.Value = '2 mm';
            app.InteractiveModelSetupPanel = uipanel(app.UIFigure);
            app.InteractiveModelSetupPanel.Title = 'Interactive Model Setup';
            app.MirrorSidesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.MirrorSidesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MirrorSidesCheckBoxValueChanged, true);
            app.MirrorSidesCheckBox.Enable = 'off';
            app.MirrorSidesCheckBox.Text = 'Mirror VTAs / Efields';
            app.PatientsListBoxLabel = uilabel(app.InteractiveModelSetupPanel);
            app.PatientsListBoxLabel.Enable = 'off';
            app.PatientsListBoxLabel.Text = 'Patients';
            app.PatientsListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.PatientsListBox.Multiselect = 'on';
            app.PatientsListBox.ValueChangedFcn = createCallbackFcn(app, @PatientsListBoxValueChanged, true);
            app.PatientsListBox.Enable = 'off';
            app.PatientsListBox.Value = {'Item 1'};
            app.VariableofInterestVOIDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofInterestVOIDropDownLabel.Enable = 'off';
            app.VariableofInterestVOIDropDownLabel.Text = 'Variable of Interest (VOI)';
            app.VariableofInterestVOIDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofInterestVOIDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofInterestVOIDropDownValueChanged, true);
            app.VariableofInterestVOIDropDown.Enable = 'off';
            app.CovariatesListBox = uilistbox(app.InteractiveModelSetupPanel);
            app.CovariatesListBox.Multiselect = 'on';
            app.CovariatesListBox.ValueChangedFcn = createCallbackFcn(app, @CovariatesListBoxValueChanged, true);
            app.CovariatesListBox.Enable = 'off';
            app.CovariatesListBox.Value = {'Item 1'};
            app.CleanVOIfromthefollowingcovariatesCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.CleanVOIfromthefollowingcovariatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CleanVOIfromthefollowingcovariatesCheckBoxValueChanged, true);
            app.CleanVOIfromthefollowingcovariatesCheckBox.Enable = 'off';
            app.CleanVOIfromthefollowingcovariatesCheckBox.Text = 'Clean VOI from the following covariates';
            app.ModelSetupDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDownLabel.Enable = 'off';
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.ModelSetupDropDown.Items = {'Correlations (Horn 2017)', 'Weighted Average (Horn 2017)', 'Combined Map (Horn 2017)', 'Thresholded N-map (Boes 2015)', 'One Sample T-Test', 'Two Sample T-Test', 'Database Lookup', 'Weighted R2-Map'};
            app.ModelSetupDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelSetupDropDownValueChanged, true);
            app.ModelSetupDropDown.Enable = 'off';
            app.ModelSetupDropDown.Value = 'Correlations (Horn 2017)';
            app.Corrtype = uidropdown(app.InteractiveModelSetupPanel);
            app.Corrtype.Items = {'Spearman', 'Pearson', 'Bend', 'Skipped Spearman', 'Skipped Pearson'};
            app.Corrtype.ValueChangedFcn = createCallbackFcn(app, @CorrtypeValueChanged, true);
            app.Corrtype.Enable = 'off';
            app.Corrtype.Value = 'Spearman';
            app.SubcohortsDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDownLabel.Enable = 'off';
            app.SubcohortsDropDownLabel.Text = 'Subcohorts';
            app.SubcohortsDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohortsDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohortsDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDownValueChanged, true);
            app.SubcohortsDropDown.Enable = 'off';
            app.SubcohortsDropDown.Value = 'Choose...';
            app.SmoothFingerprintsCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.SmoothFingerprintsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothFingerprintsCheckBoxValueChanged, true);
            app.SmoothFingerprintsCheckBox.Enable = 'off';
            app.SmoothFingerprintsCheckBox.Text = 'Smooth Fingerprints';
            app.NormalizeFingerprintsCheckBox = uicheckbox(app.InteractiveModelSetupPanel);
            app.NormalizeFingerprintsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeFingerprintsCheckBoxValueChanged, true);
            app.NormalizeFingerprintsCheckBox.Enable = 'off';
            app.NormalizeFingerprintsCheckBox.Text = 'Normalize Fingerprints';
            app.VariableofCertaintyVOCDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.VariableofCertaintyVOCDropDownLabel.Enable = 'off';
            app.VariableofCertaintyVOCDropDownLabel.Text = 'Variable of Certainty (VOC)';
            app.VariableofCertaintyVOCDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.VariableofCertaintyVOCDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableofCertaintyVOCDropDownValueChanged, true);
            app.VariableofCertaintyVOCDropDown.Enable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.InteractiveModelSetupPanel);
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.InteractiveModelSetupPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Enable = 'off';
            app.ThresholdEditField.Value = 0.2;
            app.SubcohorttotestagainstDropDownLabel = uilabel(app.InteractiveModelSetupPanel);
            app.SubcohorttotestagainstDropDownLabel.Enable = 'off';
            app.SubcohorttotestagainstDropDownLabel.Text = 'Subcohort to test against';
            app.SubcohorttotestagainstDropDown = uidropdown(app.InteractiveModelSetupPanel);
            app.SubcohorttotestagainstDropDown.Items = {'Choose...', 'All Patients', 'Set from Selection', 'Inverse Set from Selection'};
            app.SubcohorttotestagainstDropDown.ValueChangedFcn = createCallbackFcn(app, @SubcohorttotestagainstDropDownValueChanged, true);
            app.SubcohorttotestagainstDropDown.Enable = 'off';
            app.SubcohorttotestagainstDropDown.Value = 'Choose...';
            app.BusyLampLabel = uilabel(app.UIFigure);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.UIFigure);
            app.VisualizationThresholdsPanel = uipanel(app.UIFigure);
            app.VisualizationThresholdsPanel.Title = 'Visualization & Thresholds';
            app.ColorBar = uiaxes(app.VisualizationThresholdsPanel);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.ColorButtonPositiveBase = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositiveBase.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositiveBasePushed, true);
            app.ColorButtonPositiveBase.Enable = 'off';
            app.ColorButtonPositiveBase.Text = 'Color';
            app.ColorButtonNegativeBase = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegativeBase.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativeBasePushed, true);
            app.ColorButtonNegativeBase.Enable = 'off';
            app.ColorButtonNegativeBase.Text = 'Color';
            app.ShowPositiveRegionsCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowPositiveRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowPositiveRegionsCheckBoxValueChanged, true);
            app.ShowPositiveRegionsCheckBox.Enable = 'off';
            app.ShowPositiveRegionsCheckBox.Text = 'Show Positive Regions';
            app.ShowPositiveRegionsCheckBox.Value = true;
            app.ShowNegativeRegionsCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ShowNegativeRegionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNegativeRegionsCheckBoxValueChanged, true);
            app.ShowNegativeRegionsCheckBox.Enable = 'off';
            app.ShowNegativeRegionsCheckBox.Text = 'Show Negative Regions';
            app.ExportasNIfTIButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportasNIfTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportasNIfTIButtonPushed, true);
            app.ExportasNIfTIButton.Enable = 'off';
            app.ExportasNIfTIButton.Text = 'Export as NIfTI';
            app.ConsiderSignificantVoxelsonlyCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.ConsiderSignificantVoxelsonlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsiderSignificantVoxelsonlyCheckBoxValueChanged, true);
            app.ConsiderSignificantVoxelsonlyCheckBox.Enable = 'off';
            app.ConsiderSignificantVoxelsonlyCheckBox.Text = 'Consider Significant Voxels only';
            app.RefreshViewButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.RefreshViewButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshViewButtonPushed, true);
            app.RefreshViewButton.Enable = 'off';
            app.RefreshViewButton.Text = 'Refresh View';
            app.AutoRefreshCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.AutoRefreshCheckBox.Enable = 'off';
            app.AutoRefreshCheckBox.Text = 'Auto-Refresh';
            app.levelEditFieldLabel = uilabel(app.VisualizationThresholdsPanel);
            app.levelEditFieldLabel.Enable = 'off';
            app.levelEditFieldLabel.Text = '𝝰-level';
            app.levelEditField = uieditfield(app.VisualizationThresholdsPanel, 'numeric');
            app.levelEditField.ValueChangedFcn = createCallbackFcn(app, @levelEditFieldValueChanged, true);
            app.levelEditField.Enable = 'off';
            app.levelEditField.Value = 0.05;
            app.MultTestStrategy = uidropdown(app.VisualizationThresholdsPanel);
            app.MultTestStrategy.Items = {'FDR', 'Bonferroni', 'Uncorrected'};
            app.MultTestStrategy.ValueChangedFcn = createCallbackFcn(app, @MultTestStrategyValueChanged, true);
            app.MultTestStrategy.Enable = 'off';
            app.MultTestStrategy.Value = 'FDR';
            app.LHCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.LHCheckBox.ValueChangedFcn = createCallbackFcn(app, @LHCheckBoxValueChanged, true);
            app.LHCheckBox.Enable = 'off';
            app.LHCheckBox.Text = 'LH';
            app.RHCheckBox = uicheckbox(app.VisualizationThresholdsPanel);
            app.RHCheckBox.ValueChangedFcn = createCallbackFcn(app, @RHCheckBoxValueChanged, true);
            app.RHCheckBox.Enable = 'off';
            app.RHCheckBox.Text = 'RH';
            app.VisualizationDropDownLabel = uilabel(app.VisualizationThresholdsPanel);
            app.VisualizationDropDownLabel.Enable = 'off';
            app.VisualizationDropDownLabel.Text = 'Visualization';
            app.VisualizationDropDown = uidropdown(app.VisualizationThresholdsPanel);
            app.VisualizationDropDown.Items = {'Regions', 'Surface (Elvis)', 'Surface (Surfice)'};
            app.VisualizationDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationDropDownValueChanged, true);
            app.VisualizationDropDown.Enable = 'off';
            app.VisualizationDropDown.Value = 'Regions';
            app.ModelDropDownLabel = uilabel(app.VisualizationThresholdsPanel);
            app.ModelDropDownLabel.Enable = 'off';
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.VisualizationThresholdsPanel);
            app.ModelDropDown.Items = {'Smoothed', 'Full'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Enable = 'off';
            app.ModelDropDown.Value = 'Smoothed';
            app.ColorButtonNegativePeak = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonNegativePeak.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonNegativePeakPushed, true);
            app.ColorButtonNegativePeak.Enable = 'off';
            app.ColorButtonNegativePeak.Text = 'Color';
            app.ColorButtonPositivePeak = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ColorButtonPositivePeak.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPositivePeakPushed, true);
            app.ColorButtonPositivePeak.Enable = 'off';
            app.ColorButtonPositivePeak.Text = 'Color';
            app.ExportModelButton = uibutton(app.VisualizationThresholdsPanel, 'push');
            app.ExportModelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportModelButtonPushed, true);
            app.ExportModelButton.Text = 'Export Model';
            app.CrossvalidationPredictionPanel = uipanel(app.UIFigure);
            app.CrossvalidationPredictionPanel.Title = 'Crossvalidation & Prediction';
            app.TrainOnListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.TrainOnListBoxLabel.Enable = 'off';
            app.TrainOnListBoxLabel.Text = 'Train On';
            app.TrainOnListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.TrainOnListBox.Multiselect = 'on';
            app.TrainOnListBox.ValueChangedFcn = createCallbackFcn(app, @TrainOnListBoxValueChanged, true);
            app.TrainOnListBox.Enable = 'off';
            app.TrainOnListBox.Value = {'Item 1'};
            app.PredictListBoxLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.PredictListBoxLabel.Enable = 'off';
            app.PredictListBoxLabel.Text = 'Predict';
            app.PredictListBox = uilistbox(app.CrossvalidationPredictionPanel);
            app.PredictListBox.Multiselect = 'on';
            app.PredictListBox.ValueChangedFcn = createCallbackFcn(app, @PredictListBoxValueChanged, true);
            app.PredictListBox.Enable = 'off';
            app.PredictListBox.Value = {'Item 1'};
            app.RunCVButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.RunCVButton.ButtonPushedFcn = createCallbackFcn(app, @RunCVButtonPushed, true);
            app.RunCVButton.Enable = 'off';
            app.RunCVButton.Text = 'Run';
            app.PosthoccorrectforgroupCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.PosthoccorrectforgroupCheckBox.ValueChangedFcn = createCallbackFcn(app, @PosthoccorrectforgroupCheckBoxValueChanged, true);
            app.PosthoccorrectforgroupCheckBox.Enable = 'off';
            app.PosthoccorrectforgroupCheckBox.Text = 'Post-hoc correct for group';
            app.StrategyDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.StrategyDropDownLabel.Enable = 'off';
            app.StrategyDropDownLabel.Text = 'Strategy';
            app.StrategyDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.StrategyDropDown.Items = {'Leave-Nothing-Out (Permutation-Based)', 'Leave-One-Patient-Out', 'Leave-One-Cohort-Out', 'k-fold (randomized)', 'Custom (Patients)', 'Custom (Cohorts)', 'Custom (Sets)', 'Custom (Subcohorts)', 'Import Model'};
            app.StrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @StrategyDropDownValueChanged, true);
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Value = 'Leave-Nothing-Out (Permutation-Based)';
            app.kEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.kEditFieldLabel.Enable = 'off';
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.CrossvalidationPredictionPanel, 'text');
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @kEditFieldValueChanged, true);
            app.kEditField.Enable = 'off';
            app.LiveVisualizeCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.LiveVisualizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @LiveVisualizeCheckBoxValueChanged, true);
            app.LiveVisualizeCheckBox.Enable = 'off';
            app.LiveVisualizeCheckBox.Text = 'Live Visualize';
            app.BaseSimilarityonDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.BaseSimilarityonDropDownLabel.Enable = 'off';
            app.BaseSimilarityonDropDownLabel.Text = 'Base Similarity on ';
            app.BaseSimilarityonDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.BaseSimilarityonDropDown.Items = {'Spatial Correlations (Spearman)', 'Spatial Correlations (Pearson)', 'Spatial Correlations (Bend)'};
            app.BaseSimilarityonDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseSimilarityonDropDownValueChanged, true);
            app.BaseSimilarityonDropDown.Enable = 'off';
            app.BaseSimilarityonDropDown.Value = 'Spatial Correlations (Spearman)';
            app.MaskDropDownLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.MaskDropDownLabel.Enable = 'off';
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.CrossvalidationPredictionPanel);
            app.MaskDropDown.Items = {'Gray Matter', 'Brain', 'Cortex & Cerebellum', 'Cortex', 'Cerebellum', 'Custom Image', 'Custom Equation'};
            app.MaskDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskDropDownValueChanged, true);
            app.MaskDropDown.Enable = 'off';
            app.MaskDropDown.Value = 'Gray Matter';
            app.ExportModelsasNIfTICheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.ExportModelsasNIfTICheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportModelsasNIfTICheckBoxValueChanged, true);
            app.ExportModelsasNIfTICheckBox.Enable = 'off';
            app.ExportModelsasNIfTICheckBox.Text = 'Export Models as NIfTI';
            app.iterationsEditFieldLabel = uilabel(app.CrossvalidationPredictionPanel);
            app.iterationsEditFieldLabel.Visible = 'off';
            app.iterationsEditFieldLabel.Text = 'iterations';
            app.iterationsEditField = uieditfield(app.CrossvalidationPredictionPanel, 'numeric');
            app.iterationsEditField.Visible = 'off';
            app.iterationsEditField.Value = 1;
            app.LoadModelButton = uibutton(app.CrossvalidationPredictionPanel, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Visible = 'off';
            app.LoadModelButton.Text = 'Load Model';
            app.SubcohortsDropDown_2Label = uilabel(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2Label.Visible = 'off';
            app.SubcohortsDropDown_2Label.Text = 'Subcohorts';
            app.SubcohortsDropDown_2 = uidropdown(app.CrossvalidationPredictionPanel);
            app.SubcohortsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SubcohortsDropDown_2ValueChanged, true);
            app.SubcohortsDropDown_2.Visible = 'off';
            app.ImportedModelShow = uilistbox(app.CrossvalidationPredictionPanel);
            app.ImportedModelShow.Visible = 'off';
            app.ShowImportedModelCheckBox = uicheckbox(app.CrossvalidationPredictionPanel);
            app.ShowImportedModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowImportedModelCheckBoxValueChanged, true);
            app.ShowImportedModelCheckBox.Visible = 'off';
            app.ShowImportedModelCheckBox.Text = 'Show Imported Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_networkmappingexplorer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end