classdef ea_calculate_unifiedmapping < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FiberfilteringButtonGroup      matlab.ui.container.ButtonGroup
        StatisticsPanel                matlab.ui.container.Panel
        EfieldmetricDropDown_2         matlab.ui.control.DropDown
        StimulationModelDropDown       matlab.ui.control.DropDown
        StimulationModelDropDownLabel  matlab.ui.control.Label
        EfieldmetricDropDown           matlab.ui.control.DropDown
        EfieldmetricDropDownLabel      matlab.ui.control.Label
        BiophysicalModelFamilyButtonGroup  matlab.ui.container.ButtonGroup
        PathwayActivationModelingButton  matlab.ui.control.RadioButton
        StimulationVolumesButton       matlab.ui.control.RadioButton
        CalculateinDropDown            matlab.ui.control.DropDown
        CalculateinDropDownLabel       matlab.ui.control.Label
        CalculationMethodButtonGroup   matlab.ui.container.ButtonGroup
        FiberBasedMethodButenko2025Button  matlab.ui.control.RadioButton
        EFieldBasedMethodButton        matlab.ui.control.RadioButton
        CalculationSettingsPanel       matlab.ui.container.Panel
        SweetspotButtonGroup           matlab.ui.container.ButtonGroup
        mmLabel                        matlab.ui.control.Label
        SSResolutionEditField          matlab.ui.control.NumericEditField
        SSResolutionEditFieldLabel     matlab.ui.control.Label
        PrefilteringButtonGroup        matlab.ui.container.ButtonGroup
        VmLabel                        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        ThresholdEditField_3Label      matlab.ui.control.Label
        NetworkmappingButtonGroup      matlab.ui.container.ButtonGroup
        MaskwithSeedCheckBox           matlab.ui.control.CheckBox
        NormalizeFingerprintsCheckBox  matlab.ui.control.CheckBox
        SmoothFingerprintsCheckBox     matlab.ui.control.CheckBox
        ResolutionstructuralDropDown   matlab.ui.control.DropDown
        ResolutionstructuralDropDownLabel  matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        explorerobj % tractset like object which will save the calculate settings!
        TargetExplorerApp
    end
    
    
    methods (Access = public)
        
        function results = enable_rules(app)
                app.CalculationMethodButtonGroup.Enable = 'on';
                app.EFieldBasedMethodButton.Enable = 'on';
                app.FiberBasedMethodButenko2025Button.Enable = 'on';
                app.CalculateinDropDown.Enable = 'on';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'on';
                app.PathwayActivationModelingButton.Enable = 'on';
                app.StimulationVolumesButton.Enable = 'on';
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdEditField_3Label.Enable = 'on';
                
                %set up for fiber filtering options
                biophysicalModel = app.BiophysicalModelFamilyButtonGroup.SelectedObject.Text;
                if strcmp(biophysicalModel, 'Stimulation Volumes')
                    spaceValue = app.CalculateinDropDown.Value;
                    if strcmp (spaceValue, 'Template Space')
                        app.CalculationMethodButtonGroup.Enable = 'off';
                        app.EfieldmetricDropDown.Enable = 'off';
                        app.EfieldmetricDropDownLabel.Enable = 'off';
                        app.EfieldmetricDropDown.Value = 'Magnitude';
                    elseif strcmp(spaceValue, 'Native Space')
                        app.CalculationMethodButtonGroup.Enable = 'on';
                        app.EfieldmetricDropDown.Enable = 'on';
                        app.EfieldmetricDropDownLabel.Enable = 'on';
                    end

                    % app.EfieldmetricDropDown.Enable = 'on';
                    app.StimulationModelDropDown.Enable = 'on';
                    app.EfieldmetricDropDown_2.Enable = 'on';
                elseif strcmp(biophysicalModel, 'Pathway Activation Modeling')
                    app.CalculationMethodButtonGroup.Enable = 'off';
                    app.EfieldmetricDropDown.Enable = 'off';
                    app.StimulationModelDropDown.Enable = 'off';
                    app.StimulationModelDropDownLabel.Enable = 'off';
                    app.EfieldmetricDropDown_2.Enable = 'off';
                end
                
                activation_metric = app.explorerobj.statsettings.stimulationmodel;
                if strcmp(activation_metric, 'Sigmoid Field')
                    app.EfieldmetricDropDown_2.Value = 'Peak';
                    app.EfieldmetricDropDown_2.Enable = 'off';
                    app.explorerobj.statsettings.efieldmetric = 'Peak';
                else
                    app.EfieldmetricDropDown_2.Enable='on';
                end

                %set up when template vs native space
                % spaceValue = app.CalculateinDropDown.Value;
                % if strcmp (spaceValue, 'Template Space')
                % elseif strcmp(spaceValue, 'Native Space')
                % end
                % switch value
                %     case 'Template Space'
                %         app.CalculationMethodButtonGroup.SelectedObject = ...
                %             app.EFieldBasedMethodButton;
                % 
                %         app.explorerobj.calcsettings.calcspace  = 1;
                %         app.explorerobj.calcsettings.calcmethod = 1;
                % 
                %     case 'Native Space'
                %         app.CalculationMethodButtonGroup.SelectedObject = ...
                %             app.FiberBasedMethodButenko2025Button;
                % 
                %         app.explorerobj.calcsettings.calcspace  = 0;
                %         app.explorerobj.calcsettings.calcmethod = 2;
                % end

                % app.ResolutionfunctionalDropDown.Enable = 'on';

                % app.ConnectomeDropDown_2Label.Enable = 'off';
                % app.ConnectomeDropDown.Enable = 'off';
                % 
                % app.ConnectomeDropDown_2Label.Enable = 'on';
                % app.ConnectomeDropDown.Enable = 'on';
                % [mdl,sf]=ea_genmodlist;
                % dmri_connectomenames = mdl(sf==1);
                % dmri_mtconnectomenames = mdl(sf==3);
                % app.ConnectomeDropDown.Items = [dmri_connectomenames,dmri_mtconnectomenames];
                % app.ResolutionstructuralDropDownLabel.Visible = 'off';
                % app.ResolutionstructuralDropDown.Visible = 'off';
                % app.SSResolutionEditField.Visible = 'off';
                % app.SSResolutionEditFieldLabel.Visible = 'off';
                % app.CalculateinDropDownLabel.Enable = 'on';
                % app.CalculateinDropDown.Enable = 'on';
                % app.FiberfilteringCalculationMethodSwitch.Enable = 'on';
                % app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'on';
                % app.BiophysicalModelFamilyButtonGroup.Enable = 'on';
                % app.PathwayActivationModelingButton.Enable = 'on';
                % app.StimulationVolumesButton.Enable = 'on';
                % app.ThresholdEditField.Enable = 'on';
                % app.ThresholdEditField_3Label.Enable = 'on';
                % 
                % app.ConnectomeDropDown_2Label.Enable = 'on';
                % app.ConnectomeDropDown.Enable = 'on';
                % [mdl,sf]=ea_genmodlist;
                % app.ConnectomeDropDown.Items=mdl;
                % [~,ix]=ismember(app.ConnectomeDropDown.Value,mdl);
                % switch sf(ix)
                %     case 1 % structural connectome selected
                %         app.ResolutionstructuralDropDown.Visible = 'on';
                %         app.ResolutionstructuralDropDownLabel.Visible = 'on';
                %     case 2 % functional connectome selected
                %         app.ResolutionstructuralDropDown.Visible = 'off';
                %         app.ResolutionstructuralDropDown.Value = '2 mm';
                % end
                % app.SSResolutionEditField.Visible = 'off';
                % app.SSResolutionEditFieldLabel.Visible = 'off';
                % app.CalculateinDropDownLabel.Enable = 'off';
                % app.CalculateinDropDown.Enable = 'off';
                % app.FiberfilteringCalculationMethodSwitch.Enable = 'off';
                % app.FiberfilteringCalculationMethodSwitchLabel.Enable = 'off';
                % app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                % app.PathwayActivationModelingButton.Enable = 'off';
                % app.StimulationVolumesButton.Enable = 'off';
                % app.ThresholdEditField.Enable = 'on';
                % app.ThresholdEditField_3Label.Enable = 'on';            

        end
    end
    
    methods (Access = private)

        function disableSettings(app)
             % Check sweetspot
            if isfield(app.explorerobj.results, 'sweetspotmapping') && ...
                    ~isempty(app.explorerobj.results.sweetspotmapping)
                % disable Sweetspot controls
                app.SSResolutionEditField.Enable = 'off';
                app.SSResolutionEditFieldLabel.Enable = 'off';
            end
        
            % Check fiberfiltering
            if isfield(app.explorerobj.results, 'fiberfiltering') && ...
                    ~isempty(app.explorerobj.results.fiberfiltering)
                % disable Fiberfiltering controls
                app.CalculationMethodButtonGroup.Enable = 'off';
                app.EFieldBasedMethodButton.Enable = 'off';
                app.FiberBasedMethodButenko2025Button.Enable = 'off';
                app.CalculateinDropDown.Enable = 'off';
                app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
                app.PathwayActivationModelingButton.Enable = 'off';
                app.StimulationVolumesButton.Enable = 'off';
            end
        
            % Check networkmapping
            if isfield(app.explorerobj.results, 'networkmapping') && ...
                    ~isempty(app.explorerobj.results.networkmapping)
                % disable Networkmapping controls
                % app.NormalizeFingerprintsCheckBox.Enable = 'off';
                % app.SmoothFingerprintsCheckBox.Enable = 'off';
                app.ResolutionstructuralDropDown.Enable = 'off';
                app.ResolutionstructuralDropDownLabel.Enable = 'off';
            end
            % app.CalculationMethodButtonGroup.Enable = 'off';
            % app.EFieldBasedMethodButton.Enable = 'off';
            % app.FiberBasedMethodButenko2025Button.Enable = 'off';
            % app.CalculateinDropDown.Enable = 'off';
            % app.BiophysicalModelFamilyButtonGroup.Enable = 'off';
            % app.PathwayActivationModelingButton.Enable = 'off';
            % app.StimulationVolumesButton.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            % app.ThresholdEditField_3Label.Enable = 'off';          
        end

        function pull_statssettings(app)
             app.StimulationModelDropDown.Value = app.explorerobj.statsettings.stimulationmodel;
             app.EfieldmetricDropDown_2.Value = app.explorerobj.statsettings.efieldmetric;
             app.EfieldmetricDropDown.Value = app.explorerobj.e_field_metric;       
             app.NormalizeFingerprintsCheckBox.Value = app.explorerobj.normalize_fp; %this should be mvoed to statsettings field too
             app.SmoothFingerprintsCheckBox.Value = app.explorerobj.smooth_fp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, explorer)

            if ~exist('explorer','var')
                ea_error('Something went wrong! try again!');
            else
                app.explorerobj = explorer;
            end
            app.SaveButton.BackgroundColor = [0 1 0];
            %check if something has been saved
            app.UIFigure.Name = 'Calculate Settings';
            initialize_settings(app)
            app.StimulationModelDropDown.Items = {'Electric Field', 'Probability Activation Function', 'VTA'};
            app.StimulationModelDropDown.ItemsData = {'Electric Field', 'Sigmoid Field', 'VTA'};
            
            if strcmp(app.explorerobj.statsettings.stimulationmodel, 'Probability Activation Function')
                app.explorerobj.statsettings.stimulationmodel = 'Sigmoid Field';
            end
            pull_statssettings(app)
    
            if app.explorerobj.hasResults
                disableSettings(app);
            end

            app.StimulationModelDropDown.Items = {'Electric Field', 'Probability Activation Function', 'VTA'};
            app.StimulationModelDropDown.ItemsData = {'Electric Field', 'Sigmoid Field', 'VTA'};

            function initialize_settings(app)
                % %tools button group
                % if app.explorerobj.calcsettings.selectedTool == 1
                %     app.SweetspotButton.Value = 1;
                % elseif app.explorerobj.calcsettings.selectedTool == 2
                %     app.FiberfilteringButton.Value = 1;
                % elseif app.explorerobj.calcsettings.selectedTool == 3
                %     app.NetworkmappingButton.Value = 1;
                % end
                enable_rules(app);
                %connectome
                % if app.explorerobj.drawTool == 2
                %     if ~isempty(app.explorerobj.calcsettings.fibfilt_connectome)
                %         app.ConnectomeDropDown.Value = app.explorerobj.calcsettings.fibfilt_connectome;
                %     end
                % elseif app.explorerobj.drawTool == 3
                %     if ~isempty(app.explorerobj.calcsettings.netmap_connectome)
                %         app.ConnectomeDropDown.Value = app.explorerobj.calcsettings.netmap_connectome;
                %     end
                % end
                
                %threshold
                app.ThresholdEditField.Value = app.explorerobj.calcsettings.calcthreshold;
                %resolution
                app.ResolutionstructuralDropDown.Value = app.explorerobj.calcsettings.structuralresolution;
                % app.ResolutionfunctionalDropDown.Value = app.explorerobj.calcsettings.functionalresolution;
                %calculate in
                if app.explorerobj.calcsettings.calcspace == 1
                    app.CalculateinDropDown.Value = 'Template Space';
                elseif app.explorerobj.calcsettings.calcspace == 0
                    app.CalculateinDropDown.Value = 'Native Space';
                end
                %Fiberfiltering calculation method
                switch app.explorerobj.calcsettings.calcmethod
                    case 1
                        app.EFieldBasedMethodButton.Value = true;
                        app.FiberBasedMethodButenko2025Button.Value = false;
                    case 2
                        app.EFieldBasedMethodButton.Value = false;
                        app.FiberBasedMethodButenko2025Button.Value = true;
                end
                % if app.explorerobj.calcsettings.calcmethod == 1
                %     app.EFieldBasedMethodButton.Value = 1;
                % elseif app.explorerobj.calcsettings.calcmethod == 2
                %     app.EFieldBasedMethodButton.Value = 2;
                % end
                %biophysical model family
                if app.explorerobj.calcsettings.switch_connectivity == 1
                    if app.explorerobj.calcsettings.connectivity_type == 1
                        app.StimulationVolumesButton.Value = 1;
                    elseif app.explorerobj.calcsettings.connectivity_type == 2
                        app.PathwayActivationModelingButton.Value = 1;
                    end
                end
                % restore normalize/smooth checkboxes
                if isfield(app.explorerobj.calcsettings, 'normalize_fp')
                    app.NormalizeFingerprintsCheckBox.Value = app.explorerobj.calcsettings.normalize_fp;
                end

                if isfield(app.explorerobj.calcsettings, 'smooth_fp')
                    app.SmoothFingerprintsCheckBox.Value = app.explorerobj.calcsettings.smooth_fp;
                end
                % decide whether mask nm with seeds or not
                app.MaskwithSeedCheckBox.Value = app.explorerobj.mask_vta_fp; 
            end

        end

        % Selection changed function: SweetspotButtonGroup
        function SweetspotButtonGroupSelectionChanged(app, event)
            selectedButton = app.SweetspotButtonGroup.SelectedObject;
            enable_rules(app);           
        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % selectedButton = app.SweetspotButtonGroup.SelectedObject;
            % if strcmp(selectedButton.Text,'Sweetspot')
            %     selectedTool = 1;
            % elseif strcmp(selectedButton.Text,'Fiberfiltering')
            %     selectedTool = 2;
            % elseif strcmp(selectedButton.Text,'Networkmapping')
            %     selectedTool = 3;
            % end
            % app.explorerobj.calcsettings.selectedTool = selectedTool;
            % if selectedTool == 2
            %     app.explorerobj.calcsettings.fibfilt_connectome = app.ConnectomeDropDown.Value;
            %     if exist(fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown.Value),'dir') %this is inside the dMRI_MultiTract folder
            %         if exist(fullfile(ea_getconnectomebase('dmri'),app.ConnectomeDropDown.Value),'dir') %same connectome exists in both
            %             warndlg("The same connectome exists in Multitract format as well as in Singletract format. Please set unique names before continuing. Further results will only reflect Singletract mode.");
            %             app.explorerobj.calcsettings.multi_pathways = 0;
            %         else
            %             %if it only exists in the multitract pathway, then
            %             %check once more for the multiple .mat files and
            %             %set app.tractset.multi_pathways = 1;
            %             mt_connectome = fullfile(ea_getconnectomebase('dMRI_MultiTract'),app.ConnectomeDropDown.Value);
            %             mt_connectome_dir = dir(fullfile(mt_connectome,'*.mat'));
            %             mt_files = {mt_connectome_dir.name};
            %             if length(mt_files) > 1
            %                 app.explorerobj.calcsettings.multi_pathways = 1;
            %             else
            %                 app.explorerobj.calcsettings.multi_pathways = 0;
            %             end
            %         end
            %     else
            %         app.explorerobj.calcsettings.multi_pathways = 0;
            %     end
            % % elseif selectedTool == 3
            %     app.explorerobj.calcsettings.netmap_connectome = app.ConnectomeDropDown.Value;
            % end
           

            % save & close
            setappdata(app.explorerobj.resultfig, 'explorer', app.explorerobj);
                % Pass settings to explorer and let it handle everything
            % if ~isempty(app.TargetExplorerApp) && isvalid(app.TargetExplorerApp)
            %     app.TargetExplorerApp.calcsettings = app.calcsettings;
            %     app.TargetExplorerApp.updateFromCalcSettings(); % method in explorer
            % end
    % Optionally close calculator:
    % delete(app);
            app.SaveButton.BackgroundColor = [1 1 1];
            setappdata(app.explorerobj.resultfig, 'calcsettings_saved', true);
            app.explorerobj.save;
            delete(app);

        end

        % Callback function
        function FiberfilteringCalculationMethodSwitchValueChanged(app, event)
            value = app.FiberfilteringCalculationMethodSwitch.Value;
            disp(class(app))
            if strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'E-field Based Method')
                app.CalculateinDropDown.Value = 'Template Space';
                app.CalculateinDropDown.Enable = 'on';
                app.explorerobj.calcsettings.calcspace = 1;
                app.explorerobj.calcsettings.calcmethod = 1;
            elseif strcmp(app.FiberfilteringCalculationMethodSwitch.Value,'Fiber Based Method')
                app.CalculateinDropDown.Value = 'Native Space';
                app.CalculateinDropDown.Enable = 'on';
                app.explorerobj.calcsettings.calcspace = 0;
                app.explorerobj.calcsettings.calcmethod = 2;
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.explorerobj.calcsettings.calcthreshold = value; %check this!!!
        end

        % Selection changed function: BiophysicalModelFamilyButtonGroup
        function BiophysicalModelFamilyButtonGroupSelectionChanged(app, event)

            selectedButton = app.BiophysicalModelFamilyButtonGroup.SelectedObject;
            app.explorerobj.calcsettings.switch_connectivity = 1;
        
            if strcmp(selectedButton.Text, 'Stimulation Volumes')
                app.explorerobj.calcsettings.connectivity_type = 1;
            elseif strcmp(selectedButton.Text, 'Pathway Activation Modeling')
                app.explorerobj.calcsettings.connectivity_type = 2;
            end

    enable_rules(app)
           %TODO - Highly interlinked with the rest of the stats button, so
           %you need to coordinate!
        end

        % Value changed function: ResolutionstructuralDropDown
        function ResolutionstructuralDropDownValueChanged(app, event)
            value = app.ResolutionstructuralDropDown.Value; % check  this
            app.explorerobj.calcsettings.resolution = value;
        end

        % Value changed function: CalculateinDropDown
        function CalculateinDropDownValueChanged(app, event)
            value = app.CalculateinDropDown.Value;
            switch value
                case 'Template Space'
                    app.CalculationMethodButtonGroup.SelectedObject = ...
                        app.EFieldBasedMethodButton;
        
                    app.explorerobj.calcsettings.calcspace  = 1;
                    app.explorerobj.calcsettings.calcmethod = 1;
        
                case 'Native Space'
                    app.CalculationMethodButtonGroup.SelectedObject = ...
                        app.FiberBasedMethodButenko2025Button;
        
                    app.explorerobj.calcsettings.calcspace  = 0;
                    app.explorerobj.calcsettings.calcmethod = 2;
            end
            enable_rules(app)
        end

        % Selection changed function: CalculationMethodButtonGroup
        function CalculationMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.CalculationMethodButtonGroup.SelectedObject;

            switch selectedButton.Text
        
                case 'E-field Based Method'
                    app.CalculateinDropDown.Value = 'Template Space';
                    app.CalculateinDropDown.Enable = 'on';
        
                    app.explorerobj.calcsettings.calcspace  = 1;
                    app.explorerobj.calcsettings.calcmethod = 1;
        
                case 'Fiber Based Method'
                    app.CalculateinDropDown.Value = 'Native Space';
                    app.CalculateinDropDown.Enable = 'on';
        
                    app.explorerobj.calcsettings.calcspace  = 0;
                    app.explorerobj.calcsettings.calcmethod = 2;
            end
            
        end

        % Value changed function: NormalizeFingerprintsCheckBox
        function NormalizeFingerprintsCheckBoxValueChanged(app, event)
            value = app.NormalizeFingerprintsCheckBox.Value;
            app.explorerobj.normalize_fp = value;
        end

        % Value changed function: SmoothFingerprintsCheckBox
        function SmoothFingerprintsCheckBoxValueChanged(app, event)
            value = app.SmoothFingerprintsCheckBox.Value;
            app.explorerobj.smooth_fp = value;
        end

        % Value changed function: SSResolutionEditField
        function SSResolutionEditFieldValueChanged(app, event)
            value = app.SSResolutionEditField.Value;
            app.explorerobj.calcsettings.sweetspotresolution = value;
            
        end

        % Value changed function: EfieldmetricDropDown
        function EfieldmetricDropDownValueChanged(app, event)
            value = app.EfieldmetricDropDown.Value;
            app.explorerobj.e_field_metric = value;
        end

        % Value changed function: StimulationModelDropDown
        function StimulationModelDropDownValueChanged(app, event)
            value = app.StimulationModelDropDown.Value;
            % app.explorerobj.calcsettings.stimulationmodel = value;
            app.explorerobj.statsettings.stimulationmodel = value;
            enable_rules(app);  
        end

        % Value changed function: EfieldmetricDropDown_2
        function EfieldmetricDropDown_2ValueChanged(app, event)
            value = app.EfieldmetricDropDown_2.Value;
            app.explorerobj.statsettings.efieldmetric=value;
            switch value
                case 'Electric Field'
                    app.EfieldmetricDropDown_2.Visible=true;
                    % app.EfieldmetricDropDown_2.Enable=true;
                    % app.explorer.statsettings.efieldmetric=app.EfieldmetricDropDown_2.Value;
                    app.explorerobj.SigmoidTransform = 0;
                    % app.explorer.statsettings.statfamily = 'Correlations';
                    % app.explorer.statsettings.stattest = 'Spearman';
                case 'Sigmoid Field'
                    app.EfieldmetricDropDown_2.Visible=true;
                    app.EfieldmetricDropDown_2.Enable = false;
                    app.EfieldmetricDropDown_2.Value='Peak';
                    app.explorerobj.SigmoidTransform = 1;
                case 'VTA'
                    app.EfieldmetricDropDown_2.Visible=true;
                    app.EfieldmetricDropDown_2.Enable = false;
                    app.EfieldmetricDropDown_2.Value='Peak';
                    app.explorerobj.SigmoidTransform = 0;
                    % app.explorer.statsettings.statfamily = '1 - Sample T-Test';
                    % app.explorer.statsettings.stattest = 'Spearman';
            end
            % app.enforcestatrules;
            % % app.setefieldsliders;
            % app.ea_unifiedmapping_ui_enablerules;
            % app.pushstatsettings;
            % app.draw;
        end

        % Value changed function: MaskwithSeedCheckBox
        function MaskwithSeedCheckBoxValueChanged(app, event)
            app.explorerobj.mask_vta_fp = app.MaskwithSeedCheckBox.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculationSettingsPanel = uipanel(app.UIFigure);
            app.CalculationSettingsPanel.Title = 'Calculation Settings';
            app.SaveButton = uibutton(app.CalculationSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NetworkmappingButtonGroup = uibuttongroup(app.CalculationSettingsPanel);
            app.NetworkmappingButtonGroup.Title = 'Networkmapping';
            app.ResolutionstructuralDropDownLabel = uilabel(app.NetworkmappingButtonGroup);
            app.ResolutionstructuralDropDownLabel.Text = 'Resolution structural';
            app.ResolutionstructuralDropDown = uidropdown(app.NetworkmappingButtonGroup);
            app.ResolutionstructuralDropDown.Items = {'2 mm', '1 mm', '0.5 mm'};
            app.ResolutionstructuralDropDown.Editable = 'on';
            app.ResolutionstructuralDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionstructuralDropDownValueChanged, true);
            app.ResolutionstructuralDropDown.Value = '2 mm';
            app.SmoothFingerprintsCheckBox = uicheckbox(app.NetworkmappingButtonGroup);
            app.SmoothFingerprintsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SmoothFingerprintsCheckBoxValueChanged, true);
            app.SmoothFingerprintsCheckBox.Text = 'Smooth Fingerprints';
            app.NormalizeFingerprintsCheckBox = uicheckbox(app.NetworkmappingButtonGroup);
            app.NormalizeFingerprintsCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeFingerprintsCheckBoxValueChanged, true);
            app.NormalizeFingerprintsCheckBox.Text = 'Normalize Fingerprints';
            app.MaskwithSeedCheckBox = uicheckbox(app.NetworkmappingButtonGroup);
            app.MaskwithSeedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaskwithSeedCheckBoxValueChanged, true);
            app.MaskwithSeedCheckBox.Text = 'Mask with Seed';
            app.PrefilteringButtonGroup = uibuttongroup(app.CalculationSettingsPanel);
            app.PrefilteringButtonGroup.Title = 'Pre-filtering';
            app.ThresholdEditField_3Label = uilabel(app.PrefilteringButtonGroup);
            app.ThresholdEditField_3Label.Text = 'Exclude fibers/voxels for which the electric field remains below ...';
            app.ThresholdEditField = uieditfield(app.PrefilteringButtonGroup, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 50;
            app.VmLabel = uilabel(app.PrefilteringButtonGroup);
            app.VmLabel.Text = 'V/m';
            app.SweetspotButtonGroup = uibuttongroup(app.CalculationSettingsPanel);
            app.SweetspotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SweetspotButtonGroupSelectionChanged, true);
            app.SweetspotButtonGroup.Title = 'Sweetspot';
            app.SSResolutionEditFieldLabel = uilabel(app.SweetspotButtonGroup);
            app.SSResolutionEditFieldLabel.Text = 'Resolution';
            app.SSResolutionEditField = uieditfield(app.SweetspotButtonGroup, 'numeric');
            app.SSResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @SSResolutionEditFieldValueChanged, true);
            app.SSResolutionEditField.Value = 0.5;
            app.mmLabel = uilabel(app.SweetspotButtonGroup);
            app.mmLabel.Text = 'mm';
            app.FiberfilteringButtonGroup = uibuttongroup(app.UIFigure);
            app.FiberfilteringButtonGroup.Title = 'Fiberfiltering';
            app.CalculationMethodButtonGroup = uibuttongroup(app.FiberfilteringButtonGroup);
            app.CalculationMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalculationMethodButtonGroupSelectionChanged, true);
            app.CalculationMethodButtonGroup.Title = 'Calculation Method';
            app.EFieldBasedMethodButton = uiradiobutton(app.CalculationMethodButtonGroup);
            app.EFieldBasedMethodButton.Text = 'E-Field Based Method';
            app.EFieldBasedMethodButton.Value = true;
            app.FiberBasedMethodButenko2025Button = uiradiobutton(app.CalculationMethodButtonGroup);
            app.FiberBasedMethodButenko2025Button.Text = 'Fiber Based Method (Butenko 2025)';
            app.CalculateinDropDownLabel = uilabel(app.FiberfilteringButtonGroup);
            app.CalculateinDropDownLabel.Text = 'Calculate in...';
            app.CalculateinDropDown = uidropdown(app.FiberfilteringButtonGroup);
            app.CalculateinDropDown.Items = {'Template Space', 'Native Space'};
            app.CalculateinDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateinDropDownValueChanged, true);
            app.CalculateinDropDown.Value = 'Template Space';
            app.BiophysicalModelFamilyButtonGroup = uibuttongroup(app.FiberfilteringButtonGroup);
            app.BiophysicalModelFamilyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BiophysicalModelFamilyButtonGroupSelectionChanged, true);
            app.BiophysicalModelFamilyButtonGroup.Title = 'Biophysical Model Family';
            app.StimulationVolumesButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.StimulationVolumesButton.Text = 'Stimulation Volumes';
            app.StimulationVolumesButton.Value = true;
            app.PathwayActivationModelingButton = uiradiobutton(app.BiophysicalModelFamilyButtonGroup);
            app.PathwayActivationModelingButton.Text = 'Pathway Activation Modeling';
            app.StatisticsPanel = uipanel(app.FiberfilteringButtonGroup);
            app.StatisticsPanel.Title = 'Statistics';
            app.EfieldmetricDropDownLabel = uilabel(app.StatisticsPanel);
            app.EfieldmetricDropDownLabel.Text = 'E-field metric';
            app.EfieldmetricDropDown = uidropdown(app.StatisticsPanel);
            app.EfieldmetricDropDown.Items = {'Magnitude', 'Projection'};
            app.EfieldmetricDropDown.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricDropDownValueChanged, true);
            app.EfieldmetricDropDown.Value = 'Magnitude';
            app.StimulationModelDropDownLabel = uilabel(app.StatisticsPanel);
            app.StimulationModelDropDownLabel.Text = 'Stimulation Model';
            app.StimulationModelDropDown = uidropdown(app.StatisticsPanel);
            app.StimulationModelDropDown.Items = {'Electric Field', 'Probability Activation Function', 'VTA'};
            app.StimulationModelDropDown.ValueChangedFcn = createCallbackFcn(app, @StimulationModelDropDownValueChanged, true);
            app.StimulationModelDropDown.Value = 'Electric Field';
            app.EfieldmetricDropDown_2 = uidropdown(app.StatisticsPanel);
            app.EfieldmetricDropDown_2.Items = {'Sum', 'Mean', 'Peak', 'Peak 5%'};
            app.EfieldmetricDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EfieldmetricDropDown_2ValueChanged, true);
            app.EfieldmetricDropDown_2.Value = 'Sum';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_calculate_unifiedmapping(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end