classdef ea_unifiedmapping_exportatlas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportasAtlasUIFigure        matlab.ui.Figure
        OpenAtlasFolderButton        matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        ColorforPositiveFibersLabel  matlab.ui.control.Label
        ColorforNegativeFibersLabel  matlab.ui.control.Label
        ModelNameEditField           matlab.ui.control.EditField
        ModelNameEditFieldLabel      matlab.ui.control.Label
        AtlasNameEditField           matlab.ui.control.EditField
        AtlasNameEditFieldLabel      matlab.ui.control.Label
        PositiveColor                matlab.ui.control.Button
        NegativeColor                matlab.ui.control.Button
    end


    properties (Access = public)
        disctract % Fibers drawn in the resultfig
        spot
        network
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, disctract, spot, network, atlasname, tractname, negativecolor, positivecolor)
            if exist('disctract', 'var') && ~isempty(disctract)
                app.disctract = disctract;
            end
            if exist('atlasname', 'var') && ~isempty(atlasname)
                app.AtlasNameEditField.Value = atlasname;
            end
            if exist('tractname', 'var') && ~isempty(tractname)
                app.ModelNameEditField.Value = tractname;
            end
            if exist('negativecolor', 'var') && ~isempty(negativecolor)
                app.NegativeColor.BackgroundColor = negativecolor;
            end
            if exist('positivecolor', 'var') && ~isempty(positivecolor)
                app.PositiveColor.BackgroundColor = positivecolor;
            end
            if exist('spot','var')
                app.spot = spot;
            end
            if exist('network','var')
                app.network = network;
            end
            if exist([ea_space([],'atlases'), app.AtlasNameEditField.Value], 'dir')
                app.OpenAtlasFolderButton.Enable = 'on';
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            mode = 'AddNewAtlas';
            
            if exist([ea_space([],'atlases'), app.AtlasNameEditField.Value], 'dir')
                confirmExport = uiconfirm(app.ExportasAtlasUIFigure,...
                    'Atlas already exists!', 'Confirm Export',...
                    'Options', {'Add to current atlas', 'Choose a new name'},...
                    'Icon', 'warning', 'DefaultOption', 2);
                switch confirmExport
                    case 'Add to current atlas'
                        mode = 'AddNewTract';
                    case 'Choose a new name'
                        app.ExportasAtlasUIFigure.CurrentObject = app.AtlasNameEditField;
                        return;
                end
            end
            
            atlasFolder = [ea_space([],'atlases'), app.AtlasNameEditField.Value];
            
            if ~isempty(app.disctract)
                tract = app.disctract;
                tract.fibcolor = [app.NegativeColor.BackgroundColor;app.PositiveColor.BackgroundColor];
                % tract.ea_fibformat = '1.0';
                ea_mkdir([atlasFolder, filesep, 'midline']);
                if exist([atlasFolder, filesep, 'midline', filesep, app.ModelNameEditField.Value, '.mat'], 'file')
                    confirmExport = uiconfirm(app.ExportasAtlasUIFigure,...
                        'Tract already exists!', 'Confirm Export',...
                        'Options', {'Overwrite', 'Choose a new name'},...
                        'Icon', 'warning', 'DefaultOption', 2);
                    if strcmp(confirmExport, 'Choose a new name')
                        app.ExportasAtlasUIFigure.CurrentObject = app.ModelNameEditField;
                        return;
                    end
                end
                save([atlasFolder, filesep, 'midline', filesep, app.ModelNameEditField.Value, '.mat'], '-struct', 'tract');
                fprintf('\nFibers exported as atlas:\n%s\n\n',...
                    [atlasFolder, filesep, 'midline', filesep, app.ModelNameEditField.Value, '.mat']);
                app.OpenAtlasFolderButton.Enable = 'on';
            end

            if ~isempty(app.spot)
                sweetspot = app.spot;
                for exp = 1:length(sweetspot)
                    if exp == 1
                        hem = 'rh';
                    else
                        hem = 'lh';
                    end
                    ss_path = fullfile(atlasFolder,hem,'sweetspot');
                    ea_mkdir(ss_path);
                    sweetspot{exp}.fname = fullfile(ss_path, [app.ModelNameEditField.Value, '.nii']);
                    
                    if exist(fullfile(ss_path, [app.ModelNameEditField.Value, '.nii']), 'file')
                        confirmExport = uiconfirm(app.ExportasAtlasUIFigure,...
                            'Sweetspot already exists!', 'Confirm Export',...
                            'Options', {'Overwrite', 'Choose a new name'},...
                            'Icon', 'warning', 'DefaultOption', 2);
                        if strcmp(confirmExport, 'Choose a new name')
                            app.ExportasAtlasUIFigure.CurrentObject = app.ModelNameEditField;
                            return;
                        end
                    end
                    ea_write_nii(sweetspot{exp});
                end
                fprintf('\nSweetspot exported as atlas:\n%s\n\n',...
                    [atlasFolder, filesep, 'sweetspot', filesep, app.ModelNameEditField.Value, '.nii']);
                app.OpenAtlasFolderButton.Enable = 'on';
            end

            if ~isempty(app.network)
                networkmapping = app.network;
                nm_path = fullfile(atlasFolder,'heatmap');
                ea_mkdir(nm_path);
                networkmapping.fname = fullfile(nm_path, app.ModelNameEditField.Value);
                if exist(fullfile(nm_path, [app.ModelNameEditField.Value, '.nii']), 'file')
                    confirmExport = uiconfirm(app.ExportasAtlasUIFigure,...
                        'Networkmap already exists!', 'Confirm Export',...
                        'Options', {'Overwrite', 'Choose a new name'},...
                        'Icon', 'warning', 'DefaultOption', 2);
                    if strcmp(confirmExport, 'Choose a new name')
                        app.ExportasAtlasUIFigure.CurrentObject = app.ModelNameEditField;
                        return;
                    end
                end
                ea_write_nii(networkmapping);
                if isfield(networkmapping, 'cifti')
                    cifti_write(networkmapping.cifti, fullfile(nm_path,strrep(app.ModelNameEditField.Value, '.nii', '.dscalar.nii')));
                end
                fprintf('\nNetwork exported as atlas:\n%s\n\n',...
                    [atlasFolder, filesep, 'heatmap', filesep, app.ModelNameEditField.Value, '.nii']);
                app.OpenAtlasFolderButton.Enable = 'on';
            end
%             ea_mkdir([atlasFolder, filesep, 'nifti']);
%             ea_discfibers2nifti([atlasFolder, filesep, 'midline', filesep, app.ModelNameEditField.Value, '.mat'],...
%                 1, 'both', [atlasFolder, filesep, 'nifti', filesep, app.ModelNameEditField.Value, '.nii'])
%             fprintf('Fibers exported as nifti:\n%s\n\n',...
%                 [atlasFolder, filesep, 'nifti', filesep, app.ModelNameEditField.Value, '.nii']);
            
%             if strcmp(mode, 'AddNewTract') && exist([atlasFolder,  filesep, 'atlas_index.mat'], 'file')
%                 atlasInfo = load([atlasFolder,  filesep, 'atlas_index.mat']);
%                 atlasInfo.atlases.rebuild = 1;
%                 save([atlasFolder,  filesep, 'atlas_index.mat'], '-struct', 'atlasInfo');
%             end
        end

        % Button pushed function: NegativeColor
        function NegativeColorButtonPushed(app, event)
            app.NegativeColor.BackgroundColor = uisetcolor(app.NegativeColor.BackgroundColor);
        end

        % Button pushed function: PositiveColor
        function PositiveColorButtonPushed(app, event)
            app.PositiveColor.BackgroundColor = uisetcolor(app.PositiveColor.BackgroundColor);
        end

        % Button pushed function: OpenAtlasFolderButton
        function OpenAtlasFolderButtonPushed(app, event)
            if exist([ea_space([],'atlases'), app.AtlasNameEditField.Value], 'dir')
                ea_opendir([ea_space([],'atlases'), app.AtlasNameEditField.Value]);
            end
        end

        % Value changed function: AtlasNameEditField
        function AtlasNameEditFieldValueChanged(app, event)
            value = app.AtlasNameEditField.Value;
            if exist([ea_space([],'atlases'), value], 'dir')
                app.OpenAtlasFolderButton.Enable = 'on';
            else
                app.OpenAtlasFolderButton.Enable = 'off';
            end
        end

        % Value changing function: ModelNameEditField
        function ModelNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            if exist([ea_space([],'atlases'), app.AtlasNameEditField.Value, filesep, 'midline',filesep, changingValue, '.mat'], 'file')
                app.ModelNameEditField.BackgroundColor = [0.98 0.70 0.70];
                app.ModelNameEditField.Tooltip = 'Tract already exists!';
            else
                app.ModelNameEditField.BackgroundColor = [1 1 1];
                app.ModelNameEditField.Tooltip = sprintf('Name of the tract.\nOnly letters and numbers allowed.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportasAtlasUIFigure = uifigure('Visible', 'off');
            app.ExportasAtlasUIFigure.Name = 'Export as Atlas';
            app.NegativeColor = uibutton(app.ExportasAtlasUIFigure, 'push');
            app.NegativeColor.ButtonPushedFcn = createCallbackFcn(app, @NegativeColorButtonPushed, true);
            app.NegativeColor.Text = '';
            app.PositiveColor = uibutton(app.ExportasAtlasUIFigure, 'push');
            app.PositiveColor.ButtonPushedFcn = createCallbackFcn(app, @PositiveColorButtonPushed, true);
            app.PositiveColor.Text = '';
            app.AtlasNameEditFieldLabel = uilabel(app.ExportasAtlasUIFigure);
            app.AtlasNameEditFieldLabel.Text = 'Atlas Name:';
            app.AtlasNameEditField = uieditfield(app.ExportasAtlasUIFigure, 'text');
            app.AtlasNameEditField.ValueChangedFcn = createCallbackFcn(app, @AtlasNameEditFieldValueChanged, true);
            app.AtlasNameEditField.Value = 'DBSMapping Atlas';
            app.ModelNameEditFieldLabel = uilabel(app.ExportasAtlasUIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameEditField = uieditfield(app.ExportasAtlasUIFigure, 'text');
            app.ModelNameEditField.ValueChangingFcn = createCallbackFcn(app, @ModelNameEditFieldValueChanging, true);
            app.ModelNameEditField.Tooltip = {'Name of the tract.'; 'Only letters and numbers allowed.'};
            app.ModelNameEditField.Value = 'Tract/Sweetspot/Networkmapping';
            app.ColorforNegativeFibersLabel = uilabel(app.ExportasAtlasUIFigure);
            app.ColorforNegativeFibersLabel.Text = 'Color for Negative Fibers:';
            app.ColorforPositiveFibersLabel = uilabel(app.ExportasAtlasUIFigure);
            app.ColorforPositiveFibersLabel.Text = 'Color for Positive Fibers:';
            app.ExportButton = uibutton(app.ExportasAtlasUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.OpenAtlasFolderButton = uibutton(app.ExportasAtlasUIFigure, 'push');
            app.OpenAtlasFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAtlasFolderButtonPushed, true);
            app.OpenAtlasFolderButton.Enable = 'off';
            app.OpenAtlasFolderButton.Text = 'Open Atlas Folder';
            % Show the figure after all components are created
            app.ExportasAtlasUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_unifiedmapping_exportatlas(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportasAtlasUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportasAtlasUIFigure)
        end
    end
end