classdef ea_unifiedmapping_refinefibercolor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RefineFIberColorUIFigure  matlab.ui.Figure
        AlphaGradientLevel        matlab.ui.control.Spinner
        AlphaGradientLevelLabel   matlab.ui.control.Label
        ColorGradientLevel        matlab.ui.control.Spinner
        ColorGradientLevelLabel   matlab.ui.control.Label
        StatusImage               matlab.ui.control.Image
        UpdateFigure              matlab.ui.control.Button
        AlphaExponent             matlab.ui.control.Spinner
        AlphaExponentLabel        matlab.ui.control.Label
        ColorExponent             matlab.ui.control.Spinner
        ColorExponentLabel        matlab.ui.control.Label
        AlphaScale                matlab.ui.control.DropDown
        AlphaScaleDropDownLabel   matlab.ui.control.Label
        ColorScale                matlab.ui.control.DropDown
        ColorScaleDropDownLabel   matlab.ui.control.Label
        FiberType                 matlab.ui.control.DropDown
        ColorStart                matlab.ui.control.Button
        ColorEnd                  matlab.ui.control.Button
        FiberTypeDropDownLabel    matlab.ui.control.Label
        Side                      matlab.ui.control.DropDown
        SideDropDownLabel         matlab.ui.control.Label
        Group                     matlab.ui.control.DropDown
        GroupDropDownLabel        matlab.ui.control.Label
        ColorBar                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        drawobject % handles to fiber drawn
        drawvals % fiber weight
        groupInd % fiber group
        sideInd % fiber side
        updateInd % index of fibers to be updated
        cmap % Adjusted cmap
    end
    
    methods (Access = private)
        
        function updateGUI(app, event)
            if ~exist('event', 'var')
                app.Group.Items = string(1:size(app.drawobject ,1));
                group = 1;
            else
                group = str2double(app.Group.Value);
            end

            app.groupInd = group;

            if ~isempty(app.drawobject.fiberfiltering{group, 1}) && isempty(app.drawobject.fiberfiltering{group, 2})
                app.Side.Items = "Right";
            elseif isempty(app.drawobject.fiberfiltering{group, 1}) && ~isempty(app.drawobject.fiberfiltering{group, 2})
                app.Side.Items = "Left";
            else
                app.Side.Items = ["Both", "Right", "Left"];
            end

            switch app.Side.Value
                case 'Right'
                    side = 1;
                case 'Left'
                    side = 2;
                case 'Both'
                    side = [1 2];
            end

            app.sideInd = side;

            if app.Side.Value == "Both"
                drawobj = vertcat(app.drawobject.fiberfiltering{group, side});
                vals = vertcat(app.drawvals{group, side});
            else
                drawobj = app.drawobject.fiberfiltering{group, side};
                vals = app.drawvals{group, side};
            end

            if all(vals>0)
                app.FiberType.Items = "Positive";
            elseif all(vals<0)
                app.FiberType.Items = "Negative";
            else
                app.FiberType.Items = ["Positive", "Negative"];
            end

            switch app.FiberType.Value
                case 'Positive'
                    valStart = min(vals(vals>0));
                    valEnd = max(vals(vals>0));
                    app.updateInd = find(vals>0);
                case 'Negative'
                    valStart = min(vals(vals<0));
                    valEnd = max(vals(vals<0));
                    app.updateInd = find(vals<0);
            end

            if ~exist('event', 'var') || ismember(event.Source.Tag, {'Group', 'Side', 'FiberType'})
                app.ColorStart.BackgroundColor = drawobj(find(vals == valStart, 1)).FaceColor;
                app.ColorEnd.BackgroundColor = drawobj(find(vals == valEnd, 1)).FaceColor;
            end

            cbcmap = ea_colorgradient(app.ColorGradientLevel.Value, app.ColorStart.BackgroundColor, app.ColorEnd.BackgroundColor);

            switch app.ColorScale.Value
                case 'Linear'
                    app.ColorExponentLabel.Enable = 'off';
                    app.ColorExponent.Enable = 'off';
                case 'Power'
                    app.ColorExponentLabel.Enable = 'on';
                    app.ColorExponent.Enable = 'on';
                    scale = round((0:1/(app.ColorGradientLevel.Value-1):1).^app.ColorExponent.Value*(app.ColorGradientLevel.Value-1)) + 1;
                    if app.FiberType.Value == "Negative"
                        scale = flip(app.ColorGradientLevel.Value-scale+1);
                    end
                    cbcmap = cbcmap(scale, :);
            end

            app.cmap = cbcmap;

            if app.AlphaScale.Value == "Power"
                app.AlphaExponentLabel.Enable = 'on';
                app.AlphaExponent.Enable = 'on';
            else
                app.AlphaExponentLabel.Enable = 'off';
                app.AlphaExponent.Enable = 'off';
            end

            if app.AlphaScale.Value == "Constant (1)"
                app.AlphaGradientLevelLabel.Enable = 'off';
                app.AlphaGradientLevel.Enable = 'off';
            else
                app.AlphaGradientLevelLabel.Enable = 'on';
                app.AlphaGradientLevel.Enable = 'on';
            end

            cbTicks = [1, app.ColorGradientLevel.Value];
            cbTicksLabel = {num2str(valStart, '%.2f'), num2str(valEnd, '%.2f')};
            ea_plot_colorbar(cbcmap, [], 'h', '', cbTicks, cbTicksLabel, app.ColorBar);
        end
        
        function updateFigure(app)
            if app.Side.Value == "Both"
                drawobj = vertcat(app.drawobject.fiberfiltering{app.groupInd, :});
                vals = vertcat(app.drawvals{app.groupInd, :});
            else
                drawobj = app.drawobject.fiberfiltering{app.groupInd, app.sideInd};
                vals = app.drawvals{app.groupInd, app.sideInd};
            end

            drawobj = drawobj(app.updateInd);
            vals = vals(app.updateInd);

            cmapInd = round(normalize(vals, 'range', [1, app.ColorGradientLevel.Value]));
            fibcolor = mat2cell(app.cmap(cmapInd,:), ones(size(app.updateInd)));

            amapInd = round(normalize(vals, 'range', [1, app.AlphaGradientLevel.Value]));
            switch app.AlphaScale.Value
                case 'Constant (1)'
                    fibalpha = mat2cell(ones(size(app.updateInd)), ones(size(app.updateInd)));
                case 'Linear'
                    alphamap = (0:1/(app.AlphaGradientLevel.Value-1):1)';
                    if app.FiberType.Value == "Negative"
                        alphamap = flip(alphamap);
                    end
                    fibalpha = mat2cell(alphamap(amapInd), ones(size(app.updateInd)));
                case 'Power'
                    alphamap = ((0:1/(app.AlphaGradientLevel.Value-1):1).^app.AlphaExponent.Value)';
                    if app.FiberType.Value == "Negative"
                        alphamap = flip(alphamap);
                    end
                    fibalpha = mat2cell(alphamap(amapInd), ones(size(app.updateInd)));
            end
            ea_busyaction('on', app.StatusImage);
            [drawobj.FaceColor] = fibcolor{:};
            [drawobj.FaceAlpha] = fibalpha{:};
            ea_busyaction('off', app.StatusImage);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, drawobject, drawvals)
            app.drawobject = drawobject;
            app.drawvals = drawvals;

            updateGUI(app);
        end

        % Value changed function: Group
        function GroupValueChanged(app, event)
            updateGUI(app, event);
        end

        % Value changed function: Side
        function SideValueChanged(app, event)
            updateGUI(app, event);
        end

        % Value changed function: FiberType
        function FiberTypeValueChanged(app, event)
            updateGUI(app, event);
        end

        % Button pushed function: ColorStart
        function ColorStartButtonPushed(app, event)
            app.ColorStart.BackgroundColor = uisetcolor;
            figure(app.RefineFIberColorUIFigure);
            updateGUI(app, event);
        end

        % Button pushed function: ColorEnd
        function ColorEndButtonPushed(app, event)
            app.ColorEnd.BackgroundColor = uisetcolor;
            figure(app.RefineFIberColorUIFigure);
            updateGUI(app, event);
        end

        % Value changed function: ColorScale
        function ColorScaleValueChanged(app, event)
            updateGUI(app, event);
        end

        % Value changed function: AlphaScale
        function AlphaScaleValueChanged(app, event)
            updateGUI(app, event);
        end

        % Value changed function: ColorExponent
        function ColorExponentValueChanged(app, event)
            updateGUI(app, event);
        end

        % Value changed function: AlphaExponent
        function AlphaExponentValueChanged(app, event)
            updateGUI(app, event);
        end

        % Button pushed function: UpdateFigure
        function UpdateFigureButtonPushed(app, event)
            updateFigure(app);
        end

        % Value changed function: ColorGradientLevel
        function ColorGradientLevelValueChanged(app, event)
            updateGUI(app, event);
        end

        % Value changed function: AlphaGradientLevel
        function AlphaGradientLevelValueChanged(app, event)
            updateGUI(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RefineFIberColorUIFigure = uifigure('Visible', 'off');
            app.RefineFIberColorUIFigure.Name = 'Refine Fiber Color';
            app.ColorBar = uiaxes(app.RefineFIberColorUIFigure);
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.GroupDropDownLabel = uilabel(app.RefineFIberColorUIFigure);
            app.GroupDropDownLabel.Text = 'Group';
            app.Group = uidropdown(app.RefineFIberColorUIFigure);
            app.Group.Items = {};
            app.Group.ValueChangedFcn = createCallbackFcn(app, @GroupValueChanged, true);
            app.Group.Tag = 'Group';
            app.Group.Value = {};
            app.SideDropDownLabel = uilabel(app.RefineFIberColorUIFigure);
            app.SideDropDownLabel.Text = 'Side';
            app.Side = uidropdown(app.RefineFIberColorUIFigure);
            app.Side.Items = {};
            app.Side.ValueChangedFcn = createCallbackFcn(app, @SideValueChanged, true);
            app.Side.Tag = 'Side';
            app.Side.Value = {};
            app.FiberTypeDropDownLabel = uilabel(app.RefineFIberColorUIFigure);
            app.FiberTypeDropDownLabel.Text = 'Fiber Type';
            app.ColorEnd = uibutton(app.RefineFIberColorUIFigure, 'push');
            app.ColorEnd.ButtonPushedFcn = createCallbackFcn(app, @ColorEndButtonPushed, true);
            app.ColorEnd.Tag = 'EndColor';
            app.ColorEnd.Text = 'Color';
            app.ColorStart = uibutton(app.RefineFIberColorUIFigure, 'push');
            app.ColorStart.ButtonPushedFcn = createCallbackFcn(app, @ColorStartButtonPushed, true);
            app.ColorStart.Tag = 'StartColor';
            app.ColorStart.Text = 'Color';
            app.FiberType = uidropdown(app.RefineFIberColorUIFigure);
            app.FiberType.Items = {};
            app.FiberType.ValueChangedFcn = createCallbackFcn(app, @FiberTypeValueChanged, true);
            app.FiberType.Tag = 'FiberType';
            app.FiberType.Value = {};
            app.ColorScaleDropDownLabel = uilabel(app.RefineFIberColorUIFigure);
            app.ColorScaleDropDownLabel.Text = 'Color Scale';
            app.ColorScale = uidropdown(app.RefineFIberColorUIFigure);
            app.ColorScale.Items = {'Linear', 'Power'};
            app.ColorScale.ValueChangedFcn = createCallbackFcn(app, @ColorScaleValueChanged, true);
            app.ColorScale.Value = 'Linear';
            app.AlphaScaleDropDownLabel = uilabel(app.RefineFIberColorUIFigure);
            app.AlphaScaleDropDownLabel.Text = 'Alpha Scale';
            app.AlphaScale = uidropdown(app.RefineFIberColorUIFigure);
            app.AlphaScale.Items = {'Constant (1)', 'Linear', 'Power'};
            app.AlphaScale.ValueChangedFcn = createCallbackFcn(app, @AlphaScaleValueChanged, true);
            app.AlphaScale.Value = 'Constant (1)';
            app.ColorExponentLabel = uilabel(app.RefineFIberColorUIFigure);
            app.ColorExponentLabel.Enable = 'off';
            app.ColorExponentLabel.Text = 'Exponent k';
            app.ColorExponent = uispinner(app.RefineFIberColorUIFigure);
            app.ColorExponent.Limits = [1 50];
            app.ColorExponent.RoundFractionalValues = 'on';
            app.ColorExponent.ValueDisplayFormat = '%d';
            app.ColorExponent.ValueChangedFcn = createCallbackFcn(app, @ColorExponentValueChanged, true);
            app.ColorExponent.Enable = 'off';
            app.ColorExponent.Value = 5;
            app.AlphaExponentLabel = uilabel(app.RefineFIberColorUIFigure);
            app.AlphaExponentLabel.Enable = 'off';
            app.AlphaExponentLabel.Text = 'Exponent k';
            app.AlphaExponent = uispinner(app.RefineFIberColorUIFigure);
            app.AlphaExponent.Limits = [1 50];
            app.AlphaExponent.RoundFractionalValues = 'on';
            app.AlphaExponent.ValueDisplayFormat = '%d';
            app.AlphaExponent.ValueChangedFcn = createCallbackFcn(app, @AlphaExponentValueChanged, true);
            app.AlphaExponent.Enable = 'off';
            app.AlphaExponent.Value = 5;
            app.UpdateFigure = uibutton(app.RefineFIberColorUIFigure, 'push');
            app.UpdateFigure.ButtonPushedFcn = createCallbackFcn(app, @UpdateFigureButtonPushed, true);
            app.UpdateFigure.Text = 'Update Figure';
            app.StatusImage = uiimage(app.RefineFIberColorUIFigure);
            app.StatusImage.Visible = 'off';
            app.ColorGradientLevelLabel = uilabel(app.RefineFIberColorUIFigure);
            app.ColorGradientLevelLabel.Text = 'Gradient Level';
            app.ColorGradientLevel = uispinner(app.RefineFIberColorUIFigure);
            app.ColorGradientLevel.Step = 64;
            app.ColorGradientLevel.Limits = [64 1024];
            app.ColorGradientLevel.RoundFractionalValues = 'on';
            app.ColorGradientLevel.ValueDisplayFormat = '%d';
            app.ColorGradientLevel.ValueChangedFcn = createCallbackFcn(app, @ColorGradientLevelValueChanged, true);
            app.ColorGradientLevel.Value = 512;
            app.AlphaGradientLevelLabel = uilabel(app.RefineFIberColorUIFigure);
            app.AlphaGradientLevelLabel.Enable = 'off';
            app.AlphaGradientLevelLabel.Text = 'Gradient Level';
            app.AlphaGradientLevel = uispinner(app.RefineFIberColorUIFigure);
            app.AlphaGradientLevel.Step = 64;
            app.AlphaGradientLevel.Limits = [64 1024];
            app.AlphaGradientLevel.RoundFractionalValues = 'on';
            app.AlphaGradientLevel.ValueDisplayFormat = '%d';
            app.AlphaGradientLevel.ValueChangedFcn = createCallbackFcn(app, @AlphaGradientLevelValueChanged, true);
            app.AlphaGradientLevel.Enable = 'off';
            app.AlphaGradientLevel.Value = 512;
            % Show the figure after all components are created
            app.RefineFIberColorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_unifiedmapping_refinefibercolor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RefineFIberColorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RefineFIberColorUIFigure)
        end
    end
end