classdef LeG_ACPCGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure          matlab.ui.Figure
        FlipCkbH            matlab.ui.control.CheckBox
        ToolbarAxial        matlab.ui.container.Panel
        PanAxialH           matlab.ui.control.StateButton
        RestoreAxialH       matlab.ui.control.Button
        ZoomAxialH          matlab.ui.control.StateButton
        ToolbarCor          matlab.ui.container.Panel
        PanCorH             matlab.ui.control.StateButton
        RestoreCorH         matlab.ui.control.Button
        ZoomCorH            matlab.ui.control.StateButton
        ToolbarSag          matlab.ui.container.Panel
        PanSagH             matlab.ui.control.StateButton
        RestoreSagH         matlab.ui.control.Button
        ZoomSagH            matlab.ui.control.StateButton
        InstructionsButton  matlab.ui.control.Button
        AxialAxH            matlab.ui.control.UIAxes
        CorAxH              matlab.ui.control.UIAxes
        SagAxH              matlab.ui.control.UIAxes
    end

    properties (Access = public)
        NiftiFile
        imgInfo %nii header
        img %nii 3D matrix
        imgR %rotated (90 deg increments) nii 3D matrix
        imgScale %mm to vox scaling
        imgScaleR
        imgSize %size in voxels
        imgSizeR
        imgCenter %voxel center of image
        imgCenterR
        b %spm transform vector
        ACPC %ACPC/midline points, xyz are columns, AC/PC/ML are rows
        ACPCR
        BB %bounding box vector of box widths for each dimension centered on mid-ACPC line
        BBR
        BBVal %current xy position of mouse
        BBLim %current xy bounding box ranges
        BBBool %which box edge is active?
        BtnDwn %is mouse button down or up? (used for dragging)
        Dragging %are we dragging a border?
        R %all possible 90 deg rotation combinations
        RIdx
        RIdxMax
        PointCnt %counts up to 3 then starts back at 1 (for setting AC/PC/ML)
        imgCRng
        HoverAx %current axis based on location of mouse (see getHoverAx)
        ClickPntAx
        aH %array of axis handles
        iH %array of image handles
        pH %handles to ACPC points
        rH %handles to cropping box
        tH %handles to axes titles
        zoomH %handles to zoomin button in axis toolbar
        panH
        currAxisIdx
        TitleCell
        SelectionType='' %mouse button
        KeyType='' %keyboard
        SavedChanges (1,1) logical = false
    end
    
    methods (Access = private)
        
        function voxR = rotateVox(~, vox, matSize, b)
            XYZ = vox-(matSize/2);
            XYZ(XYZ<0) = XYZ(XYZ<0)-1;
            XYZR = [XYZ,ones(size(XYZ,1),1)]*spm_matrix(b); XYZR = XYZR(:,1:3);
            matSizeR = [matSize,1]*spm_matrix(b); matSizeR = abs(matSizeR(1:3));
            XYZR(XYZR<0) = XYZR(XYZR<0)+1;
            voxR = XYZR+(matSizeR/2);
        end
        
        function vox = unrotateVox(~, voxR, matSizeR, b)
            XYZR = voxR-(matSizeR/2);
            XYZR(XYZR<0) = XYZR(XYZR<0)-1;
            XYZ = [XYZR,ones(size(XYZR,1),1)]/spm_matrix(b); XYZ = XYZ(:,1:3);
            matSize = [matSizeR,1]/spm_matrix(b); matSize = abs(matSize(1:3));
            XYZ(XYZ<0) = XYZ(XYZ<0)+1;
            vox = XYZ+(matSize/2);
        end
        
        function restoreView(app, varargin)
            axH = varargin{1};
            axIdx = strcmp(axH.Tag,{'SaggitalAx','CoronalAx','AxialAx'});
            axis(axH,[get(app.iH(axIdx),'xdata'),get(app.iH(axIdx),'ydata')]);
            app.zoomH(axIdx).Value = false;
            app.panH(axIdx).Value = false;
        end
        
        function flipLeftRight(app,varargin)
            if app.FlipCkbH.Value
                ImgInfo = spm_vol(varargin{1});
                Img = spm_read_vols(ImgInfo);

                p = [2,1,3];
                Img = permute(Img,p);
                Img = fliplr(Img);
                Img = ipermute(Img,p);

                spm_write_vol(ImgInfo,Img);
                disp('A left/right flip was performed!');
            end
        end

        function newlim = zoomAroundPoint(~, curlim, curPt, zoomFactor)
            xlim = curlim(1:2);
            ylim = curlim(3:4);

            newXLim = curPt(1) + (xlim - curPt(1)) ./ zoomFactor;
            newYLim = curPt(2) + (ylim - curPt(2)) ./ zoomFactor;

            newlim = [newXLim, newYLim];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            app.MainFigure.Visible = 'off';
            
            p = inputParser;
            addParameter(p,'NiftiFile','',@(x)exist(x,'file'));
            parse(p,varargin{:});
            
            if isempty(p.Results.NiftiFile)
                [path,file,ext] = LeG_lastPath('*.nii','Choose nifti file...');
                app.NiftiFile = fullfile(path,[file,ext]);
            else
                app.NiftiFile = p.Results.NiftiFile;
            end
            %Perform 90 deg rotations to standard space. Renames with "sd" prefix.
            [app.NiftiFile,FlipB] = LeG_rotateImg2Standard(app.NiftiFile);
            
            app.imgInfo = spm_vol(app.NiftiFile);
            app.img = spm_read_vols(app.imgInfo); app.imgR = app.img;
            app.imgScale = sqrt(sum(app.imgInfo.mat(1:3,1:3).^2,1)); app.imgScaleR = app.imgScale;
            app.imgSize = size(app.img); app.imgSizeR = app.imgSize;
            app.imgCenter = round(size(app.img)/2); app.imgCenterR = app.imgCenter; %voxel center of image
            app.b = [0,0,0,0,0,0,1,1,1,0,0,0];
            
            app.ACPC = nan(3,3); app.ACPCR = app.ACPC; %xyz are columns, AC/PC/ML are rows
            app.BB = floor(app.imgSize/4); app.BBR = app.BB; %bounding box vector of box widths for each dimension centered on mid-ACPC line
            app.BBVal = nan(2,1); %current xy position of mouse
            app.BBLim = nan(2); %current xy bounding box ranges
            app.BBBool = false(2); %which box edge is active?
            app.BtnDwn = false; %is mouse button down or up? (used for dragging)
            app.Dragging = false; %are we dragging a border?
            
            r = 0:pi/2:3*pi/2;
            [x,y,z] = meshgrid(r,r,r);
            app.R = [x(:),y(:),z(:)]; %all possible rotation combinations
            app.RIdx = 1;
            app.RIdxMax = size(app.R,1);
            
            app.PointCnt = 1; %counts up to 3 then starts back at 1 (for setting AC/PC/ML)
            app.imgCRng = prctile(app.img(:),[1,99]);
            
            app.HoverAx = [true;false;false]; %current axis based on location of mouse (see getHoverAx)
            
            set(app.MainFigure,'colormap',gray);
            app.aH = [app.SagAxH,app.CorAxH,app.AxialAxH]; set(app.aH,'nextplot','add');
            app.iH = nan(1,3);
            app.pH = nan(3,3);
            app.rH = nan(1,3);
            app.zoomH = [app.ZoomSagH,app.ZoomCorH,app.ZoomAxialH];
            app.panH = [app.PanSagH,app.PanCorH,app.PanAxialH];
            for k=1:3
                app.aH(k).Toolbar.Visible = 'off';
                disableDefaultInteractivity(app.aH(k))
            end
            
            app.TitleCell = {'Saggital (right -> anterior)','Coronal (up -> superior)','Axial (up -> anterior)'};
            for k=1:3 %by axes (i.e. dimension)
                app.tH(k) = title(app.aH(k),sprintf('%s %0.0f/%0.0f',app.TitleCell{k},app.imgCenterR(k),app.imgSizeR(k)));
                switch k
                    case 1
                        app.iH(k) = imagesc(squeeze(app.img(app.imgCenter(k),:,:))','parent',app.aH(k),app.imgCRng);
                        % app.iH(k) = imshow(squeeze(app.img(app.imgCenter(k),:,:))',app.imgCRng,'parent',app.aH(k)); %imshow is slower
                        app.pH(1,k) = plot(app.aH(k),app.ACPC(1,2),app.ACPC(1,3),'.g'); set(app.pH(1,k),'markersize',20);
                        app.pH(2,k) = plot(app.aH(k),app.ACPC(2,2),app.ACPC(2,3),'.r'); set(app.pH(2,k),'markersize',20);
                        app.pH(3,k) = plot(app.aH(k),app.ACPC(3,2),app.ACPC(3,3),'.b'); set(app.pH(3,k),'markersize',20);
                    case 2
                        app.iH(k) = imagesc(squeeze(app.img(:,app.imgCenter(k),:))','parent',app.aH(k),app.imgCRng);
                        % app.iH(k) = imshow(squeeze(app.img(:,app.imgCenter(k),:))',app.imgCRng,'parent',app.aH(k));
                        app.pH(1,k) = plot(app.aH(k),app.ACPC(1,1),app.ACPC(1,3),'.g'); set(app.pH(1,k),'markersize',20);
                        app.pH(2,k) = plot(app.aH(k),app.ACPC(2,1),app.ACPC(2,3),'.r'); set(app.pH(2,k),'markersize',20);
                        app.pH(3,k) = plot(app.aH(k),app.ACPC(3,1),app.ACPC(3,3),'.b'); set(app.pH(3,k),'markersize',20);
                    case 3
                        app.iH(k) = imagesc(squeeze(app.img(:,:,app.imgCenter(k)))','parent',app.aH(k),app.imgCRng);
                        % app.iH(k) = imshow(squeeze(app.img(:,:,app.imgCenter(k)))',app.imgCRng,'parent',app.aH(k));
                        app.pH(1,k) = plot(app.aH(k),app.ACPC(1,1),app.ACPC(1,2),'.g'); set(app.pH(1,k),'markersize',20);
                        app.pH(2,k) = plot(app.aH(k),app.ACPC(2,1),app.ACPC(2,2),'.r'); set(app.pH(2,k),'markersize',20);
                        app.pH(3,k) = plot(app.aH(k),app.ACPC(3,1),app.ACPC(3,2),'.b'); set(app.pH(3,k),'markersize',20);
                end
                SDiff = setdiff(1:3,k);
                app.rH(k) = rectangle('parent',app.aH(k),'position',[app.imgCenter(SDiff)-app.BB(SDiff),app.BB(SDiff)*2],'edgecolor','r','linewidth',2);
                set(app.aH(k),'DataAspectRatio',[fliplr(app.imgScale(SDiff)),1],'xtick',[],'xticklabel',[],'ytick',[],'yticklabel',[]);
                axis(app.aH(k),[get(app.iH(k),'xdata'),get(app.iH(k),'ydata')]);
                set(app.iH(k),'PickableParts','none');
            end
            set(app.pH,'visible','off')
            set(app.rH,'visible','off')
            
            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.MainFigure.Position;
            if any(scrsize(3:4)<[1560,610])
                app.MainFigure.AutoResizeChildren = "off";
                app.MainFigure.Scrollable = "on";
                app.MainFigure.WindowState = 'maximized';
            else
                app.MainFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
            end
            
            app.MainFigure.Visible = 'on'; pause(0.1);
            if FlipB
%                 uialert(app.MainFigure,'A left/right flip might be needed! Confirm by checking the images carefully and select the checkbox before closing. The images are displayed using the radiological convention with the left on the right side and vice versa.',''); pause(0.1);
            end
        end

        % Callback function: MainFigure, MainFigure, MainFigure
        function ButtonDownKeyPressScrollFcn(app, event)
            app.ClickPntAx = get(app.aH,'CurrentPoint');
            switch event.EventName
                case 'WindowKeyPress'
                    app.KeyType = event.Key;
                case 'WindowMousePress'
                    app.SelectionType = get(event.Source,'selectiontype'); %normal (left click), alt (right click), extend (scroll wheel click)
            end
            cAH = app.aH(app.HoverAx);
            if isempty(cAH)
                cAH = get(app.MainFigure,'currentaxes');
                if isempty(cAH)
                    return;
                end
            end
            app.currAxisIdx = find(all(cAH.Position==cell2mat(get(app.aH,'position')),2),1);
            
            if strcmp(event.EventName,'WindowKeyPress')
                if strcmp(event.Key,'rightarrow') || strcmp(event.Key,'leftarrow')
                    if any([strcmp(event.Modifier,'control'),strcmp(event.Modifier,'command')])
                        switch event.Key
                            case 'rightarrow'
                                app.RIdx = app.RIdx+1;
                            case 'leftarrow'
                                app.RIdx = app.RIdx-1;
                        end
                        app.RIdx(app.RIdx<1) = 1;
                        app.RIdx(app.RIdx>app.RIdxMax) = app.RIdxMax;
                        
                        app.imgR = app.img;
                        rmat = app.R(app.RIdx,:); %xyz rotate
                        nrot = round(rmat/(pi/2)); %number of rotations
                        for m=1:3 %spm order (shear, scale, rotation, translation)
                            pmat = [setdiff(1:3,m),m];
                            if nrot(m)~=0
                                app.imgR = permute(app.imgR,pmat);
                                app.imgR = rot90(app.imgR,nrot(m));
                                app.imgR = ipermute(app.imgR,pmat);
                            end
                        end
                        app.imgSizeR = size(app.imgR);
                        app.b(4:6) = rmat;
                        
                        app.imgScaleR = [app.imgScale,1]*spm_matrix(app.b); app.imgScaleR = abs(app.imgScaleR(1:3));
                        app.imgCenterR = round(app.rotateVox(app.imgCenter,app.imgSize,app.b));
                        app.ACPCR = app.rotateVox(app.ACPC,app.imgSize,app.b);
                        app.BBR = [app.BB,1]*spm_matrix(app.b); app.BBR = abs(app.BBR(1:3));
                        
                        set(app.aH(1),'DataAspectRatio',[fliplr(app.imgScaleR([2,3])),1],'xlim',[1,app.imgSizeR(2)],'ylim',[1,app.imgSizeR(3)]);
                        set(app.aH(2),'DataAspectRatio',[fliplr(app.imgScaleR([1,3])),1],'xlim',[1,app.imgSizeR(1)],'ylim',[1,app.imgSizeR(3)]);
                        set(app.aH(3),'DataAspectRatio',[fliplr(app.imgScaleR([1,2])),1],'xlim',[1,app.imgSizeR(1)],'ylim',[1,app.imgSizeR(2)]);
                    end
                end
            end
            
            if strcmp(event.EventName,'WindowScrollWheel') || strcmp(event.EventName,'WindowKeyPress')
                switch event.EventName
                    case 'WindowScrollWheel'
                        app.imgCenterR(app.currAxisIdx) = app.imgCenterR(app.currAxisIdx)+event.VerticalScrollCount;
                    case 'WindowKeyPress'
                        if isempty(event.Modifier)
                            switch event.Key
                                case 'rightarrow'
                                    app.imgCenterR(app.currAxisIdx) = app.imgCenterR(app.currAxisIdx)+1;
                                case 'leftarrow'
                                    app.imgCenterR(app.currAxisIdx) = app.imgCenterR(app.currAxisIdx)-1;
                            end
                        end
                end
                app.imgCenterR(app.imgCenterR<1) = 1;
                app.imgCenterR(app.imgCenterR>app.imgSizeR) = app.imgSizeR(app.imgCenterR>app.imgSizeR);
                app.imgCenter = app.unrotateVox(app.imgCenterR,app.imgSizeR,app.b);
            end
            
            if strcmp(event.EventName,'WindowMousePress')
                if strcmp(get(event.Source,'selectiontype'),'alt')
                    cp = get(app.aH(app.currAxisIdx),'currentpoint');
                    switch app.PointCnt
                        case 1 %AC
                            app.ACPCR(1,app.currAxisIdx) = app.imgCenterR(app.currAxisIdx);
                            app.ACPCR(1,setdiff(1:3,app.currAxisIdx)) = (cp(1,1:2));
                        case 2 %PC
                            app.ACPCR(2,app.currAxisIdx) = app.imgCenterR(app.currAxisIdx);
                            app.ACPCR(2,setdiff(1:3,app.currAxisIdx)) = (cp(1,1:2));
                        case 3 %ML
                            app.ACPCR(3,app.currAxisIdx) = app.imgCenterR(app.currAxisIdx);
                            app.ACPCR(3,setdiff(1:3,app.currAxisIdx)) = (cp(1,1:2));
                    end
                    app.PointCnt = app.PointCnt+1;
                    app.PointCnt(app.PointCnt>3) = 1;
                    app.ACPC = app.unrotateVox(app.ACPCR,app.imgSizeR,app.b);

                else %normal mouse press
                    if app.zoomH(app.currAxisIdx).Value
                        curAx = app.aH(app.currAxisIdx);
                        curPt = curAx.CurrentPoint(1,:);
                        curlim = [curAx.XLim, curAx.YLim, curAx.ZLim];

                        b1 = curPt(1) >= curlim(1) && curPt(1) <= curlim(2);
                        b2 = curPt(2) >= curlim(3) && curPt(2) <= curlim(4);

                        if b1 && b2
                            switch app.KeyType
                                case 'shift'
                                    newlim = app.zoomAroundPoint(curlim, curPt, 0.8); % zoom out
                                otherwise
                                    newlim = app.zoomAroundPoint(curlim, curPt, 1.2); % zoom in
                            end

                            set(curAx, ...
                                'XLim', newlim(1:2), ...
                                'YLim', newlim(3:4));
                        end
                    end
                    if ~any(isnan(app.ACPCR(:))) %have AC/PC/midline been selected?
                        if any(app.BBBool(:)) %is mouse over a rectangle border?
                            app.BtnDwn = true; %initiate a drag
                        end
                    end
                end
            end

            if strcmp(event.EventName,'WindowKeyPress')
                switch event.Key
                    case {'delete','backspace'}
                        app.ACPC = nan(3,3); app.ACPCR = app.ACPC;
                        app.PointCnt = 1;
                        set(app.rH,'visible','off')
                        set(app.MainFigure,'pointer','arrow')
                end
            end
            
            set(app.pH,'visible','off')
            for k=1:3 %by axes
                set(app.tH(k),'string',sprintf('%s %0.0f/%0.0f',app.TitleCell{k},app.imgCenterR(k),app.imgSizeR(k)));
                switch k
                    case 1
                        set(app.iH(k),'cdata',squeeze(app.imgR(app.imgCenterR(k),:,:))')
                        set(app.pH(1,k),'xdata',app.ACPCR(1,2),'ydata',app.ACPCR(1,3))
                        set(app.pH(2,k),'xdata',app.ACPCR(2,2),'ydata',app.ACPCR(2,3))
                        set(app.pH(3,k),'xdata',app.ACPCR(3,2),'ydata',app.ACPCR(3,3))
                        if (app.imgCenterR(k)>=app.ACPCR(1,k)-1 && app.imgCenterR(k)<=app.ACPCR(1,k)+1); set(app.pH(1,k),'visible','on'); end
                        if (app.imgCenterR(k)>=app.ACPCR(2,k)-1 && app.imgCenterR(k)<=app.ACPCR(2,k)+1); set(app.pH(2,k),'visible','on'); end
                        if (app.imgCenterR(k)>=app.ACPCR(3,k)-1 && app.imgCenterR(k)<=app.ACPCR(3,k)+1); set(app.pH(3,k),'visible','on'); end
                    case 2
                        set(app.iH(k),'cdata',squeeze(app.imgR(:,app.imgCenterR(k),:))')
                        set(app.pH(1,k),'xdata',app.ACPCR(1,1),'ydata',app.ACPCR(1,3))
                        set(app.pH(2,k),'xdata',app.ACPCR(2,1),'ydata',app.ACPCR(2,3))
                        set(app.pH(3,k),'xdata',app.ACPCR(3,1),'ydata',app.ACPCR(3,3))
                        if (app.imgCenterR(k)>=app.ACPCR(1,k)-1 && app.imgCenterR(k)<=app.ACPCR(1,k)+1); set(app.pH(1,k),'visible','on'); end
                        if (app.imgCenterR(k)>=app.ACPCR(2,k)-1 && app.imgCenterR(k)<=app.ACPCR(2,k)+1); set(app.pH(2,k),'visible','on'); end
                        if (app.imgCenterR(k)>=app.ACPCR(3,k)-1 && app.imgCenterR(k)<=app.ACPCR(3,k)+1); set(app.pH(3,k),'visible','on'); end
                    case 3
                        set(app.iH(k),'cdata',squeeze(app.imgR(:,:,app.imgCenterR(k)))')
                        set(app.pH(1,k),'xdata',app.ACPCR(1,1),'ydata',app.ACPCR(1,2))
                        set(app.pH(2,k),'xdata',app.ACPCR(2,1),'ydata',app.ACPCR(2,2))
                        set(app.pH(3,k),'xdata',app.ACPCR(3,1),'ydata',app.ACPCR(3,2))
                        if (app.imgCenterR(k)>=app.ACPCR(1,k)-1 && app.imgCenterR(k)<=app.ACPCR(1,k)+1); set(app.pH(1,k),'visible','on'); end
                        if (app.imgCenterR(k)>=app.ACPCR(2,k)-1 && app.imgCenterR(k)<=app.ACPCR(2,k)+1); set(app.pH(2,k),'visible','on'); end
                        if (app.imgCenterR(k)>=app.ACPCR(3,k)-1 && app.imgCenterR(k)<=app.ACPCR(3,k)+1); set(app.pH(3,k),'visible','on'); end
                end
                SDiff = setdiff(1:3,k);
                if ~any(isnan(app.ACPCR(:)))
                    set(app.rH(k),'position',[mean(app.ACPCR(1:2,SDiff),1)-app.BBR(SDiff),app.BBR(SDiff)*2],'visible','on');
                end
            end
        end

        % Close request function: MainFigure
        function CloseFcn(app, event)

            % Ask whether to save before closing
            choice = uiconfirm(app.MainFigure, ...
                'Would you like to save your changes?', ...
                'Save Changes', ...
                'Options', {'Yes','No'}, ...
                'DefaultOption', 'Yes', ...
                'CancelOption', 'No');

            if strcmp(choice, 'No')

                % Discard temporary sd* working image created by ACPC startup
                if ~isempty(app.imgInfo) && isfield(app.imgInfo, 'fname')
                    tempFile = app.imgInfo.fname;

                    [~, tempName, ~] = fileparts(tempFile);

                    if startsWith(tempName, 'sd') && exist(tempFile, 'file')
                        delete(tempFile);
                    end
                end

                app.SavedChanges = false;

                app.MainFigure.Visible = 'off';
                uiresume(app.MainFigure);
                return;
            end

            WaitH = [];

            try
                WaitH = uiprogressdlg(app.MainFigure, ...
                    'Indeterminate', 'on', ...
                    'Message', 'Overwriting image...');

                % --- Define crop bounding box (optional, centered at mid AC/PC) ---
                mACPC = mean(app.ACPCR(1:2,:),1); % midpoint between AC and PC
                BBRng = [ones(1,3); app.imgSizeR];
                if ~any(isnan(app.ACPCR(:)))
                    if strcmp(questdlg('Do you want to crop the image?'),'Yes')
                    BBRng = round([mACPC - app.BBR; mACPC + app.BBR]);
                    BBRng(BBRng < 1) = 1;
                    BBRng(2, BBRng(2,:) > app.imgSizeR) = app.imgSizeR(BBRng(2,:) > app.imgSizeR);
                end
            end
        
            % --- Crop subvolume & recenter AC/PC/Midline voxel indices ---
            imgSub = app.imgR( ...
                BBRng(1,1):BBRng(2,1), ...
                BBRng(1,2):BBRng(2,2), ...
                BBRng(1,3):BBRng(2,3));
            CenterVox = round(size(imgSub)/2);
            app.ACPCR = app.ACPCR - (BBRng(1,:) - 1);  % adjust indices for cropping
        
            % --- Build new affine (negative x scale → radiological; 4th elem = 1) ---
            app.imgScaleR(1) = -abs(app.imgScaleR(1));
            app.imgScaleR(4) = 1;
            trans = CenterVox .* -app.imgScaleR(1:3);
            finmat = diag(app.imgScaleR);
            finmat(1:3,4) = trans;
        
            % --- Write temporary "sd*" working file ---
            app.imgInfo.mat = finmat;
            app.imgInfo.dim = size(imgSub);
            spm_write_vol(app.imgInfo, imgSub); % writes to app.imgInfo.fname (has "sd" prefix)
        
            % --- We will OVERWRITE the original file (remove sd* prefix if present) ---
            imgInfoACPC = app.imgInfo; % clone header struct
            [path, file, ext] = fileparts(imgInfoACPC.fname);
            origFile = fullfile(path, [regexprep(file,'^sd',''), ext]);
            imgInfoACPC.fname = origFile;
        
            % === Case 1: No ACPC points → just finalize and exit ===
            if any(isnan(app.ACPCR(:)))
                % Put the sd* content into the original filename
                movefile(app.imgInfo.fname, imgInfoACPC.fname, 'f');

                % Optional: flip left/right if checkbox is selected
                app.flipLeftRight(imgInfoACPC.fname);

                close(WaitH);

                app.SavedChanges = true;

                app.MainFigure.Visible = 'off';
                uiresume(app.MainFigure);
                return;
            end

            % === Case 2: ACPC points available → rotate to ACPC & reslice ===

            % Start by copying the freshly written sd* voxels into the original file,
            % so transforms + reslicing operate on the same intensity content.
            copyfile(app.imgInfo.fname, imgInfoACPC.fname, 'f');
        
            % Compute ACPC points in mm (on original file header)
            ACPCmm = [app.ACPCR, ones(3,1)] * imgInfoACPC.mat';
            mACPCmm = [mean(ACPCmm(1:2,:),1); ACPCmm(2:3,:)]; % use mid AC/PC as center
        
            % -------------------
            % Step 1: Translate AC to the origin
            % -------------------
            bVec = [-mACPCmm(1,1), -mACPCmm(1,2), -mACPCmm(1,3), ...
                     0, 0, 0, ...           % pitch, roll, yaw (rad)
                     1, 1, 1, ...           % resize (x,y,z)
                     0, 0, 0];              % shear placeholders
            spm_get_space(imgInfoACPC.fname, spm_matrix(bVec) * imgInfoACPC.mat);
            imgInfoACPC = spm_vol(imgInfoACPC.fname);
        
            % Recompute ACPC mm after translation
            ACPCmm = [app.ACPCR, ones(3,1)] * imgInfoACPC.mat';
            mACPCmm = [mean(ACPCmm(1:2,:),1); ACPCmm(2:3,:)];
        
            % Determine dominant axes from AC→PC (y) and AC→midline (z)
            yVec = mACPCmm(1,1:3) - mACPCmm(2,1:3); [~, yIdx] = max(abs(yVec)); yVal = yVec(yIdx);
            zVec = mACPCmm(3,1:3) - mACPCmm(1,1:3); [~, zIdx] = max(abs(zVec)); zVal = zVec(zIdx);
            xVec = cross(yVec, zVec);              [~, xIdx] = max(abs(xVec)); xVal = xVec(xIdx);
        
            % -------------------
            % Step 2: Rotate AC→PC to align with negative y (posterior from center)
            % -------------------
            UnitVec = zeros(1,3); UnitVec(yIdx) = -1;
            RMat = LeG_genRotMat(mACPCmm(2,1:3), UnitVec);
            spm_get_space(imgInfoACPC.fname, RMat * imgInfoACPC.mat);
            imgInfoACPC = spm_vol(imgInfoACPC.fname);
        
            % Recompute after rotation
            ACPCmm = [app.ACPCR, ones(3,1)] * imgInfoACPC.mat';
            mACPCmm = [mean(ACPCmm(1:2,:),1); ACPCmm(2:3,:)];
        
            % -------------------
            % Step 3: Rotate midline point into the sagittal (yz) plane
            % -------------------
            UnitVec = zeros(1,3); UnitVec(zIdx) = 1;   % positive superior
            RotVec = mACPCmm(3,1:3); RotVec(yIdx) = 0; % rotate about y so midline in yz
            RMat = LeG_genRotMat(RotVec, UnitVec);
            if ~any(isnan(RMat(:)))
                spm_get_space(imgInfoACPC.fname, RMat * imgInfoACPC.mat);
                imgInfoACPC = spm_vol(imgInfoACPC.fname);
            end
        
            % Final sanity check
            ACPCmm = [app.ACPCR, ones(3,1)] * imgInfoACPC.mat';
            mACPCmm = [mean(ACPCmm(1:2,:),1); ACPCmm(2:3,:)];
            chk = all(round(mACPCmm(1,1:3)*1e3)/1e3 == 0) && ...
                  all(round(mACPCmm(2,[xIdx, zIdx])*1e3)/1e3 == 0) && ...
                  all(round(mACPCmm(3, xIdx)*1e3)/1e3 == 0);
        
            if chk
                disp('Rotation to ACPC space succeeded');
                save(fullfile(path, 'ACPCmm.mat'), 'ACPCmm', 'mACPCmm'); % keep mm landmarks
            else
                disp('ACPC rotation failed!');
                msgbox('ACPC rotation failed!');
            end
        
            % -------------------
            % Reslice original IN-PLACE to reference (sd*) grid
            % -------------------
            flags.mask   = 1;
            flags.interp = 4;      % 4th-order B-spline
            flags.which  = [1,0];
            flags.wrap   = [0,0,0];
            flags.prefix = '';     % IMPORTANT: overwrite target (P{2}) in place
        
            P = cell(2,1);
            P{1} = [app.imgInfo.fname, ',1'];  % reference grid (sd*)
            P{2} = [imgInfoACPC.fname, ',1'];  % target to overwrite (original)
        
            WaitH.Message = 'Reslicing image...';
            % Keep the SPM progress thermometer hidden
            fHProg = findobj('Tag','Interactive');

            if isempty(fHProg)
                fHProg = figure( ...
                    'Tag','Interactive', ...
                    'Visible','off');
            else
                set(fHProg, 'Visible','off');
            end

            spm_reslice(P, flags);
            pause(0.1);

            % Optional: flip left/right if checkbox is selected (apply to final file)
            app.flipLeftRight(imgInfoACPC.fname);

            % Cleanup UI
            close(WaitH);
            if exist('fHProg','var'), close(fHProg); end
            pause(0.1);

            % Remove temporary sd* file now that original has been overwritten
            if exist(app.imgInfo.fname, 'file'), delete(app.imgInfo.fname); end

            % Warn if non-standard orientation remains (as in your original logic)
            if any([xIdx, yIdx, zIdx] ~= [1,2,3]) || any([xVal, yVal, zVal] < 0)
                msgbox('Image was not rotated to match title descriptions. Coregistration might fail!');
            end

            % Only reach this after everything completed successfully
            app.SavedChanges = true;

            if ~isempty(WaitH) && isvalid(WaitH)
                close(WaitH);
            end

            app.MainFigure.Visible = 'off';
            uiresume(app.MainFigure);

            catch ME

                app.SavedChanges = false;

                if ~isempty(WaitH) && isvalid(WaitH)
                    close(WaitH);
                end

                if exist('fHProg','var') && isgraphics(fHProg)
                    close(fHProg);
                end

                uialert(app.MainFigure, ...
                    sprintf('AC/PC processing failed:\n\n%s', ME.message), ...
                    'AC/PC Error', ...
                    'Icon', 'error');
            end
   
        end

        % Window button motion function: MainFigure
        function ButtonMotionFcn(app, event)
            ap = cell2mat(get(app.aH,'currentpoint'));
            ap = ap([1,3,5],1:2);
            axSize = [cell2mat(get(app.aH,'xlim')),cell2mat(get(app.aH,'ylim'))];

            app.HoverAx = (ap(:,1)>=axSize(:,1) & ap(:,1)<=axSize(:,2)) & (ap(:,2)>=axSize(:,3) & ap(:,2)<=axSize(:,4)); %boolean 3x1, true when mouse hovers over axis
            DiffAx = ~app.HoverAx;
            
            if any(app.HoverAx)
                if get(app.zoomH(app.HoverAx),'value')
                    return; %skip if zoom is on
                end
            end
            
            %pan 2D
            panIdx = all([app.HoverAx';[app.panH.Value]]);
            if any(panIdx)
                if strcmp(app.SelectionType,'normal') || strcmp(app.SelectionType,'extend')
                    pts = [app.aH(panIdx).CurrentPoint(1,1:2);app.ClickPntAx{panIdx}(1,1:2)];
                    dpt = diff(pts);
                    set(app.aH(panIdx),'XLim',app.aH(panIdx).XLim + dpt(1),'YLim',app.aH(panIdx).YLim + dpt(2));
                end
            end

            if ~any(isnan(app.ACPCR(:))) %have AC/PC/midline been selected?
                if any(app.HoverAx) %is mouse over an axis?
                    app.BBVal = ap(app.HoverAx,:)'; %current xy position of mouse (relative to current axis)
                    app.BBLim = [mean(app.ACPCR(1:2,DiffAx),1)-app.BBR(DiffAx);mean(app.ACPCR(1:2,DiffAx),1)+app.BBR(DiffAx)]'; %current xy bounding box ranges
                    if ~app.Dragging

                        % Determine the closest border in each dimension.
                        % Never allow both opposite borders of one dimension to be active.
                        app.BBBool = false(2);

                        inside = app.BBVal >= app.BBLim(:,1) & ...
                            app.BBVal <= app.BBLim(:,2);

                        for d = 1:2

                            [edgeDist, edgeIdx] = min( ...
                                abs(app.BBLim(d,:) - app.BBVal(d)));

                            otherDim = 3 - d;

                            if edgeDist <= 10 && inside(otherDim)
                                app.BBBool(d, edgeIdx) = true;
                            end
                        end
                    end

                    if any(app.BBBool(:))

                        set(app.MainFigure,'pointer','hand')

                        if app.BtnDwn

                            app.Dragging = true;
                            BBLimAdj = app.BBLim;

                            % Move each active border using its matching mouse coordinate
                            for d = 1:2
                                edgeIdx = find(app.BBBool(d,:), 1);

                                if ~isempty(edgeIdx)
                                    BBLimAdj(d, edgeIdx) = app.BBVal(d);
            end
        end

        app.BBR(DiffAx) = app.BBR(DiffAx) + ...
            diff(BBLimAdj - app.BBLim,1,2)';

        % Do not allow the crop box to collapse
        app.BBR = max(app.BBR, 10);

        app.BB = [app.BBR,1] / spm_matrix(app.b);
        app.BB = abs(app.BB(1:3));

                            for k = 1:3
                                sdiff = setdiff(1:3,k);

                                rectPos = [ ...
                                    mean(app.ACPCR(1:2,sdiff),1) - app.BBR(sdiff), ...
                                    2 * app.BBR(sdiff)];

                                set(app.rH(k), 'Position', rectPos);
                            end
                        end
                    else
                        set(app.MainFigure,'pointer','arrow')
                    end
                    % clc; disp([app.BBVal,app.BBLim,app.BBBool]);
                end
            end
        end

        % Window button up function: MainFigure
        function ButtonUpFcn(app, event)
            app.BtnDwn = false;
            app.Dragging = false;
            app.SelectionType = '';
        end

        % Button pushed function: InstructionsButton
        function InstructionsBtnFcn(app, event)
            msgcell = {...
                '*Check that image orientations match titles'
                '*If mismatch, press ctrl+ left/right arrows to rotate to match titles'
                '*Close window to save (CT) OR continue for AC/PC alignment (MR)'
                '*AC/PC alignment...'
                '   -Hover mouse cursor over image to activate'
                '   -Use scroll wheel or press left/right arrows to scroll active image'
                '   -Right click (in order) AC, PC, then superior mid-saggital points'
                '   -Green (AC), red (PC), and blue (mid-saggital) points will appear'
                '   -Place mid-saggital point at superior brain edge overlying the falx'
                '       anywhere between the AC and PC points'
                '   -Points can be placed using any of the 3 image orientations'
                '   -Axial view works well for placing AC and PC points'
                '   -Coronal view works well for placing superior mid-saggital point'
                '   -Press "magnifier" button to zoom image to help place points'
                '   -Press "home" button to restore image to original view'
                '   -Press delete or backspace to clear points if misplaced'
                '   -Cropping box will appear when all 3 points are placed'
                '   -Remember to deselect zoom and restore view before adjusting box'
                '   -Adjust cropping box to skin edge'
                '   -Close window to save'
                };
            LeG_msgbox(msgcell,'ACPCGUI');
        end

        % Button pushed function: RestoreAxialH
        function RestoreAxialBtnFcn(app, event)
            restoreView(app,app.AxialAxH)
        end

        % Button pushed function: RestoreCorH
        function RestoreCorBtnFcn(app, event)
            restoreView(app,app.CorAxH)
        end

        % Button pushed function: RestoreSagH
        function RestoreSagBtnFcn(app, event)
            restoreView(app,app.SagAxH)
        end

        % Window key release function: MainFigure
        function WindowKeyRelease(app, event)
            app.KeyType = '';
        end

        % Value changed function: PanAxialH, ZoomAxialH
        function AxialToolbarFcn(app, event)
            switch event.Source.Tag
                case 'ZoomAxial'
                    if event.Value
                        app.PanAxialH.Value = false;
                    end
                case 'PanAxial'
                    if event.Value
                        app.ZoomAxialH.Value = false;
                    end
            end
        end

        % Value changed function: PanCorH, ZoomCorH
        function CorToolbarFcn(app, event)
            switch event.Source.Tag
                case 'ZoomCor'
                    if event.Value
                        app.PanCorH.Value = false;
                    end
                case 'PanCor'
                    if event.Value
                        app.ZoomCorH.Value = false;
                    end
            end
        end

        % Value changed function: PanSagH, ZoomSagH
        function SagToolbarFcn(app, event)
            switch event.Source.Tag
                case 'ZoomSag'
                    if event.Value
                        app.PanSagH.Value = false;
                    end
                case 'PanSag'
                    if event.Value
                        app.ZoomSagH.Value = false;
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'ACPCGUI';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @CloseFcn, true);
            app.MainFigure.WindowButtonDownFcn = createCallbackFcn(app, @ButtonDownKeyPressScrollFcn, true);
            app.MainFigure.WindowButtonUpFcn = createCallbackFcn(app, @ButtonUpFcn, true);
            app.MainFigure.WindowButtonMotionFcn = createCallbackFcn(app, @ButtonMotionFcn, true);
            app.MainFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ButtonDownKeyPressScrollFcn, true);
            app.MainFigure.WindowKeyPressFcn = createCallbackFcn(app, @ButtonDownKeyPressScrollFcn, true);
            app.MainFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @WindowKeyRelease, true);
            app.SagAxH = uiaxes(app.MainFigure);
            app.SagAxH.XTick = [];
            app.SagAxH.YTick = [];
            app.SagAxH.Tag = 'SaggitalAx';
            colormap(app.SagAxH, 'parula')
            app.CorAxH = uiaxes(app.MainFigure);
            app.CorAxH.XTick = [];
            app.CorAxH.YTick = [];
            app.CorAxH.Tag = 'CoronalAx';
            colormap(app.CorAxH, 'parula')
            app.AxialAxH = uiaxes(app.MainFigure);
            app.AxialAxH.XTick = [];
            app.AxialAxH.YTick = [];
            app.AxialAxH.Tag = 'AxialAx';
            colormap(app.AxialAxH, 'parula')
            app.InstructionsButton = uibutton(app.MainFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsBtnFcn, true);
            app.InstructionsButton.Text = 'Instructions';
            app.ToolbarSag = uipanel(app.MainFigure);
            app.ZoomSagH = uibutton(app.ToolbarSag, 'state');
            app.ZoomSagH.ValueChangedFcn = createCallbackFcn(app, @SagToolbarFcn, true);
            app.ZoomSagH.Tag = 'ZoomSag';
            app.ZoomSagH.Tooltip = {'Zoom In'};
            app.ZoomSagH.Icon = 'zoomin_icon.png';
            app.ZoomSagH.Text = '';
            app.RestoreSagH = uibutton(app.ToolbarSag, 'push');
            app.RestoreSagH.ButtonPushedFcn = createCallbackFcn(app, @RestoreSagBtnFcn, true);
            app.RestoreSagH.Tag = 'RestoreSag';
            app.RestoreSagH.Icon = 'restoreview_icon.png';
            app.RestoreSagH.Tooltip = {'Restore View'};
            app.RestoreSagH.Text = '';
            app.PanSagH = uibutton(app.ToolbarSag, 'state');
            app.PanSagH.ValueChangedFcn = createCallbackFcn(app, @SagToolbarFcn, true);
            app.PanSagH.Tag = 'PanSag';
            app.PanSagH.Tooltip = {'Pan'};
            app.PanSagH.Icon = 'pan_icon.png';
            app.PanSagH.Text = '';
            app.ToolbarCor = uipanel(app.MainFigure);
            app.ZoomCorH = uibutton(app.ToolbarCor, 'state');
            app.ZoomCorH.ValueChangedFcn = createCallbackFcn(app, @CorToolbarFcn, true);
            app.ZoomCorH.Tag = 'ZoomCor';
            app.ZoomCorH.Tooltip = {'Zoom In'};
            app.ZoomCorH.Icon = 'zoomin_icon.png';
            app.ZoomCorH.Text = '';
            app.RestoreCorH = uibutton(app.ToolbarCor, 'push');
            app.RestoreCorH.ButtonPushedFcn = createCallbackFcn(app, @RestoreCorBtnFcn, true);
            app.RestoreCorH.Tag = 'RestoreCor';
            app.RestoreCorH.Icon = 'restoreview_icon.png';
            app.RestoreCorH.Tooltip = {'Restore View'};
            app.RestoreCorH.Text = '';
            app.PanCorH = uibutton(app.ToolbarCor, 'state');
            app.PanCorH.ValueChangedFcn = createCallbackFcn(app, @CorToolbarFcn, true);
            app.PanCorH.Tag = 'PanCor';
            app.PanCorH.Tooltip = {'Pan'};
            app.PanCorH.Icon = 'pan_icon.png';
            app.PanCorH.Text = '';
            app.ToolbarAxial = uipanel(app.MainFigure);
            app.ZoomAxialH = uibutton(app.ToolbarAxial, 'state');
            app.ZoomAxialH.ValueChangedFcn = createCallbackFcn(app, @AxialToolbarFcn, true);
            app.ZoomAxialH.Tag = 'ZoomAxial';
            app.ZoomAxialH.Tooltip = {'Zoom In'};
            app.ZoomAxialH.Icon = 'zoomin_icon.png';
            app.ZoomAxialH.Text = '';
            app.RestoreAxialH = uibutton(app.ToolbarAxial, 'push');
            app.RestoreAxialH.ButtonPushedFcn = createCallbackFcn(app, @RestoreAxialBtnFcn, true);
            app.RestoreAxialH.Tag = 'RestoreAxial';
            app.RestoreAxialH.Icon = 'restoreview_icon.png';
            app.RestoreAxialH.Tooltip = {'Restore View'};
            app.RestoreAxialH.Text = '';
            app.PanAxialH = uibutton(app.ToolbarAxial, 'state');
            app.PanAxialH.ValueChangedFcn = createCallbackFcn(app, @AxialToolbarFcn, true);
            app.PanAxialH.Tag = 'PanAxial';
            app.PanAxialH.Tooltip = {'Pan'};
            app.PanAxialH.Icon = 'pan_icon.png';
            app.PanAxialH.Text = '';
            app.FlipCkbH = uicheckbox(app.MainFigure);
            app.FlipCkbH.Enable = 'off';
            app.FlipCkbH.Visible = 'off';
            app.FlipCkbH.Tooltip = {'Select this before closing if the left/right orientation of the images is incorrect. The images use the radiological convention, so the left is displayed on the right side and vice versa.'};
            app.FlipCkbH.Text = 'Flip Left/Right';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_ACPCGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end