classdef LeG_AssignElectrodesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure      matlab.ui.Figure
        Toolbar3DPanel  matlab.ui.container.Panel
        Pan3DH          matlab.ui.control.StateButton
        Select3DH       matlab.ui.control.StateButton
        Restore3DH      matlab.ui.control.Button
        Export3DH       matlab.ui.control.Button
        Rotate3DH       matlab.ui.control.StateButton
        Zoom3DH         matlab.ui.control.StateButton
        BrainVisLabel   matlab.ui.control.Label
        BrainAlphaDpdH  matlab.ui.control.DropDown
        AutoNameBtnH    matlab.ui.control.Button
        SelCntH         matlab.ui.control.NumericEditField
        BtnPanel        matlab.ui.container.Panel
        DepthCkbH       matlab.ui.control.CheckBox
        MicroCkbH       matlab.ui.control.CheckBox
        MarkGndBtnH     matlab.ui.control.Button
        MarkRefBtnH     matlab.ui.control.Button
        AssignBtnH      matlab.ui.control.Button
        ClearBtnH       matlab.ui.control.Button
        ClearAllBtnH    matlab.ui.control.Button
        AlphaPanel      matlab.ui.container.Panel
        UndoBtnH        matlab.ui.control.Button
        TableControls   matlab.ui.container.Panel
        MapTypeLabel    matlab.ui.control.Label
        ColsLabel       matlab.ui.control.Label
        RowsLabel       matlab.ui.control.Label
        ModeLabel       matlab.ui.control.Label
        TableModePupH   matlab.ui.control.DropDown
        MapTypePupH     matlab.ui.control.DropDown
        ColEditH        matlab.ui.control.EditField
        RowEditH        matlab.ui.control.EditField
        InstructBtnH    matlab.ui.control.Button
        MapTableH       matlab.ui.control.Table
        BrainAxH        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        LeGUI
        GridSize
        GCM1
        GCM2
        GLM

        Alpha
        CurTableVal
        CurTableIdx
        SelLabel %selected labels in table
        SelChan1 %selected chan1 in table
        SelChan2 %selected chan2 in table
        ChangeDetected
        KeyType %keyboard press type
        SelectionType %mouse press type
        ClickPntFig
        ClickPntBrainAx
        SelElec %selected electrodes in 3D plot

        BrainSurfRawH
        ElecXYZProjRawH
        PolyH
        LightH

        CurView3D
        BrainAxLimits
        HoverAx
        PolyPnts
        AutoNameApp = []
        DeleteColumnMenu
        DeleteColumnMenuItem
    end

    methods (Access = private)

        function stopOrbit(app, varargin)
            [app.CurView3D(1),app.CurView3D(2)] = view(app.BrainAxH);
            if app.Alpha>0
                camlight(app.LightH)
            end
        end

        function autoFill(app, varargin)
            t = app.MapTableH;
            p = app.MapTypePupH;
            CurrVal = app.CurTableVal; %current table value
            if length(CurrVal)==1
                ValHeader = regexp(CurrVal{1},'^[A-Za-z]+','match','once');
                ValNumber = str2double(regexp(CurrVal,'\d+$','match','once'));
                CurrIdx = app.CurTableIdx;
                CurrIdx(any(isnan(CurrIdx),2),:) = [];
                if size(CurrIdx,1)>1 || isempty(CurrIdx)
                    return;
                end
                ridxs = CurrIdx(1); %row indexes
                cidxs = CurrIdx(2); %col indexes
                if ~isempty(varargin{1}.Modifier)
                    switch [varargin{1}.Modifier{:}]
                        case {'control','command'} %increment values
                            switch varargin{1}.Key
                                case 'uparrow'
                                    ridxs = (ridxs:-1:1); %decrease indexes
                                    vals = ValNumber+(0:length(ridxs)-1);
                                case 'downarrow'
                                    midx = app.GridSize(1); %max index
                                    ridxs = (ridxs:1:midx); %increase indexes
                                    vals = ValNumber+(0:length(ridxs)-1);
                                case 'rightarrow'
                                    midx = app.GridSize(2); %max index
                                    cidxs = (cidxs:1:midx); %increase indexes
                                    vals = ValNumber+(0:length(cidxs)-1);
                                case 'leftarrow'
                                    cidxs = (cidxs:-1:1); %decrease indexes
                                    vals = ValNumber+(0:length(cidxs)-1);
                            end
                        case {'alt','shiftcontrol'} %decrement values
                            switch varargin{1}.Key
                                case 'uparrow'
                                    ridxs = (ridxs:-1:1); %decrease indexes
                                    vals = ValNumber-(0:length(ridxs)-1);
                                case 'downarrow'
                                    midx = app.GridSize(1); %max index
                                    ridxs = (ridxs:1:midx); %increase indexes
                                    vals = ValNumber-(0:length(ridxs)-1);
                                case 'rightarrow'
                                    midx = app.GridSize(2); %max index
                                    cidxs = (cidxs:1:midx); %increase indexes
                                    vals = ValNumber-(0:length(cidxs)-1);
                                case 'leftarrow'
                                    cidxs = (cidxs:-1:1); %decrease indexes
                                    vals = ValNumber-(0:length(cidxs)-1);
                            end
                    end %end modifier switch
                    if exist('vals','var')
                        if any(isnan(vals))
                            vals = cellfun(@(x)num2str(x),num2cell(vals),'uniformoutput',false);
                        else
                            vals = cellfun(@(x)[ValHeader,num2str(x)],num2cell(vals),'uniformoutput',false);
                        end

                        Ridxs = ridxs; Cidxs = cidxs;
                        if length(ridxs)==1
                            Ridxs = repmat(ridxs,1,length(cidxs));
                        else
                            Cidxs = repmat(cidxs,1,length(ridxs));
                        end

                        pSelLabel = app.GLM(ridxs,cidxs);
                        switch get(p,'value')
                            case 'LabelMap'
                                app.GLM(ridxs,cidxs) = vals;
                            case 'ChannelMap1'
                                app.GCM1(ridxs,cidxs) = vals;
                            case 'ChannelMap2'
                                app.GCM2(ridxs,cidxs) = vals;
                        end
                        t.Data(ridxs,cidxs) = vals;

                        app.CurTableIdx = [Ridxs(:),Cidxs(:)];
                        app.updateSelTableVars; %updates CurTableVal, SelLabel, SelChan1, SelChan2
                        app.SelElec = app.findElecMapRawIdx(pSelLabel);
                        nanidx = isnan(app.SelElec);
                        app.SelElec(nanidx) = [];
                        app.SelLabel(nanidx) = [];
                        app.SelChan1(nanidx) = [];
                        app.SelChan2(nanidx) = [];
                        idx = ismember(app.LeGUI.ElecMapRaw(:,1),app.SelLabel);
%                         app.LeGUI.ElecMapRaw(idx,:) = repmat({'NaN',nan,nan},sum(idx),1); 
                        %clear previous assignment
                        app.LeGUI.ElecMapRaw(idx,1) = {'NaN'};
                        app.LeGUI.ElecMapRaw(app.SelElec,:) = [app.SelLabel,num2cell(app.SelChan1),num2cell(app.SelChan2)];
                        app.SelCntH.Value = length(app.SelElec);
                        app.setElecCellColor;
                    end
                end %end isempty modifier
            end
        end

        function out = buildCurrentAutoNameOut(app)

            % Make sure Assign GUI data are synchronized first
            app.syncAllAssignments();

            nContacts = size(app.LeGUI.ElecMapRaw, 1);
            nShafts   = size(app.GLM, 2);

          
            out = struct();

            out.ShaftNames      = cell(1, nShafts);
            out.ShaftModels     = cell(1, nShafts);
            out.ShaftMembership = app.LeGUI.ShaftMembership;

            out.Shafts = repmat(struct('indices', []), 1, nShafts);

            out.ElecMapRaw  = app.LeGUI.ElecMapRaw;
            out.LabelMap    = app.LeGUI.LabelMap;
            out.ChannelMap1 = app.LeGUI.ChannelMap1;
            out.ChannelMap2 = app.LeGUI.ChannelMap2;

            % Preserve current electrode classifications
            out.DepthElecRaw = app.LeGUI.DepthElecRaw;
            out.MicroElecRaw = app.LeGUI.MicroElecRaw;
            out.RefElecRaw   = app.LeGUI.RefElecRaw;
            out.GndElecRaw   = app.LeGUI.GndElecRaw;

            for s = 1:nShafts

                % Recover shaft contacts in the CURRENT LabelMap order.
                % LabelMap is stored with contact 1 at the bottom, so read
                % the column from bottom -> top to preserve distal -> proximal.
                shaftIdx = [];

                for r = size(app.GLM,1):-1:1

                    thisLabel = app.GLM{r,s};

                    if isempty(thisLabel) || strcmpi(thisLabel,'NaN')
                        continue;
                    end

                    contactIdx = find(strcmp(app.LeGUI.ElecMapRaw(:,1), thisLabel), 1);

                    if ~isempty(contactIdx)
                        shaftIdx(end+1,1) = contactIdx; %#ok<AGROW>
                    end
                end

    out.Shafts(s).indices = shaftIdx;

    % Preserve existing custom name
                % Preserve existing custom name
                if s <= numel(app.LeGUI.ShaftNames) && ...
                        ~isempty(app.LeGUI.ShaftNames{s})

                    out.ShaftNames{s} = app.LeGUI.ShaftNames{s};
                else
                    out.ShaftNames{s} = '';
                end

                % Preserve existing selected model
                if s <= numel(app.LeGUI.ShaftModels) && ...
                        ~isempty(app.LeGUI.ShaftModels{s})

                    out.ShaftModels{s} = app.LeGUI.ShaftModels{s};
                else
                    out.ShaftModels{s} = '';
                end
            end

            if numel(out.ShaftMembership) ~= nContacts
                error(['Current ShaftMembership has %d entries, but there are ' ...
                    '%d physical contacts.'], ...
                    numel(out.ShaftMembership), nContacts);
            end
        end

        function handles = buildTable(app, handles)

            p = app.MapTypePupH;
            t = app.MapTableH;
            nr = app.RowEditH;
            nc = app.ColEditH;

            oldGLM = app.GLM;
            oldGCM1 = app.GCM1;
            oldGCM2 = app.GCM2;

            oldSize = size(oldGLM);

            newRows = str2double(get(nr,'value'));
            newCols = str2double(get(nc,'value'));

            if isnan(newRows) || isnan(newCols) || newRows < 1 || newCols < 1
                msgbox('Rows and columns must be positive numbers.');
                set(nr,'value',num2str(oldSize(1)))
                set(nc,'value',num2str(oldSize(2)))
                return;
            end

            newRows = round(newRows);
            newCols = round(newCols);

            % If shrinking, ask first because data may be deleted
            if newRows < oldSize(1) || newCols < oldSize(2)
                answer = questdlg( ...
                    'This will remove rows/columns and may delete assignments. Proceed?', ...
                    'Resize Table', ...
                    'Yes','No','No');

                if ~strcmp(answer,'Yes')
                    set(nr,'value',num2str(oldSize(1)))
                    set(nc,'value',num2str(oldSize(2)))
                    return;
                end
            end

            app.lastChange;

            % Make new blank maps
            newGLM = repmat({'NaN'}, newRows, newCols);
            newGCM1 = repmat({'NaN'}, newRows, newCols);
            newGCM2 = repmat({'NaN'}, newRows, newCols);

            % Copy over existing data into shared size
            keepRows = min(oldSize(1), newRows);
            keepCols = min(oldSize(2), newCols);

            rowOffset = newRows - keepRows;

            newGLM((1:keepRows)+rowOffset, 1:keepCols) = oldGLM(1:keepRows, 1:keepCols);
            newGCM1((1:keepRows)+rowOffset, 1:keepCols) = oldGCM1(1:keepRows, 1:keepCols);
            newGCM2((1:keepRows)+rowOffset, 1:keepCols) = oldGCM2(1:keepRows, 1:keepCols);

            app.GLM = newGLM;
            app.GCM1 = newGCM1;
            app.GCM2 = newGCM2;
            app.GridSize = size(app.GLM);

            set(nr,'value',num2str(app.GridSize(1)))
            set(nc,'value',num2str(app.GridSize(2)))

            switch p.Value
                case 'LabelMap'
                    set(t,'data',app.GLM)
                case 'ChannelMap1'
                    set(t,'data',app.GCM1)
                case 'ChannelMap2'
                    set(t,'data',app.GCM2)
            end

            app.CurTableIdx = [];
            app.updateSelTableVars;
            app.LeGUI.ChangeDetected = true;
            app.setElecCellColor;

        end

        function Idx = findElecMapRawIdx(app, Labels)
            %Finds matching row in ElecMapRaw for Labels.
            Idx = [];
            if ~isempty(Labels)
                [~,Idx] = ismember(Labels,app.LeGUI.ElecMapRaw(:,1));
                nanidx = strcmp(Labels,'NaN')|(Idx==0);
                Idx(nanidx) = nan;
            end
        end

        function [Row, Col] = findTableRowCol(app, Labels)
            %Finds matching row/col in table for Labels
            Row = []; Col = [];
            if ~isempty(Labels)
                [~,idx] = ismember(Labels(:,1),app.GLM);
                [Row,Col] = ind2sub(size(app.GLM),idx);
                nanidx = strcmp(Labels(:,1),'NaN')|(idx==0);
                Row(nanidx) = nan;
                Col(nanidx) = nan;
            end
        end

        function dupFlag = flagDuplicates(app, varargin)
            %Returns true if duplicates exist in table
            glm = app.GLM; glm(cellfun(@(x)strcmpi(x,'NaN'),glm)) = [];
            gcm1 = app.GCM1; gcm1(cellfun(@(x)strcmpi(x,'NaN'),gcm1)) = [];
            gcm2 = app.GCM2; gcm2(cellfun(@(x)strcmpi(x,'NaN'),gcm2)) = [];
            [uGLM,~,iGLM] = unique(glm);
            [uGCM1,~,iGCM1] = unique(gcm1);
            [uGCM2,~,iGCM2] = unique(gcm2);
            dupFlag = length(iGLM)>length(uGLM) || length(iGCM1)>length(uGCM1) || length(iGCM2)>length(uGCM2);
        end

        function genChanMap(app, varargin)
            
            app.syncAllAssignments();
        end

        function updateCell(app, varargin)
            e = varargin{2};
            NewData = e.NewData;

            if isempty(NewData) || (isstring(NewData) && strlength(NewData)==0)
                NewData = 'NaN';
                app.MapTableH.Data(e.Indices(1),e.Indices(2)) = {NewData};
            end

            if strcmpi(NewData,'NaN')
                NewData = 'NaN';
                app.MapTableH.Data(e.Indices(1),e.Indices(2)) = {NewData};
            end
            PrevData = e.PreviousData;
            if strcmpi(PrevData,'NaN')
                PrevData = 'NaN';
            end
            p = app.MapTypePupH; %dropdown menu
            idx = sub2ind(size(app.GLM),e.Indices(1),e.Indices(2));
            switch p.Value
                case 'LabelMap'
                    app.GLM(idx) = {NewData};
                case 'ChannelMap1'
                    app.GCM1(idx) = {NewData};
                case 'ChannelMap2'
                    app.GCM2(idx) = {NewData};
            end
            app.CurTableIdx = e.Indices;
            app.updateSelTableVars; %updates CurTableVal, SelLabel, SelChan1, SelChan2
            switch app.MapTypePupH.Value
                case 'LabelMap'
                    app.SelElec = app.findElecMapRawIdx(PrevData);
                otherwise
                    app.SelElec = app.findElecMapRawIdx(app.SelLabel);
            end
            nanidx = isnan(app.SelElec);
            app.SelElec(nanidx) = [];
            app.SelLabel(nanidx) = [];
            app.SelChan1(nanidx) = [];
            app.SelChan2(nanidx) = [];
            idx = ismember(app.LeGUI.ElecMapRaw(:,1),app.SelLabel);
%             app.LeGUI.ElecMapRaw(idx,:) = repmat({'NaN',nan,nan},sum(idx),1);
            %clear previous assignment
            app.LeGUI.ElecMapRaw(idx,1) = {'NaN'};
            app.LeGUI.ElecMapRaw(app.SelElec,:) = [app.SelLabel,num2cell(app.SelChan1),num2cell(app.SelChan2)];
            app.SelCntH.Value = length(app.SelElec);
            app.syncAllAssignments();
            app.setElecCellColor;
        end


        function clearAll(app, varargin)
            app.LeGUI.ElecMapRaw = [repmat({'NaN'},size(app.LeGUI.ElecXYZProjRaw,1),1),num2cell(nan(size(app.LeGUI.ElecXYZProjRaw,1),2))];
            app.LeGUI.DepthElecRaw = [];
            app.LeGUI.MicroElecRaw = [];
            app.LeGUI.RefElecRaw = [];
            app.LeGUI.GndElecRaw = [];
            app.LeGUI.ChangeDetected = true;
            app.SelElec = []; %index into ElecXYZProjRaw (i.e. all electrodes not just electrodes assigned to channel)
            app.CurTableIdx = [];
            app.updateSelTableVars;
            app.SelCntH.Value = length(app.SelElec);
            app.setElecCellColor;
        end

        function restoreView(app, varargin)
            set(app.BrainAxH,'XLim',app.BrainAxLimits(:,1),'YLim',app.BrainAxLimits(:,2),'ZLim',app.BrainAxLimits(:,3));
            app.Zoom3DH.Value = false;
            app.Pan3DH.Value = false;
            app.Rotate3DH.Value = false;
            app.Select3DH.Value = false;
            app.CurView3D = [115,10];
            view(app.BrainAxH,app.CurView3D);
            app.stopOrbit;
        end

        function assignSelected(app, varargin)

            if ~strcmp(app.TableModePupH.Value, 'Assign')
            msgbox('Change table mode to "Assign" before making an assignment!');
            return;
        end

        if isempty(app.SelElec) || isempty(app.CurTableIdx) || ...
                numel(app.SelElec) ~= size(app.CurTableIdx, 1)

            msgbox('Electrode and table selection must be the same length!');
            return;
        end

        app.lastChange;

        % Validate selected physical contacts
        selectedContacts = app.SelElec(:);
        selectedContacts = selectedContacts(isfinite(selectedContacts));
        selectedContacts = selectedContacts(selectedContacts >= 1);
        selectedContacts = selectedContacts( ...
            selectedContacts <= size(app.LeGUI.ElecMapRaw, 1));

        targetCells = app.CurTableIdx;

        if numel(selectedContacts) ~= size(targetCells, 1)
            msgbox('Electrode and table selection must be the same length!');
            return;
        end

        for n = 1:numel(selectedContacts)

            contactIdx = selectedContacts(n);
            targetRow = targetCells(n, 1);
            targetCol = targetCells(n, 2);

            targetLabel = app.GLM{targetRow, targetCol};

            if isempty(targetLabel) || strcmpi(targetLabel, 'NaN')
                msgbox('The selected table cell must contain a valid label.');
                return;
            end

            
            oldLabel = app.LeGUI.ElecMapRaw{contactIdx, 1};
            oldChan1 = app.LeGUI.ElecMapRaw{contactIdx, 2};
            oldChan2 = app.LeGUI.ElecMapRaw{contactIdx, 3};

            
            if ~isempty(oldLabel) && ~strcmpi(string(oldLabel), "NaN")

                [oldRow, oldCol] = find(strcmp(app.GLM, oldLabel), 1);

                if ~isempty(oldRow)

                    % Do not erase the target cell if assigning to same place
                    sameCell = oldRow == targetRow && oldCol == targetCol;

                    if ~sameCell
                        app.GCM1{oldRow, oldCol} = 'NaN';
                        app.GCM2{oldRow, oldCol} = 'NaN';
                    end
                end
            end

          
            if isnumeric(oldChan1) && isscalar(oldChan1) && isfinite(oldChan1)
                targetChan1 = oldChan1;
            else
                targetChan1 = str2double(string(app.GCM1{targetRow, targetCol}));
            end

            if isnumeric(oldChan2) && isscalar(oldChan2) && isfinite(oldChan2)
                targetChan2 = oldChan2;
            else
                targetChan2 = str2double(string(app.GCM2{targetRow, targetCol}));
            end

           
            if isfinite(targetChan1)

                for k = 1:numel(app.GCM1)

                    if k == sub2ind(size(app.GCM1), targetRow, targetCol)
                        continue;
                    end

                    existingChan = str2double(string(app.GCM1{k}));

                    if isfinite(existingChan) && existingChan == targetChan1
                        app.GCM1{k} = 'NaN';
                    end
                end

                app.GCM1{targetRow, targetCol} = num2str(targetChan1);
            else
                app.GCM1{targetRow, targetCol} = 'NaN';
            end

            if isfinite(targetChan2)

                for k = 1:numel(app.GCM2)

                    if k == sub2ind(size(app.GCM2), targetRow, targetCol)
                        continue;
                    end

                    existingChan = str2double(string(app.GCM2{k}));

                    if isfinite(existingChan) && existingChan == targetChan2
                        app.GCM2{k} = 'NaN';
                    end
                end

                app.GCM2{targetRow, targetCol} = num2str(targetChan2);
            else
                app.GCM2{targetRow, targetCol} = 'NaN';
            end

            
            previousContact = strcmp( ...
                app.LeGUI.ElecMapRaw(:, 1), targetLabel);

            previousContact(contactIdx) = false;

            if any(previousContact)
                % Clear label only; preserve those contacts' physical channels
                app.LeGUI.ElecMapRaw(previousContact, 1) = {'NaN'};
            end

            
            app.LeGUI.ElecMapRaw(contactIdx, :) = { ...
                targetLabel, targetChan1, targetChan2};
        end

        app.LeGUI.DepthElecRaw( ...
            ismember(app.LeGUI.DepthElecRaw, selectedContacts)) = [];

        app.LeGUI.MicroElecRaw( ...
            ismember(app.LeGUI.MicroElecRaw, selectedContacts)) = [];

        if app.DepthCkbH.Value
            app.LeGUI.DepthElecRaw = unique( ...
                [app.LeGUI.DepthElecRaw; selectedContacts]);
        end

        if app.MicroCkbH.Value
            app.LeGUI.MicroElecRaw = unique( ...
                [app.LeGUI.MicroElecRaw; selectedContacts]);
        end

        % Refresh whichever table is currently visible
        switch app.MapTypePupH.Value
            case 'LabelMap'
                app.MapTableH.Data = app.GLM;

            case 'ChannelMap1'
                app.MapTableH.Data = app.GCM1;

            case 'ChannelMap2'
                app.MapTableH.Data = app.GCM2;
        end

        % Synchronize LabelMap, channel maps, ElecMapRaw and membership
        app.syncAllAssignments();

        app.SelElec = [];
        app.CurTableIdx = [];
        app.updateSelTableVars();
        app.SelCntH.Value = 0;
        app.setElecCellColor();
    end

    function setElecCellColor(app, varargin)
        %Sets color for assigned and selected
        %electrodes and table cells
        if size(app.LeGUI.ElecXYZProjRaw,1)~=size(app.LeGUI.ElecMapRaw,1)
            msgbox('ElecXYZProjRaw is not the same size as ElecMapRaw!');
            return;
        end

        colorlist = [[0,0,1];[0,1,0];[0,0,0];[1,0,1];[0,0.5,0];[1,0.5,0]]; %'Base','Grid','Depth','Micro','Gnd','Ref'

        elecmapraw = app.LeGUI.ElecMapRaw;
        eleccolorraw = ones(size(elecmapraw,1),1);
        elecmapidxs = 1:size(elecmapraw,1);
        eleccolorraw(~strcmp(elecmapraw(:,1),'NaN')) = 2; %green (grid)
        eleccolorraw(ismember(elecmapidxs,app.LeGUI.DepthElecRaw)) = 3;
        eleccolorraw(ismember(elecmapidxs,app.LeGUI.MicroElecRaw)) = 4;
        eleccolorraw(ismember(elecmapidxs,app.LeGUI.GndElecRaw)) = 5;
        eleccolorraw(ismember(elecmapidxs,app.LeGUI.RefElecRaw)) = 6;

        [row,col] = app.findTableRowCol(elecmapraw(:,1)); %these can be nan, output is same length as elecmapraw

        scfg = app.MapTableH.StyleConfigurations;
        n = size(scfg,1);

        [uc,~,ucidx] = unique(eleccolorraw);
        for k=1:length(uc)
            idx = (ucidx==k);
            rcidx = [row(idx),col(idx)];
            rcidx(any(isnan(rcidx),2),:) = [];
            set(app.ElecXYZProjRawH(idx),'color',colorlist(uc(k),:));
            if uc(k)>1 && ~isempty(rcidx)
                if all(colorlist(uc(k),:)==[0,0,0])
                    addStyle(app.MapTableH,uistyle('BackgroundColor',[0.5,0.5,0.5]),'cell',rcidx);
                else
                    addStyle(app.MapTableH,uistyle('BackgroundColor',colorlist(uc(k),:)),'cell',rcidx);
                end
            end
        end
        set(app.ElecXYZProjRawH(app.SelElec),'color',[1,0,0]); %this sets selected electrode as red (on 3d brain)

        if ~isempty(app.CurTableIdx)
            addStyle(app.MapTableH,uistyle('BackgroundColor',[1,0,0]),'cell',app.CurTableIdx);
        end
        if n>0 && ~isempty(scfg.Target)
            removeStyle(app.MapTableH,1:n);
        end

        app.applyCheckModeColors;
    end

    function lastChange(app, varargin)
        persistent selelec curtableidx curtableval sellabel...
            selchan1 selchan2 glm gcm1 gcm2 elecmapraw...
            depthelecraw microelecraw refelecraw gndelecraw

        undoFlag = false;
        if nargin>1
            undoFlag = varargin{1};
        end

        if undoFlag
            if ~app.LeGUI.ChangeDetected
                msgbox('No change detected!')
                return;
            end

            app.SelElec = selelec; %index into ElecXYZProjRaw for selected electrodes in 3d plot
            app.CurTableIdx = curtableidx; %row,col for selected cells in table
            app.CurTableVal = curtableval;
            app.SelLabel = sellabel;
            app.SelChan1 = selchan1;
            app.SelChan2 = selchan2;

            app.GLM = glm;
            app.GCM1 = gcm1;
            app.GCM2 = gcm2;

            app.LeGUI.ElecMapRaw = elecmapraw;
            app.LeGUI.DepthElecRaw = depthelecraw;
            app.LeGUI.MicroElecRaw = microelecraw;
            app.LeGUI.RefElecRaw = refelecraw;
            app.LeGUI.GndElecRaw = gndelecraw;

            switch app.MapTypePupH.Value
                case 'LabelMap'
                    set(app.MapTableH,'data',app.GLM)
                case 'ChannelMap1'
                    set(app.MapTableH,'data',app.GCM1)
                case 'ChannelMap2'
                    set(app.MapTableH,'data',app.GCM2)
            end
            app.GridSize = size(app.GLM);
            set(app.RowEditH,'value',num2str(app.GridSize(1)))
            set(app.ColEditH,'value',num2str(app.GridSize(2)))
            app.SelCntH.Value = length(app.SelElec);
            app.setElecCellColor;

        else %save previous values to persistent variables
            selelec = app.SelElec;
            curtableidx = app.CurTableIdx;
            curtableval = app.CurTableVal;
            sellabel = app.SelLabel;
            selchan1 = app.SelChan1;
            selchan2 = app.SelChan2;

            glm = app.GLM;
            gcm1 = app.GCM1;
            gcm2 = app.GCM2;

            elecmapraw = app.LeGUI.ElecMapRaw;
            depthelecraw = app.LeGUI.DepthElecRaw;
            microelecraw = app.LeGUI.MicroElecRaw;
            refelecraw = app.LeGUI.RefElecRaw;
            gndelecraw = app.LeGUI.GndElecRaw;

        end %undoFlag

    end %lastChange function


    function clearSelected(app, varargin)
    % Unassign selected contacts/cells without destroying
    % physical channel information.

    % Save table selection BEFORE clearing GUI state
    selectedTableIdx = app.CurTableIdx;

    % Contacts selected either in 3D or through table labels
    selectedElec = unique([ ...
        app.SelElec; ...
        app.findElecMapRawIdx(app.SelLabel)]);

    selectedElec(isnan(selectedElec)) = [];
    selectedElec(selectedElec < 1) = [];
    selectedElec(selectedElec > size(app.LeGUI.ElecXYZProjRaw,1)) = [];

    % Unassign LABEL only.
    % Preserve ChannelMap1 / ChannelMap2 values.
    if ~isempty(selectedElec)
        app.LeGUI.ElecMapRaw(selectedElec,1) = {'NaN'};
    end

    % Remove contact classifications
    app.LeGUI.DepthElecRaw( ...
        ismember(app.LeGUI.DepthElecRaw,selectedElec)) = [];

    app.LeGUI.MicroElecRaw( ...
        ismember(app.LeGUI.MicroElecRaw,selectedElec)) = [];

    app.LeGUI.RefElecRaw( ...
        ismember(app.LeGUI.RefElecRaw,selectedElec)) = [];

    app.LeGUI.GndElecRaw( ...
        ismember(app.LeGUI.GndElecRaw,selectedElec)) = [];

    % Clear selected cells from the TABLE
    if ~isempty(selectedTableIdx)

        idx = sub2ind(size(app.GLM), ...
            selectedTableIdx(:,1), ...
            selectedTableIdx(:,2));

        app.GLM(idx)  = {'NaN'};
        app.GCM1(idx) = {'NaN'};
        app.GCM2(idx) = {'NaN'};
    end

    % Clear GUI selection state only AFTER using it
    app.SelElec = [];
    app.CurTableIdx = [];
    app.updateSelTableVars();

    % Refresh visible table
    switch app.MapTypePupH.Value
        case 'LabelMap'
            app.MapTableH.Data = app.GLM;

        case 'ChannelMap1'
            app.MapTableH.Data = app.GCM1;

        case 'ChannelMap2'
            app.MapTableH.Data = app.GCM2;
    end

    app.syncAllAssignments();

    app.LeGUI.ChangeDetected = true;
    app.SelCntH.Value = 0;
    app.setElecCellColor();
end

    function updateSelTableVars(app, varargin)
        if isempty(app.CurTableIdx)
            app.CurTableVal = [];
            app.SelLabel = [];
            app.SelChan1 = [];
            app.SelChan2 = [];
        else
            Idx = sub2ind(size(app.GLM),app.CurTableIdx(:,1),app.CurTableIdx(:,2));
            app.SelLabel = app.GLM(Idx);
            app.SelChan1 = str2double(app.GCM1(Idx));
            app.SelChan2 = str2double(app.GCM2(Idx));
            app.CurTableVal = app.MapTableH.Data(Idx);
        end
    end


    function renameAutoName(app, out) %goes through each electrode and allows you to pick a new prefix
        oldNames = out.ShaftNames; %leadNames -> ShaftNames from AutoName file
        for s = 1:numel(oldNames)

            oldName = oldNames{s};

            % get contacts in this shaft (lead)
            shaftIdx = find(out.ShaftMembership == s);

            % highlight all contacts in shaft
            app.SelElec = shaftIdx(:);
            app.CurTableIdx = [];
            app.updateSelTableVars;
            app.SelCntH.Value = length(app.SelElec);
            app.setElecCellColor;
            drawnow;

            % Ask user for new shaft name
            answer = inputdlg( ...
                sprintf('What do you want to rename electrode %s to?', oldName), ...
                'Rename electrode', ...
                [1 50], ...
                {oldName});

            % If user cancels, keep old name
            if isempty(answer)
                newName = oldName;
            else
                newName = strtrim(answer{1});
                if isempty(newName)
                    newName = oldName;
                end
            end

            % Replace labels in LabelMap and ElecMapRaw
            for c = 1:numel(shaftIdx)
                oldLabel = sprintf('%s%d', oldName, c);
                newLabel = sprintf('%s%d', newName, c);

                app.GLM(strcmp(app.GLM, oldLabel)) = {newLabel};

                idxRaw = strcmp(app.LeGUI.ElecMapRaw(:,1), oldLabel);
                app.LeGUI.ElecMapRaw(idxRaw,1) = {newLabel};
            end
        end

        % Update table view after renaming
        app.MapTypePupH.Value = 'LabelMap';
        app.MapTableH.Data = app.GLM;

        app.SelElec = [];
        app.CurTableIdx = [];
        app.updateSelTableVars;
        app.SelCntH.Value = 0;
        app.setElecCellColor;
    end


    function applyCheckModeColors(app)

        if ~strcmp(app.TableModePupH.Value, 'Check')
            return;
        end

        nCols = size(app.GLM, 2);


        hues = linspace(0, 1, nCols + 1)';
        hues(end) = [];


        hues = mod(hues + 0.18, 1);

        colors = hsv2rgb([hues, 0.65*ones(nCols,1), 0.85*ones(nCols,1)]);


        redLike = colors(:,1) > 0.75 & colors(:,2) < 0.45 & colors(:,3) < 0.45;
        colors(redLike,:) = repmat([0.00 0.60 0.80], sum(redLike), 1);

        elecmapraw = app.LeGUI.ElecMapRaw;

        for c = 1:nCols

            thisColor = colors(c,:);

            colLabels = app.GLM(:,c);
            validCells = ~cellfun(@isempty,colLabels) & ~strcmpi(colLabels,'NaN');

            if any(validCells)
                rcidx = [find(validCells), repmat(c,sum(validCells),1)];

                addStyle(app.MapTableH, ...
                    uistyle('BackgroundColor', thisColor), ...
                    'cell', rcidx);
            end

            labelsThisCol = colLabels(validCells);
            idxElec = ismember(elecmapraw(:,1), labelsThisCol);

            if any(idxElec)
                set(app.ElecXYZProjRawH(idxElec), 'Color', thisColor);
            end
        end

        % selected contact/cell stays red on top
        if ~isempty(app.SelElec)
            set(app.ElecXYZProjRawH(app.SelElec), 'Color', [1 0 0]);
        end

        if ~isempty(app.CurTableIdx)
            addStyle(app.MapTableH, ...
                uistyle('BackgroundColor',[1 0 0]), ...
                'cell', app.CurTableIdx);
        end
    end


    function deleteSelectedColumn(app)

        if ~strcmp(app.TableModePupH.Value,'Edit')
            return;
        end

        if isempty(app.CurTableIdx)
            return;
        end

        colToDelete = app.CurTableIdx(1,2);

        answer = questdlg( ...
            sprintf('Delete column %d and unassign contacts?', colToDelete), ...
            'Delete Column', ...
            'Yes','No','No');

        if ~strcmp(answer,'Yes')
            return;
        end

        app.lastChange;

        % Find labels in this column
        labelsToDelete = app.GLM(:, colToDelete);
        labelsToDelete = labelsToDelete(~cellfun(@isempty, labelsToDelete));
        labelsToDelete = labelsToDelete(~strcmpi(labelsToDelete,'NaN'));

        % Unassign matching contacts
        if ~isempty(labelsToDelete)

            idxRaw = ismember(app.LeGUI.ElecMapRaw(:,1), labelsToDelete);
            rawIdx = find(idxRaw);

            app.LeGUI.ElecMapRaw(idxRaw,1) = {'NaN'};

            app.LeGUI.DepthElecRaw(ismember(app.LeGUI.DepthElecRaw, rawIdx)) = [];
            app.LeGUI.MicroElecRaw(ismember(app.LeGUI.MicroElecRaw, rawIdx)) = [];
            app.LeGUI.RefElecRaw(ismember(app.LeGUI.RefElecRaw, rawIdx)) = [];
            app.LeGUI.GndElecRaw(ismember(app.LeGUI.GndElecRaw, rawIdx)) = [];
        end

        % Delete column
        app.GLM(:,colToDelete) = [];
        app.GCM1(:,colToDelete) = [];
        app.GCM2(:,colToDelete) = [];
        % Keep AutoName shaft metadata aligned with table columns
        if numel(app.LeGUI.ShaftNames) >= colToDelete
            app.LeGUI.ShaftNames(colToDelete) = [];
        end

        if numel(app.LeGUI.ShaftModels) >= colToDelete
            app.LeGUI.ShaftModels(colToDelete) = [];
        end

        % Force membership to be rebuilt from the edited table
        app.LeGUI.ShaftMembership = [];
        app.LeGUI.ChangeDetected = true;

        % Update table
        app.GridSize = size(app.GLM);

        switch app.MapTypePupH.Value
            case 'LabelMap'
                app.MapTableH.Data = app.GLM;
            case 'ChannelMap1'
                app.MapTableH.Data = app.GCM1;
            case 'ChannelMap2'
                app.MapTableH.Data = app.GCM2;
        end

        app.ColEditH.Value = num2str(app.GridSize(2));

        app.CurTableIdx = [];
        app.syncAllAssignments();
        app.setElecCellColor();

    end

    function syncAllAssignments(app)


        if ~isequal(size(app.GLM), size(app.GCM1), size(app.GCM2))
            error(['LabelMap, ChannelMap1, and ChannelMap2 must have ' ...
                'identical dimensions.']);
        end

        % Every non-NaN label must be unique
        validLabels = app.GLM(~strcmpi(app.GLM, 'NaN'));

        if numel(unique(validLabels)) ~= numel(validLabels)
            error(['Duplicate labels exist in LabelMap. Each contact label ' ...
                'must occur exactly once.']);
        end

        channelMap1 = nan(size(app.GCM1));
        channelMap2 = nan(size(app.GCM2));

        for k = 1:numel(app.GCM1)
            channelMap1(k) = str2double(string(app.GCM1{k}));
        end

        for k = 1:numel(app.GCM2)
            channelMap2(k) = str2double(string(app.GCM2{k}));
        end

        app.LeGUI.LabelMap   = app.GLM;
        app.LeGUI.ChannelMap1 = channelMap1;
        app.LeGUI.ChannelMap2 = channelMap2;


        % Rebuild every physical contact from its current table label

        nContacts = size(app.LeGUI.ElecMapRaw,1);
        shaftMembership = nan(nContacts,1);

        for contactIdx = 1:nContacts

            contactLabel = app.LeGUI.ElecMapRaw{contactIdx,1};

            if isempty(contactLabel) || strcmpi(string(contactLabel), "NaN")
                % Preserve physical channel identity while contact is unassigned
                app.LeGUI.ElecMapRaw{contactIdx,1} = 'NaN';
                continue;
            end
            [row, col] = find(strcmp(app.GLM, contactLabel));

            if isempty(row)
                % Assignment was removed, but preserve physical channel identity
                app.LeGUI.ElecMapRaw{contactIdx,1} = 'NaN';
                continue;
            end

            if numel(row) ~= 1
                error('Label "%s" occurs more than once in LabelMap.', ...
                    char(string(contactLabel)));
            end

            app.LeGUI.ElecMapRaw(contactIdx,:) = { ...
                app.GLM{row,col}, ...
                channelMap1(row,col), ...
                channelMap2(row,col)};

            % Current table column is the definitive shaft identity
            shaftMembership(contactIdx) = col;
        end

        app.LeGUI.ShaftMembership = shaftMembership;
        app.LeGUI.ChangeDetected = true;
    end

    function createColorLegend(app)

        legendY = 50;

        % Assigned — blue
        dot = uilabel(app.MainFigure);
        dot.Text = '●';
        dot.FontSize = 18;
        dot.FontColor = [0 0 0];
        dot.Position = [425 legendY 20 22];

        lbl = uilabel(app.MainFigure);
        lbl.Text = 'Assigned';
        lbl.FontSize = 11;
        lbl.Position = [445 legendY 65 22];

        % Unassigned — black
        dot = uilabel(app.MainFigure);
        dot.Text = '●';
        dot.FontSize = 18;
        dot.FontColor = [0 0 1];
        dot.Position = [515 legendY 20 22];

        lbl = uilabel(app.MainFigure);
        lbl.Text = 'Unassigned';
        lbl.FontSize = 11;
        lbl.Position = [535 legendY 80 22];

        % Selected — red
        dot = uilabel(app.MainFigure);
        dot.Text = '●';
        dot.FontSize = 18;
        dot.FontColor = [1 0 0];
        dot.Position = [620 legendY 20 22];

        lbl = uilabel(app.MainFigure);
        lbl.Text = 'Selected';
        lbl.FontSize = 11;
        lbl.Position = [640 legendY 65 22];

end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            app.MainFigure.Visible = 'off';

            %             set(app.MainFigure,'Renderer','opengl');

            app.LeGUI = varargin{1}; %LeGUI object


            app.GridSize = size(app.LeGUI.LabelMap);

            set(app.MapTableH,'columneditable',true)
            set(app.TableModePupH,'value','Edit')

            app.GCM1 = reshape(strtrim(cellstr(num2str(app.LeGUI.ChannelMap1(:)))),size(app.LeGUI.ChannelMap1));
            app.GCM2 = reshape(strtrim(cellstr(num2str(app.LeGUI.ChannelMap2(:)))),size(app.LeGUI.ChannelMap2));
            app.GLM = app.LeGUI.LabelMap;

            % Initialize figure objects
            app.Alpha = 1;
            app.BrainAlphaDpdH.Value = '1.0';
            app.KeyType = ''; %current key press - set to empty when released
            app.SelectionType = ''; %normal (left mouse click), alt (right click), extend (scroll wheel click) - used for gui behavior
            app.HoverAx = false;
            app.PolyPnts = [];
            app.CurView3D = [115,10];

            app.SelElec = []; %index into ElecXYZProjRaw for selected electrodes in 3d plot
            app.CurTableIdx = []; %row,col for selected cells in table
            app.updateSelTableVars;

            app.BrainSurfRawH = patch(app.LeGUI.BrainSurfRaw,'parent',app.BrainAxH);
            set(app.BrainSurfRawH,'FaceAlpha',app.Alpha,'AlphaDataMapping','none','FaceColor',[0.5,0.5,0.5],'EdgeColor','none','FaceLighting','gouraud','AmbientStrength',0.3,'DiffuseStrength',0.8,'SpecularStrength',0.1,'SpecularExponent',10,'SpecularColorReflectance',1)

            hold(app.BrainAxH,'on')
            app.ElecXYZProjRawH = nan(size(app.LeGUI.ElecXYZProjRaw,1),1);
            for k=1:size(app.LeGUI.ElecXYZProjRaw,1)
                ElecMarkerSize = ceil((size(app.LeGUI.ElecFullIdxProjRaw{k},1)).^(1/3)*mean(app.LeGUI.XYZScale)*12);
                app.ElecXYZProjRawH(k) = plot3(app.BrainAxH,app.LeGUI.ElecXYZProjRaw(k,1),app.LeGUI.ElecXYZProjRaw(k,2),app.LeGUI.ElecXYZProjRaw(k,3),'.','markersize',ElecMarkerSize);
            end
            app.PolyH = plot3(app.BrainAxH,nan,nan,nan,'r'); %polygon for selecting electrodes in 3D plot

            view(app.BrainAxH,app.CurView3D)
            axis(app.BrainAxH,'vis3d','equal')
            camva(app.BrainAxH,10)
            app.LightH = light('parent',app.BrainAxH);
            camlight(app.LightH)
            set(app.BrainAxH,'xgrid','on','ygrid','on','zgrid','on')
            set(app.BrainAxH,'xcolor','k','ycolor','k','zcolor','k')
            set(app.BrainAxH,'xtickmode','auto','ytickmode','auto','ztickmode','auto')
            set(app.BrainAxH,'xticklabelmode','auto','yticklabelmode','auto','zticklabelmode','auto')
            xlabel(app.BrainAxH,'x'); ylabel(app.BrainAxH,'y'); zlabel(app.BrainAxH,'z');
            app.BrainAxLimits = [min([app.LeGUI.BrainSurfRaw.vertices;app.LeGUI.ElecXYZProjRaw]);max([app.LeGUI.BrainSurfRaw.vertices;app.LeGUI.ElecXYZProjRaw])];
            set(app.BrainAxH,'XLim',app.BrainAxLimits(:,1),'YLim',app.BrainAxLimits(:,2),'ZLim',app.BrainAxLimits(:,3));

            app.BrainAxH.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.BrainAxH)

            set(app.MapTableH,'data',app.GLM)
            set(app.RowEditH,'value',num2str(app.GridSize(1)))
            set(app.ColEditH,'value',num2str(app.GridSize(2)))

            app.SelCntH.Value = length(app.SelElec);
            app.setElecCellColor;

            % Right-click menu for deleting table columns
            app.DeleteColumnMenu = uicontextmenu(app.MainFigure);

            app.DeleteColumnMenuItem = uimenu(app.DeleteColumnMenu);
            app.DeleteColumnMenuItem.Text = 'Delete Column';
            app.DeleteColumnMenuItem.MenuSelectedFcn = @(src,event) app.deleteSelectedColumn();

            app.MapTableH.ContextMenu = app.DeleteColumnMenu;
            app.lastChange; %initialize persistent variables

            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.MainFigure.Position;
            if any(scrsize(3:4)<[1600,800])
                app.MainFigure.AutoResizeChildren = "off";
                app.MainFigure.Scrollable = "on";
                app.MainFigure.WindowState = 'maximized';
            else
                app.MainFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
            end
            app.createColorLegend();

            app.MainFigure.Visible = 'on';


        end

        % Button pushed function: ClearAllBtnH
        function ClearAllBtnFcn(app, event)
            if ~strcmp(questdlg('This will clear all assignments! Proceed?'),'Yes')
                return;
            end
            app.lastChange; %record current state before making changes
            app.clearAll;
        end

        % Button pushed function: ClearBtnH
        function ClearBtnFcn(app, event)
            if ~strcmp(app.TableModePupH.Value,'Check')
                msgbox('Change table mode to "Check" before clearing assignments!')
                return
            end
            if isempty(app.SelElec) && isempty(app.CurTableIdx)
                msgbox('Electrode or cell in table must be selected first!')
                return;
            end
            app.lastChange; %record current state before making changes
            app.clearSelected;
        end

        % Value changed function: TableModePupH
        function TableModePupFcn(app, event)
            if app.flagDuplicates
                set(app.TableModePupH,'value','Edit')
                set(app.MapTableH,'columneditable',true)
                msgbox('Duplicate values found! Remove duplicates before making assignments.');
            else
                switch event.Source.Value
                    case 'Edit'
                        set(app.MapTableH,'columneditable',true)
                        app.MapTableH.ContextMenu = app.DeleteColumnMenu;
                    case 'Check'
                        set(app.MapTableH,'columneditable',false)
                        app.SelElec = app.findElecMapRawIdx(app.SelLabel);
                        app.SelElec(isnan(app.SelElec)) = [];
                        app.SelCntH.Value = length(app.SelElec);
                        app.setElecCellColor;
                        app.MapTableH.ContextMenu = [];
                    otherwise
                        set(app.MapTableH,'columneditable',false)
                        app.MapTableH.ContextMenu = [];
                end
            end
        end

        % Button pushed function: InstructBtnH
        function InstructBtnFcn(app, event)
            msgcell = {...
                '*To create a new map, select "Edit" mode and specify #rows and #cols'
                '*Add rows/cols to match the # of electrodes'
                '*Design a 2D layout that approximates the 3D electrode positions in the brain'
                '*Place labels for leads/grids'
                '*Use "NaN" to add spaces between leads/grids'
                '*Press ctrl+up/down/left/right arrows to auto-increment a table value'
                '*Select "Assign" mode to assign table values to electrodes'
                '*Select electrode in 3D plot and corresponding label in table and click "Assign"'
                '*After assigning labels, switch table view to "ChannelMap1"'
                '*Continue by placing channel numbers that correspond to the previously assigned labels'
                '*Close AssignElectrodesGUI to populate LocateElectrodesGUI with assigned values'
                };
            LeG_msgbox(msgcell,'AssignElectrodesGUI');
        end

        % Value changed function: MapTypePupH
        function MapTypePupFcn(app, event)
            switch event.Source.Value
                case 'LabelMap' %basic anatomical labels based on lead location
                    set(app.MapTableH,'data',app.GLM)
                case 'ChannelMap1' %research mapping
                    set(app.MapTableH,'data',app.GCM1)
                case 'ChannelMap2' %clinical mapping
                    set(app.MapTableH,'data',app.GCM2)
            end
        end

        % Button pushed function: MarkGndBtnH
        function MarkGndBtnFcn(app, event)
            if ~strcmp(app.TableModePupH.Value,'Assign')
                msgbox('Change table mode to "Assign" before making an assignment!')
                return;
            end
            if isempty(app.SelElec)
                msgbox('Electrode must be selected first!')
                return;
            end
            if all(app.SelElec>=1) && all(app.SelElec<=size(app.LeGUI.ElecXYZProjRaw,1))
                app.lastChange; %record current state before making changes
                app.LeGUI.GndElecRaw = unique([app.LeGUI.GndElecRaw;app.SelElec]);
                app.LeGUI.ChangeDetected = true;
                app.SelElec = [];
                app.CurTableIdx = [];
                app.updateSelTableVars;
                app.SelCntH.Value = length(app.SelElec);
                app.setElecCellColor;
            end
        end

        % Button pushed function: MarkRefBtnH
        function MarkRefBtnFcn(app, event)
            if ~strcmp(app.TableModePupH.Value,'Assign')
                msgbox('Change table mode to "Assign" before making an assignment!')
                return;
            end
            if isempty(app.SelElec)
                msgbox('Electrode must be selected first!')
                return;
            end
            if all(app.SelElec>=1) && all(app.SelElec<=size(app.LeGUI.ElecXYZProjRaw,1))
                app.lastChange; %record current state before making changes
                app.LeGUI.RefElecRaw = unique([app.LeGUI.RefElecRaw;app.SelElec]);
                app.LeGUI.ChangeDetected = true;
                app.SelElec = [];
                app.CurTableIdx = [];
                app.updateSelTableVars;
                app.SelCntH.Value = length(app.SelElec);
                app.setElecCellColor;
            end
        end

        % Value changed function: ColEditH, RowEditH
        function RowColEditFcn(app, event)
            if strcmp(app.TableModePupH.Value,'Edit')
                app.lastChange; %record current state before making changes
                app.buildTable;
            else
                set(app.RowEditH,'value',num2str(app.GridSize(1)))
                set(app.ColEditH,'value',num2str(app.GridSize(2)))
            end
        end

        % Close request function: MainFigure
        function CloseRequestFcn(app, event)
            try
                if ~isempty(app.AutoNameApp) && isvalid(app.AutoNameApp)

                    if isvalid(app.AutoNameApp.AutoNameUIFigure)
                        delete(app.AutoNameApp.AutoNameUIFigure);
                    end

                    app.AutoNameApp = [];
                end
            catch
            end

            if ~isempty(app.LeGUI.ElecMapRaw)
                b1 = all(strcmp(app.LeGUI.ElecMapRaw(:,1),'NaN')); %LabelMap
                b2 = all(isnan([app.LeGUI.ElecMapRaw{:,2}])); %ChannelMap1
                b3 = all(isnan([app.LeGUI.ElecMapRaw{:,3}])); %ChannelMap2
                if (b1 && b2 && b3) %Do LabelMap or ChannelMap1/2 have any values assigned?
                    if strcmp(questdlg('No assignments were made! Proceed?'),'No')
                        return;
                    end
                end
            end
            app.syncAllAssignments();
            app.LeGUI.assignElecsReturn;
            delete(app);
        end

        % Window button down function: MainFigure
        function WindowButtonDownFcn(app, event)
            app.SelectionType = get(event.Source,'selectiontype'); %normal (left click), alt (right click), extend (scroll wheel click)
            app.ClickPntFig = get(event.Source,'currentpoint'); %initial click location for drag brightness/contrast
            app.ClickPntBrainAx = get(app.BrainAxH,'CurrentPoint');
            [app.CurView3D(1),app.CurView3D(2)] = view(app.BrainAxH);

            h = hittest(app.MainFigure);
            if isempty(h) || ~isequal(ancestor(h,'matlab.ui.control.UIAxes'), app.BrainAxH)
                return;  % click was on a UI control (e.g., Assign), not the brain axes
            end

            if isempty(event.Source.CurrentObject)
                return
            end
            cp = get(app.BrainAxH,'currentpoint');

            xlimit = get(app.BrainAxH,'xlim');
            ylimit = get(app.BrainAxH,'ylim');
            zlimit = get(app.BrainAxH,'ylim');

            b1 = any(cp(:,1)>=xlimit(1)&cp(:,1)<=xlimit(2));
            b2 = any(cp(:,2)>=ylimit(1)&cp(:,2)<=ylimit(2));
            b3 = any(cp(:,3)>=zlimit(1)&cp(:,3)<=zlimit(2));

            if (b1 && b2 && b3) %clicked inside brain axis
                if app.Rotate3DH.Value || app.Select3DH.Value || app.Pan3DH.Value %don't select or draw if zoom/pan/rotate/select are enabled
                    return;
                end
                x0 = app.LeGUI.ElecXYZProjRaw;
                midx = [];
                if ~isempty(x0)
                    x1 = repmat(cp(1,:),size(x0,1),1);
                    x2 = repmat(cp(2,:),size(x0,1),1);
                    d = sqrt(sum(cross((x0-x1),(x0-x2),2).^2,2))./sqrt(sum((x2-x1).^2,2)); %distance from each electrode to line perpendicular to screen at click point (http://mathworld.wolfram.com/Point-LineDistance3-Dimensional.html)
                    [midx,idx] = min(d);
                end
                if app.Zoom3DH.Value
                    if midx<10
                        pnt = x0(idx,:);
                    else
                        pnt = mean(app.ClickPntBrainAx);
                    end
                    curlim = [app.BrainAxH.XLim,app.BrainAxH.YLim,app.BrainAxH.ZLim];
                    switch app.KeyType
                        case 'shift'
                            newlim = matlab.graphics.interaction.internal.zoom.zoomAroundPoint3D(curlim, pnt, 0.8); %zoomfactor 1.2
                        otherwise
                            newlim = matlab.graphics.interaction.internal.zoom.zoomAroundPoint3D(curlim, pnt, 1.2); %zoomfactor 1.2
                    end
                    set(app.BrainAxH,'xlim',newlim(1:2),'ylim',newlim(3:4),'zlim',newlim(5:6))
                else
                    if midx<10 %if electrode found
                        switch app.KeyType
                            case 'shift'
                                if ~any(idx==app.SelElec)
                                    app.SelElec = [app.SelElec;idx];
                                end
                            otherwise
                                app.SelElec = idx;
                        end
                    else
                        app.SelElec = [];
                    end
                    if strcmp(app.TableModePupH.Value,'Check')
                        [row,col] = findTableRowCol(app,app.LeGUI.ElecMapRaw(app.SelElec,1));
                        app.CurTableIdx = [row,col];
                        app.CurTableIdx(any(isnan(app.CurTableIdx),2),:) = [];
                        app.updateSelTableVars;
                    end
                    app.setElecCellColor;
                end
                app.SelCntH.Value = length(app.SelElec);
            end %clicked inside BrainAx
        end

        % Window key press function: MainFigure
        function WindowKeyPressFcn(app, event)
            app.KeyType = event.Key;
            switch event.Key
                %                 case 'a' %assign selected electrodes to channels in table
                %                     app.assignSelected;
                case {'leftarrow','rightarrow'}
                    if app.Rotate3DH.Value && ~strcmp(event.Source.CurrentObject.Tag,'MapTable')
                        [az,el] = view(app.BrainAxH);
                        switch event.Key
                            case 'leftarrow'
                                az = az + 1;
                            case 'rightarrow'
                                az = az - 1;
                        end
                        view(app.BrainAxH,[az,el])
                        app.CurView3D = [az,el];
                    end
                case {'uparrow','downarrow'}
                    if app.Rotate3DH.Value && ~strcmp(event.Source.CurrentObject.Tag,'MapTable')
                        [az,el] = view(app.BrainAxH);
                        switch event.Key
                            case 'uparrow'
                                el = el - 1;
                            case 'downarrow'
                                el = el + 1;
                        end
                        view(app.BrainAxH,[az,el])
                        app.CurView3D = [az,el];
                    end
                case {'o','z','r','s','p'}
                    CurAx = event.Source.CurrentAxes;
                    if strcmp(CurAx.Tag,'BrainAx') && ~strcmp(event.Source.CurrentObject.Tag,'MapTable')
                        switch event.Key
                            case 'z' %zoom
                                if app.Zoom3DH.Value
                                    app.Zoom3DH.Value = false;
                                else
                                    app.Zoom3DH.Value = true;
                                    app.Rotate3DH.Value = false;
                                    app.Select3DH.Value = false;
                                    app.Pan3DH.Value = false;
                                end
                            case 'o' %orbit or rotate
                                if app.Rotate3DH.Value
                                    app.Rotate3DH.Value = false;
                                else
                                    app.Rotate3DH.Value = true;
                                    app.Zoom3DH.Value = false;
                                    app.Select3DH.Value = false;
                                    app.Pan3DH.Value = false;
                                end
                            case 'r' %restore view
                                d.Axes = CurAx;
                                restoreView(app,[],d)
                            case 's' %polygon select
                                if app.Select3DH.Value
                                    app.Select3DH.Value = false;
                                else
                                    app.Select3DH.Value = true;
                                    app.Rotate3DH.Value = false;
                                    app.Zoom3DH.Value = false;
                                    app.Pan3DH.Value = false;
                                end
                            case 'p' %pan
                                if app.Pan3DH.Value
                                    app.Pan3DH.Value = false;
                                else
                                    app.Pan3DH.Value = true;
                                    app.Rotate3DH.Value = false;
                                    app.Zoom3DH.Value = false;
                                    app.Select3DH.Value = false;
                                end
                        end
                    end
            end
            if strcmp(app.TableModePupH.Value,'Edit')
                app.lastChange; %record current state before making changes
                app.autoFill(event); %automatically fills in col/row of table with incremented values (ctrl+up/dwn/left/right)
                app.LeGUI.ChangeDetected = true;
            end
        end

        % Cell edit callback: MapTableH
        function CellEditFcn(app, event)
            if strcmp(app.TableModePupH.Value,'Edit')
                app.lastChange; %record current state before making changes
                app.updateCell(app,event);
                app.LeGUI.ChangeDetected = true;
            end
        end

        % Window key release function: MainFigure
        function WindowKeyRelease(app, event)
            app.KeyType = '';
        end

        % Cell selection callback: MapTableH
        function CellSelectionFcn(app, event)
            app.CurTableIdx = event.Indices;
            app.updateSelTableVars;

            if strcmp(app.TableModePupH.Value,'Check')
                app.SelElec = app.findElecMapRawIdx(app.SelLabel);
                app.SelElec(isnan(app.SelElec)) = [];
                app.SelCntH.Value = length(app.SelElec);
            end
            app.setElecCellColor;
        end

        % Window button up function: MainFigure
        function WindowButtonUp(app, event)
            app.SelectionType = '';
            if strcmp(event.Source.CurrentAxes.Tag,'BrainAx') && app.Rotate3DH.Value
                app.stopOrbit;
            end
            %select electrodes inside polygon
            if ~isempty(app.PolyPnts)
                pos1 = get(app.BrainAxH,'cameraposition');
                pos2 = get(app.BrainAxH,'cameratarget');
                pos3 = pos1-pos2;

                R = LeG_genRotMat(pos3,[0,0,1]);

                rPoly = app.PolyPnts*R(1:3,1:3)';
                rElec = app.LeGUI.ElecXYZProjRaw*R(1:3,1:3)';

                b = inpolygon(rElec(:,1),rElec(:,2),rPoly(:,1),rPoly(:,2));
                selelec = reshape(find(b),[],1);

                % Nothing inside polygon
                if isempty(selelec)
                    app.SelElec = [];
                    app.SelCntH.Value = 0;

                    % Remove selection polygon
                    set(app.PolyH,'xdata',nan,'ydata',nan,'zdata',nan);
                    app.PolyPnts = [];

                    app.setElecCellColor;
                    return;
                end

                xyz = app.LeGUI.ElecXYZProjRaw(selelec,:);

                idx = [];
                [~,idx(:,1)] = sort(sqrt(sum(xyz.^2,2)),'descend'); %sort based on distance from center (high to low)
                [~,idx(:,2)] = sort(xyz(:,1),'ascend'); 
                [~,idx(:,3)] = sort(xyz(:,2),'descend'); 
                [~,idx(:,4)] = sort(xyz(:,3),'descend'); 

                md = nan(4,1);
                for k=1:4
                    md(k) = mean(sqrt(sum(diff(xyz(idx(:,k),:)).^2,2))); %average distance between adjacent electrodes
                end

                [~,midx] = min(md); %minimum distance between adjacent electrodes

                %reorder selected electrodes based on min adjacent distance so assignment to selected labels in table will match (assuming a column is selected in table)
                sxyz = xyz(idx(:,midx),:); %xyz of sorted electrodes
                sdist = [sqrt(sum(sxyz.^2,2)),sxyz(:,3)]; %distance from center or z-value of sorted electrode

            
                [~,mxidx] = max(abs(sdist(end,:)-sdist(1,:))); %which one has the largest difference between 1st and last electrode relative to center or z-value
                if sdist(end,mxidx)>sdist(1,mxidx) %if the last electrode has a larger value, flip to get the correct orientation
                    app.SelElec = selelec(flipud(idx(:,midx))); %flip so that 1st electrode is either the farthest from center or is the most superior (largest z)
                else
                    app.SelElec = selelec(idx(:,midx));
                end

                if strcmp(app.TableModePupH.Value,'Check')
                    [row,col] = findTableRowCol(app,app.LeGUI.ElecMapRaw(app.SelElec,1)); %this can return nans
                    app.CurTableIdx = [row,col];
                    app.CurTableIdx(any(isnan(app.CurTableIdx),2),:) = [];
                    app.updateSelTableVars;
                end
                app.SelCntH.Value = length(app.SelElec);
                app.setElecCellColor;
            end
            set(app.PolyH,'xdata',nan,'ydata',nan,'zdata',nan) %reset polygon
            app.PolyPnts = []; %clear polygon points
        end

        % Button pushed function: AssignBtnH
        function AssignBtnFcn(app, event)
            app.assignSelected;
        end

        % Button pushed function: Restore3DH
        function Restore3DFcn(app, event)
            d.Axes = app.BrainAxH;
            app.restoreView([],d);
        end

        % Value changed function: Pan3DH, Rotate3DH, Select3DH, Zoom3DH
        function Toolbar3DFcn(app, event)
            app.MainFigure.CurrentAxes = app.BrainAxH;
            switch event.Source.Tag
                case 'Zoom3D'
                    if event.Value
                        app.Rotate3DH.Value = false;
                        app.Select3DH.Value = false;
                        app.Pan3DH.Value = false;
                    end
                case 'Rotate3D'
                    if event.Value
                        app.Zoom3DH.Value = false;
                        app.Select3DH.Value = false;
                        app.Pan3DH.Value = false;
                    end
                case 'Select3D'
                    if event.Value
                        app.Zoom3DH.Value = false;
                        app.Rotate3DH.Value = false;
                        app.Pan3DH.Value = false;
                    end
                case 'Pan3D'
                    if event.Value
                        app.Zoom3DH.Value = false;
                        app.Rotate3DH.Value = false;
                        app.Select3DH.Value = false;
                    end
            end
        end

        % Window button motion function: MainFigure
        function WindowButtonMotionFcn(app, event)
            ap = mean(get(app.BrainAxH,'currentpoint'));
            axSize = [get(app.BrainAxH,'xlim'),get(app.BrainAxH,'ylim'),get(app.BrainAxH,'zlim')];
            app.HoverAx = (ap(:,1)>=axSize(:,1) & ap(:,1)<=axSize(:,2)) & (ap(:,2)>=axSize(:,3) & ap(:,2)<=axSize(:,4)) & (ap(:,3)>=axSize(:,5) & ap(:,3)<=axSize(:,6)); %true when mouse hovers over BrainAx

            %mouse cursor
            if app.HoverAx && (app.Zoom3DH.Value || app.Rotate3DH.Value || app.Select3DH.Value || app.Pan3DH.Value)
                if strcmp(event.Source.Pointer,'arrow')
                    event.Source.Pointer = 'hand';
                end
            else
                if ~strcmp(event.Source.Pointer,'arrow')
                    event.Source.Pointer = 'arrow';
                end
            end

            %polygon 3D
            if app.HoverAx && app.Select3DH.Value
                if strcmp(app.SelectionType,'normal') || strcmp(app.SelectionType,'extend') %drag condition
                    cp = app.BrainAxH.CurrentPoint;
                    app.PolyPnts = [app.PolyPnts;mean(cp)];
                    set(app.PolyH,'xdata',app.PolyPnts(:,1),'ydata',app.PolyPnts(:,2),'zdata',app.PolyPnts(:,3)) %draw polygon
                end
            end

            %rotate 3D
            if app.HoverAx && app.Rotate3DH.Value
                if strcmp(app.SelectionType,'normal') || strcmp(app.SelectionType,'extend') %drag condition
                    cp = get(app.MainFigure,'currentpoint');
                    curview3d = app.CurView3D - (cp-app.ClickPntFig)*0.3;
                    view(app.BrainAxH,curview3d);
                end
            end

            %pan 3D
            if app.HoverAx && app.Pan3DH.Value
                if strcmp(app.SelectionType,'normal') || strcmp(app.SelectionType,'extend') %drag condition
                    cp = mean(app.BrainAxH.CurrentPoint); %current point
                    sp = mean(app.ClickPntBrainAx); %starting point
                    pts = [cp;sp];
                    dpt = diff(pts);
                    set(app.BrainAxH,'XLim',app.BrainAxH.XLim + dpt(1),'YLim',app.BrainAxH.YLim + dpt(2),'ZLim',app.BrainAxH.ZLim + dpt(3));
                end
            end

        end

        % Value changed function: BrainAlphaDpdH
        function BrainAlphaDpdFcn(app, event)
            app.Alpha = str2double(get(app.BrainAlphaDpdH,'value')); app.Alpha(app.Alpha<0) = 0; app.Alpha(app.Alpha>1) = 1;
            if app.Alpha>0
                camlight(app.LightH)
                set(app.BrainSurfRawH,'FaceAlpha',app.Alpha,'visible','on')
            else
                set(app.BrainSurfRawH,'visible','off')
            end
        end

        % Button pushed function: UndoBtnH
        function UndoBtnFcn(app, event)
            app.lastChange(true);
        end

        % Button pushed function: AutoNameBtnH
        function AutoNameBtnHButtonPushed(app, event)

            try
                app.AutoNameBtnH.Enable = 'off';
            catch
                return;
            end

            try
                app.lastChange;

                % Use current edited Assign state whenever valid data exist
                % Determine whether AutoName has already been applied successfully.
                nContacts = size(app.LeGUI.ElecMapRaw, 1);

%                 hasValidMembership = ...
%                     ~isempty(app.LeGUI.ShaftMembership) && ...
%                     numel(app.LeGUI.ShaftMembership) == nContacts && ...
%                     any(isfinite(app.LeGUI.ShaftMembership));
% 
%                 hasValidNames = ...
%                     ~isempty(app.LeGUI.ShaftNames) && ...
%                     any(~cellfun(@isempty, app.LeGUI.ShaftNames));
% 
%                 hasValidModels = ...
%                     ~isempty(app.LeGUI.ShaftModels) && ...
%                     numel(app.LeGUI.ShaftModels) == numel(app.LeGUI.ShaftNames);
% 
%                 hasAssignedLabels = ...
%                     ~isempty(app.LeGUI.ElecMapRaw) && ...
%                     any(~strcmp(app.LeGUI.ElecMapRaw(:,1), 'NaN'));

                nContacts = size(app.LeGUI.ElecMapRaw,1);

                hasExistingLabels = ...
                    ~isempty(app.LeGUI.ElecMapRaw) && ...
                    any(~strcmp(app.LeGUI.ElecMapRaw(:,1),'NaN'));

                hasExistingNames = ...
                    ~isempty(app.LeGUI.ShaftNames) && ...
                    any(~cellfun(@isempty,app.LeGUI.ShaftNames));

                hasPreviousAutoName = ...
                    hasExistingLabels || hasExistingNames;

                if hasPreviousAutoName
                    out = app.buildCurrentAutoNameOut();
                else
                    out = LeG_autoNameElecs(app.LeGUI);
                end

                %                 if hasCurrentState
                %                     % AutoName has already been applied.
                %                     % Reopen using the current edited Assign GUI state.
                %                     out = app.buildCurrentAutoNameOut();
                %                 else
%                     % First AutoName opening.
%                     % Run the original automatic shaft detection.
%                     out = LeG_autoNameElecs(app.LeGUI);
%                 end

                app.AutoNameApp = LeG_label(app, out);
                uiwait(app.AutoNameApp.AutoNameUIFigure);

            catch ME
                try
                    if isvalid(app) && isvalid(app.AutoNameBtnH)
                        app.AutoNameBtnH.Enable = 'on';
                    end
                catch
                end
                rethrow(ME)
            end

            try
                if isvalid(app) && isvalid(app.AutoNameBtnH)
                    app.AutoNameBtnH.Enable = 'on';
                end
            catch
            end


        end

        % Button pushed function: Export3DH
        function Export3DHButtonPushed(app, event)
            rootdir = LeG_lastDir('Select destination for image file...');

            if all(rootdir==0)
                return;
            end

            % Use patient information from the parent LeGUI
            namestr = regexp(app.LeGUI.PatientIDStr,'^[A-Za-z]+','match','once');
            mrnstr = cell2mat(regexp( ...
                app.LeGUI.PatientIDStr, ...
                '\((\d+)\)', ...
                'tokens', ...
                'once'));

            viewstr = regexprep(num2str(app.CurView3D),'\s+','_');

            elecstr = 'NaN';

            if ~isempty(app.SelElec)
                elecstr = regexprep( ...
                    app.LeGUI.ElecMapRaw{app.SelElec(end),1}, ...
                    '\d+$', ...
                    '');
            end

            fname = fullfile( ...
                rootdir, ...
                [namestr,'_',mrnstr,'_3D_',elecstr,'_',viewstr,'.png']);

            exportgraphics(app.BrainAxH,fname,'Resolution',300);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'AssignElectrodesGUI';
            app.MainFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.MainFigure.WindowButtonDownFcn = createCallbackFcn(app, @WindowButtonDownFcn, true);
            app.MainFigure.WindowButtonUpFcn = createCallbackFcn(app, @WindowButtonUp, true);
            app.MainFigure.WindowButtonMotionFcn = createCallbackFcn(app, @WindowButtonMotionFcn, true);
            app.MainFigure.WindowKeyPressFcn = createCallbackFcn(app, @WindowKeyPressFcn, true);
            app.MainFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @WindowKeyRelease, true);
            app.MainFigure.Tag = 'MainFigure';
            app.BrainAxH = uiaxes(app.MainFigure);
            app.BrainAxH.XTick = [];
            app.BrainAxH.YTick = [];
            app.BrainAxH.Tag = 'BrainAx';
            colormap(app.BrainAxH, 'parula')
            app.MapTableH = uitable(app.MainFigure);
            app.MapTableH.CellEditCallback = createCallbackFcn(app, @CellEditFcn, true);
            app.MapTableH.CellSelectionCallback = createCallbackFcn(app, @CellSelectionFcn, true);
            app.MapTableH.Tag = 'MapTable';
            app.InstructBtnH = uibutton(app.MainFigure, 'push');
            app.InstructBtnH.ButtonPushedFcn = createCallbackFcn(app, @InstructBtnFcn, true);
            app.InstructBtnH.Tag = 'InstructBtn';
            app.InstructBtnH.Text = 'Instructions';
            app.TableControls = uipanel(app.MainFigure);
            app.TableControls.Title = 'Table Controls';
            app.RowEditH = uieditfield(app.TableControls, 'text');
            app.RowEditH.ValueChangedFcn = createCallbackFcn(app, @RowColEditFcn, true);
            app.RowEditH.Tag = 'RowEdit';
            app.RowEditH.Tooltip = {'# of rows'};
            app.RowEditH.Value = '#Rows';
            app.ColEditH = uieditfield(app.TableControls, 'text');
            app.ColEditH.ValueChangedFcn = createCallbackFcn(app, @RowColEditFcn, true);
            app.ColEditH.Tag = 'ColEdit';
            app.ColEditH.Tooltip = {'# of columns'};
            app.ColEditH.Value = '#Cols';
            app.MapTypePupH = uidropdown(app.TableControls);
            app.MapTypePupH.Items = {'LabelMap', 'ChannelMap1', 'ChannelMap2'};
            app.MapTypePupH.ValueChangedFcn = createCallbackFcn(app, @MapTypePupFcn, true);
            app.MapTypePupH.Tag = 'MapTypePup';
            app.MapTypePupH.Tooltip = {'Table view showing assigned electrode labels or channels'};
            app.MapTypePupH.Value = 'LabelMap';
            app.TableModePupH = uidropdown(app.TableControls);
            app.TableModePupH.Items = {'Edit', 'Assign', 'Check'};
            app.TableModePupH.ValueChangedFcn = createCallbackFcn(app, @TableModePupFcn, true);
            app.TableModePupH.Tag = 'MapTypePup';
            app.TableModePupH.Tooltip = {'Table mode for editing cell contents, assigning cells to electrodes, or checking assignments'};
            app.TableModePupH.Value = 'Edit';
            app.ModeLabel = uilabel(app.TableControls);
            app.ModeLabel.Text = 'Mode';
            app.RowsLabel = uilabel(app.TableControls);
            app.RowsLabel.Text = '#Rows';
            app.ColsLabel = uilabel(app.TableControls);
            app.ColsLabel.Text = '#Cols';
            app.MapTypeLabel = uilabel(app.TableControls);
            app.MapTypeLabel.Text = 'Map Type';
            app.UndoBtnH = uibutton(app.MainFigure, 'push');
            app.UndoBtnH.ButtonPushedFcn = createCallbackFcn(app, @UndoBtnFcn, true);
            app.UndoBtnH.Tag = 'UndoBtn';
            app.UndoBtnH.Text = 'Undo';
            app.AlphaPanel = uipanel(app.MainFigure);
            app.BtnPanel = uipanel(app.MainFigure);
            app.ClearAllBtnH = uibutton(app.BtnPanel, 'push');
            app.ClearAllBtnH.ButtonPushedFcn = createCallbackFcn(app, @ClearAllBtnFcn, true);
            app.ClearAllBtnH.Tag = 'ClearAllBtn';
            app.ClearAllBtnH.Text = 'Clear All';
            app.ClearBtnH = uibutton(app.BtnPanel, 'push');
            app.ClearBtnH.ButtonPushedFcn = createCallbackFcn(app, @ClearBtnFcn, true);
            app.ClearBtnH.Tag = 'ClearBtn';
            app.ClearBtnH.Tooltip = {'Remove assignments from the current selection'};
            app.ClearBtnH.Text = 'Clear Selection';
            app.AssignBtnH = uibutton(app.BtnPanel, 'push');
            app.AssignBtnH.ButtonPushedFcn = createCallbackFcn(app, @AssignBtnFcn, true);
            app.AssignBtnH.Tag = 'AssignBtn';
            app.AssignBtnH.Text = 'Assign';
            app.MarkRefBtnH = uibutton(app.BtnPanel, 'push');
            app.MarkRefBtnH.ButtonPushedFcn = createCallbackFcn(app, @MarkRefBtnFcn, true);
            app.MarkRefBtnH.Tag = 'MarkRefBtn';
            app.MarkRefBtnH.Text = 'Mark Ref';
            app.MarkGndBtnH = uibutton(app.BtnPanel, 'push');
            app.MarkGndBtnH.ButtonPushedFcn = createCallbackFcn(app, @MarkGndBtnFcn, true);
            app.MarkGndBtnH.Tag = 'MarkGndBtn';
            app.MarkGndBtnH.Text = 'Mark Gnd';
            app.MicroCkbH = uicheckbox(app.BtnPanel);
            app.MicroCkbH.Tag = 'MicroCkb';
            app.MicroCkbH.Text = 'Micro';
            app.DepthCkbH = uicheckbox(app.BtnPanel);
            app.DepthCkbH.Tag = 'DepthCkb';
            app.DepthCkbH.Text = 'Depth';
            app.DepthCkbH.Value = true;
            app.SelCntH = uieditfield(app.MainFigure, 'numeric');
            app.SelCntH.RoundFractionalValues = 'on';
            app.SelCntH.ValueDisplayFormat = '%0.0f';
            app.SelCntH.Tag = 'SelCnt';
            app.SelCntH.Editable = 'off';
            app.SelCntH.Tooltip = {'# selected electrodes'};
            app.AutoNameBtnH = uibutton(app.MainFigure, 'push');
            app.AutoNameBtnH.ButtonPushedFcn = createCallbackFcn(app, @AutoNameBtnHButtonPushed, true);
            app.AutoNameBtnH.Text = 'Auto Name';
            app.BrainAlphaDpdH = uidropdown(app.MainFigure);
            app.BrainAlphaDpdH.Items = {'1.0', '0.5', '0.2', '0.1', '0.0'};
            app.BrainAlphaDpdH.ValueChangedFcn = createCallbackFcn(app, @BrainAlphaDpdFcn, true);
            app.BrainAlphaDpdH.Tag = 'BrainAlphaDpd';
            app.BrainAlphaDpdH.Tooltip = {'Visibility (alpha) of brain surface'};
            app.BrainAlphaDpdH.Value = '1.0';
            app.BrainVisLabel = uilabel(app.MainFigure);
            app.BrainVisLabel.Tag = 'BrainAlpha';
            app.BrainVisLabel.Text = 'Brain Vis';
            app.Toolbar3DPanel = uipanel(app.MainFigure);
            app.Zoom3DH = uibutton(app.Toolbar3DPanel, 'state');
            app.Zoom3DH.ValueChangedFcn = createCallbackFcn(app, @Toolbar3DFcn, true);
            app.Zoom3DH.Tag = 'Zoom3D';
            app.Zoom3DH.Tooltip = {'Zoom In (z)'};
            app.Zoom3DH.Icon = 'zoomin_icon.png';
            app.Zoom3DH.Text = '';
            app.Rotate3DH = uibutton(app.Toolbar3DPanel, 'state');
            app.Rotate3DH.ValueChangedFcn = createCallbackFcn(app, @Toolbar3DFcn, true);
            app.Rotate3DH.Tag = 'Rotate3D';
            app.Rotate3DH.Tooltip = {'Rotate 3D (o)'};
            app.Rotate3DH.Icon = 'rotate_icon.png';
            app.Rotate3DH.Text = '';
            app.Export3DH = uibutton(app.Toolbar3DPanel, 'push');
            app.Export3DH.ButtonPushedFcn = createCallbackFcn(app, @Export3DHButtonPushed, true);
            app.Export3DH.Tag = 'Export3D';
            app.Export3DH.Icon = 'export_icon.png';
            app.Export3DH.Tooltip = {'Export PNG'};
            app.Export3DH.Text = '';
            app.Restore3DH = uibutton(app.Toolbar3DPanel, 'push');
            app.Restore3DH.ButtonPushedFcn = createCallbackFcn(app, @Restore3DFcn, true);
            app.Restore3DH.Tag = 'Restore3D';
            app.Restore3DH.Icon = 'restoreview_icon.png';
            app.Restore3DH.Tooltip = {'Restore View (r)'};
            app.Restore3DH.Text = '';
            app.Select3DH = uibutton(app.Toolbar3DPanel, 'state');
            app.Select3DH.ValueChangedFcn = createCallbackFcn(app, @Toolbar3DFcn, true);
            app.Select3DH.Tag = 'Select3D';
            app.Select3DH.Tooltip = {'Polygon selection (s)'};
            app.Select3DH.Icon = 'pointer_crosshairs2.png';
            app.Select3DH.Text = '';
            app.Pan3DH = uibutton(app.Toolbar3DPanel, 'state');
            app.Pan3DH.ValueChangedFcn = createCallbackFcn(app, @Toolbar3DFcn, true);
            app.Pan3DH.Tag = 'Pan3D';
            app.Pan3DH.Tooltip = {'Pan (p)'};
            app.Pan3DH.Icon = 'pan_icon.png';
            app.Pan3DH.Text = '';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_AssignElectrodesGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end