classdef LeG_AtlasMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProbabilityRadiusEditField  matlab.ui.control.EditField
        ProbabilityRadiusEditFieldLabel  matlab.ui.control.Label
        SaveProbabilitiesCheckBox   matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        LeGUI 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            if nargin>1
                app.LeGUI = varargin{1}; %LeGUI object
            end  

            if ~isempty(app.LeGUI)
                if ~isfield(app.LeGUI.atlas,'probradius') || isempty(app.LeGUI.atlas.probradius)
                    app.LeGUI.atlas.probradius = 10;
                end
                if ~isfield(app.LeGUI.atlas,'saveprobabilities') || isempty(app.LeGUI.atlas.saveprobabilities)
                    app.LeGUI.atlas.saveprobabilities = true;
                end
                app.ProbabilityRadiusEditField.Value = num2str(app.LeGUI.atlas.probradius);
                app.SaveProbabilitiesCheckBox.Value = logical(app.LeGUI.atlas.saveprobabilities);
            end
            
            app.UIFigure.Name = 'Atlas Parameters';

            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.UIFigure.Position;
            app.UIFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end

        % Value changed function: ProbabilityRadiusEditField
        function ProbabilityRadiusEditFieldFcn(app, event)
            app.LeGUI.atlas.probradius = str2double(app.ProbabilityRadiusEditField.Value);
        end

        % Value changed function: SaveProbabilitiesCheckBox
        function SaveProbabilitiesCheckBoxFcn(app, event)
            app.LeGUI.atlas.saveprobabilities = app.SaveProbabilitiesCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.ProbabilityRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.ProbabilityRadiusEditFieldLabel.Text = 'Probability Radius';
            app.ProbabilityRadiusEditField = uieditfield(app.UIFigure, 'text');
            app.ProbabilityRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @ProbabilityRadiusEditFieldFcn, true);
            app.ProbabilityRadiusEditField.Tooltip = {'Radius of sphere in mm for atlas probability calculation.'};
            app.ProbabilityRadiusEditField.Value = '10';
            app.SaveProbabilitiesCheckBox = uicheckbox(app.UIFigure);
            app.SaveProbabilitiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveProbabilitiesCheckBoxFcn, true);
            app.SaveProbabilitiesCheckBox.Text = 'Save atlas probabilities';
            app.SaveProbabilitiesCheckBox.Tooltip = {'When disabled, Save skips atlas probability calculations to speed up saving.'};
            app.SaveProbabilitiesCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_AtlasMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end