classdef LeG_CoregMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CostDropDown        matlab.ui.control.DropDown
        SepEditField        matlab.ui.control.EditField
        TolEditField        matlab.ui.control.EditField
        FWHMEditFieldLabel  matlab.ui.control.Label
        FWHMEditField       matlab.ui.control.EditField
        TolEditFieldLabel   matlab.ui.control.Label
        SepEditFieldLabel   matlab.ui.control.Label
        CostDropDownLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        LeGUI 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            if nargin>1
                app.LeGUI = varargin{1}; %LeGUI object
            end  

            if ~isempty(app.LeGUI)
                app.CostDropDown.Value = app.LeGUI.coreg.cost_fun;
                app.SepEditField.Value = regexprep(num2str(app.LeGUI.coreg.sep),'\s+',',');
                app.TolEditField.Value = regexprep(num2str(app.LeGUI.coreg.tol),'\s+',',');
                app.FWHMEditField.Value = regexprep(num2str(app.LeGUI.coreg.fwhm),'\s+',',');
            end
            
            app.UIFigure.Name = 'Coregistration Parameters';

            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.UIFigure.Position;
            app.UIFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end

        % Value changed function: CostDropDown
        function CostDropDownFcn(app, event)
            app.LeGUI.coreg.cost_fun = app.CostDropDown.Value;
        end

        % Value changed function: SepEditField
        function SepEditFieldFcn(app, event)
            app.LeGUI.coreg.sep = str2num(app.SepEditField.Value);
        end

        % Value changed function: TolEditField
        function TolEditFieldFcn(app, event)
            app.LeGUI.coreg.tol = str2num(app.TolEditField.Value);
        end

        % Value changed function: FWHMEditField
        function FWHMEditFieldFcn(app, event)
            app.LeGUI.coreg.fwhm = str2num(app.FWHMEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.CostDropDownLabel = uilabel(app.UIFigure);
            app.CostDropDownLabel.Text = 'Cost';
            app.SepEditFieldLabel = uilabel(app.UIFigure);
            app.SepEditFieldLabel.Text = 'Sep';
            app.TolEditFieldLabel = uilabel(app.UIFigure);
            app.TolEditFieldLabel.Text = 'Tol';
            app.FWHMEditField = uieditfield(app.UIFigure, 'text');
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMEditFieldFcn, true);
            app.FWHMEditField.Tooltip = {'Histogram Smoothing'; 'Gaussian smoothing to apply to the 256x256 joint histogram.'; 'A 1-by-2 array must be entered.'};
            app.FWHMEditField.Value = '7,7';
            app.FWHMEditFieldLabel = uilabel(app.UIFigure);
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.TolEditField = uieditfield(app.UIFigure, 'text');
            app.TolEditField.ValueChangedFcn = createCallbackFcn(app, @TolEditFieldFcn, true);
            app.TolEditField.Tooltip = {'Tolerances'; 'The accuracy for each parameter. Iterations stop when differences between successive estimates are less than the required tolerance.'; 'A 1-by-12 array must be entered.'};
            app.TolEditField.Value = '0.02,0.02,0.02,0.001,0.001,0.001,0.01,0.01,0.01,0.001,0.001,0.001';
            app.SepEditField = uieditfield(app.UIFigure, 'text');
            app.SepEditField.ValueChangedFcn = createCallbackFcn(app, @SepEditFieldFcn, true);
            app.SepEditField.Tooltip = {'Separation'; 'The average distance between sampled points (in mm). Can be a vector to allow a coarse registration followed by increasingly fine ones.'; 'A 1-by-X array must be entered.'};
            app.SepEditField.Value = '4,2';
            app.CostDropDown = uidropdown(app.UIFigure);
            app.CostDropDown.Items = {'Mutual Information', 'Normalised Mutual Information', 'Entropy Correlation Coefficient', 'Normalised Cross Correlation'};
            app.CostDropDown.ItemsData = {'mi', 'nmi', 'ecc', 'ncc'};
            app.CostDropDown.ValueChangedFcn = createCallbackFcn(app, @CostDropDownFcn, true);
            app.CostDropDown.Tooltip = {'Objective Function'; 'Registration involves finding parameters that either maximise or minimise some objective function. For inter-modal registration, use Mutual Information, Normalised Mutual Information, or Entropy Correlation Coefficient. For within modality, you could also use Normalised Cross Correlation.'};
            app.CostDropDown.Value = 'nmi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_CoregMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end