classdef LeG_DetectMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MaxElecsEditField       matlab.ui.control.EditField
        MaxElecsEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        LeGUI 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            if nargin>1
                app.LeGUI = varargin{1}; %LeGUI object
            end  

            if ~isempty(app.LeGUI)
                app.MaxElecsEditField.Value = num2str(app.LeGUI.detect.maxelecs);
            end
            
            app.UIFigure.Name = 'Auto Electrode Detection Parameters';

            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.UIFigure.Position;
            app.UIFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end

        % Value changed function: MaxElecsEditField
        function MaxElecsEditFieldFcn(app, event)
            app.LeGUI.detect.maxelecs = str2double(app.MaxElecsEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.MaxElecsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxElecsEditFieldLabel.Text = 'Max Elecs';
            app.MaxElecsEditField = uieditfield(app.UIFigure, 'text');
            app.MaxElecsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxElecsEditFieldFcn, true);
            app.MaxElecsEditField.Tooltip = {'Maximum number of electrodes that detection algorithm will return.'};
            app.MaxElecsEditField.Value = '250';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_DetectMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end