classdef LeG_SegMenu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BiasRegEditField            matlab.ui.control.EditField
        BiasFWHMEditField           matlab.ui.control.EditField
        nGausGrayEditField          matlab.ui.control.EditField
        nGausWhiteEditField         matlab.ui.control.EditField
        nGausWhiteEditField_2Label  matlab.ui.control.Label
        nGausCSFEditField           matlab.ui.control.EditField
        nGausCSFEditFieldLabel      matlab.ui.control.Label
        nGausBoneEditField          matlab.ui.control.EditField
        nGauseBoneEditFieldLabel    matlab.ui.control.Label
        nGausSoftEditField          matlab.ui.control.EditField
        nGausSoftEditField_2Label   matlab.ui.control.Label
        nGausAirEditField           matlab.ui.control.EditField
        nGausAirEditFieldLabel      matlab.ui.control.Label
        MRFEditField                matlab.ui.control.EditField
        MRFEditFieldLabel           matlab.ui.control.Label
        CleanupEditField            matlab.ui.control.EditField
        RegEditField                matlab.ui.control.EditField
        CleanupEditFieldLabel       matlab.ui.control.Label
        RegEditField_2Label         matlab.ui.control.Label
        nGausGrayEditFieldLabel     matlab.ui.control.Label
        BiasFWHMEditFieldLabel      matlab.ui.control.Label
        FWHMEditField               matlab.ui.control.EditField
        FWHMEditFieldLabel          matlab.ui.control.Label
        SampEditField               matlab.ui.control.EditField
        SampEditFieldLabel          matlab.ui.control.Label
        BiasRegEditFieldLabel       matlab.ui.control.Label
    end

    
    properties (Access = public)
        LeGUI 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, varargin)
            if nargin>1
                app.LeGUI = varargin{1}; %LeGUI object
            end

            if ~isempty(app.LeGUI)
                app.BiasRegEditField.Value = num2str(app.LeGUI.segment.channel.biasreg);
                app.BiasFWHMEditField.Value = num2str(app.LeGUI.segment.channel.biasfwhm);

                app.nGausGrayEditField.Value = num2str(app.LeGUI.segment.tissue(1).ngaus);
                app.nGausWhiteEditField.Value = num2str(app.LeGUI.segment.tissue(2).ngaus);
                app.nGausCSFEditField.Value = num2str(app.LeGUI.segment.tissue(3).ngaus);
                app.nGausBoneEditField.Value = num2str(app.LeGUI.segment.tissue(4).ngaus);
                app.nGausSoftEditField.Value = num2str(app.LeGUI.segment.tissue(5).ngaus);
                app.nGausAirEditField.Value = num2str(app.LeGUI.segment.tissue(6).ngaus);

                app.MRFEditField.Value = num2str(app.LeGUI.segment.warp.mrf);
                app.CleanupEditField.Value = num2str(app.LeGUI.segment.warp.cleanup);
                app.RegEditField.Value = regexprep(num2str(app.LeGUI.segment.warp.reg),'\s+',',');
                app.FWHMEditField.Value = num2str(app.LeGUI.segment.warp.fwhm);
                app.SampEditField.Value = num2str(app.LeGUI.segment.warp.samp);
            end

            app.UIFigure.Name = 'Segmentation Parameters';

            %place figure at center of screen
            scrsize = get(0,'screensize');
            winpos = app.UIFigure.Position;
            app.UIFigure.Position = [(scrsize(3)-winpos(3))/2,(scrsize(4)-winpos(4))/2,winpos(3),winpos(4)];
        end

        % Close request function: UIFigure
        function CloseRequestFcn(app, event)
            delete(app)
        end

        % Value changed function: BiasRegEditField
        function BiasRegEditFieldFcn(app, event)
            app.LeGUI.segment.channel.biasreg = str2double(app.BiasRegEditField.Value);

        end

        % Value changed function: BiasFWHMEditField
        function BiasFWHMEditFieldFcn(app, event)
            app.LeGUI.segment.channel.biasfwhm = str2double(app.BiasFWHMEditField.Value);
            
        end

        % Value changed function: nGausGrayEditField
        function nGausGrayEditFieldFcn(app, event)
            app.LeGUI.segment.tissue(1).ngaus = str2double(app.nGausGrayEditField.Value);
            
        end

        % Value changed function: nGausWhiteEditField
        function nGausWhiteEditFieldFcn(app, event)
            app.LeGUI.segment.tissue(2).ngaus = str2double(app.nGausWhiteEditField.Value);
            
        end

        % Value changed function: nGausCSFEditField
        function nGausCSFEditFieldFcn(app, event)
            app.LeGUI.segment.tissue(3).ngaus = str2double(app.nGausCSFEditField.Value);
            
        end

        % Value changed function: nGausBoneEditField
        function nGausBoneEditFieldFcn(app, event)
            app.LeGUI.segment.tissue(4).ngaus = str2double(app.nGausBoneEditField.Value);
            
        end

        % Value changed function: nGausSoftEditField
        function nGausSoftEditFieldFcn(app, event)
            app.LeGUI.segment.tissue(5).ngaus = str2double(app.nGausSoftEditField.Value);
            
        end

        % Value changed function: nGausAirEditField
        function nGausAirEditFieldFcn(app, event)
            app.LeGUI.segment.tissue(6).ngaus = str2double(app.nGausAirEditField.Value);
            
        end

        % Value changed function: MRFEditField
        function MRFEditFieldFcn(app, event)
            app.LeGUI.segment.warp.mrf = str2double(app.MRFEditField.Value);
            
        end

        % Value changed function: CleanupEditField
        function CleanupEditFieldFcn(app, event)
            app.LeGUI.segment.warp.cleanup = str2double(app.CleanupEditField.Value);
            
        end

        % Value changed function: RegEditField
        function RegEditFieldFcn(app, event)
            app.LeGUI.segment.warp.reg = str2num(app.RegEditField.Value);
            
        end

        % Value changed function: FWHMEditField
        function FWHMEditFieldFcn(app, event)
            app.LeGUI.segment.warp.fwhm = str2double(app.FWHMEditField.Value);
            
        end

        % Value changed function: SampEditField
        function SampEditFieldFcn(app, event)
            app.LeGUI.segment.warp.samp = str2double(app.SampEditField.Value);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.BiasRegEditFieldLabel = uilabel(app.UIFigure);
            app.BiasRegEditFieldLabel.Text = 'BiasReg';
            app.SampEditFieldLabel = uilabel(app.UIFigure);
            app.SampEditFieldLabel.Text = 'Samp';
            app.SampEditField = uieditfield(app.UIFigure, 'text');
            app.SampEditField.ValueChangedFcn = createCallbackFcn(app, @SampEditFieldFcn, true);
            app.SampEditField.Tooltip = {'Sampling distance'; 'This encodes the approximate distance between sampled points when estimating the model parameters. Smaller values use more of the data, but the procedure is slower and needs more memory. Determining the best setting involves a compromise between speed and accuracy.'; 'An 1-by-1 array must be entered.'};
            app.SampEditField.Value = '4';
            app.FWHMEditFieldLabel = uilabel(app.UIFigure);
            app.FWHMEditFieldLabel.Text = 'FWHM';
            app.FWHMEditField = uieditfield(app.UIFigure, 'text');
            app.FWHMEditField.ValueChangedFcn = createCallbackFcn(app, @FWHMEditFieldFcn, true);
            app.FWHMEditField.Tooltip = {'Smoothness (fwhm)'; 'This is used to derive a fudge factor to account for correlations between neighbouring voxels. Smoother data have more spatial correlations, rendering the assumptions of the model inaccurate.'; 'An 1-by-1 array must be entered.'; 'Choose 5mm for pet/spect, 0 for mri'};
            app.FWHMEditField.Value = '0';
            app.BiasFWHMEditFieldLabel = uilabel(app.UIFigure);
            app.BiasFWHMEditFieldLabel.Text = 'BiasFWHM';
            app.nGausGrayEditFieldLabel = uilabel(app.UIFigure);
            app.nGausGrayEditFieldLabel.Text = 'nGausGray';
            app.RegEditField_2Label = uilabel(app.UIFigure);
            app.RegEditField_2Label.Text = 'Reg';
            app.CleanupEditFieldLabel = uilabel(app.UIFigure);
            app.CleanupEditFieldLabel.Text = 'Cleanup';
            app.RegEditField = uieditfield(app.UIFigure, 'text');
            app.RegEditField.ValueChangedFcn = createCallbackFcn(app, @RegEditFieldFcn, true);
            app.RegEditField.Tooltip = {'Warping Regularisation'; 'Registration involves simultaneously minimising two terms. One of these is a measure of similarity between the images (mean-squared difference in the current situation), whereas the other is a measure of the roughness of the deformations. This measure of roughness involves the sum of the following terms:'; '* Absolute displacements need to be penalised by a tiny amount. The first element encodes the amount of penalty on these. Ideally, absolute displacements should not be penalised, but it is necessary for technical reasons.'; '* The membrane energy of the deformation is penalised (2nd element), usually by a relatively small amount. This penalises the sum of squares of the derivatives of the velocity field (ie the sum of squares of the elements of the Jacobian tensors).'; '* The bending energy is penalised (3rd element). This penalises the sum of squares of the 2nd derivatives of the velocity.'; '* Linear elasticity regularisation is also included (4th and 5th elements). The first parameter (mu) is similar to that for linear elasticity, except it penalises the sum of squares of the Jacobian tensors after they have been made symmetric (by averaging with the transpose). This term essentially penalises length changes, without penalising rotations.'; '* The final term also relates to linear elasticity, and is the weight that denotes how much to penalise changes to the divergence of the velocities (lambda). This divergence is a measure of the rate of volumetric expansion or contraction. The amount of regularisation determines the tradeoff between the terms. More regularisation gives smoother deformations, where the smoothness measure is determined by the bending energy of the deformations.'; 'An 1-by-5 array must be entered.'};
            app.RegEditField.Value = '0,0.001,0.5,0.05,0.2';
            app.CleanupEditField = uieditfield(app.UIFigure, 'text');
            app.CleanupEditField.ValueChangedFcn = createCallbackFcn(app, @CleanupEditFieldFcn, true);
            app.CleanupEditField.Tooltip = {'Clean Up'; 'This uses a crude routine for extracting the brain from segmented images. It begins by taking the white matter, and eroding it a couple of times to get rid of any odd voxels. The algorithm continues on to do conditional dilations for several iterations, where the condition is based upon gray or white matter being present. This identified region is then used to clean up the grey and white matter partitions. Note that the fluid class will also be cleaned, such that aqueous and vitreous humour in the eyeballs, as well as other assorted fluid regions (except CSF) will be removed.'};
            app.CleanupEditField.Value = '1';
            app.MRFEditFieldLabel = uilabel(app.UIFigure);
            app.MRFEditFieldLabel.Text = 'MRF';
            app.MRFEditField = uieditfield(app.UIFigure, 'text');
            app.MRFEditField.ValueChangedFcn = createCallbackFcn(app, @MRFEditFieldFcn, true);
            app.MRFEditField.Tooltip = {'MRF Parameter'; 'When tissue class images are written out, a few iterations of a simple Markov Random Field (MRF) cleanup procedure are run. This parameter controls the strength of the MRF. Setting the value to zero will disable the cleanup.'; 'An 1-by-1 array must be entered.'};
            app.MRFEditField.Value = '1';
            app.nGausAirEditFieldLabel = uilabel(app.UIFigure);
            app.nGausAirEditFieldLabel.Text = 'nGausAir';
            app.nGausAirEditField = uieditfield(app.UIFigure, 'text');
            app.nGausAirEditField.ValueChangedFcn = createCallbackFcn(app, @nGausAirEditFieldFcn, true);
            app.nGausAirEditField.Tooltip = {'Num. Gaussians'; 'The number of Gaussians used to represent the intensity distribution for tissue class.'};
            app.nGausAirEditField.Value = '2';
            app.nGausSoftEditField_2Label = uilabel(app.UIFigure);
            app.nGausSoftEditField_2Label.Text = 'nGausSoft';
            app.nGausSoftEditField = uieditfield(app.UIFigure, 'text');
            app.nGausSoftEditField.ValueChangedFcn = createCallbackFcn(app, @nGausSoftEditFieldFcn, true);
            app.nGausSoftEditField.Tooltip = {'Num. Gaussians'; 'The number of Gaussians used to represent the intensity distribution for tissue class.'};
            app.nGausSoftEditField.Value = '4';
            app.nGauseBoneEditFieldLabel = uilabel(app.UIFigure);
            app.nGauseBoneEditFieldLabel.Text = 'nGauseBone';
            app.nGausBoneEditField = uieditfield(app.UIFigure, 'text');
            app.nGausBoneEditField.ValueChangedFcn = createCallbackFcn(app, @nGausBoneEditFieldFcn, true);
            app.nGausBoneEditField.Tooltip = {'Num. Gaussians'; 'The number of Gaussians used to represent the intensity distribution for tissue class.'};
            app.nGausBoneEditField.Value = '3';
            app.nGausCSFEditFieldLabel = uilabel(app.UIFigure);
            app.nGausCSFEditFieldLabel.Text = 'nGausCSF';
            app.nGausCSFEditField = uieditfield(app.UIFigure, 'text');
            app.nGausCSFEditField.ValueChangedFcn = createCallbackFcn(app, @nGausCSFEditFieldFcn, true);
            app.nGausCSFEditField.Tooltip = {'Num. Gaussians'; 'The number of Gaussians used to represent the intensity distribution for tissue class.'};
            app.nGausCSFEditField.Value = '2';
            app.nGausWhiteEditField_2Label = uilabel(app.UIFigure);
            app.nGausWhiteEditField_2Label.Text = 'nGausWhite';
            app.nGausWhiteEditField = uieditfield(app.UIFigure, 'text');
            app.nGausWhiteEditField.ValueChangedFcn = createCallbackFcn(app, @nGausWhiteEditFieldFcn, true);
            app.nGausWhiteEditField.Tooltip = {'Num. Gaussians'; 'The number of Gaussians used to represent the intensity distribution for tissue class.'};
            app.nGausWhiteEditField.Value = '2';
            app.nGausGrayEditField = uieditfield(app.UIFigure, 'text');
            app.nGausGrayEditField.ValueChangedFcn = createCallbackFcn(app, @nGausGrayEditFieldFcn, true);
            app.nGausGrayEditField.Tooltip = {'Num. Gaussians'; 'The number of Gaussians used to represent the intensity distribution for tissue class.'};
            app.nGausGrayEditField.Value = '2';
            app.BiasFWHMEditField = uieditfield(app.UIFigure, 'text');
            app.BiasFWHMEditField.ValueChangedFcn = createCallbackFcn(app, @BiasFWHMEditFieldFcn, true);
            app.BiasFWHMEditField.Tooltip = {'FWHM of Gaussian smoothness of bias.'; 'If your intensity non-uniformity is very smooth, then choose a large FWHM. This will prevent the algorithm from trying to model out intensity variation due to different tissue types. '};
            app.BiasFWHMEditField.Value = '60';
            app.BiasRegEditField = uieditfield(app.UIFigure, 'text');
            app.BiasRegEditField.ValueChangedFcn = createCallbackFcn(app, @BiasRegEditFieldFcn, true);
            app.BiasRegEditField.Tooltip = {'Bias regularisation'; 'MR images are usually corrupted by a smooth, spatially varying artifact that modulates the intensity of the image (bias). These artifacts, although not usually a problem for visual inspection, can impede automated processing of the images. If your data has very little intensity non-uniformity artifact, then the bias regularisation should be increased. This effectively tells the algorithm that there is very little bias in your data, so it does not try to model it.'};
            app.BiasRegEditField.Value = '0.001';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeG_SegMenu(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end