classdef ea_OSS_optimizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SSEweightSlider               matlab.ui.control.Slider
        SSEweightSliderLabel          matlab.ui.control.Label
        SetTargetTractsButton         matlab.ui.control.Button
        OSSSettingsButton             matlab.ui.control.Button
        OptimizeusingOSSButton        matlab.ui.control.Button
        NumberofIterationsEditField   matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        OptAlgorithmDropDown          matlab.ui.control.DropDown
        OptAlgorithmDropDownLabel     matlab.ui.control.Label
        DistancemetricDropDown        matlab.ui.control.DropDown
        DistancemetricDropDownLabel   matlab.ui.control.Label
        ANNModelPanel                 matlab.ui.container.Panel
        OptimizeusingANNButton        matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        TrainANNusingLabel            matlab.ui.control.Label
        SideeffecterrorthresholdEditField  matlab.ui.control.NumericEditField
        SideeffecterrorthresholdEditFieldLabel  matlab.ui.control.Label
        ErrorthresholdEditField       matlab.ui.control.NumericEditField
        ErrorthresholdEditFieldLabel  matlab.ui.control.Label
        VATButton                     matlab.ui.control.Button
        PAMButton                     matlab.ui.control.Button
        ChoosePatientFoldersButton    matlab.ui.control.Button
        MaxCylindricEditField         matlab.ui.control.NumericEditField
        MaxCylindricEditFieldLabel    matlab.ui.control.Label
        MinCylindricEditField         matlab.ui.control.NumericEditField
        MinCylindricEditFieldLabel    matlab.ui.control.Label
        MaxSegmentedEditField         matlab.ui.control.NumericEditField
        MaxSegmentedEditFieldLabel    matlab.ui.control.Label
        MinSegmentedEditField         matlab.ui.control.NumericEditField
        MinSegmentedEditFieldLabel    matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        OptAmplitudesmALabel          matlab.ui.control.Label
        QuestionsandsuggestionskonstantinmgtugmailcomLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        PAMOptimizerLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        patientlist={} % cell with patient folders loaded
        stimfolder = [];

        % additional properties for PathwayTune
        Optimize_Weight_CheckBox = {}
        MasterDict = '';
        SE_err_threshold = 5.0;
        Err_threshold = 10.0;

        optim_space = 'MNI152NLin2009bAsym';
        targets_set = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OptimizeusingOSSButton
        function OptimizeusingOSSButtonPushed(app, event)
           
%             % for now, same weights for both sides!
%             % for current optimization, they are all fixed!
%             ea_save_fixed_weights(app.stimfolder, app.Optimize_Weight_CheckBox, app.tractset.cleartunevars.weights, app.slider_name, pos_and_neg)
%             fixed_symptom_file = [app.stimfolder,filesep,'Fixed_symptoms.json'];
            
            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);
            app.stimfolder = fullfile(patientPath, filesep, 'stimulations', filesep, app.optim_space, filesep, 'NBstim');
            if ~isfolder(app.stimfolder)
                mkdir(app.stimfolder)
            end
            
            % check the reconstruction file
            reconstruction_file = dir_without_dots([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [reconst, el_model_right, el_model_left, side] = ea_get_reconstruction(reconstruction_file_path);
            if isempty(el_model_right)
                side = 1;
            end   

            % load options for OSS-DBS (including default axon properties)
            options = ea_get_OSS_DBS_options(patientPath, [], [], strcmp(app.optim_space,'native'));
            options.prefs.machine.vatsettings.butenko_calcPAM = 1;
            options.prefs.machine.vatsettings.butenko_calcVAT = 0;

            % atm, it just creates some dummies
            S = ea_set_optimizer(options,app.stimfolder);

            % assign per contact bounds based on the electrode type and
            % assigned thresholds
            [min_bound_per_contact, max_bound_per_contact, S] = ea_get_currents_per_contact(app.MinCylindricEditField.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value, reconst, side, 1);
            N_contacts = length(min_bound_per_contact);
            S.label = 'NBstim';

            % create a master dictionary that will guide
            % Launcher_OSS_NetBlend.py
            % in this specific case, we convert to A
            jsonDict.netblendict.min_bound_per_contact = min_bound_per_contact .* 0.001;
            jsonDict.netblendict.max_bound_per_contact = max_bound_per_contact .* 0.001;

            % pass it as within a Docker
            %jsonDict.netblendict.ActivProfileDict = [app.stimfolder,filesep,'target_profiles.json'];
            %jsonDict.netblendict.symptom_weights_file = [app.stimfolder,filesep, 'Fixed_symptoms.json'];
            jsonDict.netblendict.num_iterations = app.NumberofIterationsEditField.Value;
            jsonDict.netblendict.optim_alg = app.OptAlgorithmDropDown.Value;
            jsonDict.netblendict.similarity_metric = app.DistancemetricDropDown.Value;

            % IMPORTANT: if netblendict.StimSets, then
            % run_pathway_activation knows it is optimization
            jsonDict.netblendict.Optimizer = 1;
            jsonDict.netblendict.StimSets = 0;

            PathwayTune_master_dict = [app.stimfolder,filesep, 'master_dict.json'];
            app.MasterDict = PathwayTune_master_dict;

            fixedSymptomsDictPath = [app.stimfolder,filesep, 'Fixed_symptoms.json'];
            targetProfilesDictPath = [app.stimfolder,filesep,'target_profiles.json'];
            if isfile(fixedSymptomsDictPath) && isfile(targetProfilesDictPath)
                ea_merge_PathwayTune_dicts(app.stimfolder,jsonDict,'None','None',fixedSymptomsDictPath,targetProfilesDictPath)
            elseif isfile(PathwayTune_master_dict) && (~isfile(fixedSymptomsDictPath) || ~isfile(targetProfilesDictPath))
                ea_merge_PathwayTune_dicts(app.stimfolder,jsonDict,'None','None',fixedSymptomsDictPath,targetProfilesDictPath,PathwayTune_master_dict)
            end

            options.optimizer = 1;
            options.PathwayTune_master_dict = PathwayTune_master_dict;

            ea_delete(fixedSymptomsDictPath)
            ea_delete(targetProfilesDictPath)

            env = ea_conda_env('OSS-DBSv2');

            ea_genvat_butenko(S,options)
            env = ea_conda_env('OSS-DBSv2');  % ensure the correct env

            % post-processings
            % needs refactoring
            options.optimizer = 0; 
            % check if activation results are available and then make predictions 
            % these figures are used to display results
            activ_profile_fig_rh = [app.stimfolder,filesep,'NB_rh',filesep,'Activation_profile_rh.png'];
            activ_profile_fig_lh = [app.stimfolder,filesep,'NB_lh',filesep,'Activation_profile_lh.png'];
            symptom_profile_fig_rh = [app.stimfolder,filesep,'NB_rh',filesep,'Symptom_profiles_rh.png'];
            symptom_profile_fig_lh = [app.stimfolder,filesep,'NB_lh',filesep,'Symptom_profiles_lh.png'];
            
            if length(side) == 2
                % get the best solution and solve again for it
                [stim_currents_perc_rh] = ea_get_optimal_currents(N_contacts,app.stimfolder,0,1);
                [stim_currents_perc_lh] = ea_get_optimal_currents(N_contacts,app.stimfolder,1,1);

                if any(stim_currents_perc_rh) || any(stim_currents_perc_lh)

                    S = ea_cleartune_generateSfile(stim_currents_perc_rh,stim_currents_perc_lh,S,2);
                    ea_genvat_butenko(S,options)
    
                    if ~isfolder([app.stimfolder,filesep,'OSS_sim_files_rh',filesep,'Results'])
                        disp('Activation was not computed for the right electrode')
                        if ~isfolder([app.stimfolder,filesep,'OSS_sim_files_lh',filesep,'Results'])
                            disp('Activation was not computed for the left electrode')
                        end
                        return
                    elseif ~isfolder([app.stimfolder,filesep,'OSS_sim_files_lh',filesep,'Results'])
                        disp('Activation was not computed for the left electrode')
                        return
                    else
                        % we need to add some packages to our mambaforge python
                        if any(stim_currents_perc_rh)
                            env.system(['python', ' ', ea_getearoot, 'ext_libs/PathwayTune/Improvement4Protocol.py ', ...
                                    app.stimfolder, ' ', num2str(side(1)), ' ', PathwayTune_master_dict]);	% 0 is right side, 1 is the left side here
                            ea_displayOptimResults(activ_profile_fig_rh, symptom_profile_fig_rh)
                        end

                        if any(stim_currents_perc_lh)
                            env.system(['python', ' ', ea_getearoot, 'ext_libs/PathwayTune/Improvement4Protocol.py ', ...
                                    app.stimfolder, ' ', num2str(side(2)), ' ', PathwayTune_master_dict]);	% 0 is right side, 1 is the left side here
                            ea_displayOptimResults(activ_profile_fig_lh, symptom_profile_fig_lh)
                        end

                        if any(stim_currents_perc_rh) && any(stim_currents_perc_lh)    
                            % now we reconcile across hemispheres
                            env.system(['python', ' ', ea_getearoot, 'ext_libs/PathwayTune/Bilateral_results.py ', ...
                                    app.stimfolder, ' ', 'improvement']);	
                        end
                    end   
                end

            elseif side == 0

                % get the best solution and solve again for it
                [stim_currents_perc_rh] = ea_get_optimal_currents(N_contacts,app.stimfolder,0,1);
                if any(stim_currents_perc_rh)
                    S = ea_cleartune_generateSfile(stim_currents_perc_rh,NaN(1,length(stim_currents_perc_rh)),S,2);
                    ea_genvat_butenko(S,options)
    
                    if ~isfolder([app.stimfolder,filesep,'OSS_sim_files_rh',filesep,'Results'])
                        disp('Activation was not computed for the right electrode')
                        return
                    else
                        env.system(['python', ' ', ea_getearoot, 'ext_libs/PathwayTune/Improvement4Protocol.py ', ...
                            app.stimfolder, ' ', num2str(side), ' ', ea_path_helper(PathwayTune_master_dict)]);	% 0 is right side, 1 is the left side here
                    end
                    ea_displayOptimResults(activ_profile_fig_rh, symptom_profile_fig_rh)
                end

            elseif side == 1

                % get the best solution and solve again for it
                [stim_currents_perc_lh] = ea_get_optimal_currents(N_contacts,app.stimfolder,1,1);
                if any(stim_currents_perc_lh)
                    S = ea_cleartune_generateSfile(NaN(1,length(stim_currents_perc_lh)),stim_currents_perc_lh,S,2);
                    ea_genvat_butenko(S,options)
    
                    if ~isfolder([app.stimfolder,filesep,'OSS_sim_files_lh',filesep,'Results'])
                        disp('Activation was not computed for the left electrode')
                        return
                    else
                        env.system(['python', ' ', ea_getearoot, 'ext_libs/PathwayTune/Improvement4Protocol.py ', ...
                            app.stimfolder, ' ', num2str(side), ' ', PathwayTune_master_dict]);	% 0 is right side, 1 is the left side here
                    end 
    
                    ea_displayOptimResults(activ_profile_fig_lh, symptom_profile_fig_lh)
                end
            end
        end

        % Button pushed function: PAMButton
        function PAMButtonPushed(app, event)
            
            % if ~app.targets_set
            %     % initialize a default target activation
            % 
            %     targetType = getappdata(app.UIFigure, 'targetType');
            %     targetActivation = str2double(getappdata(app.UIFigure, 'targetActivation')) * 0.01;
            %     pathwayWeights = str2double(getappdata(app.UIFigure, 'fixedWeights')) * 0.01;  % these are not symptom weights
            % 
            %     [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);
            %     app.stimfolder = fullfile(patientPath, filesep, 'stimulations', filesep, app.optim_space, filesep, 'NBstim');
            %     if ~isfolder(app.stimfolder)
            %         mkdir(app.stimfolder)
            %     end
            %     ea_create_target_dict(targetType,targetActivation,app.stimfolder,prefs.machine.vatsettings.butenko_connectome,pathwayWeights)
            % end

            % fixed definitions
            hemi_labels = {'right','left'};
            hemi_sfx = {'_rh','_lh'};

            % train ANN using OSS-DBS
            
            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);


            % first prepare training and test sets

            % we will choose number of samples depending on the
            % electrode model within Network Blending

            app.stimfolder = fullfile(patientPath, filesep, 'stimulations', filesep, app.optim_space, filesep, 'NBstim');
            mkdir(app.stimfolder)

            reconstruction_file = dir_without_dots([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            
            ea_set_ANN_training(reconstruction_file_path,app.stimfolder,app.MinCylindricEditField.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value)
            %ea_warndlg(['The Train/Test sets were saved in ', app.stimfolder])

            % check the reconstruction file
            [~, el_model_right, el_model_left, side] = ea_get_reconstruction(reconstruction_file_path);
            if isempty(el_model_right)
                side = 1;
            end

            % load options for OSS-DBS (including default axon properties)
            options = ea_get_OSS_DBS_options(patientPath, [], [], strcmp(app.optim_space,'native'));
            options.stimSetMode = 1;  % OSS needs to solve a classic StimSet problem
            options.trainANN = 1;

            % check the reconstruction file
            [reconst, el_model_right, el_model_left, side] = ea_get_reconstruction(reconstruction_file_path);
            if isempty(el_model_right)
                side = 1;
            end    

            % the first two return arguments are already stored in StimSets_info.json
            % this is an excessive call, since we already di it 
            [min_bound_per_contact, max_bound_per_contact, S] = ea_get_currents_per_contact(app.MinCylindricEditField.Value,app.MaxCylindricEditField.Value, app.MinSegmentedEditField.Value, app.MaxSegmentedEditField.Value, reconst, side, 1);
            S.label = 'NBstim';

            % create a master dictionary that will guide ANN_module.py
            % in this specific case, we convert to A
            jsonDict.netblendict.min_bound_per_contact = min_bound_per_contact .* 0.001;
            jsonDict.netblendict.max_bound_per_contact = max_bound_per_contact .* 0.001;

            %jsonDict.netblendict.FF_dictionary = 'Selected Connectome';
            %jsonDict.netblendict.ActivProfileDict = [app.stimfolder,filesep,'target_profiles.json'];
            %jsonDict.netblendict.symptom_weights_file = [app.stimfolder,filesep, 'Fixed_symptoms.json'];

            % Acceptable error thresholds, convert from %
            jsonDict.netblendict.Err_threshold = app.ErrorthresholdEditField.Value / 100.0;
            jsonDict.netblendict.SE_err_threshold = app.SideeffecterrorthresholdEditField.Value / 100.0;

            % optimization parameters
            jsonDict.netblendict.num_iterations_ANN = app.NumberofIterationsEditField.Value;
            jsonDict.netblendict.optim_alg = app.OptAlgorithmDropDown.Value;
            jsonDict.netblendict.similarity_metric = app.DistancemetricDropDown.Value;

            % IMPORTANT: for ANN training, we need to solve a classic
            % StimSet problem
            jsonDict.netblendict.Optimizer = 0;
            jsonDict.netblendict.StimSets = 1;


            % PathwayTune_master_dict = [app.stimfolder,filesep, 'netblend_dict_file.json'];
            % jsonText = jsonencode(jsonDict);
            % fid = fopen(PathwayTune_master_dict, 'w');
            % fprintf(fid, '%s', jsonText)
            % fclose(fid);

            % it will actually attempt to run both side
            % and we can run both sides if necessary
            %ea_genvat_butenko(S,options,[],1)

            fixedSymptomsDictPath = [app.stimfolder,filesep, 'Fixed_symptoms.json'];
            targetProfilesDictPath = [app.stimfolder,filesep,'target_profiles.json'];
            if length(side) == 2
                stimSetsDictPath_rh = [app.stimfolder,filesep,'NB',hemi_sfx{1},filesep, 'StimSets_info.json'];
                stimSetsDictPath_lh = [app.stimfolder,filesep,'NB',hemi_sfx{2},filesep, 'StimSets_info.json'];
            else
                if side == 0
                    stimSetsDictPath_rh = [app.stimfolder,filesep,'NB',hemi_sfx{1},filesep, 'StimSets_info.json'];
                    stimSetsDictPath_lh = 'None';
                else
                    stimSetsDictPath_lh = [app.stimfolder,filesep,'NB',hemi_sfx{2},filesep, 'StimSets_info.json'];
                    stimSetsDictPath_rh = 'None';          
                end
            end

            PathwayTune_master_dict = [app.stimfolder,filesep, 'master_dict.json'];
            app.MasterDict = PathwayTune_master_dict;

            fixedSymptomsDictPath = [app.stimfolder,filesep, 'Fixed_symptoms.json'];
            targetProfilesDictPath = [app.stimfolder,filesep,'target_profiles.json'];
            if isfile(fixedSymptomsDictPath) && isfile(targetProfilesDictPath)
                ea_merge_PathwayTune_dicts(app.stimfolder,jsonDict,stimSetsDictPath_rh,stimSetsDictPath_lh,fixedSymptomsDictPath,targetProfilesDictPath)
            elseif isfile(PathwayTune_master_dict) && (~isfile(fixedSymptomsDictPath) || ~isfile(targetProfilesDictPath))
                ea_merge_PathwayTune_dicts(app.stimfolder,jsonDict,stimSetsDictPath_rh,stimSetsDictPath_lh,fixedSymptomsDictPath,targetProfilesDictPath,PathwayTune_master_dict)
            end


            %ea_merge_PathwayTune_dicts(app.stimfolder,jsonDict,stimSetsDictPath_rh,stimSetsDictPath_lh,fixedSymptomsDictPath,targetProfilesDictPath)
            %PathwayTune_master_dict = [app.stimfolder,filesep, 'master_dict.json'];
            %app.MasterDict = PathwayTune_master_dict;

            % clean-up
            ea_delete(stimSetsDictPath_rh)
            ea_delete(stimSetsDictPath_lh)
            ea_delete(fixedSymptomsDictPath)
            ea_delete(targetProfilesDictPath)

            ea_genvat_butenko(S,options)

            % now switch to an environment with TensorFlow
            ea_get_ANN_env();
            env = ea_conda_env('SynthSeg');

            if length(side) == 2
                % run train_test_ANN()
                for hemi = 0:1
                    env.system(['python', ' ', ea_getearoot, 'ext_libs/PathwayTune/ANN_module.py ', ...
                            app.stimfolder, ' ', num2str(hemi)]);	% 0 is right side, 1 is the left side here

                    % implement check if the model was accepted on the next
                    % stage
                end
            else
                env.system(['/python', ' ', ea_getearoot, 'ext_libs/PathwayTune/ANN_module.py ', ...
                        app.stimfolder, ' ', num2str(side)]);	% 0 is right side, 1 is the left side here
            end

            % it also stores info about pathways that ANN learned (some could have been excluded)
            % os.environ['STIMDIR'] + '/NB_' + str(side) + '/ANN_abs_errors.json'

            % only if ANN training returns a success flag, otherwise throw
            % a warning
            %app.OptimizeusingANNButton.Enable = 1;
        end

        % Button pushed function: OSSSettingsButton
        function OSSSettingsButtonPushed(app, event)
            ea_vatsettings_butenko;
        end

        % Button pushed function: ChoosePatientFoldersButton
        function ChoosePatientFoldersButtonPushed(app, event)
            app.patientlist=ea_getpatients;
            %app.enablerules;
            %app.draw(1,event)
            %app.isbusy(0);
        end

        % Button pushed function: SetTargetTractsButton
        function SetTargetTractsButtonPushed(app, event)
            % if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
            %     set(hObject,'BackgroundColor','white');
            % end

            prefs = ea_prefs('');
            ea_pathway_targets(prefs.machine.vatsettings.butenko_connectome, app.UIFigure);
%             settings_saved = getappdata(app.OSSDBSSettingsUIFigure, 'settings_saved');
%             waitfor(app.OSSDBSSettingsUIFigure,'settings_saved', 1)
            targetType = getappdata(app.UIFigure, 'targetType');
            targetActivation = str2double(getappdata(app.UIFigure, 'targetActivation')) * 0.01;
            pathwayWeights = str2double(getappdata(app.UIFigure, 'fixedWeights')) * 0.01;  % these are not symptom weights

            [patientID, patientPath] = ea_get_patient_folder(app.patientlist, 1);
            app.stimfolder = fullfile(patientPath, filesep, 'stimulations', filesep, app.optim_space, filesep, 'NBstim');
            if ~isfolder(app.stimfolder)
                mkdir(app.stimfolder)
            end

            ea_create_target_dict(targetType,targetActivation,app.stimfolder,prefs.machine.vatsettings.butenko_connectome,pathwayWeights)

            app.targets_set = true;
            
            if any(strcmp(targetType,'SSE'))
                % if the slider was not touched, it will be assumed as 50%
                % weight for SSE (via rem_weight in pam_optimizer.py)
                app.SSEweightSlider.Enable = 1;
            end

            jsonDict.fixed_symptom_weights = [];
            jsonText = jsonencode(jsonDict);
            jsonText(end-2:end-1) = '{}';
            json_file = [app.stimfolder,filesep,'Fixed_symptoms.json'];
            fid = fopen(json_file, 'w');
            fprintf(fid, '%s', jsonText)
            fclose(fid);
            
            % enable optimization buttons
            app.OptimizeusingOSSButton.Enable = "on";
            app.PAMButton.Enable = "on";

        end

        % Button pushed function: OptimizeusingANNButton
        function OptimizeusingANNButtonPushed(app, event)
            % check the reconstruction file
            reconstruction_file = dir_without_dots([patientPath,filesep,'reconstruction',filesep,'*desc-reconstruction.mat']);
            reconstruction_file_path = [reconstruction_file.folder,filesep,reconstruction_file.name];
            [reconst, el_model_right, el_model_left, side] = ea_get_reconstruction(reconstruction_file_path);
            if isempty(el_model_right)
                side = 1;
            end  

            % now switch to an environment with TensorFlow
            ea_get_ANN_env();
            env = ea_conda_env('SynthSeg');

            if length(side) == 2
                for hemi = 0:1
                    env.system(['python ', ea_getearoot, 'ext_libs/PathwayTune/NB_outline.py ', ...
                            app.stimfolder, ' ', app.MasterDict, ' ', num2str(hemi)]);	% 0 is right side, 1 is the left side here
                end
            else
                env.system(['python ', ea_getearoot, 'ext_libs/PathwayTune/NB_outline.py ', ...
                        app.stimfolder, ' ', app.MasterDict, ' ', num2str(side)]);	% 0 is right side, 1 is the left side here

                env.system(['python ', ea_getearoot, 'ext_libs/PathwayTune/Bilateral_results.py ', ...
                        app.stimfolder, ' ', 'weights']);

                env.system(['python ', ea_getearoot, 'ext_libs/PathwayTune/Bilateral_results.py ', ...
                        app.stimfolder, ' ', 'improvement']);	
            end
        end

        % Value changed function: SSEweightSlider
        function SSEweightSliderValueChanged(app, event)
            SSE_weight = app.SSEweightSlider.Value*0.01;

            symptomWeights = [1-SSE_weight;1-SSE_weight;SSE_weight;SSE_weight];
            symptomNames = {'target_rh';'target_lh';'SSE_rh';'SSE_lh'};
            ea_save_fixed_weights(app.stimfolder,0,symptomWeights,symptomNames,zeros(size(symptomWeights)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PAMOptimizerLabel = uilabel(app.UIFigure);
            app.PAMOptimizerLabel.Text = 'PAM Optimizer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'PathwayTune_logo.png';
            app.QuestionsandsuggestionskonstantinmgtugmailcomLabel = uilabel(app.UIFigure);
            app.QuestionsandsuggestionskonstantinmgtugmailcomLabel.Text = 'Questions and suggestions konstantinmgtu@gmail.com';
            app.OptAmplitudesmALabel = uilabel(app.UIFigure);
            app.OptAmplitudesmALabel.Text = 'Opt. Amplitudes, mA';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'contact_types.png';
            app.MinSegmentedEditFieldLabel = uilabel(app.UIFigure);
            app.MinSegmentedEditFieldLabel.Text = 'Min. Segmented';
            app.MinSegmentedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinSegmentedEditField.Value = -2;
            app.MaxSegmentedEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSegmentedEditFieldLabel.Text = 'Max. Segmented';
            app.MaxSegmentedEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxSegmentedEditField.Value = 2;
            app.MinCylindricEditFieldLabel = uilabel(app.UIFigure);
            app.MinCylindricEditFieldLabel.Text = 'Min. Cylindric';
            app.MinCylindricEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinCylindricEditField.Value = -4;
            app.MaxCylindricEditFieldLabel = uilabel(app.UIFigure);
            app.MaxCylindricEditFieldLabel.Text = 'Max. Cylindric';
            app.MaxCylindricEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxCylindricEditField.Value = 4;
            app.ChoosePatientFoldersButton = uibutton(app.UIFigure, 'push');
            app.ChoosePatientFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePatientFoldersButtonPushed, true);
            app.ChoosePatientFoldersButton.Text = 'Choose Patient Folder(s)...';
            app.ANNModelPanel = uipanel(app.UIFigure);
            app.ANNModelPanel.Title = 'ANN Model';
            app.PAMButton = uibutton(app.ANNModelPanel, 'push');
            app.PAMButton.ButtonPushedFcn = createCallbackFcn(app, @PAMButtonPushed, true);
            app.PAMButton.Enable = 'off';
            app.PAMButton.Text = 'PAM';
            app.VATButton = uibutton(app.ANNModelPanel, 'push');
            app.VATButton.Enable = 'off';
            app.VATButton.Text = 'VAT';
            app.ErrorthresholdEditFieldLabel = uilabel(app.ANNModelPanel);
            app.ErrorthresholdEditFieldLabel.Text = 'Error threshold';
            app.ErrorthresholdEditField = uieditfield(app.ANNModelPanel, 'numeric');
            app.ErrorthresholdEditField.Value = 10;
            app.SideeffecterrorthresholdEditFieldLabel = uilabel(app.ANNModelPanel);
            app.SideeffecterrorthresholdEditFieldLabel.Text = 'Side-effect error threshold';
            app.SideeffecterrorthresholdEditField = uieditfield(app.ANNModelPanel, 'numeric');
            app.SideeffecterrorthresholdEditField.Value = 5;
            app.TrainANNusingLabel = uilabel(app.ANNModelPanel);
            app.TrainANNusingLabel.Text = 'Train ANN using';
            app.Label_2 = uilabel(app.ANNModelPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.ANNModelPanel);
            app.Label_3.Text = '%';
            app.OptimizeusingANNButton = uibutton(app.ANNModelPanel, 'push');
            app.OptimizeusingANNButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeusingANNButtonPushed, true);
            app.OptimizeusingANNButton.Enable = 'off';
            app.OptimizeusingANNButton.Text = 'Optimize using ANN';
            app.DistancemetricDropDownLabel = uilabel(app.UIFigure);
            app.DistancemetricDropDownLabel.Text = 'Distance metric';
            app.DistancemetricDropDown = uidropdown(app.UIFigure);
            app.DistancemetricDropDown.Items = {'Canberra', 'Euclidean', 'Cosine', 'Bray-Curtis', 'Manhattan'};
            app.DistancemetricDropDown.Value = 'Canberra';
            app.OptAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.OptAlgorithmDropDownLabel.Text = 'Opt. Algorithm';
            app.OptAlgorithmDropDown = uidropdown(app.UIFigure);
            app.OptAlgorithmDropDown.Items = {'Dual Annealing', 'PSO'};
            app.OptAlgorithmDropDown.Value = 'Dual Annealing';
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIterationsEditField.Value = 1000;
            app.OptimizeusingOSSButton = uibutton(app.UIFigure, 'push');
            app.OptimizeusingOSSButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeusingOSSButtonPushed, true);
            app.OptimizeusingOSSButton.Enable = 'off';
            app.OptimizeusingOSSButton.Tooltip = {'Optimize current protocol for selected weights'};
            app.OptimizeusingOSSButton.Text = 'Optimize using OSS';
            app.OSSSettingsButton = uibutton(app.UIFigure, 'push');
            app.OSSSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @OSSSettingsButtonPushed, true);
            app.OSSSettingsButton.Text = 'OSS Settings';
            app.SetTargetTractsButton = uibutton(app.UIFigure, 'push');
            app.SetTargetTractsButton.ButtonPushedFcn = createCallbackFcn(app, @SetTargetTractsButtonPushed, true);
            app.SetTargetTractsButton.Text = 'Set Target Tracts';
            app.SSEweightSliderLabel = uilabel(app.UIFigure);
            app.SSEweightSliderLabel.Enable = 'off';
            app.SSEweightSliderLabel.Text = 'SSE weight';
            app.SSEweightSlider = uislider(app.UIFigure);
            app.SSEweightSlider.ValueChangedFcn = createCallbackFcn(app, @SSEweightSliderValueChanged, true);
            app.SSEweightSlider.Enable = 'off';
            app.SSEweightSlider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_OSS_optimizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end