classdef ea_pathway_targets < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingforConnectomeUIFigure  matlab.ui.Figure
        SaveButton                    matlab.ui.control.Button
        SettingTable                  matlab.ui.control.Table
    end

    
    properties (Access = public)
        connectome % Multi-tract connectome
        multitract
        settings_saved = 0;
        hOptimSettingGUI % hObject of the VAT setting window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, connectome, hOptimSettingGUI)
            if startsWith(connectome, 'Multi-Tract: ')
                app.connectome = strrep(connectome, 'Multi-Tract: ', '');
                app.multitract = 1;
                % Get tract names
                TractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
                connFolder = [TractConnFolder, filesep, app.connectome];
                tractNames = strrep(ea_regexpdir(connFolder, '\.mat$', 0), [connFolder, filesep], '');
                tractNames = strrep(tractNames, '.mat', '');
                app.SettingTable.Data = repmat(["Target",100.0], numel(tractNames), 1);
            else
                TractConnFolder = [ea_getconnectomebase, 'dMRI'];
                app.connectome = connectome;
                app.multitract = 0;
                tractNames = app.connectome;
                %app.SettingTable.Data = repmat([10,5.7], 1, 1);
                app.SettingTable.Data = repmat(["Target",100.0], 1, 1);
            end

            app.hOptimSettingGUI = hOptimSettingGUI;
            
            % Set the Table
            app.SettingTable.RowName = tractNames;
            waitfor(app,'settings_saved',1)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            settings = app.SettingTable.Data;
            setappdata(app.hOptimSettingGUI, 'targetType', settings(:,1));
            setappdata(app.hOptimSettingGUI, 'targetActivation', settings(:,2));      

            % add wait for Save
%            prefs=ea_prefs('');
            
%             vatsettings = prefs.machine.vatsettings;
%             if ~startsWith(vatsettings.butenko_connectome, 'Multi-Tract: ') % Values from the EditField
%                 vatsettings.butenko_axonLength = settings(:,1);
%                 vatsettings.butenko_fiberDiameter = settings(:,2);
%             else % Multi-Tract connectome case, values from appdata
%                 vatsettings.butenko_axonLength = settings(:,1);
%                 vatsettings.butenko_fiberDiameter = settings(:,2);
%             end
            %ea_setprefs('vatsettings',vatsettings);
            app.settings_saved = 1;
            setappdata(app.hOptimSettingGUI, 'settings_saved', 1);

            delete(app)
        end

        % Close request function: SettingforConnectomeUIFigure
        function SettingforConnectomeUIFigureCloseRequest(app, event)
            app.SaveButtonPushed;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingforConnectomeUIFigure = uifigure('Visible', 'off');
            app.SettingforConnectomeUIFigure.Name = 'Setting for Connectome';
            app.SettingforConnectomeUIFigure.CloseRequestFcn = createCallbackFcn(app, @SettingforConnectomeUIFigureCloseRequest, true);
            app.SettingTable = uitable(app.SettingforConnectomeUIFigure);
            app.SettingTable.ColumnName = {'Target/SSE/CSE'; 'Target Activation, %'};
            app.SettingTable.RowName = {};
            app.SettingTable.ColumnEditable = true;
            app.SaveButton = uibutton(app.SettingforConnectomeUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.SettingforConnectomeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_pathway_targets(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingforConnectomeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingforConnectomeUIFigure)
        end
    end
end