classdef ea_addStimSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddStimulationSetUIFigure  matlab.ui.Figure
        SaveButton                 matlab.ui.control.Button
        LeftSideLabel              matlab.ui.control.Label
        RightSideLabel             matlab.ui.control.Label
        NumberofStimulationsEditFieldLeft  matlab.ui.control.NumericEditField
        NumberofStimulationsEditField_2Label  matlab.ui.control.Label
        NumberofStimulationsEditFieldRight  matlab.ui.control.NumericEditField
        NumberofStimulationsEditFieldLabel  matlab.ui.control.Label
        StimulationsLeft           matlab.ui.control.Table
        StimulationsRight          matlab.ui.control.Table
        ContextMenuRight           matlab.ui.container.ContextMenu
        PasteMenuRight             matlab.ui.container.Menu
        ContextMenuLeft            matlab.ui.container.ContextMenu
        PasteMenuLeft              matlab.ui.container.Menu
    end

    
    properties (Access = public)
        numContacts % Number of contacts
        stimFolder % Stimulation folder
        hStimSetCheckbox % Handle of the StimSet object in stimparams GUI
    end
    
    methods (Access = private)
        
        function errorQuitFcn(app)
            app.delete;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, numContacts, stimFolder, hStimSetCheckBox)
            app.numContacts = numContacts;
            app.stimFolder = stimFolder;
            app.hStimSetCheckbox = hStimSetCheckBox;

            ColumnName = strcat('Contact', strsplit(num2str(0:numContacts-1)));
            app.StimulationsRight.ColumnName = ColumnName;
            app.StimulationsLeft.ColumnName = ColumnName;

            stimFile = fullfile(stimFolder, "Current_protocols_0.csv");
            if isfile(stimFile)
                data = table2array(readtable(stimFile,'NumHeaderLines',1));
                if ~isempty(data)
                    if size(data,2) ~= app.numContacts
                        selection = uiconfirm(app.AddStimulationSetUIFigure, ...
                            'Protocol csv file contains different number of contacts!', ...
                            'Error', 'Icon', 'error', ...
                            'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 1);
                        if strcmp(selection, 'Overwrite')
                            app.StimulationsRight.Data = [];
                        else
                            errorQuitFcn(app);
                        end
                    else
                        app.StimulationsRight.Data = data;
                        app.NumberofStimulationsEditFieldRight.Value = size(data,1);
                    end
                end             
            else
                fid = fopen(stimFile,'w');
                fprintf(fid, '%s\n', strjoin(ColumnName,','));
                fclose(fid);
            end

            stimFile = fullfile(stimFolder, "Current_protocols_1.csv");
            if isfile(stimFile)
                data = table2array(readtable(stimFile,'NumHeaderLines',1));
                if ~isempty(data)
                    if size(data,2) ~= app.numContacts
                        selection = uiconfirm(app.AddStimulationSetUIFigure, ...
                            'Protocol csv file contains different number of contacts!', ...
                            'Error', 'Icon', 'error', ...
                            'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 1);
                        if strcmp(selection, 'Overwrite')
                            app.StimulationsLeft.Data = [];
                        else
                            errorQuitFcn(app);
                        end
                    else
                        app.StimulationsLeft.Data = data;
                        app.NumberofStimulationsEditFieldLeft.Value = size(data,1);
                    end
                end 
            else
                fid = fopen(stimFile,'w');
                fprintf(fid, '%s\n', strjoin(ColumnName,','));
                fclose(fid);
            end
        end

        % Value changed function: NumberofStimulationsEditFieldRight
        function NumberofStimulationsEditFieldRightValueChanged(app, event)
            numStimulation = app.NumberofStimulationsEditFieldRight.Value;
            currentData = app.StimulationsRight.Data;
            if numStimulation == size(currentData,1)
                return;
            elseif numStimulation < size(currentData,1)
                newData = currentData(1:numStimulation,:);
            elseif numStimulation > size(currentData,1)
                newData = nan(numStimulation,app.numContacts);
                newData(1:size(currentData,1),:) = currentData;
            end
            app.StimulationsRight.Data = newData;
        end

        % Value changed function: NumberofStimulationsEditFieldLeft
        function NumberofStimulationsEditFieldLeftValueChanged(app, event)
            numStimulation = app.NumberofStimulationsEditFieldLeft.Value;
            currentData = app.StimulationsLeft.Data;
            if numStimulation == size(currentData,1)
                return;
            elseif numStimulation < size(currentData,1)
                newData = currentData(1:numStimulation,:);
            elseif numStimulation > size(currentData,1)
                newData = nan(numStimulation,app.numContacts);
                newData(1:size(currentData,1),:) = currentData;
            end
            app.StimulationsLeft.Data = newData;
        end

        % Menu selected function: PasteMenuRight
        function PasteMenuRightSelected(app, event)
            data = str2num(clipboard('paste'));
            if isempty(data)
                errinfo = 'Failed to convert clipboard content into numbers';
            elseif size(data,2) ~= app.numContacts
                errinfo = 'Number of contacts doesn''t match!';
            end

            if exist('errinfo', 'var')
                uialert(app.AddStimulationSetUIFigure, errinfo, 'Error');
            else
                app.StimulationsRight.Data = data;
            end
        end

        % Menu selected function: PasteMenuLeft
        function PasteMenuLeftSelected(app, event)
            data = str2num(clipboard('paste'));
            if isempty(data)
                errinfo = 'Failed to convert clipboard content into numbers';
            elseif size(data,2) ~= app.numContacts
                errinfo = 'Number of contacts doesn''t match!';
            end

            if exist('errinfo', 'var')
                uialert(app.AddStimulationSetUIFigure, errinfo, 'Error');
            else
                app.StimulationsLeft.Data = data;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            stimRightFile = fullfile(app.stimFolder, "Current_protocols_0.csv");
            if ~isempty(app.StimulationsRight.Data) || ~all(isnan(app.StimulationsRight.Data),'all')
                table = array2table(app.StimulationsRight.Data, 'VariableNames', app.StimulationsRight.ColumnName);
                writetable(table, stimRightFile);
            else
                ea_delete(stimRightFile);
            end

            stimLeftFile = fullfile(app.stimFolder, "Current_protocols_1.csv");
            if ~isempty(app.StimulationsLeft.Data) || ~all(isnan(app.StimulationsLeft.Data),'all')
                table = array2table(app.StimulationsLeft.Data, 'VariableNames', app.StimulationsRight.ColumnName);
                writetable(table, stimLeftFile);
            else
                ea_delete(stimLeftFile);
            end

            if ~isfile(stimRightFile) && ~isfile(stimLeftFile)
                app.hStimSetCheckbox.Value = 0;
            end

            app.delete;
        end

        % Close request function: AddStimulationSetUIFigure
        function AddStimulationSetUIFigureCloseRequest(app, event)
            app.SaveButtonPushed;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddStimulationSetUIFigure = uifigure('Visible', 'off');
            app.AddStimulationSetUIFigure.Name = 'Add Stimulation Set';
            app.AddStimulationSetUIFigure.CloseRequestFcn = createCallbackFcn(app, @AddStimulationSetUIFigureCloseRequest, true);
            app.StimulationsRight = uitable(app.AddStimulationSetUIFigure);
            app.StimulationsRight.ColumnName = {'Contact 0'; 'Contact 1'; 'Contact 2'; 'Contact 3'};
            app.StimulationsRight.ColumnEditable = true;
            app.StimulationsLeft = uitable(app.AddStimulationSetUIFigure);
            app.StimulationsLeft.ColumnName = {'Contact 0'; 'Contact 1'; 'Contact 2'; 'Contact 3'};
            app.StimulationsLeft.ColumnEditable = true;
            app.NumberofStimulationsEditFieldLabel = uilabel(app.AddStimulationSetUIFigure);
            app.NumberofStimulationsEditFieldLabel.Text = 'Number of Stimulations';
            app.NumberofStimulationsEditFieldRight = uieditfield(app.AddStimulationSetUIFigure, 'numeric');
            app.NumberofStimulationsEditFieldRight.ValueChangedFcn = createCallbackFcn(app, @NumberofStimulationsEditFieldRightValueChanged, true);
            app.NumberofStimulationsEditField_2Label = uilabel(app.AddStimulationSetUIFigure);
            app.NumberofStimulationsEditField_2Label.Text = 'Number of Stimulations';
            app.NumberofStimulationsEditFieldLeft = uieditfield(app.AddStimulationSetUIFigure, 'numeric');
            app.NumberofStimulationsEditFieldLeft.ValueChangedFcn = createCallbackFcn(app, @NumberofStimulationsEditFieldLeftValueChanged, true);
            app.RightSideLabel = uilabel(app.AddStimulationSetUIFigure);
            app.RightSideLabel.Text = 'Right Side';
            app.LeftSideLabel = uilabel(app.AddStimulationSetUIFigure);
            app.LeftSideLabel.Text = 'Left Side';
            app.SaveButton = uibutton(app.AddStimulationSetUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ContextMenuRight = uicontextmenu(app.AddStimulationSetUIFigure);
            app.PasteMenuRight = uimenu(app.ContextMenuRight);
            app.PasteMenuRight.MenuSelectedFcn = createCallbackFcn(app, @PasteMenuRightSelected, true);
            app.PasteMenuRight.Text = 'Paste';
            % Assign app.ContextMenuRight
            app.StimulationsRight.ContextMenu = app.ContextMenuRight;
            app.ContextMenuLeft = uicontextmenu(app.AddStimulationSetUIFigure);
            app.PasteMenuLeft = uimenu(app.ContextMenuLeft);
            app.PasteMenuLeft.MenuSelectedFcn = createCallbackFcn(app, @PasteMenuLeftSelected, true);
            app.PasteMenuLeft.Text = 'Paste';
            % Assign app.ContextMenuLeft
            app.StimulationsLeft.ContextMenu = app.ContextMenuLeft;
            % Show the figure after all components are created
            app.AddStimulationSetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_addStimSet(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddStimulationSetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddStimulationSetUIFigure)
        end
    end
end