classdef ea_axonact_connsetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SettingforConnectomeUIFigure  matlab.ui.Figure
        SaveButton                    matlab.ui.control.Button
        SettingTable                  matlab.ui.control.Table
    end

    
    properties (Access = public)
        connectome % Multi-tract connectome
        hVATSettingGUI % hObject of the VAT setting window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, connectome, hVATSettingGUI)
            app.connectome = connectome;
            app.hVATSettingGUI = hVATSettingGUI;
            % Get tract names
            multiTractConnFolder = [ea_getconnectomebase, 'dMRI_MultiTract'];
            connFolder = [multiTractConnFolder, filesep, app.connectome];
            tractNames = strrep(ea_regexpdir(connFolder, '\.mat$', 0), [connFolder, filesep], '');
            tractNames = strrep(tractNames, '.mat', '');
            
            % Set the Table
            app.SettingTable.RowName = tractNames;
            app.SettingTable.Data = repmat([10,5.7], numel(tractNames), 1);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            settings = app.SettingTable.Data;
            setappdata(app.hVATSettingGUI, 'axonLength', settings(:,1));
            setappdata(app.hVATSettingGUI, 'fiberDiameter', settings(:,2));
            delete(app)
        end

        % Close request function: SettingforConnectomeUIFigure
        function SettingforConnectomeUIFigureCloseRequest(app, event)
            app.SaveButtonPushed;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SettingforConnectomeUIFigure = uifigure('Visible', 'off');
            app.SettingforConnectomeUIFigure.Name = 'Setting for Connectome';
            app.SettingforConnectomeUIFigure.CloseRequestFcn = createCallbackFcn(app, @SettingforConnectomeUIFigureCloseRequest, true);
            app.SettingTable = uitable(app.SettingforConnectomeUIFigure);
            app.SettingTable.ColumnName = {'Axon Length (mm)'; 'Fiber Diameter (mm)'};
            app.SettingTable.RowName = {};
            app.SettingTable.ColumnEditable = true;
            app.SaveButton = uibutton(app.SettingforConnectomeUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.SettingforConnectomeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_axonact_connsetting(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SettingforConnectomeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SettingforConnectomeUIFigure)
        end
    end
end