classdef ea_freesurfersetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FreesurferSettingsUIFigure     matlab.ui.Figure
        SaveButton                     matlab.ui.control.Button
        FreeSurferdirectoryButton      matlab.ui.control.Button
        BrainstemCheckBox              matlab.ui.control.CheckBox
        HippocampalSubfieldsAmygdalaCheckBox  matlab.ui.control.CheckBox
        ThalamicStructuresCheckBox     matlab.ui.control.CheckBox
        RunSubcorticalSegmentationsCheckBox  matlab.ui.control.CheckBox
        ImportSurfaceReconAllCheckBox  matlab.ui.control.CheckBox
    end


    methods (Access = private)
        
        function SetSubcorticalsegCheckBox(app)
            if app.ThalamicStructuresCheckBox.Value...
                    || app.HippocampalSubfieldsAmygdalaCheckBox.Value ...
                    || app.BrainstemCheckBox.Value
                app.RunSubcorticalSegmentationsCheckBox.Value = true;
            else
                app.RunSubcorticalSegmentationsCheckBox.Value = false;
            end
        end
        
        function saved = SavePrefs(app)
            saved = 0;
            ea_setprefs('fs.reconall', num2str(app.ImportSurfaceReconAllCheckBox.Value), 'user');
            ea_setprefs('fs.subcorticalseg.thalamus', num2str(app.ThalamicStructuresCheckBox.Value), 'user');
            ea_setprefs('fs.subcorticalseg.hippo_amygdala', num2str(app.HippocampalSubfieldsAmygdalaCheckBox.Value), 'user');
            ea_setprefs('fs.subcorticalseg.brainstem', num2str(app.BrainstemCheckBox.Value), 'user');
            if ~isfolder(app.FreeSurferdirectoryButton.Text)
                uialert(app.FreesurferSettingsUIFigure, 'Please specify FreeSurfer installation directory!', 'Error');
            else
                ea_setprefs('fs.dir', app.FreeSurferdirectoryButton.Text, 'user');
                saved = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            prefs = ea_prefs;
            if ~isempty(prefs.fs.dir)
                app.FreeSurferdirectoryButton.Text = prefs.fs.dir;
                app.FreeSurferdirectoryButton.Tooltip = ['FreeSurfer installed: ', prefs.fs.dir];
            else
                app.FreeSurferdirectoryButton.Tooltip = 'Specify FreeSurfer installation directory';
            end

            app.ImportSurfaceReconAllCheckBox.Value = logical(prefs.fs.reconall);
            app.ThalamicStructuresCheckBox.Value = logical(prefs.fs.subcorticalseg.thalamus);
            app.HippocampalSubfieldsAmygdalaCheckBox.Value = logical(prefs.fs.subcorticalseg.hippo_amygdala);
            app.BrainstemCheckBox.Value = logical(prefs.fs.subcorticalseg.brainstem);

            SetSubcorticalsegCheckBox(app);
        end

        % Value changed function: ThalamicStructuresCheckBox
        function ThalamicStructuresCheckBoxValueChanged(app, event)
            SetSubcorticalsegCheckBox(app);
        end

        % Value changed function: HippocampalSubfieldsAmygdalaCheckBox
        function HippocampalSubfieldsAmygdalaCheckBoxValueChanged(app, event)
            SetSubcorticalsegCheckBox(app);
        end

        % Value changed function: BrainstemCheckBox
        function BrainstemCheckBoxValueChanged(app, event)
            SetSubcorticalsegCheckBox(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if SavePrefs(app)
                delete(app);
            end
        end

        % Close request function: FreesurferSettingsUIFigure
        function FreesurferSettingsUIFigureCloseRequest(app, event)
            if SavePrefs(app)
                delete(app);
            end
        end

        % Button pushed function: FreeSurferdirectoryButton
        function FreeSurferdirectoryButtonPushed(app, event)
            fsdir = uigetdir;
            if ischar(fsdir)
                app.FreeSurferdirectoryButton.Text = fsdir;
                app.FreeSurferdirectoryButton.Tooltip = ['FreeSurfer installed: ', fsdir];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FreesurferSettingsUIFigure = uifigure('Visible', 'off');
            app.FreesurferSettingsUIFigure.Name = 'Freesurfer Settings';
            app.FreesurferSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @FreesurferSettingsUIFigureCloseRequest, true);
            app.ImportSurfaceReconAllCheckBox = uicheckbox(app.FreesurferSettingsUIFigure);
            app.ImportSurfaceReconAllCheckBox.Text = 'Import Surface (Recon-All)';
            app.ImportSurfaceReconAllCheckBox.Value = true;
            app.RunSubcorticalSegmentationsCheckBox = uicheckbox(app.FreesurferSettingsUIFigure);
            app.RunSubcorticalSegmentationsCheckBox.Text = 'Run Subcortical Segmentations';
            app.RunSubcorticalSegmentationsCheckBox.Value = true;
            app.ThalamicStructuresCheckBox = uicheckbox(app.FreesurferSettingsUIFigure);
            app.ThalamicStructuresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ThalamicStructuresCheckBoxValueChanged, true);
            app.ThalamicStructuresCheckBox.Text = 'Thalamic Structures';
            app.ThalamicStructuresCheckBox.Value = true;
            app.HippocampalSubfieldsAmygdalaCheckBox = uicheckbox(app.FreesurferSettingsUIFigure);
            app.HippocampalSubfieldsAmygdalaCheckBox.ValueChangedFcn = createCallbackFcn(app, @HippocampalSubfieldsAmygdalaCheckBoxValueChanged, true);
            app.HippocampalSubfieldsAmygdalaCheckBox.Text = 'Hippocampal Subfields & Amygdala';
            app.BrainstemCheckBox = uicheckbox(app.FreesurferSettingsUIFigure);
            app.BrainstemCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrainstemCheckBoxValueChanged, true);
            app.BrainstemCheckBox.Text = 'Brainstem';
            app.FreeSurferdirectoryButton = uibutton(app.FreesurferSettingsUIFigure, 'push');
            app.FreeSurferdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FreeSurferdirectoryButtonPushed, true);
            app.FreeSurferdirectoryButton.Text = 'FreeSurfer directory';
            app.SaveButton = uibutton(app.FreesurferSettingsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.FreesurferSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_freesurfersetting

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FreesurferSettingsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FreesurferSettingsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FreesurferSettingsUIFigure)
        end
    end
end