classdef ea_rename_pts_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RenamePatientsUIFigure     matlab.ui.Figure
        StatusImage                matlab.ui.control.Image
        CancelButton               matlab.ui.control.Button
        RenameButton               matlab.ui.control.Button
        SubjIDTable                matlab.ui.control.Table
        NumberingPanel             matlab.ui.container.Panel
        StartNumberEditFieldLabel  matlab.ui.control.Label
        ZeroPaddingEditField       matlab.ui.control.NumericEditField
        ZeroPaddingEditFieldLabel  matlab.ui.control.Label
        StartNumberEditField       matlab.ui.control.NumericEditField
        NumberingBaseText          matlab.ui.control.EditField
        NumberingBaseTextOption    matlab.ui.control.DropDown
        NumberingPostion           matlab.ui.control.DropDown
        NumberingCheckBox          matlab.ui.control.CheckBox
        ReplacePanel               matlab.ui.container.Panel
        WithTextEditField          matlab.ui.control.EditField
        WithEditFieldLabel         matlab.ui.control.Label
        ReplaceTheTextEditField    matlab.ui.control.EditField
        ReplaceCheckBox            matlab.ui.control.CheckBox
        InsertPanel                matlab.ui.container.Panel
        InsertAtEndEditField       matlab.ui.control.EditField
        InsertEndCheckBox          matlab.ui.control.CheckBox
        InsertAtStartEditField     matlab.ui.control.EditField
        InsertStartCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        BIDSRoot
        FullList
    end
    
    methods (Access = private)
        
        function updateNewSubjId(app)
            isRenamed = 0;
            newSubjId = app.SubjIDTable.Data.oldSubjId;

            if app.InsertStartCheckBox.Value
                if ~isempty(app.InsertAtStartEditField.Value)
                    isRenamed = 1;
                    newSubjId = strcat(app.InsertAtStartEditField.Value, newSubjId);
                end
            end

            if app.InsertEndCheckBox.Value
                if ~isempty(app.InsertAtEndEditField.Value)
                    isRenamed = 1;
                    newSubjId = strcat(newSubjId, app.InsertAtEndEditField.Value);
                end
            end

            if app.ReplaceCheckBox.Value
                if ~isempty(app.ReplaceTheTextEditField.Value)
                    isRenamed = 1;
                    newSubjId = regexprep(newSubjId, app.ReplaceTheTextEditField.Value, app.WithTextEditField.Value);
                end
            end

            if app.NumberingCheckBox.Value
                isRenamed = 1;
                switch app.NumberingBaseTextOption.Value
                    case 'Original Filename'
                        app.NumberingBaseText.Visible = 'off';
                        app.NumberingBaseText.Value = '';
                    case 'Custom Filename'
                        app.NumberingBaseText.Visible = 'on';
                end

                if isempty(app.NumberingBaseText.Value)
                    baseName = newSubjId;
                else
                    baseName = app.NumberingBaseText.Value;
                end

                numDecimalSubjId = numel(num2str(numel(newSubjId)));
                if app.ZeroPaddingEditField.Value == 0
                    app.StartNumberEditField.ValueDisplayFormat = '%d';
                elseif app.ZeroPaddingEditField.Value < numDecimalSubjId
                    app.ZeroPaddingEditField.Value = numDecimalSubjId;
                    app.StartNumberEditField.ValueDisplayFormat = ['%0', num2str(numDecimalSubjId), 'd'];
                else
                    app.StartNumberEditField.ValueDisplayFormat = ['%0', num2str(app.ZeroPaddingEditField.Value), 'd'];
                end

                numbers = app.StartNumberEditField.Value : app.StartNumberEditField.Value + numel(newSubjId) - 1;
                numbers = arrayfun(@(x) num2str(x, app.StartNumberEditField.ValueDisplayFormat), numbers, 'Uni', 0)';

                switch app.NumberingPostion.Value
                    case 'After'
                        newSubjId = strcat(baseName, numbers);
                    case 'Before'
                        newSubjId = strcat(numbers, baseName);
                end
            end
     
            app.SubjIDTable.Data.newSubjId = newSubjId;

            if isRenamed
                [~, uniqueIdx] = unique(newSubjId);
                dupIdx = find(ismember(newSubjId, newSubjId(setdiff(1:numel(newSubjId), uniqueIdx))));
                conflictIdx = unique([dupIdx, find(ismember(newSubjId, app.FullList))]);
                addStyle(app.SubjIDTable, uistyle('FontColor', 'red'), 'row', conflictIdx);
                addStyle(app.SubjIDTable, uistyle('FontColor', 'black'), 'column', 1);
            else
                addStyle(app.SubjIDTable, uistyle('FontColor', '#139FFF'), 'column', 2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, BIDSRoot, oldSubjId)
            app.BIDSRoot = BIDSRoot;
            bids = BIDSFetcher(BIDSRoot);
            app.FullList = bids.subjId;
            numDecimalSubjId = numel(num2str(numel(oldSubjId)));
            app.ZeroPaddingEditField.Value = numDecimalSubjId;
            app.StartNumberEditField.ValueDisplayFormat = ['%0', num2str(numDecimalSubjId), 'd'];
            app.SubjIDTable.Data = cell2table([oldSubjId, oldSubjId], 'VariableNames', {'oldSubjId', 'newSubjId'});
            addStyle(app.SubjIDTable, uistyle('FontColor', '#139FFF'), 'column', 2);
        end

        % Value changed function: InsertStartCheckBox
        function InsertStartCheckBoxValueChanged(app, event)
            if app.InsertStartCheckBox.Value
                app.NumberingCheckBox.Value = 0;
            end
            updateNewSubjId(app);
        end

        % Value changed function: InsertAtStartEditField
        function InsertAtStartEditFieldValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: InsertEndCheckBox
        function InsertEndCheckBoxValueChanged(app, event)
            if app.InsertEndCheckBox.Value
                app.NumberingCheckBox.Value = 0;
            end
            updateNewSubjId(app);
        end

        % Value changed function: InsertAtEndEditField
        function InsertAtEndEditFieldValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: ReplaceCheckBox
        function ReplaceCheckBoxValueChanged(app, event)
            if app.ReplaceCheckBox.Value
                app.NumberingCheckBox.Value = 0;
            end
            updateNewSubjId(app);
        end

        % Value changed function: ReplaceTheTextEditField
        function ReplaceTheTextEditFieldValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: WithTextEditField
        function WithTextEditFieldValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: NumberingCheckBox
        function NumberingCheckBoxValueChanged(app, event)
            if app.NumberingCheckBox.Value
                app.InsertStartCheckBox.Value = 0;
                app.InsertEndCheckBox.Value = 0;
                app.ReplaceCheckBox.Value = 0;
            end
            updateNewSubjId(app);
        end

        % Value changed function: NumberingPostion
        function NumberingPostionValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: NumberingBaseTextOption
        function NumberingBaseTextOptionValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: NumberingBaseText
        function NumberingBaseTextValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: StartNumberEditField
        function StartNumberEditFieldValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Value changed function: ZeroPaddingEditField
        function ZeroPaddingEditFieldValueChanged(app, event)
            updateNewSubjId(app);
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            ea_busyaction('on', app.StatusImage);
            renameFile = fullfile(app.BIDSRoot, ['Rename_', char(datetime('now','Format','yyyyMMddHHmmss')), '.csv']);
            writetable(app.SubjIDTable.Data, renameFile);
            ea_cprintf('CmdWinWarnings', 'Renaming list saved to: %s\n', renameFile);
            ea_rename_pts(app.BIDSRoot, app.SubjIDTable.Data.oldSubjId, app.SubjIDTable.Data.newSubjId);
            setappdata(groot, 'newSubjId', app.SubjIDTable.Data.newSubjId);
            ea_busyaction('off', app.StatusImage);
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Close request function: RenamePatientsUIFigure
        function RenamePatientsUIFigureCloseRequest(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RenamePatientsUIFigure = uifigure('Visible', 'off');
            app.RenamePatientsUIFigure.Name = 'Rename Patient(s)  [a-zA-Z0-9]';
            app.RenamePatientsUIFigure.CloseRequestFcn = createCallbackFcn(app, @RenamePatientsUIFigureCloseRequest, true);
            app.InsertPanel = uipanel(app.RenamePatientsUIFigure);
            app.InsertPanel.Title = 'Insert';
            app.InsertStartCheckBox = uicheckbox(app.InsertPanel);
            app.InsertStartCheckBox.ValueChangedFcn = createCallbackFcn(app, @InsertStartCheckBoxValueChanged, true);
            app.InsertStartCheckBox.Tag = 'InsertStartCheckBox';
            app.InsertStartCheckBox.Text = 'Insert At Start';
            app.InsertAtStartEditField = uieditfield(app.InsertPanel, 'text');
            app.InsertAtStartEditField.InputType = 'alphanumerics';
            app.InsertAtStartEditField.ValueChangedFcn = createCallbackFcn(app, @InsertAtStartEditFieldValueChanged, true);
            app.InsertAtStartEditField.Tag = 'InsertAtStartEditField';
            app.InsertEndCheckBox = uicheckbox(app.InsertPanel);
            app.InsertEndCheckBox.ValueChangedFcn = createCallbackFcn(app, @InsertEndCheckBoxValueChanged, true);
            app.InsertEndCheckBox.Tag = 'InsertEndCheckBox';
            app.InsertEndCheckBox.Text = 'Insert At End';
            app.InsertAtEndEditField = uieditfield(app.InsertPanel, 'text');
            app.InsertAtEndEditField.InputType = 'alphanumerics';
            app.InsertAtEndEditField.ValueChangedFcn = createCallbackFcn(app, @InsertAtEndEditFieldValueChanged, true);
            app.InsertAtEndEditField.Tag = 'InsertAtEndEditField';
            app.ReplacePanel = uipanel(app.RenamePatientsUIFigure);
            app.ReplacePanel.Title = 'Replace';
            app.ReplaceCheckBox = uicheckbox(app.ReplacePanel);
            app.ReplaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReplaceCheckBoxValueChanged, true);
            app.ReplaceCheckBox.Tag = 'ReplaceCheckBox';
            app.ReplaceCheckBox.Text = 'Replace The Text';
            app.ReplaceTheTextEditField = uieditfield(app.ReplacePanel, 'text');
            app.ReplaceTheTextEditField.InputType = 'alphanumerics';
            app.ReplaceTheTextEditField.ValueChangedFcn = createCallbackFcn(app, @ReplaceTheTextEditFieldValueChanged, true);
            app.ReplaceTheTextEditField.Tag = 'ReplaceTheTextEditField';
            app.ReplaceTheTextEditField.Tooltip = {'Plain string or regular expression.'};
            app.WithEditFieldLabel = uilabel(app.ReplacePanel);
            app.WithEditFieldLabel.Text = 'With';
            app.WithTextEditField = uieditfield(app.ReplacePanel, 'text');
            app.WithTextEditField.InputType = 'alphanumerics';
            app.WithTextEditField.ValueChangedFcn = createCallbackFcn(app, @WithTextEditFieldValueChanged, true);
            app.WithTextEditField.Tag = 'WithTextEditField';
            app.NumberingPanel = uipanel(app.RenamePatientsUIFigure);
            app.NumberingPanel.Title = 'Numbering';
            app.NumberingCheckBox = uicheckbox(app.NumberingPanel);
            app.NumberingCheckBox.ValueChangedFcn = createCallbackFcn(app, @NumberingCheckBoxValueChanged, true);
            app.NumberingCheckBox.Tag = 'NumberingCheckBox';
            app.NumberingCheckBox.Text = 'Numbering';
            app.NumberingPostion = uidropdown(app.NumberingPanel);
            app.NumberingPostion.Items = {'After', 'Before'};
            app.NumberingPostion.ValueChangedFcn = createCallbackFcn(app, @NumberingPostionValueChanged, true);
            app.NumberingPostion.Tag = 'NumberingPostion';
            app.NumberingPostion.Value = 'After';
            app.NumberingBaseTextOption = uidropdown(app.NumberingPanel);
            app.NumberingBaseTextOption.Items = {'Original Filename', 'Custom Filename'};
            app.NumberingBaseTextOption.ValueChangedFcn = createCallbackFcn(app, @NumberingBaseTextOptionValueChanged, true);
            app.NumberingBaseTextOption.Tag = 'NumberingBaseTextOption';
            app.NumberingBaseTextOption.Value = 'Original Filename';
            app.NumberingBaseText = uieditfield(app.NumberingPanel, 'text');
            app.NumberingBaseText.ValueChangedFcn = createCallbackFcn(app, @NumberingBaseTextValueChanged, true);
            app.NumberingBaseText.Tag = 'NumberingBaseText';
            app.NumberingBaseText.Visible = 'off';
            app.StartNumberEditField = uieditfield(app.NumberingPanel, 'numeric');
            app.StartNumberEditField.Limits = [1 Inf];
            app.StartNumberEditField.ValueDisplayFormat = '%.0f';
            app.StartNumberEditField.ValueChangedFcn = createCallbackFcn(app, @StartNumberEditFieldValueChanged, true);
            app.StartNumberEditField.Value = 1;
            app.ZeroPaddingEditFieldLabel = uilabel(app.NumberingPanel);
            app.ZeroPaddingEditFieldLabel.Text = 'Zero Padding';
            app.ZeroPaddingEditField = uieditfield(app.NumberingPanel, 'numeric');
            app.ZeroPaddingEditField.Limits = [0 Inf];
            app.ZeroPaddingEditField.ValueDisplayFormat = '%.0f';
            app.ZeroPaddingEditField.ValueChangedFcn = createCallbackFcn(app, @ZeroPaddingEditFieldValueChanged, true);
            app.ZeroPaddingEditField.Tooltip = {'For example, 4 will generate numbers in the form of 0001 (4 decimals), while 0 means no leading zeros.'};
            app.StartNumberEditFieldLabel = uilabel(app.NumberingPanel);
            app.StartNumberEditFieldLabel.Text = 'Start Number';
            app.SubjIDTable = uitable(app.RenamePatientsUIFigure);
            app.SubjIDTable.ColumnName = {'Old ID'; 'New ID'};
            app.SubjIDTable.RowName = {};
            app.SubjIDTable.ColumnEditable = [false true];
            app.SubjIDTable.Tooltip = {'Conflicting patient IDs will be shown in red.'};
            app.RenameButton = uibutton(app.RenamePatientsUIFigure, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename';
            app.CancelButton = uibutton(app.RenamePatientsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.StatusImage = uiimage(app.RenamePatientsUIFigure);
            app.StatusImage.Tag = 'busystatus';
            app.StatusImage.Visible = 'off';
            % Show the figure after all components are created
            app.RenamePatientsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_rename_pts_gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RenamePatientsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RenamePatientsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RenamePatientsUIFigure)
        end
    end
end