classdef ea_update_pts_nifti < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UpdatePanel            matlab.ui.container.Panel
        HelpText               matlab.ui.control.Label
        DeleteFileButton       matlab.ui.control.Button
        ReplaceFileButton      matlab.ui.control.Button
        OpenDirectoryButton    matlab.ui.control.Button
        FilePath               matlab.ui.control.TextArea
        FilePathTextAreaLabel  matlab.ui.control.Label
        TreePanel              matlab.ui.container.Panel
        Tree                   matlab.ui.container.Tree
    end

    
    properties (Access = private)
        bids
        subjId
    end
    
    methods (Access = private)
        
        function UpdateGUIStatus(app)
            selectedNodes = app.Tree.SelectedNodes;
            if ismember(selectedNodes.Text, {'preopAnat', 'postopAnat'})
                selectedNodes = app.Tree.SelectedNodes.Children(1);
                app.Tree.SelectedNodes = selectedNodes;
            end

            app.HelpText.Text = GetHelpText(app);

            if ~isempty(selectedNodes.NodeData)
                app.FilePath.Value = selectedNodes.NodeData;
                app.FilePath.Enable = "on";
                app.OpenDirectoryButton.Enable = "on";
                app.ReplaceFileButton.Enable = "on";
                app.DeleteFileButton.Enable = "on";
                app.DeleteFileButton.Text = "Delete File";
                if ismember(selectedNodes.Text, {'coreg', 'coregTonemap', 'norm', 'normTonemap'})
                    app.ReplaceFileButton.Enable = "off";
                else
                    app.ReplaceFileButton.Enable = "on";
                end
            else
                app.FilePath.Value = '';
                app.FilePath.Enable = "off";
                app.OpenDirectoryButton.Enable = "off";
                app.ReplaceFileButton.Enable = "off";
                app.DeleteFileButton.Enable = "on";
                app.DeleteFileButton.Text = "Delete Files";
            end
        end
        
        function helpText = GetHelpText(app)
            selectedNodes = app.Tree.SelectedNodes;
            if ismember(selectedNodes.Text, {'raw', 'preproc'})
                % pipeline node selected
                pipeline = selectedNodes.Text;
                modality = selectedNodes.Parent.Text;
                helpText = sprintf('<b><i>Replace</i></b> or <b><i>Delete</i></b> "<b>%s</b>" image of "<b>%s</b>"?', pipeline, modality);
            elseif ismember(selectedNodes.Text, {'coreg', 'coregTonemap', 'norm', 'normTonemap'})
                % pipeline node selected
                pipeline = selectedNodes.Text;
                modality = selectedNodes.Parent.Text;
                helpText = sprintf('<b><i>Delete</i></b> "<b>%s</b>" image of "<b>%s</b>"?', pipeline, modality);
            else
                % modality node selected
                pipeline = ['"<b><i>', strjoin({selectedNodes.Children.Text}, '</i></b>", "<b><i>'), '</i></b>"'];
                modality = selectedNodes.Text;
                helpText = sprintf('<b><i>Delete</i></b> %s images of "<b>%s</b>"?', pipeline, modality);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bids, subjId, treeStruct)
            app.bids = bids;
            app.subjId = subjId;
            ea_struct2tree(treeStruct, app.Tree, expandAll=true);
            app.Tree.SelectedNodes = app.Tree.Children(1).Children(1).Children(1);
            UpdateGUIStatus(app);
            app.FilePath.Value = app.Tree.SelectedNodes.NodeData;
            if ismember(app.Tree.SelectedNodes.Text, {'coreg', 'coregTonemap', 'norm', 'normTonemap'})
                app.ReplaceFileButton.Enable = "off";
            else
                app.ReplaceFileButton.Enable = "on";
            end
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            UpdateGUIStatus(app);
        end

        % Button pushed function: OpenDirectoryButton
        function OpenDirectoryButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            ea_opendir(selectedNodes.NodeData, chdir=false);
        end

        % Button pushed function: ReplaceFileButton
        function ReplaceFileButtonPushed(app, event)
            [fileName, fileDir] = uigetfile({'*.nii'; '*.nii.gz'}, 'Select New NIfTI File');
            figure(app.UIFigure);

            if ~ischar(fileName)
                return;
            end

            newFile = fullfile(fileDir, fileName);
            targetFile = app.Tree.SelectedNodes.NodeData;

            if strcmp(newFile, targetFile)
                ea_cprintf('CmdWinWarnings', 'Selected file and target file are the same!\n');
                return;
            end

            if endsWith(newFile, '.nii.gz') && endsWith(targetFile, '.nii')
                gunzip(newFile);
                movefile(erase(newFile, '.gz' + textBoundary('end')), targetFile);
            elseif endsWith(newFile, '.nii') && endsWith(targetFile, '.nii.gz')
                gzip(newFile);
                movefile([newFile, '.gz'], targetFile);
            else
                copyfile(newFile, targetFile);
            end
        end

        % Button pushed function: DeleteFileButton
        function DeleteFileButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;

            if ~isempty(selectedNodes.NodeData)
                delete(selectedNodes.NodeData);
            else
                answer = uiconfirm(app.UIFigure,...
                    ['Are you sure you want to delete all "', selectedNodes.Text, '" images?'],...
                    'Confirm Delete',...
                    'Options', {'Yes', 'Cancel'},...
                    'DefaultOption', 2, 'CancelOption', 2);
                switch answer
                    case 'Yes'
                        ea_delete({selectedNodes.Children.NodeData});

                        % Delete further derivatives
                        subjDir = app.bids.getLeadDBSDirs(app.subjId);  
                        searchPattern = ['(?-i)', selectedNodes.Text];
                        coregFiles = ea_regexpdir(subjDir.coregDir, searchPattern);
                        normFiles = ea_regexpdir(subjDir.normDir, searchPattern);
                        files = [coregFiles; normFiles];
                        
                        % In case subjId contains nodeName
                        deleteInd = ~cellfun(@isempty, regexp(erase(files, ['sub-', app.subjId]), searchPattern, 'once'));

                        ea_delete(files(deleteInd));
                    case 'Cancel'
                        return;
                end
            end

            ea_genrawimagesjson(app.bids.datasetDir, app.subjId);

            nodeName = selectedNodes.Text;
            neighborNodeName = {selectedNodes.Parent.Children.Text};
            nodeInd = find(ismember({selectedNodes.Parent.Children.Text}, nodeName));
            if nodeInd+1 <= length(neighborNodeName)
                app.Tree.SelectedNodes = selectedNodes.Parent.Children(nodeInd+1);
            elseif nodeInd-1 >= 1
                app.Tree.SelectedNodes = selectedNodes.Parent.Children(nodeInd-1);
            else
                app.Tree.SelectedNodes = selectedNodes.Parent;
            end
            app.TreeSelectionChanged;

            delete(selectedNodes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Replace/Delete NIfTIs of Selected Patient';
            app.TreePanel = uipanel(app.UIFigure);
            app.Tree = uitree(app.TreePanel);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.UpdatePanel = uipanel(app.UIFigure);
            app.FilePathTextAreaLabel = uilabel(app.UpdatePanel);
            app.FilePathTextAreaLabel.Text = 'File Path';
            app.FilePath = uitextarea(app.UpdatePanel);
            app.FilePath.Editable = 'off';
            app.OpenDirectoryButton = uibutton(app.UpdatePanel, 'push');
            app.OpenDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDirectoryButtonPushed, true);
            app.OpenDirectoryButton.Text = 'Open Directory';
            app.ReplaceFileButton = uibutton(app.UpdatePanel, 'push');
            app.ReplaceFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceFileButtonPushed, true);
            app.ReplaceFileButton.Text = 'Replace File';
            app.DeleteFileButton = uibutton(app.UpdatePanel, 'push');
            app.DeleteFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFileButtonPushed, true);
            app.DeleteFileButton.Text = 'Delete File';
            app.HelpText = uilabel(app.UpdatePanel);
            app.HelpText.Interpreter = 'html';
            app.HelpText.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ea_update_pts_nifti(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end