classdef lead_import < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeadMigrateUIFigure           matlab.ui.Figure
        RecentDatasetsDropDown        matlab.ui.control.DropDown
        RerunButton                   matlab.ui.control.Button
        RegeneraterawimagejsonButton  matlab.ui.control.Button
        BusyLamp                      matlab.ui.control.Lamp
        SelectoutputdatasetdirectoryButton  matlab.ui.control.Button
        ConvertDICOMdatatoBIDScompliantdatasetCheckBox  matlab.ui.control.CheckBox
        SelectpatientsButton          matlab.ui.control.Button
        MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox  matlab.ui.control.CheckBox
        ConverttoDicomusingDropDown   matlab.ui.control.DropDown
        ConverttoDicomusingDropDownLabel  matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        source_folder % Description
        output_folder
        dicom_source_folder
        input_label
        output_label
        flag
        close_flag
        handles
        options
        subjID = {};
        doOnlyRaw = 0;
        notMigrated = {};
        
    end
    
    methods (Access = public)
        function DropFcn(app, event, ~)
            switch event.DropType
                case 'file'
                    app.source_folder = event.Data;
                case 'string'
                    app.source_folder = {event.Data};
            end
            nonexist = cellfun(@(x) ~exist(x, 'dir'), patdir);
            if any(nonexist)
                fprintf('\nExcluded non-existent/invalid folder:\n');
                cellfun(@disp, patdir(nonexist));
                fprintf('\n');
                app.source_folder(nonexist) = [];
            end
            enablerules(app,app.source_folder{1});
        end
        
        function result = checkIfOneExist(app,cell_of_filenames,str_to_match)
            result = 0;
            %regexp returns an array of cells ({0} = does not match str,
            %{[1]} = matches the str).
            %isempty returns an array of 1 when the regexp is not
            %satisfied. Inverse of the cell fun will return 1 if the regexp
            %matches the str, and 0 if regexp does not match the str
            %any will check if there is atleast one pattern has matched
            if any(~cellfun('isempty',regexpi(cell_of_filenames,str_to_match)))
                result = 1;
            end
        end
       
        function result = checkIfNoneExist(app,cell_of_filenames,str_to_match)
            output = checkIfOneExist(app,cell_of_filenames,str_to_match);
            result = ~output;
        end
        
        function result = checkIfOnlyExist(app,cell_of_filenames,str_to_match)
            result = 0;
            if all(~cellfun('isempty',regexpi(cell_of_filenames,str_to_match)))
                result = 1;
            end
        end
        
        function count = checkValidity(app,folders)
            
            count=0;
            for i=1:length(folders)
                path=folders{i};
                if isempty(path)
                    return;
                end
                found = ea_checkSpecialChars(path);
                [~,dataset_folder,~] = fileparts(path);
                is_underscore = regexp(dataset_folder,'.*_.*','match');
                if ~isempty(is_underscore)
                    ea_warndlg("It looks like you have underscores in your dataset folder name: %s. Please remove it and try again...",dataset_folder)
                end
                if found || ~isempty(is_underscore)
                   count=count+1;
                end
            end
            
        end


        function is_member_flag = ea_ismember(app,cellA,stringB)
            %%this function returns a boolean value instead of a cell
            is_member_flag = 0;
            if ~iscell(cellA)
                ea_error("You have to supply a cell as the first argument");
            end
            if ~isstring(stringB)
                ea_error("You have to supply a string as the second argument");
            end
            cell_output = ismember(cellA,stringB);
            boolen_value = find(cell_output);
            if length(boolen_value) >= 1
                is_member_flag = 1;
            end

            return
        end

        function results = enablerules(app,selection)                   
            [filepath, filename] = fileparts(selection);
            app.subjID{1} = regexprep(filename, '[\W_]', '');
            subfolder = dir_without_dots(selection);
            subfolder = {subfolder.name};
            results = 'pass';
            % detection function: if the patient has derivatives and
            % raw data, but not in BIDS format then it should be migrated.
            if endsWith(filepath, 'sourcedata')
                % Input is sourcedata folder under a bids dataset 
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                
                app.doOnlyRaw = 0;
                app.dicom_source_folder{1} = selection;
                
            % BIDS Compliant raw data nifti files are available! in this case,
            % dcm -> bids conversion should require special handling. 
            % Only the conversion from nifti -> lead bids will be performed
            elseif any(ismember(subfolder,'rawdata')) || any(ismember(subfolder,'sourcedata'))
                prefs_dir = fullfile(selection,'derivatives','leaddbs',app.subjID{1},'prefs');
                if ~isfile(fullfile(prefs_dir,[app.subjID{1},'_','desc-rawimages.json']))
                    app.RegeneraterawimagejsonButton.Visible = 'on';
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                    app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                    app.output_folder = {selection};
                    op_label(app,'dest');
                end

            elseif checkIfOneExist(app,subfolder,'^glanat.nii$') && checkIfOneExist(app,subfolder,'anat.*.nii') && checkIfNoneExist(app,subfolder,'DICOM') && checkIfNoneExist(app,subfolder,'.*.dcm')
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                
                app.doOnlyRaw = 0;
 
            % only raw nifti available, no dicom is available. Migrate
            % will be done, but only to move & rename the raw files
            elseif checkIfOnlyExist(app,subfolder,'.*.nii||.*.nii.gz') && ~any(ismember(subfolder,'ea_ui.mat'))
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                
                app.doOnlyRaw = 1;

            % both dicom & derivatives available. Requires special
            % handling in the migrate code.
            elseif any(ismember(subfolder,'ea_ui.mat')) && checkIfOneExist(app,subfolder,'^glanat*.nii$') && checkIfOneExist(app,subfolder,'dicom||.*.dcm')
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                
                app.doOnlyRaw = 0;
                if isfolder(fullfile(selection,'DICOM'))
                    app.dicom_source_folder{1} = fullfile(selection,'DICOM');
                else
                    if ~exist(fullfile(selection,'DICOM'),'dir')
                        mkdir(fullfile(selection,'DICOM'))
                    end
                    for i=1:length(subfolder)
                        if regexp(subfolder{i},'.*.dcm')
                            movefile(fullfile(selection,subfolder{i}),fullfile(selection,'DICOM'))
                        end
                    end
                end
                app.dicom_source_folder{1} = fullfile(selection,'DICOM');
            elseif any(ismember(subfolder,'ea_ui.mat')) && checkIfOneExist(app,subfolder,'[^anat]*.nii$') 
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;

                app.doOnlyRaw = 1;

            elseif checkIfOneExist(app,subfolder,'.*.nii||.*.nii.gz') && checkIfOneExist(app,subfolder,'dicom||.*.dcm') % raw nifti + dicom
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                
                app.doOnlyRaw = 1;
                if isfolder(fullfile(selection,'DICOM'))
                    app.dicom_source_folder{1} = fullfile(selection,'DICOM');
                else
                    if ~exist(fullfile(selection,'DICOM'),'dir')
                        mkdir(fullfile(selection,'DICOM'))
                    end
                    for i=1:length(subfolder)
                        if regexp(subfolder{i},'.*.dcm')
                            movefile(fullfile(selection,subfolder{i}),fullfile(selection,'DICOM'))
                        end
                    end
                    app.dicom_source_folder{1} = fullfile(selection,'DICOM');
                end

            % only DICOM files available
            elseif checkIfOneExist(app,subfolder,'dicom*||.*.dcm')
                %todo: remove support for .dcm files inside
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                
                app.doOnlyRaw = 0;
                if ~isempty(ea_regexpdir(selection, 'DICOM', 0, 'd'))
                    app.dicom_source_folder = ea_regexpdir(selection, 'DICOM', 0, 'd');
                    [~,dicom_folder,~] = fileparts(app.dicom_source_folder);
                    if ~strcmp(dicom_folder,'DICOM')
                        
                        app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                        app.doOnlyRaw = 0;
                        folders_inside = dir_without_dots(app.dicom_source_folder{1});
                        subfolders_inside = folders_inside([folders_inside.isdir]); %only get the folders, since they have to be moved to DICOM]
                        
                        if ~exist(fullfile(selection,'DICOM'),'dir')
                            mkdir(fullfile(selection,'DICOM'))
                        end
                        subfolder_names = {subfolders_inside.name};
                        for i=1:length(subfolder_names)
                            movefile(fullfile(selection,dicom_folder,subfolder_names{i}), fullfile(selection,'DICOM',subfolder_names{i}))
                        end
                        app.dicom_source_folder{1} = fullfile(selection,'DICOM');

                    else
                        if ~exist(fullfile(selection,'DICOM'),'dir')
                            mkdir(fullfile(selection,'DICOM'))
                        end
                        for i=1:length(subfolder)
                            if regexp(subfolder{i},'.*.dcm')
                                movefile(fullfile(selection,subfolder{i}),fullfile(selection,'DICOM'))
                            end
                        end
                        app.dicom_source_folder{1} = fullfile(selection,'DICOM');
                    end
                end

            elseif checkIfOneExist(app,subfolder,'^ea_.*.mat$') || ea_ismember(app,subfolder,"current_headmodel") || ea_ismember(app,subfolder,"stimulation") % for detached files only, experimental
                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                
                app.doOnlyRaw = 0;
                

            % additional check for subfolders of dicom. Not just
            % folders inside which there might be dicom files.
            elseif ~isfolder(fullfile(selection,'DICOM')) && ~isfolder(fullfile(selection,'dicom'))
                is_dicom_inside = dir(fullfile(selection,'**/*.dcm'));
                if ~isempty(is_dicom_inside)
              
                    app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                    app.doOnlyRaw = 0;
                    folders_inside = dir_without_dots(selection);
                    subfolders_inside = folders_inside([folders_inside.isdir]); %only get the folders, since they have to be moved to DICOM]
                    if ~exist(fullfile(selection,'DICOM'),'dir')
                        mkdir(fullfile(selection,'DICOM'))
                    end
                    subfolder_names = {subfolders_inside.name};
                    for i=1:length(subfolder_names)
                        movefile(fullfile(selection,subfolder_names{i}), fullfile(selection,'DICOM',subfolder_names{i}))
                    end
                    app.dicom_source_folder{1} = fullfile(selection,'DICOM');

                else
                    ea_warndlg('%s: unable to detect the type of processing required, skipping for now', app.subjID{1});
                    results = 'skip';
                end

            else
                results = 'fail';%case not handled
            end
        end

        function op_label(app,flag,source_pt)
            if strcmp(flag,'source')
                app.SelectpatientsButton.Text = source_pt;
                app.SelectpatientsButton.FontColor = 'g';
            end
            if strcmp(flag,'multsource')
                app.SelectpatientsButton.Text = ['Loaded Multiple Patients(' num2str(length(app.source_folder)) ')'];
                app.SelectpatientsButton.FontColor = 'g';
                app.SelectpatientsButton.FontColor = 'g';
                Label = uilabel(app.LeadMigrateUIFigure,'Text','Loaded multiple patients, Import will automatically detect the dataset type','Position',[100,189,450,22]);
            end
            if strcmp(flag,'dest')
                app.SelectoutputdatasetdirectoryButton.Text = app.output_folder{1};
                app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
            end
        end

        function reset_rules(app)
            app.doOnlyRaw = 0;
            app.flag = '';
            app.RunButton.Enable = 'on';
            app.ConverttoDicomusingDropDown.Enable = 'on';
            app.ConverttoDicomusingDropDownLabel.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'on';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
            app.SelectpatientsButton.Text = '';
        end


        function actualRun(app,dicom_conv,doMigrate,run_prop)

            if strcmp(app.flag,'onlyMigrate')
                f = waitbar(0,'Migrating dataset..');
                waitbar(0.33,f,'Migrating dataset...');
                if dicom_conv && ~isempty(app.dicom_source_folder) && ~app.doOnlyRaw
                    ea_legacy2bids(run_prop.source,run_prop.dest,1,app.dicom_source_folder,1,0);
                elseif ~dicom_conv && doMigrate && app.doOnlyRaw
                    ea_legacy2bids(run_prop.source,run_prop.dest,0,'',0,1);
                elseif doMigrate && ~dicom_conv && ~app.doOnlyRaw
                    ea_legacy2bids(run_prop.source,run_prop.dest,0,'',0,0);
                end
                waitbar(1,f,'Done!');
                close(f);
            end
            if strcmp(app.flag,'MigrateDCMconv')
                f = waitbar(0,'Migrating dataset..');
                waitbar(0.33,f,'Migrating dataset...');
                %app.dicom_source: parent dicom,
                %implement checkpoint if folder is not empty
                if app.doOnlyRaw && dicom_conv
                    ea_legacy2bids(run_prop.source,run_prop.dest,1,app.dicom_source_folder,1,1);
                elseif dicom_conv && ~app.doOnlyRaw
                    ea_legacy2bids(run_prop.source,run_prop.dest,1,app.dicom_source_folder,1,0)
                end
                ea_dataset_import(run_prop.source, run_prop.dest, run_prop.convert_using, 1);
                waitbar(1,f,'Done!');
                close(f);
            end
            % only DICOM conversion, no migration
            if strcmp(app.flag,'onlyDCMconv')
                f = waitbar(0,'Migrating dataset..');
                waitbar(0.33,f,'Migrating dataset...');
                ea_dataset_import(run_prop.source, run_prop.dest, run_prop.convert_using, 1);
                waitbar(1,f,'Done!');
                close(f);
            end
        end

        function notMigrated = runImport(app,source_dirs,reRunImport)                
            if reRunImport
                app.doOnlyRaw = 0;
                subfolder = dir_without_dots(source_dirs{1});
                subfolder = {subfolder.name};
                if ~all(ismember(subfolder,'ea_ui.mat')) && checkIfOneExist(app,subfolder,'.*.nii||.*.nii.gz') && checkIfOneExist(app,subfolder,'dicom||.*.dcm') % raw nifti + dicom
                    app.doOnlyRaw = 1;
                elseif checkIfOnlyExist(app,subfolder,'.*.nii||.*.nii.gz') && ~any(ismember(subfolder,'ea_ui.mat'))
                    app.doOnlyRaw = 1;
                end
            else
                notMigrated = {};
            end
            for pt = 1:length(source_dirs)
                source_dir = {source_dirs{pt}};
                if pt == 1
                    dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
                    doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
                    %store the first person's dicom conv values
                    dicom_conv_first = dicom_conv;
                    doMigrate_first = doMigrate;
                    setFlags(app,dicom_conv,doMigrate)
                elseif pt >= 2
                    reset_rules(app)
                    op_label(app,'source',source_dirs{pt})
                    app.RunButton.Enable = 'off';
                    results = enablerules(app,source_dirs{pt});
                    if strcmp(results,'skip')
                        notMigrated{end+1} = source_dirs{pt};
                        continue
                    else
                        if app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value %only if the user has DICOMs in the first place
                            if dicom_conv_first ~= app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value %user has chosen not to do dicom convert in the first patient, rule followed for all
                                app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = dicom_conv;
                            else
                                dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
                            end
                        else
                            dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
                        end
                        if app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value %only if the user has migratable files in the first place
                            if doMigrate_first ~= app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                                app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = doMigrate;
                            else
                                doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
                            end
                        else
                            doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
                        end
                        setFlags(app,dicom_conv,doMigrate)
                        if strcmp(app.flag,'skip')
                            results = enablerules(app,source_dirs{pt});
                            dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
                            doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
                            setFlags(app,dicom_conv,doMigrate)
                            %continue
                        end
                    end
                end
                run_prop.source = source_dir;
                run_prop.dest = app.output_folder;
                run_prop.convert_using = app.ConverttoDicomusingDropDown.Value;
                actualRun(app,dicom_conv,doMigrate,run_prop)
                if reRunImport
                    app.notMigrated(1) = [];
                    if ~isempty(app.notMigrated) %next patient, if available
                        app.SelectpatientsButton.Text = app.notMigrated(1);
                        app.SelectpatientsButton.Tooltip = app.notMigrated(1);
                    end
                end
            end
            % if running automatically, close as soon as the migration
            % is over.
        end

        function reRunImport(app,notMigratedFile)
            app.doOnlyRaw = 0;
            subfolder = dir_without_dots(notMigratedFile);
            subfolder = {subfolder.name};
            if ~all(ismember(subfolder,'ea_ui.mat')) && checkIfOneExist(app,subfolder,'.*.nii||.*.nii.gz') && checkIfOneExist(app,subfolder,'dicom||.*.dcm') % raw nifti + dicom
                app.doOnlyRaw = 1;
            elseif checkIfOnlyExist(app,subfolder,'.*.nii||.*.nii.gz') && ~any(ismember(subfolder,'ea_ui.mat'))
                app.doOnlyRaw = 1;
            end
            dicom_conv = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
            doMigrate = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
            setFlags(app,dicom_conv,doMigrate)

            run_prop.source = {notMigratedFile};
            run_prop.dest = app.output_folder;
            run_prop.convert_using = app.ConverttoDicomusingDropDown.Value;
            actualRun(app,dicom_conv,doMigrate,run_prop);
        end

        function setFlags(app,dicom_conv,doMigrate)
            if dicom_conv && ~doMigrate
                app.flag = 'onlyDCMconv';
            elseif ~dicom_conv && doMigrate
                app.flag = 'onlyMigrate';
            elseif dicom_conv && doMigrate
                app.flag = 'MigrateDCMconv';
            else
                app.flag = 'skip';
            end
        end
        function [bidsroot,subjID] = generateopparams(app,dest)
            bidsroot = dest;
            subjID = app.subjID;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, source_folder, options, handles, dest_folder)
            app.LeadMigrateUIFigure.Name = 'Lead Import';
            app.LeadMigrateUIFigure.HandleVisibility = 'on';
            app.SelectpatientsButton.FontColor = 'r';
            
            if exist('source_folder','var')
                if ischar(source_folder)
                    source_folder = {source_folder};
                end
                app.source_folder = source_folder;
                count = checkValidity(app,app.source_folder);
                if count >= 1
                    return
                end
                results = enablerules(app,source_folder{1});
                
                if strcmp(results,'fail')
                    if exist('handles','var')
                        setappdata(handles.leadfigure, 'BIDSRoot', {});
                        setappdata(handles.leadfigure, 'subjID', {});
                    end
                    close(app.LeadMigrateUIFigure);
                    return
                end
                if length(app.source_folder) > 1
                    op_label(app,'multsource');
                else
                    op_label(app,'source',app.source_folder{1})
                end
                app.close_flag = 1;    
            end
            
            if exist('dest_folder','var')
                if ischar(dest_folder)
                    dest_folder = {dest_folder};
                end
                app.output_folder = dest_folder;
                op_label(app,'dest');
                app.RunButton.Enable = 'on';
            end

            if isfile([ea_getearoot, 'common', filesep, 'ea_recentpatients.mat'])
                load([ea_getearoot, 'common', filesep, 'ea_recentpatients.mat'], 'recentfolders');
                if ~strcmp(['No recent patients found'], recentfolders)
                    recentfolders = regexprep(recentfolders, ['\', filesep, 'derivatives.*$'], '');
                    recentfolders = ['Recent Datasets'; unique(recentfolders)];
                    app.RecentDatasetsDropDown.Items = recentfolders;
                    app.RecentDatasetsDropDown.Value = 'Recent Datasets';
                end
            end

            if exist('handles','var')
                app.handles = handles;
                if ~isempty(getappdata(app.handles.leadfigure, 'BIDSRoot'))
                    if contains(app.source_folder{1},'sourcedata')
                        app.output_folder = {getappdata(app.handles.leadfigure, 'BIDSRoot')};
                        op_label(app,'dest');
                        app.close_flag = 1;
                        
                        app.RunButton.Enable = 'on';
                    end
                else
                    app.SelectoutputdatasetdirectoryButton.FontColor = 'r';
                end
            end
            if exist('options','var')
                app.options = options;
            end
        end

        % Button pushed function: SelectpatientsButton
        function SelectpatientsButtonPushed(app, event)
            reset_rules(app)
            app.BusyLamp.Color = 'red';
            app.source_folder = ea_uigetdir(pwd);
            if isempty(app.source_folder)
                return;
            end
            figure(app.LeadMigrateUIFigure);
            count = checkValidity(app,app.source_folder);
            if count >= 1
                return
            end
            app.SelectpatientsButton.Tooltip = app.source_folder;
            if length(app.source_folder) > 1
                op_label(app,'multsource');
            else
                op_label(app,'source',app.source_folder{1})
            end
            enablerules(app,app.source_folder{1});
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: 
        % ConvertDICOMdatatoBIDScompliantdatasetCheckBox
        function ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: ConverttoDicomusingDropDown
        function ConverttoDicomusingDropDownValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConverttoDicomusingDropDown.Value;
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            
            if isempty(app.output_folder)
                errordlg("No output directory selected, select to continue...",'Output directory error')
            else
                source_dirs = app.source_folder;
                reRunImport = 0;
                app.notMigrated = runImport(app,source_dirs,reRunImport);
                if ~isempty(app.notMigrated)
                    figure(app.LeadMigrateUIFigure);
                    app.RerunButton.Visible = 'on';
                    app.RunButton.Enable = 'off';
                    app.SelectpatientsButton.Text = app.notMigrated{1};
                    app.SelectpatientsButton.Tooltip = app.notMigrated{1};
                end


           end
           disp('Process done ******');
           if app.close_flag
               ea_busyaction('on',app.handles.leadfigure,'dbs');
               setappdata(app.handles.leadfigure, 'BIDSRoot', app.output_folder{1});
               setappdata(app.handles.leadfigure, 'subjID', app.subjID);
               app.delete;
               %close(app.LeadMigrateUIFigure);
           end                                           
            
        end

        % Callback function
        function MigrateOptionsButtonGroupSelectionChanged(app, event)
           
        end

        % Value changed function: 
        % MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox
        function MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged(app, event)
            value = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;

        end

        % Button pushed function: SelectoutputdatasetdirectoryButton
        function SelectoutputdatasetdirectoryButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            selpath = uigetdir(pwd);
            if selpath == 0
                return
            end
            figure(app.LeadMigrateUIFigure);
            count = checkValidity(app,{selpath});
            if count >= 1
                return
            end
            if ~isempty(ea_regexpdir(selpath,app.subjID{1},1,'dir'))
                ea_warndlg("Selected path %s seems to already have a previously Imported dataset. If you run into any issues, consider deleting previous dataset and starting migrate again.",selpath)
            end
            app.output_folder = {selpath};
            op_label(app,'dest');
            output_message = app.output_folder{1};
            
            if isempty(app.output_label)
                app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
            else
                app.output_label.Label.Text = '';
                app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
            end
            app.SelectoutputdatasetdirectoryButton.Tooltip = app.output_folder;
            app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
            app.SelectoutputdatasetdirectoryButton.FontWeight = 'bold';
            if ~isempty(app.source_folder) && ~isempty(app.output_folder)
                app.RunButton.Enable = 'on';
            end
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: RegeneraterawimagejsonButton
        function RegeneraterawimagejsonButtonPushed(app, event)
            rawdata_folder = fullfile(app.source_folder,'rawdata');
            for i=1:length(rawdata_folder)
                subj_name = dir_without_dots(rawdata_folder{i});
                subj_name = subj_name([subj_name.isdir]);
                subj_name = subj_name.name;
                prefs_dir = fullfile(app.source_folder{i},'derivatives','leaddbs',subj_name,'prefs');
                if ~exist(prefs_dir,'dir')
                    mkdir(prefs_dir)
                end
                raw_preop_dir = fullfile(rawdata_folder{i},subj_name,'ses-preop','anat');
                raw_postop_dir = fullfile(rawdata_folder{i},subj_name,'ses-postop','anat');
                opt.FileName = fullfile(prefs_dir,[subj_name,'_','desc-rawimages.json']);
                
                preop_files = dir_without_dots(raw_preop_dir);
                preop_files = {preop_files.name};
                
                if ~isempty(preop_files)
                    for j=1:length(preop_files)
                        json_val = regexprep(preop_files{j},'(.nii)|(.gz)','');
                        if contains(preop_files{j},'acq-')
                            temp_tag = strsplit(json_val,'-');
                            modality_str = temp_tag{end};
                            rawdata_fieldname = modality_str;
                            anat_files_selected.preop.anat.(rawdata_fieldname) = json_val;
                        else
                            temp_tag = strsplit(json_val,'preop_');
                            rawdata_fieldname = temp_tag{end};
                            anat_files_selected.preop.anat.(rawdata_fieldname) = json_val;
                        end
                    end
                end
                postop_files = dir_without_dots(raw_postop_dir);
                postop_files = {postop_files.name};
                
                for k=1:length(postop_files)
                    json_val = regexprep(postop_files{k},'(.nii)|(.gz)','');
                    if contains(postop_files{k},'ct','IgnoreCase',true)
                        rawdata_fieldname = 'CT';
                        anat_files_selected.postop.anat.(rawdata_fieldname) = json_val;
                    else
                        temp_tag = strsplit(json_val,'-');
                        rawdata_fieldname = temp_tag{end};
                        anat_files_selected.postop.anat.(rawdata_fieldname) = json_val;
                    end
                end
                savejson('',anat_files_selected,opt);
            end   
        end

        % Button pushed function: RerunButton
        function RerunButtonPushed(app, event)
           %while ~isempty(app.notMigrated)
           reRunImport = 1;
           %app.SelectpatientsButton.Text = app.notMigrated{1};
           %app.SelectpatientsButton.Tooltip = app.notMigrated{1};
           source_dirs = {app.notMigrated{1}};
           runImport(app,source_dirs,reRunImport);
            %end

        end

        % Value changed function: RecentDatasetsDropDown
        function RecentDatasetsDropDownValueChanged(app, event)
            value = app.RecentDatasetsDropDown.Value;
            if ~strcmp(value, 'Recent Datasets')
                app.output_folder = {value};
                op_label(app, 'dest');
                output_message = app.output_folder{1};
            
                if isempty(app.output_label)
                    app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
                else
                    app.output_label.Label.Text = '';
                    app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",output_message,"Position",[37,290,250,22]);
                end
                app.SelectoutputdatasetdirectoryButton.Tooltip = app.output_folder;
                app.SelectoutputdatasetdirectoryButton.FontColor = 'g';
                app.SelectoutputdatasetdirectoryButton.FontWeight = 'bold';
                if ~isempty(app.source_folder) && ~isempty(app.output_folder)
                    app.RunButton.Enable = 'on';
                end
                app.BusyLamp.Color = 'green';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeadMigrateUIFigure = uifigure('Visible', 'off');
            app.LeadMigrateUIFigure.Name = 'Lead Migrate';
            app.RunButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.ConverttoDicomusingDropDownLabel = uilabel(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDownLabel.Text = 'Convert to Dicom using..';
            app.ConverttoDicomusingDropDown = uidropdown(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDown.Items = {'dcm2niix', 'dicm2nii', 'SPM'};
            app.ConverttoDicomusingDropDown.ValueChangedFcn = createCallbackFcn(app, @ConverttoDicomusingDropDownValueChanged, true);
            app.ConverttoDicomusingDropDown.Value = 'dcm2niix';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged, true);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Text = 'Migrate legacy Lead-DBS dataset to BIDS dataset';
            app.SelectpatientsButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpatientsButtonPushed, true);
            app.SelectpatientsButton.Text = 'Select patient(s)';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged, true);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Text = 'Convert DICOM data to BIDS compliant dataset';
            app.SelectoutputdatasetdirectoryButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectoutputdatasetdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputdatasetdirectoryButtonPushed, true);
            app.SelectoutputdatasetdirectoryButton.Text = 'Select output dataset directory';
            app.BusyLamp = uilamp(app.LeadMigrateUIFigure);
            app.RegeneraterawimagejsonButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RegeneraterawimagejsonButton.ButtonPushedFcn = createCallbackFcn(app, @RegeneraterawimagejsonButtonPushed, true);
            app.RegeneraterawimagejsonButton.Visible = 'off';
            app.RegeneraterawimagejsonButton.Text = 'Regenerate rawimage.json';
            app.RerunButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RerunButton.ButtonPushedFcn = createCallbackFcn(app, @RerunButtonPushed, true);
            app.RerunButton.Visible = 'off';
            app.RerunButton.Text = 'Re-run';
            app.RecentDatasetsDropDown = uidropdown(app.LeadMigrateUIFigure);
            app.RecentDatasetsDropDown.Items = {'Recent Datasets'};
            app.RecentDatasetsDropDown.ValueChangedFcn = createCallbackFcn(app, @RecentDatasetsDropDownValueChanged, true);
            app.RecentDatasetsDropDown.Tooltip = {'Recent Datasets'};
            app.RecentDatasetsDropDown.Value = 'Recent Datasets';
            % Show the figure after all components are created
            app.LeadMigrateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_import(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeadMigrateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeadMigrateUIFigure)
        end
    end
end