classdef lead_migrate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeadMigrateUIFigure          matlab.ui.Figure
        RunButton                    matlab.ui.control.Button
        ConverttoDicomusingDropDownLabel  matlab.ui.control.Label
        ConverttoDicomusingDropDown  matlab.ui.control.DropDown
        MigrateOptionsButtonGroup    matlab.ui.container.ButtonGroup
        RetainsourcedirectorycopiesdataButton  matlab.ui.control.RadioButton
        MovefilesandcreateanewdirectoryButton  matlab.ui.control.RadioButton
        MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox  matlab.ui.control.CheckBox
        SelectpatientsButton         matlab.ui.control.Button
        ConvertDICOMdatatoBIDScompliantdatasetCheckBox  matlab.ui.control.CheckBox
        SelectoutputdirectoryoptionalButton  matlab.ui.control.Button
        BusyLamp                     matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        source_files % Description
        output_files
        dicom_source_file
        input_label
        output_label
        flag
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectpatientsButton
        function SelectpatientsButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            %reset everything
            app.ConverttoDicomusingDropDown.Enable = 'on';
            app.ConverttoDicomusingDropDownLabel.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'on';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'on';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
            %%
            selpath = ea_uigetdir(pwd);
            if isempty(app.input_label)
                app.input_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",'Loaded Source Directory(s)!',"Position",[37,290,250,22]);
            else
                app.input_label.Label.Text = '';
                app.input_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",'Loaded Source Directory(s)!',"Position",[37,290,250,22]);
            end
            

            %selpath now contains a list of input directories.
            for selection = 1:length(selpath)
                %if length(selpath) > 1
                %    first_child = dir_without_dots(selpath{selection});
                   % files_inside = dir_without_dots(first_child);
                %else
                files_inside = dir_without_dots(selpath{selection});
                %end
                files_inside = {files_inside.name};
                %detection function: if the pat has derivatives and 
                % raw data, but not in BIDS format then it should be
                % migrated.
                if any(ismember(files_inside,'glanat.nii')) && any(cellfun('isempty',regexp(files_inside,'raw_anat.*.nii'))) && all(cellfun('isempty',regexpi(files_inside,'DICOM')))  && all(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                     %msgbox("Looks like preprocessing has been completed, but no dicom images are present. Conitinuing to migrate into BIDS-compliant dataset..");
                     app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'off';
                     app.ConverttoDicomusingDropDown.Enable = 'off';
                     app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                     app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                     app.flag = 'onlyMigrate';
                    %only raw nifti available, no dicom is available. Migrate
                 %will be done, but only to move & rename the raw files
                elseif ~any(ismember(files_inside,'glanat.nii')) && any(cellfun('isempty',regexp(files_inside,'raw_anat.*.nii'))) && ~any(ismember(files_inside,'rawdata')) && all(cellfun('isempty',regexpi(files_inside,'DICOM')))  
                       %msgbox("Raw Nifti detected without any DICOM images. Continuing to run Migration to generate raw BIDS-compliant dataset only..");
                       app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'off';
                       app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 0;
                       app.ConverttoDicomusingDropDown.Enable = 'off';
                       app.ConverttoDicomusingDropDownLabel.Enable = 'off';
                       app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                       app.flag = 'onlyMigrate';
                %only DICOM files available
                elseif ~any(endsWith(files_inside,'.nii')) && (any(ismember(files_inside,'DICOM')) || ~any(cellfun('isempty',regexp(files_inside,'.*.dcm'))))
                    %todo: remove support for .dcm files inside 
                    %if any(ismember(files_inside,'DICOM')) || ~any(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                    %msgbox("DICOM files detected. Continuing to run dicom to nifti conversion, and nifti to Lead-BIDS conversion.");
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'off';
                    app.ConverttoDicomusingDropDown.Enable = 'on';
                    app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                    app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                    app.flag = 'MigrateDCMconv';
                    app.dicom_source_file = fullfile(selpath,'DICOM');
                %BIDS Compliant raw data nifti files are available! in this case,
                %dcm -> bids conversion should require special
                %handling. Only the conversion from nifti -> lead bids
                %will be performed
                elseif any(ismember(files_inside,'rawdata')) 
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 0;
                    app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Enable = 'off';
                    app.ConverttoDicomusingDropDown.Enable = 'on';
                    app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                    app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Enable = 'on';
                    app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                    app.flag = 'onlyDCMconv';
                %both dicom & derivatives available. Requires special
                %handling in the migrate code.
                elseif any(ismember(files_inside,'glanat.nii')) && any(cellfun('isempty',regexp(files_inside,'raw_anat.*.nii'))) 
                    if any(ismember(files_inside,'DICOM')) || ~any(cellfun('isempty',regexp(files_inside,'.*.dcm')))
                        app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value = 1;
                        app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value = 1;
                        app.ConverttoDicomusingDropDown.Enable = 'on';
                        app.ConverttoDicomusingDropDownLabel.Enable = 'on';
                        app.dicom_source_file = fullfile(selpath,'DICOM');
                        app.flag = 'MigrateDCMconv';
                    end
                else
                    msgbox("Unable to detect the type of processing required, please select options manually..")
                end
            end
            app.source_files = selpath;
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: 
        % ConvertDICOMdatatoBIDScompliantdatasetCheckBox
        function ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value;
            if value && ~app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                app.flag = 'onlyDCMconv';
            elseif ~value && app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            elseif value && app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            end
            app.BusyLamp.Color = 'green';
        end

        % Value changed function: ConverttoDicomusingDropDown
        function ConverttoDicomusingDropDownValueChanged(app, event)
            app.BusyLamp.Color = 'red';
            value = app.ConverttoDicomusingDropDown.Value;
            
            if app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value && ~app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                app.flag = 'onlyDCMconv';
            elseif app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value && app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            elseif ~app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value && app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            end
            app.BusyLamp.Color = 'green';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            source = app.source_files;
            if ~isempty(app.output_files)
                dest = app.output_files;
            else
                dest = source;
            end
            if strcmp(app.MigrateOptionsButtonGroup.SelectedObject,app.MigrateOptionsButtonGroup.Buttons(2).Text) 
                %user chooses to move file
                move = 1;
            else
                %user chooses to copy file
                move = 0;
            end
            %for johannes
            convert_using = app.ConverttoDicomusingDropDown.Value;
            if strcmp(app.flag,'onlyMigrate')
               f = waitbar(0,'Migrating dataset..');
               waitbar(0.33,f,'Migrating dataset...');
               if ~isempty(app.dicom_source_file)
                   legacy2bids(source,dest,1,app.dicom_source_file,move);
               else
                   legacy2bids(source,dest,0,'',move);
               end
               waitbar(1,f,'Done!');
               % after migration is done, trigger ea_dataset_import.m
               % without DICOM conversion to sort out .nii.gz files in BIDS
               % dataset
               ea_dataset_import(source, [], [], 0)
               
            end
            %both the migrate and the dcm code here. Migrate runs first to
            %dump the legacy raw dataset into the new folder, "legacy_rawdata"
            if strcmp(app.flag,'MigrateDCMconv')
                %app.dicom_source: parent dicom,
                %implement checkpoint if folder is not empty
                legacy2bids(source,dest,1,app.dicom_source_file,move,1);
                ea_dataset_import(source, dest, convert_using, 1);
               
            end
            
            % only DICOM conversion, no migration 
            if strcmp(app.flag,'onlyDCMconv')
                ea_dataset_import(source, dest, convert_using, 1);
            end
            app.BusyLamp.Color = 'green';
        end

        % Selection changed function: MigrateOptionsButtonGroup
        function MigrateOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.MigrateOptionsButtonGroup.SelectedObject;
            if app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && ~app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            elseif app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            elseif ~app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyDCMconv';
            end
        end

        % Value changed function: 
        % MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox
        function MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged(app, event)
            value = app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value;
            if value && ~app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            elseif value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            elseif ~value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag =  'onlyDCMconv';
            end
        end

        % Button pushed function: 
        % SelectoutputdirectoryoptionalButton
        function SelectoutputdirectoryoptionalButtonPushed(app, event)
            app.BusyLamp.Color = 'red';
            app.output_files = ea_uigetdir(pwd);
            if isempty(app.output_label)
                app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",'Loaded Source Directory(s)!',"Position",[37,290,250,22]);
            else
                app.output_label.Label.Text = '';
                app.output_label.Label = uilabel(app.LeadMigrateUIFigure,"Text",'Loaded Source Directory(s)!',"Position",[37,290,250,22]);
            end
            
            op_status.Label = uilabel(app.LeadMigrateUIFigure,"Text",'Loaded Output Directory(s)!',"Position",[420,290,250,22]);
            if app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && ~app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyMigrate';
            elseif app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'MigrateDCMconv';
            elseif ~app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Value && app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Value
                app.flag = 'onlyDCMconv';
            end
            app.BusyLamp.Color = 'green';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeadMigrateUIFigure = uifigure('Visible', 'off');
            app.LeadMigrateUIFigure.Name = 'Lead Migrate';
            app.RunButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ConverttoDicomusingDropDownLabel = uilabel(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDownLabel.Text = 'Convert to Dicom using..';
            app.ConverttoDicomusingDropDown = uidropdown(app.LeadMigrateUIFigure);
            app.ConverttoDicomusingDropDown.Items = {'dcm2niix', 'dicm2nii', 'SPM'};
            app.ConverttoDicomusingDropDown.ValueChangedFcn = createCallbackFcn(app, @ConverttoDicomusingDropDownValueChanged, true);
            app.ConverttoDicomusingDropDown.Value = 'dcm2niix';
            app.MigrateOptionsButtonGroup = uibuttongroup(app.LeadMigrateUIFigure);
            app.MigrateOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MigrateOptionsButtonGroupSelectionChanged, true);
            app.MigrateOptionsButtonGroup.Title = 'Migrate Options';
            app.RetainsourcedirectorycopiesdataButton = uiradiobutton(app.MigrateOptionsButtonGroup);
            app.RetainsourcedirectorycopiesdataButton.Tooltip = {'Using this option results in longer processing for large datasets. It is safer as it retains a copy of the old dataset.'};
            app.RetainsourcedirectorycopiesdataButton.Text = 'Retain source directory (copies data)';
            app.RetainsourcedirectorycopiesdataButton.Value = true;
            app.MovefilesandcreateanewdirectoryButton = uiradiobutton(app.MigrateOptionsButtonGroup);
            app.MovefilesandcreateanewdirectoryButton.Tooltip = {'Using this option results in faster processing. Use with caution as a copy of the old dataset will not be retained. '};
            app.MovefilesandcreateanewdirectoryButton.Text = 'Move files and create a new directory';
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBoxValueChanged, true);
            app.MigratelegacyLeadDBSdatasettoBIDSdatasetCheckBox.Text = 'Migrate legacy Lead-DBS dataset to BIDS dataset';
            app.SelectpatientsButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectpatientsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpatientsButtonPushed, true);
            app.SelectpatientsButton.Text = 'Select patient(s)';
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox = uicheckbox(app.LeadMigrateUIFigure);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.ValueChangedFcn = createCallbackFcn(app, @ConvertDICOMdatatoBIDScompliantdatasetCheckBoxValueChanged, true);
            app.ConvertDICOMdatatoBIDScompliantdatasetCheckBox.Text = 'Convert DICOM data to BIDS compliant dataset';
            app.SelectoutputdirectoryoptionalButton = uibutton(app.LeadMigrateUIFigure, 'push');
            app.SelectoutputdirectoryoptionalButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputdirectoryoptionalButtonPushed, true);
            app.SelectoutputdirectoryoptionalButton.Text = 'Select output directory (optional)';
            app.BusyLamp = uilamp(app.LeadMigrateUIFigure);
            % Show the figure after all components are created
            app.LeadMigrateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lead_migrate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeadMigrateUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeadMigrateUIFigure)
        end
    end
end