classdef plot_studio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DATASELECTIONPanel              matlab.ui.container.Panel
        XaxislabelEditFieldLabel        matlab.ui.control.Label
        XaxislabelEditField             matlab.ui.control.EditField
        OKButton                        matlab.ui.control.Button
        YaxislabelEditFieldLabel        matlab.ui.control.Label
        YaxislabelEditField             matlab.ui.control.EditField
        OKButton_2                      matlab.ui.control.Button
        TitlelabelEditFieldLabel        matlab.ui.control.Label
        TitlelabelEditField             matlab.ui.control.EditField
        OKButton_3                      matlab.ui.control.Button
        STATISTICSPanel                 matlab.ui.container.Panel
        CorrelationTypeDropDownLabel    matlab.ui.control.Label
        CorrelationTypeDropDown         matlab.ui.control.DropDown
        DisplayasranksCheckBox          matlab.ui.control.CheckBox
        HighlightoutliersCheckBox       matlab.ui.control.CheckBox
        CalculateerrorusingDropDownLabel  matlab.ui.control.Label
        CalculateerrorusingDropDown     matlab.ui.control.DropDown
        REFRESHVIEWButton               matlab.ui.control.Button
        SaveasDropDownLabel             matlab.ui.control.Label
        SaveasDropDown                  matlab.ui.control.DropDown
        EXPORTSAVEButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        CohortColorsTab                 matlab.ui.container.Tab
        MarkerColor1DropDownLabel       matlab.ui.control.Label
        MarkerColor1DropDown            matlab.ui.control.DropDown
        MarkerColor2DropDownLabel       matlab.ui.control.Label
        MarkerColor2DropDown            matlab.ui.control.DropDown
        MarkerColor3DropDownLabel       matlab.ui.control.Label
        MarkerColor3DropDown            matlab.ui.control.DropDown
        MarkerColor4DropDownLabel       matlab.ui.control.Label
        MarkerColor4DropDown            matlab.ui.control.DropDown
        MarkerColor5DropDownLabel       matlab.ui.control.Label
        MarkerColor5DropDown            matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        DiseaseMarkersTab               matlab.ui.container.Tab
        MarkerType1DropDownLabel        matlab.ui.control.Label
        MarkerType1DropDown             matlab.ui.control.DropDown
        MarkerType2DropDownLabel        matlab.ui.control.Label
        MarkerType2DropDown             matlab.ui.control.DropDown
        ThisdefinesthediseasetypeLabel  matlab.ui.control.Label
        MarkerSizeEditFieldLabel        matlab.ui.control.Label
        MarkerSizeEditField             matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %To test this, comment out the variables. 
        %X = randn(40,1);
        %Y = randn(40,1);
        X_axis_title 
        Y_axis_title
        title_fig
        I
        Ihat
        Marker_type_1
        Marker_type_2
        Marker_type_3
        Marker_type_4
        Marker_type_5
        Marker_color_1
        Marker_color_2
        Marker_color_3
        Marker_color_4
        Marker_color_5
        Marker_size
        save_as
        rankdisp
        highlite
        cohorts
        error_calc
       % cohorts = {'Berlin','London'}
        diseases
        group
        % diseases = {'Parkinsons','Alzhimers'}       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, I, Ihat, cohorts, diseases, groups)
            app.I = I;
            app.Ihat = Ihat;
            app.cohorts = cohorts;
            app.diseases = diseases;
            app.group = groups;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.X_axis_title = app.XaxislabelEditField.Value; 
        end

        % Button pushed function: OKButton_2
        function OKButton_2Pushed(app, event)
            app.Y_axis_title = app.YaxislabelEditField.Value;
        end

        % Callback function
        function OKButton_3Pushed(app, event)
            %app.title_fig = app.TitleEditField.Value;
        end

        % Button pushed function: REFRESHVIEWButton
        function REFRESHVIEWButtonPushed(app, event)
            Xval = app.I;
            Yval = app.Ihat;
            %Xval = app.X
            %Yval = app.Y;
            lx = length(Xval);
            Xrank = tiedrank(Xval);
            Yrank = tiedrank(Yval);
            if strcmp(app.CorrelationTypeDropDown.Value,'Perm. Pearson')
                [R,p] = ea_permcorr(Xval,Yval,'pearson');
            else
                [R,p] = corr(Xval,Yval,'Type',app.CorrelationTypeDropDown.Value);
            end
            
            ax = app.UIAxes;
            app.title_fig = app.TitlelabelEditField.Value;
            app.X_axis_title = app.XaxislabelEditField.Value;
            app.Y_axis_title = app.YaxislabelEditField.Value;
            
            if ~strcmp(app.title_fig,"")
                ax.Title.String = app.title_fig;
            
            else
                ax.Title.String = ['R-value = ' num2str(R) ', P-value = ' num2str(p) ', ' app.CorrelationTypeDropDown.Value];
            end
            if ~strcmp(app.X_axis_title,"")
                ax.XLabel.String = app.X_axis_title;
            
            else
                ax.XLabel.String = 'X-AXIS';
            end
            if ~strcmp(app.Y_axis_title,"")
                ax.YLabel.String = app.Y_axis_title;
            
            else
                ax.YLabel.String = 'Y-AXIS';
            end
            grp_cohorts = [];
            grp_disease = [];
            if strcmp(app.group,'')
               for i = 1:(length(app.cohorts))
                  if i == length(app.cohorts)
                     temp = cell(ceil(lx/length(app.cohorts)),1);
                     temp(:) = app.cohorts(i);
                  else
                     temp = cell(round(lx/length(app.cohorts)),1);
                     temp(:) = app.cohorts(i);
                   end  
                  grp_cohorts = [grp_cohorts;temp];
                  for j = 1:length(app.diseases)
                     if i == length(app.cohorts)
                        temp_disease = cell(ceil(lx/(length(app.cohorts))*length(app.diseases)),1);
                        temp_disease(:) = app.diseases(j);
                     else
                        temp_disease = cell(round(lx/(length(app.cohorts))*length(app.diseases)),1);
                        temp_disease(:) = app.diseases(j);
                     end  
                     grp_disease = [grp_disease;temp_disease];
                  end
               end
               group_var = {grp_cohorts,grp_disease};
            else
               group_var = app.group;
            end
              % Description % Description
            %group 1 tab details:
            app.Marker_color_1 = app.MarkerColor1DropDown.Value;
            app.Marker_color_2 = app.MarkerColor2DropDown.Value;
            app.Marker_color_3 = app.MarkerColor3DropDown.Value;
            app.Marker_color_4 = app.MarkerColor4DropDown.Value;
            app.Marker_color_5 = app.MarkerColor5DropDown.Value;
            
            %group 2 tab details:
            app.Marker_type_1 = app.MarkerType1DropDown.Value;
            app.Marker_type_2 = app.MarkerType2DropDown.Value;
           
            if strcmp(app.Marker_color_1,"Red")
                app.Marker_color_1 = 'r';
            elseif strcmp(app.Marker_color_1,"Yellow")
                app.Marker_color_1 = 'y';
            elseif strcmp(app.Marker_color_1,"Magenta")
                app.Marker_color_1 = 'm';
            elseif strcmp(app.Marker_color_1,"Blue")
                app.Marker_color_1 = 'b';
            elseif strcmp(app.Marker_color_1,"Black")
                app.Marker_color_1 = 'k';
            elseif strcmp(app.Marker_color_1,"Cyan")
                app.Marker_color_1 = 'c';  
            elseif strcmp(app.Marker_color_1,"White")
                app.Marker_color_1 = 'w';
            elseif strcmp(app.Marker_color_1,"Green")
                app.Marker_color_1 = 'g';
            else
                app.Marker_color_1 = 'n';
            end
            
            if strcmp(app.Marker_color_2,"Red")
                app.Marker_color_2 = 'r';
            elseif strcmp(app.Marker_color_2,"Yellow")
                app.Marker_color_2 = 'y';
            elseif strcmp(app.Marker_color_2,"Magenta")
                app.Marker_color_2 = 'm';
            elseif strcmp(app.Marker_color_2,"Blue")
                app.Marker_color_2 = 'b';
            elseif strcmp(app.Marker_color_2,"Black")
                app.Marker_color_2 = 'k';
            elseif strcmp(app.Marker_color_2,"Cyan")
                app.Marker_color_2 = 'c';  
            elseif strcmp(app.Marker_color_2,"White")
                app.Marker_color_2 = 'w';
            elseif strcmp(app.Marker_color_2,"Green")
                app.Marker_color_2 = 'g';
            else
                app.Marker_color_2 = 'n';
            end
            if strcmp(app.Marker_color_3,"Red")
                app.Marker_color_3 = 'r';
            elseif strcmp(app.Marker_color_3,"Yellow")
                app.Marker_color_3 = 'y';
            elseif strcmp(app.Marker_color_3,"Magenta")
                app.Marker_color_3 = 'm';
            elseif strcmp(app.Marker_color_3,"Blue")
                app.Marker_color_3 = 'b';
            elseif strcmp(app.Marker_color_3,"Black")
                app.Marker_color_3 = 'k';
            elseif strcmp(app.Marker_color_3,"Cyan")
                app.Marker_color_3 = 'c';  
            elseif strcmp(app.Marker_color_3,"White")
                app.Marker_color_3 = 'w';
            elseif strcmp(app.Marker_color_3,"Green")
                app.Marker_color_3 = 'g';
            else
                app.Marker_color_3 = 'n';
            end
            if strcmp(app.Marker_color_4,"Red")
                app.Marker_color_4 = 'r';
            elseif strcmp(app.Marker_color_4,"Yellow")
                app.Marker_color_4 = 'y';
            elseif strcmp(app.Marker_color_4,"Magenta")
                app.Marker_color_4 = 'm';
            elseif strcmp(app.Marker_color_4,"Blue")
                app.Marker_color_4 = 'b';
            elseif strcmp(app.Marker_color_4,"Black")
                app.Marker_color_4 = 'k';
            elseif strcmp(app.Marker_color_4,"Cyan")
                app.Marker_color_4 = 'c';  
            elseif strcmp(app.Marker_color_4,"White")
                app.Marker_color_4 = 'w';
            elseif strcmp(app.Marker_color_4,"Green")
                app.Marker_color_4 = 'g';
            else
                app.Marker_color_4 = 'n';
            end
            if strcmp(app.Marker_color_5,"Red")
                app.Marker_color_5 = 'r';
            elseif strcmp(app.Marker_color_5,"Yellow")
                app.Marker_color_5 = 'y';
            elseif strcmp(app.Marker_color_5,"Magenta")
                app.Marker_color_5 = 'm';
            elseif strcmp(app.Marker_color_5,"Blue")
                app.Marker_color_5 = 'b';
            elseif strcmp(app.Marker_color_5,"Black")
                app.Marker_color_5 = 'k';
            elseif strcmp(app.Marker_color_5,"Cyan")
                app.Marker_color_5 = 'c';  
            elseif strcmp(app.Marker_color_5,"White")
                app.Marker_color_5 = 'w';
            elseif strcmp(app.Marker_color_5,"Green")
                app.Marker_color_5 = 'g';
            else
                app.Marker_color_5 = 'n';
            end
            if strcmp(app.Marker_type_1,'None')
                app.Marker_type_1 = 'n';
            end
            if strcmp(app.Marker_type_2,'None')
                app.Marker_type_2 = 'n';
            end
            colorlist = [];
            full_clr = [app.Marker_color_1,app.Marker_color_2,app.Marker_color_3,app.Marker_color_4,app.Marker_color_5];
                for i=1:length(app.cohorts)
                    for j=1:length(app.diseases)
                        colorlist = [colorlist,full_clr(i)];
                    end
                end
           
            xnam = app.XaxislabelEditField.Value;
            ynam = app.YaxislabelEditField.Value;
            full_mrkr = [app.Marker_type_1,app.Marker_type_2];
            full_mrkr = full_mrkr(1:length(app.diseases));
            mdl = GeneralizedLinearModel.fit(Xval,Yval,'Distribution','normal');
            newx=linspace(min(Xval),max(Xval),50)';
            [newy,yci]=predict(mdl,newx);
            if strcmp(app.cohorts,'') & strcmp(app.diseases,'')
                clr = app.Marker_color_1;
                
                if strcmp(app.CorrelationTypeDropDown.Value,'Spearman') & app.rankdisp == 1
                   mdl = GeneralizedLinearModel.fit(Xrank,Yrank,'Distribution','normal');
                   newx=linspace(min(Xrank),max(Xrank),50)';
                   [newy,yci]=predict(mdl,newx);
                   X = [newx' fliplr(newx')];
                   Y =  [yci(:,1)' fliplr(yci(:,2)')];
                   hold(app.UIAxes,'on');
                   plot(ax,newx,newy,'-r','LineWidth',1.4);
                   patch(ax,X,Y,[0.894 0.824 0.894],'edgecolor',[1 1 1]);
                   scatter(ax,Xrank,Yrank,app.Marker_size,clr,'filled','Marker',app.Marker_type_1); %actual scatter plot
                   hold(app.UIAxes,'off');
                else
                   X = [newx' fliplr(newx')];
                   Y =  [yci(:,1)' fliplr(yci(:,2)')];
                   hold(app.UIAxes,'on');
                   plot(ax,newx,newy,'-r','LineWidth',1.4);
                   patch(ax,X,Y,[0.894 0.824 0.894],'edgecolor',[1 1 1]);
                   scatter(ax,Xval,Yval,app.Marker_size,clr,'filled','marker',app.Marker_type_1); %actual scatter plot
                   hold(app.UIAxes,'off');
                end
                if app.highlite == 1
                   Y_std = isoutlier(Yval);
                   plot(ax,newx,newy,'-r','LineWidth',1.4);
                   hold(app.UIAxes,'on');
                   %patch(ax,[min(Xval) max(Xval) max(Xval) min(Xval)], [abs(yci(1))+y1(1) abs(yci(1))+y1(end), abs(yci(2))+y1(1) abs(yci(2))+y1(end)],[0.91 0.75 0.75],'edgecolor',[1 1 1])
                   c = ones(length(Xval),1);
                   c(Y_std == 1) = 0;
                   X = [newx' fliplr(newx')];
                   Y =  [yci(:,1)' fliplr(yci(:,2)')];
                   hold(app.UIAxes,'on');
                   plot(ax,newx,newy,'-r','LineWidth',1.4);
                   patch(ax,X,Y,[0.894 0.824 0.894],'edgecolor',[1 1 1]);
                   scatter(ax,Xval,Yval,app.Marker_size,clr,'filled'); %actual scatter plot
                   data = gscatter(ax,Xval,Yval,c,'kn','*n',10,'on');
                   legend(ax,data,app.cohorts);
                   hold(app.UIAxes,'off')
                end
            
            else
               if strcmp(app.CorrelationTypeDropDown.Value,'Spearman') & app.rankdisp == 1
                  mdl = GeneralizedLinearModel.fit(Xrank,Yrank,'Distribution','normal');
                  newx=linspace(min(Xrank),max(Xrank),50)';
                  [newy,yci]=predict(mdl,newx);
                  X = [newx' fliplr(newx')];
                  Y =  [yci(:,1)' fliplr(yci(:,2)')];
                  hold (app.UIAxes,'on');
                  patch(ax,X,Y,[0.894 0.824 0.894],'edgecolor',[1 1 1]);
                  plot(ax,newx,newy,'-r','LineWidth',1.4);
                  data = gscatter(ax,Xrank,Yrank,group_var,colorlist,full_mrkr,app.Marker_size,'off',xnam,ynam); 
                  legend(ax,data,app.cohorts);
                  hold(app.UIAxes,'off');
               else
                %if strcmp(app.error_calc,'Confidence Interval')
                 %    ci=1.96*nanstd(y1)./sqrt(sum(~isnan(y1)));
                 %    yci = bsxfun(@plus,ymean,[-ci;ci]);
                 %    plot(ax,Xval,y1,'-r','LineWidth',1.4);                   
                 %elseif strcmp(app.error_calc,'Std. dev')
                 %    ci=nanstd(y1);
                 %    yci =bsxfun(@plus,ymean,[-ci;ci]);
                  %    plot(ax,Xval,y1,'-r','LineWidth',1.4); 
                  %elseif strcmp(app.error_calc, '95 percentile')
                 %    ymean=nanmedian(y1);
                 %    yci=prctile(y1,[2.5 97.5]);
                 %    plot(ax,Xval,y1,'-r','LineWidth',1.4); 
                 %else
                  %   ci=nanstd(y1)./sqrt(sum(~isnan(y1)));
                %   yci=bsxfun(@plus,ymean,[-ci;ci]);
                %   plot(ax,Xval,y1,'-r','LineWidth',1.4);                  
                %end

                  X = [newx' fliplr(newx')];
                  Y =  [yci(:,1)' fliplr(yci(:,2)')];
                  hold (app.UIAxes,'on');
                  patch(ax,X,Y,[0.894 0.824 0.894],'edgecolor',[1 1 1]); 
                  plot(ax,newx,newy,'-r','LineWidth',1.4);
                  data = gscatter(ax,Xval,Yval,group_var,colorlist,full_mrkr,app.Marker_size,'on',xnam,ynam); %actual scatter plot
                  legend(ax,data,app.cohorts);
                  hold (app.UIAxes,'off');
               end
            
               if app.highlite == 1
                  %plot with the desired parameters, points inside the
                  %distribution's width
                  %if strcmp(app.error_calc,'Confidence Interval')
                  %   ci=1.96*nanstd(y1)./sqrt(sum(~isnan(y1)));
                  %   yci = bsxfun(@plus,ymean,[-ci;ci]);
                  %   plot(ax,Xval,y1,'-r','LineWidth',1.4)                   
                  %elseif strcmp(app.error_calc,'Std. dev')
                  %   ci=nanstd(y1);
                  %   yci =bsxfun(@plus,ymean,[-ci;ci]);
                  %   plot(ax,Xval,y1,'-r','LineWidth',1.4)                   
                  %elseif strcmp(app.error_calc, '95 percentile')
                  %   ymean=nanmedian(y1);
                  %   yci=prctile(y1,[2.5 97.5]);
                  %   plot(ax,Xval,y1,'-r','LineWidth',1.4) 
                  %else
                  %   ci=nanstd(y1)./sqrt(sum(~isnan(y1)));
                  %   yci=bsxfun(@plus,ymean,[-ci;ci]); 
                  %   plot(ax,Xval,y1,'-r','LineWidth',1.4)
                                 
               % defined as atleast three standard deviations away from the
               % mean
                   Y_std = isoutlier(Yval);
                   %patch(ax,[min(Xval) max(Xval) max(Xval) min(Xval)], [abs(yci(1))+y1(1) abs(yci(1))+y1(end), abs(yci(2))+y1(1) abs(yci(2))+y1(end)],[0.91 0.75 0.75],'edgecolor',[1 1 1]);
                   c = ones(length(Xval),1);
                   c(Y_std == 1) = 0;
                   X = [newx' fliplr(newx')];
                   Y =  [yci(:,1)' fliplr(yci(:,2)')];
                   hold (app.UIAxes,'on');
                   patch(ax,X,Y,[0.894 0.824 0.894],'edgecolor',[1 1 1]);
                   plot(ax,newx,newy,'-r','LineWidth',1.4);
                   gscatter(ax,Xval,Yval,c,'kn','*n',10,'on');
                   data = gscatter(ax,Xval,Yval,group_var,colorlist,full_mrkr,app.Marker_size,'off',xnam,ynam); %actual scatter plot
                   legend(ax,data,app.cohorts);
                   hold (app.UIAxes,'off');
               end          
            end
            
        end

        % Callback function
        function MarkerSizeEditFieldValueChanged(app, event)
            
        end

        % Value changed function: MarkerColor1DropDown
        function MarkerColor1DropDownValueChanged(app, event)
            app.Marker_color_1 = app.MarkerColor1DropDown.Value;
            
        end

        % Size changed function: DiseaseMarkersTab
        function VaryCircleSizeCheckBoxValueChanged(app, event)
            position = app.DiseaseMarkersTab.Position;
            
        end

        % Button pushed function: EXPORTSAVEButton
        function EXPORTSAVEButtonPushed(app, event)
            app.save_as = app.SaveasDropDown.Value;
            ax = app.UIAxes;
            if strcmp(app.save_as,'PNG')
                [file,path,indx] = uiputfile('corr.png');
                %exportgraphics(ax,[app.output_dir '/' 'correlation_plot' '.png']);
            elseif strcmp(app.save_as, 'JPEG')
                [file,path,indx] = uiputfile('corr.jpg');
            elseif strcmp(app.save_as, 'PDF')
                [file,path,indx] = uiputfile('corr.pdf');
            else
                [file,path,indx] = uiputfile('corr.jiff');
            end
        end

        % Callback function
        function CircleColorsDropDownValueChanged(app, event)
            
        end

        % Callback function
        function VaryColorsCheckBoxValueChanged(app, event)
            app.VaryClr = app.VaryColorsCheckBox.Value;
        end

        % Value changed function: MarkerColor2DropDown
        function MarkerColor2DropDownValueChanged(app, event)
            app.Marker_color_2 = app.MarkerColor2DropDown.Value;
        end

        % Value changed function: MarkerType1DropDown
        function MarkerType1DropDownValueChanged(app, event)
            app.Marker_type_1 = app.MarkerType1DropDown.Value;   
        end

        % Callback function
        function Markertype2DropDownValueChanged(app, event)
            
        end

        % Callback function
        function MarkerSizeEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged2(app, event)
            app.Marker_size = app.MarkerSizeEditField.Value;          
        end

        % Value changed function: DisplayasranksCheckBox
        function DisplayasranksCheckBoxValueChanged(app, event)
            app.rankdisp = app.DisplayasranksCheckBox.Value;
            
        end

        % Callback function
        function DisplayErrorBarsCheckBoxValueChanged(app, event)
            app.ErrorBox = app.DisplayErrorBarsCheckBox.Value;
            
        end

        % Value changed function: HighlightoutliersCheckBox
        function HighlightoutliersCheckBoxValueChanged(app, event)
            app.highlite = app.HighlightoutliersCheckBox.Value;
            
        end

        % Drop down opening function: CalculateerrorusingDropDown
        function CalculateerrorusingDropDownOpening(app, event)
        
        end

        % Value changed function: CalculateerrorusingDropDown
        function CalculateerrorusingDropDownValueChanged(app, event)
            app.error_calc = app.CalculateerrorusingDropDown.Value;
            
        end

        % Button pushed function: OKButton_3
        function OKButton_3Pushed2(app, event)
            app.title_fig = app.TitlelabelEditFieldLabel; 
        end

        % Value changed function: MarkerColor3DropDown
        function MarkerColor3DropDownValueChanged(app, event)
            app.Marker_color_3 = app.MarkerColor3DropDown.Value;
            
        end

        % Value changed function: MarkerColor4DropDown
        function MarkerColor4DropDownValueChanged(app, event)
            app.Marker_color_4 = app.MarkerColor4DropDown.Value;
            
        end

        % Value changed function: MarkerColor5DropDown
        function MarkerColor5DropDownValueChanged(app, event)
            app.Marker_color_5 = app.MarkerColor5DropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DATASELECTIONPanel = uipanel(app.UIFigure);
            app.DATASELECTIONPanel.Title = 'DATA SELECTION';
            app.XaxislabelEditFieldLabel = uilabel(app.DATASELECTIONPanel);
            app.XaxislabelEditFieldLabel.Text = 'X axis label';
            app.XaxislabelEditField = uieditfield(app.DATASELECTIONPanel, 'text');
            app.XaxislabelEditField.Value = 'X axis';
            app.OKButton = uibutton(app.DATASELECTIONPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.YaxislabelEditFieldLabel = uilabel(app.DATASELECTIONPanel);
            app.YaxislabelEditFieldLabel.Text = 'Y axis label';
            app.YaxislabelEditField = uieditfield(app.DATASELECTIONPanel, 'text');
            app.YaxislabelEditField.Value = 'Y axis';
            app.OKButton_2 = uibutton(app.DATASELECTIONPanel, 'push');
            app.OKButton_2.ButtonPushedFcn = createCallbackFcn(app, @OKButton_2Pushed, true);
            app.OKButton_2.Text = 'OK';
            app.TitlelabelEditFieldLabel = uilabel(app.DATASELECTIONPanel);
            app.TitlelabelEditFieldLabel.Text = 'Title label';
            app.TitlelabelEditField = uieditfield(app.DATASELECTIONPanel, 'text');
            app.OKButton_3 = uibutton(app.DATASELECTIONPanel, 'push');
            app.OKButton_3.ButtonPushedFcn = createCallbackFcn(app, @OKButton_3Pushed2, true);
            app.OKButton_3.Text = 'OK';
            app.STATISTICSPanel = uipanel(app.UIFigure);
            app.STATISTICSPanel.Title = 'STATISTICS';
            app.CorrelationTypeDropDownLabel = uilabel(app.STATISTICSPanel);
            app.CorrelationTypeDropDownLabel.Text = 'Correlation Type ';
            app.CorrelationTypeDropDown = uidropdown(app.STATISTICSPanel);
            app.CorrelationTypeDropDown.Items = {'Pearson', 'Perm. Pearson', 'Spearman', 'Kendall'};
            app.CorrelationTypeDropDown.Value = 'Pearson';
            app.DisplayasranksCheckBox = uicheckbox(app.STATISTICSPanel);
            app.DisplayasranksCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayasranksCheckBoxValueChanged, true);
            app.DisplayasranksCheckBox.Text = 'Display as ranks';
            app.HighlightoutliersCheckBox = uicheckbox(app.STATISTICSPanel);
            app.HighlightoutliersCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighlightoutliersCheckBoxValueChanged, true);
            app.HighlightoutliersCheckBox.Text = 'Highlight outliers';
            app.CalculateerrorusingDropDownLabel = uilabel(app.STATISTICSPanel);
            app.CalculateerrorusingDropDownLabel.Text = 'Calculate error using';
            app.CalculateerrorusingDropDown = uidropdown(app.STATISTICSPanel);
            app.CalculateerrorusingDropDown.Items = {'Confidence Interval', 'Std. dev', '95 percentile', 'sem'};
            app.CalculateerrorusingDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CalculateerrorusingDropDownOpening, true);
            app.CalculateerrorusingDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateerrorusingDropDownValueChanged, true);
            app.CalculateerrorusingDropDown.Value = 'Std. dev';
            app.REFRESHVIEWButton = uibutton(app.UIFigure, 'push');
            app.REFRESHVIEWButton.ButtonPushedFcn = createCallbackFcn(app, @REFRESHVIEWButtonPushed, true);
            app.REFRESHVIEWButton.Text = 'REFRESH VIEW';
            app.SaveasDropDownLabel = uilabel(app.UIFigure);
            app.SaveasDropDownLabel.Text = 'Save as..';
            app.SaveasDropDown = uidropdown(app.UIFigure);
            app.SaveasDropDown.Items = {'PNG', 'JPEG', 'TIFF', 'PDF'};
            app.SaveasDropDown.Value = 'PNG';
            app.EXPORTSAVEButton = uibutton(app.UIFigure, 'push');
            app.EXPORTSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTSAVEButtonPushed, true);
            app.EXPORTSAVEButton.Text = 'EXPORT & SAVE';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CohortColorsTab = uitab(app.TabGroup);
            app.CohortColorsTab.Title = 'Cohort Colors';
            app.MarkerColor1DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor1DropDownLabel.Text = 'Marker Color 1';
            app.MarkerColor1DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor1DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor1DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor1DropDownValueChanged, true);
            app.MarkerColor1DropDown.Value = 'Red';
            app.MarkerColor2DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor2DropDownLabel.Text = 'Marker Color 2';
            app.MarkerColor2DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor2DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor2DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor2DropDownValueChanged, true);
            app.MarkerColor2DropDown.Value = 'Red';
            app.MarkerColor3DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor3DropDownLabel.Text = 'Marker Color 3';
            app.MarkerColor3DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor3DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor3DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor3DropDownValueChanged, true);
            app.MarkerColor3DropDown.Value = 'Red';
            app.MarkerColor4DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor4DropDownLabel.Text = 'Marker Color 4';
            app.MarkerColor4DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor4DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor4DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor4DropDownValueChanged, true);
            app.MarkerColor4DropDown.Value = 'Red';
            app.MarkerColor5DropDownLabel = uilabel(app.CohortColorsTab);
            app.MarkerColor5DropDownLabel.Text = 'Marker Color 5';
            app.MarkerColor5DropDown = uidropdown(app.CohortColorsTab);
            app.MarkerColor5DropDown.Items = {'Red', 'Green', 'Magenta', 'Yellow', 'Blue', 'Cyan', 'Black', 'White', 'None'};
            app.MarkerColor5DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColor5DropDownValueChanged, true);
            app.MarkerColor5DropDown.Value = 'Red';
            app.Label = uilabel(app.CohortColorsTab);
            app.Label.Text = '';
            app.DiseaseMarkersTab = uitab(app.TabGroup);
            app.DiseaseMarkersTab.SizeChangedFcn = createCallbackFcn(app, @VaryCircleSizeCheckBoxValueChanged, true);
            app.DiseaseMarkersTab.Title = 'Disease Markers';
            app.MarkerType1DropDownLabel = uilabel(app.DiseaseMarkersTab);
            app.MarkerType1DropDownLabel.Text = 'Marker Type 1';
            app.MarkerType1DropDown = uidropdown(app.DiseaseMarkersTab);
            app.MarkerType1DropDown.Items = {'o', 's', 'd', '.', '^', 'v', '>', '<', 'p', 'h', '*', '+', 'x', 'None'};
            app.MarkerType1DropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerType1DropDownValueChanged, true);
            app.MarkerType1DropDown.Value = 'o';
            app.MarkerType2DropDownLabel = uilabel(app.DiseaseMarkersTab);
            app.MarkerType2DropDownLabel.Text = 'Marker Type 2';
            app.MarkerType2DropDown = uidropdown(app.DiseaseMarkersTab);
            app.MarkerType2DropDown.Items = {'o', 's', 'd', '.', '^', 'v', '>', '<', 'p', 'h', '*', '+', 'x', 'None'};
            app.MarkerType2DropDown.Value = '.';
            app.ThisdefinesthediseasetypeLabel = uilabel(app.DiseaseMarkersTab);
            app.ThisdefinesthediseasetypeLabel.Text = 'This defines the disease type';
            app.MarkerSizeEditFieldLabel = uilabel(app.DiseaseMarkersTab);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.MarkerSizeEditField = uieditfield(app.DiseaseMarkersTab, 'numeric');
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged2, true);
            app.MarkerSizeEditField.Value = 20;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ZTick = [0 0.5 1];
            app.UIAxes.ZTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_studio(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end